classdef app_Reporte < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Reporte                         matlab.ui.Figure
        TabGroup2                       matlab.ui.container.TabGroup
        ReporteGeneralTab               matlab.ui.container.Tab
        TabGroup                        matlab.ui.container.TabGroup
        BordedecaminoTab                matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        UITable_2                       matlab.ui.control.Table
        UITable_3                       matlab.ui.control.Table
        UITable_4                       matlab.ui.control.Table
        UITable_5                       matlab.ui.control.Table
        UITable_6                       matlab.ui.control.Table
        UITable_7                       matlab.ui.control.Table
        MitaddebloqueTab                matlab.ui.container.Tab
        UITable_10                      matlab.ui.control.Table
        UITable_11                      matlab.ui.control.Table
        UITable_13                      matlab.ui.control.Table
        UITable_12                      matlab.ui.control.Table
        UITable_14                      matlab.ui.control.Table
        UITable_15                      matlab.ui.control.Table
        UITable_16                      matlab.ui.control.Table
        UITable_17                      matlab.ui.control.Table
        UITable_18                      matlab.ui.control.Table
        UITable_19                      matlab.ui.control.Table
        UITable_20                      matlab.ui.control.Table
        UITable_21                      matlab.ui.control.Table
        UITable_22                      matlab.ui.control.Table
        UITable_23                      matlab.ui.control.Table
        UITable_24                      matlab.ui.control.Table
        UITable_25                      matlab.ui.control.Table
        UITable_26                      matlab.ui.control.Table
        InterseccionesTab               matlab.ui.container.Tab
        UITable_27                      matlab.ui.control.Table
        UITable_28                      matlab.ui.control.Table
        UITable_29                      matlab.ui.control.Table
        UITable_30                      matlab.ui.control.Table
        UITable_31                      matlab.ui.control.Table
        FlujoTab                        matlab.ui.container.Tab
        UITable_32                      matlab.ui.control.Table
        UITable_33                      matlab.ui.control.Table
        UITable_34                      matlab.ui.control.Table
        UITable_35                      matlab.ui.control.Table
        UITable_36                      matlab.ui.control.Table
        UITable_37                      matlab.ui.control.Table
        InstalacionesVRUyusodesueloTab  matlab.ui.container.Tab
        UITable_38                      matlab.ui.control.Table
        UITable_39                      matlab.ui.control.Table
        UITable_40                      matlab.ui.control.Table
        UITable_41                      matlab.ui.control.Table
        UITable_42                      matlab.ui.control.Table
        UITable_43                      matlab.ui.control.Table
        UITable_44                      matlab.ui.control.Table
        UITable_45                      matlab.ui.control.Table
        UITable_46                      matlab.ui.control.Table
        UITable_47                      matlab.ui.control.Table
        UITable_48                      matlab.ui.control.Table
        UITable_49                      matlab.ui.control.Table
        UITable_50                      matlab.ui.control.Table
        VelocidadesTab                  matlab.ui.container.Tab
        UITable_51                      matlab.ui.control.Table
        UITable_52                      matlab.ui.control.Table
        UITable_53                      matlab.ui.control.Table
        UITable_54                      matlab.ui.control.Table
        ReportedetalladoTab             matlab.ui.container.Tab
        TabGroup_2                      matlab.ui.container.TabGroup
        ClasificacindeestrellasTab      matlab.ui.container.Tab
        UITable_9                       matlab.ui.control.Table
        UsuariosvialesButtonGroup       matlab.ui.container.ButtonGroup
        CupantedevehculosButton         matlab.ui.control.RadioButton
        MotociclistaButton              matlab.ui.control.RadioButton
        CiclistasButton                 matlab.ui.control.RadioButton
        PeatonesButton                  matlab.ui.control.RadioButton
        ImprimirButton                  matlab.ui.control.Button
        PDFButton                       matlab.ui.control.Button
        DescargarButton                 matlab.ui.control.Button
        MapaPanel                       matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        DistanciaClasificacinporestrellasPanel  matlab.ui.container.Panel
        UIAxes_2                        matlab.ui.control.UIAxes
        MedidasdemejoramientoTab        matlab.ui.container.Tab
        DATOSGENERALESDELACARRETERAPanel  matlab.ui.container.Panel
        NombreTextAreaLabel             matlab.ui.control.Label
        NombreTextArea                  matlab.ui.control.TextArea
        TramosguardadosDropDownLabel    matlab.ui.control.Label
        TramosguardadosDropDown         matlab.ui.control.DropDown
        ATRIBUTOSCODIFICADOSPanel       matlab.ui.container.Panel
        PuntajedeclasificacinTextAreaLabel  matlab.ui.control.Label
        PuntajedeclasificacinTextArea   matlab.ui.control.TextArea
        ClasificacindeestrellasTextAreaLabel  matlab.ui.control.Label
        ClasificacindeestrellasTextArea  matlab.ui.control.TextArea
        TabGroup3                       matlab.ui.container.TabGroup
        BordedecaminoTab_2              matlab.ui.container.Tab
        UITable_55                      matlab.ui.control.Table
        MitaddebloqueTab_2              matlab.ui.container.Tab
        UITable_56                      matlab.ui.control.Table
        InterseccionesTab_2             matlab.ui.container.Tab
        UITable_57                      matlab.ui.control.Table
        FlujoTab_2                      matlab.ui.container.Tab
        UITable_58                      matlab.ui.control.Table
        InstalacionesVRUyusodelsueloTab  matlab.ui.container.Tab
        UITable_59                      matlab.ui.control.Table
        VelocidadesTab_2                matlab.ui.container.Tab
        UITable_60                      matlab.ui.control.Table
        MEDIDASADOPTADASPanel           matlab.ui.container.Panel
        PuntajedeclasificacinTextArea_2Label  matlab.ui.control.Label
        PuntajedeclasificacinTextArea_2  matlab.ui.control.TextArea
        ClasificacindeestrellasTextArea_2Label  matlab.ui.control.Label
        ClasificacindeestrellasTextArea_2  matlab.ui.control.TextArea
        UITable2                        matlab.ui.control.Table
        GuardarButton                   matlab.ui.control.Button
        PDFButton_2                     matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            datos={'Barrera de seguridad - metal',0,0;'Barrera de seguridad - concreto',0,0;'Barrera de seguridad - amigable para motocicletas',0,0;'Barrera de seguridad - cables',0,0;'Cara vertical agresiva',0,0;'Corte con pendiente ascendente - gradiente para rodar sobre éste',0,0;'Corte con pendiente ascendente - gradiente para no rodar sobre éste',0,0;'Cuneta de drenaje profunda',0,0;'Terraplen',0,0;'precipicio',0,0;'Arbol>=10cm',0,0;'Señal,poste o polo>=10cm',0,0;'Estructura rígida',0,0;'Estructura semirígido',0,0;'Barrera de seguridad con punta no corregida',0,0;'Grandes rocas>=20cm de alto',0,0;'Ninguna',0,0};
            app.UITable.Data=datos;
            datos={'0 a < 1m',0,0;'1 a < 5m',0,0;'5 a < 10m',0,0;'>= 10m',0,0};
            app.UITable_2.Data=datos;
            datos={'Barrera de seguridad - metal',0,0;'Barrera de seguridad - concreto',0,0;'Barrera de seguridad - amigable para motocicletas',0,0;'Barrera de seguridad - cables',0,0;'Cara vertical agresiva',0,0;'Corte con pendiente ascendente - gradiente para rodar sobre éste',0,0;'Corte con pendiente ascendente - gradiente para no rodar sobre éste',0,0;'Cuneta de drenaje profunda',0,0;'Terraplen',0,0;'precipicio',0,0;'Arbol>=10cm',0,0;'Señal,poste o polo>=10cm',0,0;'Estructura rígida',0,0;'Estructura semirígido',0,0;'Barrera de seguridad con punta no corregida',0,0;'Grandes rocas>=20cm de alto',0,0;'Ninguna',0,0};
            app.UITable_3.Data=datos;
            datos={'0 a < 1m',0,0;'1 a < 5m',0,0;'5 a < 10m',0,0;'>= 10m',0,0};
            app.UITable_4.Data=datos;
            datos={'Ancho (>= 2.4 m)',0,0;'Medio (>= 1.0 m a < 2.4 m)',0,0;'Estrecho (>= 0 m a 1.0 m)',0,0;'Ninguna',0,0};
            app.UITable_5.Data=datos;
            datos={'Ancho (>= 2.4 m)',0,0;'Medio (>= 1.0 m a < 2.4 m)',0,0;'Estrecho (>= 0 m a 1.0 m)',0,0;'Ninguna',0,0};
            app.UITable_6.Data=datos;
            datos={'Presente',0,0;'No presente',0,0};
            app.UITable_7.Data=datos;
            datos={'5 estrellas',0,0;'4 estrellas',0,0;'3 estrellas',0,0;'2 estrellas',0,0;'1 estrellas',0,0;'No aplica',0,0;'Totales',0,0;};
            app.UITable_9.Data=datos;
            datos={'Distancia al objeto del lado del conductor',0;'Objeto del lado del conductor',0;'Distancia al objeto del lado del pasajero',0;'Objeto del lado del pasajero',0;'Acotamiento del lado del conductor',0;'Acotamiento del lado del pasajero',0;'Bandas sonoras',0;};
            app.UITable_55.Data=datos;
            datos={'Etiqueta de calzada',0;'Costo de actualizaión',0;'Tipo de separador central',0;'Tiras sonoras de la línea central',0;'Número de carriles',0;'Ancho de carril',0;'Curvatura',0;'Calidad de la curva',0;'Pendiente',0;'Condiciones del camino',0;'Resistencia al deslizamiento',0;'Delineación',0;'Alumbrado público',0;'Estacionamiento para ocupantes de vehículos',0;'Vía de acceso',0;'Obras de carretera',0;'Distancia de visibilidad',0};
            app.UITable_56.Data=datos;
            datos={'Tipo de intersección',0;'Canalización de intersecciones',0;'Volumen de la carretera intersectada',0;'Calidad de la intercción',0;'Puntos de acceso a la propiedad',0};
            app.UITable_57.Data=datos;
            datos={'Flujo vehicular (TDPA)',0;'Flujo observado de motocicletas',0;'Flujo peatonal en hora pico cruzando la carretera',0;'Flujo observado de peatones a lo largo de la carretera del lado del conductor',0;'Flujo observado de peatones a lo largo de la carretera del lado del pasajero',0;'Flujo de bicicletas en horas pico',0};
            app.UITable_58.Data=datos;
            datos={'Uso de suelo-lado del conductor',0;'Uso de suelo-lado del pasajero',0;'Tipo de área',0;'Instalaciones de paso de peatones-carretera principal',0;'Calidad del paso de peatones',0;'Instalaciones de paso de peatones-carretera secundaria',0;'Cercado peatonal',0;'Acera-lado del conductor',0;'Acera-lado del pasajero',0;'Instalaciones para vehículos motorizados',0;'Instalaciones para bicicletas',0;'Advertencia de zona escolar',0;'Supervisor de cruce de zona escolar',0};
            app.UITable_59.Data=datos;
            datos={'Límite de velocidad',0;'Límites de velocidad diferenciales',0;'Infraestructura para disminuir velocidad',0;'Velocidad de funcionamiento (porcentil 85)',0};
            app.UITable_60.Data=datos;
            datos={'Límite de velocidad',0;'Límites de velocidad diferenciales',0;'Infraestructura para disminuir velocidad',0;'Velocidad de funcionamiento (porcentil 85)',0};
            app.UITable2.Data=datos;
            Contramedidas=readtable('Ejemplo.xlsx');
            app.UITable2.Data=Contramedidas;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Reporte = uifigure('Visible', 'off');
            app.Reporte.Name = 'MATLAB App';
            app.TabGroup2 = uitabgroup(app.Reporte);
            app.ReporteGeneralTab = uitab(app.TabGroup2);
            app.ReporteGeneralTab.Title = 'Reporte General';
            app.TabGroup = uitabgroup(app.ReporteGeneralTab);
            app.BordedecaminoTab = uitab(app.TabGroup);
            app.BordedecaminoTab.Title = 'Borde de camino';
            app.UITable = uitable(app.BordedecaminoTab);
            app.UITable.ColumnName = {'Objeto del lado del conductor'; 'Km'; '%'};
            app.UITable.RowName = {};
            app.UITable_2 = uitable(app.BordedecaminoTab);
            app.UITable_2.ColumnName = {'Distancia al objeto del lado del conductor'; 'Km'; '%'};
            app.UITable_2.RowName = {};
            app.UITable_3 = uitable(app.BordedecaminoTab);
            app.UITable_3.ColumnName = {'Objeto del lado del pasajero'; 'Km'; '%'};
            app.UITable_3.RowName = {};
            app.UITable_4 = uitable(app.BordedecaminoTab);
            app.UITable_4.ColumnName = {'Distancia al objeto del lado del pasajero'; 'Km'; '%'};
            app.UITable_4.RowName = {};
            app.UITable_5 = uitable(app.BordedecaminoTab);
            app.UITable_5.ColumnName = {'Acotamiento del lado del pasajero'; 'Km'; '%'};
            app.UITable_5.RowName = {};
            app.UITable_6 = uitable(app.BordedecaminoTab);
            app.UITable_6.ColumnName = {'Acotamiento del lado del conductor'; 'Km'; '%'};
            app.UITable_6.RowName = {};
            app.UITable_7 = uitable(app.BordedecaminoTab);
            app.UITable_7.ColumnName = {'Bandas sonoras'; 'Km'; '%'};
            app.UITable_7.RowName = {};
            app.MitaddebloqueTab = uitab(app.TabGroup);
            app.MitaddebloqueTab.Title = 'Mitad de bloque';
            app.UITable_10 = uitable(app.MitaddebloqueTab);
            app.UITable_10.ColumnName = {'Etiqueta de calzada'; 'Km'; '%'};
            app.UITable_10.RowName = {};
            app.UITable_11 = uitable(app.MitaddebloqueTab);
            app.UITable_11.ColumnName = {'Costo de actualización'; 'Km'; '%'};
            app.UITable_11.RowName = {};
            app.UITable_13 = uitable(app.MitaddebloqueTab);
            app.UITable_13.ColumnName = {'Tiras sonoras de la línea central'; 'Km'; '%'};
            app.UITable_13.RowName = {};
            app.UITable_12 = uitable(app.MitaddebloqueTab);
            app.UITable_12.ColumnName = {'Tipo de separador central'; 'Km'; '%'};
            app.UITable_12.RowName = {};
            app.UITable_14 = uitable(app.MitaddebloqueTab);
            app.UITable_14.ColumnName = {'Numero de carriles'; 'Km'; '%'};
            app.UITable_14.RowName = {};
            app.UITable_15 = uitable(app.MitaddebloqueTab);
            app.UITable_15.ColumnName = {'Ancho de carril'; 'Km'; '%'};
            app.UITable_15.RowName = {};
            app.UITable_16 = uitable(app.MitaddebloqueTab);
            app.UITable_16.ColumnName = {'Curvatura'; 'Km'; '%'};
            app.UITable_16.RowName = {};
            app.UITable_17 = uitable(app.MitaddebloqueTab);
            app.UITable_17.ColumnName = {'Calidad de curva'; 'Km'; '%'};
            app.UITable_17.RowName = {};
            app.UITable_18 = uitable(app.MitaddebloqueTab);
            app.UITable_18.ColumnName = {'Pendiente'; 'Km'; '%'};
            app.UITable_18.RowName = {};
            app.UITable_19 = uitable(app.MitaddebloqueTab);
            app.UITable_19.ColumnName = {'Condiciones del camino'; 'Km'; '%'};
            app.UITable_19.RowName = {};
            app.UITable_20 = uitable(app.MitaddebloqueTab);
            app.UITable_20.ColumnName = {'Resistencia al deslizamiento'; 'Km'; '%'};
            app.UITable_20.RowName = {};
            app.UITable_21 = uitable(app.MitaddebloqueTab);
            app.UITable_21.ColumnName = {'Delineación'; 'Km'; '%'};
            app.UITable_21.RowName = {};
            app.UITable_22 = uitable(app.MitaddebloqueTab);
            app.UITable_22.ColumnName = {'Alumbrado público'; 'Km'; '%'};
            app.UITable_22.RowName = {};
            app.UITable_23 = uitable(app.MitaddebloqueTab);
            app.UITable_23.ColumnName = {'Estacionamiento para ocupantes de vehículos'; 'Km'; '%'};
            app.UITable_23.RowName = {};
            app.UITable_24 = uitable(app.MitaddebloqueTab);
            app.UITable_24.ColumnName = {'Vía de acceso'; 'Km'; '%'};
            app.UITable_24.RowName = {};
            app.UITable_25 = uitable(app.MitaddebloqueTab);
            app.UITable_25.ColumnName = {'Obras de carretera'; 'Km'; '%'};
            app.UITable_25.RowName = {};
            app.UITable_26 = uitable(app.MitaddebloqueTab);
            app.UITable_26.ColumnName = {'Distancia de visibilidad'; 'Km'; '%'};
            app.UITable_26.RowName = {};
            app.InterseccionesTab = uitab(app.TabGroup);
            app.InterseccionesTab.Title = 'Intersecciones';
            app.UITable_27 = uitable(app.InterseccionesTab);
            app.UITable_27.ColumnName = {'Canalización de intersecciones'; 'Km'; '%'};
            app.UITable_27.RowName = {};
            app.UITable_28 = uitable(app.InterseccionesTab);
            app.UITable_28.ColumnName = {'Tipo de intersección'; 'Km'; '%'};
            app.UITable_28.RowName = {};
            app.UITable_29 = uitable(app.InterseccionesTab);
            app.UITable_29.ColumnName = {'Calidad de la intersección'; 'Km'; '%'};
            app.UITable_29.RowName = {};
            app.UITable_30 = uitable(app.InterseccionesTab);
            app.UITable_30.ColumnName = {'Volumen de la carretera intersectada'; 'Km'; '%'};
            app.UITable_30.RowName = {};
            app.UITable_31 = uitable(app.InterseccionesTab);
            app.UITable_31.ColumnName = {'Puntos de acceso a la propiedad'; 'Km'; '%'};
            app.UITable_31.RowName = {};
            app.FlujoTab = uitab(app.TabGroup);
            app.FlujoTab.Title = 'Flujo';
            app.UITable_32 = uitable(app.FlujoTab);
            app.UITable_32.ColumnName = {'Flujo observado de motocicletas'; 'Km'; '%'};
            app.UITable_32.RowName = {};
            app.UITable_33 = uitable(app.FlujoTab);
            app.UITable_33.ColumnName = {'Flujo vehicular (TDPA)'; 'Km'; '%'};
            app.UITable_33.RowName = {};
            app.UITable_34 = uitable(app.FlujoTab);
            app.UITable_34.ColumnName = {'Flujo observado de peatones a lo largo de la carretera del lado del conductor'; 'Km'; '%'};
            app.UITable_34.RowName = {};
            app.UITable_35 = uitable(app.FlujoTab);
            app.UITable_35.ColumnName = {'Flujo peatonal en hora pico cruzando la carretera'; 'Km'; '%'};
            app.UITable_35.RowName = {};
            app.UITable_36 = uitable(app.FlujoTab);
            app.UITable_36.ColumnName = {'Flujo observado de peatones a lo largo de la carretera del lado del pasajero'; 'Km'; '%'};
            app.UITable_36.RowName = {};
            app.UITable_37 = uitable(app.FlujoTab);
            app.UITable_37.ColumnName = {' Flujo de bicicletas en hora pico'; 'Km'; '%'};
            app.UITable_37.RowName = {};
            app.InstalacionesVRUyusodesueloTab = uitab(app.TabGroup);
            app.InstalacionesVRUyusodesueloTab.Title = 'Instalaciones VRU y uso de suelo';
            app.UITable_38 = uitable(app.InstalacionesVRUyusodesueloTab);
            app.UITable_38.ColumnName = {'Instalaciones de paso de peatones - carretera inspeccionada'; 'Km'; '%'};
            app.UITable_38.RowName = {};
            app.UITable_39 = uitable(app.InstalacionesVRUyusodesueloTab);
            app.UITable_39.ColumnName = {'Uso del suelo: lado del conductor'; 'Km'; '%'};
            app.UITable_39.RowName = {};
            app.UITable_40 = uitable(app.InstalacionesVRUyusodesueloTab);
            app.UITable_40.ColumnName = {'Uso del suelo: lado del pasajero'; 'Km'; '%'};
            app.UITable_40.RowName = {};
            app.UITable_41 = uitable(app.InstalacionesVRUyusodesueloTab);
            app.UITable_41.ColumnName = {'Calidad del paso de peatones'; 'Km'; '%'};
            app.UITable_41.RowName = {};
            app.UITable_42 = uitable(app.InstalacionesVRUyusodesueloTab);
            app.UITable_42.ColumnName = {'Cercado peatonal'; 'Km'; '%'};
            app.UITable_42.RowName = {};
            app.UITable_43 = uitable(app.InstalacionesVRUyusodesueloTab);
            app.UITable_43.ColumnName = {'Instalaciones de paso de peatones: carretera que se cruza'; 'Km'; '%'};
            app.UITable_43.RowName = {};
            app.UITable_44 = uitable(app.InstalacionesVRUyusodesueloTab);
            app.UITable_44.ColumnName = {'Acera - lado del conductor'; 'Km'; '%'};
            app.UITable_44.RowName = {};
            app.UITable_45 = uitable(app.InstalacionesVRUyusodesueloTab);
            app.UITable_45.ColumnName = {'Acera - lado del pasajero'; 'Km'; '%'};
            app.UITable_45.RowName = {};
            app.UITable_46 = uitable(app.InstalacionesVRUyusodesueloTab);
            app.UITable_46.ColumnName = {'Instalaciones para vehículos motorizados de dos ruedas'; 'Km'; '%'};
            app.UITable_46.RowName = {};
            app.UITable_47 = uitable(app.InstalacionesVRUyusodesueloTab);
            app.UITable_47.ColumnName = {'Instalaciones para bicicletas'; 'Km'; '%'};
            app.UITable_47.RowName = {};
            app.UITable_48 = uitable(app.InstalacionesVRUyusodesueloTab);
            app.UITable_48.ColumnName = {'Supervisor de cruce de zona escolar'; 'Km'; '%'};
            app.UITable_48.RowName = {};
            app.UITable_49 = uitable(app.InstalacionesVRUyusodesueloTab);
            app.UITable_49.ColumnName = {'Advertencia de zona escolar'; 'Km'; '%'};
            app.UITable_49.RowName = {};
            app.UITable_50 = uitable(app.InstalacionesVRUyusodesueloTab);
            app.UITable_50.ColumnName = {'Tipo de área'; 'Km'; '%'};
            app.UITable_50.RowName = {};
            app.VelocidadesTab = uitab(app.TabGroup);
            app.VelocidadesTab.Title = 'Velocidades';
            app.UITable_51 = uitable(app.VelocidadesTab);
            app.UITable_51.ColumnName = {'Límites de velocidad diferencial'; 'Km'; '%'};
            app.UITable_51.RowName = {};
            app.UITable_52 = uitable(app.VelocidadesTab);
            app.UITable_52.ColumnName = {'Límite de velocidad'; 'Km'; '%'};
            app.UITable_52.RowName = {};
            app.UITable_53 = uitable(app.VelocidadesTab);
            app.UITable_53.ColumnName = {'Velocidad de funcionamiento (percentil 85)'; 'Km'; '%'};
            app.UITable_53.RowName = {};
            app.UITable_54 = uitable(app.VelocidadesTab);
            app.UITable_54.ColumnName = {'Infraestructura para disminuir velocidad'; 'Km'; '%'};
            app.UITable_54.RowName = {};
            app.ReportedetalladoTab = uitab(app.TabGroup2);
            app.ReportedetalladoTab.Title = 'Reporte detallado';
            app.TabGroup_2 = uitabgroup(app.ReportedetalladoTab);
            app.ClasificacindeestrellasTab = uitab(app.TabGroup_2);
            app.ClasificacindeestrellasTab.Title = 'Clasificación de estrellas';
            app.UITable_9 = uitable(app.ClasificacindeestrellasTab);
            app.UITable_9.ColumnName = {'Clasificación de estrellas'; 'Longitud (Km)'; 'Porcentaje (%)'};
            app.UITable_9.RowName = {};
            app.UsuariosvialesButtonGroup = uibuttongroup(app.ReportedetalladoTab);
            app.UsuariosvialesButtonGroup.Title = 'Usuarios viales';
            app.CupantedevehculosButton = uiradiobutton(app.UsuariosvialesButtonGroup);
            app.CupantedevehculosButton.Text = 'Cupante de vehículos';
            app.CupantedevehculosButton.Value = true;
            app.MotociclistaButton = uiradiobutton(app.UsuariosvialesButtonGroup);
            app.MotociclistaButton.Text = 'Motociclista';
            app.CiclistasButton = uiradiobutton(app.UsuariosvialesButtonGroup);
            app.CiclistasButton.Text = 'Ciclistas';
            app.PeatonesButton = uiradiobutton(app.UsuariosvialesButtonGroup);
            app.PeatonesButton.Text = 'Peatones';
            app.ImprimirButton = uibutton(app.ReportedetalladoTab, 'push');
            app.ImprimirButton.Icon = '61764.png';
            app.ImprimirButton.Text = 'Imprimir';
            app.PDFButton = uibutton(app.ReportedetalladoTab, 'push');
            app.PDFButton.Icon = 'icono-PDF.png';
            app.PDFButton.Text = 'PDF';
            app.DescargarButton = uibutton(app.ReportedetalladoTab, 'push');
            app.DescargarButton.Icon = '5077aba5e425c55dff227a93b655da7b-icono-de-descarga-simple-by-vexels.png';
            app.DescargarButton.Text = 'Descargar';
            app.MapaPanel = uipanel(app.ReportedetalladoTab);
            app.MapaPanel.Title = 'Mapa';
            app.UIAxes = uiaxes(app.MapaPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.DistanciaClasificacinporestrellasPanel = uipanel(app.ReportedetalladoTab);
            app.DistanciaClasificacinporestrellasPanel.Title = 'Distancia-Clasificación por estrellas';
            app.UIAxes_2 = uiaxes(app.DistanciaClasificacinporestrellasPanel);
            title(app.UIAxes_2, '')
            xlabel(app.UIAxes_2, 'Distancia')
            ylabel(app.UIAxes_2, 'Clasificación por estrellas')
            app.MedidasdemejoramientoTab = uitab(app.TabGroup2);
            app.MedidasdemejoramientoTab.Title = 'Medidas de mejoramiento';
            app.DATOSGENERALESDELACARRETERAPanel = uipanel(app.MedidasdemejoramientoTab);
            app.DATOSGENERALESDELACARRETERAPanel.Title = 'DATOS GENERALES DE LA CARRETERA';
            app.NombreTextAreaLabel = uilabel(app.DATOSGENERALESDELACARRETERAPanel);
            app.NombreTextAreaLabel.Text = 'Nombre:';
            app.NombreTextArea = uitextarea(app.DATOSGENERALESDELACARRETERAPanel);
            app.TramosguardadosDropDownLabel = uilabel(app.DATOSGENERALESDELACARRETERAPanel);
            app.TramosguardadosDropDownLabel.Text = 'Tramos guardados';
            app.TramosguardadosDropDown = uidropdown(app.DATOSGENERALESDELACARRETERAPanel);
            app.TramosguardadosDropDown.Items = {'Tramo 1', 'Tramo 2', 'Tramo 3', 'Tramo 4'};
            app.TramosguardadosDropDown.Value = 'Tramo 1';
            app.ATRIBUTOSCODIFICADOSPanel = uipanel(app.MedidasdemejoramientoTab);
            app.ATRIBUTOSCODIFICADOSPanel.Title = 'ATRIBUTOS CODIFICADOS';
            app.PuntajedeclasificacinTextAreaLabel = uilabel(app.ATRIBUTOSCODIFICADOSPanel);
            app.PuntajedeclasificacinTextAreaLabel.Text = 'Puntaje de clasificación';
            app.PuntajedeclasificacinTextArea = uitextarea(app.ATRIBUTOSCODIFICADOSPanel);
            app.ClasificacindeestrellasTextAreaLabel = uilabel(app.ATRIBUTOSCODIFICADOSPanel);
            app.ClasificacindeestrellasTextAreaLabel.Text = 'Clasificación de estrellas';
            app.ClasificacindeestrellasTextArea = uitextarea(app.ATRIBUTOSCODIFICADOSPanel);
            app.TabGroup3 = uitabgroup(app.ATRIBUTOSCODIFICADOSPanel);
            app.BordedecaminoTab_2 = uitab(app.TabGroup3);
            app.BordedecaminoTab_2.Title = 'Borde de camino';
            app.UITable_55 = uitable(app.BordedecaminoTab_2);
            app.UITable_55.ColumnName = {'Atributo'; 'Dato seleccionado'};
            app.UITable_55.RowName = {};
            app.MitaddebloqueTab_2 = uitab(app.TabGroup3);
            app.MitaddebloqueTab_2.Title = 'Mitad de bloque';
            app.UITable_56 = uitable(app.MitaddebloqueTab_2);
            app.UITable_56.ColumnName = {'Atributo'; 'Dato seleccionado'};
            app.UITable_56.RowName = {};
            app.InterseccionesTab_2 = uitab(app.TabGroup3);
            app.InterseccionesTab_2.Title = 'Intersecciones';
            app.UITable_57 = uitable(app.InterseccionesTab_2);
            app.UITable_57.ColumnName = {'Atributo'; 'Dato seleccionado'};
            app.UITable_57.RowName = {};
            app.FlujoTab_2 = uitab(app.TabGroup3);
            app.FlujoTab_2.Title = 'Flujo';
            app.UITable_58 = uitable(app.FlujoTab_2);
            app.UITable_58.ColumnName = {'Atributo'; 'Dato seleccionado'};
            app.UITable_58.RowName = {};
            app.InstalacionesVRUyusodelsueloTab = uitab(app.TabGroup3);
            app.InstalacionesVRUyusodelsueloTab.Title = 'Instalaciones VRU y uso del suelo';
            app.UITable_59 = uitable(app.InstalacionesVRUyusodelsueloTab);
            app.UITable_59.ColumnName = {'Atributo'; 'Dato seleccionado'};
            app.UITable_59.RowName = {};
            app.VelocidadesTab_2 = uitab(app.TabGroup3);
            app.VelocidadesTab_2.Title = 'Velocidades';
            app.UITable_60 = uitable(app.VelocidadesTab_2);
            app.UITable_60.ColumnName = {'Atributo'; 'Dato seleccionado'};
            app.UITable_60.RowName = {};
            app.MEDIDASADOPTADASPanel = uipanel(app.MedidasdemejoramientoTab);
            app.MEDIDASADOPTADASPanel.Title = 'MEDIDAS ADOPTADAS';
            app.PuntajedeclasificacinTextArea_2Label = uilabel(app.MEDIDASADOPTADASPanel);
            app.PuntajedeclasificacinTextArea_2Label.Text = 'Puntaje de clasificación';
            app.PuntajedeclasificacinTextArea_2 = uitextarea(app.MEDIDASADOPTADASPanel);
            app.ClasificacindeestrellasTextArea_2Label = uilabel(app.MEDIDASADOPTADASPanel);
            app.ClasificacindeestrellasTextArea_2Label.Text = 'Clasificación de estrellas';
            app.ClasificacindeestrellasTextArea_2 = uitextarea(app.MEDIDASADOPTADASPanel);
            app.UITable2 = uitable(app.MEDIDASADOPTADASPanel);
            app.UITable2.ColumnName = {'#'; 'Contramedida'; 'Atributo analizado'; 'Categoría'; 'Selección'};
            app.UITable2.RowName = {};
            app.UITable2.ColumnEditable = true;
            app.GuardarButton = uibutton(app.MEDIDASADOPTADASPanel, 'push');
            app.GuardarButton.Icon = 'kisspng-computer-icons-iconfinder-desktop-wallpaper-floppy-save-icon-5ab078519b7e26.5976247815215145776369.jpg';
            app.GuardarButton.Text = 'Guardar';
            app.PDFButton_2 = uibutton(app.MEDIDASADOPTADASPanel, 'push');
            app.PDFButton_2.Icon = 'icono-PDF.png';
            app.PDFButton_2.Text = 'PDF';
            % Show the figure after all components are created
            app.Reporte.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_Reporte

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Reporte)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Reporte)
        end
    end
end