classdef FASES < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        ObtencindegeometraconarchivovboButton  matlab.ui.control.Button
        CalificacinpornmerodeestrellasButton  matlab.ui.control.Button
        ReporteButton  matlab.ui.control.Button
        IngresodedatosmanualmenteButton  matlab.ui.control.Button
        SILVVIALabel   matlab.ui.control.Label
        SISTEMAINTEGRADOPARALAVALORACINVIALLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: 
        % ObtencindegeometraconarchivovboButton
        function ObtencindegeometraconarchivovboButtonPushed(app, event)
            identificador=1;
            save('../mat/preproceso_identificador',"identificador")
            INTERFAZVBX
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            I1
        end

        % Button pushed function: 
        % CalificacinpornmerodeestrellasButton
        function CalificacinpornmerodeestrellasButtonPushed(app, event)
            Interfaz_Atributos
        end

        % Button pushed function: ReporteButton
        function ReporteButtonPushed(app, event)
            app_Reporte
        end

        % Button pushed function: IngresodedatosmanualmenteButton
        function IngresodedatosmanualmenteButtonPushed2(app, event)
             identificador=2;
            save('../mat/preproceso_identificador',"identificador")
            Inf_general_app2
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ObtencindegeometraconarchivovboButton = uibutton(app.UIFigure, 'push');
            app.ObtencindegeometraconarchivovboButton.ButtonPushedFcn = createCallbackFcn(app, @ObtencindegeometraconarchivovboButtonPushed, true);
            app.ObtencindegeometraconarchivovboButton.Text = 'Obtención de geometría con archivo .vbo';
            app.CalificacinpornmerodeestrellasButton = uibutton(app.UIFigure, 'push');
            app.CalificacinpornmerodeestrellasButton.ButtonPushedFcn = createCallbackFcn(app, @CalificacinpornmerodeestrellasButtonPushed, true);
            app.CalificacinpornmerodeestrellasButton.Text = 'Calificación por número de estrellas';
            app.ReporteButton = uibutton(app.UIFigure, 'push');
            app.ReporteButton.ButtonPushedFcn = createCallbackFcn(app, @ReporteButtonPushed, true);
            app.ReporteButton.Text = 'Reporte';
            app.IngresodedatosmanualmenteButton = uibutton(app.UIFigure, 'push');
            app.IngresodedatosmanualmenteButton.ButtonPushedFcn = createCallbackFcn(app, @IngresodedatosmanualmenteButtonPushed2, true);
            app.IngresodedatosmanualmenteButton.Text = 'Ingreso de datos manualmente';
            app.SILVVIALabel = uilabel(app.UIFigure);
            app.SILVVIALabel.Text = 'SILVVIA';
            app.SISTEMAINTEGRADOPARALAVALORACINVIALLabel = uilabel(app.UIFigure);
            app.SISTEMAINTEGRADOPARALAVALORACINVIALLabel.Text = 'SISTEMA INTEGRADO PARA LA VALORACIÓN VIAL';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FASES

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end