classdef I1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        NuevoProyectoButton     matlab.ui.control.Button
        CargarProyectoButton    matlab.ui.control.Button
        SILVVIALabel            matlab.ui.control.Label
        SISTEMAINTEGRADOPARALAVALORACINVIALLabel  matlab.ui.control.Label
        ExportarproyectoButton  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Reconoce todos los directorios del programa
            addpath(genpath('.'))
        end

        % Button pushed function: NuevoProyectoButton
        function NuevoProyectoButtonPushed(app, event)

FASES    
delete(app)
        end

        % Button pushed function: ExportarproyectoButton
        function ExportarproyectoButtonPushed(app, event)
 %addpath('cDescargas')
 %zip('PROYECTO',{'../mat/*.mat','../fotogramas/*.jpg'});
    % Seleccionar la ubicación donde guardar
            [name,path] = uiputfile('*.*','Guardar proyecto','SilvviaProj');
            if name == 0
               return 
            end
            % Ruta de la carpeta de proyecto
            path_projF = strcat(path,name,'\');
            
            % Ruta del archivo de proyecto
            % path_proj = strcat(path_projF,name,'.mat');
            % Crear carpeta
            mkdir(path_projF)
            
            %Copiar los archivos en la carpeta creada
            %copyfile('Reporte de la aplicación de las contramedidas.pdf',path_projF);
            copyfile('../mat/Datos_atributos_proceso.mat',path_projF);
            copyfile('../mat/Datos_contramedidas_postproceso.mat',path_projF);
            copyfile('../mat/Datos_interfaz_dos_proceso.mat',path_projF);
            copyfile('../mat/Datos_suavizado_contramedidas_postproceso.mat',path_projF);
            copyfile('../mat/Datos_suavizado_postproceso.mat',path_projF);
            copyfile('../mat/Inf_proyecto.mat',path_projF);
            copyfile('../mat/Matriz_contramedidas_seleccionadas_postproceso.mat',path_projF);
            copyfile('../mat/preproceso_datos_video.mat',path_projF);
            copyfile('../mat/preproceso_tiempo_img.mat',path_projF);
            copyfile('../mat/preproceso_tramo.mat',path_projF);
            copyfile('../fotogramas*',path_projF)

  
        end

        % Button pushed function: CargarProyectoButton
        function CargarProyectoButtonPushed(app, event)
            copyfile('../doc/preproceso_tramo.mat.mat','../mat/');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NuevoProyectoButton = uibutton(app.UIFigure, 'push');
            app.NuevoProyectoButton.ButtonPushedFcn = createCallbackFcn(app, @NuevoProyectoButtonPushed, true);
            app.NuevoProyectoButton.Text = 'Nuevo Proyecto';
            app.CargarProyectoButton = uibutton(app.UIFigure, 'push');
            app.CargarProyectoButton.ButtonPushedFcn = createCallbackFcn(app, @CargarProyectoButtonPushed, true);
            app.CargarProyectoButton.Text = 'Cargar Proyecto';
            app.SILVVIALabel = uilabel(app.UIFigure);
            app.SILVVIALabel.Text = 'SILVVIA';
            app.SISTEMAINTEGRADOPARALAVALORACINVIALLabel = uilabel(app.UIFigure);
            app.SISTEMAINTEGRADOPARALAVALORACINVIALLabel.Text = 'SISTEMA INTEGRADO PARA LA VALORACIÓN VIAL';
            app.ExportarproyectoButton = uibutton(app.UIFigure, 'push');
            app.ExportarproyectoButton.ButtonPushedFcn = createCallbackFcn(app, @ExportarproyectoButtonPushed, true);
            app.ExportarproyectoButton.Text = 'Exportar proyecto';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = I1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end