classdef Inf_general_app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        InformacingeneralPanel          matlab.ui.container.Panel
        DescripcinEditFieldLabel        matlab.ui.control.Label
        DescripcinEditField             matlab.ui.control.EditField
        TtulodelproyectoEditFieldLabel  matlab.ui.control.Label
        TtulodelproyectoEditField       matlab.ui.control.EditField
        LugarEditFieldLabel             matlab.ui.control.Label
        LugarEditField                  matlab.ui.control.EditField
        FechaEditFieldLabel             matlab.ui.control.Label
        FechaEditField                  matlab.ui.control.EditField
        SiguienteButton                 matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SiguienteButton
        function SiguienteButtonPushed(app, event)
T_proyecto=app.TtulodelproyectoEditField.Value
Lugar=app.LugarEditField.Value
Fecha=app.FechaEditField.Value
Descrip=app.DescripcinEditField.Value
save('../mat/Inf_proyecto','T_proyecto','Lugar','Fecha','Descrip')
im =0
rowsSelected=-1
delete("../fotogramas/*")
fotograma= '..\img\SN_CARGA.png'
save('..\mat\infor_img_alt.mat',"fotograma")
save('../mat/estado_carga',"im")
save('../mat/celda','rowsSelected')
  
Interfaz_app2
delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InformacingeneralPanel = uipanel(app.UIFigure);
            app.InformacingeneralPanel.Title = 'Información general';
            app.DescripcinEditFieldLabel = uilabel(app.InformacingeneralPanel);
            app.DescripcinEditFieldLabel.Text = 'Descripción:';
            app.DescripcinEditField = uieditfield(app.InformacingeneralPanel, 'text');
            app.TtulodelproyectoEditFieldLabel = uilabel(app.InformacingeneralPanel);
            app.TtulodelproyectoEditFieldLabel.Text = 'Título del proyecto:';
            app.TtulodelproyectoEditField = uieditfield(app.InformacingeneralPanel, 'text');
            app.LugarEditFieldLabel = uilabel(app.InformacingeneralPanel);
            app.LugarEditFieldLabel.Text = 'Lugar:';
            app.LugarEditField = uieditfield(app.InformacingeneralPanel, 'text');
            app.FechaEditFieldLabel = uilabel(app.InformacingeneralPanel);
            app.FechaEditFieldLabel.Text = 'Fecha:';
            app.FechaEditField = uieditfield(app.InformacingeneralPanel, 'text');
            app.SiguienteButton = uibutton(app.InformacingeneralPanel, 'push');
            app.SiguienteButton.ButtonPushedFcn = createCallbackFcn(app, @SiguienteButtonPushed, true);
            app.SiguienteButton.Text = 'Siguiente';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Inf_general_app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end