classdef Interfaz_Atributos < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        IMAGENDELTRAMOANALIZADOPanel    matlab.ui.container.Panel
        Image                           matlab.ui.control.Image
        RECORRIDODELTRAMOANALIZADOPanel  matlab.ui.container.Panel
        TramodeanlisisSpinnerLabel      matlab.ui.control.Label
        TramodeanlisisSpinner           matlab.ui.control.Spinner
        UIAxes                          matlab.ui.control.UIAxes
        CLASIFICACINPORESTRELLASPanel   matlab.ui.container.Panel
        Label                           matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        CostadodelavaTab                matlab.ui.container.Tab
        SeveridadlateraldistanciaalobjetoladodelconductorLabel  matlab.ui.control.Label
        Button                          matlab.ui.control.StateButton
        Button_2                        matlab.ui.control.StateButton
        Button_3                        matlab.ui.control.StateButton
        Button_4                        matlab.ui.control.StateButton
        SeveridadlateralobjetoladodelconductorLabel  matlab.ui.control.Label
        SeveridadlateraldistanciaalobjetoladodelcopilotoLabel  matlab.ui.control.Label
        Button_5                        matlab.ui.control.StateButton
        Button_6                        matlab.ui.control.StateButton
        Button_7                        matlab.ui.control.StateButton
        Button_8                        matlab.ui.control.StateButton
        SeveridadlateralobjetoladodelcopilotoLabel  matlab.ui.control.Label
        Button_519                      matlab.ui.control.StateButton
        Button_520                      matlab.ui.control.StateButton
        Button_521                      matlab.ui.control.StateButton
        Button_522                      matlab.ui.control.StateButton
        Button_526                      matlab.ui.control.StateButton
        Button_525                      matlab.ui.control.StateButton
        Button_524                      matlab.ui.control.StateButton
        Button_523                      matlab.ui.control.StateButton
        Button_527                      matlab.ui.control.StateButton
        Button_528                      matlab.ui.control.StateButton
        Button_529                      matlab.ui.control.StateButton
        Button_530                      matlab.ui.control.StateButton
        Button_532                      matlab.ui.control.StateButton
        Button_533                      matlab.ui.control.StateButton
        Button_534                      matlab.ui.control.StateButton
        Button_531                      matlab.ui.control.StateButton
        Button_535                      matlab.ui.control.StateButton
        BandassonorasenelespaldnLabel   matlab.ui.control.Label
        Button_9                        matlab.ui.control.StateButton
        Button_10                       matlab.ui.control.StateButton
        EspaldndelladoconductorLabel    matlab.ui.control.Label
        Button_11                       matlab.ui.control.StateButton
        Button_12                       matlab.ui.control.StateButton
        Button_13                       matlab.ui.control.StateButton
        Button_14                       matlab.ui.control.StateButton
        EspaldndelladopasajeroLabel     matlab.ui.control.Label
        Button_666                      matlab.ui.control.StateButton
        Button_667                      matlab.ui.control.StateButton
        Button_668                      matlab.ui.control.StateButton
        Button_669                      matlab.ui.control.StateButton
        Button_670                      matlab.ui.control.StateButton
        Button_671                      matlab.ui.control.StateButton
        Button_672                      matlab.ui.control.StateButton
        Button_673                      matlab.ui.control.StateButton
        Button_674                      matlab.ui.control.StateButton
        Button_675                      matlab.ui.control.StateButton
        Button_676                      matlab.ui.control.StateButton
        Button_677                      matlab.ui.control.StateButton
        Button_678                      matlab.ui.control.StateButton
        Button_679                      matlab.ui.control.StateButton
        Button_680                      matlab.ui.control.StateButton
        Button_681                      matlab.ui.control.StateButton
        Button_682                      matlab.ui.control.StateButton
        Button_683                      matlab.ui.control.StateButton
        Button_684                      matlab.ui.control.StateButton
        Button_685                      matlab.ui.control.StateButton
        Button_686                      matlab.ui.control.StateButton
        MedianaTab                      matlab.ui.container.Tab
        TipodecarreteraLabel            matlab.ui.control.Label
        Button_70                       matlab.ui.control.StateButton
        Button_71                       matlab.ui.control.StateButton
        Button_72                       matlab.ui.control.StateButton
        Button_73                       matlab.ui.control.StateButton
        CostodemodernizacinLabel        matlab.ui.control.Label
        TipodemedianaLabel_2            matlab.ui.control.Label
        Button_74                       matlab.ui.control.StateButton
        Button_75                       matlab.ui.control.StateButton
        Button_76                       matlab.ui.control.StateButton
        Button_77                       matlab.ui.control.StateButton
        BandassonorascentralesLabel     matlab.ui.control.Label
        SiButton_2                      matlab.ui.control.StateButton
        Button_557                      matlab.ui.control.StateButton
        Button_491                      matlab.ui.control.StateButton
        Button_492                      matlab.ui.control.StateButton
        Button_493                      matlab.ui.control.StateButton
        CurvaturaLabel_2                matlab.ui.control.Label
        Button_78                       matlab.ui.control.StateButton
        Button_79                       matlab.ui.control.StateButton
        NmerodecarrilesLabel_2          matlab.ui.control.Label
        AnchodecarrilLabel_2            matlab.ui.control.Label
        CarrilP                         matlab.ui.control.StateButton
        EstadodelasuperficiedelavaLabel  matlab.ui.control.Label
        Button_560                      matlab.ui.control.StateButton
        Button_561                      matlab.ui.control.StateButton
        CalidaddelacurvaLabel           matlab.ui.control.Label
        Button_92                       matlab.ui.control.StateButton
        Button_93                       matlab.ui.control.StateButton
        Button_94                       matlab.ui.control.StateButton
        PendienteLabel_2                matlab.ui.control.Label
        Button_96                       matlab.ui.control.StateButton
        Button_97                       matlab.ui.control.StateButton
        Button_98                       matlab.ui.control.StateButton
        ResistenciaaldeslizamientoLabel_2  matlab.ui.control.Label
        Button_100                      matlab.ui.control.StateButton
        Button_101                      matlab.ui.control.StateButton
        Button_102                      matlab.ui.control.StateButton
        Button_103                      matlab.ui.control.StateButton
        Button_104                      matlab.ui.control.StateButton
        Button_105                      matlab.ui.control.StateButton
        Button_106                      matlab.ui.control.StateButton
        Button_107                      matlab.ui.control.StateButton
        Button_108                      matlab.ui.control.StateButton
        Button_109                      matlab.ui.control.StateButton
        Button_110                      matlab.ui.control.StateButton
        Button_111                      matlab.ui.control.StateButton
        Button_112                      matlab.ui.control.StateButton
        Button_113                      matlab.ui.control.StateButton
        Button_115                      matlab.ui.control.StateButton
        Button_116                      matlab.ui.control.StateButton
        CarrilM                         matlab.ui.control.StateButton
        Button_121                      matlab.ui.control.StateButton
        Button_122                      matlab.ui.control.StateButton
        Button_562                      matlab.ui.control.StateButton
        Button_126                      matlab.ui.control.StateButton
        Button_127                      matlab.ui.control.StateButton
        Button_128                      matlab.ui.control.StateButton
        Button_559                      matlab.ui.control.StateButton
        EstacionamientoparavehculosLabel  matlab.ui.control.Label
        DelineacinLabel                 matlab.ui.control.Label
        Button_563                      matlab.ui.control.StateButton
        Button_564                      matlab.ui.control.StateButton
        AlumbradopblicoLabel            matlab.ui.control.Label
        ObrasenlacarreteraLabel         matlab.ui.control.Label
        Button_138                      matlab.ui.control.StateButton
        Button_139                      matlab.ui.control.StateButton
        Button_141                      matlab.ui.control.StateButton
        DistanciadevisibilidadLabel     matlab.ui.control.Label
        Button_144                      matlab.ui.control.StateButton
        Button_145                      matlab.ui.control.StateButton
        Button_565                      matlab.ui.control.StateButton
        Button_566                      matlab.ui.control.StateButton
        VadeservicioLabel               matlab.ui.control.Label
        SiButton_18                     matlab.ui.control.StateButton
        NoButton_19                     matlab.ui.control.StateButton
        Button_567                      matlab.ui.control.StateButton
        Button_150                      matlab.ui.control.StateButton
        Button_151                      matlab.ui.control.StateButton
        Button_660                      matlab.ui.control.StateButton
        Button_661                      matlab.ui.control.StateButton
        Button_662                      matlab.ui.control.StateButton
        CarrilG_2                       matlab.ui.control.StateButton
        InterseccionesTab               matlab.ui.container.Tab
        TipodeinterseccinLabel          matlab.ui.control.Label
        Button_152                      matlab.ui.control.StateButton
        Button_153                      matlab.ui.control.StateButton
        Button_154                      matlab.ui.control.StateButton
        Button_155                      matlab.ui.control.StateButton
        CanalizacindelainterseccinLabel  matlab.ui.control.Label
        VolumendelainterseccinLabel     matlab.ui.control.Label
        Button_568                      matlab.ui.control.StateButton
        Button_569                      matlab.ui.control.StateButton
        PuntosdeaccesoalapropiedadLabel  matlab.ui.control.Label
        CalidaddelainterseccinLabel     matlab.ui.control.Label
        Button_169                      matlab.ui.control.StateButton
        Button_170                      matlab.ui.control.StateButton
        Button_171                      matlab.ui.control.StateButton
        Button_174                      matlab.ui.control.StateButton
        Button_176                      matlab.ui.control.StateButton
        Button_177                      matlab.ui.control.StateButton
        Button_178                      matlab.ui.control.StateButton
        Button_179                      matlab.ui.control.StateButton
        Button_180                      matlab.ui.control.StateButton
        Button_181                      matlab.ui.control.StateButton
        Button_182                      matlab.ui.control.StateButton
        Button_183                      matlab.ui.control.StateButton
        Button_184                      matlab.ui.control.StateButton
        Button_185                      matlab.ui.control.StateButton
        Button_189                      matlab.ui.control.StateButton
        Button_198                      matlab.ui.control.StateButton
        Button_207                      matlab.ui.control.StateButton
        Button_208                      matlab.ui.control.StateButton
        Button_209                      matlab.ui.control.StateButton
        Button_210                      matlab.ui.control.StateButton
        Button_211                      matlab.ui.control.StateButton
        Button_212                      matlab.ui.control.StateButton
        Button_213                      matlab.ui.control.StateButton
        Button_214                      matlab.ui.control.StateButton
        Button_215                      matlab.ui.control.StateButton
        Button_216                      matlab.ui.control.StateButton
        TransitableButton               matlab.ui.control.Button
        NoTransitableButton             matlab.ui.control.Button
        TransitabilidadmedianaLabel     matlab.ui.control.Label
        FlujoTab_3                      matlab.ui.container.Tab
        CanalizacindeinterseccionesLabel_4  matlab.ui.control.Label
        motocicletasLabel               matlab.ui.control.Label
        FlujopeatonalenhorapicoquecruzalacarreteraLabel  matlab.ui.control.Label
        Button_261                      matlab.ui.control.StateButton
        Button_262                      matlab.ui.control.StateButton
        Button_263                      matlab.ui.control.StateButton
        Button_264                      matlab.ui.control.StateButton
        Button_265                      matlab.ui.control.StateButton
        Button_266                      matlab.ui.control.StateButton
        Button_267                      matlab.ui.control.StateButton
        NAButton                        matlab.ui.control.StateButton
        Button_270                      matlab.ui.control.StateButton
        Button_271                      matlab.ui.control.StateButton
        Button_275                      matlab.ui.control.StateButton
        Button_276                      matlab.ui.control.StateButton
        Button_277                      matlab.ui.control.StateButton
        Button_278                      matlab.ui.control.StateButton
        Button_279                      matlab.ui.control.StateButton
        Button_280                      matlab.ui.control.StateButton
        Button_281                      matlab.ui.control.StateButton
        Button_282                      matlab.ui.control.StateButton
        Button_283                      matlab.ui.control.StateButton
        Button_284                      matlab.ui.control.StateButton
        CanalizacindeinterseccionesLabel_5  matlab.ui.control.Label
        CanalizacindeinterseccionesLabel_6  matlab.ui.control.Label
        Button_572                      matlab.ui.control.StateButton
        Button_573                      matlab.ui.control.StateButton
        Button_574                      matlab.ui.control.StateButton
        Button_575                      matlab.ui.control.StateButton
        Button_576                      matlab.ui.control.StateButton
        Button_577                      matlab.ui.control.StateButton
        Button_578                      matlab.ui.control.StateButton
        Button_579                      matlab.ui.control.StateButton
        Button_580                      matlab.ui.control.StateButton
        Button_581                      matlab.ui.control.StateButton
        Button_582                      matlab.ui.control.StateButton
        Button_583                      matlab.ui.control.StateButton
        Button_584                      matlab.ui.control.StateButton
        Button_585                      matlab.ui.control.StateButton
        Button_586                      matlab.ui.control.StateButton
        Button_587                      matlab.ui.control.StateButton
        Button_588                      matlab.ui.control.StateButton
        Button_589                      matlab.ui.control.StateButton
        Button_590                      matlab.ui.control.StateButton
        Button_591                      matlab.ui.control.StateButton
        Button_592                      matlab.ui.control.StateButton
        Button_593                      matlab.ui.control.StateButton
        Button_594                      matlab.ui.control.StateButton
        Button_595                      matlab.ui.control.StateButton
        Button_596                      matlab.ui.control.StateButton
        Button_597                      matlab.ui.control.StateButton
        Button_598                      matlab.ui.control.StateButton
        Button_599                      matlab.ui.control.StateButton
        Button_600                      matlab.ui.control.StateButton
        Button_601                      matlab.ui.control.StateButton
        Button_602                      matlab.ui.control.StateButton
        Button_603                      matlab.ui.control.StateButton
        Button_604                      matlab.ui.control.StateButton
        FlujovehicularTDPAEditFieldLabel  matlab.ui.control.Label
        FlujovehicularTDPAEditField     matlab.ui.control.NumericEditField
        Button_712                      matlab.ui.control.StateButton
        UsosTab                         matlab.ui.container.Tab
        UsodesueloladodelconductorLabel  matlab.ui.control.Label
        Button_318                      matlab.ui.control.StateButton
        Button_319                      matlab.ui.control.StateButton
        Button_320                      matlab.ui.control.StateButton
        Button_321                      matlab.ui.control.StateButton
        InfraestructuradecrucepeatonaleneltramoanalizadoLabel  matlab.ui.control.Label
        Button_380                      matlab.ui.control.StateButton
        Button_381                      matlab.ui.control.StateButton
        CalidaddecrucepeatonalLabel     matlab.ui.control.Label
        Button_329                      matlab.ui.control.StateButton
        VallaspeatonalesLabel           matlab.ui.control.Label
        Button_332                      matlab.ui.control.StateButton
        Button_333                      matlab.ui.control.StateButton
        AceraladodelconductorLabel      matlab.ui.control.Label
        Button_335                      matlab.ui.control.StateButton
        Button_336                      matlab.ui.control.StateButton
        Button_337                      matlab.ui.control.StateButton
        Button_340                      matlab.ui.control.StateButton
        Button_356                      matlab.ui.control.StateButton
        Button_357                      matlab.ui.control.StateButton
        InfraestructuraparamotocicletasLabel  matlab.ui.control.Label
        Button_413                      matlab.ui.control.StateButton
        Button_414                      matlab.ui.control.StateButton
        UsodesueloladodelcopilotoLabel  matlab.ui.control.Label
        TipodereaLabel                  matlab.ui.control.Label
        Button_611                      matlab.ui.control.StateButton
        Button_612                      matlab.ui.control.StateButton
        Button_382                      matlab.ui.control.StateButton
        Button_383                      matlab.ui.control.StateButton
        Button_384                      matlab.ui.control.StateButton
        Button_385                      matlab.ui.control.StateButton
        Button_386                      matlab.ui.control.StateButton
        Button_387                      matlab.ui.control.StateButton
        Button_388                      matlab.ui.control.StateButton
        Button_389                      matlab.ui.control.StateButton
        Button_390                      matlab.ui.control.StateButton
        InfraestructuradecrucepeatonalenlainterseccinLabel  matlab.ui.control.Label
        Button_402                      matlab.ui.control.StateButton
        Button_403                      matlab.ui.control.StateButton
        Button_404                      matlab.ui.control.StateButton
        Button_405                      matlab.ui.control.StateButton
        AceraladodelcopilotoLabel       matlab.ui.control.Label
        Button_415                      matlab.ui.control.StateButton
        Button_416                      matlab.ui.control.StateButton
        Button_417                      matlab.ui.control.StateButton
        Button_418                      matlab.ui.control.StateButton
        InfraestructuraparabicicletasLabel  matlab.ui.control.Label
        Button_419                      matlab.ui.control.StateButton
        Button_420                      matlab.ui.control.StateButton
        Button_421                      matlab.ui.control.StateButton
        Button_422                      matlab.ui.control.StateButton
        Button_423                      matlab.ui.control.StateButton
        Button_424                      matlab.ui.control.StateButton
        Button_425                      matlab.ui.control.StateButton
        AdvertenciadezonaescolarLabel   matlab.ui.control.Label
        Button_427                      matlab.ui.control.StateButton
        Button_428                      matlab.ui.control.StateButton
        Button_429                      matlab.ui.control.StateButton
        Button_430                      matlab.ui.control.StateButton
        SupervisorenelcrucedezonaescolarLabel  matlab.ui.control.Label
        Button_431                      matlab.ui.control.StateButton
        Button_432                      matlab.ui.control.StateButton
        Button_433                      matlab.ui.control.StateButton
        Button_687                      matlab.ui.control.StateButton
        Button_688                      matlab.ui.control.StateButton
        Button_689                      matlab.ui.control.StateButton
        Button_690                      matlab.ui.control.StateButton
        Button_691                      matlab.ui.control.StateButton
        Button_692                      matlab.ui.control.StateButton
        Button_693                      matlab.ui.control.StateButton
        Button_694                      matlab.ui.control.StateButton
        Button_695                      matlab.ui.control.StateButton
        Button_696                      matlab.ui.control.StateButton
        Button_697                      matlab.ui.control.StateButton
        Button_698                      matlab.ui.control.StateButton
        Button_699                      matlab.ui.control.StateButton
        Button_700                      matlab.ui.control.StateButton
        Button_701                      matlab.ui.control.StateButton
        Button_702                      matlab.ui.control.StateButton
        Button_703                      matlab.ui.control.StateButton
        Button_704                      matlab.ui.control.StateButton
        Button_705                      matlab.ui.control.StateButton
        Button_706                      matlab.ui.control.StateButton
        Button_707                      matlab.ui.control.StateButton
        Button_708                      matlab.ui.control.StateButton
        Button_709                      matlab.ui.control.StateButton
        Button_710                      matlab.ui.control.StateButton
        Button_711                      matlab.ui.control.StateButton
        VelocidadesTab                  matlab.ui.container.Tab
        LmitedevelocidadLabel           matlab.ui.control.Label
        Button_434                      matlab.ui.control.StateButton
        Button_435                      matlab.ui.control.StateButton
        Button_436                      matlab.ui.control.StateButton
        Button_437                      matlab.ui.control.StateButton
        Button_438                      matlab.ui.control.StateButton
        DiferencialdelmitesdevelocidadLabel  matlab.ui.control.Label
        Button_658                      matlab.ui.control.StateButton
        Button_659                      matlab.ui.control.StateButton
        Button_446                      matlab.ui.control.StateButton
        Button_447                      matlab.ui.control.StateButton
        Button_448                      matlab.ui.control.StateButton
        Button_449                      matlab.ui.control.StateButton
        Button_450                      matlab.ui.control.StateButton
        Button_451                      matlab.ui.control.StateButton
        Button_452                      matlab.ui.control.StateButton
        Button_453                      matlab.ui.control.StateButton
        Button_454                      matlab.ui.control.StateButton
        Button_455                      matlab.ui.control.StateButton
        Button_456                      matlab.ui.control.StateButton
        Button_457                      matlab.ui.control.StateButton
        Button_458                      matlab.ui.control.StateButton
        Button_459                      matlab.ui.control.StateButton
        Button_460                      matlab.ui.control.StateButton
        Button_461                      matlab.ui.control.StateButton
        Button_462                      matlab.ui.control.StateButton
        Button_463                      matlab.ui.control.StateButton
        Button_464                      matlab.ui.control.StateButton
        Button_465                      matlab.ui.control.StateButton
        Velocidaddeoperacinpercentil85Label  matlab.ui.control.Label
        GestindelavelocidadcalmantedeltrficoLabel  matlab.ui.control.Label
        Button_656                      matlab.ui.control.StateButton
        Button_665                      matlab.ui.control.StateButton
        Button_631                      matlab.ui.control.StateButton
        Button_632                      matlab.ui.control.StateButton
        Button_633                      matlab.ui.control.StateButton
        Button_634                      matlab.ui.control.StateButton
        Button_635                      matlab.ui.control.StateButton
        Button_636                      matlab.ui.control.StateButton
        Button_637                      matlab.ui.control.StateButton
        Button_638                      matlab.ui.control.StateButton
        Button_639                      matlab.ui.control.StateButton
        Button_640                      matlab.ui.control.StateButton
        Button_641                      matlab.ui.control.StateButton
        Button_642                      matlab.ui.control.StateButton
        Button_643                      matlab.ui.control.StateButton
        Button_644                      matlab.ui.control.StateButton
        Button_645                      matlab.ui.control.StateButton
        Button_646                      matlab.ui.control.StateButton
        Button_647                      matlab.ui.control.StateButton
        Button_648                      matlab.ui.control.StateButton
        Button_649                      matlab.ui.control.StateButton
        Button_650                      matlab.ui.control.StateButton
        Button_651                      matlab.ui.control.StateButton
        Button_652                      matlab.ui.control.StateButton
        Button_653                      matlab.ui.control.StateButton
        Button_654                      matlab.ui.control.StateButton
        Button_655                      matlab.ui.control.StateButton
        ResultadoTab                    matlab.ui.container.Tab
        PDF                             matlab.ui.control.Button
        VehculoEditFieldLabel           matlab.ui.control.Label
        VehculoEditField                matlab.ui.control.NumericEditField
        MotociclistaEditFieldLabel      matlab.ui.control.Label
        MotociclistaEditField           matlab.ui.control.NumericEditField
        CiclistaEditFieldLabel          matlab.ui.control.Label
        CiclistaEditField               matlab.ui.control.NumericEditField
        PeatnEditFieldLabel             matlab.ui.control.Label
        PeatnEditField                  matlab.ui.control.NumericEditField
        SiguientetramoButton            matlab.ui.control.Button
        ValorEstVeh                     matlab.ui.control.NumericEditField
        ValorEstMoto                    matlab.ui.control.NumericEditField
        ValorEstCicli                   matlab.ui.control.NumericEditField
        ValorEstPeaton                  matlab.ui.control.NumericEditField
        NmerodeestrellasLabel           matlab.ui.control.Label
        ValorLabel                      matlab.ui.control.Label
        ReporteFinalButton              matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes5                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        ContextMenu                     matlab.ui.container.ContextMenu
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
    end

    
    properties (Access = private)
        A1 % Description
        A2 % Description
        A3 % Description
        Matriz1 %#Atributo
        Matriz3 %Nombre atributo
        Datos_interfaz_1
        v1
        Matriz2 % Nombre Boton
        Matriz4 % Coeficientes Vehiculo
        Matriz5 % Coeficientes Moticicleta
        Matriz6 % Coeficientes Ciclista
        Matriz7 % Coeficientes Peatones
        Matriz8 % Matriz para almacenar nombre atributo
        jpgFilename % Para llamar imagen
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Mensaje de advertencia al iniciar el programa
            warndlg('Los botones están seleccionados para un caso particular, el usuario deberá de modificarlos de acuerdo a su preferencia','Aviso')
            
            %Nombres de las matrices
            app.Matriz1=[]; %#Atributo
            app.Matriz2={}; %Nombre boton
            app.Matriz3={}; %Nombre atributo
            app.Matriz4=[]; %Coeficientes Vehiculo
            app.Matriz5=[]; %Coeficientes Motocicleta
            app.Matriz6=[]; %Coeficientes Ciclista
            app.Matriz7=[]; %Coeficientes Peatones
            app.Matriz8={}; %Nombre atributo una sola fila
        
            
            %Se cargan matrices provenientes del preproceso
            app.Image.Visible='off';
            
            load('../mat/preproceso_tramo.mat');
            app.Image.Visible='off';
 
           
            %B=Datos_interfaz_uno.Tramos;
            B=sum(arrayfun(@(tramo) ~isempty(tramo.Tramos),tramo));
           
            %Matriz 1
            Atributo_elegido(length(B)).Atributo=[]
            
            %Datos_interfaz_uno Matriz 2
            Datos_interfaz_uno(length(B)).Tramos = []
            Datos_interfaz_uno(length(B)).Distancia = []
            Datos_interfaz_uno(length(B)).Xt = []
            Datos_interfaz_uno(length(B)).yt = []
            Datos_interfaz_uno(length(B)).Ocupante_del_vehiculo = []
            Datos_interfaz_uno(length(B)).Motociclista = []
            Datos_interfaz_uno(length(B)).Ciclista = []
            Datos_interfaz_uno(length(B)).Peaton = []
            Datos_interfaz_uno(length(B)).Puntuacion_Ocupante_del_vehiculo = []
            Datos_interfaz_uno(length(B)).Puntuacion_Motociclista = []
            Datos_interfaz_uno(length(B)).Puntuacion_Ciclista = []
            Datos_interfaz_uno(length(B)).Puntuacion_Peaton = []
            Datos_interfaz_uno(length(B)).Severidad_lateral_distancia_al_objeto_lado_del_conductor = {}
            Datos_interfaz_uno(length(B)).Severidad_lateral_objeto_lado_del_conductor= {}
            Datos_interfaz_uno(length(B)).Severidad_lateral_distancia_al_objeto_lado_del_copiloto = {}
            Datos_interfaz_uno(length(B)).Severidad_lateral_objeto_lado_del_copiloto = {}
            Datos_interfaz_uno(length(B)).Bandas_sonoras_en_el_espaldon = {}
            Datos_interfaz_uno(length(B)).Ancho_de_espaldon_pavimentado_lado_del_conductor = {}
            Datos_interfaz_uno(length(B)).Ancho_de_espaldon_pavimentado_lado_del_copiloto = {}
            Datos_interfaz_uno(length(B)).Tipo_de_carretera = {}
            Datos_interfaz_uno(length(B)).Costo_de_modernizacion= {}
            Datos_interfaz_uno(length(B)).Tipo_de_mediana = {}
            Datos_interfaz_uno(length(B)).Bandas_sonoras_centrales = {}
            Datos_interfaz_uno(length(B)).Numero_de_carriles = {}
            Datos_interfaz_uno(length(B)).Ancho_de_carril = {}
            Datos_interfaz_uno(length(B)).Curvatura = {}
            Datos_interfaz_uno(length(B)).Calidad_de_la_curva = {}
            Datos_interfaz_uno(length(B)).Pendiente= {}
            Datos_interfaz_uno(length(B)).Estado_de_la_superficie_de_la_via = {}
            Datos_interfaz_uno(length(B)).Resistencia_al_deslizamiento = {}
            Datos_interfaz_uno(length(B)).Delineacion = {}
            Datos_interfaz_uno(length(B)).Alumbrado_publico = {}
            Datos_interfaz_uno(length(B)).Estacionamiento_para_vehiculos = {}
            Datos_interfaz_uno(length(B)).Via_de_servicio = {}
            Datos_interfaz_uno(length(B)).Obras_en_la_carretera = {}
            Datos_interfaz_uno(length(B)).Distancia_de_visibilidad = {}
            Datos_interfaz_uno(length(B)).Tipo_de_interseccion = {}
            Datos_interfaz_uno(length(B)).Canalizacion_de_la_interseccion= {}
            Datos_interfaz_uno(length(B)).Volumen_de_la_interseccion = {}
            Datos_interfaz_uno(length(B)).Calidad_de_la_interseccion = {}
            Datos_interfaz_uno(length(B)).Puntos_de_acceso_a_la_propiedad = {}
            Datos_interfaz_uno(length(B)).Flujo_vehicular = {}
            Datos_interfaz_uno(length(B)).Porcentaje_de_motocicletas = {}
            Datos_interfaz_uno(length(B)).Flujo_peatonal_en_hora_pico_que_cruza_la_carretera = {}
            Datos_interfaz_uno(length(B)).Flujo_peatonal_en_hora_pico_a_lo_largo_de_la_carretera_Cond = {}
            Datos_interfaz_uno(length(B)).Flujo_peatonal_en_hora_pico_a_lo_largo_de_la_carretera_Cop = {}
            Datos_interfaz_uno(length(B)).Flujo_de_bicicletas_en_hora_pico = {}
            Datos_interfaz_uno(length(B)).Uso_de_suelo_lado_del_conductor= {}
            Datos_interfaz_uno(length(B)).Uso_de_suelo_lado_del_copiloto = {}
            Datos_interfaz_uno(length(B)).Tipo_de_area = {}
            Datos_interfaz_uno(length(B)).Infraestructura_de_cruce_peatonal_en_el_tramo_analizado = {}
            Datos_interfaz_uno(length(B)).Calidad_de_cruce_peatonal = {}
            Datos_interfaz_uno(length(B)).Infraestructura_de_cruce_peatonal_en_la_interseccion = {}
            Datos_interfaz_uno(length(B)).Vallas_peatonales= {}
            Datos_interfaz_uno(length(B)).Acera_lado_del_conductor = {}
            Datos_interfaz_uno(length(B)).Acera_lado_del_copiloto = {}
            Datos_interfaz_uno(length(B)).Infraestructura_para_motocicletas = {}
            Datos_interfaz_uno(length(B)).Infraestructura_para_bicicletas = {}
            Datos_interfaz_uno(length(B)).Advertencia_de_zona_escolar = {}
            Datos_interfaz_uno(length(B)).Supervisor_en_el_cruce_de_zona_escolar= {}
            Datos_interfaz_uno(length(B)).Limite_de_velocidad = {}
            Datos_interfaz_uno(length(B)).Diferencial_de_limites_de_velocidad = {}
            Datos_interfaz_uno(length(B)).Gestion_de_la_velocidad_calmante_del_trafico = {}
            Datos_interfaz_uno(length(B)).Velocidad_de_operacion_percentil_85 = {}
            Datos_interfaz_uno(length(B)).Transitabilidad = {}
            
            %Datos_interfaz_dos Matriz 3
            Datos_interfaz_dos(length(B)).Tramos = []
            Datos_interfaz_dos(length(B)).Distancia = []
            Datos_interfaz_dos(length(B)).Xt = []
            Datos_interfaz_dos(length(B)).yt = []
            Datos_interfaz_dos(length(B)).Ocupante_del_vehiculo = []
            Datos_interfaz_dos(length(B)).Motociclista = []
            Datos_interfaz_dos(length(B)).Ciclista = []
            Datos_interfaz_dos(length(B)).Peaton = []
            Datos_interfaz_dos(length(B)).Puntuacion_Ocupante_del_vehiculo = []
            Datos_interfaz_dos(length(B)).Puntuacion_Motociclista = []
            Datos_interfaz_dos(length(B)).Puntuacion_Ciclista = []
            Datos_interfaz_dos(length(B)).Puntuacion_Peaton = []
            Datos_interfaz_dos(length(B)).Severidad_lateral_distancia_al_objeto_lado_del_conductor = {}
            Datos_interfaz_dos(length(B)).Severidad_lateral_objeto_lado_del_conductor= {}
            Datos_interfaz_dos(length(B)).Severidad_lateral_distancia_al_objeto_lado_del_copiloto = {}
            Datos_interfaz_dos(length(B)).Severidad_lateral_objeto_lado_del_copiloto = {}
            Datos_interfaz_dos(length(B)).Bandas_sonoras_en_el_espaldon = {}
            Datos_interfaz_dos(length(B)).Ancho_de_espaldon_pavimentado_lado_del_conductor = {}
            Datos_interfaz_dos(length(B)).Ancho_de_espaldon_pavimentado_lado_del_copiloto = {}
            Datos_interfaz_dos(length(B)).Tipo_de_carretera = {}
            Datos_interfaz_dos(length(B)).Costo_de_modernizacion= {}
            Datos_interfaz_dos(length(B)).Tipo_de_mediana = {}
            Datos_interfaz_dos(length(B)).Bandas_sonoras_centrales = {}
            Datos_interfaz_dos(length(B)).Numero_de_carriles = {}
            Datos_interfaz_dos(length(B)).Ancho_de_carril = {}
            Datos_interfaz_dos(length(B)).Curvatura = {}
            Datos_interfaz_dos(length(B)).Calidad_de_la_curva = {}
            Datos_interfaz_dos(length(B)).Pendiente= {}
            Datos_interfaz_dos(length(B)).Estado_de_la_superficie_de_la_via = {}
            Datos_interfaz_dos(length(B)).Resistencia_al_deslizamiento = {}
            Datos_interfaz_dos(length(B)).Delineacion = {}
            Datos_interfaz_dos(length(B)).Alumbrado_publico = {}
            Datos_interfaz_dos(length(B)).Estacionamiento_para_vehiculos = {}
            Datos_interfaz_dos(length(B)).Via_de_servicio = {}
            Datos_interfaz_dos(length(B)).Obras_en_la_carretera = {}
            Datos_interfaz_dos(length(B)).Distancia_de_visibilidad = {}
            Datos_interfaz_dos(length(B)).Tipo_de_interseccion = {}
            Datos_interfaz_dos(length(B)).Canalizacion_de_la_interseccion= {}
            Datos_interfaz_dos(length(B)).Volumen_de_la_interseccion = {}
            Datos_interfaz_dos(length(B)).Calidad_de_la_interseccion = {}
            Datos_interfaz_dos(length(B)).Puntos_de_acceso_a_la_propiedad = {}
            Datos_interfaz_dos(length(B)).Flujo_vehicular = {}
            Datos_interfaz_dos(length(B)).Porcentaje_de_motocicletas = {}
            Datos_interfaz_dos(length(B)).Flujo_peatonal_en_hora_pico_que_cruza_la_carretera = {}
            Datos_interfaz_dos(length(B)).Flujo_peatonal_en_hora_pico_a_lo_largo_de_la_carretera_Cond = {}
            Datos_interfaz_dos(length(B)).Flujo_peatonal_en_hora_pico_a_lo_largo_de_la_carretera_Cop = {}
            Datos_interfaz_dos(length(B)).Flujo_de_bicicletas_en_hora_pico = {}
            Datos_interfaz_dos(length(B)).Uso_de_suelo_lado_del_conductor= {}
            Datos_interfaz_dos(length(B)).Uso_de_suelo_lado_del_copiloto = {}
            Datos_interfaz_dos(length(B)).Tipo_de_area = {}
            Datos_interfaz_dos(length(B)).Infraestructura_de_cruce_peatonal_en_el_tramo_analizado = {}
            Datos_interfaz_dos(length(B)).Calidad_de_cruce_peatonal = {}
            Datos_interfaz_dos(length(B)).Infraestructura_de_cruce_peatonal_en_la_interseccion = {}
            Datos_interfaz_dos(length(B)).Vallas_peatonales= {}
            Datos_interfaz_dos(length(B)).Acera_lado_del_conductor = {}
            Datos_interfaz_dos(length(B)).Acera_lado_del_copiloto = {}
            Datos_interfaz_dos(length(B)).Infraestructura_para_motocicletas = {}
            Datos_interfaz_dos(length(B)).Infraestructura_para_bicicletas = {}
            Datos_interfaz_dos(length(B)).Advertencia_de_zona_escolar = {}
            Datos_interfaz_dos(length(B)).Supervisor_en_el_cruce_de_zona_escolar= {}
            Datos_interfaz_dos(length(B)).Limite_de_velocidad = {}
            Datos_interfaz_dos(length(B)).Diferencial_de_limites_de_velocidad = {}
            Datos_interfaz_dos(length(B)).Gestion_de_la_velocidad_calmante_del_trafico = {}
            Datos_interfaz_dos(length(B)).Velocidad_de_operacion_percentil_85 = {}
            Datos_interfaz_dos(length(B)).Transitabilidad = {}
            
            %Matriz 8 Nombre atributo una sola fila
            %Datos_interfaz_dos Matriz 3
            Datos_interfaz_tres(1).Tramos = []
            Datos_interfaz_tres(1).Distancia = []
            Datos_interfaz_tres(1).Xt = []
            Datos_interfaz_tres(1).yt = []
            Datos_interfaz_tres(1).Ocupante_del_vehiculo = []
            Datos_interfaz_tres(1).Motociclista = []
            Datos_interfaz_tres(1).Ciclista = []
            Datos_interfaz_tres(1).Peaton = []
            Datos_interfaz_tres(1).Puntuacion_Ocupante_del_vehiculo = []
            Datos_interfaz_tres(1).Puntuacion_Motociclista = []
            Datos_interfaz_tres(1).Puntuacion_Ciclista = []
            Datos_interfaz_tres(1).Puntuacion_Peaton = []
            Datos_interfaz_tres(1).Severidad_lateral_distancia_al_objeto_lado_del_conductor = {}
            Datos_interfaz_tres(1).Severidad_lateral_objeto_lado_del_conductor= {}
            Datos_interfaz_tres(1).Severidad_lateral_distancia_al_objeto_lado_del_copiloto = {}
            Datos_interfaz_tres(1).Severidad_lateral_objeto_lado_del_copiloto = {}
            Datos_interfaz_tres(1).Bandas_sonoras_en_el_espaldon = {}
            Datos_interfaz_tres(1).Ancho_de_espaldon_pavimentado_lado_del_conductor = {}
            Datos_interfaz_tres(1).Ancho_de_espaldon_pavimentado_lado_del_copiloto = {}
            Datos_interfaz_tres(1).Tipo_de_carretera = {}
            Datos_interfaz_tres(1).Costo_de_modernizacion= {}
            Datos_interfaz_tres(1).Tipo_de_mediana = {}
            Datos_interfaz_tres(1).Bandas_sonoras_centrales = {}
            Datos_interfaz_tres(1).Numero_de_carriles = {}
            Datos_interfaz_tres(1).Ancho_de_carril = {}
            Datos_interfaz_tres(1).Curvatura = {}
            Datos_interfaz_tres(1).Calidad_de_la_curva = {}
            Datos_interfaz_tres(1).Pendiente= {}
            Datos_interfaz_tres(1).Estado_de_la_superficie_de_la_via = {}
            Datos_interfaz_tres(1).Resistencia_al_deslizamiento = {}
            Datos_interfaz_tres(1).Delineacion = {}
            Datos_interfaz_tres(1).Alumbrado_publico = {}
            Datos_interfaz_tres(1).Estacionamiento_para_vehiculos = {}
            Datos_interfaz_tres(1).Via_de_servicio = {}
            Datos_interfaz_tres(1).Obras_en_la_carretera = {}
            Datos_interfaz_tres(1).Distancia_de_visibilidad = {}
            Datos_interfaz_tres(1).Tipo_de_interseccion = {}
            Datos_interfaz_tres(1).Canalizacion_de_la_interseccion= {}
            Datos_interfaz_tres(1).Volumen_de_la_interseccion = {}
            Datos_interfaz_tres(1).Calidad_de_la_interseccion = {}
            Datos_interfaz_tres(1).Puntos_de_acceso_a_la_propiedad = {}
            Datos_interfaz_tres(1).Flujo_vehicular = {}
            Datos_interfaz_tres(1).Porcentaje_de_motocicletas = {}
            Datos_interfaz_tres(1).Flujo_peatonal_en_hora_pico_que_cruza_la_carretera = {}
            Datos_interfaz_tres(1).Flujo_peatonal_en_hora_pico_a_lo_largo_de_la_carretera_Cond = {}
            Datos_interfaz_tres(1).Flujo_peatonal_en_hora_pico_a_lo_largo_de_la_carretera_Cop = {}
            Datos_interfaz_tres(1).Flujo_de_bicicletas_en_hora_pico = {}
            Datos_interfaz_tres(1).Uso_de_suelo_lado_del_conductor= {}
            Datos_interfaz_tres(1).Uso_de_suelo_lado_del_copiloto = {}
            Datos_interfaz_tres(1).Tipo_de_area = {}
            Datos_interfaz_tres(1).Infraestructura_de_cruce_peatonal_en_el_tramo_analizado = {}
            Datos_interfaz_tres(1).Calidad_de_cruce_peatonal = {}
            Datos_interfaz_tres(1).Infraestructura_de_cruce_peatonal_en_la_interseccion = {}
            Datos_interfaz_tres(1).Vallas_peatonales= {}
            Datos_interfaz_tres(1).Acera_lado_del_conductor = {}
            Datos_interfaz_tres(1).Acera_lado_del_copiloto = {}
            Datos_interfaz_tres(1).Infraestructura_para_motocicletas = {}
            Datos_interfaz_tres(1).Infraestructura_para_bicicletas = {}
            Datos_interfaz_tres(1).Advertencia_de_zona_escolar = {}
            Datos_interfaz_tres(1).Supervisor_en_el_cruce_de_zona_escolar= {}
            Datos_interfaz_tres(1).Limite_de_velocidad = {}
            Datos_interfaz_tres(1).Diferencial_de_limites_de_velocidad = {}
            Datos_interfaz_tres(1).Gestion_de_la_velocidad_calmante_del_trafico = {}
            Datos_interfaz_tres(1).Velocidad_de_operacion_percentil_85 = {}
            Datos_interfaz_tres(1).Transitabilidad = {}
            
            %Matrices de los Coeficientes
            %Matriz 4
            Coeficientes_Veh(1).Salida_lado_conductor=[]
            %Matriz 4.1
            Coeficientes_Veh(1).Salida_lado_copiloto=[]
            %Matriz 4.2
            Coeficientes_Veh(1).Perdida_de_control=[]
            %Matriz 4.3
            Coeficientes_Veh(1).Adelantamiento=[]
            %Matriz 4.4
            Coeficientes_Veh(1).Interseccion=[]
            %Matriz 4.5
            Coeficientes_Veh(1).Acceso_a_Propiedades=[]
            %Matriz 5
            Coeficientes_Mot(1).Salida_lado_conductor=[]
            %Matriz 5.1
            Coeficientes_Mot(1).Salida_lado_copiloto=[]
            %Matriz 5.2
            Coeficientes_Mot(1).Perdida_de_control=[]
            %Matriz 5.3
            Coeficientes_Mot(1).Adelantamiento=[]
            %Matriz 5.4
            Coeficientes_Mot(1).Interseccion=[]
            %Matriz 5.5
            Coeficientes_Mot(1).Acceso_a_Propiedades=[]
            %Matriz 5.6
            Coeficientes_Mot(1).A_lo_largo=[]
            %Matriz 6
            Coeficientes_Ci(1).A_lo_largo=[]
            %Matriz 6.1
            Coeficientes_Ci(1).Salida_camino=[]
            %Matriz 6.2
            Coeficientes_Ci(1).Interseccion=[]
            %Matriz 7
            Coeficientes_Pe(1).A_lo_largo_conductor=[]
            %Matriz 7.1
            Coeficientes_Pe(1).A_lo_largo_copiloto=[]
            %Matriz 7.2
            Coeficientes_Pe(1).Cruce_peatonal_inspeccionada=[]
            %Matriz 7.3
            Coeficientes_Pe(1).Cruce_peatonal_lateral=[]
            
            %Guardado de matrices
            save('../mat/Datos_atributos_proceso',"Atributo_elegido")
            save('../mat/Datos_interfaz_uno_proceso',"Datos_interfaz_uno")
            save('../mat/Datos_interfaz_dos_proceso',"Datos_interfaz_dos")
            save('../mat/Coeficientes_Veh',"Coeficientes_Veh")
            save('../mat/Coeficientes_Mot',"Coeficientes_Mot")
            save('../mat/Coeficientes_Ci',"Coeficientes_Ci")
            save('../mat/Coeficientes_Pe',"Coeficientes_Pe")
            save('../mat/Datos_interfaz_tres_proceso',"Datos_interfaz_tres")


        end

        % Value changed function: Button
        function ButtonValueChanged(app, event)
            value = app.Button.Value;
            a1=1;
            b1=cellstr('0 a < 1 m');
            c1=cellstr('app.Button');
            app.Matriz2(1)=c1;
            app.Matriz1(1)=a1;
            app.Matriz3(1)=b1;

            
            app.Button.BackgroundColor=[1.00,1.00,0.00];
            app.Button_2.BackgroundColor=[0.96,0.96,0.96];
            app.Button_3.BackgroundColor=[0.96,0.96,0.96];
            app.Button_4.BackgroundColor=[0.96,0.96,0.96];


        end

        % Value changed function: CarrilP
        function CarrilPValueChanged(app, event)
            value = app.CarrilP.Value;
            
            A87=3;
            B87=cellstr('Estrecho (≥ 0 m a < 2,75 m)');
            c87=cellstr('app.CarrilP');
            app.Matriz2(13)=c87;
            app.Matriz1(13)=A87;
            app.Matriz3(13)=B87;
            
            app.CarrilG_2.BackgroundColor=[0.96,0.96,0.96];
            app.CarrilM.BackgroundColor=[0.96,0.96,0.96];
            app.CarrilP.BackgroundColor=[1.00,1.00,0.00];
            
        end

        % Value changed function: CarrilM
        function CarrilMValueChanged(app, event)
            value = app.CarrilM.Value;  
            A86=2;
            B86=cellstr('Medio (≥ 2,75 m a < 3,25 m)');
            c86=cellstr('app.CarrilM');
            app.Matriz2(13)=c86;
            app.Matriz1(13)=A86;
            app.Matriz3(13)=B86;
            
            app.CarrilG_2.BackgroundColor=[0.96,0.96,0.96];
            app.CarrilM.BackgroundColor=[1.00,1.00,0.00];
            app.CarrilP.BackgroundColor=[0.96,0.96,0.96];
       
            
        end

        % Value changed function: Button_660
        function Button_660ValueChanged(app, event)
          value = app.Button_660.Value;
            A79=1;
            B79=cellstr('Uno');
            c79=cellstr('app.Button_660');
            app.Matriz2(12)=c79;
            app.Matriz1(12)=A79;
            app.Matriz3(12)=B79;
  
            app.Button_660.BackgroundColor=[1.00,1.00,0.00];
            app.Button_661.BackgroundColor=[0.96,0.96,0.96];
            app.Button_121.BackgroundColor=[0.96,0.96,0.96];
            app.Button_122.BackgroundColor=[0.96,0.96,0.96];
	        app.Button_662.BackgroundColor=[0.96,0.96,0.96];
            app.Button_559.BackgroundColor=[0.96,0.96,0.96];
        end

        % Callback function
        function ResultadoButtonPushed(app, event)
           [EstVehiculo,EstMotocicleta,EstCiclista,EstPeaton]=Calculo_estrellas(app.Matriz1);
           app.EstrellasEditField.Value=EstVehiculo;
           app.EstrellasEditField_1.Value=EstMotocicleta;
           app.EstrellasEditField_2.Value=EstCiclista;
           app.EstrellasEditField_3.Value=EstPeaton;
        end

        % Value changed function: NAButton
        function NAButtonValueChanged(app, event)
            value = app.NAButton.Value;  
            A156=1;
            B156=cellstr('No registrado');
            c156=cellstr('app.NAButton');
            app.Matriz2(31)=c156;
            app.Matriz1(31)=A156;
            app.Matriz3(31)=B156;
            
            app.NAButton.BackgroundColor=[1.00,1.00,0.00];
            app.Button_270.BackgroundColor=[0.96,0.96,0.96];
            app.Button_271.BackgroundColor=[0.96,0.96,0.96];
            app.Button_263.BackgroundColor=[0.96,0.96,0.96];
            app.Button_264.BackgroundColor=[0.96,0.96,0.96];
            app.Button_261.BackgroundColor=[0.96,0.96,0.96];
            app.Button_262.BackgroundColor=[0.96,0.96,0.96];
            app.Button_275.BackgroundColor=[0.96,0.96,0.96];
            app.Button_276.BackgroundColor=[0.96,0.96,0.96];
            app.Button_712.BackgroundColor=[0.96,0.96,0.96];


        end

        % Value changed function: Button_434
        function Button_434ValueChanged(app, event)
            A241=30;
            B241=cellstr('< 30 km/h');
            c241=cellstr('app.Button_434');
            app.Matriz2(49)=c241;
            app.Matriz1(49)=A241;
            app.Matriz3(49)=B241;
            
            value = app.Button_434.Value;  
            
            app.Button_434.BackgroundColor=[1.00,1.00,0.00];
            app.Button_435.BackgroundColor=[0.96,0.96,0.96];
            app.Button_436.BackgroundColor=[0.96,0.96,0.96];
            app.Button_437.BackgroundColor=[0.96,0.96,0.96];
            app.Button_438.BackgroundColor=[0.96,0.96,0.96];
            app.Button_446.BackgroundColor=[0.96,0.96,0.96];
            app.Button_447.BackgroundColor=[0.96,0.96,0.96];
            app.Button_448.BackgroundColor=[0.96,0.96,0.96];
            app.Button_449.BackgroundColor=[0.96,0.96,0.96];
            app.Button_450.BackgroundColor=[0.96,0.96,0.96];
            app.Button_451.BackgroundColor=[0.96,0.96,0.96];
            app.Button_452.BackgroundColor=[0.96,0.96,0.96];
            app.Button_453.BackgroundColor=[0.96,0.96,0.96];
            app.Button_454.BackgroundColor=[0.96,0.96,0.96];
            app.Button_455.BackgroundColor=[0.96,0.96,0.96];
            app.Button_456.BackgroundColor=[0.96,0.96,0.96];
            app.Button_457.BackgroundColor=[0.96,0.96,0.96];
            app.Button_458.BackgroundColor=[0.96,0.96,0.96];
            app.Button_459.BackgroundColor=[0.96,0.96,0.96];
            app.Button_460.BackgroundColor=[0.96,0.96,0.96];
            app.Button_461.BackgroundColor=[0.96,0.96,0.96];
            app.Button_462.BackgroundColor=[0.96,0.96,0.96];
            app.Button_463.BackgroundColor=[0.96,0.96,0.96];
            app.Button_464.BackgroundColor=[0.96,0.96,0.96];
            app.Button_465.BackgroundColor=[0.96,0.96,0.96];

            
        end

        % Value changed function: Button_435
        function Button_435ValueChanged(app, event)
            A242=35;
            B242=cellstr('35 km/h');
            c242=cellstr('app.Button_435');
            app.Matriz2(49)=c242;
            app.Matriz1(49)=A242;
            app.Matriz3(49)=B242;
            
            value = app.Button_435.Value;  
            
            app.Button_434.BackgroundColor=[0.96,0.96,0.96];
            app.Button_435.BackgroundColor=[1.00,1.00,0.00];
            app.Button_436.BackgroundColor=[0.96,0.96,0.96];
            app.Button_437.BackgroundColor=[0.96,0.96,0.96];
            app.Button_438.BackgroundColor=[0.96,0.96,0.96];
            app.Button_446.BackgroundColor=[0.96,0.96,0.96];
            app.Button_447.BackgroundColor=[0.96,0.96,0.96];
            app.Button_448.BackgroundColor=[0.96,0.96,0.96];
            app.Button_449.BackgroundColor=[0.96,0.96,0.96];
            app.Button_450.BackgroundColor=[0.96,0.96,0.96];
            app.Button_451.BackgroundColor=[0.96,0.96,0.96];
            app.Button_452.BackgroundColor=[0.96,0.96,0.96];
            app.Button_453.BackgroundColor=[0.96,0.96,0.96];
            app.Button_454.BackgroundColor=[0.96,0.96,0.96];
            app.Button_455.BackgroundColor=[0.96,0.96,0.96];
            app.Button_456.BackgroundColor=[0.96,0.96,0.96];
            app.Button_457.BackgroundColor=[0.96,0.96,0.96];
            app.Button_458.BackgroundColor=[0.96,0.96,0.96];
            app.Button_459.BackgroundColor=[0.96,0.96,0.96];
            app.Button_460.BackgroundColor=[0.96,0.96,0.96];
            app.Button_461.BackgroundColor=[0.96,0.96,0.96];
            app.Button_462.BackgroundColor=[0.96,0.96,0.96];
            app.Button_463.BackgroundColor=[0.96,0.96,0.96];
            app.Button_464.BackgroundColor=[0.96,0.96,0.96];
            app.Button_465.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_658
        function Button_658ValueChanged(app, event)
            A267=2;
            B267=cellstr('Presente');
            c267=cellstr('app.Button_658');
            app.Matriz2(50)=c267;
            app.Matriz1(50)=A267;
            app.Matriz3(50)=B267;
            
            value = app.Button_658.Value;
            
            app.Button_658.BackgroundColor=[1.00,1.00,0.00];
            app.Button_659.BackgroundColor=[0.96,0.96,0.96];
                
        end

        % Value changed function: Button_659
        function Button_659ValueChanged(app, event)
            value = app.Button_659.Value;
            A266=1;
            B266=cellstr('Ausente');
            c266=cellstr('app.Button_659');
            app.Matriz2(50)=c266;
            app.Matriz1(50)=A266;
            app.Matriz3(50)=B266;

            app.Button_658.BackgroundColor=[0.96,0.96,0.96];
            app.Button_659.BackgroundColor=[1.00,1.00,0.00];
                
        end

        % Value changed function: Button_436
        function Button_436ValueChanged(app, event)
            A243=40;
            B243=cellstr('40 km/h');
            c243=cellstr('app.Button_436');
            app.Matriz2(49)=c243;
            app.Matriz1(49)=A243;
            app.Matriz3(49)=B243;
            
            value = app.Button_436.Value;
            
            app.Button_434.BackgroundColor=[0.96,0.96,0.96];
            app.Button_435.BackgroundColor=[0.96,0.96,0.96];
            app.Button_436.BackgroundColor=[1.00,1.00,0.00];
            app.Button_437.BackgroundColor=[0.96,0.96,0.96];
            app.Button_438.BackgroundColor=[0.96,0.96,0.96];
            app.Button_446.BackgroundColor=[0.96,0.96,0.96];
            app.Button_447.BackgroundColor=[0.96,0.96,0.96];
            app.Button_448.BackgroundColor=[0.96,0.96,0.96];
            app.Button_449.BackgroundColor=[0.96,0.96,0.96];
            app.Button_450.BackgroundColor=[0.96,0.96,0.96];
            app.Button_451.BackgroundColor=[0.96,0.96,0.96];
            app.Button_452.BackgroundColor=[0.96,0.96,0.96];
            app.Button_453.BackgroundColor=[0.96,0.96,0.96];
            app.Button_454.BackgroundColor=[0.96,0.96,0.96];
            app.Button_455.BackgroundColor=[0.96,0.96,0.96];
            app.Button_456.BackgroundColor=[0.96,0.96,0.96];
            app.Button_457.BackgroundColor=[0.96,0.96,0.96];
            app.Button_458.BackgroundColor=[0.96,0.96,0.96];
            app.Button_459.BackgroundColor=[0.96,0.96,0.96];
            app.Button_460.BackgroundColor=[0.96,0.96,0.96];
            app.Button_461.BackgroundColor=[0.96,0.96,0.96];
            app.Button_462.BackgroundColor=[0.96,0.96,0.96];
            app.Button_463.BackgroundColor=[0.96,0.96,0.96];
            app.Button_464.BackgroundColor=[0.96,0.96,0.96];
            app.Button_465.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_437
        function Button_437ValueChanged(app, event)
            A244=45;
            B244=cellstr('45 km/h');
            c244=cellstr('app.Button_437');
            app.Matriz2(49)=c244;
            app.Matriz1(49)=A244;
            app.Matriz3(49)=B244;
            
            value = app.Button_437.Value;
            app.Button_434.BackgroundColor=[0.96,0.96,0.96];
            app.Button_435.BackgroundColor=[0.96,0.96,0.96];
            app.Button_436.BackgroundColor=[0.96,0.96,0.96];
            app.Button_437.BackgroundColor=[1.00,1.00,0.00];
            app.Button_438.BackgroundColor=[0.96,0.96,0.96];
            app.Button_446.BackgroundColor=[0.96,0.96,0.96];
            app.Button_447.BackgroundColor=[0.96,0.96,0.96];
            app.Button_448.BackgroundColor=[0.96,0.96,0.96];
            app.Button_449.BackgroundColor=[0.96,0.96,0.96];
            app.Button_450.BackgroundColor=[0.96,0.96,0.96];
            app.Button_451.BackgroundColor=[0.96,0.96,0.96];
            app.Button_452.BackgroundColor=[0.96,0.96,0.96];
            app.Button_453.BackgroundColor=[0.96,0.96,0.96];
            app.Button_454.BackgroundColor=[0.96,0.96,0.96];
            app.Button_455.BackgroundColor=[0.96,0.96,0.96];
            app.Button_456.BackgroundColor=[0.96,0.96,0.96];
            app.Button_457.BackgroundColor=[0.96,0.96,0.96];
            app.Button_458.BackgroundColor=[0.96,0.96,0.96];
            app.Button_459.BackgroundColor=[0.96,0.96,0.96];
            app.Button_460.BackgroundColor=[0.96,0.96,0.96];
            app.Button_461.BackgroundColor=[0.96,0.96,0.96];
            app.Button_462.BackgroundColor=[0.96,0.96,0.96];
            app.Button_463.BackgroundColor=[0.96,0.96,0.96];
            app.Button_464.BackgroundColor=[0.96,0.96,0.96];
            app.Button_465.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_438
        function Button_438ValueChanged(app, event)
            A245=50;
            B245=cellstr('50 km/h');
            c245=cellstr('app.Button_438');
            app.Matriz2(49)=c245;
            app.Matriz1(49)=A245;
            app.Matriz3(49)=B245;
            
            value = app.Button_438.Value;
            app.Button_434.BackgroundColor=[0.96,0.96,0.96];
            app.Button_435.BackgroundColor=[0.96,0.96,0.96];
            app.Button_436.BackgroundColor=[0.96,0.96,0.96];
            app.Button_437.BackgroundColor=[0.96,0.96,0.96];
            app.Button_438.BackgroundColor=[1.00,1.00,0.00];
            app.Button_446.BackgroundColor=[0.96,0.96,0.96];
            app.Button_447.BackgroundColor=[0.96,0.96,0.96];
            app.Button_448.BackgroundColor=[0.96,0.96,0.96];
            app.Button_449.BackgroundColor=[0.96,0.96,0.96];
            app.Button_450.BackgroundColor=[0.96,0.96,0.96];
            app.Button_451.BackgroundColor=[0.96,0.96,0.96];
            app.Button_452.BackgroundColor=[0.96,0.96,0.96];
            app.Button_453.BackgroundColor=[0.96,0.96,0.96];
            app.Button_454.BackgroundColor=[0.96,0.96,0.96];
            app.Button_455.BackgroundColor=[0.96,0.96,0.96];
            app.Button_456.BackgroundColor=[0.96,0.96,0.96];
            app.Button_457.BackgroundColor=[0.96,0.96,0.96];
            app.Button_458.BackgroundColor=[0.96,0.96,0.96];
            app.Button_459.BackgroundColor=[0.96,0.96,0.96];
            app.Button_460.BackgroundColor=[0.96,0.96,0.96];
            app.Button_461.BackgroundColor=[0.96,0.96,0.96];
            app.Button_462.BackgroundColor=[0.96,0.96,0.96];
            app.Button_463.BackgroundColor=[0.96,0.96,0.96];
            app.Button_464.BackgroundColor=[0.96,0.96,0.96];
            app.Button_465.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_446
        function Button_446ValueChanged(app, event)
            A246=55;
            B246=cellstr('55 km/h');
            c246=cellstr('app.Button_446');
            app.Matriz2(49)=c246;
            app.Matriz1(49)=A246;
            app.Matriz3(49)=B246;
            
            value = app.Button_446.Value;
            app.Button_434.BackgroundColor=[0.96,0.96,0.96];
            app.Button_435.BackgroundColor=[0.96,0.96,0.96];
            app.Button_436.BackgroundColor=[0.96,0.96,0.96];
            app.Button_437.BackgroundColor=[0.96,0.96,0.96];
            app.Button_438.BackgroundColor=[0.96,0.96,0.96];
            app.Button_446.BackgroundColor=[1.00,1.00,0.00];
            app.Button_447.BackgroundColor=[0.96,0.96,0.96];
            app.Button_448.BackgroundColor=[0.96,0.96,0.96];
            app.Button_449.BackgroundColor=[0.96,0.96,0.96];
            app.Button_450.BackgroundColor=[0.96,0.96,0.96];
            app.Button_451.BackgroundColor=[0.96,0.96,0.96];
            app.Button_452.BackgroundColor=[0.96,0.96,0.96];
            app.Button_453.BackgroundColor=[0.96,0.96,0.96];
            app.Button_454.BackgroundColor=[0.96,0.96,0.96];
            app.Button_455.BackgroundColor=[0.96,0.96,0.96];
            app.Button_456.BackgroundColor=[0.96,0.96,0.96];
            app.Button_457.BackgroundColor=[0.96,0.96,0.96];
            app.Button_458.BackgroundColor=[0.96,0.96,0.96];
            app.Button_459.BackgroundColor=[0.96,0.96,0.96];
            app.Button_460.BackgroundColor=[0.96,0.96,0.96];
            app.Button_461.BackgroundColor=[0.96,0.96,0.96];
            app.Button_462.BackgroundColor=[0.96,0.96,0.96];
            app.Button_463.BackgroundColor=[0.96,0.96,0.96];
            app.Button_464.BackgroundColor=[0.96,0.96,0.96];
            app.Button_465.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_447
        function Button_447ValueChanged(app, event)
            A247=60;
            B247=cellstr('60 km/h');
            c247=cellstr('app.Button_447');
            app.Matriz2(49)=c247;
            app.Matriz1(49)=A247;
            app.Matriz3(49)=B247;
            
            value = app.Button_447.Value;
            app.Button_434.BackgroundColor=[0.96,0.96,0.96];
            app.Button_435.BackgroundColor=[0.96,0.96,0.96];
            app.Button_436.BackgroundColor=[0.96,0.96,0.96];
            app.Button_437.BackgroundColor=[0.96,0.96,0.96];
            app.Button_438.BackgroundColor=[0.96,0.96,0.96];
            app.Button_446.BackgroundColor=[0.96,0.96,0.96];
            app.Button_447.BackgroundColor=[1.00,1.00,0.00];
            app.Button_448.BackgroundColor=[0.96,0.96,0.96];
            app.Button_449.BackgroundColor=[0.96,0.96,0.96];
            app.Button_450.BackgroundColor=[0.96,0.96,0.96];
            app.Button_451.BackgroundColor=[0.96,0.96,0.96];
            app.Button_452.BackgroundColor=[0.96,0.96,0.96];
            app.Button_453.BackgroundColor=[0.96,0.96,0.96];
            app.Button_454.BackgroundColor=[0.96,0.96,0.96];
            app.Button_455.BackgroundColor=[0.96,0.96,0.96];
            app.Button_456.BackgroundColor=[0.96,0.96,0.96];
            app.Button_457.BackgroundColor=[0.96,0.96,0.96];
            app.Button_458.BackgroundColor=[0.96,0.96,0.96];
            app.Button_459.BackgroundColor=[0.96,0.96,0.96];
            app.Button_460.BackgroundColor=[0.96,0.96,0.96];
            app.Button_461.BackgroundColor=[0.96,0.96,0.96];
            app.Button_462.BackgroundColor=[0.96,0.96,0.96];
            app.Button_463.BackgroundColor=[0.96,0.96,0.96];
            app.Button_464.BackgroundColor=[0.96,0.96,0.96];
            app.Button_465.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_448
        function Button_448ValueChanged(app, event)
            A248=65;
            B248=cellstr('65 km/h');
            c248=cellstr('app.Button_448');
            app.Matriz2(49)=c248;
            app.Matriz1(49)=A248;
            app.Matriz3(49)=B248;
            
            value = app.Button_448.Value;
            app.Button_434.BackgroundColor=[0.96,0.96,0.96];
            app.Button_435.BackgroundColor=[0.96,0.96,0.96];
            app.Button_436.BackgroundColor=[0.96,0.96,0.96];
            app.Button_437.BackgroundColor=[0.96,0.96,0.96];
            app.Button_438.BackgroundColor=[0.96,0.96,0.96];
            app.Button_446.BackgroundColor=[0.96,0.96,0.96];
            app.Button_447.BackgroundColor=[0.96,0.96,0.96];
            app.Button_448.BackgroundColor=[1.00,1.00,0.00];
            app.Button_449.BackgroundColor=[0.96,0.96,0.96];
            app.Button_450.BackgroundColor=[0.96,0.96,0.96];
            app.Button_451.BackgroundColor=[0.96,0.96,0.96];
            app.Button_452.BackgroundColor=[0.96,0.96,0.96];
            app.Button_453.BackgroundColor=[0.96,0.96,0.96];
            app.Button_454.BackgroundColor=[0.96,0.96,0.96];
            app.Button_455.BackgroundColor=[0.96,0.96,0.96];
            app.Button_456.BackgroundColor=[0.96,0.96,0.96];
            app.Button_457.BackgroundColor=[0.96,0.96,0.96];
            app.Button_458.BackgroundColor=[0.96,0.96,0.96];
            app.Button_459.BackgroundColor=[0.96,0.96,0.96];
            app.Button_460.BackgroundColor=[0.96,0.96,0.96];
            app.Button_461.BackgroundColor=[0.96,0.96,0.96];
            app.Button_462.BackgroundColor=[0.96,0.96,0.96];
            app.Button_463.BackgroundColor=[0.96,0.96,0.96];
            app.Button_464.BackgroundColor=[0.96,0.96,0.96];
            app.Button_465.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_449
        function Button_449ValueChanged(app, event)
            A249=70;
            B249=cellstr('70 km/h');
            c249=cellstr('app.Button_449');
            app.Matriz2(49)=c249;
            app.Matriz1(49)=A249;
            app.Matriz3(49)=B249;
            
            value = app.Button_449.Value;
            app.Button_434.BackgroundColor=[0.96,0.96,0.96];
            app.Button_435.BackgroundColor=[0.96,0.96,0.96];
            app.Button_436.BackgroundColor=[0.96,0.96,0.96];
            app.Button_437.BackgroundColor=[0.96,0.96,0.96];
            app.Button_438.BackgroundColor=[0.96,0.96,0.96];
            app.Button_446.BackgroundColor=[0.96,0.96,0.96];
            app.Button_447.BackgroundColor=[0.96,0.96,0.96];
            app.Button_448.BackgroundColor=[0.96,0.96,0.96];
            app.Button_449.BackgroundColor=[1.00,1.00,0.00];
            app.Button_450.BackgroundColor=[0.96,0.96,0.96];
            app.Button_451.BackgroundColor=[0.96,0.96,0.96];
            app.Button_452.BackgroundColor=[0.96,0.96,0.96];
            app.Button_453.BackgroundColor=[0.96,0.96,0.96];
            app.Button_454.BackgroundColor=[0.96,0.96,0.96];
            app.Button_455.BackgroundColor=[0.96,0.96,0.96];
            app.Button_456.BackgroundColor=[0.96,0.96,0.96];
            app.Button_457.BackgroundColor=[0.96,0.96,0.96];
            app.Button_458.BackgroundColor=[0.96,0.96,0.96];
            app.Button_459.BackgroundColor=[0.96,0.96,0.96];
            app.Button_460.BackgroundColor=[0.96,0.96,0.96];
            app.Button_461.BackgroundColor=[0.96,0.96,0.96];
            app.Button_462.BackgroundColor=[0.96,0.96,0.96];
            app.Button_463.BackgroundColor=[0.96,0.96,0.96];
            app.Button_464.BackgroundColor=[0.96,0.96,0.96];
            app.Button_465.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_450
        function Button_450ValueChanged(app, event)
            A250=75;
            B250=cellstr('75 km/h');
            c250=cellstr('app.Button_450');
            app.Matriz2(49)=c250;
            app.Matriz1(49)=A250;
            app.Matriz3(49)=B250;
            
            value = app.Button_450.Value;
            app.Button_434.BackgroundColor=[0.96,0.96,0.96];
            app.Button_435.BackgroundColor=[0.96,0.96,0.96];
            app.Button_436.BackgroundColor=[0.96,0.96,0.96];
            app.Button_437.BackgroundColor=[0.96,0.96,0.96];
            app.Button_438.BackgroundColor=[0.96,0.96,0.96];
            app.Button_446.BackgroundColor=[0.96,0.96,0.96];
            app.Button_447.BackgroundColor=[0.96,0.96,0.96];
            app.Button_448.BackgroundColor=[0.96,0.96,0.96];
            app.Button_449.BackgroundColor=[0.96,0.96,0.96];
            app.Button_450.BackgroundColor=[1.00,1.00,0.00];
            app.Button_451.BackgroundColor=[0.96,0.96,0.96];
            app.Button_452.BackgroundColor=[0.96,0.96,0.96];
            app.Button_453.BackgroundColor=[0.96,0.96,0.96];
            app.Button_454.BackgroundColor=[0.96,0.96,0.96];
            app.Button_455.BackgroundColor=[0.96,0.96,0.96];
            app.Button_456.BackgroundColor=[0.96,0.96,0.96];
            app.Button_457.BackgroundColor=[0.96,0.96,0.96];
            app.Button_458.BackgroundColor=[0.96,0.96,0.96];
            app.Button_459.BackgroundColor=[0.96,0.96,0.96];
            app.Button_460.BackgroundColor=[0.96,0.96,0.96];
            app.Button_461.BackgroundColor=[0.96,0.96,0.96];
            app.Button_462.BackgroundColor=[0.96,0.96,0.96];
            app.Button_463.BackgroundColor=[0.96,0.96,0.96];
            app.Button_464.BackgroundColor=[0.96,0.96,0.96];
            app.Button_465.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_451
        function Button_451ValueChanged(app, event)
            A251=80;
            B251=cellstr('80 km/h');
            c251=cellstr('app.Button_451');
            app.Matriz2(49)=c251;
            app.Matriz1(49)=A251;
            app.Matriz3(49)=B251;
            
            value = app.Button_451.Value;
            app.Button_434.BackgroundColor=[0.96,0.96,0.96];
            app.Button_435.BackgroundColor=[0.96,0.96,0.96];
            app.Button_436.BackgroundColor=[0.96,0.96,0.96];
            app.Button_437.BackgroundColor=[0.96,0.96,0.96];
            app.Button_438.BackgroundColor=[0.96,0.96,0.96];
            app.Button_446.BackgroundColor=[0.96,0.96,0.96];
            app.Button_447.BackgroundColor=[0.96,0.96,0.96];
            app.Button_448.BackgroundColor=[0.96,0.96,0.96];
            app.Button_449.BackgroundColor=[0.96,0.96,0.96];
            app.Button_450.BackgroundColor=[0.96,0.96,0.96];
            app.Button_451.BackgroundColor=[1.00,1.00,0.00];
            app.Button_452.BackgroundColor=[0.96,0.96,0.96];
            app.Button_453.BackgroundColor=[0.96,0.96,0.96];
            app.Button_454.BackgroundColor=[0.96,0.96,0.96];
            app.Button_455.BackgroundColor=[0.96,0.96,0.96];
            app.Button_456.BackgroundColor=[0.96,0.96,0.96];
            app.Button_457.BackgroundColor=[0.96,0.96,0.96];
            app.Button_458.BackgroundColor=[0.96,0.96,0.96];
            app.Button_459.BackgroundColor=[0.96,0.96,0.96];
            app.Button_460.BackgroundColor=[0.96,0.96,0.96];
            app.Button_461.BackgroundColor=[0.96,0.96,0.96];
            app.Button_462.BackgroundColor=[0.96,0.96,0.96];
            app.Button_463.BackgroundColor=[0.96,0.96,0.96];
            app.Button_464.BackgroundColor=[0.96,0.96,0.96];
            app.Button_465.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_452
        function Button_452ValueChanged(app, event)
            A252=85;
            B252=cellstr('85 km/h');
            c252=cellstr('app.Button_452');
            app.Matriz2(49)=c252;
            app.Matriz1(49)=A252;
            app.Matriz3(49)=B252;
            
            value = app.Button_452.Value;
            app.Button_434.BackgroundColor=[0.96,0.96,0.96];
            app.Button_435.BackgroundColor=[0.96,0.96,0.96];
            app.Button_436.BackgroundColor=[0.96,0.96,0.96];
            app.Button_437.BackgroundColor=[0.96,0.96,0.96];
            app.Button_438.BackgroundColor=[0.96,0.96,0.96];
            app.Button_446.BackgroundColor=[0.96,0.96,0.96];
            app.Button_447.BackgroundColor=[0.96,0.96,0.96];
            app.Button_448.BackgroundColor=[0.96,0.96,0.96];
            app.Button_449.BackgroundColor=[0.96,0.96,0.96];
            app.Button_450.BackgroundColor=[0.96,0.96,0.96];
            app.Button_451.BackgroundColor=[0.96,0.96,0.96];
            app.Button_452.BackgroundColor=[1.00,1.00,0.00];
            app.Button_453.BackgroundColor=[0.96,0.96,0.96];
            app.Button_454.BackgroundColor=[0.96,0.96,0.96];
            app.Button_455.BackgroundColor=[0.96,0.96,0.96];
            app.Button_456.BackgroundColor=[0.96,0.96,0.96];
            app.Button_457.BackgroundColor=[0.96,0.96,0.96];
            app.Button_458.BackgroundColor=[0.96,0.96,0.96];
            app.Button_459.BackgroundColor=[0.96,0.96,0.96];
            app.Button_460.BackgroundColor=[0.96,0.96,0.96];
            app.Button_461.BackgroundColor=[0.96,0.96,0.96];
            app.Button_462.BackgroundColor=[0.96,0.96,0.96];
            app.Button_463.BackgroundColor=[0.96,0.96,0.96];
            app.Button_464.BackgroundColor=[0.96,0.96,0.96];
            app.Button_465.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_453
        function Button_453ValueChanged(app, event)
            A253=90;
            B253=cellstr('90 km/h');
            c253=cellstr('app.Button_453');
            app.Matriz2(49)=c253;
            app.Matriz1(49)=A253;
            app.Matriz3(49)=B253;
            
            value = app.Button_453.Value;
            app.Button_434.BackgroundColor=[0.96,0.96,0.96];
            app.Button_435.BackgroundColor=[0.96,0.96,0.96];
            app.Button_436.BackgroundColor=[0.96,0.96,0.96];
            app.Button_437.BackgroundColor=[0.96,0.96,0.96];
            app.Button_438.BackgroundColor=[0.96,0.96,0.96];
            app.Button_446.BackgroundColor=[0.96,0.96,0.96];
            app.Button_447.BackgroundColor=[0.96,0.96,0.96];
            app.Button_448.BackgroundColor=[0.96,0.96,0.96];
            app.Button_449.BackgroundColor=[0.96,0.96,0.96];
            app.Button_450.BackgroundColor=[0.96,0.96,0.96];
            app.Button_451.BackgroundColor=[0.96,0.96,0.96];
            app.Button_452.BackgroundColor=[0.96,0.96,0.96];
            app.Button_453.BackgroundColor=[1.00,1.00,0.00];
            app.Button_454.BackgroundColor=[0.96,0.96,0.96];
            app.Button_455.BackgroundColor=[0.96,0.96,0.96];
            app.Button_456.BackgroundColor=[0.96,0.96,0.96];
            app.Button_457.BackgroundColor=[0.96,0.96,0.96];
            app.Button_458.BackgroundColor=[0.96,0.96,0.96];
            app.Button_459.BackgroundColor=[0.96,0.96,0.96];
            app.Button_460.BackgroundColor=[0.96,0.96,0.96];
            app.Button_461.BackgroundColor=[0.96,0.96,0.96];
            app.Button_462.BackgroundColor=[0.96,0.96,0.96];
            app.Button_463.BackgroundColor=[0.96,0.96,0.96];
            app.Button_464.BackgroundColor=[0.96,0.96,0.96];
            app.Button_465.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_454
        function Button_454ValueChanged(app, event)
            A254=95;
            B254=cellstr('95 km/h');
            c254=cellstr('app.Button_454');
            app.Matriz2(49)=c254;
            app.Matriz1(49)=A254;
            app.Matriz3(49)=B254;
            
            value = app.Button_454.Value;
            app.Button_434.BackgroundColor=[0.96,0.96,0.96];
            app.Button_435.BackgroundColor=[0.96,0.96,0.96];
            app.Button_436.BackgroundColor=[0.96,0.96,0.96];
            app.Button_437.BackgroundColor=[0.96,0.96,0.96];
            app.Button_438.BackgroundColor=[0.96,0.96,0.96];
            app.Button_446.BackgroundColor=[0.96,0.96,0.96];
            app.Button_447.BackgroundColor=[0.96,0.96,0.96];
            app.Button_448.BackgroundColor=[0.96,0.96,0.96];
            app.Button_449.BackgroundColor=[0.96,0.96,0.96];
            app.Button_450.BackgroundColor=[0.96,0.96,0.96];
            app.Button_451.BackgroundColor=[0.96,0.96,0.96];
            app.Button_452.BackgroundColor=[0.96,0.96,0.96];
            app.Button_453.BackgroundColor=[0.96,0.96,0.96];
            app.Button_454.BackgroundColor=[1.00,1.00,0.00];
            app.Button_455.BackgroundColor=[0.96,0.96,0.96];
            app.Button_456.BackgroundColor=[0.96,0.96,0.96];
            app.Button_457.BackgroundColor=[0.96,0.96,0.96];
            app.Button_458.BackgroundColor=[0.96,0.96,0.96];
            app.Button_459.BackgroundColor=[0.96,0.96,0.96];
            app.Button_460.BackgroundColor=[0.96,0.96,0.96];
            app.Button_461.BackgroundColor=[0.96,0.96,0.96];
            app.Button_462.BackgroundColor=[0.96,0.96,0.96];
            app.Button_463.BackgroundColor=[0.96,0.96,0.96];
            app.Button_464.BackgroundColor=[0.96,0.96,0.96];
            app.Button_465.BackgroundColor=[0.96,0.96,0.96];

        end

        % Value changed function: Button_455
        function Button_455ValueChanged(app, event)
            A255=100;
            B255=cellstr('100 km/h');
            c255=cellstr('app.Button_455');
            app.Matriz2(49)=c255;
            app.Matriz1(49)=A255;
            app.Matriz3(49)=B255;
            
            value = app.Button_455.Value;
            app.Button_434.BackgroundColor=[0.96,0.96,0.96];
            app.Button_435.BackgroundColor=[0.96,0.96,0.96];
            app.Button_436.BackgroundColor=[0.96,0.96,0.96];
            app.Button_437.BackgroundColor=[0.96,0.96,0.96];
            app.Button_438.BackgroundColor=[0.96,0.96,0.96];
            app.Button_446.BackgroundColor=[0.96,0.96,0.96];
            app.Button_447.BackgroundColor=[0.96,0.96,0.96];
            app.Button_448.BackgroundColor=[0.96,0.96,0.96];
            app.Button_449.BackgroundColor=[0.96,0.96,0.96];
            app.Button_450.BackgroundColor=[0.96,0.96,0.96];
            app.Button_451.BackgroundColor=[0.96,0.96,0.96];
            app.Button_452.BackgroundColor=[0.96,0.96,0.96];
            app.Button_453.BackgroundColor=[0.96,0.96,0.96];
            app.Button_454.BackgroundColor=[0.96,0.96,0.96];
            app.Button_455.BackgroundColor=[1.00,1.00,0.00];
            app.Button_456.BackgroundColor=[0.96,0.96,0.96];
            app.Button_457.BackgroundColor=[0.96,0.96,0.96];
            app.Button_458.BackgroundColor=[0.96,0.96,0.96];
            app.Button_459.BackgroundColor=[0.96,0.96,0.96];
            app.Button_460.BackgroundColor=[0.96,0.96,0.96];
            app.Button_461.BackgroundColor=[0.96,0.96,0.96];
            app.Button_462.BackgroundColor=[0.96,0.96,0.96];
            app.Button_463.BackgroundColor=[0.96,0.96,0.96];
            app.Button_464.BackgroundColor=[0.96,0.96,0.96];
            app.Button_465.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_456
        function Button_456ValueChanged(app, event)
            A256=105;
            B256=cellstr('105 km/h');
            c256=cellstr('app.Button_456');
            app.Matriz2(49)=c256;
            app.Matriz1(49)=A256;
            app.Matriz3(49)=B256;
            
            value = app.Button_456.Value;
            app.Button_434.BackgroundColor=[0.96,0.96,0.96];
            app.Button_435.BackgroundColor=[0.96,0.96,0.96];
            app.Button_436.BackgroundColor=[0.96,0.96,0.96];
            app.Button_437.BackgroundColor=[0.96,0.96,0.96];
            app.Button_438.BackgroundColor=[0.96,0.96,0.96];
            app.Button_446.BackgroundColor=[0.96,0.96,0.96];
            app.Button_447.BackgroundColor=[0.96,0.96,0.96];
            app.Button_448.BackgroundColor=[0.96,0.96,0.96];
            app.Button_449.BackgroundColor=[0.96,0.96,0.96];
            app.Button_450.BackgroundColor=[0.96,0.96,0.96];
            app.Button_451.BackgroundColor=[0.96,0.96,0.96];
            app.Button_452.BackgroundColor=[0.96,0.96,0.96];
            app.Button_453.BackgroundColor=[0.96,0.96,0.96];
            app.Button_454.BackgroundColor=[0.96,0.96,0.96];
            app.Button_455.BackgroundColor=[0.96,0.96,0.96];
            app.Button_456.BackgroundColor=[1.00,1.00,0.00];
            app.Button_457.BackgroundColor=[0.96,0.96,0.96];
            app.Button_458.BackgroundColor=[0.96,0.96,0.96];
            app.Button_459.BackgroundColor=[0.96,0.96,0.96];
            app.Button_460.BackgroundColor=[0.96,0.96,0.96];
            app.Button_461.BackgroundColor=[0.96,0.96,0.96];
            app.Button_462.BackgroundColor=[0.96,0.96,0.96];
            app.Button_463.BackgroundColor=[0.96,0.96,0.96];
            app.Button_464.BackgroundColor=[0.96,0.96,0.96];
            app.Button_465.BackgroundColor=[0.96,0.96,0.96];

        end

        % Value changed function: Button_457
        function Button_457ValueChanged(app, event)
            A257=110;
            B257=cellstr('110 km/h');
            c257=cellstr('app.Button_457');
            app.Matriz2(49)=c257;
            app.Matriz1(49)=A257;
            app.Matriz3(49)=B257;
            
            value = app.Button_457.Value;
            app.Button_434.BackgroundColor=[0.96,0.96,0.96];
            app.Button_435.BackgroundColor=[0.96,0.96,0.96];
            app.Button_436.BackgroundColor=[0.96,0.96,0.96];
            app.Button_437.BackgroundColor=[0.96,0.96,0.96];
            app.Button_438.BackgroundColor=[0.96,0.96,0.96];
            app.Button_446.BackgroundColor=[0.96,0.96,0.96];
            app.Button_447.BackgroundColor=[0.96,0.96,0.96];
            app.Button_448.BackgroundColor=[0.96,0.96,0.96];
            app.Button_449.BackgroundColor=[0.96,0.96,0.96];
            app.Button_450.BackgroundColor=[0.96,0.96,0.96];
            app.Button_451.BackgroundColor=[0.96,0.96,0.96];
            app.Button_452.BackgroundColor=[0.96,0.96,0.96];
            app.Button_453.BackgroundColor=[0.96,0.96,0.96];
            app.Button_454.BackgroundColor=[0.96,0.96,0.96];
            app.Button_455.BackgroundColor=[0.96,0.96,0.96];
            app.Button_456.BackgroundColor=[0.96,0.96,0.96];
            app.Button_457.BackgroundColor=[1.00,1.00,0.00];
            app.Button_458.BackgroundColor=[0.96,0.96,0.96];
            app.Button_459.BackgroundColor=[0.96,0.96,0.96];
            app.Button_460.BackgroundColor=[0.96,0.96,0.96];
            app.Button_461.BackgroundColor=[0.96,0.96,0.96];
            app.Button_462.BackgroundColor=[0.96,0.96,0.96];
            app.Button_463.BackgroundColor=[0.96,0.96,0.96];
            app.Button_464.BackgroundColor=[0.96,0.96,0.96];
            app.Button_465.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_458
        function Button_458ValueChanged(app, event)
            A258=115;
            B258=cellstr('115 km/h');
            c258=cellstr('app.Button_458');
            app.Matriz2(49)=c258;
            app.Matriz1(49)=A258;
            app.Matriz3(49)=B258;
            
            value = app.Button_458.Value;
            app.Button_434.BackgroundColor=[0.96,0.96,0.96];
            app.Button_435.BackgroundColor=[0.96,0.96,0.96];
            app.Button_436.BackgroundColor=[0.96,0.96,0.96];
            app.Button_437.BackgroundColor=[0.96,0.96,0.96];
            app.Button_438.BackgroundColor=[0.96,0.96,0.96];
            app.Button_446.BackgroundColor=[0.96,0.96,0.96];
            app.Button_447.BackgroundColor=[0.96,0.96,0.96];
            app.Button_448.BackgroundColor=[0.96,0.96,0.96];
            app.Button_449.BackgroundColor=[0.96,0.96,0.96];
            app.Button_450.BackgroundColor=[0.96,0.96,0.96];
            app.Button_451.BackgroundColor=[0.96,0.96,0.96];
            app.Button_452.BackgroundColor=[0.96,0.96,0.96];
            app.Button_453.BackgroundColor=[0.96,0.96,0.96];
            app.Button_454.BackgroundColor=[0.96,0.96,0.96];
            app.Button_455.BackgroundColor=[0.96,0.96,0.96];
            app.Button_456.BackgroundColor=[0.96,0.96,0.96];
            app.Button_457.BackgroundColor=[0.96,0.96,0.96];
            app.Button_458.BackgroundColor=[1.00,1.00,0.00];
            app.Button_459.BackgroundColor=[0.96,0.96,0.96];
            app.Button_460.BackgroundColor=[0.96,0.96,0.96];
            app.Button_461.BackgroundColor=[0.96,0.96,0.96];
            app.Button_462.BackgroundColor=[0.96,0.96,0.96];
            app.Button_463.BackgroundColor=[0.96,0.96,0.96];
            app.Button_464.BackgroundColor=[0.96,0.96,0.96];
            app.Button_465.BackgroundColor=[0.96,0.96,0.96];

        end

        % Value changed function: Button_459
        function Button_459ValueChanged(app, event)
            A259=120;
            B259=cellstr('120 km/h');
            c259=cellstr('app.Button_459');
            app.Matriz2(49)=c259;
            app.Matriz1(49)=A259;
            app.Matriz3(49)=B259;
            
            value = app.Button_459.Value;
            app.Button_434.BackgroundColor=[0.96,0.96,0.96];
            app.Button_435.BackgroundColor=[0.96,0.96,0.96];
            app.Button_436.BackgroundColor=[0.96,0.96,0.96];
            app.Button_437.BackgroundColor=[0.96,0.96,0.96];
            app.Button_438.BackgroundColor=[0.96,0.96,0.96];
            app.Button_446.BackgroundColor=[0.96,0.96,0.96];
            app.Button_447.BackgroundColor=[0.96,0.96,0.96];
            app.Button_448.BackgroundColor=[0.96,0.96,0.96];
            app.Button_449.BackgroundColor=[0.96,0.96,0.96];
            app.Button_450.BackgroundColor=[0.96,0.96,0.96];
            app.Button_451.BackgroundColor=[0.96,0.96,0.96];
            app.Button_452.BackgroundColor=[0.96,0.96,0.96];
            app.Button_453.BackgroundColor=[0.96,0.96,0.96];
            app.Button_454.BackgroundColor=[0.96,0.96,0.96];
            app.Button_455.BackgroundColor=[0.96,0.96,0.96];
            app.Button_456.BackgroundColor=[0.96,0.96,0.96];
            app.Button_457.BackgroundColor=[0.96,0.96,0.96];
            app.Button_458.BackgroundColor=[0.96,0.96,0.96];
            app.Button_459.BackgroundColor=[1.00,1.00,0.00];
            app.Button_460.BackgroundColor=[0.96,0.96,0.96];
            app.Button_461.BackgroundColor=[0.96,0.96,0.96];
            app.Button_462.BackgroundColor=[0.96,0.96,0.96];
            app.Button_463.BackgroundColor=[0.96,0.96,0.96];
            app.Button_464.BackgroundColor=[0.96,0.96,0.96];
            app.Button_465.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_460
        function Button_460ValueChanged(app, event)
            A260=125;
            B260=cellstr('125 km/h');
            c260=cellstr('app.Button_460');
            app.Matriz2(49)=c260;
            app.Matriz1(49)=A260;
            app.Matriz3(49)=B260;
            
            value = app.Button_460.Value;
            app.Button_434.BackgroundColor=[0.96,0.96,0.96];
            app.Button_435.BackgroundColor=[0.96,0.96,0.96];
            app.Button_436.BackgroundColor=[0.96,0.96,0.96];
            app.Button_437.BackgroundColor=[0.96,0.96,0.96];
            app.Button_438.BackgroundColor=[0.96,0.96,0.96];
            app.Button_446.BackgroundColor=[0.96,0.96,0.96];
            app.Button_447.BackgroundColor=[0.96,0.96,0.96];
            app.Button_448.BackgroundColor=[0.96,0.96,0.96];
            app.Button_449.BackgroundColor=[0.96,0.96,0.96];
            app.Button_450.BackgroundColor=[0.96,0.96,0.96];
            app.Button_451.BackgroundColor=[0.96,0.96,0.96];
            app.Button_452.BackgroundColor=[0.96,0.96,0.96];
            app.Button_453.BackgroundColor=[0.96,0.96,0.96];
            app.Button_454.BackgroundColor=[0.96,0.96,0.96];
            app.Button_455.BackgroundColor=[0.96,0.96,0.96];
            app.Button_456.BackgroundColor=[0.96,0.96,0.96];
            app.Button_457.BackgroundColor=[0.96,0.96,0.96];
            app.Button_458.BackgroundColor=[0.96,0.96,0.96];
            app.Button_459.BackgroundColor=[0.96,0.96,0.96];
            app.Button_460.BackgroundColor=[1.00,1.00,0.00];
            app.Button_461.BackgroundColor=[0.96,0.96,0.96];
            app.Button_462.BackgroundColor=[0.96,0.96,0.96];
            app.Button_463.BackgroundColor=[0.96,0.96,0.96];
            app.Button_464.BackgroundColor=[0.96,0.96,0.96];
            app.Button_465.BackgroundColor=[0.96,0.96,0.96];

        end

        % Value changed function: Button_461
        function Button_461ValueChanged(app, event)
            A261=130;
            B261=cellstr('130 km/h');
            c261=cellstr('app.Button_461');
            app.Matriz2(49)=c261;
            app.Matriz1(49)=A261;
            app.Matriz3(49)=B261;
            
            value = app.Button_461.Value;
            app.Button_434.BackgroundColor=[0.96,0.96,0.96];
            app.Button_435.BackgroundColor=[0.96,0.96,0.96];
            app.Button_436.BackgroundColor=[0.96,0.96,0.96];
            app.Button_437.BackgroundColor=[0.96,0.96,0.96];
            app.Button_438.BackgroundColor=[0.96,0.96,0.96];
            app.Button_446.BackgroundColor=[0.96,0.96,0.96];
            app.Button_447.BackgroundColor=[0.96,0.96,0.96];
            app.Button_448.BackgroundColor=[0.96,0.96,0.96];
            app.Button_449.BackgroundColor=[0.96,0.96,0.96];
            app.Button_450.BackgroundColor=[0.96,0.96,0.96];
            app.Button_451.BackgroundColor=[0.96,0.96,0.96];
            app.Button_452.BackgroundColor=[0.96,0.96,0.96];
            app.Button_453.BackgroundColor=[0.96,0.96,0.96];
            app.Button_454.BackgroundColor=[0.96,0.96,0.96];
            app.Button_455.BackgroundColor=[0.96,0.96,0.96];
            app.Button_456.BackgroundColor=[0.96,0.96,0.96];
            app.Button_457.BackgroundColor=[0.96,0.96,0.96];
            app.Button_458.BackgroundColor=[0.96,0.96,0.96];
            app.Button_459.BackgroundColor=[0.96,0.96,0.96];
            app.Button_460.BackgroundColor=[0.96,0.96,0.96];
            app.Button_461.BackgroundColor=[1.00,1.00,0.00];
            app.Button_462.BackgroundColor=[0.96,0.96,0.96];
            app.Button_463.BackgroundColor=[0.96,0.96,0.96];
            app.Button_464.BackgroundColor=[0.96,0.96,0.96];
            app.Button_465.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_462
        function Button_462ValueChanged(app, event)
            A262=135;
            B262=cellstr('135 km/h');
            c262=cellstr('app.Button_462');
            app.Matriz2(49)=c262;
            app.Matriz1(49)=A262;
            app.Matriz3(49)=B262;
            
            value = app.Button_462.Value;
            app.Button_434.BackgroundColor=[0.96,0.96,0.96];
            app.Button_435.BackgroundColor=[0.96,0.96,0.96];
            app.Button_436.BackgroundColor=[0.96,0.96,0.96];
            app.Button_437.BackgroundColor=[0.96,0.96,0.96];
            app.Button_438.BackgroundColor=[0.96,0.96,0.96];
            app.Button_446.BackgroundColor=[0.96,0.96,0.96];
            app.Button_447.BackgroundColor=[0.96,0.96,0.96];
            app.Button_448.BackgroundColor=[0.96,0.96,0.96];
            app.Button_449.BackgroundColor=[0.96,0.96,0.96];
            app.Button_450.BackgroundColor=[0.96,0.96,0.96];
            app.Button_451.BackgroundColor=[0.96,0.96,0.96];
            app.Button_452.BackgroundColor=[0.96,0.96,0.96];
            app.Button_453.BackgroundColor=[0.96,0.96,0.96];
            app.Button_454.BackgroundColor=[0.96,0.96,0.96];
            app.Button_455.BackgroundColor=[0.96,0.96,0.96];
            app.Button_456.BackgroundColor=[0.96,0.96,0.96];
            app.Button_457.BackgroundColor=[0.96,0.96,0.96];
            app.Button_458.BackgroundColor=[0.96,0.96,0.96];
            app.Button_459.BackgroundColor=[0.96,0.96,0.96];
            app.Button_460.BackgroundColor=[0.96,0.96,0.96];
            app.Button_461.BackgroundColor=[0.96,0.96,0.96];
            app.Button_462.BackgroundColor=[1.00,1.00,0.00];
            app.Button_463.BackgroundColor=[0.96,0.96,0.96];
            app.Button_464.BackgroundColor=[0.96,0.96,0.96];
            app.Button_465.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_463
        function Button_463ValueChanged(app, event)
            A263=140;
            B263=cellstr('140 km/h');
            c263=cellstr('app.Button_463');
            app.Matriz2(49)=c263;
            app.Matriz1(49)=A263;
            app.Matriz3(49)=B263;
            
            value = app.Button_463.Value;
            app.Button_434.BackgroundColor=[0.96,0.96,0.96];
            app.Button_435.BackgroundColor=[0.96,0.96,0.96];
            app.Button_436.BackgroundColor=[0.96,0.96,0.96];
            app.Button_437.BackgroundColor=[0.96,0.96,0.96];
            app.Button_438.BackgroundColor=[0.96,0.96,0.96];
            app.Button_446.BackgroundColor=[0.96,0.96,0.96];
            app.Button_447.BackgroundColor=[0.96,0.96,0.96];
            app.Button_448.BackgroundColor=[0.96,0.96,0.96];
            app.Button_449.BackgroundColor=[0.96,0.96,0.96];
            app.Button_450.BackgroundColor=[0.96,0.96,0.96];
            app.Button_451.BackgroundColor=[0.96,0.96,0.96];
            app.Button_452.BackgroundColor=[0.96,0.96,0.96];
            app.Button_453.BackgroundColor=[0.96,0.96,0.96];
            app.Button_454.BackgroundColor=[0.96,0.96,0.96];
            app.Button_455.BackgroundColor=[0.96,0.96,0.96];
            app.Button_456.BackgroundColor=[0.96,0.96,0.96];
            app.Button_457.BackgroundColor=[0.96,0.96,0.96];
            app.Button_458.BackgroundColor=[0.96,0.96,0.96];
            app.Button_459.BackgroundColor=[0.96,0.96,0.96];
            app.Button_460.BackgroundColor=[0.96,0.96,0.96];
            app.Button_461.BackgroundColor=[0.96,0.96,0.96];
            app.Button_462.BackgroundColor=[0.96,0.96,0.96];
            app.Button_463.BackgroundColor=[1.00,1.00,0.00];
            app.Button_464.BackgroundColor=[0.96,0.96,0.96];
            app.Button_465.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_464
        function Button_464ValueChanged(app, event)
            A264=145;
            B264=cellstr('145 km/h');
            c264=cellstr('app.Button_464');
            app.Matriz2(49)=c264;
            app.Matriz1(49)=A264;
            app.Matriz3(49)=B264;
            
            value = app.Button_464.Value;
            app.Button_434.BackgroundColor=[0.96,0.96,0.96];
            app.Button_435.BackgroundColor=[0.96,0.96,0.96];
            app.Button_436.BackgroundColor=[0.96,0.96,0.96];
            app.Button_437.BackgroundColor=[0.96,0.96,0.96];
            app.Button_438.BackgroundColor=[0.96,0.96,0.96];
            app.Button_446.BackgroundColor=[0.96,0.96,0.96];
            app.Button_447.BackgroundColor=[0.96,0.96,0.96];
            app.Button_448.BackgroundColor=[0.96,0.96,0.96];
            app.Button_449.BackgroundColor=[0.96,0.96,0.96];
            app.Button_450.BackgroundColor=[0.96,0.96,0.96];
            app.Button_451.BackgroundColor=[0.96,0.96,0.96];
            app.Button_452.BackgroundColor=[0.96,0.96,0.96];
            app.Button_453.BackgroundColor=[0.96,0.96,0.96];
            app.Button_454.BackgroundColor=[0.96,0.96,0.96];
            app.Button_455.BackgroundColor=[0.96,0.96,0.96];
            app.Button_456.BackgroundColor=[0.96,0.96,0.96];
            app.Button_457.BackgroundColor=[0.96,0.96,0.96];
            app.Button_458.BackgroundColor=[0.96,0.96,0.96];
            app.Button_459.BackgroundColor=[0.96,0.96,0.96];
            app.Button_460.BackgroundColor=[0.96,0.96,0.96];
            app.Button_461.BackgroundColor=[0.96,0.96,0.96];
            app.Button_462.BackgroundColor=[0.96,0.96,0.96];
            app.Button_463.BackgroundColor=[0.96,0.96,0.96];
            app.Button_464.BackgroundColor=[1.00,1.00,0.00];
            app.Button_465.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_465
        function Button_465ValueChanged(app, event)
            A265=150;
            B265=cellstr('≥ 150 km/h');
            c265=cellstr('app.Button_465');
            app.Matriz2(49)=c265;
            app.Matriz1(49)=A265;
            app.Matriz3(49)=B265;
            
            value = app.Button_465.Value;
            app.Button_434.BackgroundColor=[0.96,0.96,0.96];
            app.Button_435.BackgroundColor=[0.96,0.96,0.96];
            app.Button_436.BackgroundColor=[0.96,0.96,0.96];
            app.Button_437.BackgroundColor=[0.96,0.96,0.96];
            app.Button_438.BackgroundColor=[0.96,0.96,0.96];
            app.Button_446.BackgroundColor=[0.96,0.96,0.96];
            app.Button_447.BackgroundColor=[0.96,0.96,0.96];
            app.Button_448.BackgroundColor=[0.96,0.96,0.96];
            app.Button_449.BackgroundColor=[0.96,0.96,0.96];
            app.Button_450.BackgroundColor=[0.96,0.96,0.96];
            app.Button_451.BackgroundColor=[0.96,0.96,0.96];
            app.Button_452.BackgroundColor=[0.96,0.96,0.96];
            app.Button_453.BackgroundColor=[0.96,0.96,0.96];
            app.Button_454.BackgroundColor=[0.96,0.96,0.96];
            app.Button_455.BackgroundColor=[0.96,0.96,0.96];
            app.Button_456.BackgroundColor=[0.96,0.96,0.96];
            app.Button_457.BackgroundColor=[0.96,0.96,0.96];
            app.Button_458.BackgroundColor=[0.96,0.96,0.96];
            app.Button_459.BackgroundColor=[0.96,0.96,0.96];
            app.Button_460.BackgroundColor=[0.96,0.96,0.96];
            app.Button_461.BackgroundColor=[0.96,0.96,0.96];
            app.Button_462.BackgroundColor=[0.96,0.96,0.96];
            app.Button_463.BackgroundColor=[0.96,0.96,0.96];
            app.Button_464.BackgroundColor=[0.96,0.96,0.96];
            app.Button_465.BackgroundColor=[1.00,1.00,0.00];
        end

        % Value changed function: Button_631
        function Button_631ValueChanged(app, event)
            A268=30;
            B268=cellstr('< 30 km/h');
            c268=cellstr('app.Button_631');
            app.Matriz2(52)=c268;
            app.Matriz1(52)=A268;
            app.Matriz3(52)=B268;
            
            value = app.Button_631.Value;
            app.Button_631.BackgroundColor=[1.00,1.00,0.00];
            app.Button_632.BackgroundColor=[0.96,0.96,0.96];
            app.Button_633.BackgroundColor=[0.96,0.96,0.96];
            app.Button_634.BackgroundColor=[0.96,0.96,0.96];
            app.Button_635.BackgroundColor=[0.96,0.96,0.96];
            app.Button_636.BackgroundColor=[0.96,0.96,0.96];
            app.Button_637.BackgroundColor=[0.96,0.96,0.96];
            app.Button_638.BackgroundColor=[0.96,0.96,0.96];
            app.Button_639.BackgroundColor=[0.96,0.96,0.96];
            app.Button_640.BackgroundColor=[0.96,0.96,0.96];
            app.Button_641.BackgroundColor=[0.96,0.96,0.96];
            app.Button_642.BackgroundColor=[0.96,0.96,0.96];
            app.Button_643.BackgroundColor=[0.96,0.96,0.96];
            app.Button_644.BackgroundColor=[0.96,0.96,0.96];
            app.Button_645.BackgroundColor=[0.96,0.96,0.96];
            app.Button_646.BackgroundColor=[0.96,0.96,0.96];
            app.Button_647.BackgroundColor=[0.96,0.96,0.96];
            app.Button_648.BackgroundColor=[0.96,0.96,0.96];
            app.Button_649.BackgroundColor=[0.96,0.96,0.96];
            app.Button_650.BackgroundColor=[0.96,0.96,0.96];
            app.Button_651.BackgroundColor=[0.96,0.96,0.96];
            app.Button_652.BackgroundColor=[0.96,0.96,0.96];
            app.Button_653.BackgroundColor=[0.96,0.96,0.96];
            app.Button_654.BackgroundColor=[0.96,0.96,0.96];
            app.Button_655.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_632
        function Button_632ValueChanged(app, event)
            A269=35;
            B269=cellstr('35 km/h');
            c269=cellstr('app.Button_632');
            app.Matriz2(52)=c269;
            app.Matriz1(52)=A269;
            app.Matriz3(52)=B269;
            
            value = app.Button_632.Value;
            app.Button_631.BackgroundColor=[0.96,0.96,0.96];
            app.Button_632.BackgroundColor=[1.00,1.00,0.00];
            app.Button_633.BackgroundColor=[0.96,0.96,0.96];
            app.Button_634.BackgroundColor=[0.96,0.96,0.96];
            app.Button_635.BackgroundColor=[0.96,0.96,0.96];
            app.Button_636.BackgroundColor=[0.96,0.96,0.96];
            app.Button_637.BackgroundColor=[0.96,0.96,0.96];
            app.Button_638.BackgroundColor=[0.96,0.96,0.96];
            app.Button_639.BackgroundColor=[0.96,0.96,0.96];
            app.Button_640.BackgroundColor=[0.96,0.96,0.96];
            app.Button_641.BackgroundColor=[0.96,0.96,0.96];
            app.Button_642.BackgroundColor=[0.96,0.96,0.96];
            app.Button_643.BackgroundColor=[0.96,0.96,0.96];
            app.Button_644.BackgroundColor=[0.96,0.96,0.96];
            app.Button_645.BackgroundColor=[0.96,0.96,0.96];
            app.Button_646.BackgroundColor=[0.96,0.96,0.96];
            app.Button_647.BackgroundColor=[0.96,0.96,0.96];
            app.Button_648.BackgroundColor=[0.96,0.96,0.96];
            app.Button_649.BackgroundColor=[0.96,0.96,0.96];
            app.Button_650.BackgroundColor=[0.96,0.96,0.96];
            app.Button_651.BackgroundColor=[0.96,0.96,0.96];
            app.Button_652.BackgroundColor=[0.96,0.96,0.96];
            app.Button_653.BackgroundColor=[0.96,0.96,0.96];
            app.Button_654.BackgroundColor=[0.96,0.96,0.96];
            app.Button_655.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_633
        function Button_633ValueChanged(app, event)
            A270=40;
            B270=cellstr('40 km/h');
            c270=cellstr('app.Button_633');
            app.Matriz2(52)=c270;
            app.Matriz1(52)=A270;
            app.Matriz3(52)=B270;
            
            value = app.Button_633.Value;
            app.Button_631.BackgroundColor=[0.96,0.96,0.96];
            app.Button_632.BackgroundColor=[0.96,0.96,0.96];
            app.Button_633.BackgroundColor=[1.00,1.00,0.00];
            app.Button_634.BackgroundColor=[0.96,0.96,0.96];
            app.Button_635.BackgroundColor=[0.96,0.96,0.96];
            app.Button_636.BackgroundColor=[0.96,0.96,0.96];
            app.Button_637.BackgroundColor=[0.96,0.96,0.96];
            app.Button_638.BackgroundColor=[0.96,0.96,0.96];
            app.Button_639.BackgroundColor=[0.96,0.96,0.96];
            app.Button_640.BackgroundColor=[0.96,0.96,0.96];
            app.Button_641.BackgroundColor=[0.96,0.96,0.96];
            app.Button_642.BackgroundColor=[0.96,0.96,0.96];
            app.Button_643.BackgroundColor=[0.96,0.96,0.96];
            app.Button_644.BackgroundColor=[0.96,0.96,0.96];
            app.Button_645.BackgroundColor=[0.96,0.96,0.96];
            app.Button_646.BackgroundColor=[0.96,0.96,0.96];
            app.Button_647.BackgroundColor=[0.96,0.96,0.96];
            app.Button_648.BackgroundColor=[0.96,0.96,0.96];
            app.Button_649.BackgroundColor=[0.96,0.96,0.96];
            app.Button_650.BackgroundColor=[0.96,0.96,0.96];
            app.Button_651.BackgroundColor=[0.96,0.96,0.96];
            app.Button_652.BackgroundColor=[0.96,0.96,0.96];
            app.Button_653.BackgroundColor=[0.96,0.96,0.96];
            app.Button_654.BackgroundColor=[0.96,0.96,0.96];
            app.Button_655.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_634
        function Button_634ValueChanged(app, event)
            A271=45;
            B271=cellstr('45 km/h');
            c271=cellstr('app.Button_634');
            app.Matriz2(52)=c271;
            app.Matriz1(52)=A271;
            app.Matriz3(52)=B271;
            
            value = app.Button_634.Value;
            app.Button_631.BackgroundColor=[0.96,0.96,0.96];
            app.Button_632.BackgroundColor=[0.96,0.96,0.96];
            app.Button_633.BackgroundColor=[0.96,0.96,0.96];
            app.Button_634.BackgroundColor=[1.00,1.00,0.00];
            app.Button_635.BackgroundColor=[0.96,0.96,0.96];
            app.Button_636.BackgroundColor=[0.96,0.96,0.96];
            app.Button_637.BackgroundColor=[0.96,0.96,0.96];
            app.Button_638.BackgroundColor=[0.96,0.96,0.96];
            app.Button_639.BackgroundColor=[0.96,0.96,0.96];
            app.Button_640.BackgroundColor=[0.96,0.96,0.96];
            app.Button_641.BackgroundColor=[0.96,0.96,0.96];
            app.Button_642.BackgroundColor=[0.96,0.96,0.96];
            app.Button_643.BackgroundColor=[0.96,0.96,0.96];
            app.Button_644.BackgroundColor=[0.96,0.96,0.96];
            app.Button_645.BackgroundColor=[0.96,0.96,0.96];
            app.Button_646.BackgroundColor=[0.96,0.96,0.96];
            app.Button_647.BackgroundColor=[0.96,0.96,0.96];
            app.Button_648.BackgroundColor=[0.96,0.96,0.96];
            app.Button_649.BackgroundColor=[0.96,0.96,0.96];
            app.Button_650.BackgroundColor=[0.96,0.96,0.96];
            app.Button_651.BackgroundColor=[0.96,0.96,0.96];
            app.Button_652.BackgroundColor=[0.96,0.96,0.96];
            app.Button_653.BackgroundColor=[0.96,0.96,0.96];
            app.Button_654.BackgroundColor=[0.96,0.96,0.96];
            app.Button_655.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_635
        function Button_635ValueChanged(app, event)
            A272=50;
            B272=cellstr('50 km/h');
            c272=cellstr('app.Button_635');
            app.Matriz2(52)=c272;
            app.Matriz1(52)=A272;
            app.Matriz3(52)=B272;
            
            value = app.Button_635.Value;
            app.Button_631.BackgroundColor=[0.96,0.96,0.96];
            app.Button_632.BackgroundColor=[0.96,0.96,0.96];
            app.Button_633.BackgroundColor=[0.96,0.96,0.96];
            app.Button_634.BackgroundColor=[0.96,0.96,0.96];
            app.Button_635.BackgroundColor=[1.00,1.00,0.00];
            app.Button_636.BackgroundColor=[0.96,0.96,0.96];
            app.Button_637.BackgroundColor=[0.96,0.96,0.96];
            app.Button_638.BackgroundColor=[0.96,0.96,0.96];
            app.Button_639.BackgroundColor=[0.96,0.96,0.96];
            app.Button_640.BackgroundColor=[0.96,0.96,0.96];
            app.Button_641.BackgroundColor=[0.96,0.96,0.96];
            app.Button_642.BackgroundColor=[0.96,0.96,0.96];
            app.Button_643.BackgroundColor=[0.96,0.96,0.96];
            app.Button_644.BackgroundColor=[0.96,0.96,0.96];
            app.Button_645.BackgroundColor=[0.96,0.96,0.96];
            app.Button_646.BackgroundColor=[0.96,0.96,0.96];
            app.Button_647.BackgroundColor=[0.96,0.96,0.96];
            app.Button_648.BackgroundColor=[0.96,0.96,0.96];
            app.Button_649.BackgroundColor=[0.96,0.96,0.96];
            app.Button_650.BackgroundColor=[0.96,0.96,0.96];
            app.Button_651.BackgroundColor=[0.96,0.96,0.96];
            app.Button_652.BackgroundColor=[0.96,0.96,0.96];
            app.Button_653.BackgroundColor=[0.96,0.96,0.96];
            app.Button_654.BackgroundColor=[0.96,0.96,0.96];
            app.Button_655.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_636
        function Button_636ValueChanged(app, event)
            A273=55;
            B273=cellstr('55 km/h');
            c273=cellstr('app.Button_636');
            app.Matriz2(52)=c273;
            app.Matriz1(52)=A273;
            app.Matriz3(52)=B273;
            
            value = app.Button_636.Value;
            app.Button_631.BackgroundColor=[0.96,0.96,0.96];
            app.Button_632.BackgroundColor=[0.96,0.96,0.96];
            app.Button_633.BackgroundColor=[0.96,0.96,0.96];
            app.Button_634.BackgroundColor=[0.96,0.96,0.96];
            app.Button_635.BackgroundColor=[0.96,0.96,0.96];
            app.Button_636.BackgroundColor=[1.00,1.00,0.00];
            app.Button_637.BackgroundColor=[0.96,0.96,0.96];
            app.Button_638.BackgroundColor=[0.96,0.96,0.96];
            app.Button_639.BackgroundColor=[0.96,0.96,0.96];
            app.Button_640.BackgroundColor=[0.96,0.96,0.96];
            app.Button_641.BackgroundColor=[0.96,0.96,0.96];
            app.Button_642.BackgroundColor=[0.96,0.96,0.96];
            app.Button_643.BackgroundColor=[0.96,0.96,0.96];
            app.Button_644.BackgroundColor=[0.96,0.96,0.96];
            app.Button_645.BackgroundColor=[0.96,0.96,0.96];
            app.Button_646.BackgroundColor=[0.96,0.96,0.96];
            app.Button_647.BackgroundColor=[0.96,0.96,0.96];
            app.Button_648.BackgroundColor=[0.96,0.96,0.96];
            app.Button_649.BackgroundColor=[0.96,0.96,0.96];
            app.Button_650.BackgroundColor=[0.96,0.96,0.96];
            app.Button_651.BackgroundColor=[0.96,0.96,0.96];
            app.Button_652.BackgroundColor=[0.96,0.96,0.96];
            app.Button_653.BackgroundColor=[0.96,0.96,0.96];
            app.Button_654.BackgroundColor=[0.96,0.96,0.96];
            app.Button_655.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_637
        function Button_637ValueChanged(app, event)
            A274=60;
            B274=cellstr('60 km/h');
            c274=cellstr('app.Button_637');
            app.Matriz2(52)=c274;
            app.Matriz1(52)=A274;
            app.Matriz3(52)=B274;
            
            value = app.Button_637.Value;
            app.Button_631.BackgroundColor=[0.96,0.96,0.96];
            app.Button_632.BackgroundColor=[0.96,0.96,0.96];
            app.Button_633.BackgroundColor=[0.96,0.96,0.96];
            app.Button_634.BackgroundColor=[0.96,0.96,0.96];
            app.Button_635.BackgroundColor=[0.96,0.96,0.96];
            app.Button_636.BackgroundColor=[0.96,0.96,0.96];
            app.Button_637.BackgroundColor=[1.00,1.00,0.00];
            app.Button_638.BackgroundColor=[0.96,0.96,0.96];
            app.Button_639.BackgroundColor=[0.96,0.96,0.96];
            app.Button_640.BackgroundColor=[0.96,0.96,0.96];
            app.Button_641.BackgroundColor=[0.96,0.96,0.96];
            app.Button_642.BackgroundColor=[0.96,0.96,0.96];
            app.Button_643.BackgroundColor=[0.96,0.96,0.96];
            app.Button_644.BackgroundColor=[0.96,0.96,0.96];
            app.Button_645.BackgroundColor=[0.96,0.96,0.96];
            app.Button_646.BackgroundColor=[0.96,0.96,0.96];
            app.Button_647.BackgroundColor=[0.96,0.96,0.96];
            app.Button_648.BackgroundColor=[0.96,0.96,0.96];
            app.Button_649.BackgroundColor=[0.96,0.96,0.96];
            app.Button_650.BackgroundColor=[0.96,0.96,0.96];
            app.Button_651.BackgroundColor=[0.96,0.96,0.96];
            app.Button_652.BackgroundColor=[0.96,0.96,0.96];
            app.Button_653.BackgroundColor=[0.96,0.96,0.96];
            app.Button_654.BackgroundColor=[0.96,0.96,0.96];
            app.Button_655.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_638
        function Button_638ValueChanged(app, event)
            A275=65;
            B275=cellstr('65 km/h');
            c275=cellstr('app.Button_638');
            app.Matriz2(52)=c275;
            app.Matriz1(52)=A275;
            app.Matriz3(52)=B275;
            
            value = app.Button_638.Value;
            app.Button_631.BackgroundColor=[0.96,0.96,0.96];
            app.Button_632.BackgroundColor=[0.96,0.96,0.96];
            app.Button_633.BackgroundColor=[0.96,0.96,0.96];
            app.Button_634.BackgroundColor=[0.96,0.96,0.96];
            app.Button_635.BackgroundColor=[0.96,0.96,0.96];
            app.Button_636.BackgroundColor=[0.96,0.96,0.96];
            app.Button_637.BackgroundColor=[0.96,0.96,0.96];
            app.Button_638.BackgroundColor=[1.00,1.00,0.00];
            app.Button_639.BackgroundColor=[0.96,0.96,0.96];
            app.Button_640.BackgroundColor=[0.96,0.96,0.96];
            app.Button_641.BackgroundColor=[0.96,0.96,0.96];
            app.Button_642.BackgroundColor=[0.96,0.96,0.96];
            app.Button_643.BackgroundColor=[0.96,0.96,0.96];
            app.Button_644.BackgroundColor=[0.96,0.96,0.96];
            app.Button_645.BackgroundColor=[0.96,0.96,0.96];
            app.Button_646.BackgroundColor=[0.96,0.96,0.96];
            app.Button_647.BackgroundColor=[0.96,0.96,0.96];
            app.Button_648.BackgroundColor=[0.96,0.96,0.96];
            app.Button_649.BackgroundColor=[0.96,0.96,0.96];
            app.Button_650.BackgroundColor=[0.96,0.96,0.96];
            app.Button_651.BackgroundColor=[0.96,0.96,0.96];
            app.Button_652.BackgroundColor=[0.96,0.96,0.96];
            app.Button_653.BackgroundColor=[0.96,0.96,0.96];
            app.Button_654.BackgroundColor=[0.96,0.96,0.96];
            app.Button_655.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_639
        function Button_639ValueChanged(app, event)
            A276=70;
            B276=cellstr('70 km/h');
            c276=cellstr('app.Button_639');
            app.Matriz2(52)=c276;
            app.Matriz1(52)=A276;
            app.Matriz3(52)=B276;
            
            value = app.Button_639.Value;
            app.Button_631.BackgroundColor=[0.96,0.96,0.96];
            app.Button_632.BackgroundColor=[0.96,0.96,0.96];
            app.Button_633.BackgroundColor=[0.96,0.96,0.96];
            app.Button_634.BackgroundColor=[0.96,0.96,0.96];
            app.Button_635.BackgroundColor=[0.96,0.96,0.96];
            app.Button_636.BackgroundColor=[0.96,0.96,0.96];
            app.Button_637.BackgroundColor=[0.96,0.96,0.96];
            app.Button_638.BackgroundColor=[0.96,0.96,0.96];
            app.Button_639.BackgroundColor=[1.00,1.00,0.00];
            app.Button_640.BackgroundColor=[0.96,0.96,0.96];
            app.Button_641.BackgroundColor=[0.96,0.96,0.96];
            app.Button_642.BackgroundColor=[0.96,0.96,0.96];
            app.Button_643.BackgroundColor=[0.96,0.96,0.96];
            app.Button_644.BackgroundColor=[0.96,0.96,0.96];
            app.Button_645.BackgroundColor=[0.96,0.96,0.96];
            app.Button_646.BackgroundColor=[0.96,0.96,0.96];
            app.Button_647.BackgroundColor=[0.96,0.96,0.96];
            app.Button_648.BackgroundColor=[0.96,0.96,0.96];
            app.Button_649.BackgroundColor=[0.96,0.96,0.96];
            app.Button_650.BackgroundColor=[0.96,0.96,0.96];
            app.Button_651.BackgroundColor=[0.96,0.96,0.96];
            app.Button_652.BackgroundColor=[0.96,0.96,0.96];
            app.Button_653.BackgroundColor=[0.96,0.96,0.96];
            app.Button_654.BackgroundColor=[0.96,0.96,0.96];
            app.Button_655.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_640
        function Button_640ValueChanged(app, event)
            A277=75;
            B277=cellstr('75 km/h');
            c277=cellstr('app.Button_640');
            app.Matriz2(52)=c277;
            app.Matriz1(52)=A277;
            app.Matriz3(52)=B277;
            
            value = app.Button_640.Value;
            app.Button_631.BackgroundColor=[0.96,0.96,0.96];
            app.Button_632.BackgroundColor=[0.96,0.96,0.96];
            app.Button_633.BackgroundColor=[0.96,0.96,0.96];
            app.Button_634.BackgroundColor=[0.96,0.96,0.96];
            app.Button_635.BackgroundColor=[0.96,0.96,0.96];
            app.Button_636.BackgroundColor=[0.96,0.96,0.96];
            app.Button_637.BackgroundColor=[0.96,0.96,0.96];
            app.Button_638.BackgroundColor=[0.96,0.96,0.96];
            app.Button_639.BackgroundColor=[0.96,0.96,0.96];
            app.Button_640.BackgroundColor=[1.00,1.00,0.00];
            app.Button_641.BackgroundColor=[0.96,0.96,0.96];
            app.Button_642.BackgroundColor=[0.96,0.96,0.96];
            app.Button_643.BackgroundColor=[0.96,0.96,0.96];
            app.Button_644.BackgroundColor=[0.96,0.96,0.96];
            app.Button_645.BackgroundColor=[0.96,0.96,0.96];
            app.Button_646.BackgroundColor=[0.96,0.96,0.96];
            app.Button_647.BackgroundColor=[0.96,0.96,0.96];
            app.Button_648.BackgroundColor=[0.96,0.96,0.96];
            app.Button_649.BackgroundColor=[0.96,0.96,0.96];
            app.Button_650.BackgroundColor=[0.96,0.96,0.96];
            app.Button_651.BackgroundColor=[0.96,0.96,0.96];
            app.Button_652.BackgroundColor=[0.96,0.96,0.96];
            app.Button_653.BackgroundColor=[0.96,0.96,0.96];
            app.Button_654.BackgroundColor=[0.96,0.96,0.96];
            app.Button_655.BackgroundColor=[0.96,0.96,0.96];

        end

        % Value changed function: Button_641
        function Button_641ValueChanged(app, event)
            A278=80;
            B278=cellstr('80 km/h');
            c278=cellstr('app.Button_641');
            app.Matriz2(52)=c278;
            app.Matriz1(52)=A278;
            app.Matriz3(52)=B278;
            
            value = app.Button_641.Value;
            app.Button_631.BackgroundColor=[0.96,0.96,0.96];
            app.Button_632.BackgroundColor=[0.96,0.96,0.96];
            app.Button_633.BackgroundColor=[0.96,0.96,0.96];
            app.Button_634.BackgroundColor=[0.96,0.96,0.96];
            app.Button_635.BackgroundColor=[0.96,0.96,0.96];
            app.Button_636.BackgroundColor=[0.96,0.96,0.96];
            app.Button_637.BackgroundColor=[0.96,0.96,0.96];
            app.Button_638.BackgroundColor=[0.96,0.96,0.96];
            app.Button_639.BackgroundColor=[0.96,0.96,0.96];
            app.Button_640.BackgroundColor=[0.96,0.96,0.96];
            app.Button_641.BackgroundColor=[1.00,1.00,0.00];
            app.Button_642.BackgroundColor=[0.96,0.96,0.96];
            app.Button_643.BackgroundColor=[0.96,0.96,0.96];
            app.Button_644.BackgroundColor=[0.96,0.96,0.96];
            app.Button_645.BackgroundColor=[0.96,0.96,0.96];
            app.Button_646.BackgroundColor=[0.96,0.96,0.96];
            app.Button_647.BackgroundColor=[0.96,0.96,0.96];
            app.Button_648.BackgroundColor=[0.96,0.96,0.96];
            app.Button_649.BackgroundColor=[0.96,0.96,0.96];
            app.Button_650.BackgroundColor=[0.96,0.96,0.96];
            app.Button_651.BackgroundColor=[0.96,0.96,0.96];
            app.Button_652.BackgroundColor=[0.96,0.96,0.96];
            app.Button_653.BackgroundColor=[0.96,0.96,0.96];
            app.Button_654.BackgroundColor=[0.96,0.96,0.96];
            app.Button_655.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_642
        function Button_642ValueChanged(app, event)
            A279=85;
            B279=cellstr('85 km/h');
            c279=cellstr('app.Button_642');
            app.Matriz2(52)=c279;
            app.Matriz1(52)=A279;
            app.Matriz3(52)=B279;
            
            value = app.Button_642.Value;
            app.Button_631.BackgroundColor=[0.96,0.96,0.96];
            app.Button_632.BackgroundColor=[0.96,0.96,0.96];
            app.Button_633.BackgroundColor=[0.96,0.96,0.96];
            app.Button_634.BackgroundColor=[0.96,0.96,0.96];
            app.Button_635.BackgroundColor=[0.96,0.96,0.96];
            app.Button_636.BackgroundColor=[0.96,0.96,0.96];
            app.Button_637.BackgroundColor=[0.96,0.96,0.96];
            app.Button_638.BackgroundColor=[0.96,0.96,0.96];
            app.Button_639.BackgroundColor=[0.96,0.96,0.96];
            app.Button_640.BackgroundColor=[0.96,0.96,0.96];
            app.Button_641.BackgroundColor=[0.96,0.96,0.96];
            app.Button_642.BackgroundColor=[1.00,1.00,0.00];
            app.Button_643.BackgroundColor=[0.96,0.96,0.96];
            app.Button_644.BackgroundColor=[0.96,0.96,0.96];
            app.Button_645.BackgroundColor=[0.96,0.96,0.96];
            app.Button_646.BackgroundColor=[0.96,0.96,0.96];
            app.Button_647.BackgroundColor=[0.96,0.96,0.96];
            app.Button_648.BackgroundColor=[0.96,0.96,0.96];
            app.Button_649.BackgroundColor=[0.96,0.96,0.96];
            app.Button_650.BackgroundColor=[0.96,0.96,0.96];
            app.Button_651.BackgroundColor=[0.96,0.96,0.96];
            app.Button_652.BackgroundColor=[0.96,0.96,0.96];
            app.Button_653.BackgroundColor=[0.96,0.96,0.96];
            app.Button_654.BackgroundColor=[0.96,0.96,0.96];
            app.Button_655.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_643
        function Button_643ValueChanged(app, event)
            A280=90;
            B280=cellstr('90 km/h');
            c280=cellstr('app.Button_643');
            app.Matriz2(52)=c280;
            app.Matriz1(52)=A280;
            app.Matriz3(52)=B280;
            
            value = app.Button_643.Value;
            app.Button_631.BackgroundColor=[0.96,0.96,0.96];
            app.Button_632.BackgroundColor=[0.96,0.96,0.96];
            app.Button_633.BackgroundColor=[0.96,0.96,0.96];
            app.Button_634.BackgroundColor=[0.96,0.96,0.96];
            app.Button_635.BackgroundColor=[0.96,0.96,0.96];
            app.Button_636.BackgroundColor=[0.96,0.96,0.96];
            app.Button_637.BackgroundColor=[0.96,0.96,0.96];
            app.Button_638.BackgroundColor=[0.96,0.96,0.96];
            app.Button_639.BackgroundColor=[0.96,0.96,0.96];
            app.Button_640.BackgroundColor=[0.96,0.96,0.96];
            app.Button_641.BackgroundColor=[0.96,0.96,0.96];
            app.Button_642.BackgroundColor=[0.96,0.96,0.96];
            app.Button_643.BackgroundColor=[1.00,1.00,0.00];
            app.Button_644.BackgroundColor=[0.96,0.96,0.96];
            app.Button_645.BackgroundColor=[0.96,0.96,0.96];
            app.Button_646.BackgroundColor=[0.96,0.96,0.96];
            app.Button_647.BackgroundColor=[0.96,0.96,0.96];
            app.Button_648.BackgroundColor=[0.96,0.96,0.96];
            app.Button_649.BackgroundColor=[0.96,0.96,0.96];
            app.Button_650.BackgroundColor=[0.96,0.96,0.96];
            app.Button_651.BackgroundColor=[0.96,0.96,0.96];
            app.Button_652.BackgroundColor=[0.96,0.96,0.96];
            app.Button_653.BackgroundColor=[0.96,0.96,0.96];
            app.Button_654.BackgroundColor=[0.96,0.96,0.96];
            app.Button_655.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_644
        function Button_644ValueChanged(app, event)
            A281=95;
            B281=cellstr('95 km/h');
            c281=cellstr('app.Button_644');
            app.Matriz2(52)=c281; 
            app.Matriz1(52)=A281;
            app.Matriz3(52)=B281;
            
            value = app.Button_644.Value;
            app.Button_631.BackgroundColor=[0.96,0.96,0.96];
            app.Button_632.BackgroundColor=[0.96,0.96,0.96];
            app.Button_633.BackgroundColor=[0.96,0.96,0.96];
            app.Button_634.BackgroundColor=[0.96,0.96,0.96];
            app.Button_635.BackgroundColor=[0.96,0.96,0.96];
            app.Button_636.BackgroundColor=[0.96,0.96,0.96];
            app.Button_637.BackgroundColor=[0.96,0.96,0.96];
            app.Button_638.BackgroundColor=[0.96,0.96,0.96];
            app.Button_639.BackgroundColor=[0.96,0.96,0.96];
            app.Button_640.BackgroundColor=[0.96,0.96,0.96];
            app.Button_641.BackgroundColor=[0.96,0.96,0.96];
            app.Button_642.BackgroundColor=[0.96,0.96,0.96];
            app.Button_643.BackgroundColor=[0.96,0.96,0.96];
            app.Button_644.BackgroundColor=[1.00,1.00,0.00];
            app.Button_645.BackgroundColor=[0.96,0.96,0.96];
            app.Button_646.BackgroundColor=[0.96,0.96,0.96];
            app.Button_647.BackgroundColor=[0.96,0.96,0.96];
            app.Button_648.BackgroundColor=[0.96,0.96,0.96];
            app.Button_649.BackgroundColor=[0.96,0.96,0.96];
            app.Button_650.BackgroundColor=[0.96,0.96,0.96];
            app.Button_651.BackgroundColor=[0.96,0.96,0.96];
            app.Button_652.BackgroundColor=[0.96,0.96,0.96];
            app.Button_653.BackgroundColor=[0.96,0.96,0.96];
            app.Button_654.BackgroundColor=[0.96,0.96,0.96];
            app.Button_655.BackgroundColor=[0.96,0.96,0.96];

        end

        % Value changed function: Button_645
        function Button_645ValueChanged(app, event)
            A282=100;
            B282=cellstr('100 km/h');
            c282=cellstr('app.Button_645');
            app.Matriz2(52)=c282; 
            app.Matriz1(52)=A282;
            app.Matriz3(52)=B282;
            
            value = app.Button_645.Value;
            app.Button_631.BackgroundColor=[0.96,0.96,0.96];
            app.Button_632.BackgroundColor=[0.96,0.96,0.96];
            app.Button_633.BackgroundColor=[0.96,0.96,0.96];
            app.Button_634.BackgroundColor=[0.96,0.96,0.96];
            app.Button_635.BackgroundColor=[0.96,0.96,0.96];
            app.Button_636.BackgroundColor=[0.96,0.96,0.96];
            app.Button_637.BackgroundColor=[0.96,0.96,0.96];
            app.Button_638.BackgroundColor=[0.96,0.96,0.96];
            app.Button_639.BackgroundColor=[0.96,0.96,0.96];
            app.Button_640.BackgroundColor=[0.96,0.96,0.96];
            app.Button_641.BackgroundColor=[0.96,0.96,0.96];
            app.Button_642.BackgroundColor=[0.96,0.96,0.96];
            app.Button_643.BackgroundColor=[0.96,0.96,0.96];
            app.Button_644.BackgroundColor=[0.96,0.96,0.96];
            app.Button_645.BackgroundColor=[1.00,1.00,0.00];
            app.Button_646.BackgroundColor=[0.96,0.96,0.96];
            app.Button_647.BackgroundColor=[0.96,0.96,0.96];
            app.Button_648.BackgroundColor=[0.96,0.96,0.96];
            app.Button_649.BackgroundColor=[0.96,0.96,0.96];
            app.Button_650.BackgroundColor=[0.96,0.96,0.96];
            app.Button_651.BackgroundColor=[0.96,0.96,0.96];
            app.Button_652.BackgroundColor=[0.96,0.96,0.96];
            app.Button_653.BackgroundColor=[0.96,0.96,0.96];
            app.Button_654.BackgroundColor=[0.96,0.96,0.96];
            app.Button_655.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_646
        function Button_646ValueChanged(app, event)
            A283=105;
            B283=cellstr('105 km/h');
            c283=cellstr('app.Button_646');
            app.Matriz2(52)=c283;
            app.Matriz1(52)=A283;
            app.Matriz3(52)=B283;
            
            value = app.Button_646.Value;
            app.Button_631.BackgroundColor=[0.96,0.96,0.96];
            app.Button_632.BackgroundColor=[0.96,0.96,0.96];
            app.Button_633.BackgroundColor=[0.96,0.96,0.96];
            app.Button_634.BackgroundColor=[0.96,0.96,0.96];
            app.Button_635.BackgroundColor=[0.96,0.96,0.96];
            app.Button_636.BackgroundColor=[0.96,0.96,0.96];
            app.Button_637.BackgroundColor=[0.96,0.96,0.96];
            app.Button_638.BackgroundColor=[0.96,0.96,0.96];
            app.Button_639.BackgroundColor=[0.96,0.96,0.96];
            app.Button_640.BackgroundColor=[0.96,0.96,0.96];
            app.Button_641.BackgroundColor=[0.96,0.96,0.96];
            app.Button_642.BackgroundColor=[0.96,0.96,0.96];
            app.Button_643.BackgroundColor=[0.96,0.96,0.96];
            app.Button_644.BackgroundColor=[0.96,0.96,0.96];
            app.Button_645.BackgroundColor=[0.96,0.96,0.96];
            app.Button_646.BackgroundColor=[1.00,1.00,0.00];
            app.Button_647.BackgroundColor=[0.96,0.96,0.96];
            app.Button_648.BackgroundColor=[0.96,0.96,0.96];
            app.Button_649.BackgroundColor=[0.96,0.96,0.96];
            app.Button_650.BackgroundColor=[0.96,0.96,0.96];
            app.Button_651.BackgroundColor=[0.96,0.96,0.96];
            app.Button_652.BackgroundColor=[0.96,0.96,0.96];
            app.Button_653.BackgroundColor=[0.96,0.96,0.96];
            app.Button_654.BackgroundColor=[0.96,0.96,0.96];
            app.Button_655.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_647
        function Button_647ValueChanged(app, event)
            A284=110;
            B284=cellstr('110 km/h');
            c284=cellstr('app.Button_647');
            app.Matriz2(52)=c284;
            app.Matriz1(52)=A284;
            app.Matriz3(52)=B284;
            
            value = app.Button_647.Value;
            app.Button_631.BackgroundColor=[0.96,0.96,0.96];
            app.Button_632.BackgroundColor=[0.96,0.96,0.96];
            app.Button_633.BackgroundColor=[0.96,0.96,0.96];
            app.Button_634.BackgroundColor=[0.96,0.96,0.96];
            app.Button_635.BackgroundColor=[0.96,0.96,0.96];
            app.Button_636.BackgroundColor=[0.96,0.96,0.96];
            app.Button_637.BackgroundColor=[0.96,0.96,0.96];
            app.Button_638.BackgroundColor=[0.96,0.96,0.96];
            app.Button_639.BackgroundColor=[0.96,0.96,0.96];
            app.Button_640.BackgroundColor=[0.96,0.96,0.96];
            app.Button_641.BackgroundColor=[0.96,0.96,0.96];
            app.Button_642.BackgroundColor=[0.96,0.96,0.96];
            app.Button_643.BackgroundColor=[0.96,0.96,0.96];
            app.Button_644.BackgroundColor=[0.96,0.96,0.96];
            app.Button_645.BackgroundColor=[0.96,0.96,0.96];
            app.Button_646.BackgroundColor=[0.96,0.96,0.96];
            app.Button_647.BackgroundColor=[1.00,1.00,0.00];
            app.Button_648.BackgroundColor=[0.96,0.96,0.96];
            app.Button_649.BackgroundColor=[0.96,0.96,0.96];
            app.Button_650.BackgroundColor=[0.96,0.96,0.96];
            app.Button_651.BackgroundColor=[0.96,0.96,0.96];
            app.Button_652.BackgroundColor=[0.96,0.96,0.96];
            app.Button_653.BackgroundColor=[0.96,0.96,0.96];
            app.Button_654.BackgroundColor=[0.96,0.96,0.96];
            app.Button_655.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_648
        function Button_648ValueChanged(app, event)
            A285=115;
            B285=cellstr('115 km/h');
            c285=cellstr('app.Button_648');
            app.Matriz2(52)=c285;
            app.Matriz1(52)=A285;
            app.Matriz3(52)=B285;
            
            value = app.Button_648.Value;
            app.Button_631.BackgroundColor=[0.96,0.96,0.96];
            app.Button_632.BackgroundColor=[0.96,0.96,0.96];
            app.Button_633.BackgroundColor=[0.96,0.96,0.96];
            app.Button_634.BackgroundColor=[0.96,0.96,0.96];
            app.Button_635.BackgroundColor=[0.96,0.96,0.96];
            app.Button_636.BackgroundColor=[0.96,0.96,0.96];
            app.Button_637.BackgroundColor=[0.96,0.96,0.96];
            app.Button_638.BackgroundColor=[0.96,0.96,0.96];
            app.Button_639.BackgroundColor=[0.96,0.96,0.96];
            app.Button_640.BackgroundColor=[0.96,0.96,0.96];
            app.Button_641.BackgroundColor=[0.96,0.96,0.96];
            app.Button_642.BackgroundColor=[0.96,0.96,0.96];
            app.Button_643.BackgroundColor=[0.96,0.96,0.96];
            app.Button_644.BackgroundColor=[0.96,0.96,0.96];
            app.Button_645.BackgroundColor=[0.96,0.96,0.96];
            app.Button_646.BackgroundColor=[0.96,0.96,0.96];
            app.Button_647.BackgroundColor=[0.96,0.96,0.96];
            app.Button_648.BackgroundColor=[1.00,1.00,0.00];
            app.Button_649.BackgroundColor=[0.96,0.96,0.96];
            app.Button_650.BackgroundColor=[0.96,0.96,0.96];
            app.Button_651.BackgroundColor=[0.96,0.96,0.96];
            app.Button_652.BackgroundColor=[0.96,0.96,0.96];
            app.Button_653.BackgroundColor=[0.96,0.96,0.96];
            app.Button_654.BackgroundColor=[0.96,0.96,0.96];
            app.Button_655.BackgroundColor=[0.96,0.96,0.96];

        end

        % Value changed function: Button_649
        function Button_649ValueChanged(app, event)
            A286=120;
            B286=cellstr('120 km/h');
            c286=cellstr('app.Button_649');
            app.Matriz2(52)=c286;
            app.Matriz1(52)=A286;
            app.Matriz3(52)=B286;
            
            value = app.Button_649.Value;
            app.Button_631.BackgroundColor=[0.96,0.96,0.96];
            app.Button_632.BackgroundColor=[0.96,0.96,0.96];
            app.Button_633.BackgroundColor=[0.96,0.96,0.96];
            app.Button_634.BackgroundColor=[0.96,0.96,0.96];
            app.Button_635.BackgroundColor=[0.96,0.96,0.96];
            app.Button_636.BackgroundColor=[0.96,0.96,0.96];
            app.Button_637.BackgroundColor=[0.96,0.96,0.96];
            app.Button_638.BackgroundColor=[0.96,0.96,0.96];
            app.Button_639.BackgroundColor=[0.96,0.96,0.96];
            app.Button_640.BackgroundColor=[0.96,0.96,0.96];
            app.Button_641.BackgroundColor=[0.96,0.96,0.96];
            app.Button_642.BackgroundColor=[0.96,0.96,0.96];
            app.Button_643.BackgroundColor=[0.96,0.96,0.96];
            app.Button_644.BackgroundColor=[0.96,0.96,0.96];
            app.Button_645.BackgroundColor=[0.96,0.96,0.96];
            app.Button_646.BackgroundColor=[0.96,0.96,0.96];
            app.Button_647.BackgroundColor=[0.96,0.96,0.96];
            app.Button_648.BackgroundColor=[0.96,0.96,0.96];
            app.Button_649.BackgroundColor=[1.00,1.00,0.00];
            app.Button_650.BackgroundColor=[0.96,0.96,0.96];
            app.Button_651.BackgroundColor=[0.96,0.96,0.96];
            app.Button_652.BackgroundColor=[0.96,0.96,0.96];
            app.Button_653.BackgroundColor=[0.96,0.96,0.96];
            app.Button_654.BackgroundColor=[0.96,0.96,0.96];
            app.Button_655.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_650
        function Button_650ValueChanged(app, event)
            A287=125;
            B287=cellstr('125 km/h');
            c287=cellstr('app.Button_650');
            app.Matriz2(52)=c287;
            app.Matriz1(52)=A287;
            app.Matriz3(52)=B287;
            
            value = app.Button_650.Value;
            app.Button_631.BackgroundColor=[0.96,0.96,0.96];
            app.Button_632.BackgroundColor=[0.96,0.96,0.96];
            app.Button_633.BackgroundColor=[0.96,0.96,0.96];
            app.Button_634.BackgroundColor=[0.96,0.96,0.96];
            app.Button_635.BackgroundColor=[0.96,0.96,0.96];
            app.Button_636.BackgroundColor=[0.96,0.96,0.96];
            app.Button_637.BackgroundColor=[0.96,0.96,0.96];
            app.Button_638.BackgroundColor=[0.96,0.96,0.96];
            app.Button_639.BackgroundColor=[0.96,0.96,0.96];
            app.Button_640.BackgroundColor=[0.96,0.96,0.96];
            app.Button_641.BackgroundColor=[0.96,0.96,0.96];
            app.Button_642.BackgroundColor=[0.96,0.96,0.96];
            app.Button_643.BackgroundColor=[0.96,0.96,0.96];
            app.Button_644.BackgroundColor=[0.96,0.96,0.96];
            app.Button_645.BackgroundColor=[0.96,0.96,0.96];
            app.Button_646.BackgroundColor=[0.96,0.96,0.96];
            app.Button_647.BackgroundColor=[0.96,0.96,0.96];
            app.Button_648.BackgroundColor=[0.96,0.96,0.96];
            app.Button_649.BackgroundColor=[0.96,0.96,0.96];
            app.Button_650.BackgroundColor=[1.00,1.00,0.00];
            app.Button_651.BackgroundColor=[0.96,0.96,0.96];
            app.Button_652.BackgroundColor=[0.96,0.96,0.96];
            app.Button_653.BackgroundColor=[0.96,0.96,0.96];
            app.Button_654.BackgroundColor=[0.96,0.96,0.96];
            app.Button_655.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_651
        function Button_651ValueChanged(app, event)
            A288=130;
            B288=cellstr('130 km/h');
            c288=cellstr('app.Button_651');
            app.Matriz2(52)=c288;
            app.Matriz1(52)=A288;
            app.Matriz3(52)=B288;
            
            value = app.Button_651.Value;
            app.Button_631.BackgroundColor=[0.96,0.96,0.96];
            app.Button_632.BackgroundColor=[0.96,0.96,0.96];
            app.Button_633.BackgroundColor=[0.96,0.96,0.96];
            app.Button_634.BackgroundColor=[0.96,0.96,0.96];
            app.Button_635.BackgroundColor=[0.96,0.96,0.96];
            app.Button_636.BackgroundColor=[0.96,0.96,0.96];
            app.Button_637.BackgroundColor=[0.96,0.96,0.96];
            app.Button_638.BackgroundColor=[0.96,0.96,0.96];
            app.Button_639.BackgroundColor=[0.96,0.96,0.96];
            app.Button_640.BackgroundColor=[0.96,0.96,0.96];
            app.Button_641.BackgroundColor=[0.96,0.96,0.96];
            app.Button_642.BackgroundColor=[0.96,0.96,0.96];
            app.Button_643.BackgroundColor=[0.96,0.96,0.96];
            app.Button_644.BackgroundColor=[0.96,0.96,0.96];
            app.Button_645.BackgroundColor=[0.96,0.96,0.96];
            app.Button_646.BackgroundColor=[0.96,0.96,0.96];
            app.Button_647.BackgroundColor=[0.96,0.96,0.96];
            app.Button_648.BackgroundColor=[0.96,0.96,0.96];
            app.Button_649.BackgroundColor=[0.96,0.96,0.96];
            app.Button_650.BackgroundColor=[0.96,0.96,0.96];
            app.Button_651.BackgroundColor=[1.00,1.00,0.00];
            app.Button_652.BackgroundColor=[0.96,0.96,0.96];
            app.Button_653.BackgroundColor=[0.96,0.96,0.96];
            app.Button_654.BackgroundColor=[0.96,0.96,0.96];
            app.Button_655.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_652
        function Button_652ValueChanged(app, event)
            A289=135;
            B289=cellstr('135 km/h');
            c289=cellstr('app.Button_652');
            app.Matriz2(52)=c289;
            app.Matriz1(52)=A289;
            app.Matriz3(52)=B289;
            
            value = app.Button_652.Value;
            app.Button_631.BackgroundColor=[0.96,0.96,0.96];
            app.Button_632.BackgroundColor=[0.96,0.96,0.96];
            app.Button_633.BackgroundColor=[0.96,0.96,0.96];
            app.Button_634.BackgroundColor=[0.96,0.96,0.96];
            app.Button_635.BackgroundColor=[0.96,0.96,0.96];
            app.Button_636.BackgroundColor=[0.96,0.96,0.96];
            app.Button_637.BackgroundColor=[0.96,0.96,0.96];
            app.Button_638.BackgroundColor=[0.96,0.96,0.96];
            app.Button_639.BackgroundColor=[0.96,0.96,0.96];
            app.Button_640.BackgroundColor=[0.96,0.96,0.96];
            app.Button_641.BackgroundColor=[0.96,0.96,0.96];
            app.Button_642.BackgroundColor=[0.96,0.96,0.96];
            app.Button_643.BackgroundColor=[0.96,0.96,0.96];
            app.Button_644.BackgroundColor=[0.96,0.96,0.96];
            app.Button_645.BackgroundColor=[0.96,0.96,0.96];
            app.Button_646.BackgroundColor=[0.96,0.96,0.96];
            app.Button_647.BackgroundColor=[0.96,0.96,0.96];
            app.Button_648.BackgroundColor=[0.96,0.96,0.96];
            app.Button_649.BackgroundColor=[0.96,0.96,0.96];
            app.Button_650.BackgroundColor=[0.96,0.96,0.96];
            app.Button_651.BackgroundColor=[0.96,0.96,0.96];
            app.Button_652.BackgroundColor=[1.00,1.00,0.00];
            app.Button_653.BackgroundColor=[0.96,0.96,0.96];
            app.Button_654.BackgroundColor=[0.96,0.96,0.96];
            app.Button_655.BackgroundColor=[0.96,0.96,0.96];

        end

        % Value changed function: Button_653
        function Button_653ValueChanged(app, event)
            A290=140;
            B290=cellstr('140 km/h');
            c290=cellstr('app.Button_653');
            app.Matriz2(52)=c290;
            app.Matriz1(52)=A290;
            app.Matriz3(52)=B290;
            
            value = app.Button_653.Value;
            app.Button_631.BackgroundColor=[0.96,0.96,0.96];
            app.Button_632.BackgroundColor=[0.96,0.96,0.96];
            app.Button_633.BackgroundColor=[0.96,0.96,0.96];
            app.Button_634.BackgroundColor=[0.96,0.96,0.96];
            app.Button_635.BackgroundColor=[0.96,0.96,0.96];
            app.Button_636.BackgroundColor=[0.96,0.96,0.96];
            app.Button_637.BackgroundColor=[0.96,0.96,0.96];
            app.Button_638.BackgroundColor=[0.96,0.96,0.96];
            app.Button_639.BackgroundColor=[0.96,0.96,0.96];
            app.Button_640.BackgroundColor=[0.96,0.96,0.96];
            app.Button_641.BackgroundColor=[0.96,0.96,0.96];
            app.Button_642.BackgroundColor=[0.96,0.96,0.96];
            app.Button_643.BackgroundColor=[0.96,0.96,0.96];
            app.Button_644.BackgroundColor=[0.96,0.96,0.96];
            app.Button_645.BackgroundColor=[0.96,0.96,0.96];
            app.Button_646.BackgroundColor=[0.96,0.96,0.96];
            app.Button_647.BackgroundColor=[0.96,0.96,0.96];
            app.Button_648.BackgroundColor=[0.96,0.96,0.96];
            app.Button_649.BackgroundColor=[0.96,0.96,0.96];
            app.Button_650.BackgroundColor=[0.96,0.96,0.96];
            app.Button_651.BackgroundColor=[0.96,0.96,0.96];
            app.Button_652.BackgroundColor=[0.96,0.96,0.96];
            app.Button_653.BackgroundColor=[1.00,1.00,0.00];
            app.Button_654.BackgroundColor=[0.96,0.96,0.96];
            app.Button_655.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_655
        function Button_655ValueChanged(app, event)
            A292=150;
            B292=cellstr('≥ 150 km/h');
            c292=cellstr('app.Button_655');
            app.Matriz2(52)=c292;
            app.Matriz1(52)=A292;
            app.Matriz3(52)=B292;
            
            value = app.Button_655.Value;
            app.Button_631.BackgroundColor=[0.96,0.96,0.96];
            app.Button_632.BackgroundColor=[0.96,0.96,0.96];
            app.Button_633.BackgroundColor=[0.96,0.96,0.96];
            app.Button_634.BackgroundColor=[0.96,0.96,0.96];
            app.Button_635.BackgroundColor=[0.96,0.96,0.96];
            app.Button_636.BackgroundColor=[0.96,0.96,0.96];
            app.Button_637.BackgroundColor=[0.96,0.96,0.96];
            app.Button_638.BackgroundColor=[0.96,0.96,0.96];
            app.Button_639.BackgroundColor=[0.96,0.96,0.96];
            app.Button_640.BackgroundColor=[0.96,0.96,0.96];
            app.Button_641.BackgroundColor=[0.96,0.96,0.96];
            app.Button_642.BackgroundColor=[0.96,0.96,0.96];
            app.Button_643.BackgroundColor=[0.96,0.96,0.96];
            app.Button_644.BackgroundColor=[0.96,0.96,0.96];
            app.Button_645.BackgroundColor=[0.96,0.96,0.96];
            app.Button_646.BackgroundColor=[0.96,0.96,0.96];
            app.Button_647.BackgroundColor=[0.96,0.96,0.96];
            app.Button_648.BackgroundColor=[0.96,0.96,0.96];
            app.Button_649.BackgroundColor=[0.96,0.96,0.96];
            app.Button_650.BackgroundColor=[0.96,0.96,0.96];
            app.Button_651.BackgroundColor=[0.96,0.96,0.96];
            app.Button_652.BackgroundColor=[0.96,0.96,0.96];
            app.Button_653.BackgroundColor=[0.96,0.96,0.96];
            app.Button_654.BackgroundColor=[0.96,0.96,0.96];
            app.Button_655.BackgroundColor=[1.00,1.00,0.00];
        end

        % Value changed function: Button_656
        function Button_656ValueChanged(app, event)
            value = app.Button_656.Value;
            A293=1;
            B293=cellstr('Ausente');
            c293=cellstr('app.Button_656');
            app.Matriz2(51)=c293;
            app.Matriz1(51)=A293;
            app.Matriz3(51)=B293;

            app.Button_656.BackgroundColor=[1.00,1.00,0.00];
            app.Button_665.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_665
        function Button_665ValueChanged(app, event)
            A294=2;
            B294=cellstr('Presente');
            c294=cellstr('app.Button_665');
            app.Matriz2(51)=c294;
            app.Matriz1(51)=A294;
            app.Matriz3(51)=B294;
            
            value = app.Button_665.Value;
            
            app.Button_656.BackgroundColor=[0.96,0.96,0.96];
            app.Button_665.BackgroundColor=[1.00,1.00,0.00];

        end

        % Value changed function: Button_318
        function Button_318ValueChanged(app, event)
            value = app.Button_318.Value;
            A165=4;
            B165=cellstr('Comercial');
            c165=cellstr('app.Button_318');
            app.Matriz2(36)=c165;
            app.Matriz1(36)=A165;
            app.Matriz3(36)=B165;
            
            
            app.Button_340.BackgroundColor=[0.96,0.96,0.96];
            app.Button_321.BackgroundColor=[0.96,0.96,0.96];
            app.Button_320.BackgroundColor=[0.96,0.96,0.96];
            app.Button_318.BackgroundColor=[1.00,1.00,0.00];
            app.Button_425.BackgroundColor=[0.96,0.96,0.96];
            app.Button_319.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_319
        function Button_319ValueChanged(app, event)
            value = app.Button_319.Value;
            A168=6;
            B168=cellstr('Industrial y manufactura');
            c168=cellstr('app.Button_319');
            app.Matriz2(36)=c168;
            app.Matriz1(36)=A168;
            app.Matriz3(36)=B168;
            
            app.Button_340.BackgroundColor=[0.96,0.96,0.96];
            app.Button_321.BackgroundColor=[0.96,0.96,0.96];
            app.Button_320.BackgroundColor=[0.96,0.96,0.96];
            app.Button_318.BackgroundColor=[0.96,0.96,0.96];
            app.Button_425.BackgroundColor=[0.96,0.96,0.96];
            app.Button_319.BackgroundColor=[1.00,1.00,0.00];
            
            value = app.Button_319.Value;
            
        end

        % Value changed function: Button_320
        function Button_320ValueChanged(app, event)
            value = app.Button_320.Value;
            A164=3;
            B164=cellstr('Residencial');
            c164=cellstr('app.Button_320');
            app.Matriz2(36)=c164; 
            app.Matriz1(36)=A164;
            app.Matriz3(36)=B164;
            
            app.Button_340.BackgroundColor=[0.96,0.96,0.96];
            app.Button_321.BackgroundColor=[0.96,0.96,0.96];
            app.Button_320.BackgroundColor=[1.00,1.00,0.00];
            app.Button_318.BackgroundColor=[0.96,0.96,0.96];
            app.Button_425.BackgroundColor=[0.96,0.96,0.96];
            app.Button_319.BackgroundColor=[0.96,0.96,0.96];


        end

        % Value changed function: Button_321
        function Button_321ValueChanged(app, event)
            value = app.Button_321.Value;
            A163=2;
            B163=cellstr('Agricultura y ganadería');
            c163=cellstr('app.Button_321');
            app.Matriz2(36)=c163; 
            app.Matriz1(36)=A163;
            app.Matriz3(36)=B163;
            
            app.Button_340.BackgroundColor=[0.96,0.96,0.96];
            app.Button_321.BackgroundColor=[1.00,1.00,0.00];
            app.Button_320.BackgroundColor=[0.96,0.96,0.96];
            app.Button_318.BackgroundColor=[0.96,0.96,0.96];
            app.Button_425.BackgroundColor=[0.96,0.96,0.96];
            app.Button_319.BackgroundColor=[0.96,0.96,0.96];
            
        end

        % Value changed function: Button_340
        function Button_340ValueChanged(app, event)
            value = app.Button_340.Value;
            A162=1;
            B162=cellstr('Áreas no desarrolladas');
            c162=cellstr('app.Button_340');
            app.Matriz2(36)=c162;
            app.Matriz1(36)=A162;
            app.Matriz3(36)=B162;

            app.Button_340.BackgroundColor=[1.00,1.00,0.00];
            app.Button_321.BackgroundColor=[0.96,0.96,0.96];
            app.Button_320.BackgroundColor=[0.96,0.96,0.96];
            app.Button_318.BackgroundColor=[0.96,0.96,0.96];
            app.Button_425.BackgroundColor=[0.96,0.96,0.96];
            app.Button_319.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_425
        function Button_425ValueChanged(app, event)
            value = app.Button_425.Value;
            A167=5;
            B167=cellstr('Educacional');
            c167=cellstr('app.Button_425');
            app.Matriz2(36)=c167;
            app.Matriz1(36)=A167;
            app.Matriz3(36)=B167;
            
            
            app.Button_340.BackgroundColor=[0.96,0.96,0.96];
            app.Button_321.BackgroundColor=[0.96,0.96,0.96];
            app.Button_320.BackgroundColor=[0.96,0.96,0.96];
            app.Button_318.BackgroundColor=[0.96,0.96,0.96];
            app.Button_425.BackgroundColor=[1.00,1.00,0.00];
            app.Button_319.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_706
        function Button_706ValueChanged(app, event)
            value = app.Button_706.Value;
            A172=4;
            B172=cellstr('Comercial');
            c172=cellstr('app.Button_706');
            app.Matriz2(37)=c172;
            app.Matriz1(37)=A172;
            app.Matriz3(37)=B172;

            
            app.Button_710.BackgroundColor=[0.96,0.96,0.96];
            app.Button_709.BackgroundColor=[0.96,0.96,0.96];
            app.Button_708.BackgroundColor=[0.96,0.96,0.96];
            app.Button_706.BackgroundColor=[1.00,1.00,0.00];
            app.Button_711.BackgroundColor=[0.96,0.96,0.96];
            app.Button_107.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_707
        function Button_707ValueChanged(app, event)
            value = app.Button_707.Value;
            A175=6;
            B175=cellstr('Industrial y manufactura');
            c175=cellstr('app.Button_707');
            app.Matriz2(37)=c175;
            app.Matriz1(37)=A175;
            app.Matriz3(37)=B175;
            
            app.Button_710.BackgroundColor=[0.96,0.96,0.96];
            app.Button_709.BackgroundColor=[0.96,0.96,0.96];
            app.Button_708.BackgroundColor=[0.96,0.96,0.96];
            app.Button_706.BackgroundColor=[0.96,0.96,0.96];
            app.Button_711.BackgroundColor=[0.96,0.96,0.96];
            app.Button_707.BackgroundColor=[1.00,1.00,0.00];
            
            
        end

        % Value changed function: Button_708
        function Button_708ValueChanged(app, event)
            value = app.Button_708.Value;
            A171=3;
            B171=cellstr('Residencial');
            c171=cellstr('app.Button_708');
            app.Matriz2(37)=c171;
            app.Matriz1(37)=A171;
            app.Matriz3(37)=B171;
            
            
            app.Button_710.BackgroundColor=[0.96,0.96,0.96];
            app.Button_709.BackgroundColor=[0.96,0.96,0.96];
            app.Button_708.BackgroundColor=[1.00,1.00,0.00];
            app.Button_706.BackgroundColor=[0.96,0.96,0.96];
            app.Button_711.BackgroundColor=[0.96,0.96,0.96];
            app.Button_707.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_709
        function Button_709ValueChanged(app, event)
            value = app.Button_709.Value;
            A170=2;
            B170=cellstr('Agricultura y ganadería');
            c170=cellstr('app.Button_709');
            app.Matriz2(37)=c170;
            app.Matriz1(37)=A170;
            app.Matriz3(37)=B170;
            
            
            app.Button_710.BackgroundColor=[0.96,0.96,0.96];
            app.Button_709.BackgroundColor=[1.00,1.00,0.00];
            app.Button_708.BackgroundColor=[0.96,0.96,0.96];
            app.Button_706.BackgroundColor=[0.96,0.96,0.96];
            app.Button_711.BackgroundColor=[0.96,0.96,0.96];
            app.Button_707.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_710
        function Button_710ValueChanged(app, event)
            value = app.Button_710.Value;
            A169=1;
            B169=cellstr('Áreas no desarrolladas');
            c169=cellstr('app.Button_710');
            app.Matriz2(37)=c169;
            app.Matriz1(37)=A169;
            app.Matriz3(37)=B169;
            
            app.Button_710.BackgroundColor=[1.00,1.00,0.00];
            app.Button_709.BackgroundColor=[0.96,0.96,0.96];
            app.Button_708.BackgroundColor=[0.96,0.96,0.96];
            app.Button_706.BackgroundColor=[0.96,0.96,0.96];
            app.Button_711.BackgroundColor=[0.96,0.96,0.96];
            app.Button_707.BackgroundColor=[0.96,0.96,0.96];


        end

        % Value changed function: Button_711
        function Button_711ValueChanged(app, event)
            value = app.Button_711.Value;
            A174=5;
            B174=cellstr('Educacional');
            c174=cellstr('app.Button_711');
            app.Matriz2(37)=c174;
            app.Matriz1(37)=A174;
            app.Matriz3(37)=B174;
            
            app.Button_710.BackgroundColor=[0.96,0.96,0.96];
            app.Button_709.BackgroundColor=[0.96,0.96,0.96];
            app.Button_708.BackgroundColor=[0.96,0.96,0.96];
            app.Button_706.BackgroundColor=[0.96,0.96,0.96];
            app.Button_711.BackgroundColor=[1.00,1.00,0.00];
            app.Button_707.BackgroundColor=[0.96,0.96,0.96];


        end

        % Value changed function: Button_611
        function Button_611ValueChanged(app, event)
            value = app.Button_611.Value;
            A177=2;
            B177=cellstr('Urbano/ población pequeña o pueblo');
            c177=cellstr('app.Button_611');
            app.Matriz2(38)=c177;
            app.Matriz1(38)=A177;
            app.Matriz3(38)=B177;
            
            app.Button_612.BackgroundColor=[0.96,0.96,0.96];
            app.Button_611.BackgroundColor=[1.00,1.00,0.00];


        end

        % Value changed function: Button_612
        function Button_612ValueChanged(app, event)
            value = app.Button_612.Value;
            A176=1;
            B176=cellstr('Rural/área abierta');
            c176=cellstr('app.Button_612');
            app.Matriz2(38)=c176;
            app.Matriz1(38)=A176;
            app.Matriz3(38)=B176;
            
            app.Button_612.BackgroundColor=[1.00,1.00,0.00];
            app.Button_611.BackgroundColor=[0.96,0.96,0.96];
            
             %Carril grande app.CarrilG_2.Value==1
                d176=1;
                app.Matriz4(38)=d176;
           %Carril mediano app.CarrilM.Value==1
                %d176=1.20;
                
           %Carril peque*o app.CarrilP.Value==1
                %d176=1.50;
            
        end

        % Value changed function: Button_380
        function Button_380ValueChanged(app, event)
            A183=6;
            B183=cellstr('Solo refugio');
            c183=cellstr('app.Button_380');
            app.Matriz2(39)=c183;
            app.Matriz1(39)=A183;
            app.Matriz3(39)=B183;
            
            value = app.Button_380.Value;
            app.Button_384.BackgroundColor=[0.96,0.96,0.96];
            app.Button_383.BackgroundColor=[0.96,0.96,0.96];
            app.Button_390.BackgroundColor=[0.96,0.96,0.96];
            app.Button_382.BackgroundColor=[0.96,0.96,0.96];
            app.Button_381.BackgroundColor=[0.96,0.96,0.96];
            app.Button_380.BackgroundColor=[1.00,1.00,0.00];
            app.Button_386.BackgroundColor=[0.96,0.96,0.96];
            app.Button_387.BackgroundColor=[0.96,0.96,0.96];
            app.Button_385.BackgroundColor=[0.96,0.96,0.96];
            app.Button_388.BackgroundColor=[0.96,0.96,0.96];
            app.Button_389.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_381
        function Button_381ValueChanged(app, event)
            A182=5;
            B182=cellstr('No semaforizada, señalizada con pintura y sin refugio');
            c182=cellstr('app.Button_381');
            app.Matriz2(39)=c182;
            app.Matriz1(39)=A182;
            app.Matriz3(39)=B182;
            
            value = app.Button_381.Value;
            app.Button_384.BackgroundColor=[0.96,0.96,0.96];
            app.Button_383.BackgroundColor=[0.96,0.96,0.96];
            app.Button_390.BackgroundColor=[0.96,0.96,0.96];
            app.Button_382.BackgroundColor=[0.96,0.96,0.96];
            app.Button_381.BackgroundColor=[1.00,1.00,0.00];
            app.Button_380.BackgroundColor=[0.96,0.96,0.96];
            app.Button_386.BackgroundColor=[0.96,0.96,0.96];
            app.Button_387.BackgroundColor=[0.96,0.96,0.96];
            app.Button_385.BackgroundColor=[0.96,0.96,0.96];
            app.Button_388.BackgroundColor=[0.96,0.96,0.96];
            app.Button_389.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_382
        function Button_382ValueChanged(app, event)
            A181=4;
            B181=cellstr('No semaforizada, señalizada con pintura y con refugio');
            c181=cellstr('app.Button_382');
            app.Matriz2(39)=c181;
            app.Matriz1(39)=A181;
            app.Matriz3(39)=B181;
            
            value = app.Button_382.Value;
            
            
            app.Button_384.BackgroundColor=[0.96,0.96,0.96];
            app.Button_383.BackgroundColor=[0.96,0.96,0.96];
            app.Button_390.BackgroundColor=[0.96,0.96,0.96];
            app.Button_382.BackgroundColor=[1.00,1.00,0.00];
            app.Button_381.BackgroundColor=[0.96,0.96,0.96];
            app.Button_380.BackgroundColor=[0.96,0.96,0.96];
            app.Button_386.BackgroundColor=[0.96,0.96,0.96];
            app.Button_387.BackgroundColor=[0.96,0.96,0.96];
            app.Button_385.BackgroundColor=[0.96,0.96,0.96];
            app.Button_388.BackgroundColor=[0.96,0.96,0.96];
            app.Button_389.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_383
        function Button_383ValueChanged(app, event)
            value = app.Button_383.Value;
            A179=2;
            B179=cellstr('Semaforizada con refugio');
            c179=cellstr('app.Button_383');
            app.Matriz2(39)=c179;
            app.Matriz1(39)=A179;
            app.Matriz3(39)=B179;
            
            app.Button_384.BackgroundColor=[0.96,0.96,0.96];
            app.Button_383.BackgroundColor=[1.00,1.00,0.00];
            app.Button_390.BackgroundColor=[0.96,0.96,0.96];
            app.Button_382.BackgroundColor=[0.96,0.96,0.96];
            app.Button_381.BackgroundColor=[0.96,0.96,0.96];
            app.Button_380.BackgroundColor=[0.96,0.96,0.96];
            app.Button_386.BackgroundColor=[0.96,0.96,0.96];
            app.Button_387.BackgroundColor=[0.96,0.96,0.96];
            app.Button_385.BackgroundColor=[0.96,0.96,0.96];
            app.Button_388.BackgroundColor=[0.96,0.96,0.96];
            app.Button_389.BackgroundColor=[0.96,0.96,0.96];
            
        end

        % Value changed function: Button_384
        function Button_384ValueChanged(app, event)
            value = app.Button_384.Value;
            A178=1;
            B178=cellstr('Infraestructura a desnivel');
            c178=cellstr('app.Button_384');
            app.Matriz2(39)=c178;
            app.Matriz1(39)=A178;
            app.Matriz3(39)=B178;
            
            app.Button_384.BackgroundColor=[1.00,1.00,0.00];
            app.Button_383.BackgroundColor=[0.96,0.96,0.96];
            app.Button_390.BackgroundColor=[0.96,0.96,0.96];
            app.Button_382.BackgroundColor=[0.96,0.96,0.96];
            app.Button_381.BackgroundColor=[0.96,0.96,0.96];
            app.Button_380.BackgroundColor=[0.96,0.96,0.96];
            app.Button_386.BackgroundColor=[0.96,0.96,0.96];
            app.Button_387.BackgroundColor=[0.96,0.96,0.96];
            app.Button_385.BackgroundColor=[0.96,0.96,0.96];
            app.Button_388.BackgroundColor=[0.96,0.96,0.96];
            app.Button_389.BackgroundColor=[0.96,0.96,0.96];
            
            
        end

        % Value changed function: Button_390
        function Button_390ValueChanged(app, event)
            A180=3;
            B180=cellstr('Semaforizada sin refugio');
            c180=cellstr('app.Button_390');
            app.Matriz2(39)=c180;
            app.Matriz1(39)=A180;
            app.Matriz3(39)=B180;
            
            value = app.Button_390.Value;
            app.Button_384.BackgroundColor=[0.96,0.96,0.96];
            app.Button_383.BackgroundColor=[0.96,0.96,0.96];
            app.Button_390.BackgroundColor=[1.00,1.00,0.00];
            app.Button_382.BackgroundColor=[0.96,0.96,0.96];
            app.Button_381.BackgroundColor=[0.96,0.96,0.96];
            app.Button_380.BackgroundColor=[0.96,0.96,0.96];
            app.Button_386.BackgroundColor=[0.96,0.96,0.96];
            app.Button_387.BackgroundColor=[0.96,0.96,0.96];
            app.Button_385.BackgroundColor=[0.96,0.96,0.96];
            app.Button_388.BackgroundColor=[0.96,0.96,0.96];
            app.Button_389.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_385
        function Button_385ValueChanged(app, event)
            A186=9;
            B186=cellstr('Cruce demarcado elevado, sin semáforo, sin refugio');
            c186=cellstr('app.Button_385');
            app.Matriz2(39)=c186;
            app.Matriz1(39)=A186;
            app.Matriz3(39)=B186;
            
            value = app.Button_385.Value;
            
            app.Button_384.BackgroundColor=[0.96,0.96,0.96];
            app.Button_383.BackgroundColor=[0.96,0.96,0.96];
            app.Button_390.BackgroundColor=[0.96,0.96,0.96];
            app.Button_382.BackgroundColor=[0.96,0.96,0.96];
            app.Button_381.BackgroundColor=[0.96,0.96,0.96];
            app.Button_380.BackgroundColor=[0.96,0.96,0.96];
            app.Button_386.BackgroundColor=[0.96,0.96,0.96];
            app.Button_387.BackgroundColor=[0.96,0.96,0.96];
            app.Button_385.BackgroundColor=[1.00,1.00,0.00];
            app.Button_388.BackgroundColor=[0.96,0.96,0.96];
            app.Button_389.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_386
        function Button_386ValueChanged(app, event)
            A184=7;
            B184=cellstr('Sin infraestructura');
            c184=cellstr('app.Button_386');
            app.Matriz2(39)=c184;
            app.Matriz1(39)=A184;
            app.Matriz3(39)=B184;
            
            value = app.Button_386.Value;
            
            app.Button_384.BackgroundColor=[0.96,0.96,0.96];
            app.Button_383.BackgroundColor=[0.96,0.96,0.96];
            app.Button_390.BackgroundColor=[0.96,0.96,0.96];
            app.Button_382.BackgroundColor=[0.96,0.96,0.96];
            app.Button_381.BackgroundColor=[0.96,0.96,0.96];
            app.Button_380.BackgroundColor=[0.96,0.96,0.96];
            app.Button_386.BackgroundColor=[1.00,1.00,0.00];
            app.Button_387.BackgroundColor=[0.96,0.96,0.96];
            app.Button_385.BackgroundColor=[0.96,0.96,0.96];
            app.Button_388.BackgroundColor=[0.96,0.96,0.96];
            app.Button_389.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_387
        function Button_387ValueChanged(app, event)
            A185=8;
            B185=cellstr('Cruce demarcado elevado, sin semáforo, con refugio');
            c185=cellstr('app.Button_387');
            app.Matriz2(39)=c185;
            app.Matriz1(39)=A185;
            app.Matriz3(39)=B185;
            
            value = app.Button_387.Value;
            
            app.Button_384.BackgroundColor=[0.96,0.96,0.96];
            app.Button_383.BackgroundColor=[0.96,0.96,0.96];
            app.Button_390.BackgroundColor=[0.96,0.96,0.96];
            app.Button_382.BackgroundColor=[0.96,0.96,0.96];
            app.Button_381.BackgroundColor=[0.96,0.96,0.96];
            app.Button_380.BackgroundColor=[0.96,0.96,0.96];
            app.Button_386.BackgroundColor=[0.96,0.96,0.96];
            app.Button_387.BackgroundColor=[1.00,1.00,0.00];
            app.Button_385.BackgroundColor=[0.96,0.96,0.96];
            app.Button_388.BackgroundColor=[0.96,0.96,0.96];
            app.Button_389.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_388
        function Button_388ValueChanged(app, event)
            A187=10;
            B187=cellstr('Cruce no demarcado elevado, con refugio');
            c187=cellstr('app.Button_388');
            app.Matriz2(39)=c187;
            app.Matriz1(39)=A187;
            app.Matriz3(39)=B187;
            
            value = app.Button_388.Value;
            
            
            app.Button_384.BackgroundColor=[0.96,0.96,0.96];
            app.Button_383.BackgroundColor=[0.96,0.96,0.96];
            app.Button_390.BackgroundColor=[0.96,0.96,0.96];
            app.Button_382.BackgroundColor=[0.96,0.96,0.96];
            app.Button_381.BackgroundColor=[0.96,0.96,0.96];
            app.Button_380.BackgroundColor=[0.96,0.96,0.96];
            app.Button_386.BackgroundColor=[0.96,0.96,0.96];
            app.Button_387.BackgroundColor=[0.96,0.96,0.96];
            app.Button_385.BackgroundColor=[0.96,0.96,0.96];
            app.Button_388.BackgroundColor=[1.00,1.00,0.00];
            app.Button_389.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_389
        function Button_389ValueChanged(app, event)
            A188=11;
            B188=cellstr('Cruce no demarcado elevado, sin refugio');
            c188=cellstr('app.Button_389');
            app.Matriz2(39)=c188;
            app.Matriz1(39)=A188;
            app.Matriz3(39)=B188;
            
            value = app.Button_389.Value;
            
            app.Button_384.BackgroundColor=[0.96,0.96,0.96];
            app.Button_383.BackgroundColor=[0.96,0.96,0.96];
            app.Button_390.BackgroundColor=[0.96,0.96,0.96];
            app.Button_382.BackgroundColor=[0.96,0.96,0.96];
            app.Button_381.BackgroundColor=[0.96,0.96,0.96];
            app.Button_380.BackgroundColor=[0.96,0.96,0.96];
            app.Button_386.BackgroundColor=[0.96,0.96,0.96];
            app.Button_387.BackgroundColor=[0.96,0.96,0.96];
            app.Button_385.BackgroundColor=[0.96,0.96,0.96];
            app.Button_388.BackgroundColor=[0.96,0.96,0.96];
            app.Button_389.BackgroundColor=[1.00,1.00,0.00];

        end

        % Value changed function: Button_329
        function Button_329ValueChanged(app, event)
            A190=2;
            B190=cellstr('Pobre');
            c190=cellstr('app.Button_329');
            app.Matriz2(40)=c190;
            app.Matriz1(40)=A190;
            app.Matriz3(40)=B190;
            
            value = app.Button_329.Value;
            
            app.Button_357.BackgroundColor=[0.96,0.96,0.96];
            app.Button_329.BackgroundColor=[1.00,1.00,0.00];
            app.Button_356.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_356
        function Button_356ValueChanged(app, event)
            A191=3;
            B191=cellstr('No aplica');
            c191=cellstr('app.Button_356');
            app.Matriz2(40)=c191;
            app.Matriz1(40)=A191;
            app.Matriz3(40)=B191;
            
            value = app.Button_356.Value;
            
            app.Button_357.BackgroundColor=[0.96,0.96,0.96];
            app.Button_329.BackgroundColor=[0.96,0.96,0.96];
            app.Button_356.BackgroundColor=[1.00,1.00,0.00];
        end

        % Value changed function: Button_357
        function Button_357ValueChanged(app, event)
            A189=1;
            B189=cellstr('Adecuado');
            c189=cellstr('app.Button_357');
            app.Matriz2(40)=c189;
            app.Matriz1(40)=A189;
            app.Matriz3(40)=B189;
            
            value = app.Button_357.Value;
            
            app.Button_357.BackgroundColor=[1.00,1.00,0.00];
            app.Button_329.BackgroundColor=[0.96,0.96,0.96];
            app.Button_356.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_687
        function Button_687ValueChanged(app, event)
            A197=6;
            B197=cellstr('Solo refugio');
            c197=cellstr('app.Button_687');
            app.Matriz2(41)=c197;
            app.Matriz1(41)=A197;
            app.Matriz3(41)=B197;
            
            value = app.Button_687.Value;
            
            app.Button_691.BackgroundColor=[0.96,0.96,0.96];
            app.Button_690.BackgroundColor=[0.96,0.96,0.96];
            app.Button_697.BackgroundColor=[0.96,0.96,0.96];
            app.Button_689.BackgroundColor=[0.96,0.96,0.96];
            app.Button_688.BackgroundColor=[0.96,0.96,0.96];
            app.Button_687.BackgroundColor=[1.00,1.00,0.00];
            app.Button_693.BackgroundColor=[0.96,0.96,0.96];
            app.Button_694.BackgroundColor=[0.96,0.96,0.96];
            app.Button_692.BackgroundColor=[0.96,0.96,0.96];
            app.Button_695.BackgroundColor=[0.96,0.96,0.96];
            app.Button_696.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_688
        function Button_688ValueChanged(app, event)
            A196=5;
            B196=cellstr('No semaforizada, señalizada con pintura y sin refugio');
            c196=cellstr('app.Button_688');
            app.Matriz2(41)=c196;
            app.Matriz1(41)=A196;
            app.Matriz3(41)=B196;
            
            value = app.Button_688.Value;
            
            app.Button_691.BackgroundColor=[0.96,0.96,0.96];
            app.Button_690.BackgroundColor=[0.96,0.96,0.96];
            app.Button_697.BackgroundColor=[0.96,0.96,0.96];
            app.Button_689.BackgroundColor=[0.96,0.96,0.96];
            app.Button_688.BackgroundColor=[1.00,1.00,0.00];
            app.Button_687.BackgroundColor=[0.96,0.96,0.96];
            app.Button_693.BackgroundColor=[0.96,0.96,0.96];
            app.Button_694.BackgroundColor=[0.96,0.96,0.96];
            app.Button_692.BackgroundColor=[0.96,0.96,0.96];
            app.Button_695.BackgroundColor=[0.96,0.96,0.96];
            app.Button_696.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_689
        function Button_689ValueChanged(app, event)
            A195=4;
            B195=cellstr('No semaforizada, señalizada con pintura y con refugio');
            c195=cellstr('app.Button_689');
            app.Matriz2(41)=c195;
            app.Matriz1(41)=A195;
            app.Matriz3(41)=B195;
            
            value = app.Button_689.Value;
            
            app.Button_691.BackgroundColor=[0.96,0.96,0.96];
            app.Button_690.BackgroundColor=[0.96,0.96,0.96];
            app.Button_697.BackgroundColor=[0.96,0.96,0.96];
            app.Button_689.BackgroundColor=[1.00,1.00,0.00];
            app.Button_688.BackgroundColor=[0.96,0.96,0.96];
            app.Button_687.BackgroundColor=[0.96,0.96,0.96];
            app.Button_693.BackgroundColor=[0.96,0.96,0.96];
            app.Button_694.BackgroundColor=[0.96,0.96,0.96];
            app.Button_692.BackgroundColor=[0.96,0.96,0.96];
            app.Button_695.BackgroundColor=[0.96,0.96,0.96];
            app.Button_696.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_690
        function Button_690ValueChanged(app, event)
            A193=2;
            B193=cellstr('Semaforizada con refugio');
            c193=cellstr('app.Button_690');
            app.Matriz2(41)=c193;
            app.Matriz1(41)=A193;
            app.Matriz3(41)=B193;
            
            value = app.Button_690.Value;
            app.Button_691.BackgroundColor=[0.96,0.96,0.96];
            app.Button_690.BackgroundColor=[1.00,1.00,0.00];
            app.Button_697.BackgroundColor=[0.96,0.96,0.96];
            app.Button_689.BackgroundColor=[0.96,0.96,0.96];
            app.Button_688.BackgroundColor=[0.96,0.96,0.96];
            app.Button_687.BackgroundColor=[0.96,0.96,0.96];
            app.Button_693.BackgroundColor=[0.96,0.96,0.96];
            app.Button_694.BackgroundColor=[0.96,0.96,0.96];
            app.Button_692.BackgroundColor=[0.96,0.96,0.96];
            app.Button_695.BackgroundColor=[0.96,0.96,0.96];
            app.Button_696.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_691
        function Button_691ValueChanged(app, event)
            A192=1;
            B192=cellstr('Infraestructura a desnivel');
            c192=cellstr('app.Button_691');
            app.Matriz2(41)=c192;
            app.Matriz1(41)=A192;
            app.Matriz3(41)=B192;
            
            value = app.Button_691.Value;
            
            app.Button_691.BackgroundColor=[1.00,1.00,0.00];
            app.Button_690.BackgroundColor=[0.96,0.96,0.96];
            app.Button_697.BackgroundColor=[0.96,0.96,0.96];
            app.Button_689.BackgroundColor=[0.96,0.96,0.96];
            app.Button_688.BackgroundColor=[0.96,0.96,0.96];
            app.Button_687.BackgroundColor=[0.96,0.96,0.96];
            app.Button_693.BackgroundColor=[0.96,0.96,0.96];
            app.Button_694.BackgroundColor=[0.96,0.96,0.96];
            app.Button_692.BackgroundColor=[0.96,0.96,0.96];
            app.Button_695.BackgroundColor=[0.96,0.96,0.96];
            app.Button_696.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_697
        function Button_697ValueChanged(app, event)
            A194=3;
            B194=cellstr('Semaforizada sin refugio');
            c194=cellstr('app.Button_697');
            app.Matriz2(41)=c194;
            app.Matriz1(41)=A194;
            app.Matriz3(41)=B194;
            
            value = app.Button_697.Value;
            app.Button_691.BackgroundColor=[0.96,0.96,0.96];
            app.Button_690.BackgroundColor=[0.96,0.96,0.96];
            app.Button_697.BackgroundColor=[1.00,1.00,0.00];
            app.Button_689.BackgroundColor=[0.96,0.96,0.96];
            app.Button_688.BackgroundColor=[0.96,0.96,0.96];
            app.Button_687.BackgroundColor=[0.96,0.96,0.96];
            app.Button_693.BackgroundColor=[0.96,0.96,0.96];
            app.Button_694.BackgroundColor=[0.96,0.96,0.96];
            app.Button_692.BackgroundColor=[0.96,0.96,0.96];
            app.Button_695.BackgroundColor=[0.96,0.96,0.96];
            app.Button_696.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_692
        function Button_692ValueChanged(app, event)
            A200=9;
            B200=cellstr('Cruce demarcado elevado, sin semáforo, sin refugio');
            c200=cellstr('app.Button_692');
            app.Matriz2(41)=c200;
            app.Matriz1(41)=A200;
            app.Matriz3(41)=B200;
            
            value = app.Button_692.Value;
            
            app.Button_691.BackgroundColor=[0.96,0.96,0.96];
            app.Button_690.BackgroundColor=[0.96,0.96,0.96];
            app.Button_697.BackgroundColor=[0.96,0.96,0.96];
            app.Button_689.BackgroundColor=[0.96,0.96,0.96];
            app.Button_688.BackgroundColor=[0.96,0.96,0.96];
            app.Button_687.BackgroundColor=[0.96,0.96,0.96];
            app.Button_693.BackgroundColor=[0.96,0.96,0.96];
            app.Button_694.BackgroundColor=[0.96,0.96,0.96];
            app.Button_692.BackgroundColor=[1.00,1.00,0.00];
            app.Button_695.BackgroundColor=[0.96,0.96,0.96];
            app.Button_696.BackgroundColor=[0.96,0.96,0.96];

        end

        % Value changed function: Button_693
        function Button_693ValueChanged(app, event)
            A198=7;
            B198=cellstr('Sin infraestructura');
            c198=cellstr('app.Button_693');
            app.Matriz2(41)=c198;
            app.Matriz1(41)=A198;
            app.Matriz3(41)=B198;
            
            value = app.Button_693.Value;
            
            app.Button_691.BackgroundColor=[0.96,0.96,0.96];
            app.Button_690.BackgroundColor=[0.96,0.96,0.96];
            app.Button_697.BackgroundColor=[0.96,0.96,0.96];
            app.Button_689.BackgroundColor=[0.96,0.96,0.96];
            app.Button_688.BackgroundColor=[0.96,0.96,0.96];
            app.Button_687.BackgroundColor=[0.96,0.96,0.96];
            app.Button_693.BackgroundColor=[1.00,1.00,0.00];
            app.Button_694.BackgroundColor=[0.96,0.96,0.96];
            app.Button_692.BackgroundColor=[0.96,0.96,0.96];
            app.Button_695.BackgroundColor=[0.96,0.96,0.96];
            app.Button_696.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_694
        function Button_694ValueChanged(app, event)
            A199=8;
            B199=cellstr('Cruce demarcado elevado, sin semáforo, con refugio');
            c199=cellstr('app.Button_694');
            app.Matriz2(41)=c199;
            app.Matriz1(41)=A199;
            app.Matriz3(41)=B199;
            
            value = app.Button_694.Value;
            
            app.Button_691.BackgroundColor=[0.96,0.96,0.96];
            app.Button_690.BackgroundColor=[0.96,0.96,0.96];
            app.Button_697.BackgroundColor=[0.96,0.96,0.96];
            app.Button_689.BackgroundColor=[0.96,0.96,0.96];
            app.Button_688.BackgroundColor=[0.96,0.96,0.96];
            app.Button_687.BackgroundColor=[0.96,0.96,0.96];
            app.Button_693.BackgroundColor=[0.96,0.96,0.96];
            app.Button_694.BackgroundColor=[1.00,1.00,0.00];
            app.Button_692.BackgroundColor=[0.96,0.96,0.96];
            app.Button_695.BackgroundColor=[0.96,0.96,0.96];
            app.Button_696.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_695
        function Button_695ValueChanged(app, event)
            A201=10;
            B201=cellstr('Cruce no demarcado elevado, con refugio');
            c201=cellstr('app.Button_695');
            app.Matriz2(41)=c201;
            app.Matriz1(41)=A201;
            app.Matriz3(41)=B201;
            
            value = app.Button_695.Value;
            
            app.Button_691.BackgroundColor=[0.96,0.96,0.96];
            app.Button_690.BackgroundColor=[0.96,0.96,0.96];
            app.Button_697.BackgroundColor=[0.96,0.96,0.96];
            app.Button_689.BackgroundColor=[0.96,0.96,0.96];
            app.Button_688.BackgroundColor=[0.96,0.96,0.96];
            app.Button_687.BackgroundColor=[0.96,0.96,0.96];
            app.Button_693.BackgroundColor=[0.96,0.96,0.96];
            app.Button_694.BackgroundColor=[0.96,0.96,0.96];
            app.Button_692.BackgroundColor=[0.96,0.96,0.96];
            app.Button_695.BackgroundColor=[1.00,1.00,0.00];
            app.Button_696.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_696
        function Button_696ValueChanged(app, event)
            A202=11;
            B202=cellstr('Cruce no demarcado elevado, sin refugio');
            c202=cellstr('app.Button_696');
            app.Matriz2(41)=c202;
            app.Matriz1(41)=A202;
            app.Matriz3(41)=B202;
            
            value = app.Button_696.Value;
            
            app.Button_691.BackgroundColor=[0.96,0.96,0.96];
            app.Button_690.BackgroundColor=[0.96,0.96,0.96];
            app.Button_697.BackgroundColor=[0.96,0.96,0.96];
            app.Button_689.BackgroundColor=[0.96,0.96,0.96];
            app.Button_688.BackgroundColor=[0.96,0.96,0.96];
            app.Button_687.BackgroundColor=[0.96,0.96,0.96];
            app.Button_693.BackgroundColor=[0.96,0.96,0.96];
            app.Button_694.BackgroundColor=[0.96,0.96,0.96];
            app.Button_692.BackgroundColor=[0.96,0.96,0.96];
            app.Button_695.BackgroundColor=[0.96,0.96,0.96];
            app.Button_696.BackgroundColor=[1.00,1.00,0.00];
        end

        % Value changed function: Button_332
        function Button_332ValueChanged(app, event)
            A204=1;
            B204=cellstr('Presente');
            c204=cellstr('app.Button_332');
            app.Matriz2(42)=c204;
            app.Matriz1(42)=A204;
            app.Matriz3(42)=B204;
            
            value = app.Button_332.Value;
            
            app.Button_332.BackgroundColor=[1.00,1.00,0.00];
            app.Button_333.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_333
        function Button_333ValueChanged(app, event)
            value = app.Button_333.Value;
            A205=2;
            B205=cellstr('Ausente');
            c205=cellstr('app.Button_333');
            app.Matriz2(42)=c205;
            app.Matriz1(42)=A205;
            app.Matriz3(42)=B205;

            app.Button_332.BackgroundColor=[0.96,0.96,0.96];
            app.Button_333.BackgroundColor=[1.00,1.00,0.00];
            
        end

        % Value changed function: Button_335
        function Button_335ValueChanged(app, event)
            value = app.Button_335.Value;
            A210=5;
            B210=cellstr('Ninguno');
            c210=cellstr('app.Button_335');
            app.Matriz2(43)=c210; 
            app.Matriz1(43)=A210;
            app.Matriz3(43)=B210;
            
            app.Button_403.BackgroundColor=[0.96,0.96,0.96];
            app.Button_402.BackgroundColor=[0.96,0.96,0.96];
            app.Button_337.BackgroundColor=[0.96,0.96,0.96];
            app.Button_336.BackgroundColor=[0.96,0.96,0.96];
            app.Button_335.BackgroundColor=[1.00,1.00,0.00];
            app.Button_404.BackgroundColor=[0.96,0.96,0.96];
            app.Button_405.BackgroundColor=[0.96,0.96,0.96];

        end

        % Value changed function: Button_336
        function Button_336ValueChanged(app, event)
            A209=4;
            B209=cellstr('Separación no física 0 m < 1,0 m');
            c209=cellstr('app.Button_336');
            app.Matriz2(43)=c209; 
            app.Matriz1(43)=A209;
            app.Matriz3(43)=B209;
            
            value = app.Button_336.Value;
            
            app.Button_403.BackgroundColor=[0.96,0.96,0.96];
            app.Button_402.BackgroundColor=[0.96,0.96,0.96];
            app.Button_337.BackgroundColor=[0.96,0.96,0.96];
            app.Button_336.BackgroundColor=[1.00,1.00,0.00];
            app.Button_335.BackgroundColor=[0.96,0.96,0.96];
            app.Button_404.BackgroundColor=[0.96,0.96,0.96];
            app.Button_405.BackgroundColor=[0.96,0.96,0.96];

        end

        % Value changed function: Button_337
        function Button_337ValueChanged(app, event)
            A208=3;
            B208=cellstr('Separación no física 1,0 m a 3,0 m');
            c208=cellstr('app.Button_337');
            app.Matriz2(43)=c208;
            app.Matriz1(43)=A208;
            app.Matriz3(43)=B208;
            
            value = app.Button_337.Value;
            
            app.Button_403.BackgroundColor=[0.96,0.96,0.96];
            app.Button_402.BackgroundColor=[0.96,0.96,0.96];
            app.Button_337.BackgroundColor=[1.00,1.00,0.00];
            app.Button_336.BackgroundColor=[0.96,0.96,0.96];
            app.Button_335.BackgroundColor=[0.96,0.96,0.96];
            app.Button_404.BackgroundColor=[0.96,0.96,0.96];
            app.Button_405.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_402
        function Button_402ValueChanged(app, event)
            A207=2;
            B207=cellstr('Separación no física ≥ 3,0 m');
            c207=cellstr('app.Button_402');
            app.Matriz2(43)=c207;
            app.Matriz1(43)=A207;
            app.Matriz3(43)=B207;
            
            value = app.Button_402.Value;
            
            app.Button_403.BackgroundColor=[0.96,0.96,0.96];
            app.Button_402.BackgroundColor=[1.00,1.00,0.00];
            app.Button_337.BackgroundColor=[0.96,0.96,0.96];
            app.Button_336.BackgroundColor=[0.96,0.96,0.96];
            app.Button_335.BackgroundColor=[0.96,0.96,0.96];
            app.Button_404.BackgroundColor=[0.96,0.96,0.96];
            app.Button_405.BackgroundColor=[0.96,0.96,0.96];
            
        end

        % Value changed function: Button_403
        function Button_403ValueChanged(app, event)
            A206=1;
            B206=cellstr('Barrera física');
            c206=cellstr('app.Button_403');
            app.Matriz2(43)=c206;
            app.Matriz1(43)=A206;
            app.Matriz3(43)=B206;
            
            value = app.Button_403.Value;
            
            app.Button_403.BackgroundColor=[1.00,1.00,0.00];
            app.Button_402.BackgroundColor=[0.96,0.96,0.96];
            app.Button_337.BackgroundColor=[0.96,0.96,0.96];
            app.Button_336.BackgroundColor=[0.96,0.96,0.96];
            app.Button_335.BackgroundColor=[0.96,0.96,0.96];
            app.Button_404.BackgroundColor=[0.96,0.96,0.96];
            app.Button_405.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_405
        function Button_405ValueChanged(app, event)
            A212=7;
            B212=cellstr('Camino informal de 0 m a < 1,0 m');
            c212=cellstr('app.Button_405');
            app.Matriz2(43)=c212;
            app.Matriz1(43)=A212;
            app.Matriz3(43)=B212;
            
            value = app.Button_405.Value;
            app.Button_403.BackgroundColor=[0.96,0.96,0.96];
            app.Button_402.BackgroundColor=[0.96,0.96,0.96];
            app.Button_337.BackgroundColor=[0.96,0.96,0.96];
            app.Button_336.BackgroundColor=[0.96,0.96,0.96];
            app.Button_335.BackgroundColor=[0.96,0.96,0.96];
            app.Button_404.BackgroundColor=[0.96,0.96,0.96];
            app.Button_405.BackgroundColor=[1.00,1.00,0.00];
        end

        % Value changed function: Button_404
        function Button_404ValueChanged(app, event)
            A211=6;
            B211=cellstr('Camino informal ≥ 1,0 m');
            c211=cellstr('app.Button_404');
            app.Matriz2(43)=c211;
            app.Matriz1(43)=A211;
            app.Matriz3(43)=B211;
            
            value = app.Button_404.Value;
            
            app.Button_403.BackgroundColor=[0.96,0.96,0.96];
            app.Button_402.BackgroundColor=[0.96,0.96,0.96];
            app.Button_337.BackgroundColor=[0.96,0.96,0.96];
            app.Button_336.BackgroundColor=[0.96,0.96,0.96];
            app.Button_335.BackgroundColor=[0.96,0.96,0.96];
            app.Button_404.BackgroundColor=[1.00,1.00,0.00];
            app.Button_405.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_698
        function Button_698ValueChanged(app, event)
            A217=5;
            B217=cellstr('Ninguno');
            c217=cellstr('app.Button_698');
            app.Matriz2(44)=c217;
            app.Matriz1(44)=A217;
            app.Matriz3(44)=B217;
            
            value = app.Button_698.Value;
            
            app.Button_702.BackgroundColor=[0.96,0.96,0.96];
            app.Button_701.BackgroundColor=[0.96,0.96,0.96];
            app.Button_700.BackgroundColor=[0.96,0.96,0.96];
            app.Button_699.BackgroundColor=[0.96,0.96,0.96];
            app.Button_698.BackgroundColor=[1.00,1.00,0.00];
            app.Button_703.BackgroundColor=[0.96,0.96,0.96];
            app.Button_704.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_699
        function Button_699ValueChanged(app, event)
            A216=4;
            B216=cellstr('Separación no física 0 m < 1,0 m');
            c216=cellstr('app.Button_699');
            app.Matriz2(44)=c216;
            app.Matriz1(44)=A216;
            app.Matriz3(44)=B216;
            
            value = app.Button_699.Value;
            
            app.Button_702.BackgroundColor=[0.96,0.96,0.96];
            app.Button_701.BackgroundColor=[0.96,0.96,0.96];
            app.Button_700.BackgroundColor=[0.96,0.96,0.96];
            app.Button_699.BackgroundColor=[1.00,1.00,0.00];
            app.Button_698.BackgroundColor=[0.96,0.96,0.96];
            app.Button_703.BackgroundColor=[0.96,0.96,0.96];
            app.Button_704.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_700
        function Button_700ValueChanged(app, event)
            A215=3;
            B215=cellstr('Separación no física 1,0 m a 3,0 m');
            c215=cellstr('app.Button_700');
            app.Matriz2(44)=c215;
            app.Matriz1(44)=A215;
            app.Matriz3(44)=B215;
            
            value = app.Button_700.Value;
            
            app.Button_702.BackgroundColor=[0.96,0.96,0.96];
            app.Button_701.BackgroundColor=[0.96,0.96,0.96];
            app.Button_700.BackgroundColor=[1.00,1.00,0.00];
            app.Button_699.BackgroundColor=[0.96,0.96,0.96];
            app.Button_698.BackgroundColor=[0.96,0.96,0.96];
            app.Button_703.BackgroundColor=[0.96,0.96,0.96];
            app.Button_704.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_701
        function Button_701ValueChanged(app, event)
            A214=2;
            B214=cellstr('Separación no física ≥ 3,0 m');
            c214=cellstr('app.Button_701');
            app.Matriz2(44)=c214;
            app.Matriz1(44)=A214;
            app.Matriz3(44)=B214;
            
            value = app.Button_701.Value;
            
            app.Button_702.BackgroundColor=[0.96,0.96,0.96];
            app.Button_701.BackgroundColor=[1.00,1.00,0.00];
            app.Button_700.BackgroundColor=[0.96,0.96,0.96];
            app.Button_699.BackgroundColor=[0.96,0.96,0.96];
            app.Button_698.BackgroundColor=[0.96,0.96,0.96];
            app.Button_703.BackgroundColor=[0.96,0.96,0.96];
            app.Button_704.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_702
        function Button_702ValueChanged(app, event)
            A213=1;
            B213=cellstr('Barrera física');
            c213=cellstr('app.Button_405');
            app.Matriz2(44)=c213;
            app.Matriz1(44)=A213;
            app.Matriz3(44)=B213;
            
            value = app.Button_702.Value;
            
            app.Button_702.BackgroundColor=[1.00,1.00,0.00];
            app.Button_701.BackgroundColor=[0.96,0.96,0.96];
            app.Button_700.BackgroundColor=[0.96,0.96,0.96];
            app.Button_699.BackgroundColor=[0.96,0.96,0.96];
            app.Button_698.BackgroundColor=[0.96,0.96,0.96];
            app.Button_703.BackgroundColor=[0.96,0.96,0.96];
            app.Button_704.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_704
        function Button_704ValueChanged(app, event)
            A219=7;
            B219=cellstr('Camino informal de 0 m a < 1,0 m');
            c219=cellstr('app.Button_704');
            app.Matriz2(44)=c219;
            app.Matriz1(44)=A219;
            app.Matriz3(44)=B219;
            
            value = app.Button_704.Value;
            
            app.Button_702.BackgroundColor=[0.96,0.96,0.96];
            app.Button_701.BackgroundColor=[0.96,0.96,0.96];
            app.Button_700.BackgroundColor=[0.96,0.96,0.96];
            app.Button_699.BackgroundColor=[0.96,0.96,0.96];
            app.Button_698.BackgroundColor=[0.96,0.96,0.96];
            app.Button_703.BackgroundColor=[0.96,0.96,0.96];
            app.Button_704.BackgroundColor=[1.00,1.00,0.00];
        end

        % Value changed function: Button_703
        function Button_703ValueChanged(app, event)
            
            value = app.Button_703.Value;
            A218=6;
            B218=cellstr('Camino informal de 0 m a < 1,0 m');
            c218=cellstr('app.Button_703');
            app.Matriz2(44)=c218;
            app.Matriz1(44)=A218;
            app.Matriz3(44)=B218;
            
            app.Button_702.BackgroundColor=[0.96,0.96,0.96];
            app.Button_701.BackgroundColor=[0.96,0.96,0.96];
            app.Button_700.BackgroundColor=[0.96,0.96,0.96];
            app.Button_699.BackgroundColor=[0.96,0.96,0.96];
            app.Button_698.BackgroundColor=[0.96,0.96,0.96];
            app.Button_703.BackgroundColor=[1.00,1.00,0.00];
            app.Button_704.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_413
        function Button_413ValueChanged(app, event)
            value = app.Button_413.Value;
            A225=6;
            B225=cellstr('Ninguno');
            c225=cellstr('app.Button_413');
            app.Matriz2(45)=c225;
            app.Matriz1(45)=A225;
            app.Matriz3(45)=B225;

            
            app.Button_416.BackgroundColor=[0.96,0.96,0.96];
            app.Button_415.BackgroundColor=[0.96,0.96,0.96];
            app.Button_418.BackgroundColor=[0.96,0.96,0.96];
            app.Button_417.BackgroundColor=[0.96,0.96,0.96];
            app.Button_414.BackgroundColor=[0.96,0.96,0.96];
            app.Button_413.BackgroundColor=[1.00,1.00,0.00];
        end

        % Value changed function: Button_414
        function Button_414ValueChanged(app, event)
            A224=5;
            B224=cellstr('Motovía compartida en la carretera');
            c224=cellstr('app.Button_414');
            app.Matriz2(45)=c224;
            app.Matriz1(45)=A224;
            app.Matriz3(45)=B224;
            
            value = app.Button_414.Value;
            
            app.Button_416.BackgroundColor=[0.96,0.96,0.96];
            app.Button_415.BackgroundColor=[0.96,0.96,0.96];
            app.Button_418.BackgroundColor=[0.96,0.96,0.96];
            app.Button_417.BackgroundColor=[0.96,0.96,0.96];
            app.Button_414.BackgroundColor=[1.00,1.00,0.00];
            app.Button_413.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_415
        function Button_415ValueChanged(app, event)
            A221=2;
            B221=cellstr('Motovía exclusiva de un sentido sin barrera');
            c221=cellstr('app.Button_415');
            app.Matriz2(45)=c221;
            app.Matriz1(45)=A221;
            app.Matriz3(45)=B221;
            
            value = app.Button_415.Value;
            
            app.Button_416.BackgroundColor=[0.96,0.96,0.96];
            app.Button_415.BackgroundColor=[1.00,1.00,0.00];
            app.Button_418.BackgroundColor=[0.96,0.96,0.96];
            app.Button_417.BackgroundColor=[0.96,0.96,0.96];
            app.Button_414.BackgroundColor=[0.96,0.96,0.96];
            app.Button_413.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_416
        function Button_416ValueChanged(app, event)
            A220=1;
            B220=cellstr('Motovía exclusiva de un sentido con barrera');
            c220=cellstr('app.Button_416');
            app.Matriz2(45)=c220;
            app.Matriz1(45)=A220;
            app.Matriz3(45)=B220;
            
            value = app.Button_416.Value;
            
            app.Button_416.BackgroundColor=[1.00,1.00,0.00];
            app.Button_415.BackgroundColor=[0.96,0.96,0.96];
            app.Button_418.BackgroundColor=[0.96,0.96,0.96];
            app.Button_417.BackgroundColor=[0.96,0.96,0.96];
            app.Button_414.BackgroundColor=[0.96,0.96,0.96];
            app.Button_413.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_417
        function Button_417ValueChanged(app, event)
            A223=4;
            B223=cellstr('Motovía exclusiva de dos sentidos sin barrera');
            c223=cellstr('app.Button_417');
            app.Matriz2(45)=c223;
            app.Matriz1(45)=A223;
            app.Matriz3(45)=B223;
            
            value = app.Button_417.Value;
            
            app.Button_416.BackgroundColor=[0.96,0.96,0.96];
            app.Button_415.BackgroundColor=[0.96,0.96,0.96];
            app.Button_418.BackgroundColor=[0.96,0.96,0.96];
            app.Button_417.BackgroundColor=[1.00,1.00,0.00];
            app.Button_414.BackgroundColor=[0.96,0.96,0.96];
            app.Button_413.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_418
        function Button_418ValueChanged(app, event)
            A222=3;
            B222=cellstr('Motovía exclusiva de dos sentidos con barrera');
            c222=cellstr('app.Button_418');
            app.Matriz2(45)=c222;
            app.Matriz1(45)=A222;
            app.Matriz3(45)=B222;
            
            value = app.Button_418.Value;
            
            app.Button_416.BackgroundColor=[0.96,0.96,0.96];
            app.Button_415.BackgroundColor=[0.96,0.96,0.96];
            app.Button_418.BackgroundColor=[1.00,1.00,0.00];
            app.Button_417.BackgroundColor=[0.96,0.96,0.96];
            app.Button_414.BackgroundColor=[0.96,0.96,0.96];
            app.Button_413.BackgroundColor=[0.96,0.96,0.96];

            
        end

        % Value changed function: Button_419
        function Button_419ValueChanged(app, event)
            value = app.Button_419.Value;
            A229=4;
            B229=cellstr('Ninguno');
            c229=cellstr('app.Button_419');
            app.Matriz2(46)=c229;
            app.Matriz1(46)=A229;
            app.Matriz3(46)=B229;

            
            app.Button_424.BackgroundColor=[0.96,0.96,0.96];
            app.Button_705.BackgroundColor=[0.96,0.96,0.96];
            app.Button_420.BackgroundColor=[0.96,0.96,0.96];
            app.Button_419.BackgroundColor=[1.00,1.00,0.00];
            app.Button_422.BackgroundColor=[0.96,0.96,0.96];
            app.Button_423.BackgroundColor=[0.96,0.96,0.96];
            app.Button_421.BackgroundColor=[0.96,0.96,0.96];

        end

        % Value changed function: Button_420
        function Button_420ValueChanged(app, event)
            A228=3;
            B228=cellstr('Ciclovía en la carretera');
            c228=cellstr('app.Button_420');
            app.Matriz2(46)=c228;
            app.Matriz1(46)=A228;
            app.Matriz3(46)=B228;
            
            value = app.Button_420.Value;
            
            app.Button_424.BackgroundColor=[0.96,0.96,0.96];
            app.Button_705.BackgroundColor=[0.96,0.96,0.96];
            app.Button_420.BackgroundColor=[1.00,1.00,0.00];
            app.Button_419.BackgroundColor=[0.96,0.96,0.96];
            app.Button_422.BackgroundColor=[0.96,0.96,0.96];
            app.Button_423.BackgroundColor=[0.96,0.96,0.96];
            app.Button_421.BackgroundColor=[0.96,0.96,0.96];
            
        end

        % Value changed function: Button_421
        function Button_421ValueChanged(app, event)
            A232=7;
            B232=cellstr('Ciclovía de uso compartido ');
            c232=cellstr('app.Button_421');
            app.Matriz2(46)=c232;
            app.Matriz1(46)=A232;
            app.Matriz3(46)=B232;
            
            value = app.Button_421.Value;
            
            app.Button_424.BackgroundColor=[0.96,0.96,0.96];
            app.Button_705.BackgroundColor=[0.96,0.96,0.96];
            app.Button_420.BackgroundColor=[0.96,0.96,0.96];
            app.Button_419.BackgroundColor=[0.96,0.96,0.96];
            app.Button_422.BackgroundColor=[0.96,0.96,0.96];
            app.Button_423.BackgroundColor=[0.96,0.96,0.96];
            app.Button_421.BackgroundColor=[1.00,1.00,0.00];
        end

        % Value changed function: Button_422
        function Button_422ValueChanged(app, event)
            A230=5;
            B230=cellstr('Carril extra fuera de la calzada (≥  4,2 m)');
            c230=cellstr('app.Button_422');
            app.Matriz2(46)=c230;
            app.Matriz1(46)=A230;
            app.Matriz3(46)=B230;
            
            value = app.Button_422.Value;
            
            app.Button_424.BackgroundColor=[0.96,0.96,0.96];
            app.Button_705.BackgroundColor=[0.96,0.96,0.96];
            app.Button_420.BackgroundColor=[0.96,0.96,0.96];
            app.Button_419.BackgroundColor=[0.96,0.96,0.96];
            app.Button_422.BackgroundColor=[1.00,1.00,0.00];
            app.Button_423.BackgroundColor=[0.96,0.96,0.96];
            app.Button_421.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_423
        function Button_423ValueChanged(app, event)
            A231=6;
            B231=cellstr('Vía compartida con señalización vertical');
            c231=cellstr('app.Button_423');
            app.Matriz2(46)=c231;
            app.Matriz1(46)=A231;
            app.Matriz3(46)=B231;
            
            value = app.Button_423.Value;
            
            app.Button_424.BackgroundColor=[0.96,0.96,0.96];
            app.Button_705.BackgroundColor=[0.96,0.96,0.96];
            app.Button_420.BackgroundColor=[0.96,0.96,0.96];
            app.Button_419.BackgroundColor=[0.96,0.96,0.96];
            app.Button_422.BackgroundColor=[0.96,0.96,0.96];
            app.Button_423.BackgroundColor=[1.00,1.00,0.00];
            app.Button_421.BackgroundColor=[0.96,0.96,0.96];

        end

        % Value changed function: Button_424
        function Button_424ValueChanged(app, event)
            A226=1;
            B226=cellstr('Ciclovía fuera de la carretera con barrera');
            c226=cellstr('app.Button_424');
            app.Matriz2(46)=c226;
            app.Matriz1(46)=A226;
            app.Matriz3(46)=B226;
            
            value = app.Button_424.Value;
            
            app.Button_424.BackgroundColor=[1.00,1.00,0.00];
            app.Button_705.BackgroundColor=[0.96,0.96,0.96];
            app.Button_420.BackgroundColor=[0.96,0.96,0.96];
            app.Button_419.BackgroundColor=[0.96,0.96,0.96];
            app.Button_422.BackgroundColor=[0.96,0.96,0.96];
            app.Button_423.BackgroundColor=[0.96,0.96,0.96];
            app.Button_421.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_705
        function Button_705ValueChanged(app, event)
            A227=2;
            B227=cellstr('Ciclovía fuera de la carretera sin barrera');
            c227=cellstr('app.Button_705');
            app.Matriz2(46)=c227;
            app.Matriz1(46)=A227;
            app.Matriz3(46)=B227;
            
            value = app.Button_705.Value;
            
            app.Button_424.BackgroundColor=[0.96,0.96,0.96];
            app.Button_705.BackgroundColor=[1.00,1.00,0.00];
            app.Button_420.BackgroundColor=[0.96,0.96,0.96];
            app.Button_419.BackgroundColor=[0.96,0.96,0.96];
            app.Button_422.BackgroundColor=[0.96,0.96,0.96];
            app.Button_423.BackgroundColor=[0.96,0.96,0.96];
            app.Button_421.BackgroundColor=[0.96,0.96,0.96];

        end

        % Value changed function: Button_427
        function Button_427ValueChanged(app, event)
            A233=1;
            B233=cellstr('Balizas intermitentes en zona escolar ');
            c233=cellstr('app.Button_427');
            app.Matriz2(47)=c233;
            app.Matriz1(47)=A233;
            app.Matriz3(47)=B233;
            
            value = app.Button_427.Value;
            
            app.Button_427.BackgroundColor=[1.00,1.00,0.00];
            app.Button_428.BackgroundColor=[0.96,0.96,0.96];
            app.Button_430.BackgroundColor=[0.96,0.96,0.96];
            app.Button_429.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_428
        function Button_428ValueChanged(app, event)
            A234=2;
            B234=cellstr('Señalización estática o demarcación horizontal en zona escolar ');
            c234=cellstr('app.Button_428');
            app.Matriz2(47)=c234;
            app.Matriz1(47)=A234;
            app.Matriz3(47)=B234;
            
            value = app.Button_428.Value;
            
            app.Button_427.BackgroundColor=[0.96,0.96,0.96];
            app.Button_428.BackgroundColor=[1.00,1.00,0.00];
            app.Button_430.BackgroundColor=[0.96,0.96,0.96];
            app.Button_429.BackgroundColor=[0.96,0.96,0.96];
            
        end

        % Value changed function: Button_429
        function Button_429ValueChanged(app, event)
            value = app.Button_429.Value;
            A236=4;
            B236=cellstr('No aplica (no hay escuela en el lugar) ');
            c236=cellstr('app.Button_429');
            app.Matriz2(47)=c236;
            app.Matriz1(47)=A236;
            app.Matriz3(47)=B236;
  
            app.Button_427.BackgroundColor=[0.96,0.96,0.96];
            app.Button_428.BackgroundColor=[0.96,0.96,0.96];
            app.Button_430.BackgroundColor=[0.96,0.96,0.96];
            app.Button_429.BackgroundColor=[1.00,1.00,0.00];
            
        end

        % Value changed function: Button_430
        function Button_430ValueChanged(app, event)
            A235=3;
            B235=cellstr('Sin señal de advertencia de zona escolar ');
            c235=cellstr('app.Button_430');
            app.Matriz2(47)=c235;
            app.Matriz1(47)=A235;
            app.Matriz3(47)=B235;
            
            value = app.Button_430.Value;
            
            app.Button_427.BackgroundColor=[0.96,0.96,0.96];
            app.Button_428.BackgroundColor=[0.96,0.96,0.96];
            app.Button_430.BackgroundColor=[1.00,1.00,0.00];
            app.Button_429.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_431
        function Button_431ValueChanged(app, event)
            A238=2;
            B238=cellstr('Supervisor de cruce en zona escolar no presente');
            c238=cellstr('app.Button_431');
            app.Matriz2(48)=c238;
            app.Matriz1(48)=A238;
            app.Matriz3(48)=B238;
            
            value = app.Button_431.Value;
            
            app.Button_432.BackgroundColor=[0.96,0.96,0.96];
            app.Button_431.BackgroundColor=[1.00,1.00,0.00];
            app.Button_433.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_432
        function Button_432ValueChanged(app, event)
            A237=1;
            B237=cellstr('Supervisor de cruce en zona escolar presente en horario de entrada y salida de la escuela');
            c237=cellstr('app.Button_432');
            app.Matriz2(48)=c237;
            app.Matriz1(48)=A237;
            app.Matriz3(48)=B237;
            
            value = app.Button_432.Value;
            
            app.Button_432.BackgroundColor=[1.00,1.00,0.00];
            app.Button_431.BackgroundColor=[0.96,0.96,0.96];
            app.Button_433.BackgroundColor=[0.96,0.96,0.96];
            
        end

        % Value changed function: Button_433
        function Button_433ValueChanged(app, event)
            value = app.Button_433.Value;
            A239=3;
            B239=cellstr('No aplica (no hay escuela en el lugar)');
            c239=cellstr('app.Button_433');
            app.Matriz2(48)=c239;
            app.Matriz1(48)=A239;
            app.Matriz3(48)=B239;

            app.Button_432.BackgroundColor=[0.96,0.96,0.96];
            app.Button_431.BackgroundColor=[0.96,0.96,0.96];
            app.Button_433.BackgroundColor=[1.00,1.00,0.00];
        end

        % Value changed function: Button_491
        function Button_491ValueChanged(app, event)
            A59=1;
            B59=cellstr('Bajo');
            c59=cellstr('app.Button_491');
            app.Matriz2(9)=c59;
            app.Matriz1(9)=A59;
            app.Matriz3(9)=B59;
            
            value = app.Button_491.Value;
            app.Button_491.BackgroundColor=[1.00,1.00,0.00];
            app.Button_492.BackgroundColor=[0.96,0.96,0.96];
            app.Button_493.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_492
        function Button_492ValueChanged(app, event)
            A60=2;
            B60=cellstr('Medio');
            c60=cellstr('app.Button_492');
            app.Matriz2(9)=c60;
            app.Matriz1(9)=A60;
            app.Matriz3(9)=B60;
            
            value = app.Button_492.Value;
            app.Button_491.BackgroundColor=[0.96,0.96,0.96];
            app.Button_492.BackgroundColor=[1.00,1.00,0.00];
            app.Button_493.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_493
        function Button_493ValueChanged(app, event)
            A61=3;
            B61=cellstr('Alto');
            c61=cellstr('app.Button_493');
            app.Matriz2(9)=c61;
            app.Matriz1(9)=A61;
            app.Matriz3(9)=B61;
            
            value = app.Button_493.Value;
            app.Button_491.BackgroundColor=[0.96,0.96,0.96];
            app.Button_492.BackgroundColor=[0.96,0.96,0.96];
            app.Button_493.BackgroundColor=[1.00,1.00,0.00];
        end

        % Value changed function: Button_654
        function Button_654ValueChanged(app, event)
            A291=145;
            B291=cellstr('145 km/h');
            c291=cellstr('app.Button_654');
            app.Matriz2(52)=c291;
            app.Matriz1(52)=A291;
            app.Matriz3(52)=B291;
            
            value = app.Button_654.Value;
            app.Button_631.BackgroundColor=[0.96,0.96,0.96];
            app.Button_632.BackgroundColor=[0.96,0.96,0.96];
            app.Button_633.BackgroundColor=[0.96,0.96,0.96];
            app.Button_634.BackgroundColor=[0.96,0.96,0.96];
            app.Button_635.BackgroundColor=[0.96,0.96,0.96];
            app.Button_636.BackgroundColor=[0.96,0.96,0.96];
            app.Button_637.BackgroundColor=[0.96,0.96,0.96];
            app.Button_638.BackgroundColor=[0.96,0.96,0.96];
            app.Button_639.BackgroundColor=[0.96,0.96,0.96];
            app.Button_640.BackgroundColor=[0.96,0.96,0.96];
            app.Button_641.BackgroundColor=[0.96,0.96,0.96];
            app.Button_642.BackgroundColor=[0.96,0.96,0.96];
            app.Button_643.BackgroundColor=[0.96,0.96,0.96];
            app.Button_644.BackgroundColor=[0.96,0.96,0.96];
            app.Button_645.BackgroundColor=[0.96,0.96,0.96];
            app.Button_646.BackgroundColor=[0.96,0.96,0.96];
            app.Button_647.BackgroundColor=[0.96,0.96,0.96];
            app.Button_648.BackgroundColor=[0.96,0.96,0.96];
            app.Button_649.BackgroundColor=[0.96,0.96,0.96];
            app.Button_650.BackgroundColor=[0.96,0.96,0.96];
            app.Button_651.BackgroundColor=[0.96,0.96,0.96];
            app.Button_652.BackgroundColor=[0.96,0.96,0.96];
            app.Button_653.BackgroundColor=[0.96,0.96,0.96];
            app.Button_654.BackgroundColor=[1.00,1.00,0.00];
            app.Button_655.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: FlujovehicularTDPAEditField
        function FlujovehicularTDPAEditFieldValueChanged(app, event)
            value = app.FlujovehicularTDPAEditField.Value;
            app.Matriz1(30)=value;
            %Dato=cell(value);
            
            a_2=cellstr('app.FlujovehicularTDPAEditField');
            app.Matriz2(30)=a_2;            
            
            if value>=0 & value <8000
                Dato=cellstr('0 - 8000');
            else if value>=8000 & value<16000
               Dato=cellstr('8000 - 16000');
            else if value>=16000&value<24000
                Dato=cellstr('16000 - 24000');
            else if value>=24000&value<32000
                Dato=cellstr('24000 - 32000');
            else if value>=32000 & value<40000
                Dato=cellstr('32000 - 40000');
            else if value>=40000&value<48000
                Dato=cellstr('40000 - 48000');
            else if value>=48000&value<56000
                Dato=cellstr('48000 - 56000');
            else if value>=56000&value<64000
                Dato=cellstr('56000 - 64000');
            else if value>=64000
                Dato=cellstr('64000+');
            end 
            end
            end
            end
            end
            end
            end
            end
            end
            
            app.Matriz3(30)=Dato;
        end

        % Value changed function: Button_270
        function Button_270ValueChanged(app, event)
            value = app.Button_270.Value;
            A156_1=2;
            B156_1=cellstr('0 %');
            c156_1=cellstr('app.Button_270');
            app.Matriz2(31)=c156_1;
            app.Matriz1(31)=A156_1;
            app.Matriz3(31)=B156_1;
            
            app.NAButton.BackgroundColor=[0.96,0.96,0.96];
            app.Button_270.BackgroundColor=[1.00,1.00,0.00];
            app.Button_271.BackgroundColor=[0.96,0.96,0.96];
            app.Button_263.BackgroundColor=[0.96,0.96,0.96];
            app.Button_264.BackgroundColor=[0.96,0.96,0.96];
            app.Button_261.BackgroundColor=[0.96,0.96,0.96];
            app.Button_262.BackgroundColor=[0.96,0.96,0.96];
            app.Button_275.BackgroundColor=[0.96,0.96,0.96];
            app.Button_276.BackgroundColor=[0.96,0.96,0.96];
            app.Button_712.BackgroundColor=[0.96,0.96,0.96];
            
            
        end

        % Value changed function: Button_271
        function Button_271ValueChanged(app, event)
            value = app.Button_271.Value;
            A156_2=3;
            B156_2=cellstr('1 % - 5 %');
            c156_2=cellstr('app.Button_271');
            app.Matriz2(31)=c156_2;
            app.Matriz1(31)=A156_2;
            app.Matriz3(31)=B156_2;
            
            app.NAButton.BackgroundColor=[0.96,0.96,0.96];
            app.Button_270.BackgroundColor=[0.96,0.96,0.96];
            app.Button_271.BackgroundColor=[1.00,1.00,0.00];
            app.Button_263.BackgroundColor=[0.96,0.96,0.96];
            app.Button_264.BackgroundColor=[0.96,0.96,0.96];
            app.Button_261.BackgroundColor=[0.96,0.96,0.96];
            app.Button_262.BackgroundColor=[0.96,0.96,0.96];
            app.Button_275.BackgroundColor=[0.96,0.96,0.96];
            app.Button_276.BackgroundColor=[0.96,0.96,0.96];
            app.Button_712.BackgroundColor=[0.96,0.96,0.96];


        end

        % Value changed function: Button_263
        function Button_263ValueChanged(app, event)
            value = app.Button_263.Value;
            A156_3=4;
            B156_3=cellstr('6 % - 10 %');
            c156_3=cellstr('app.Button_263');
            app.Matriz2(31)=c156_3;
            app.Matriz1(31)=A156_3;
            app.Matriz3(31)=B156_3;
            
            app.NAButton.BackgroundColor=[0.96,0.96,0.96];
            app.Button_270.BackgroundColor=[0.96,0.96,0.96];
            app.Button_271.BackgroundColor=[0.96,0.96,0.96];
            app.Button_263.BackgroundColor=[1.00,1.00,0.00];
            app.Button_264.BackgroundColor=[0.96,0.96,0.96];
            app.Button_261.BackgroundColor=[0.96,0.96,0.96];
            app.Button_262.BackgroundColor=[0.96,0.96,0.96];
            app.Button_275.BackgroundColor=[0.96,0.96,0.96];
            app.Button_276.BackgroundColor=[0.96,0.96,0.96];
            app.Button_712.BackgroundColor=[0.96,0.96,0.96];

        end

        % Value changed function: Button_264
        function Button_264ValueChanged(app, event)
            value = app.Button_264.Value;
            A156_4=5;
            B156_4=cellstr('11 % - 20 %');
            c156_4=cellstr('app.Button_264');
            app.Matriz2(31)=c156_4;
            app.Matriz1(31)=A156_4;
            app.Matriz3(31)=B156_4;
            
            app.NAButton.BackgroundColor=[0.96,0.96,0.96];
            app.Button_270.BackgroundColor=[0.96,0.96,0.96];
            app.Button_271.BackgroundColor=[0.96,0.96,0.96];
            app.Button_263.BackgroundColor=[0.96,0.96,0.96];
            app.Button_264.BackgroundColor=[1.00,1.00,0.00];
            app.Button_261.BackgroundColor=[0.96,0.96,0.96];
            app.Button_262.BackgroundColor=[0.96,0.96,0.96];
            app.Button_275.BackgroundColor=[0.96,0.96,0.96];
            app.Button_276.BackgroundColor=[0.96,0.96,0.96];
            app.Button_712.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_261
        function Button_261ValueChanged(app, event)
            value = app.Button_261.Value;
            A156_5=6;
            B156_5=cellstr('21 % - 40 %');
            c156_5=cellstr('app.Button_261');
            app.Matriz2(31)=c156_5; 
            app.Matriz1(31)=A156_5;
            app.Matriz3(31)=B156_5;
            
            app.NAButton.BackgroundColor=[0.96,0.96,0.96];
            app.Button_270.BackgroundColor=[0.96,0.96,0.96];
            app.Button_271.BackgroundColor=[0.96,0.96,0.96];
            app.Button_263.BackgroundColor=[0.96,0.96,0.96];
            app.Button_264.BackgroundColor=[0.96,0.96,0.96];
            app.Button_261.BackgroundColor=[1.00,1.00,0.00];
            app.Button_262.BackgroundColor=[0.96,0.96,0.96];
            app.Button_275.BackgroundColor=[0.96,0.96,0.96];
            app.Button_276.BackgroundColor=[0.96,0.96,0.96];
            app.Button_712.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_262
        function Button_262ValueChanged(app, event)
            value = app.Button_262.Value;
            A156_6=7;
            B156_6=cellstr('41 % - 60 %');
            c156_6=cellstr('app.Button_262');
            app.Matriz2(31)=c156_6; 
            app.Matriz1(31)=A156_6;
            app.Matriz3(31)=B156_6;
            
            app.NAButton.BackgroundColor=[0.96,0.96,0.96];
            app.Button_270.BackgroundColor=[0.96,0.96,0.96];
            app.Button_271.BackgroundColor=[0.96,0.96,0.96];
            app.Button_263.BackgroundColor=[0.96,0.96,0.96];
            app.Button_264.BackgroundColor=[0.96,0.96,0.96];
            app.Button_261.BackgroundColor=[0.96,0.96,0.96];
            app.Button_262.BackgroundColor=[1.00,1.00,0.00];
            app.Button_275.BackgroundColor=[0.96,0.96,0.96];
            app.Button_276.BackgroundColor=[0.96,0.96,0.96];
            app.Button_712.BackgroundColor=[0.96,0.96,0.96];

        end

        % Value changed function: Button_275
        function Button_275ValueChanged(app, event)
            value = app.Button_275.Value;
            A156_7=8;
            B156_7=cellstr('61 % - 80 %');
            c156_7=cellstr('app.Button_275');
            app.Matriz2(31)=c156_7; 
            app.Matriz1(31)=A156_7;
            app.Matriz3(31)=B156_7;
            
            app.NAButton.BackgroundColor=[0.96,0.96,0.96];
            app.Button_270.BackgroundColor=[0.96,0.96,0.96];
            app.Button_271.BackgroundColor=[0.96,0.96,0.96];
            app.Button_263.BackgroundColor=[0.96,0.96,0.96];
            app.Button_264.BackgroundColor=[0.96,0.96,0.96];
            app.Button_261.BackgroundColor=[0.96,0.96,0.96];
            app.Button_262.BackgroundColor=[0.96,0.96,0.96];
            app.Button_275.BackgroundColor=[1.00,1.00,0.00];
            app.Button_276.BackgroundColor=[0.96,0.96,0.96];
            app.Button_712.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_276
        function Button_276ValueChanged(app, event)
            value = app.Button_276.Value;
            A156_8=9;
            B156_8=cellstr('81 % - 99 %');
            c156_8=cellstr('app.Button_276');
            app.Matriz2(31)=c156_8; 
            app.Matriz1(31)=A156_8;
            app.Matriz3(31)=B156_8;
            
            app.NAButton.BackgroundColor=[0.96,0.96,0.96];
            app.Button_270.BackgroundColor=[0.96,0.96,0.96];
            app.Button_271.BackgroundColor=[0.96,0.96,0.96];
            app.Button_263.BackgroundColor=[0.96,0.96,0.96];
            app.Button_264.BackgroundColor=[0.96,0.96,0.96];
            app.Button_261.BackgroundColor=[0.96,0.96,0.96];
            app.Button_262.BackgroundColor=[0.96,0.96,0.96];
            app.Button_275.BackgroundColor=[0.96,0.96,0.96];
            app.Button_276.BackgroundColor=[1.00,1.00,0.00];
            app.Button_712.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_265
        function Button_265ValueChanged(app, event)
            value = app.Button_265.Value;
            A157=1;
            B157=cellstr('0');
            c157=cellstr('app.Button_265');
            app.Matriz2(32)=c157; 
            app.Matriz1(32)=A157;
            app.Matriz3(32)=B157;
            
            app.Button_265.BackgroundColor=[1.00,1.00,0.00];
            app.Button_266.BackgroundColor=[0.96,0.96,0.96];
            app.Button_267.BackgroundColor=[0.96,0.96,0.96];
            app.Button_277.BackgroundColor=[0.96,0.96,0.96];
            app.Button_278.BackgroundColor=[0.96,0.96,0.96];
            app.Button_279.BackgroundColor=[0.96,0.96,0.96];
            app.Button_280.BackgroundColor=[0.96,0.96,0.96];
            app.Button_281.BackgroundColor=[0.96,0.96,0.96];
            app.Button_282.BackgroundColor=[0.96,0.96,0.96];
            app.Button_283.BackgroundColor=[0.96,0.96,0.96];
            app.Button_284.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_266
        function Button_266ValueChanged(app, event)
            value = app.Button_266.Value;
            A157_1=2;
            B157_1=cellstr('1 a 5');
            c157_1=cellstr('app.Button_266');
            app.Matriz2(32)=c157_1; 
            app.Matriz1(32)=A157_1;
            app.Matriz3(32)=B157_1;
            
            app.Button_265.BackgroundColor=[0.96,0.96,0.96];
            app.Button_266.BackgroundColor=[1.00,1.00,0.00];
            app.Button_267.BackgroundColor=[0.96,0.96,0.96];
            app.Button_277.BackgroundColor=[0.96,0.96,0.96];
            app.Button_278.BackgroundColor=[0.96,0.96,0.96];
            app.Button_279.BackgroundColor=[0.96,0.96,0.96];
            app.Button_280.BackgroundColor=[0.96,0.96,0.96];
            app.Button_281.BackgroundColor=[0.96,0.96,0.96];
            app.Button_282.BackgroundColor=[0.96,0.96,0.96];
            app.Button_283.BackgroundColor=[0.96,0.96,0.96];
            app.Button_284.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_267
        function Button_267ValueChanged(app, event)
            value = app.Button_267.Value;
            A157_2=3;
            B157_2=cellstr('6 a 25');
            c157_2=cellstr('app.Button_267');
            app.Matriz2(32)=c157_2; 
            app.Matriz1(32)=A157_2;
            app.Matriz3(32)=B157_2;
            
            app.Button_265.BackgroundColor=[0.96,0.96,0.96];
            app.Button_266.BackgroundColor=[0.96,0.96,0.96];
            app.Button_267.BackgroundColor=[1.00,1.00,0.00];
            app.Button_277.BackgroundColor=[0.96,0.96,0.96];
            app.Button_278.BackgroundColor=[0.96,0.96,0.96];
            app.Button_279.BackgroundColor=[0.96,0.96,0.96];
            app.Button_280.BackgroundColor=[0.96,0.96,0.96];
            app.Button_281.BackgroundColor=[0.96,0.96,0.96];
            app.Button_282.BackgroundColor=[0.96,0.96,0.96];
            app.Button_283.BackgroundColor=[0.96,0.96,0.96];
            app.Button_284.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_277
        function Button_277ValueChanged(app, event)
            value = app.Button_277.Value;
            A157_3=4;
            B157_3=cellstr('26 a 50');
            c157_3=cellstr('app.Button_277');
            app.Matriz2(32)=c157_3; 
            app.Matriz1(32)=A157_3;
            app.Matriz3(32)=B157_3;
            
            app.Button_265.BackgroundColor=[0.96,0.96,0.96];
            app.Button_266.BackgroundColor=[0.96,0.96,0.96];
            app.Button_267.BackgroundColor=[0.96,0.96,0.96];
            app.Button_277.BackgroundColor=[1.00,1.00,0.00];
            app.Button_278.BackgroundColor=[0.96,0.96,0.96];
            app.Button_279.BackgroundColor=[0.96,0.96,0.96];
            app.Button_280.BackgroundColor=[0.96,0.96,0.96];
            app.Button_281.BackgroundColor=[0.96,0.96,0.96];
            app.Button_282.BackgroundColor=[0.96,0.96,0.96];
            app.Button_283.BackgroundColor=[0.96,0.96,0.96];
            app.Button_284.BackgroundColor=[0.96,0.96,0.96];

        end

        % Value changed function: Button_278
        function Button_278ValueChanged(app, event)
            value = app.Button_278.Value;
            A157_4=5;
            B157_4=cellstr('51 a 100');
            c157_4=cellstr('app.Button_278');
            app.Matriz2(32)=c157_4; 
            app.Matriz1(32)=A157_4;
            app.Matriz3(32)=B157_4;
            
            app.Button_265.BackgroundColor=[0.96,0.96,0.96];
            app.Button_266.BackgroundColor=[0.96,0.96,0.96];
            app.Button_267.BackgroundColor=[0.96,0.96,0.96];
            app.Button_277.BackgroundColor=[0.96,0.96,0.96];
            app.Button_278.BackgroundColor=[1.00,1.00,0.00];
            app.Button_279.BackgroundColor=[0.96,0.96,0.96];
            app.Button_280.BackgroundColor=[0.96,0.96,0.96];
            app.Button_281.BackgroundColor=[0.96,0.96,0.96];
            app.Button_282.BackgroundColor=[0.96,0.96,0.96];
            app.Button_283.BackgroundColor=[0.96,0.96,0.96];
            app.Button_284.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_279
        function Button_279ValueChanged(app, event)
            value = app.Button_279.Value;
            A157_5=6;
            B157_5=cellstr('101 a 200');
            c157_5=cellstr('app.Button_279');
            app.Matriz2(32)=c157_5; 
            app.Matriz1(32)=A157_5;
            app.Matriz3(32)=B157_5;
            
            app.Button_265.BackgroundColor=[0.96,0.96,0.96];
            app.Button_266.BackgroundColor=[0.96,0.96,0.96];
            app.Button_267.BackgroundColor=[0.96,0.96,0.96];
            app.Button_277.BackgroundColor=[0.96,0.96,0.96];
            app.Button_278.BackgroundColor=[0.96,0.96,0.96];
            app.Button_279.BackgroundColor=[1.00,1.00,0.00];
            app.Button_280.BackgroundColor=[0.96,0.96,0.96];
            app.Button_281.BackgroundColor=[0.96,0.96,0.96];
            app.Button_282.BackgroundColor=[0.96,0.96,0.96];
            app.Button_283.BackgroundColor=[0.96,0.96,0.96];
            app.Button_284.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_280
        function Button_280ValueChanged(app, event)
            value = app.Button_280.Value;
            A157_6=7;
            B157_6=cellstr('201 a 300');
            c157_6=cellstr('app.Button_280');
            app.Matriz2(32)=c157_6; 
            app.Matriz1(32)=A157_6;
            app.Matriz3(32)=B157_6;
            
            app.Button_265.BackgroundColor=[0.96,0.96,0.96];
            app.Button_266.BackgroundColor=[0.96,0.96,0.96];
            app.Button_267.BackgroundColor=[0.96,0.96,0.96];
            app.Button_277.BackgroundColor=[0.96,0.96,0.96];
            app.Button_278.BackgroundColor=[0.96,0.96,0.96];
            app.Button_279.BackgroundColor=[0.96,0.96,0.96];
            app.Button_280.BackgroundColor=[1.00,1.00,0.00];
            app.Button_281.BackgroundColor=[0.96,0.96,0.96];
            app.Button_282.BackgroundColor=[0.96,0.96,0.96];
            app.Button_283.BackgroundColor=[0.96,0.96,0.96];
            app.Button_284.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_281
        function Button_281ValueChanged(app, event)
            value = app.Button_281.Value;
            A157_7=8;
            B157_7=cellstr('301 a 400');
            c157_7=cellstr('app.Button_281');
            app.Matriz2(32)=c157_7; 
            app.Matriz1(32)=A157_7;
            app.Matriz3(32)=B157_7;
            
            app.Button_265.BackgroundColor=[0.96,0.96,0.96];
            app.Button_266.BackgroundColor=[0.96,0.96,0.96];
            app.Button_267.BackgroundColor=[0.96,0.96,0.96];
            app.Button_277.BackgroundColor=[0.96,0.96,0.96];
            app.Button_278.BackgroundColor=[0.96,0.96,0.96];
            app.Button_279.BackgroundColor=[0.96,0.96,0.96];
            app.Button_280.BackgroundColor=[0.96,0.96,0.96];
            app.Button_281.BackgroundColor=[1.00,1.00,0.00];
            app.Button_282.BackgroundColor=[0.96,0.96,0.96];
            app.Button_283.BackgroundColor=[0.96,0.96,0.96];
            app.Button_284.BackgroundColor=[0.96,0.96,0.96];

        end

        % Value changed function: Button_282
        function Button_282ValueChanged(app, event)
            value = app.Button_282.Value;
            A157_8=9;
            B157_8=cellstr('401 a 500');
            c157_8=cellstr('app.Button_282');
            app.Matriz2(32)=c157_8; 
            app.Matriz1(32)=A157_8;
            app.Matriz3(32)=B157_8;
            
            app.Button_265.BackgroundColor=[0.96,0.96,0.96];
            app.Button_266.BackgroundColor=[0.96,0.96,0.96];
            app.Button_267.BackgroundColor=[0.96,0.96,0.96];
            app.Button_277.BackgroundColor=[0.96,0.96,0.96];
            app.Button_278.BackgroundColor=[0.96,0.96,0.96];
            app.Button_279.BackgroundColor=[0.96,0.96,0.96];
            app.Button_280.BackgroundColor=[0.96,0.96,0.96];
            app.Button_281.BackgroundColor=[0.96,0.96,0.96];
            app.Button_282.BackgroundColor=[1.00,1.00,0.00];
            app.Button_283.BackgroundColor=[0.96,0.96,0.96];
            app.Button_284.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_283
        function Button_283ValueChanged(app, event)
            value = app.Button_283.Value;
            A157_9=10;
            B157_9=cellstr('501 a 900');
            c157_9=cellstr('app.Button_283');
            app.Matriz2(32)=c157_9; 
            app.Matriz1(32)=A157_9;
            app.Matriz3(32)=B157_9;
            
            app.Button_265.BackgroundColor=[0.96,0.96,0.96];
            app.Button_266.BackgroundColor=[0.96,0.96,0.96];
            app.Button_267.BackgroundColor=[0.96,0.96,0.96];
            app.Button_277.BackgroundColor=[0.96,0.96,0.96];
            app.Button_278.BackgroundColor=[0.96,0.96,0.96];
            app.Button_279.BackgroundColor=[0.96,0.96,0.96];
            app.Button_280.BackgroundColor=[0.96,0.96,0.96];
            app.Button_281.BackgroundColor=[0.96,0.96,0.96];
            app.Button_282.BackgroundColor=[0.96,0.96,0.96];
            app.Button_283.BackgroundColor=[1.00,1.00,0.00];
            app.Button_284.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_284
        function Button_284ValueChanged(app, event)
            value = app.Button_284.Value;
            A157_10=11;
            B157_10=cellstr('900+');
            c157_10=cellstr('app.Button_284');
            app.Matriz2(32)=c157_10; 
            app.Matriz1(32)=A157_10;
            app.Matriz3(32)=B157_10;
            
            app.Button_265.BackgroundColor=[0.96,0.96,0.96];
            app.Button_266.BackgroundColor=[0.96,0.96,0.96];
            app.Button_267.BackgroundColor=[0.96,0.96,0.96];
            app.Button_277.BackgroundColor=[0.96,0.96,0.96];
            app.Button_278.BackgroundColor=[0.96,0.96,0.96];
            app.Button_279.BackgroundColor=[0.96,0.96,0.96];
            app.Button_280.BackgroundColor=[0.96,0.96,0.96];
            app.Button_281.BackgroundColor=[0.96,0.96,0.96];
            app.Button_282.BackgroundColor=[0.96,0.96,0.96];
            app.Button_283.BackgroundColor=[0.96,0.96,0.96];
            app.Button_284.BackgroundColor=[1.00,1.00,0.00];

        end

        % Value changed function: Button_572
        function Button_572ValueChanged(app, event)
            value = app.Button_572.Value;
            A158=1;
            B158=cellstr('0');
            c158=cellstr('app.Button_572');
            app.Matriz2(33)=c158; 
            app.Matriz1(33)=A158;
            app.Matriz3(33)=B158;
            
            app.Button_572.BackgroundColor=[1.00,1.00,0.00];
            app.Button_573.BackgroundColor=[0.96,0.96,0.96];
            app.Button_574.BackgroundColor=[0.96,0.96,0.96];
            app.Button_575.BackgroundColor=[0.96,0.96,0.96];
            app.Button_576.BackgroundColor=[0.96,0.96,0.96];
            app.Button_577.BackgroundColor=[0.96,0.96,0.96];
            app.Button_578.BackgroundColor=[0.96,0.96,0.96];
            app.Button_579.BackgroundColor=[0.96,0.96,0.96];
            app.Button_580.BackgroundColor=[0.96,0.96,0.96];
            app.Button_581.BackgroundColor=[0.96,0.96,0.96];
            app.Button_582.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_573
        function Button_573ValueChanged(app, event)
            value = app.Button_573.Value;
            A158_1=2;
            B158_1=cellstr('1 a 5');
            c158_1=cellstr('app.Button_573');
            app.Matriz2(33)=c158_1; 
            app.Matriz1(33)=A158_1;
            app.Matriz3(33)=B158_1;
            
            app.Button_572.BackgroundColor=[0.96,0.96,0.96];
            app.Button_573.BackgroundColor=[1.00,1.00,0.00];
            app.Button_574.BackgroundColor=[0.96,0.96,0.96];
            app.Button_575.BackgroundColor=[0.96,0.96,0.96];
            app.Button_576.BackgroundColor=[0.96,0.96,0.96];
            app.Button_577.BackgroundColor=[0.96,0.96,0.96];
            app.Button_578.BackgroundColor=[0.96,0.96,0.96];
            app.Button_579.BackgroundColor=[0.96,0.96,0.96];
            app.Button_580.BackgroundColor=[0.96,0.96,0.96];
            app.Button_581.BackgroundColor=[0.96,0.96,0.96];
            app.Button_582.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_574
        function Button_574ValueChanged(app, event)
            value = app.Button_574.Value;
            A158_2=3;
            B158_2=cellstr('6 a 25');
            c158_2=cellstr('app.Button_574');
            app.Matriz2(33)=c158_2; 
            app.Matriz1(33)=A158_2;
            app.Matriz3(33)=B158_2;
            
            app.Button_572.BackgroundColor=[0.96,0.96,0.96];
            app.Button_573.BackgroundColor=[0.96,0.96,0.96];
            app.Button_574.BackgroundColor=[1.00,1.00,0.00];
            app.Button_575.BackgroundColor=[0.96,0.96,0.96];
            app.Button_576.BackgroundColor=[0.96,0.96,0.96];
            app.Button_577.BackgroundColor=[0.96,0.96,0.96];
            app.Button_578.BackgroundColor=[0.96,0.96,0.96];
            app.Button_579.BackgroundColor=[0.96,0.96,0.96];
            app.Button_580.BackgroundColor=[0.96,0.96,0.96];
            app.Button_581.BackgroundColor=[0.96,0.96,0.96];
            app.Button_582.BackgroundColor=[0.96,0.96,0.96];


        end

        % Value changed function: Button_575
        function Button_575ValueChanged(app, event)
            value = app.Button_575.Value;
            A158_3=4;
            B158_3=cellstr('26 a 50');
            c158_3=cellstr('app.Button_575');
            app.Matriz2(33)=c158_3; 
            app.Matriz1(33)=A158_3;
            app.Matriz3(33)=B158_3;
            
            app.Button_572.BackgroundColor=[0.96,0.96,0.96];
            app.Button_573.BackgroundColor=[0.96,0.96,0.96];
            app.Button_574.BackgroundColor=[0.96,0.96,0.96];
            app.Button_575.BackgroundColor=[1.00,1.00,0.00];
            app.Button_576.BackgroundColor=[0.96,0.96,0.96];
            app.Button_577.BackgroundColor=[0.96,0.96,0.96];
            app.Button_578.BackgroundColor=[0.96,0.96,0.96];
            app.Button_579.BackgroundColor=[0.96,0.96,0.96];
            app.Button_580.BackgroundColor=[0.96,0.96,0.96];
            app.Button_581.BackgroundColor=[0.96,0.96,0.96];
            app.Button_582.BackgroundColor=[0.96,0.96,0.96];

        end

        % Value changed function: Button_576
        function Button_576ValueChanged(app, event)
            value = app.Button_576.Value;
            A158_4=5;
            B158_4=cellstr('51 a 100');
            c158_4=cellstr('app.Button_576');
            app.Matriz2(33)=c158_4; 
            app.Matriz1(33)=A158_4;
            app.Matriz3(33)=B158_4;
            
            app.Button_572.BackgroundColor=[0.96,0.96,0.96];
            app.Button_573.BackgroundColor=[0.96,0.96,0.96];
            app.Button_574.BackgroundColor=[0.96,0.96,0.96];
            app.Button_575.BackgroundColor=[0.96,0.96,0.96];
            app.Button_576.BackgroundColor=[1.00,1.00,0.00];
            app.Button_577.BackgroundColor=[0.96,0.96,0.96];
            app.Button_578.BackgroundColor=[0.96,0.96,0.96];
            app.Button_579.BackgroundColor=[0.96,0.96,0.96];
            app.Button_580.BackgroundColor=[0.96,0.96,0.96];
            app.Button_581.BackgroundColor=[0.96,0.96,0.96];
            app.Button_582.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_577
        function Button_577ValueChanged(app, event)
            value = app.Button_577.Value;
            A158_5=6;
            B158_5=cellstr('101 a 200');
            c158_5=cellstr('app.Button_577');
            app.Matriz2(33)=c158_5; 
            app.Matriz1(33)=A158_5;
            app.Matriz3(33)=B158_5;
            
            app.Button_572.BackgroundColor=[0.96,0.96,0.96];
            app.Button_573.BackgroundColor=[0.96,0.96,0.96];
            app.Button_574.BackgroundColor=[0.96,0.96,0.96];
            app.Button_575.BackgroundColor=[0.96,0.96,0.96];
            app.Button_576.BackgroundColor=[0.96,0.96,0.96];
            app.Button_577.BackgroundColor=[1.00,1.00,0.00];
            app.Button_578.BackgroundColor=[0.96,0.96,0.96];
            app.Button_579.BackgroundColor=[0.96,0.96,0.96];
            app.Button_580.BackgroundColor=[0.96,0.96,0.96];
            app.Button_581.BackgroundColor=[0.96,0.96,0.96];
            app.Button_582.BackgroundColor=[0.96,0.96,0.96];

        end

        % Value changed function: Button_578
        function Button_578ValueChanged(app, event)
            value = app.Button_578.Value;
            A158_6=7;
            B158_6=cellstr('201 a 300');
            c158_6=cellstr('app.Button_578');
            app.Matriz2(33)=c158_6; 
            app.Matriz1(33)=A158_6;
            app.Matriz3(33)=B158_6;
            
            app.Button_572.BackgroundColor=[0.96,0.96,0.96];
            app.Button_573.BackgroundColor=[0.96,0.96,0.96];
            app.Button_574.BackgroundColor=[0.96,0.96,0.96];
            app.Button_575.BackgroundColor=[0.96,0.96,0.96];
            app.Button_576.BackgroundColor=[0.96,0.96,0.96];
            app.Button_577.BackgroundColor=[0.96,0.96,0.96];
            app.Button_578.BackgroundColor=[1.00,1.00,0.00];
            app.Button_579.BackgroundColor=[0.96,0.96,0.96];
            app.Button_580.BackgroundColor=[0.96,0.96,0.96];
            app.Button_581.BackgroundColor=[0.96,0.96,0.96];
            app.Button_582.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_579
        function Button_579ValueChanged(app, event)
            value = app.Button_579.Value;
            A158_7=8;
            B158_7=cellstr('301 a 400');
            c158_7=cellstr('app.Button_579');
            app.Matriz2(33)=c158_7; 
            app.Matriz1(33)=A158_7;
            app.Matriz3(33)=B158_7;
            
            app.Button_572.BackgroundColor=[0.96,0.96,0.96];
            app.Button_573.BackgroundColor=[0.96,0.96,0.96];
            app.Button_574.BackgroundColor=[0.96,0.96,0.96];
            app.Button_575.BackgroundColor=[0.96,0.96,0.96];
            app.Button_576.BackgroundColor=[0.96,0.96,0.96];
            app.Button_577.BackgroundColor=[0.96,0.96,0.96];
            app.Button_578.BackgroundColor=[0.96,0.96,0.96];
            app.Button_579.BackgroundColor=[1.00,1.00,0.00];
            app.Button_580.BackgroundColor=[0.96,0.96,0.96];
            app.Button_581.BackgroundColor=[0.96,0.96,0.96];
            app.Button_582.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_580
        function Button_580ValueChanged(app, event)
            value = app.Button_580.Value;
            A158_8=9;
            B158_8=cellstr('401 a 500');
            c158_8=cellstr('app.Button_580');
            app.Matriz2(33)=c158_8; 
            app.Matriz1(33)=A158_8;
            app.Matriz3(33)=B158_8;
            
            app.Button_572.BackgroundColor=[0.96,0.96,0.96];
            app.Button_573.BackgroundColor=[0.96,0.96,0.96];
            app.Button_574.BackgroundColor=[0.96,0.96,0.96];
            app.Button_575.BackgroundColor=[0.96,0.96,0.96];
            app.Button_576.BackgroundColor=[0.96,0.96,0.96];
            app.Button_577.BackgroundColor=[0.96,0.96,0.96];
            app.Button_578.BackgroundColor=[0.96,0.96,0.96];
            app.Button_579.BackgroundColor=[0.96,0.96,0.96];
            app.Button_580.BackgroundColor=[1.00,1.00,0.00];
            app.Button_581.BackgroundColor=[0.96,0.96,0.96];
            app.Button_582.BackgroundColor=[0.96,0.96,0.96];


        end

        % Value changed function: Button_581
        function Button_581ValueChanged(app, event)
            value = app.Button_581.Value;
            A158_9=10;
            B158_9=cellstr('501 a 900');
            c158_9=cellstr('app.Button_581');
            app.Matriz2(33)=c158_9; 
            app.Matriz1(33)=A158_9;
            app.Matriz3(33)=B158_9;
            
            app.Button_572.BackgroundColor=[0.96,0.96,0.96];
            app.Button_573.BackgroundColor=[0.96,0.96,0.96];
            app.Button_574.BackgroundColor=[0.96,0.96,0.96];
            app.Button_575.BackgroundColor=[0.96,0.96,0.96];
            app.Button_576.BackgroundColor=[0.96,0.96,0.96];
            app.Button_577.BackgroundColor=[0.96,0.96,0.96];
            app.Button_578.BackgroundColor=[0.96,0.96,0.96];
            app.Button_579.BackgroundColor=[0.96,0.96,0.96];
            app.Button_580.BackgroundColor=[0.96,0.96,0.96];
            app.Button_581.BackgroundColor=[1.00,1.00,0.00];
            app.Button_582.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_582
        function Button_582ValueChanged(app, event)
            value = app.Button_582.Value;
            A158_10=11;
            B158_10=cellstr('900+');
            c158_10=cellstr('app.Button_582');
            app.Matriz2(33)=c158_10; 
            app.Matriz1(33)=A158_10;
            app.Matriz3(33)=B158_10;
            
            app.Button_572.BackgroundColor=[0.96,0.96,0.96];
            app.Button_573.BackgroundColor=[0.96,0.96,0.96];
            app.Button_574.BackgroundColor=[0.96,0.96,0.96];
            app.Button_575.BackgroundColor=[0.96,0.96,0.96];
            app.Button_576.BackgroundColor=[0.96,0.96,0.96];
            app.Button_577.BackgroundColor=[0.96,0.96,0.96];
            app.Button_578.BackgroundColor=[0.96,0.96,0.96];
            app.Button_579.BackgroundColor=[0.96,0.96,0.96];
            app.Button_580.BackgroundColor=[0.96,0.96,0.96];
            app.Button_581.BackgroundColor=[0.96,0.96,0.96];
            app.Button_582.BackgroundColor=[1.00,1.00,0.00];
        end

        % Value changed function: Button_583
        function Button_583ValueChanged(app, event)
            value = app.Button_583.Value;
            A159=1;
            B159=cellstr('0');
            c159=cellstr('app.Button_583');
            app.Matriz2(34)=c159;
            app.Matriz1(34)=A159;
            app.Matriz3(34)=B159;
            
            app.Button_583.BackgroundColor=[1.00,1.00,0.00];
            app.Button_584.BackgroundColor=[0.96,0.96,0.96];
            app.Button_585.BackgroundColor=[0.96,0.96,0.96];
            app.Button_586.BackgroundColor=[0.96,0.96,0.96];
            app.Button_587.BackgroundColor=[0.96,0.96,0.96];
            app.Button_588.BackgroundColor=[0.96,0.96,0.96];
            app.Button_589.BackgroundColor=[0.96,0.96,0.96];
            app.Button_590.BackgroundColor=[0.96,0.96,0.96];
            app.Button_591.BackgroundColor=[0.96,0.96,0.96];
            app.Button_592.BackgroundColor=[0.96,0.96,0.96];
            app.Button_593.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_584
        function Button_584ValueChanged(app, event)
            value = app.Button_584.Value;
            A159_1=2;
            B159_1=cellstr('1 a 5');
            c159_1=cellstr('app.Button_584');
            app.Matriz2(34)=c159_1;
            app.Matriz1(34)=A159_1;
            app.Matriz3(34)=B159_1;
            
            app.Button_583.BackgroundColor=[0.96,0.96,0.96];
            app.Button_584.BackgroundColor=[1.00,1.00,0.00];
            app.Button_585.BackgroundColor=[0.96,0.96,0.96];
            app.Button_586.BackgroundColor=[0.96,0.96,0.96];
            app.Button_587.BackgroundColor=[0.96,0.96,0.96];
            app.Button_588.BackgroundColor=[0.96,0.96,0.96];
            app.Button_589.BackgroundColor=[0.96,0.96,0.96];
            app.Button_590.BackgroundColor=[0.96,0.96,0.96];
            app.Button_591.BackgroundColor=[0.96,0.96,0.96];
            app.Button_592.BackgroundColor=[0.96,0.96,0.96];
            app.Button_593.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_585
        function Button_585ValueChanged(app, event)
            value = app.Button_585.Value;
            A159_2=3;
            B159_2=cellstr('6 a 25');
            c159_2=cellstr('app.Button_585');
            app.Matriz2(34)=c159_2;
            app.Matriz1(34)=A159_2;
            app.Matriz3(34)=B159_2;
            
            app.Button_583.BackgroundColor=[0.96,0.96,0.96];
            app.Button_584.BackgroundColor=[0.96,0.96,0.96];
            app.Button_585.BackgroundColor=[1.00,1.00,0.00];
            app.Button_586.BackgroundColor=[0.96,0.96,0.96];
            app.Button_587.BackgroundColor=[0.96,0.96,0.96];
            app.Button_588.BackgroundColor=[0.96,0.96,0.96];
            app.Button_589.BackgroundColor=[0.96,0.96,0.96];
            app.Button_590.BackgroundColor=[0.96,0.96,0.96];
            app.Button_591.BackgroundColor=[0.96,0.96,0.96];
            app.Button_592.BackgroundColor=[0.96,0.96,0.96];
            app.Button_593.BackgroundColor=[0.96,0.96,0.96];

        end

        % Value changed function: Button_586
        function Button_586ValueChanged(app, event)
            value = app.Button_586.Value;
            A159_3=4;
            B159_3=cellstr('26 a 50');
            c159_3=cellstr('app.Button_586');
            app.Matriz2(34)=c159_3;
            app.Matriz1(34)=A159_3;
            app.Matriz3(34)=B159_3;
            
            app.Button_583.BackgroundColor=[0.96,0.96,0.96];
            app.Button_584.BackgroundColor=[0.96,0.96,0.96];
            app.Button_585.BackgroundColor=[0.96,0.96,0.96];
            app.Button_586.BackgroundColor=[1.00,1.00,0.00];
            app.Button_587.BackgroundColor=[0.96,0.96,0.96];
            app.Button_588.BackgroundColor=[0.96,0.96,0.96];
            app.Button_589.BackgroundColor=[0.96,0.96,0.96];
            app.Button_590.BackgroundColor=[0.96,0.96,0.96];
            app.Button_591.BackgroundColor=[0.96,0.96,0.96];
            app.Button_592.BackgroundColor=[0.96,0.96,0.96];
            app.Button_593.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_587
        function Button_587ValueChanged(app, event)
            value = app.Button_587.Value;
            A159_4=5;
            B159_4=cellstr('51 a 100');
            c159_4=cellstr('app.Button_587');
            app.Matriz2(34)=c159_4;
            app.Matriz1(34)=A159_4;
            app.Matriz3(34)=B159_4;
            
            app.Button_583.BackgroundColor=[0.96,0.96,0.96];
            app.Button_584.BackgroundColor=[0.96,0.96,0.96];
            app.Button_585.BackgroundColor=[0.96,0.96,0.96];
            app.Button_586.BackgroundColor=[0.96,0.96,0.96];
            app.Button_587.BackgroundColor=[1.00,1.00,0.00];
            app.Button_588.BackgroundColor=[0.96,0.96,0.96];
            app.Button_589.BackgroundColor=[0.96,0.96,0.96];
            app.Button_590.BackgroundColor=[0.96,0.96,0.96];
            app.Button_591.BackgroundColor=[0.96,0.96,0.96];
            app.Button_592.BackgroundColor=[0.96,0.96,0.96];
            app.Button_593.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_588
        function Button_588ValueChanged(app, event)
            value = app.Button_588.Value;
            A159_5=6;
            B159_5=cellstr('101 a 200');
            c159_5=cellstr('app.Button_588');
            app.Matriz2(34)=c159_5;
            app.Matriz1(34)=A159_5;
            app.Matriz3(34)=B159_5;
            
            app.Button_583.BackgroundColor=[0.96,0.96,0.96];
            app.Button_584.BackgroundColor=[0.96,0.96,0.96];
            app.Button_585.BackgroundColor=[0.96,0.96,0.96];
            app.Button_586.BackgroundColor=[0.96,0.96,0.96];
            app.Button_587.BackgroundColor=[0.96,0.96,0.96];
            app.Button_588.BackgroundColor=[1.00,1.00,0.00];
            app.Button_589.BackgroundColor=[0.96,0.96,0.96];
            app.Button_590.BackgroundColor=[0.96,0.96,0.96];
            app.Button_591.BackgroundColor=[0.96,0.96,0.96];
            app.Button_592.BackgroundColor=[0.96,0.96,0.96];
            app.Button_593.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_589
        function Button_589ValueChanged(app, event)
            value = app.Button_589.Value;
            A159_6=7;
            B159_6=cellstr('201 a 300');
            c159_6=cellstr('app.Button_589');
            app.Matriz2(34)=c159_6;
            app.Matriz1(34)=A159_6;
            app.Matriz3(34)=B159_6;
            
            app.Button_583.BackgroundColor=[0.96,0.96,0.96];
            app.Button_584.BackgroundColor=[0.96,0.96,0.96];
            app.Button_585.BackgroundColor=[0.96,0.96,0.96];
            app.Button_586.BackgroundColor=[0.96,0.96,0.96];
            app.Button_587.BackgroundColor=[0.96,0.96,0.96];
            app.Button_588.BackgroundColor=[0.96,0.96,0.96];
            app.Button_589.BackgroundColor=[1.00,1.00,0.00];
            app.Button_590.BackgroundColor=[0.96,0.96,0.96];
            app.Button_591.BackgroundColor=[0.96,0.96,0.96];
            app.Button_592.BackgroundColor=[0.96,0.96,0.96];
            app.Button_593.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_590
        function Button_590ValueChanged(app, event)
            value = app.Button_590.Value;
            A159_7=8;
            B159_7=cellstr('301 a 400');
            c159_7=cellstr('app.Button_590');
            app.Matriz2(34)=c159_7;
            app.Matriz1(34)=A159_7;
            app.Matriz3(34)=B159_7;
            
            app.Button_583.BackgroundColor=[0.96,0.96,0.96];
            app.Button_584.BackgroundColor=[0.96,0.96,0.96];
            app.Button_585.BackgroundColor=[0.96,0.96,0.96];
            app.Button_586.BackgroundColor=[0.96,0.96,0.96];
            app.Button_587.BackgroundColor=[0.96,0.96,0.96];
            app.Button_588.BackgroundColor=[0.96,0.96,0.96];
            app.Button_589.BackgroundColor=[0.96,0.96,0.96];
            app.Button_590.BackgroundColor=[1.00,1.00,0.00];
            app.Button_591.BackgroundColor=[0.96,0.96,0.96];
            app.Button_592.BackgroundColor=[0.96,0.96,0.96];
            app.Button_593.BackgroundColor=[0.96,0.96,0.96];

        end

        % Value changed function: Button_591
        function Button_591ValueChanged(app, event)
            value = app.Button_591.Value;
            A159_8=9;
            B159_8=cellstr('401 a 500');
            c159_8=cellstr('app.Button_591');
            app.Matriz2(34)=c159_8;
            app.Matriz1(34)=A159_8;
            app.Matriz3(34)=B159_8;
            
            app.Button_583.BackgroundColor=[0.96,0.96,0.96];
            app.Button_584.BackgroundColor=[0.96,0.96,0.96];
            app.Button_585.BackgroundColor=[0.96,0.96,0.96];
            app.Button_586.BackgroundColor=[0.96,0.96,0.96];
            app.Button_587.BackgroundColor=[0.96,0.96,0.96];
            app.Button_588.BackgroundColor=[0.96,0.96,0.96];
            app.Button_589.BackgroundColor=[0.96,0.96,0.96];
            app.Button_590.BackgroundColor=[0.96,0.96,0.96];
            app.Button_591.BackgroundColor=[1.00,1.00,0.00];
            app.Button_592.BackgroundColor=[0.96,0.96,0.96];
            app.Button_593.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_592
        function Button_592ValueChanged(app, event)
            value = app.Button_592.Value;
            A159_9=10;
            B159_9=cellstr('501 a 900');
            c159_9=cellstr('app.Button_592');
            app.Matriz2(34)=c159_9;
            app.Matriz1(34)=A159_9;
            app.Matriz3(34)=B159_9;
            
            app.Button_583.BackgroundColor=[0.96,0.96,0.96];
            app.Button_584.BackgroundColor=[0.96,0.96,0.96];
            app.Button_585.BackgroundColor=[0.96,0.96,0.96];
            app.Button_586.BackgroundColor=[0.96,0.96,0.96];
            app.Button_587.BackgroundColor=[0.96,0.96,0.96];
            app.Button_588.BackgroundColor=[0.96,0.96,0.96];
            app.Button_589.BackgroundColor=[0.96,0.96,0.96];
            app.Button_590.BackgroundColor=[0.96,0.96,0.96];
            app.Button_591.BackgroundColor=[0.96,0.96,0.96];
            app.Button_592.BackgroundColor=[1.00,1.00,0.00];
            app.Button_593.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_593
        function Button_593ValueChanged(app, event)
            value = app.Button_593.Value;
            A159_10=11;
            B159_10=cellstr('900+');
            c159_10=cellstr('app.Button_593');
            app.Matriz2(34)=c159_10;
            app.Matriz1(34)=A159_10;
            app.Matriz3(34)=B159_10;
            
            app.Button_583.BackgroundColor=[0.96,0.96,0.96];
            app.Button_584.BackgroundColor=[0.96,0.96,0.96];
            app.Button_585.BackgroundColor=[0.96,0.96,0.96];
            app.Button_586.BackgroundColor=[0.96,0.96,0.96];
            app.Button_587.BackgroundColor=[0.96,0.96,0.96];
            app.Button_588.BackgroundColor=[0.96,0.96,0.96];
            app.Button_589.BackgroundColor=[0.96,0.96,0.96];
            app.Button_590.BackgroundColor=[0.96,0.96,0.96];
            app.Button_591.BackgroundColor=[0.96,0.96,0.96];
            app.Button_592.BackgroundColor=[0.96,0.96,0.96];
            app.Button_593.BackgroundColor=[1.00,1.00,0.00];
        end

        % Value changed function: Button_594
        function Button_594ValueChanged(app, event)
            value = app.Button_594.Value;
            A160=1;
            B160=cellstr('0');
            c160=cellstr('app.Button_594');
            app.Matriz2(35)=c160;
            app.Matriz1(35)=A160;
            app.Matriz3(35)=B160;
            
            app.Button_594.BackgroundColor=[1.00,1.00,0.00];
            app.Button_595.BackgroundColor=[0.96,0.96,0.96];
            app.Button_596.BackgroundColor=[0.96,0.96,0.96];
            app.Button_597.BackgroundColor=[0.96,0.96,0.96];
            app.Button_598.BackgroundColor=[0.96,0.96,0.96];
            app.Button_599.BackgroundColor=[0.96,0.96,0.96];
            app.Button_600.BackgroundColor=[0.96,0.96,0.96];
            app.Button_601.BackgroundColor=[0.96,0.96,0.96];
            app.Button_602.BackgroundColor=[0.96,0.96,0.96];
            app.Button_603.BackgroundColor=[0.96,0.96,0.96];
            app.Button_604.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_595
        function Button_595ValueChanged(app, event)
            value = app.Button_595.Value;
            A160_1=2;
            B160_1=cellstr('1 a 5');
            c160_1=cellstr('app.Button_595');
            app.Matriz2(35)=c160_1;
            app.Matriz1(35)=A160_1;
            app.Matriz3(35)=B160_1;
            
            app.Button_594.BackgroundColor=[0.96,0.96,0.96];
            app.Button_595.BackgroundColor=[1.00,1.00,0.00];
            app.Button_596.BackgroundColor=[0.96,0.96,0.96];
            app.Button_597.BackgroundColor=[0.96,0.96,0.96];
            app.Button_598.BackgroundColor=[0.96,0.96,0.96];
            app.Button_599.BackgroundColor=[0.96,0.96,0.96];
            app.Button_600.BackgroundColor=[0.96,0.96,0.96];
            app.Button_601.BackgroundColor=[0.96,0.96,0.96];
            app.Button_602.BackgroundColor=[0.96,0.96,0.96];
            app.Button_603.BackgroundColor=[0.96,0.96,0.96];
            app.Button_604.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_596
        function Button_596ValueChanged(app, event)
            value = app.Button_596.Value;
            A160_2=3;
            B160_2=cellstr('6 a 25');
            c160_2=cellstr('app.Button_596');
            app.Matriz2(35)=c160_2;
            app.Matriz1(35)=A160_2;
            app.Matriz3(35)=B160_2;
            
            app.Button_594.BackgroundColor=[0.96,0.96,0.96];
            app.Button_595.BackgroundColor=[0.96,0.96,0.96];
            app.Button_596.BackgroundColor=[1.00,1.00,0.00];
            app.Button_597.BackgroundColor=[0.96,0.96,0.96];
            app.Button_598.BackgroundColor=[0.96,0.96,0.96];
            app.Button_599.BackgroundColor=[0.96,0.96,0.96];
            app.Button_600.BackgroundColor=[0.96,0.96,0.96];
            app.Button_601.BackgroundColor=[0.96,0.96,0.96];
            app.Button_602.BackgroundColor=[0.96,0.96,0.96];
            app.Button_603.BackgroundColor=[0.96,0.96,0.96];
            app.Button_604.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_597
        function Button_597ValueChanged(app, event)
            value = app.Button_597.Value;
            A160_3=4;
            B160_3=cellstr('26 a 50');
            c160_3=cellstr('app.Button_597');
            app.Matriz2(35)=c160_3;
            app.Matriz1(35)=A160_3;
            app.Matriz3(35)=B160_3;
            
            app.Button_594.BackgroundColor=[0.96,0.96,0.96];
            app.Button_595.BackgroundColor=[0.96,0.96,0.96];
            app.Button_596.BackgroundColor=[0.96,0.96,0.96];
            app.Button_597.BackgroundColor=[1.00,1.00,0.00];
            app.Button_598.BackgroundColor=[0.96,0.96,0.96];
            app.Button_599.BackgroundColor=[0.96,0.96,0.96];
            app.Button_600.BackgroundColor=[0.96,0.96,0.96];
            app.Button_601.BackgroundColor=[0.96,0.96,0.96];
            app.Button_602.BackgroundColor=[0.96,0.96,0.96];
            app.Button_603.BackgroundColor=[0.96,0.96,0.96];
            app.Button_604.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_598
        function Button_598ValueChanged(app, event)
            value = app.Button_598.Value;
            A160_4=5;
            B160_4=cellstr('51 a 100');
            c160_4=cellstr('app.Button_598');
            app.Matriz2(35)=c160_4;
            app.Matriz1(35)=A160_4;
            app.Matriz3(35)=B160_4;
            
            app.Button_594.BackgroundColor=[0.96,0.96,0.96];
            app.Button_595.BackgroundColor=[0.96,0.96,0.96];
            app.Button_596.BackgroundColor=[0.96,0.96,0.96];
            app.Button_597.BackgroundColor=[0.96,0.96,0.96];
            app.Button_598.BackgroundColor=[1.00,1.00,0.00];
            app.Button_599.BackgroundColor=[0.96,0.96,0.96];
            app.Button_600.BackgroundColor=[0.96,0.96,0.96];
            app.Button_601.BackgroundColor=[0.96,0.96,0.96];
            app.Button_602.BackgroundColor=[0.96,0.96,0.96];
            app.Button_603.BackgroundColor=[0.96,0.96,0.96];
            app.Button_604.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_599
        function Button_599ValueChanged(app, event)
            value = app.Button_599.Value;
            A160_5=6;
            B160_5=cellstr('101 a 200');
            c160_5=cellstr('app.Button_599');
            app.Matriz2(35)=c160_5;
            app.Matriz1(35)=A160_5;
            app.Matriz3(35)=B160_5;
            
            app.Button_594.BackgroundColor=[0.96,0.96,0.96];
            app.Button_595.BackgroundColor=[0.96,0.96,0.96];
            app.Button_596.BackgroundColor=[0.96,0.96,0.96];
            app.Button_597.BackgroundColor=[0.96,0.96,0.96];
            app.Button_598.BackgroundColor=[0.96,0.96,0.96];
            app.Button_599.BackgroundColor=[1.00,1.00,0.00];
            app.Button_560.BackgroundColor=[0.96,0.96,0.96];
            app.Button_601.BackgroundColor=[0.96,0.96,0.96];
            app.Button_602.BackgroundColor=[0.96,0.96,0.96];
            app.Button_603.BackgroundColor=[0.96,0.96,0.96];
            app.Button_604.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_600
        function Button_600ValueChanged(app, event)
            value = app.Button_600.Value;
            A160_6=7;
            B160_6=cellstr('201 a 300');
            c160_6=cellstr('app.Button_600');
            app.Matriz2(35)=c160_6;
            app.Matriz1(35)=A160_6;
            app.Matriz3(35)=B160_6;
            
            app.Button_594.BackgroundColor=[0.96,0.96,0.96];
            app.Button_595.BackgroundColor=[0.96,0.96,0.96];
            app.Button_596.BackgroundColor=[0.96,0.96,0.96];
            app.Button_597.BackgroundColor=[0.96,0.96,0.96];
            app.Button_598.BackgroundColor=[0.96,0.96,0.96];
            app.Button_599.BackgroundColor=[0.96,0.96,0.96];
            app.Button_600.BackgroundColor=[1.00,1.00,0.00];
            app.Button_601.BackgroundColor=[0.96,0.96,0.96];
            app.Button_602.BackgroundColor=[0.96,0.96,0.96];
            app.Button_603.BackgroundColor=[0.96,0.96,0.96];
            app.Button_604.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_601
        function Button_601ValueChanged(app, event)
            value = app.Button_601.Value;
            A160_7=8;
            B160_7=cellstr('301 a 400');
            c160_7=cellstr('app.Button_601');
            app.Matriz2(35)=c160_7;
            app.Matriz1(35)=A160_7;
            app.Matriz3(35)=B160_7;
            
            app.Button_594.BackgroundColor=[0.96,0.96,0.96];
            app.Button_595.BackgroundColor=[0.96,0.96,0.96];
            app.Button_596.BackgroundColor=[0.96,0.96,0.96];
            app.Button_597.BackgroundColor=[0.96,0.96,0.96];
            app.Button_598.BackgroundColor=[0.96,0.96,0.96];
            app.Button_599.BackgroundColor=[0.96,0.96,0.96];
            app.Button_600.BackgroundColor=[0.96,0.96,0.96];
            app.Button_601.BackgroundColor=[1.00,1.00,0.00];
            app.Button_602.BackgroundColor=[0.96,0.96,0.96];
            app.Button_603.BackgroundColor=[0.96,0.96,0.96];
            app.Button_604.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_602
        function Button_602ValueChanged(app, event)
            value = app.Button_602.Value;
            A160_8=9;
            B160_8=cellstr('401 a 500');
            c160_8=cellstr('app.Button_602');
            app.Matriz2(35)=c160_8;
            app.Matriz1(35)=A160_8;
            app.Matriz3(35)=B160_8;
            
            app.Button_594.BackgroundColor=[0.96,0.96,0.96];
            app.Button_595.BackgroundColor=[0.96,0.96,0.96];
            app.Button_596.BackgroundColor=[0.96,0.96,0.96];
            app.Button_597.BackgroundColor=[0.96,0.96,0.96];
            app.Button_598.BackgroundColor=[0.96,0.96,0.96];
            app.Button_599.BackgroundColor=[0.96,0.96,0.96];
            app.Button_600.BackgroundColor=[0.96,0.96,0.96];
            app.Button_601.BackgroundColor=[0.96,0.96,0.96];
            app.Button_602.BackgroundColor=[1.00,1.00,0.00];
            app.Button_603.BackgroundColor=[0.96,0.96,0.96];
            app.Button_604.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_603
        function Button_603ValueChanged(app, event)
            value = app.Button_603.Value;
            A160_9=10;
            B160_9=cellstr('501 a 900');
            c160_9=cellstr('app.Button_603');
            app.Matriz2(35)=c160_9;
            app.Matriz1(35)=A160_9;
            app.Matriz3(35)=B160_9;
            
            app.Button_594.BackgroundColor=[0.96,0.96,0.96];
            app.Button_595.BackgroundColor=[0.96,0.96,0.96];
            app.Button_596.BackgroundColor=[0.96,0.96,0.96];
            app.Button_597.BackgroundColor=[0.96,0.96,0.96];
            app.Button_598.BackgroundColor=[0.96,0.96,0.96];
            app.Button_599.BackgroundColor=[0.96,0.96,0.96];
            app.Button_600.BackgroundColor=[0.96,0.96,0.96];
            app.Button_601.BackgroundColor=[0.96,0.96,0.96];
            app.Button_602.BackgroundColor=[0.96,0.96,0.96];
            app.Button_603.BackgroundColor=[1.00,1.00,0.00];
            app.Button_604.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_604
        function Button_604ValueChanged(app, event)
            value = app.Button_604.Value;
            A160_10=11;
            B160_10=cellstr('900+');
            c160_10=cellstr('app.Button_604');
            app.Matriz2(35)=c160_10;
            app.Matriz1(35)=A160_10;
            app.Matriz3(35)=B160_10;
            
            app.Button_594.BackgroundColor=[0.96,0.96,0.96];
            app.Button_595.BackgroundColor=[0.96,0.96,0.96];
            app.Button_596.BackgroundColor=[0.96,0.96,0.96];
            app.Button_597.BackgroundColor=[0.96,0.96,0.96];
            app.Button_598.BackgroundColor=[0.96,0.96,0.96];
            app.Button_599.BackgroundColor=[0.96,0.96,0.96];
            app.Button_600.BackgroundColor=[0.96,0.96,0.96];
            app.Button_601.BackgroundColor=[0.96,0.96,0.96];
            app.Button_602.BackgroundColor=[0.96,0.96,0.96];
            app.Button_603.BackgroundColor=[0.96,0.96,0.96];
            app.Button_604.BackgroundColor=[1.00,1.00,0.00];
        end

        % Button pushed function: TransitableButton
        function TransitableButtonPushed(app, event)
            
            %value=app.TransitableButton.Value;
            A1110=1;
            c1110=cellstr('app.TransitableButton');
            b1110=cellstr('Transitable');
            app.Matriz1(53)=A1110;
            app.Matriz2(53)=c1110;
            app.Matriz3(53)=b1110;
            
            app.TransitableButton.BackgroundColor=[1.00,1.00,0.00];
            app.NoTransitableButton.BackgroundColor=[0.96,0.96,0.96];
            
        end

        % Button pushed function: NoTransitableButton
        function NoTransitableButtonPushed(app, event)
            
            A1111=2;
            c1111=cellstr('app.NoTransitableButton');
            b1111=cellstr('Transitable');
            app.Matriz1(53)=A1111;
            app.Matriz2(53)=c1111;
            app.Matriz3(53)=b1111;
            
            app.Matriz1(53)=A1111;
            app.TransitableButton.BackgroundColor=[0.96,0.96,0.96];
            app.NoTransitableButton.BackgroundColor=[1.00,1.00,0.00];
        end

        % Value changed function: Button_207
        function Button_207ValueChanged(app, event)
            value = app.Button_207.Value;
            A121=1;
            B121=cellstr('Carril de incorporación');
            c121=cellstr('app.Button_207');
            app.Matriz2(25)=c121;
            app.Matriz1(25)=A121;
            app.Matriz3(25)=B121;
            
            app.Button_207.BackgroundColor=[1.00,1.00,0.00];
            app.Button_185.BackgroundColor=[0.96,0.96,0.96];
            app.Button_182.BackgroundColor=[0.96,0.96,0.96];
            app.Button_179.BackgroundColor=[0.96,0.96,0.96];
            app.Button_183.BackgroundColor=[0.96,0.96,0.96];
            app.Button_181.BackgroundColor=[0.96,0.96,0.96];
            app.Button_176.BackgroundColor=[0.96,0.96,0.96];
            app.Button_152.BackgroundColor=[0.96,0.96,0.96];
            app.Button_180.BackgroundColor=[0.96,0.96,0.96];
            app.Button_177.BackgroundColor=[0.96,0.96,0.96];
            app.Button_174.BackgroundColor=[0.96,0.96,0.96];
            app.Button_178.BackgroundColor=[0.96,0.96,0.96];
            app.Button_184.BackgroundColor=[0.96,0.96,0.96];
            app.Button_154.BackgroundColor=[0.96,0.96,0.96];
            app.Button_155.BackgroundColor=[0.96,0.96,0.96];
            app.Button_153.BackgroundColor=[0.96,0.96,0.96];
            
        end

        % Value changed function: Button_185
        function Button_185ValueChanged(app, event)
            value = app.Button_185.Value;
            A122=2;
            B122=cellstr('Redondel');
            c122=cellstr('app.Button_185');
            app.Matriz2(25)=c122;
            app.Matriz1(25)=A122;
            app.Matriz3(25)=B122;
            
            app.Button_207.BackgroundColor=[0.96,0.96,0.96];
            app.Button_185.BackgroundColor=[1.00,1.00,0.00];
            app.Button_182.BackgroundColor=[0.96,0.96,0.96];
            app.Button_179.BackgroundColor=[0.96,0.96,0.96];
            app.Button_183.BackgroundColor=[0.96,0.96,0.96];
            app.Button_181.BackgroundColor=[0.96,0.96,0.96];
            app.Button_176.BackgroundColor=[0.96,0.96,0.96];
            app.Button_152.BackgroundColor=[0.96,0.96,0.96];
            app.Button_180.BackgroundColor=[0.96,0.96,0.96];
            app.Button_177.BackgroundColor=[0.96,0.96,0.96];
            app.Button_174.BackgroundColor=[0.96,0.96,0.96];
            app.Button_178.BackgroundColor=[0.96,0.96,0.96];
            app.Button_184.BackgroundColor=[0.96,0.96,0.96];
            app.Button_154.BackgroundColor=[0.96,0.96,0.96];
            app.Button_155.BackgroundColor=[0.96,0.96,0.96];
            app.Button_153.BackgroundColor=[0.96,0.96,0.96];

        end

        % Value changed function: Button_182
        function Button_182ValueChanged(app, event)
            value = app.Button_182.Value;
            A123=3;
            B123=cellstr('3 ramas (no semaforizada) con carril de giro protegido');
            c123=cellstr('app.Button_182');
            app.Matriz2(25)=c123;
            app.Matriz1(25)=A123;
            app.Matriz3(25)=B123;
            
            app.Button_207.BackgroundColor=[0.96,0.96,0.96];
            app.Button_185.BackgroundColor=[0.96,0.96,0.96];
            app.Button_182.BackgroundColor=[1.00,1.00,0.00];
            app.Button_179.BackgroundColor=[0.96,0.96,0.96];
            app.Button_183.BackgroundColor=[0.96,0.96,0.96];
            app.Button_181.BackgroundColor=[0.96,0.96,0.96];
            app.Button_176.BackgroundColor=[0.96,0.96,0.96];
            app.Button_152.BackgroundColor=[0.96,0.96,0.96];
            app.Button_180.BackgroundColor=[0.96,0.96,0.96];
            app.Button_177.BackgroundColor=[0.96,0.96,0.96];
            app.Button_174.BackgroundColor=[0.96,0.96,0.96];
            app.Button_178.BackgroundColor=[0.96,0.96,0.96];
            app.Button_184.BackgroundColor=[0.96,0.96,0.96];
            app.Button_154.BackgroundColor=[0.96,0.96,0.96];
            app.Button_155.BackgroundColor=[0.96,0.96,0.96];
            app.Button_153.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_179
        function Button_179ValueChanged(app, event)
            value = app.Button_179.Value;
            A124=4;
            B124=cellstr('3 ramas (no semaforizada) con carril de giro no protegido');
            c124=cellstr('app.Button_179');
            app.Matriz2(25)=c124;
            app.Matriz1(25)=A124;
            app.Matriz3(25)=B124;
            
            app.Button_207.BackgroundColor=[0.96,0.96,0.96];
            app.Button_185.BackgroundColor=[0.96,0.96,0.96];
            app.Button_182.BackgroundColor=[0.96,0.96,0.96];
            app.Button_179.BackgroundColor=[1.00,1.00,0.00];
            app.Button_183.BackgroundColor=[0.96,0.96,0.96];
            app.Button_181.BackgroundColor=[0.96,0.96,0.96];
            app.Button_176.BackgroundColor=[0.96,0.96,0.96];
            app.Button_152.BackgroundColor=[0.96,0.96,0.96];
            app.Button_180.BackgroundColor=[0.96,0.96,0.96];
            app.Button_177.BackgroundColor=[0.96,0.96,0.96];
            app.Button_174.BackgroundColor=[0.96,0.96,0.96];
            app.Button_178.BackgroundColor=[0.96,0.96,0.96];
            app.Button_184.BackgroundColor=[0.96,0.96,0.96];
            app.Button_154.BackgroundColor=[0.96,0.96,0.96];
            app.Button_155.BackgroundColor=[0.96,0.96,0.96];
            app.Button_153.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_183
        function Button_183ValueChanged(app, event)
            value = app.Button_183.Value;
            A125=5;
            B125=cellstr('3 ramas (semaforizada) con carril de giro protegido');
            c125=cellstr('app.Button_183');
            app.Matriz2(25)=c125;
            app.Matriz1(25)=A125;
            app.Matriz3(25)=B125;
            
            app.Button_207.BackgroundColor=[0.96,0.96,0.96];
            app.Button_185.BackgroundColor=[0.96,0.96,0.96];
            app.Button_182.BackgroundColor=[0.96,0.96,0.96];
            app.Button_179.BackgroundColor=[0.96,0.96,0.96];
            app.Button_183.BackgroundColor=[1.00,1.00,0.00];
            app.Button_181.BackgroundColor=[0.96,0.96,0.96];
            app.Button_176.BackgroundColor=[0.96,0.96,0.96];
            app.Button_152.BackgroundColor=[0.96,0.96,0.96];
            app.Button_180.BackgroundColor=[0.96,0.96,0.96];
            app.Button_177.BackgroundColor=[0.96,0.96,0.96];
            app.Button_174.BackgroundColor=[0.96,0.96,0.96];
            app.Button_178.BackgroundColor=[0.96,0.96,0.96];
            app.Button_184.BackgroundColor=[0.96,0.96,0.96];
            app.Button_154.BackgroundColor=[0.96,0.96,0.96];
            app.Button_155.BackgroundColor=[0.96,0.96,0.96];
            app.Button_153.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_181
        function Button_181ValueChanged(app, event)
            value = app.Button_181.Value;
            A126=6;
            B126=cellstr('3 ramas (semaforizada) con carril de giro no protegido');
            c126=cellstr('app.Button_181');
            app.Matriz2(25)=c126;
            app.Matriz1(25)=A126;
            app.Matriz3(25)=B126;
            
            app.Button_207.BackgroundColor=[0.96,0.96,0.96];
            app.Button_185.BackgroundColor=[0.96,0.96,0.96];
            app.Button_182.BackgroundColor=[0.96,0.96,0.96];
            app.Button_179.BackgroundColor=[0.96,0.96,0.96];
            app.Button_183.BackgroundColor=[0.96,0.96,0.96];
            app.Button_181.BackgroundColor=[1.00,1.00,0.00];
            app.Button_176.BackgroundColor=[0.96,0.96,0.96];
            app.Button_152.BackgroundColor=[0.96,0.96,0.96];
            app.Button_180.BackgroundColor=[0.96,0.96,0.96];
            app.Button_177.BackgroundColor=[0.96,0.96,0.96];
            app.Button_174.BackgroundColor=[0.96,0.96,0.96];
            app.Button_178.BackgroundColor=[0.96,0.96,0.96];
            app.Button_184.BackgroundColor=[0.96,0.96,0.96];
            app.Button_154.BackgroundColor=[0.96,0.96,0.96];
            app.Button_155.BackgroundColor=[0.96,0.96,0.96];
            app.Button_153.BackgroundColor=[0.96,0.96,0.96];

        end

        % Value changed function: Button_176
        function Button_176ValueChanged(app, event)
            value = app.Button_176.Value;
            A127=7;
            B127=cellstr('4 ramas (no semaforizada) con carril de giro protegido');
            c127=cellstr('app.Button_176');
            app.Matriz2(25)=c127;
            app.Matriz1(25)=A127;
            app.Matriz3(25)=B127;
            
            app.Button_207.BackgroundColor=[0.96,0.96,0.96];
            app.Button_185.BackgroundColor=[0.96,0.96,0.96];
            app.Button_182.BackgroundColor=[0.96,0.96,0.96];
            app.Button_179.BackgroundColor=[0.96,0.96,0.96];
            app.Button_183.BackgroundColor=[0.96,0.96,0.96];
            app.Button_181.BackgroundColor=[0.96,0.96,0.96];
            app.Button_176.BackgroundColor=[1.00,1.00,0.00];
            app.Button_152.BackgroundColor=[0.96,0.96,0.96];
            app.Button_180.BackgroundColor=[0.96,0.96,0.96];
            app.Button_177.BackgroundColor=[0.96,0.96,0.96];
            app.Button_174.BackgroundColor=[0.96,0.96,0.96];
            app.Button_178.BackgroundColor=[0.96,0.96,0.96];
            app.Button_184.BackgroundColor=[0.96,0.96,0.96];
            app.Button_154.BackgroundColor=[0.96,0.96,0.96];
            app.Button_155.BackgroundColor=[0.96,0.96,0.96];
            app.Button_153.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_152
        function Button_152ValueChanged(app, event)
            value = app.Button_152.Value;
            A128=8;
            B128=cellstr('4 ramas (no semaforizada) con carril de giro no protegido');
            c128=cellstr('app.Button_152');
            app.Matriz2(25)=c128;
            app.Matriz1(25)=A128;
            app.Matriz3(25)=B128;
            
            app.Button_207.BackgroundColor=[0.96,0.96,0.96];
            app.Button_185.BackgroundColor=[0.96,0.96,0.96];
            app.Button_182.BackgroundColor=[0.96,0.96,0.96];
            app.Button_179.BackgroundColor=[0.96,0.96,0.96];
            app.Button_183.BackgroundColor=[0.96,0.96,0.96];
            app.Button_181.BackgroundColor=[0.96,0.96,0.96];
            app.Button_176.BackgroundColor=[0.96,0.96,0.96];
            app.Button_152.BackgroundColor=[1.00,1.00,0.00];
            app.Button_180.BackgroundColor=[0.96,0.96,0.96];
            app.Button_177.BackgroundColor=[0.96,0.96,0.96];
            app.Button_174.BackgroundColor=[0.96,0.96,0.96];
            app.Button_178.BackgroundColor=[0.96,0.96,0.96];
            app.Button_184.BackgroundColor=[0.96,0.96,0.96];
            app.Button_154.BackgroundColor=[0.96,0.96,0.96];
            app.Button_155.BackgroundColor=[0.96,0.96,0.96];
            app.Button_153.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_180
        function Button_180ValueChanged(app, event)
            value = app.Button_180.Value;
            A129=9;
            B129=cellstr('4 ramas (semaforizada) con carril de giro protegido');
            c129=cellstr('app.Button_180');
            app.Matriz2(25)=c129;
            app.Matriz1(25)=A129;
            app.Matriz3(25)=B129;
            
            app.Button_207.BackgroundColor=[0.96,0.96,0.96];
            app.Button_185.BackgroundColor=[0.96,0.96,0.96];
            app.Button_182.BackgroundColor=[0.96,0.96,0.96];
            app.Button_179.BackgroundColor=[0.96,0.96,0.96];
            app.Button_183.BackgroundColor=[0.96,0.96,0.96];
            app.Button_181.BackgroundColor=[0.96,0.96,0.96];
            app.Button_176.BackgroundColor=[0.96,0.96,0.96];
            app.Button_152.BackgroundColor=[0.96,0.96,0.96];
            app.Button_180.BackgroundColor=[1.00,1.00,0.00];
            app.Button_177.BackgroundColor=[0.96,0.96,0.96];
            app.Button_174.BackgroundColor=[0.96,0.96,0.96];
            app.Button_178.BackgroundColor=[0.96,0.96,0.96];
            app.Button_184.BackgroundColor=[0.96,0.96,0.96];
            app.Button_154.BackgroundColor=[0.96,0.96,0.96];
            app.Button_155.BackgroundColor=[0.96,0.96,0.96];
            app.Button_153.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_177
        function Button_177ValueChanged(app, event)
            value = app.Button_177.Value;
            A130=10;
            B130=cellstr('4 ramas (semaforizada) con carril de giro no protegido');
            c130=cellstr('app.Button_177');
            app.Matriz2(25)=c130;
            app.Matriz1(25)=A130;
            app.Matriz3(25)=B130;
            
            app.Button_207.BackgroundColor=[0.96,0.96,0.96];
            app.Button_185.BackgroundColor=[0.96,0.96,0.96];
            app.Button_182.BackgroundColor=[0.96,0.96,0.96];
            app.Button_179.BackgroundColor=[0.96,0.96,0.96];
            app.Button_183.BackgroundColor=[0.96,0.96,0.96];
            app.Button_181.BackgroundColor=[0.96,0.96,0.96];
            app.Button_176.BackgroundColor=[0.96,0.96,0.96];
            app.Button_152.BackgroundColor=[0.96,0.96,0.96];
            app.Button_180.BackgroundColor=[0.96,0.96,0.96];
            app.Button_177.BackgroundColor=[1.00,1.00,0.00];
            app.Button_174.BackgroundColor=[0.96,0.96,0.96];
            app.Button_178.BackgroundColor=[0.96,0.96,0.96];
            app.Button_184.BackgroundColor=[0.96,0.96,0.96];
            app.Button_154.BackgroundColor=[0.96,0.96,0.96];
            app.Button_155.BackgroundColor=[0.96,0.96,0.96];
            app.Button_153.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_174
        function Button_174ValueChanged(app, event)
            value = app.Button_174.Value;
            A132=11;
            B132=cellstr('Ninguno');
            c132=cellstr('app.Button_174');
            app.Matriz2(25)=c132;
            app.Matriz1(25)=A132;
            app.Matriz3(25)=B132;
            
            app.Button_207.BackgroundColor=[0.96,0.96,0.96];
            app.Button_185.BackgroundColor=[0.96,0.96,0.96];
            app.Button_182.BackgroundColor=[0.96,0.96,0.96];
            app.Button_179.BackgroundColor=[0.96,0.96,0.96];
            app.Button_183.BackgroundColor=[0.96,0.96,0.96];
            app.Button_181.BackgroundColor=[0.96,0.96,0.96];
            app.Button_176.BackgroundColor=[0.96,0.96,0.96];
            app.Button_152.BackgroundColor=[0.96,0.96,0.96];
            app.Button_180.BackgroundColor=[0.96,0.96,0.96];
            app.Button_177.BackgroundColor=[0.96,0.96,0.96];
            app.Button_174.BackgroundColor=[1.00,1.00,0.00];
            app.Button_178.BackgroundColor=[0.96,0.96,0.96];
            app.Button_184.BackgroundColor=[0.96,0.96,0.96];
            app.Button_154.BackgroundColor=[0.96,0.96,0.96];
            app.Button_155.BackgroundColor=[0.96,0.96,0.96];
            app.Button_153.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_178
        function Button_178ValueChanged(app, event)
            value = app.Button_178.Value;
            A133=12;
            B133=cellstr('Cruce de ferrocarril - pasivo (solo señalización vertical)');
            c133=cellstr('app.Button_178');
            app.Matriz2(25)=c133;
            app.Matriz1(25)=A133;
            app.Matriz3(25)=B133;
            
            app.Button_207.BackgroundColor=[0.96,0.96,0.96];
            app.Button_185.BackgroundColor=[0.96,0.96,0.96];
            app.Button_182.BackgroundColor=[0.96,0.96,0.96];
            app.Button_179.BackgroundColor=[0.96,0.96,0.96];
            app.Button_183.BackgroundColor=[0.96,0.96,0.96];
            app.Button_181.BackgroundColor=[0.96,0.96,0.96];
            app.Button_176.BackgroundColor=[0.96,0.96,0.96];
            app.Button_152.BackgroundColor=[0.96,0.96,0.96];
            app.Button_180.BackgroundColor=[0.96,0.96,0.96];
            app.Button_177.BackgroundColor=[0.96,0.96,0.96];
            app.Button_174.BackgroundColor=[0.96,0.96,0.96];
            app.Button_178.BackgroundColor=[1.00,1.00,0.00];
            app.Button_184.BackgroundColor=[0.96,0.96,0.96];
            app.Button_154.BackgroundColor=[0.96,0.96,0.96];
            app.Button_155.BackgroundColor=[0.96,0.96,0.96];
            app.Button_153.BackgroundColor=[0.96,0.96,0.96];

        end

        % Value changed function: Button_184
        function Button_184ValueChanged(app, event)
            value = app.Button_184.Value;
            A134=13;
            B134=cellstr('Cruce de ferrocarril - activo (semáforo intermitente / pluma)');
            c134=cellstr('app.Button_184');
            app.Matriz2(25)=c134;
            app.Matriz1(25)=A134;
            app.Matriz3(25)=B134;
            
            app.Button_207.BackgroundColor=[0.96,0.96,0.96];
            app.Button_185.BackgroundColor=[0.96,0.96,0.96];
            app.Button_182.BackgroundColor=[0.96,0.96,0.96];
            app.Button_179.BackgroundColor=[0.96,0.96,0.96];
            app.Button_183.BackgroundColor=[0.96,0.96,0.96];
            app.Button_181.BackgroundColor=[0.96,0.96,0.96];
            app.Button_176.BackgroundColor=[0.96,0.96,0.96];
            app.Button_152.BackgroundColor=[0.96,0.96,0.96];
            app.Button_180.BackgroundColor=[0.96,0.96,0.96];
            app.Button_177.BackgroundColor=[0.96,0.96,0.96];
            app.Button_174.BackgroundColor=[0.96,0.96,0.96];
            app.Button_178.BackgroundColor=[0.96,0.96,0.96];
            app.Button_184.BackgroundColor=[1.00,1.00,0.00];
            app.Button_154.BackgroundColor=[0.96,0.96,0.96];
            app.Button_155.BackgroundColor=[0.96,0.96,0.96];
            app.Button_153.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_154
        function Button_154ValueChanged(app, event)
            value = app.Button_154.Value;
            A135=14;
            B135=cellstr('Punto de cruce de mediana - informal');
            c135=cellstr('app.Button_154');
            app.Matriz2(25)=c135;
            app.Matriz1(25)=A135;
            app.Matriz3(25)=B135;
            
            app.Button_207.BackgroundColor=[0.96,0.96,0.96];
            app.Button_185.BackgroundColor=[0.96,0.96,0.96];
            app.Button_182.BackgroundColor=[0.96,0.96,0.96];
            app.Button_179.BackgroundColor=[0.96,0.96,0.96];
            app.Button_183.BackgroundColor=[0.96,0.96,0.96];
            app.Button_181.BackgroundColor=[0.96,0.96,0.96];
            app.Button_176.BackgroundColor=[0.96,0.96,0.96];
            app.Button_152.BackgroundColor=[0.96,0.96,0.96];
            app.Button_180.BackgroundColor=[0.96,0.96,0.96];
            app.Button_177.BackgroundColor=[0.96,0.96,0.96];
            app.Button_174.BackgroundColor=[0.96,0.96,0.96];
            app.Button_178.BackgroundColor=[0.96,0.96,0.96];
            app.Button_184.BackgroundColor=[0.96,0.96,0.96];
            app.Button_154.BackgroundColor=[1.00,1.00,0.00];
            app.Button_155.BackgroundColor=[0.96,0.96,0.96];
            app.Button_153.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_155
        function Button_155ValueChanged(app, event)
            value = app.Button_155.Value;
            A136=15;
            B136=cellstr('Punto de cruce de mediana - formal');
            c136=cellstr('app.Button_155');
            app.Matriz2(25)=c136;
            app.Matriz1(25)=A136;
            app.Matriz3(25)=B136;
            
            app.Button_207.BackgroundColor=[0.96,0.96,0.96];
            app.Button_185.BackgroundColor=[0.96,0.96,0.96];
            app.Button_182.BackgroundColor=[0.96,0.96,0.96];
            app.Button_179.BackgroundColor=[0.96,0.96,0.96];
            app.Button_183.BackgroundColor=[0.96,0.96,0.96];
            app.Button_181.BackgroundColor=[0.96,0.96,0.96];
            app.Button_176.BackgroundColor=[0.96,0.96,0.96];
            app.Button_152.BackgroundColor=[0.96,0.96,0.96];
            app.Button_180.BackgroundColor=[0.96,0.96,0.96];
            app.Button_177.BackgroundColor=[0.96,0.96,0.96];
            app.Button_174.BackgroundColor=[0.96,0.96,0.96];
            app.Button_178.BackgroundColor=[0.96,0.96,0.96];
            app.Button_184.BackgroundColor=[0.96,0.96,0.96];
            app.Button_154.BackgroundColor=[0.96,0.96,0.96];
            app.Button_155.BackgroundColor=[1.00,1.00,0.00];
            app.Button_153.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_153
        function Button_153ValueChanged(app, event)
            value = app.Button_153.Value;
            A137=16;
            B137=cellstr('Mini redondel');
            c137=cellstr('app.Button_153');
            app.Matriz2(25)=c137;
            app.Matriz1(25)=A137;
            app.Matriz3(25)=B137;
            
            app.Button_207.BackgroundColor=[0.96,0.96,0.96];
            app.Button_185.BackgroundColor=[0.96,0.96,0.96];
            app.Button_182.BackgroundColor=[0.96,0.96,0.96];
            app.Button_179.BackgroundColor=[0.96,0.96,0.96];
            app.Button_183.BackgroundColor=[0.96,0.96,0.96];
            app.Button_181.BackgroundColor=[0.96,0.96,0.96];
            app.Button_176.BackgroundColor=[0.96,0.96,0.96];
            app.Button_152.BackgroundColor=[0.96,0.96,0.96];
            app.Button_180.BackgroundColor=[0.96,0.96,0.96];
            app.Button_177.BackgroundColor=[0.96,0.96,0.96];
            app.Button_174.BackgroundColor=[0.96,0.96,0.96];
            app.Button_178.BackgroundColor=[0.96,0.96,0.96];
            app.Button_184.BackgroundColor=[0.96,0.96,0.96];
            app.Button_154.BackgroundColor=[0.96,0.96,0.96];
            app.Button_155.BackgroundColor=[0.96,0.96,0.96];
            app.Button_153.BackgroundColor=[1.00,1.00,0.00];


        end

        % Value changed function: Button_569
        function Button_569ValueChanged(app, event)
            value = app.Button_569.Value;
            A138=1;
            B138=cellstr('Ausente');
            c138=cellstr('app.Button_569');
            app.Matriz2(26)=c138;
            app.Matriz1(26)=A138;
            app.Matriz3(26)=B138;
            
            app.Button_569.BackgroundColor=[1.00,1.00,0.00];
            app.Button_568.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_568
        function Button_568ValueChanged(app, event)
            value = app.Button_568.Value;
            A139=2;
            B139=cellstr('Presente');
            c139=cellstr('app.Button_568');
            app.Matriz2(26)=c139;
            app.Matriz1(26)=A139;
            app.Matriz3(26)=B139;
            
            app.Button_569.BackgroundColor=[0.96,0.96,0.96];
            app.Button_568.BackgroundColor=[1.00,1.00,0.00];
        end

        % Callback function
        function Button_712ValueChanged(app, event)
            value = app.Button_712.Value;
            A139_1=3;
            B139_1=cellstr('No aplica');
            app.Matriz1(26)=A139_1;
        end

        % Value changed function: Button_189
        function Button_189ValueChanged(app, event)
            value = app.Button_189.Value;
            A140=1;
            B140=cellstr('≥ 15,000 vehículos');
            c140=cellstr('app.Button_189');
            app.Matriz2(27)=c140;
            app.Matriz1(27)=A140;
            app.Matriz3(27)=B140;
            
            app.Button_189.BackgroundColor=[1.00,1.00,0.00];
            app.Button_198.BackgroundColor=[0.96,0.96,0.96];
            app.Button_210.BackgroundColor=[0.96,0.96,0.96];
            app.Button_211.BackgroundColor=[0.96,0.96,0.96];
            app.Button_212.BackgroundColor=[0.96,0.96,0.96];
            app.Button_208.BackgroundColor=[0.96,0.96,0.96];
            app.Button_209.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_198
        function Button_198ValueChanged(app, event)
            value = app.Button_198.Value;
            A141=2;
            B141=cellstr('10,000 a 15,000 vehículos');
            c141=cellstr('app.Button_198');
            app.Matriz2(27)=c141;
            app.Matriz1(27)=A141;
            app.Matriz3(27)=B141;
            
            app.Button_189.BackgroundColor=[0.96,0.96,0.96];
            app.Button_198.BackgroundColor=[1.00,1.00,0.00];
            app.Button_210.BackgroundColor=[0.96,0.96,0.96];
            app.Button_211.BackgroundColor=[0.96,0.96,0.96];
            app.Button_212.BackgroundColor=[0.96,0.96,0.96];
            app.Button_208.BackgroundColor=[0.96,0.96,0.96];
            app.Button_209.BackgroundColor=[0.96,0.96,0.96];


        end

        % Value changed function: Button_210
        function Button_210ValueChanged(app, event)
            value = app.Button_210.Value;
            A142=3;
            B142=cellstr('5,000 a 10,000 vehículos');
            c142=cellstr('app.Button_210');
            app.Matriz2(27)=c142;
            app.Matriz1(27)=A142;
            app.Matriz3(27)=B142;
            
            app.Button_189.BackgroundColor=[0.96,0.96,0.96];
            app.Button_198.BackgroundColor=[0.96,0.96,0.96];
            app.Button_210.BackgroundColor=[1.00,1.00,0.00];
            app.Button_211.BackgroundColor=[0.96,0.96,0.96];
            app.Button_212.BackgroundColor=[0.96,0.96,0.96];
            app.Button_208.BackgroundColor=[0.96,0.96,0.96];
            app.Button_209.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_211
        function Button_211ValueChanged(app, event)
            value = app.Button_211.Value;
            A143=4;
            B143=cellstr('1,000 a 5,000 vehículos');
            c143=cellstr('app.Button_211');
            app.Matriz2(27)=c143;
            app.Matriz1(27)=A143;
            app.Matriz3(27)=B143;
            
            app.Button_189.BackgroundColor=[0.96,0.96,0.96];
            app.Button_198.BackgroundColor=[0.96,0.96,0.96];
            app.Button_210.BackgroundColor=[0.96,0.96,0.96];
            app.Button_211.BackgroundColor=[1.00,1.00,0.00];
            app.Button_212.BackgroundColor=[0.96,0.96,0.96];
            app.Button_208.BackgroundColor=[0.96,0.96,0.96];
            app.Button_209.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_212
        function Button_212ValueChanged(app, event)
            value = app.Button_212.Value;
            A144=5;
            B144=cellstr('100 a 1,000 vehículos');
            c144=cellstr('app.Button_212');
            app.Matriz2(27)=c144;
            app.Matriz1(27)=A144;
            app.Matriz3(27)=B144;
            
            app.Button_189.BackgroundColor=[0.96,0.96,0.96];
            app.Button_198.BackgroundColor=[0.96,0.96,0.96];
            app.Button_210.BackgroundColor=[0.96,0.96,0.96];
            app.Button_211.BackgroundColor=[0.96,0.96,0.96];
            app.Button_212.BackgroundColor=[1.00,1.00,0.00];
            app.Button_208.BackgroundColor=[0.96,0.96,0.96];
            app.Button_209.BackgroundColor=[0.96,0.96,0.96];

        end

        % Value changed function: Button_208
        function Button_208ValueChanged(app, event)
            value = app.Button_208.Value;
            A145=6;
            B145=cellstr('1 a 100 vehículos');
            c145=cellstr('app.Button_208');
            app.Matriz2(27)=c145;
            app.Matriz1(27)=A145;
            app.Matriz3(27)=B145;
            
            app.Button_189.BackgroundColor=[0.96,0.96,0.96];
            app.Button_198.BackgroundColor=[0.96,0.96,0.96];
            app.Button_210.BackgroundColor=[0.96,0.96,0.96];
            app.Button_211.BackgroundColor=[0.96,0.96,0.96];
            app.Button_212.BackgroundColor=[0.96,0.96,0.96];
            app.Button_208.BackgroundColor=[1.00,1.00,0.00];
            app.Button_209.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_209
        function Button_209ValueChanged(app, event)
            value = app.Button_209.Value;
            A146=7;
            B146=cellstr('Ninguno');
            c146=cellstr('app.Button_209');
            app.Matriz2(27)=c146;
            app.Matriz1(27)=A146;
            app.Matriz3(27)=B146;
            
            app.Button_189.BackgroundColor=[0.96,0.96,0.96];
            app.Button_198.BackgroundColor=[0.96,0.96,0.96];
            app.Button_210.BackgroundColor=[0.96,0.96,0.96];
            app.Button_211.BackgroundColor=[0.96,0.96,0.96];
            app.Button_212.BackgroundColor=[0.96,0.96,0.96];
            app.Button_208.BackgroundColor=[0.96,0.96,0.96];
            app.Button_209.BackgroundColor=[1.00,1.00,0.00];
        end

        % Value changed function: Button_214
        function Button_214ValueChanged(app, event)
            value = app.Button_214.Value;
            A147=1;
            B147=cellstr('Adecuada');
            c147=cellstr('app.Button_214');
            app.Matriz2(28)=c147;
            app.Matriz1(28)=A147;
            app.Matriz3(28)=B147;
            
            app.Button_214.BackgroundColor=[1.00,1.00,0.00];
            app.Button_213.BackgroundColor=[0.96,0.96,0.96];
            app.Button_215.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_213
        function Button_213ValueChanged(app, event)
            value = app.Button_213.Value;
            A148=2;
            B148=cellstr('Deficiente');
            c148=cellstr('app.Button_213');
            app.Matriz2(28)=c148;
            app.Matriz1(28)=A148;
            app.Matriz3(28)=B148;
            
            app.Button_214.BackgroundColor=[0.96,0.96,0.96];
            app.Button_213.BackgroundColor=[1.00,1.00,0.00];
            app.Button_215.BackgroundColor=[0.96,0.96,0.96];

        end

        % Value changed function: Button_215
        function Button_215ValueChanged(app, event)
            value = app.Button_215.Value;
            A149=3;
            B149=cellstr('No aplica');
            c149=cellstr('app.Button_215');
            app.Matriz2(28)=c149;
            app.Matriz1(28)=A149;
            app.Matriz3(28)=B149;
            
            app.Button_214.BackgroundColor=[0.96,0.96,0.96];
            app.Button_213.BackgroundColor=[0.96,0.96,0.96];
            app.Button_215.BackgroundColor=[1.00,1.00,0.00];

        end

        % Value changed function: Button_169
        function Button_169ValueChanged(app, event)
            value = app.Button_169.Value;
            A150=1;
            B150=cellstr('Acceso comercial 1+');
            c150=cellstr('app.Button_169');
            app.Matriz2(29)=c150;
            app.Matriz1(29)=A150;
            app.Matriz3(29)=B150;
            
            app.Button_169.BackgroundColor=[1.00,1.00,0.00];
            app.Button_170.BackgroundColor=[0.96,0.96,0.96];
            app.Button_171.BackgroundColor=[0.96,0.96,0.96];
            app.Button_216.BackgroundColor=[0.96,0.96,0.96];

        end

        % Value changed function: Button_170
        function Button_170ValueChanged(app, event)
            value = app.Button_170.Value;
            A151=2;
            B151=cellstr('Acceso residencial 3+');
            c151=cellstr('app.Button_170');
            app.Matriz2(29)=c151;
            app.Matriz1(29)=A151;
            app.Matriz3(29)=B151;
            
            app.Button_169.BackgroundColor=[0.96,0.96,0.96];
            app.Button_170.BackgroundColor=[1.00,1.00,0.00];
            app.Button_171.BackgroundColor=[0.96,0.96,0.96];
            app.Button_216.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_171
        function Button_171ValueChanged(app, event)
            value = app.Button_171.Value;
            A152=3;
            B152=cellstr('Acceso residencial 1 o 2');
            c152=cellstr('app.Button_171');
            app.Matriz2(29)=c152;
            app.Matriz1(29)=A152;
            app.Matriz3(29)=B152;
            
            app.Button_169.BackgroundColor=[0.96,0.96,0.96];
            app.Button_170.BackgroundColor=[0.96,0.96,0.96];
            app.Button_171.BackgroundColor=[1.00,1.00,0.00];
            app.Button_216.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_216
        function Button_216ValueChanged(app, event)
            value = app.Button_216.Value;
            A153=4;
            B153=cellstr('Ninguno');
            c153=cellstr('app.Button_216');
            app.Matriz2(29)=c153;
            app.Matriz1(29)=A153;
            app.Matriz3(29)=B153;
            
            app.Button_169.BackgroundColor=[0.96,0.96,0.96];
            app.Button_170.BackgroundColor=[0.96,0.96,0.96];
            app.Button_171.BackgroundColor=[0.96,0.96,0.96];
            app.Button_216.BackgroundColor=[1.00,1.00,0.00];

        end

        % Value changed function: Button_2
        function Button_2ValueChanged(app, event)
            value = app.Button_2.Value;
            a2=2;
            B2=cellstr('1 a < 5 m');
            c2=cellstr('app.Button_2');
            app.Matriz2(1)=c2;
            app.Matriz1(1)=a2;
            app.Matriz3(1)=B2;
            
            app.Button.BackgroundColor=[0.96,0.96,0.96];
            app.Button_2.BackgroundColor=[1.00,1.00,0.00];
            app.Button_3.BackgroundColor=[0.96,0.96,0.96];
            app.Button_4.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_3
        function Button_3ValueChanged(app, event)
            value = app.Button_3.Value;
            a3=3;
            B3=cellstr('5 a < 10 m');
            c3=cellstr('app.Button_3');
            app.Matriz2(1)=c3;
            app.Matriz1(1)=a3;
            app.Matriz3(1)=B3;
            
            app.Button.BackgroundColor=[0.96,0.96,0.96];
            app.Button_2.BackgroundColor=[0.96,0.96,0.96];
            app.Button_3.BackgroundColor=[1.00,1.00,0.00];
            app.Button_4.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_4
        function Button_4ValueChanged(app, event)
            value = app.Button_4.Value;
            A4=4;
            B4=cellstr('≥ 10 m');
            c4=cellstr('app.Button_4');
            app.Matriz2(1)=c4;
            app.Matriz1(1)=A4;
            app.Matriz3(1)=B4;
            
            app.Button.BackgroundColor=[0.96,0.96,0.96];
            app.Button_2.BackgroundColor=[0.96,0.96,0.96];
            app.Button_3.BackgroundColor=[0.96,0.96,0.96];
            app.Button_4.BackgroundColor=[1.00,1.00,0.00];
        end

        % Value changed function: Button_5
        function Button_5ValueChanged(app, event)
            value = app.Button_5.Value;
            A22=1;
            B22=cellstr('0 a < 1 m');
            c22=cellstr('app.Button_5');
            app.Matriz2(3)=c22;
            app.Matriz1(3)=A22;
            app.Matriz3(3)=B22;
            
            app.Button_5.BackgroundColor=[1.00,1.00,0.00];
            app.Button_6.BackgroundColor=[0.96,0.96,0.96];
            app.Button_7.BackgroundColor=[0.96,0.96,0.96];
            app.Button_8.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_6
        function Button_6ValueChanged(app, event)
            value = app.Button_6.Value;
            A23=2;
            B23=cellstr('1 a < 5 m');
            c23=cellstr('app.Button_6');
            app.Matriz2(3)=c23;
            app.Matriz1(3)=A23;
            app.Matriz3(3)=B23;
            
            app.Button_5.BackgroundColor=[0.96,0.96,0.96];
            app.Button_6.BackgroundColor=[1.00,1.00,0.00];
            app.Button_7.BackgroundColor=[0.96,0.96,0.96];
            app.Button_8.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_7
        function Button_7ValueChanged(app, event)
            value = app.Button_7.Value;
            A24=3;
            B24=cellstr('5 a < 10 m');
            c24=cellstr('app.Button_7');
            app.Matriz2(3)=c24;
            app.Matriz1(3)=A24;
            app.Matriz3(3)=B24;
            
            app.Button_5.BackgroundColor=[0.96,0.96,0.96];
            app.Button_6.BackgroundColor=[0.96,0.96,0.96];
            app.Button_7.BackgroundColor=[1.00,1.00,0.00];
            app.Button_8.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_8
        function Button_8ValueChanged(app, event)
            value = app.Button_8.Value;
            A25=4;
            B25=cellstr('≥ 10 m');
            c25=cellstr('app.Button_8');
            app.Matriz2(3)=c25;
            app.Matriz1(3)=A25;
            app.Matriz3(3)=B25;
            
            app.Button_5.BackgroundColor=[0.96,0.96,0.96];
            app.Button_6.BackgroundColor=[0.96,0.96,0.96];
            app.Button_7.BackgroundColor=[0.96,0.96,0.96];
            app.Button_8.BackgroundColor=[1.00,1.00,0.00];

        end

        % Value changed function: Button_530
        function Button_530ValueChanged(app, event)
            value = app.Button_530.Value;
            A5=1;
            B5=cellstr('Barrera de seguridad – metal');
            c5=cellstr('app.Button_530');
            app.Matriz2(2)=c5;
            app.Matriz1(2)=A5;
            app.Matriz3(2)=B5;
            
            app.Button_530.BackgroundColor=[1.00,1.00,0.00];
            app.Button_529.BackgroundColor=[0.96,0.96,0.96];
            app.Button_533.BackgroundColor=[0.96,0.96,0.96];
            app.Button_532.BackgroundColor=[0.96,0.96,0.96];
            app.Button_526.BackgroundColor=[0.96,0.96,0.96];
            app.Button_525.BackgroundColor=[0.96,0.96,0.96];
            app.Button_534.BackgroundColor=[0.96,0.96,0.96];
            app.Button_524.BackgroundColor=[0.96,0.96,0.96];
            app.Button_523.BackgroundColor=[0.96,0.96,0.96];
            app.Button_519.BackgroundColor=[0.96,0.96,0.96];
            app.Button_520.BackgroundColor=[0.96,0.96,0.96];
            app.Button_521.BackgroundColor=[0.96,0.96,0.96];
            app.Button_527.BackgroundColor=[0.96,0.96,0.96];
            app.Button_528.BackgroundColor=[0.96,0.96,0.96];
            app.Button_522.BackgroundColor=[0.96,0.96,0.96];
            app.Button_535.BackgroundColor=[0.96,0.96,0.96];
            app.Button_531.BackgroundColor=[0.96,0.96,0.96];
            
        end

        % Value changed function: Button_529
        function Button_529ValueChanged(app, event)
            value = app.Button_529.Value;
            A6=2;
            B6=cellstr('Barrera de seguridad – hormigón');
            c6=cellstr('app.Button_529');
            app.Matriz2(2)=c6;
            app.Matriz1(2)=A6;
            app.Matriz3(2)=B6;
            
            app.Button_530.BackgroundColor=[0.96,0.96,0.96];
            app.Button_529.BackgroundColor=[1.00,1.00,0.00];
            app.Button_533.BackgroundColor=[0.96,0.96,0.96];
            app.Button_532.BackgroundColor=[0.96,0.96,0.96];
            app.Button_526.BackgroundColor=[0.96,0.96,0.96];
            app.Button_525.BackgroundColor=[0.96,0.96,0.96];
            app.Button_534.BackgroundColor=[0.96,0.96,0.96];
            app.Button_524.BackgroundColor=[0.96,0.96,0.96];
            app.Button_523.BackgroundColor=[0.96,0.96,0.96];
            app.Button_519.BackgroundColor=[0.96,0.96,0.96];
            app.Button_520.BackgroundColor=[0.96,0.96,0.96];
            app.Button_521.BackgroundColor=[0.96,0.96,0.96];
            app.Button_527.BackgroundColor=[0.96,0.96,0.96];
            app.Button_528.BackgroundColor=[0.96,0.96,0.96];
            app.Button_522.BackgroundColor=[0.96,0.96,0.96];
            app.Button_535.BackgroundColor=[0.96,0.96,0.96];
            app.Button_531.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_533
        function Button_533ValueChanged(app, event)
            value = app.Button_533.Value;
            A7=3;
            B7=cellstr('Barrera de seguridad – amigable para motocicletas');
            c7=cellstr('app.Button_533');
            app.Matriz2(2)=c7;
            app.Matriz1(2)=A7;
            app.Matriz3(2)=B7;
            
            app.Button_530.BackgroundColor=[0.96,0.96,0.96];
            app.Button_529.BackgroundColor=[0.96,0.96,0.96];
            app.Button_533.BackgroundColor=[1.00,1.00,0.00];
            app.Button_532.BackgroundColor=[0.96,0.96,0.96];
            app.Button_526.BackgroundColor=[0.96,0.96,0.96];
            app.Button_525.BackgroundColor=[0.96,0.96,0.96];
            app.Button_534.BackgroundColor=[0.96,0.96,0.96];
            app.Button_524.BackgroundColor=[0.96,0.96,0.96];
            app.Button_523.BackgroundColor=[0.96,0.96,0.96];
            app.Button_519.BackgroundColor=[0.96,0.96,0.96];
            app.Button_520.BackgroundColor=[0.96,0.96,0.96];
            app.Button_521.BackgroundColor=[0.96,0.96,0.96];
            app.Button_527.BackgroundColor=[0.96,0.96,0.96];
            app.Button_528.BackgroundColor=[0.96,0.96,0.96];
            app.Button_522.BackgroundColor=[0.96,0.96,0.96];
            app.Button_535.BackgroundColor=[0.96,0.96,0.96];
            app.Button_531.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_532
        function Button_532ValueChanged(app, event)
            value = app.Button_532.Value;
            A8=4;
            B8=cellstr('Barrera de seguridad – cable de acero');
            c8=cellstr('app.Button_532');
            app.Matriz2(2)=c8;
            app.Matriz1(2)=A8;
            app.Matriz3(2)=B8;
            
            app.Button_530.BackgroundColor=[0.96,0.96,0.96];
            app.Button_529.BackgroundColor=[0.96,0.96,0.96];
            app.Button_533.BackgroundColor=[0.96,0.96,0.96];
            app.Button_532.BackgroundColor=[1.00,1.00,0.00];
            app.Button_526.BackgroundColor=[0.96,0.96,0.96];
            app.Button_525.BackgroundColor=[0.96,0.96,0.96];
            app.Button_534.BackgroundColor=[0.96,0.96,0.96];
            app.Button_524.BackgroundColor=[0.96,0.96,0.96];
            app.Button_523.BackgroundColor=[0.96,0.96,0.96];
            app.Button_519.BackgroundColor=[0.96,0.96,0.96];
            app.Button_520.BackgroundColor=[0.96,0.96,0.96];
            app.Button_521.BackgroundColor=[0.96,0.96,0.96];
            app.Button_527.BackgroundColor=[0.96,0.96,0.96];
            app.Button_528.BackgroundColor=[0.96,0.96,0.96];
            app.Button_522.BackgroundColor=[0.96,0.96,0.96];
            app.Button_535.BackgroundColor=[0.96,0.96,0.96];
            app.Button_531.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_526
        function Button_526ValueChanged(app, event)
            value = app.Button_526.Value;
            A9=5;
            B9=cellstr('Cara vertical agresiva en el talud en corte');
            c9=cellstr('app.Button_526');
            app.Matriz2(2)=c9;
            app.Matriz1(2)=A9;
            app.Matriz3(2)=B9;
            
            app.Button_530.BackgroundColor=[0.96,0.96,0.96];
            app.Button_529.BackgroundColor=[0.96,0.96,0.96];
            app.Button_533.BackgroundColor=[0.96,0.96,0.96];
            app.Button_532.BackgroundColor=[0.96,0.96,0.96];
            app.Button_526.BackgroundColor=[1.00,1.00,0.00];
            app.Button_525.BackgroundColor=[0.96,0.96,0.96];
            app.Button_534.BackgroundColor=[0.96,0.96,0.96];
            app.Button_524.BackgroundColor=[0.96,0.96,0.96];
            app.Button_523.BackgroundColor=[0.96,0.96,0.96];
            app.Button_519.BackgroundColor=[0.96,0.96,0.96];
            app.Button_520.BackgroundColor=[0.96,0.96,0.96];
            app.Button_521.BackgroundColor=[0.96,0.96,0.96];
            app.Button_527.BackgroundColor=[0.96,0.96,0.96];
            app.Button_528.BackgroundColor=[0.96,0.96,0.96];
            app.Button_522.BackgroundColor=[0.96,0.96,0.96];
            app.Button_535.BackgroundColor=[0.96,0.96,0.96];
            app.Button_531.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_525
        function Button_525ValueChanged(app, event)
            value = app.Button_525.Value;
            A10=6;
            B10=cellstr('Pendiente ascendente del talud en corte (15° a 75°)');
            c10=cellstr('app.Button_525');
            app.Matriz2(2)=c10;
            app.Matriz1(2)=A10;
            app.Matriz3(2)=B10;
            
            app.Button_530.BackgroundColor=[0.96,0.96,0.96];
            app.Button_529.BackgroundColor=[0.96,0.96,0.96];
            app.Button_533.BackgroundColor=[0.96,0.96,0.96];
            app.Button_532.BackgroundColor=[0.96,0.96,0.96];
            app.Button_526.BackgroundColor=[0.96,0.96,0.96];
            app.Button_525.BackgroundColor=[1.00,1.00,0.00];
            app.Button_534.BackgroundColor=[0.96,0.96,0.96];
            app.Button_524.BackgroundColor=[0.96,0.96,0.96];
            app.Button_523.BackgroundColor=[0.96,0.96,0.96];
            app.Button_519.BackgroundColor=[0.96,0.96,0.96];
            app.Button_520.BackgroundColor=[0.96,0.96,0.96];
            app.Button_521.BackgroundColor=[0.96,0.96,0.96];
            app.Button_527.BackgroundColor=[0.96,0.96,0.96];
            app.Button_528.BackgroundColor=[0.96,0.96,0.96];
            app.Button_522.BackgroundColor=[0.96,0.96,0.96];
            app.Button_535.BackgroundColor=[0.96,0.96,0.96];
            app.Button_531.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_534
        function Button_534ValueChanged(app, event)
            value = app.Button_534.Value;
            A11=7;
            B11=cellstr('Pendiente empinada del talud en corte (> 75°)');
            c11=cellstr('app.Button_534');
            app.Matriz2(2)=c11;
            app.Matriz1(2)=A11;
            app.Matriz3(2)=B11;
            
            app.Button_530.BackgroundColor=[0.96,0.96,0.96];
            app.Button_529.BackgroundColor=[0.96,0.96,0.96];
            app.Button_533.BackgroundColor=[0.96,0.96,0.96];
            app.Button_532.BackgroundColor=[0.96,0.96,0.96];
            app.Button_526.BackgroundColor=[0.96,0.96,0.96];
            app.Button_525.BackgroundColor=[0.96,0.96,0.96];
            app.Button_534.BackgroundColor=[1.00,1.00,0.00];
            app.Button_524.BackgroundColor=[0.96,0.96,0.96];
            app.Button_523.BackgroundColor=[0.96,0.96,0.96];
            app.Button_519.BackgroundColor=[0.96,0.96,0.96];
            app.Button_520.BackgroundColor=[0.96,0.96,0.96];
            app.Button_521.BackgroundColor=[0.96,0.96,0.96];
            app.Button_527.BackgroundColor=[0.96,0.96,0.96];
            app.Button_528.BackgroundColor=[0.96,0.96,0.96];
            app.Button_522.BackgroundColor=[0.96,0.96,0.96];
            app.Button_535.BackgroundColor=[0.96,0.96,0.96];
            app.Button_531.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_524
        function Button_524ValueChanged(app, event)
            value = app.Button_524.Value;
            A12=8;
            B12=cellstr('Cuneta profunda');
            c12=cellstr('app.Button_524');
            app.Matriz2(2)=c12;
            app.Matriz1(2)=A12;
            app.Matriz3(2)=B12;
            
            app.Button_530.BackgroundColor=[0.96,0.96,0.96];
            app.Button_529.BackgroundColor=[0.96,0.96,0.96];
            app.Button_533.BackgroundColor=[0.96,0.96,0.96];
            app.Button_532.BackgroundColor=[0.96,0.96,0.96];
            app.Button_526.BackgroundColor=[0.96,0.96,0.96];
            app.Button_525.BackgroundColor=[0.96,0.96,0.96];
            app.Button_534.BackgroundColor=[0.96,0.96,0.96];
            app.Button_524.BackgroundColor=[1.00,1.00,0.00];
            app.Button_523.BackgroundColor=[0.96,0.96,0.96];
            app.Button_519.BackgroundColor=[0.96,0.96,0.96];
            app.Button_520.BackgroundColor=[0.96,0.96,0.96];
            app.Button_521.BackgroundColor=[0.96,0.96,0.96];
            app.Button_527.BackgroundColor=[0.96,0.96,0.96];
            app.Button_528.BackgroundColor=[0.96,0.96,0.96];
            app.Button_522.BackgroundColor=[0.96,0.96,0.96];
            app.Button_535.BackgroundColor=[0.96,0.96,0.96];
            app.Button_531.BackgroundColor=[0.96,0.96,0.96];

        end

        % Value changed function: Button_523
        function Button_523ValueChanged(app, event)
            value = app.Button_523.Value;
            A13=9;
            B13=cellstr('Terraplén');
            c13=cellstr('app.Button_523');
            app.Matriz2(2)=c13;
            app.Matriz1(2)=A13;
            app.Matriz3(2)=B13;
            
            app.Button_530.BackgroundColor=[0.96,0.96,0.96];
            app.Button_529.BackgroundColor=[0.96,0.96,0.96];
            app.Button_533.BackgroundColor=[0.96,0.96,0.96];
            app.Button_532.BackgroundColor=[0.96,0.96,0.96];
            app.Button_526.BackgroundColor=[0.96,0.96,0.96];
            app.Button_525.BackgroundColor=[0.96,0.96,0.96];
            app.Button_534.BackgroundColor=[0.96,0.96,0.96];
            app.Button_524.BackgroundColor=[0.96,0.96,0.96];
            app.Button_523.BackgroundColor=[1.00,1.00,0.00];
            app.Button_519.BackgroundColor=[0.96,0.96,0.96];
            app.Button_520.BackgroundColor=[0.96,0.96,0.96];
            app.Button_521.BackgroundColor=[0.96,0.96,0.96];
            app.Button_527.BackgroundColor=[0.96,0.96,0.96];
            app.Button_528.BackgroundColor=[0.96,0.96,0.96];
            app.Button_522.BackgroundColor=[0.96,0.96,0.96];
            app.Button_535.BackgroundColor=[0.96,0.96,0.96];
            app.Button_531.BackgroundColor=[0.96,0.96,0.96];

        end

        % Value changed function: Button_519
        function Button_519ValueChanged(app, event)
            value = app.Button_519.Value;
            A14=10;
            B14=cellstr('Precipicio');
            c14=cellstr('app.Button_519');
            app.Matriz2(2)=c14;
            app.Matriz1(2)=A14;
            app.Matriz3(2)=B14;
            
            app.Button_530.BackgroundColor=[0.96,0.96,0.96];
            app.Button_529.BackgroundColor=[0.96,0.96,0.96];
            app.Button_533.BackgroundColor=[0.96,0.96,0.96];
            app.Button_532.BackgroundColor=[0.96,0.96,0.96];
            app.Button_526.BackgroundColor=[0.96,0.96,0.96];
            app.Button_525.BackgroundColor=[0.96,0.96,0.96];
            app.Button_534.BackgroundColor=[0.96,0.96,0.96];
            app.Button_524.BackgroundColor=[0.96,0.96,0.96];
            app.Button_523.BackgroundColor=[0.96,0.96,0.96];
            app.Button_519.BackgroundColor=[1.00,1.00,0.00];
            app.Button_520.BackgroundColor=[0.96,0.96,0.96];
            app.Button_521.BackgroundColor=[0.96,0.96,0.96];
            app.Button_527.BackgroundColor=[0.96,0.96,0.96];
            app.Button_528.BackgroundColor=[0.96,0.96,0.96];
            app.Button_522.BackgroundColor=[0.96,0.96,0.96];
            app.Button_535.BackgroundColor=[0.96,0.96,0.96];
            app.Button_531.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_520
        function Button_520ValueChanged(app, event)
            value = app.Button_520.Value;
            A15=11;
            B15=cellstr('Árbol (≥ 10 cm de diámetro)');
            c15=cellstr('app.Button_520');
            app.Matriz2(2)=c15;
            app.Matriz1(2)=A15;
            app.Matriz3(2)=B15;
            
            app.Button_530.BackgroundColor=[0.96,0.96,0.96];
            app.Button_529.BackgroundColor=[0.96,0.96,0.96];
            app.Button_533.BackgroundColor=[0.96,0.96,0.96];
            app.Button_532.BackgroundColor=[0.96,0.96,0.96];
            app.Button_526.BackgroundColor=[0.96,0.96,0.96];
            app.Button_525.BackgroundColor=[0.96,0.96,0.96];
            app.Button_534.BackgroundColor=[0.96,0.96,0.96];
            app.Button_524.BackgroundColor=[0.96,0.96,0.96];
            app.Button_523.BackgroundColor=[0.96,0.96,0.96];
            app.Button_519.BackgroundColor=[0.96,0.96,0.96];
            app.Button_520.BackgroundColor=[1.00,1.00,0.00];
            app.Button_521.BackgroundColor=[0.96,0.96,0.96];
            app.Button_527.BackgroundColor=[0.96,0.96,0.96];
            app.Button_528.BackgroundColor=[0.96,0.96,0.96];
            app.Button_522.BackgroundColor=[0.96,0.96,0.96];
            app.Button_535.BackgroundColor=[0.96,0.96,0.96];
            app.Button_531.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_521
        function Button_521ValueChanged(app, event)
            value = app.Button_521.Value;
            A16=12;
            B16=cellstr('Letrero / poste / barra rígidos (≥ 10 cm de diámetro)');
            c16=cellstr('app.Button_521');
            app.Matriz2(2)=c16;
            app.Matriz1(2)=A16;
            app.Matriz3(2)=B16;
            
            app.Button_530.BackgroundColor=[0.96,0.96,0.96];
            app.Button_529.BackgroundColor=[0.96,0.96,0.96];
            app.Button_533.BackgroundColor=[0.96,0.96,0.96];
            app.Button_532.BackgroundColor=[0.96,0.96,0.96];
            app.Button_526.BackgroundColor=[0.96,0.96,0.96];
            app.Button_525.BackgroundColor=[0.96,0.96,0.96];
            app.Button_534.BackgroundColor=[0.96,0.96,0.96];
            app.Button_524.BackgroundColor=[0.96,0.96,0.96];
            app.Button_523.BackgroundColor=[0.96,0.96,0.96];
            app.Button_519.BackgroundColor=[0.96,0.96,0.96];
            app.Button_520.BackgroundColor=[0.96,0.96,0.96];
            app.Button_521.BackgroundColor=[1.00,1.00,0.00];
            app.Button_527.BackgroundColor=[0.96,0.96,0.96];
            app.Button_528.BackgroundColor=[0.96,0.96,0.96];
            app.Button_522.BackgroundColor=[0.96,0.96,0.96];
            app.Button_535.BackgroundColor=[0.96,0.96,0.96];
            app.Button_531.BackgroundColor=[0.96,0.96,0.96];

        end

        % Value changed function: Button_527
        function Button_527ValueChanged(app, event)
            value = app.Button_527.Value;
            A17=13;
            B17=cellstr('Estructura / puente / edificio rígidos');
            c17=cellstr('app.Button_527');
            app.Matriz2(2)=c17;
            app.Matriz1(2)=A17;
            app.Matriz3(2)=B17;
            
            app.Button_530.BackgroundColor=[0.96,0.96,0.96];
            app.Button_529.BackgroundColor=[0.96,0.96,0.96];
            app.Button_533.BackgroundColor=[0.96,0.96,0.96];
            app.Button_532.BackgroundColor=[0.96,0.96,0.96];
            app.Button_526.BackgroundColor=[0.96,0.96,0.96];
            app.Button_525.BackgroundColor=[0.96,0.96,0.96];
            app.Button_534.BackgroundColor=[0.96,0.96,0.96];
            app.Button_524.BackgroundColor=[0.96,0.96,0.96];
            app.Button_523.BackgroundColor=[0.96,0.96,0.96];
            app.Button_519.BackgroundColor=[0.96,0.96,0.96];
            app.Button_520.BackgroundColor=[0.96,0.96,0.96];
            app.Button_521.BackgroundColor=[0.96,0.96,0.96];
            app.Button_527.BackgroundColor=[1.00,1.00,0.00];
            app.Button_528.BackgroundColor=[0.96,0.96,0.96];
            app.Button_522.BackgroundColor=[0.96,0.96,0.96];
            app.Button_535.BackgroundColor=[0.96,0.96,0.96];
            app.Button_531.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_528
        function Button_528ValueChanged(app, event)
            value = app.Button_528.Value;
            A18=14;
            B18=cellstr('Estructura /edificio colapsable');
            c18=cellstr('app.Button_528');
            app.Matriz2(2)=c18;
            app.Matriz1(2)=A18;
            app.Matriz3(2)=B18;
            
            app.Button_530.BackgroundColor=[0.96,0.96,0.96];
            app.Button_529.BackgroundColor=[0.96,0.96,0.96];
            app.Button_533.BackgroundColor=[0.96,0.96,0.96];
            app.Button_532.BackgroundColor=[0.96,0.96,0.96];
            app.Button_526.BackgroundColor=[0.96,0.96,0.96];
            app.Button_525.BackgroundColor=[0.96,0.96,0.96];
            app.Button_534.BackgroundColor=[0.96,0.96,0.96];
            app.Button_524.BackgroundColor=[0.96,0.96,0.96];
            app.Button_523.BackgroundColor=[0.96,0.96,0.96];
            app.Button_519.BackgroundColor=[0.96,0.96,0.96];
            app.Button_520.BackgroundColor=[0.96,0.96,0.96];
            app.Button_521.BackgroundColor=[0.96,0.96,0.96];
            app.Button_527.BackgroundColor=[0.96,0.96,0.96];
            app.Button_528.BackgroundColor=[1.00,1.00,0.00];
            app.Button_522.BackgroundColor=[0.96,0.96,0.96];
            app.Button_535.BackgroundColor=[0.96,0.96,0.96];
            app.Button_531.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_522
        function Button_522ValueChanged(app, event)
            value = app.Button_522.Value;
            A19=15;
            B19=cellstr('Extremo de barrera desprotegido');
            c19=cellstr('app.Button_522');
            app.Matriz2(2)=c19;
            app.Matriz1(2)=A19;
            app.Matriz3(2)=B19;
            
            app.Button_530.BackgroundColor=[0.96,0.96,0.96];
            app.Button_529.BackgroundColor=[0.96,0.96,0.96];
            app.Button_533.BackgroundColor=[0.96,0.96,0.96];
            app.Button_532.BackgroundColor=[0.96,0.96,0.96];
            app.Button_526.BackgroundColor=[0.96,0.96,0.96];
            app.Button_525.BackgroundColor=[0.96,0.96,0.96];
            app.Button_534.BackgroundColor=[0.96,0.96,0.96];
            app.Button_524.BackgroundColor=[0.96,0.96,0.96];
            app.Button_523.BackgroundColor=[0.96,0.96,0.96];
            app.Button_519.BackgroundColor=[0.96,0.96,0.96];
            app.Button_520.BackgroundColor=[0.96,0.96,0.96];
            app.Button_521.BackgroundColor=[0.96,0.96,0.96];
            app.Button_527.BackgroundColor=[0.96,0.96,0.96];
            app.Button_528.BackgroundColor=[0.96,0.96,0.96];
            app.Button_522.BackgroundColor=[1.00,1.00,0.00];
            app.Button_535.BackgroundColor=[0.96,0.96,0.96];
            app.Button_531.BackgroundColor=[0.96,0.96,0.96];

        end

        % Value changed function: Button_535
        function Button_535ValueChanged(app, event)
            value = app.Button_535.Value;
            A20=16;
            B20=cellstr('Grandes rocas (≥ 20 cm de altura)');
            c20=cellstr('app.Button_535');
            app.Matriz2(2)=c20;
            app.Matriz1(2)=A20;
            app.Matriz3(2)=B20;
            
            app.Button_530.BackgroundColor=[0.96,0.96,0.96];
            app.Button_529.BackgroundColor=[0.96,0.96,0.96];
            app.Button_533.BackgroundColor=[0.96,0.96,0.96];
            app.Button_532.BackgroundColor=[0.96,0.96,0.96];
            app.Button_526.BackgroundColor=[0.96,0.96,0.96];
            app.Button_525.BackgroundColor=[0.96,0.96,0.96];
            app.Button_534.BackgroundColor=[0.96,0.96,0.96];
            app.Button_524.BackgroundColor=[0.96,0.96,0.96];
            app.Button_523.BackgroundColor=[0.96,0.96,0.96];
            app.Button_519.BackgroundColor=[0.96,0.96,0.96];
            app.Button_520.BackgroundColor=[0.96,0.96,0.96];
            app.Button_521.BackgroundColor=[0.96,0.96,0.96];
            app.Button_527.BackgroundColor=[0.96,0.96,0.96];
            app.Button_528.BackgroundColor=[0.96,0.96,0.96];
            app.Button_522.BackgroundColor=[0.96,0.96,0.96];
            app.Button_535.BackgroundColor=[1.00,1.00,0.00];
            app.Button_531.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_531
        function Button_531ValueChanged(app, event)
            value = app.Button_531.Value;
            A21=17;
            B21=cellstr('Ninguno (u objeto > 20 m de la carretera)');
            c21=cellstr('app.Button_531');
            app.Matriz2(2)=c21;
            app.Matriz1(2)=A21;
            app.Matriz3(2)=B21;
            
            app.Button_530.BackgroundColor=[0.96,0.96,0.96];
            app.Button_529.BackgroundColor=[0.96,0.96,0.96];
            app.Button_533.BackgroundColor=[0.96,0.96,0.96];
            app.Button_532.BackgroundColor=[0.96,0.96,0.96];
            app.Button_526.BackgroundColor=[0.96,0.96,0.96];
            app.Button_525.BackgroundColor=[0.96,0.96,0.96];
            app.Button_534.BackgroundColor=[0.96,0.96,0.96];
            app.Button_524.BackgroundColor=[0.96,0.96,0.96];
            app.Button_523.BackgroundColor=[0.96,0.96,0.96];
            app.Button_519.BackgroundColor=[0.96,0.96,0.96];
            app.Button_520.BackgroundColor=[0.96,0.96,0.96];
            app.Button_521.BackgroundColor=[0.96,0.96,0.96];
            app.Button_527.BackgroundColor=[0.96,0.96,0.96];
            app.Button_528.BackgroundColor=[0.96,0.96,0.96];
            app.Button_522.BackgroundColor=[0.96,0.96,0.96];
            app.Button_535.BackgroundColor=[0.96,0.96,0.96];
            app.Button_531.BackgroundColor=[1.00,1.00,0.00];
        end

        % Value changed function: Button_677
        function Button_677ValueChanged(app, event)
            value = app.Button_677.Value;
            A26=1;
            B26=cellstr('Barrera de seguridad – metal');
            c26=cellstr('app.Button_677');
            app.Matriz2(4)=c26;
            app.Matriz1(4)=A26;
            app.Matriz3(4)=B26;
            
            app.Button_677.BackgroundColor=[1.00,1.00,0.00];
            app.Button_676.BackgroundColor=[0.96,0.96,0.96];
            app.Button_679.BackgroundColor=[0.96,0.96,0.96];
            app.Button_678.BackgroundColor=[0.96,0.96,0.96];
            app.Button_670.BackgroundColor=[0.96,0.96,0.96];
            app.Button_671.BackgroundColor=[0.96,0.96,0.96];
            app.Button_680.BackgroundColor=[0.96,0.96,0.96];
            app.Button_672.BackgroundColor=[0.96,0.96,0.96];
            app.Button_673.BackgroundColor=[0.96,0.96,0.96];
            app.Button_666.BackgroundColor=[0.96,0.96,0.96];
            app.Button_667.BackgroundColor=[0.96,0.96,0.96];
            app.Button_668.BackgroundColor=[0.96,0.96,0.96];
            app.Button_674.BackgroundColor=[0.96,0.96,0.96];
            app.Button_675.BackgroundColor=[0.96,0.96,0.96];
            app.Button_669.BackgroundColor=[0.96,0.96,0.96];
            app.Button_682.BackgroundColor=[0.96,0.96,0.96];
            app.Button_681.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_676
        function Button_676ValueChanged(app, event)
            value = app.Button_676.Value;
            A27=2;
            B27=cellstr('Barrera de seguridad – hormigón');
            c27=cellstr('app.Button_676');
            app.Matriz2(4)=c27;
            app.Matriz1(4)=A27;
            app.Matriz3(4)=B27;
            
            app.Button_677.BackgroundColor=[0.96,0.96,0.96];
            app.Button_676.BackgroundColor=[1.00,1.00,0.00];
            app.Button_679.BackgroundColor=[0.96,0.96,0.96];
            app.Button_678.BackgroundColor=[0.96,0.96,0.96];
            app.Button_670.BackgroundColor=[0.96,0.96,0.96];
            app.Button_671.BackgroundColor=[0.96,0.96,0.96];
            app.Button_680.BackgroundColor=[0.96,0.96,0.96];
            app.Button_672.BackgroundColor=[0.96,0.96,0.96];
            app.Button_673.BackgroundColor=[0.96,0.96,0.96];
            app.Button_666.BackgroundColor=[0.96,0.96,0.96];
            app.Button_667.BackgroundColor=[0.96,0.96,0.96];
            app.Button_668.BackgroundColor=[0.96,0.96,0.96];
            app.Button_674.BackgroundColor=[0.96,0.96,0.96];
            app.Button_675.BackgroundColor=[0.96,0.96,0.96];
            app.Button_669.BackgroundColor=[0.96,0.96,0.96];
            app.Button_682.BackgroundColor=[0.96,0.96,0.96];
            app.Button_681.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_679
        function Button_679ValueChanged(app, event)
            value = app.Button_679.Value;
            A28=3;
            B28=cellstr('Barrera de seguridad – amigable para motocicletas');
            c28=cellstr('app.Button_679');
            app.Matriz2(4)=c28;
            app.Matriz1(4)=A28;
            app.Matriz3(4)=B28;
            
            app.Button_677.BackgroundColor=[0.96,0.96,0.96];
            app.Button_676.BackgroundColor=[0.96,0.96,0.96];
            app.Button_679.BackgroundColor=[1.00,1.00,0.00];
            app.Button_678.BackgroundColor=[0.96,0.96,0.96];
            app.Button_670.BackgroundColor=[0.96,0.96,0.96];
            app.Button_671.BackgroundColor=[0.96,0.96,0.96];
            app.Button_680.BackgroundColor=[0.96,0.96,0.96];
            app.Button_672.BackgroundColor=[0.96,0.96,0.96];
            app.Button_673.BackgroundColor=[0.96,0.96,0.96];
            app.Button_666.BackgroundColor=[0.96,0.96,0.96];
            app.Button_667.BackgroundColor=[0.96,0.96,0.96];
            app.Button_668.BackgroundColor=[0.96,0.96,0.96];
            app.Button_674.BackgroundColor=[0.96,0.96,0.96];
            app.Button_675.BackgroundColor=[0.96,0.96,0.96];
            app.Button_669.BackgroundColor=[0.96,0.96,0.96];
            app.Button_682.BackgroundColor=[0.96,0.96,0.96];
            app.Button_681.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_678
        function Button_678ValueChanged(app, event)
            value = app.Button_678.Value;
            A29=4;
            B29=cellstr('Barrera de seguridad – cable de acero');
            c29=cellstr('app.Button_678');
            app.Matriz2(4)=c29;
            app.Matriz1(4)=A29;
            app.Matriz3(4)=B29;
            
            app.Button_677.BackgroundColor=[0.96,0.96,0.96];
            app.Button_676.BackgroundColor=[0.96,0.96,0.96];
            app.Button_679.BackgroundColor=[0.96,0.96,0.96];
            app.Button_678.BackgroundColor=[1.00,1.00,0.00];
            app.Button_670.BackgroundColor=[0.96,0.96,0.96];
            app.Button_671.BackgroundColor=[0.96,0.96,0.96];
            app.Button_680.BackgroundColor=[0.96,0.96,0.96];
            app.Button_672.BackgroundColor=[0.96,0.96,0.96];
            app.Button_673.BackgroundColor=[0.96,0.96,0.96];
            app.Button_666.BackgroundColor=[0.96,0.96,0.96];
            app.Button_667.BackgroundColor=[0.96,0.96,0.96];
            app.Button_668.BackgroundColor=[0.96,0.96,0.96];
            app.Button_674.BackgroundColor=[0.96,0.96,0.96];
            app.Button_675.BackgroundColor=[0.96,0.96,0.96];
            app.Button_669.BackgroundColor=[0.96,0.96,0.96];
            app.Button_682.BackgroundColor=[0.96,0.96,0.96];
            app.Button_681.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_670
        function Button_670ValueChanged(app, event)
            value = app.Button_670.Value;
            A30=5;
            B30=cellstr('Cara vertical agresiva en el talud en corte');
            c30=cellstr('app.Button_670');
            app.Matriz2(4)=c30;
            app.Matriz1(4)=A30;
            app.Matriz3(4)=B30;
            
            app.Button_677.BackgroundColor=[0.96,0.96,0.96];
            app.Button_676.BackgroundColor=[0.96,0.96,0.96];
            app.Button_679.BackgroundColor=[0.96,0.96,0.96];
            app.Button_678.BackgroundColor=[0.96,0.96,0.96];
            app.Button_670.BackgroundColor=[1.00,1.00,0.00];
            app.Button_671.BackgroundColor=[0.96,0.96,0.96];
            app.Button_680.BackgroundColor=[0.96,0.96,0.96];
            app.Button_672.BackgroundColor=[0.96,0.96,0.96];
            app.Button_673.BackgroundColor=[0.96,0.96,0.96];
            app.Button_666.BackgroundColor=[0.96,0.96,0.96];
            app.Button_667.BackgroundColor=[0.96,0.96,0.96];
            app.Button_668.BackgroundColor=[0.96,0.96,0.96];
            app.Button_674.BackgroundColor=[0.96,0.96,0.96];
            app.Button_675.BackgroundColor=[0.96,0.96,0.96];
            app.Button_669.BackgroundColor=[0.96,0.96,0.96];
            app.Button_682.BackgroundColor=[0.96,0.96,0.96];
            app.Button_681.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_671
        function Button_671ValueChanged(app, event)
            value = app.Button_671.Value;
            A31=6;
            B31=cellstr('Pendiente ascendente del talud en corte (15° a 75°)');
            c31=cellstr('app.Button_671');
            app.Matriz2(4)=c31;
            app.Matriz1(4)=A31;
            app.Matriz3(4)=B31;
            
            app.Button_677.BackgroundColor=[0.96,0.96,0.96];
            app.Button_676.BackgroundColor=[0.96,0.96,0.96];
            app.Button_679.BackgroundColor=[0.96,0.96,0.96];
            app.Button_678.BackgroundColor=[0.96,0.96,0.96];
            app.Button_670.BackgroundColor=[0.96,0.96,0.96];
            app.Button_671.BackgroundColor=[1.00,1.00,0.00];
            app.Button_680.BackgroundColor=[0.96,0.96,0.96];
            app.Button_672.BackgroundColor=[0.96,0.96,0.96];
            app.Button_673.BackgroundColor=[0.96,0.96,0.96];
            app.Button_666.BackgroundColor=[0.96,0.96,0.96];
            app.Button_667.BackgroundColor=[0.96,0.96,0.96];
            app.Button_668.BackgroundColor=[0.96,0.96,0.96];
            app.Button_674.BackgroundColor=[0.96,0.96,0.96];
            app.Button_675.BackgroundColor=[0.96,0.96,0.96];
            app.Button_669.BackgroundColor=[0.96,0.96,0.96];
            app.Button_682.BackgroundColor=[0.96,0.96,0.96];
            app.Button_681.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_680
        function Button_680ValueChanged(app, event)
            value = app.Button_680.Value;
            A32=7;
            B32=cellstr('Pendiente empinada del talud en corte (> 75°)');
            c32=cellstr('app.Button_680');
            app.Matriz2(4)=c32;
            app.Matriz1(4)=A32;
            app.Matriz3(4)=B32;
            
            app.Button_677.BackgroundColor=[0.96,0.96,0.96];
            app.Button_676.BackgroundColor=[0.96,0.96,0.96];
            app.Button_679.BackgroundColor=[0.96,0.96,0.96];
            app.Button_678.BackgroundColor=[0.96,0.96,0.96];
            app.Button_670.BackgroundColor=[0.96,0.96,0.96];
            app.Button_671.BackgroundColor=[0.96,0.96,0.96];
            app.Button_680.BackgroundColor=[1.00,1.00,0.00];
            app.Button_672.BackgroundColor=[0.96,0.96,0.96];
            app.Button_673.BackgroundColor=[0.96,0.96,0.96];
            app.Button_666.BackgroundColor=[0.96,0.96,0.96];
            app.Button_667.BackgroundColor=[0.96,0.96,0.96];
            app.Button_668.BackgroundColor=[0.96,0.96,0.96];
            app.Button_674.BackgroundColor=[0.96,0.96,0.96];
            app.Button_675.BackgroundColor=[0.96,0.96,0.96];
            app.Button_669.BackgroundColor=[0.96,0.96,0.96];
            app.Button_682.BackgroundColor=[0.96,0.96,0.96];
            app.Button_681.BackgroundColor=[0.96,0.96,0.96];

        end

        % Value changed function: Button_672
        function Button_672ValueChanged(app, event)
            value = app.Button_672.Value;
            A33=8;
            B33=cellstr('Cuneta profunda');
            c33=cellstr('app.Button_672');
            app.Matriz2(4)=c33;
            app.Matriz1(4)=A33;
            app.Matriz3(4)=B33;
            
            app.Button_677.BackgroundColor=[0.96,0.96,0.96];
            app.Button_676.BackgroundColor=[0.96,0.96,0.96];
            app.Button_679.BackgroundColor=[0.96,0.96,0.96];
            app.Button_678.BackgroundColor=[0.96,0.96,0.96];
            app.Button_670.BackgroundColor=[0.96,0.96,0.96];
            app.Button_671.BackgroundColor=[0.96,0.96,0.96];
            app.Button_680.BackgroundColor=[0.96,0.96,0.96];
            app.Button_672.BackgroundColor=[1.00,1.00,0.00];
            app.Button_673.BackgroundColor=[0.96,0.96,0.96];
            app.Button_666.BackgroundColor=[0.96,0.96,0.96];
            app.Button_667.BackgroundColor=[0.96,0.96,0.96];
            app.Button_668.BackgroundColor=[0.96,0.96,0.96];
            app.Button_674.BackgroundColor=[0.96,0.96,0.96];
            app.Button_675.BackgroundColor=[0.96,0.96,0.96];
            app.Button_669.BackgroundColor=[0.96,0.96,0.96];
            app.Button_682.BackgroundColor=[0.96,0.96,0.96];
            app.Button_681.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_673
        function Button_673ValueChanged(app, event)
            value = app.Button_673.Value;
            A34=9;
            B34=cellstr('Terraplén');
            c34=cellstr('app.Button_673');
            app.Matriz2(4)=c34;
            app.Matriz1(4)=A34;
            app.Matriz3(4)=B34;
            
            app.Button_677.BackgroundColor=[0.96,0.96,0.96];
            app.Button_676.BackgroundColor=[0.96,0.96,0.96];
            app.Button_679.BackgroundColor=[0.96,0.96,0.96];
            app.Button_678.BackgroundColor=[0.96,0.96,0.96];
            app.Button_670.BackgroundColor=[0.96,0.96,0.96];
            app.Button_671.BackgroundColor=[0.96,0.96,0.96];
            app.Button_680.BackgroundColor=[0.96,0.96,0.96];
            app.Button_672.BackgroundColor=[0.96,0.96,0.96];
            app.Button_673.BackgroundColor=[1.00,1.00,0.00];
            app.Button_666.BackgroundColor=[0.96,0.96,0.96];
            app.Button_667.BackgroundColor=[0.96,0.96,0.96];
            app.Button_668.BackgroundColor=[0.96,0.96,0.96];
            app.Button_674.BackgroundColor=[0.96,0.96,0.96];
            app.Button_675.BackgroundColor=[0.96,0.96,0.96];
            app.Button_669.BackgroundColor=[0.96,0.96,0.96];
            app.Button_682.BackgroundColor=[0.96,0.96,0.96];
            app.Button_681.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_666
        function Button_666ValueChanged(app, event)
            value = app.Button_666.Value;
            A35=10;
            B35=cellstr('Precipicio');
            c35=cellstr('app.Button_666');
            app.Matriz2(4)=c35;
            app.Matriz1(4)=A35;
            app.Matriz3(4)=B35;
            
            app.Button_677.BackgroundColor=[0.96,0.96,0.96];
            app.Button_676.BackgroundColor=[0.96,0.96,0.96];
            app.Button_679.BackgroundColor=[0.96,0.96,0.96];
            app.Button_678.BackgroundColor=[0.96,0.96,0.96];
            app.Button_670.BackgroundColor=[0.96,0.96,0.96];
            app.Button_671.BackgroundColor=[0.96,0.96,0.96];
            app.Button_680.BackgroundColor=[0.96,0.96,0.96];
            app.Button_672.BackgroundColor=[0.96,0.96,0.96];
            app.Button_673.BackgroundColor=[0.96,0.96,0.96];
            app.Button_666.BackgroundColor=[1.00,1.00,0.00];
            app.Button_667.BackgroundColor=[0.96,0.96,0.96];
            app.Button_668.BackgroundColor=[0.96,0.96,0.96];
            app.Button_674.BackgroundColor=[0.96,0.96,0.96];
            app.Button_675.BackgroundColor=[0.96,0.96,0.96];
            app.Button_669.BackgroundColor=[0.96,0.96,0.96];
            app.Button_682.BackgroundColor=[0.96,0.96,0.96];
            app.Button_681.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_667
        function Button_667ValueChanged(app, event)
            value = app.Button_667.Value;
            A36=11;
            B36=cellstr('Árbol (≥ 10 cm de diámetro)');
            c36=cellstr('app.Button_667');
            app.Matriz2(4)=c36;
            app.Matriz1(4)=A36;
            app.Matriz3(4)=B36;
            
            app.Button_677.BackgroundColor=[0.96,0.96,0.96];
            app.Button_676.BackgroundColor=[0.96,0.96,0.96];
            app.Button_679.BackgroundColor=[0.96,0.96,0.96];
            app.Button_678.BackgroundColor=[0.96,0.96,0.96];
            app.Button_670.BackgroundColor=[0.96,0.96,0.96];
            app.Button_671.BackgroundColor=[0.96,0.96,0.96];
            app.Button_680.BackgroundColor=[0.96,0.96,0.96];
            app.Button_672.BackgroundColor=[0.96,0.96,0.96];
            app.Button_673.BackgroundColor=[0.96,0.96,0.96];
            app.Button_666.BackgroundColor=[0.96,0.96,0.96];
            app.Button_667.BackgroundColor=[1.00,1.00,0.00];
            app.Button_668.BackgroundColor=[0.96,0.96,0.96];
            app.Button_674.BackgroundColor=[0.96,0.96,0.96];
            app.Button_675.BackgroundColor=[0.96,0.96,0.96];
            app.Button_669.BackgroundColor=[0.96,0.96,0.96];
            app.Button_682.BackgroundColor=[0.96,0.96,0.96];
            app.Button_681.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_668
        function Button_668ValueChanged(app, event)
            value = app.Button_668.Value;
            A37=12;
            B37=cellstr('Letrero / poste / barra rígidos (≥ 10 cm de diámetro)');
            c37=cellstr('app.Button_668');
            app.Matriz2(4)=c37;
            app.Matriz1(4)=A37;
            app.Matriz3(4)=B37;
            
            app.Button_677.BackgroundColor=[0.96,0.96,0.96];
            app.Button_676.BackgroundColor=[0.96,0.96,0.96];
            app.Button_679.BackgroundColor=[0.96,0.96,0.96];
            app.Button_678.BackgroundColor=[0.96,0.96,0.96];
            app.Button_670.BackgroundColor=[0.96,0.96,0.96];
            app.Button_671.BackgroundColor=[0.96,0.96,0.96];
            app.Button_680.BackgroundColor=[0.96,0.96,0.96];
            app.Button_672.BackgroundColor=[0.96,0.96,0.96];
            app.Button_673.BackgroundColor=[0.96,0.96,0.96];
            app.Button_666.BackgroundColor=[0.96,0.96,0.96];
            app.Button_667.BackgroundColor=[0.96,0.96,0.96];
            app.Button_668.BackgroundColor=[1.00,1.00,0.00];
            app.Button_674.BackgroundColor=[0.96,0.96,0.96];
            app.Button_675.BackgroundColor=[0.96,0.96,0.96];
            app.Button_669.BackgroundColor=[0.96,0.96,0.96];
            app.Button_682.BackgroundColor=[0.96,0.96,0.96];
            app.Button_681.BackgroundColor=[0.96,0.96,0.96];


        end

        % Value changed function: Button_674
        function Button_674ValueChanged(app, event)
            value = app.Button_674.Value;
            A38=13;
            B38=cellstr('Estructura / puente / edificio rígidos');
            c38=cellstr('app.Button_674');
            app.Matriz2(4)=c38;
            app.Matriz1(4)=A38;
            app.Matriz3(4)=B38;
            
            app.Button_677.BackgroundColor=[0.96,0.96,0.96];
            app.Button_676.BackgroundColor=[0.96,0.96,0.96];
            app.Button_679.BackgroundColor=[0.96,0.96,0.96];
            app.Button_678.BackgroundColor=[0.96,0.96,0.96];
            app.Button_670.BackgroundColor=[0.96,0.96,0.96];
            app.Button_671.BackgroundColor=[0.96,0.96,0.96];
            app.Button_680.BackgroundColor=[0.96,0.96,0.96];
            app.Button_672.BackgroundColor=[0.96,0.96,0.96];
            app.Button_673.BackgroundColor=[0.96,0.96,0.96];
            app.Button_666.BackgroundColor=[0.96,0.96,0.96];
            app.Button_667.BackgroundColor=[0.96,0.96,0.96];
            app.Button_668.BackgroundColor=[0.96,0.96,0.96];
            app.Button_674.BackgroundColor=[1.00,1.00,0.00];
            app.Button_675.BackgroundColor=[0.96,0.96,0.96];
            app.Button_669.BackgroundColor=[0.96,0.96,0.96];
            app.Button_682.BackgroundColor=[0.96,0.96,0.96];
            app.Button_681.BackgroundColor=[0.96,0.96,0.96];

        end

        % Value changed function: Button_675
        function Button_675ValueChanged(app, event)
            value = app.Button_675.Value;
            A39=14;
            B39=cellstr('Estructura /edificio colapsable');
            c39=cellstr('app.Button_675');
            app.Matriz2(4)=c39;
            app.Matriz1(4)=A39;
            app.Matriz3(4)=B39;
            
            app.Button_677.BackgroundColor=[0.96,0.96,0.96];
            app.Button_676.BackgroundColor=[0.96,0.96,0.96];
            app.Button_679.BackgroundColor=[0.96,0.96,0.96];
            app.Button_678.BackgroundColor=[0.96,0.96,0.96];
            app.Button_670.BackgroundColor=[0.96,0.96,0.96];
            app.Button_671.BackgroundColor=[0.96,0.96,0.96];
            app.Button_680.BackgroundColor=[0.96,0.96,0.96];
            app.Button_672.BackgroundColor=[0.96,0.96,0.96];
            app.Button_673.BackgroundColor=[0.96,0.96,0.96];
            app.Button_666.BackgroundColor=[0.96,0.96,0.96];
            app.Button_667.BackgroundColor=[0.96,0.96,0.96];
            app.Button_668.BackgroundColor=[0.96,0.96,0.96];
            app.Button_674.BackgroundColor=[0.96,0.96,0.96];
            app.Button_675.BackgroundColor=[1.00,1.00,0.00];
            app.Button_669.BackgroundColor=[0.96,0.96,0.96];
            app.Button_682.BackgroundColor=[0.96,0.96,0.96];
            app.Button_681.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_669
        function Button_669ValueChanged(app, event)
            value = app.Button_669.Value;
            A40=15;
            B40=cellstr('Extremo de barrera desprotegido');
            c40=cellstr('app.Button_669');
            app.Matriz2(4)=c40;
            app.Matriz1(4)=A40;
            app.Matriz3(4)=B40;
            
            app.Button_677.BackgroundColor=[0.96,0.96,0.96];
            app.Button_676.BackgroundColor=[0.96,0.96,0.96];
            app.Button_679.BackgroundColor=[0.96,0.96,0.96];
            app.Button_678.BackgroundColor=[0.96,0.96,0.96];
            app.Button_670.BackgroundColor=[0.96,0.96,0.96];
            app.Button_671.BackgroundColor=[0.96,0.96,0.96];
            app.Button_680.BackgroundColor=[0.96,0.96,0.96];
            app.Button_672.BackgroundColor=[0.96,0.96,0.96];
            app.Button_673.BackgroundColor=[0.96,0.96,0.96];
            app.Button_666.BackgroundColor=[0.96,0.96,0.96];
            app.Button_667.BackgroundColor=[0.96,0.96,0.96];
            app.Button_668.BackgroundColor=[0.96,0.96,0.96];
            app.Button_674.BackgroundColor=[0.96,0.96,0.96];
            app.Button_675.BackgroundColor=[0.96,0.96,0.96];
            app.Button_669.BackgroundColor=[1.00,1.00,0.00];
            app.Button_682.BackgroundColor=[0.96,0.96,0.96];
            app.Button_681.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_682
        function Button_682ValueChanged(app, event)
            value = app.Button_682.Value;
            A41=16;
            B41=cellstr('Grandes rocas (≥ 20 cm de altura)');
            c41=cellstr('app.Button_682');
            app.Matriz2(4)=c41;
            app.Matriz1(4)=A41;
            app.Matriz3(4)=B41;
            
            app.Button_677.BackgroundColor=[0.96,0.96,0.96];
            app.Button_676.BackgroundColor=[0.96,0.96,0.96];
            app.Button_679.BackgroundColor=[0.96,0.96,0.96];
            app.Button_678.BackgroundColor=[0.96,0.96,0.96];
            app.Button_670.BackgroundColor=[0.96,0.96,0.96];
            app.Button_671.BackgroundColor=[0.96,0.96,0.96];
            app.Button_680.BackgroundColor=[0.96,0.96,0.96];
            app.Button_672.BackgroundColor=[0.96,0.96,0.96];
            app.Button_673.BackgroundColor=[0.96,0.96,0.96];
            app.Button_666.BackgroundColor=[0.96,0.96,0.96];
            app.Button_667.BackgroundColor=[0.96,0.96,0.96];
            app.Button_668.BackgroundColor=[0.96,0.96,0.96];
            app.Button_674.BackgroundColor=[0.96,0.96,0.96];
            app.Button_675.BackgroundColor=[0.96,0.96,0.96];
            app.Button_669.BackgroundColor=[0.96,0.96,0.96];
            app.Button_682.BackgroundColor=[1.00,1.00,0.00];
            app.Button_681.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_681
        function Button_681ValueChanged(app, event)
            value = app.Button_681.Value;
            A42=17;
            B42=cellstr('Ninguno (u objeto > 20 m de la carretera)');
            c42=cellstr('app.Button_681');
            app.Matriz2(4)=c42;
            app.Matriz1(4)=A42;
            app.Matriz3(4)=B42;
            
            app.Button_677.BackgroundColor=[0.96,0.96,0.96];
            app.Button_676.BackgroundColor=[0.96,0.96,0.96];
            app.Button_679.BackgroundColor=[0.96,0.96,0.96];
            app.Button_678.BackgroundColor=[0.96,0.96,0.96];
            app.Button_670.BackgroundColor=[0.96,0.96,0.96];
            app.Button_671.BackgroundColor=[0.96,0.96,0.96];
            app.Button_680.BackgroundColor=[0.96,0.96,0.96];
            app.Button_672.BackgroundColor=[0.96,0.96,0.96];
            app.Button_673.BackgroundColor=[0.96,0.96,0.96];
            app.Button_666.BackgroundColor=[0.96,0.96,0.96];
            app.Button_667.BackgroundColor=[0.96,0.96,0.96];
            app.Button_668.BackgroundColor=[0.96,0.96,0.96];
            app.Button_674.BackgroundColor=[0.96,0.96,0.96];
            app.Button_675.BackgroundColor=[0.96,0.96,0.96];
            app.Button_669.BackgroundColor=[0.96,0.96,0.96];
            app.Button_682.BackgroundColor=[0.96,0.96,0.96];
            app.Button_681.BackgroundColor=[1.00,1.00,0.00];
        end

        % Value changed function: Button_13
        function Button_13ValueChanged(app, event)
            value = app.Button_13.Value;
            A45=1;
            B45=cellstr('Ancho (≥ 2,4 m)');
            c45=cellstr('app.Button_13');
            app.Matriz2(6)=c45;
            app.Matriz1(6)=A45;
            app.Matriz3(6)=B45;
            
            app.Button_13.BackgroundColor=[1.00,1.00,0.00];
            app.Button_12.BackgroundColor=[0.96,0.96,0.96];
            app.Button_11.BackgroundColor=[0.96,0.96,0.96];
            app.Button_14.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_12
        function Button_12ValueChanged(app, event)
            value = app.Button_12.Value;
            A46=2;
            B46=cellstr('Medio (≥ 1,0 m a 2,4 m)');
            c46=cellstr('app.Button_12');
            app.Matriz2(6)=c46;
            app.Matriz1(6)=A46;
            app.Matriz3(6)=B46;
            
            app.Button_13.BackgroundColor=[0.96,0.96,0.96];
            app.Button_12.BackgroundColor=[1.00,1.00,0.00];
            app.Button_11.BackgroundColor=[0.96,0.96,0.96];
            app.Button_14.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_11
        function Button_11ValueChanged(app, event)
            value = app.Button_11.Value;
            A47=3;
            B47=cellstr('Estrecho (≥ 0 m a 1,0 m)');
            c47=cellstr('app.Button_11');
            app.Matriz2(6)=c47;
            app.Matriz1(6)=A47;
            app.Matriz3(6)=B47;
            
            app.Button_13.BackgroundColor=[0.96,0.96,0.96];
            app.Button_12.BackgroundColor=[0.96,0.96,0.96];
            app.Button_11.BackgroundColor=[1.00,1.00,0.00];
            app.Button_14.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_14
        function Button_14ValueChanged(app, event)
            value = app.Button_14.Value;
            A48=4;
            B48=cellstr('Ninguno');
            c48=cellstr('app.Button_14');
            app.Matriz2(6)=c48;
            app.Matriz1(6)=A48;
            app.Matriz3(6)=B48;
            
            app.Button_13.BackgroundColor=[0.96,0.96,0.96];
            app.Button_12.BackgroundColor=[0.96,0.96,0.96];
            app.Button_11.BackgroundColor=[0.96,0.96,0.96];
            app.Button_14.BackgroundColor=[1.00,1.00,0.00];
        end

        % Value changed function: Button_685
        function Button_685ValueChanged(app, event)
            value = app.Button_685.Value;
            A49=1;
            B49=cellstr('Ancho (≥ 2,4 m)');
            c49=cellstr('app.Button_685');
            app.Matriz2(7)=c49;
            app.Matriz1(7)=A49;
            app.Matriz3(7)=B49;
            
            app.Button_685.BackgroundColor=[1.00,1.00,0.00];
            app.Button_684.BackgroundColor=[0.96,0.96,0.96];
            app.Button_683.BackgroundColor=[0.96,0.96,0.96];
            app.Button_686.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_684
        function Button_684ValueChanged(app, event)
            value = app.Button_684.Value;
            A50=2;
            B50=cellstr('Medio (≥ 1,0 m a 2,4 m)');
            c50=cellstr('app.Button_684');
            app.Matriz2(7)=c50;
            app.Matriz1(7)=A50;
            app.Matriz3(7)=B50;
            
            app.Button_685.BackgroundColor=[0.96,0.96,0.96];
            app.Button_684.BackgroundColor=[1.00,1.00,0.00];
            app.Button_683.BackgroundColor=[0.96,0.96,0.96];
            app.Button_686.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_683
        function Button_683ValueChanged(app, event)
            value = app.Button_683.Value;
            A51=3;
            B51=cellstr('Estrecho (≥ 0 m a 1,0 m)');
            c51=cellstr('app.Button_683');
            app.Matriz2(7)=c51;
            app.Matriz1(7)=A51;
            app.Matriz3(7)=B51;
            
            app.Button_685.BackgroundColor=[0.96,0.96,0.96];
            app.Button_684.BackgroundColor=[0.96,0.96,0.96];
            app.Button_683.BackgroundColor=[1.00,1.00,0.00];
            app.Button_686.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_686
        function Button_686ValueChanged(app, event)
            value = app.Button_686.Value;
            A52=4;
            B52=cellstr('Ninguno');
            c52=cellstr('app.Button_686');
            app.Matriz2(7)=c52;
            app.Matriz1(7)=A52;
            app.Matriz3(7)=B52;
            
            app.Button_685.BackgroundColor=[0.96,0.96,0.96];
            app.Button_684.BackgroundColor=[0.96,0.96,0.96];
            app.Button_683.BackgroundColor=[0.96,0.96,0.96];
            app.Button_686.BackgroundColor=[1.00,1.00,0.00];
        end

        % Value changed function: Button_10
        function Button_10ValueChanged(app, event)
            value = app.Button_10.Value;
            A44=2;
            B44=cellstr('Presente');
            c44=cellstr('app.Button_10');
            app.Matriz2(5)=c44;
            app.Matriz1(5)=A44;
            app.Matriz3(5)=B44;
            
            app.Button_10.BackgroundColor=[1.00,1.00,0.00];
            app.Button_9.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_9
        function Button_9ValueChanged(app, event)
            value = app.Button_9.Value;
            A43=1;
            B43=cellstr('Ausente');
            c43=cellstr('app.Button_9');
            app.Matriz2(5)=c43;
            app.Matriz1(5)=A43;
            app.Matriz3(5)=B43;
            
            app.Button_10.BackgroundColor=[0.96,0.96,0.96];
            app.Button_9.BackgroundColor=[1.00,1.00,0.00];
        end

        % Value changed function: Button_70
        function Button_70ValueChanged(app, event)
            value = app.Button_70.Value;
            A54=1;
            B54=cellstr('Calzada A de una calzada dividida');
            c54=cellstr('app.Button_70');
            app.Matriz2(8)=c54;
            app.Matriz1(8)=A54;
            app.Matriz3(8)=B54;
            
            app.Button_70.BackgroundColor=[1.00,1.00,0.00];
            app.Button_71.BackgroundColor=[0.96,0.96,0.96];
            app.Button_72.BackgroundColor=[0.96,0.96,0.96];
            app.Button_73.BackgroundColor=[0.96,0.96,0.96];
            app.Button_102.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_71
        function Button_71ValueChanged(app, event)
            value = app.Button_71.Value;
            A55=2;
            B55=cellstr('Calzada B de una calzada dividida');
            c55=cellstr('app.Button_71');
            app.Matriz2(8)=c55;
            app.Matriz1(8)=A55;
            app.Matriz3(8)=B55;
            
            app.Button_70.BackgroundColor=[0.96,0.96,0.96];
            app.Button_71.BackgroundColor=[1.00,1.00,0.00];
            app.Button_72.BackgroundColor=[0.96,0.96,0.96];
            app.Button_73.BackgroundColor=[0.96,0.96,0.96];
            app.Button_102.BackgroundColor=[0.96,0.96,0.96];

        end

        % Value changed function: Button_72
        function Button_72ValueChanged(app, event)
            value = app.Button_72.Value;
            A56=3;
            B56=cellstr('Carretera no dividida');
            c56=cellstr('app.Button_72');
            app.Matriz2(8)=c56;
            app.Matriz1(8)=A56;
            app.Matriz3(8)=B56;
            
            app.Button_70.BackgroundColor=[0.96,0.96,0.96];
            app.Button_71.BackgroundColor=[0.96,0.96,0.96];
            app.Button_72.BackgroundColor=[1.00,1.00,0.00];
            app.Button_73.BackgroundColor=[0.96,0.96,0.96];
            app.Button_102.BackgroundColor=[0.96,0.96,0.96];

        end

        % Value changed function: Button_73
        function Button_73ValueChanged(app, event)
            value = app.Button_73.Value;
            A57=4;
            B57=cellstr('Calzada A de una infraestructura de motocicletas');
            c57=cellstr('app.Button_73');
            app.Matriz2(8)=c57;
            app.Matriz1(8)=A57;
            app.Matriz3(8)=B57;
            
            app.Button_70.BackgroundColor=[0.96,0.96,0.96];
            app.Button_71.BackgroundColor=[0.96,0.96,0.96];
            app.Button_72.BackgroundColor=[0.96,0.96,0.96];
            app.Button_73.BackgroundColor=[1.00,1.00,0.00];
            app.Button_102.BackgroundColor=[0.96,0.96,0.96];

        end

        % Value changed function: Button_102
        function Button_102ValueChanged(app, event)
            value = app.Button_102.Value;
            A58=5;
            B58=cellstr('Calzada B de una infraestructura de motocicletas');
            c58=cellstr('app.Button_102');
            app.Matriz2(8)=c58;
            app.Matriz1(8)=A58;
            app.Matriz3(8)=B58;
            
            app.Button_70.BackgroundColor=[0.96,0.96,0.96];
            app.Button_71.BackgroundColor=[0.96,0.96,0.96];
            app.Button_72.BackgroundColor=[0.96,0.96,0.96];
            app.Button_73.BackgroundColor=[0.96,0.96,0.96];
            app.Button_102.BackgroundColor=[1.00,1.00,0.00];
        end

        % Value changed function: Button_108
        function Button_108ValueChanged(app, event)
            value = app.Button_108.Value;
            A62=1;
            B62=cellstr('Barrera de seguridad – metal');
            c62=cellstr('app.Button_108');
            app.Matriz2(10)=c62;
            app.Matriz1(10)=A62;
            app.Matriz3(10)=B62;
            
            app.Button_108.BackgroundColor=[1.00,1.00,0.00];
            app.Button_107.BackgroundColor=[0.96,0.96,0.96];
            app.Button_112.BackgroundColor=[0.96,0.96,0.96];
            app.Button_111.BackgroundColor=[0.96,0.96,0.96];
            app.Button_106.BackgroundColor=[0.96,0.96,0.96];
	        app.Button_105.BackgroundColor=[0.96,0.96,0.96];
            app.Button_104.BackgroundColor=[0.96,0.96,0.96];
            app.Button_77.BackgroundColor=[0.96,0.96,0.96];
            app.Button_103.BackgroundColor=[0.96,0.96,0.96];
            app.Button_76.BackgroundColor=[0.96,0.96,0.96];
            app.Button_74.BackgroundColor=[0.96,0.96,0.96];
            app.Button_109.BackgroundColor=[0.96,0.96,0.96];
            app.Button_113.BackgroundColor=[0.96,0.96,0.96];
            app.Button_75.BackgroundColor=[0.96,0.96,0.96];
            app.Button_110.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_107
        function Button_107ValueChanged(app, event)
            value = app.Button_107.Value;
            A63=2;
            B63=cellstr('Barrera de seguridad – concreto');
            c63=cellstr('app.Button_107');
            app.Matriz2(10)=c63;
            app.Matriz1(10)=A63;
            app.Matriz3(10)=B63;
            
            app.Button_108.BackgroundColor=[0.96,0.96,0.96];
            app.Button_107.BackgroundColor=[1.00,1.00,0.00];
            app.Button_112.BackgroundColor=[0.96,0.96,0.96];
            app.Button_111.BackgroundColor=[0.96,0.96,0.96];
            app.Button_106.BackgroundColor=[0.96,0.96,0.96];
	        app.Button_105.BackgroundColor=[0.96,0.96,0.96];
            app.Button_104.BackgroundColor=[0.96,0.96,0.96];
            app.Button_77.BackgroundColor=[0.96,0.96,0.96];
            app.Button_103.BackgroundColor=[0.96,0.96,0.96];
            app.Button_76.BackgroundColor=[0.96,0.96,0.96];
            app.Button_74.BackgroundColor=[0.96,0.96,0.96];
            app.Button_109.BackgroundColor=[0.96,0.96,0.96];
            app.Button_113.BackgroundColor=[0.96,0.96,0.96];
            app.Button_75.BackgroundColor=[0.96,0.96,0.96];
            app.Button_110.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_112
        function Button_112ValueChanged(app, event)
            value = app.Button_112.Value;
            A64=3;
            B64=cellstr('Mediana física con un ancho ≥ 20 m');
            c64=cellstr('app.Button_112');
            app.Matriz2(10)=c64;
            app.Matriz1(10)=A64;
            app.Matriz3(10)=B64;
            
            app.Button_108.BackgroundColor=[0.96,0.96,0.96];
            app.Button_107.BackgroundColor=[0.96,0.96,0.96];
            app.Button_112.BackgroundColor=[1.00,1.00,0.00];
            app.Button_111.BackgroundColor=[0.96,0.96,0.96];
            app.Button_106.BackgroundColor=[0.96,0.96,0.96];
	        app.Button_105.BackgroundColor=[0.96,0.96,0.96];
            app.Button_104.BackgroundColor=[0.96,0.96,0.96];
            app.Button_77.BackgroundColor=[0.96,0.96,0.96];
            app.Button_103.BackgroundColor=[0.96,0.96,0.96];
            app.Button_76.BackgroundColor=[0.96,0.96,0.96];
            app.Button_74.BackgroundColor=[0.96,0.96,0.96];
            app.Button_109.BackgroundColor=[0.96,0.96,0.96];
            app.Button_113.BackgroundColor=[0.96,0.96,0.96];
            app.Button_75.BackgroundColor=[0.96,0.96,0.96];
            app.Button_110.BackgroundColor=[0.96,0.96,0.96];

        end

        % Value changed function: Button_111
        function Button_111ValueChanged(app, event)
            value = app.Button_111.Value;
            A65=4;
            B65=cellstr('Mediana física con un ancho ≥ 10 m a < 20 m');
            c65=cellstr('app.Button_111');
            app.Matriz2(10)=c65;
            app.Matriz1(10)=A65;
            app.Matriz3(10)=B65;
            
            app.Button_108.BackgroundColor=[0.96,0.96,0.96];
            app.Button_107.BackgroundColor=[0.96,0.96,0.96];
            app.Button_112.BackgroundColor=[0.96,0.96,0.96];
            app.Button_111.BackgroundColor=[1.00,1.00,0.00];
            app.Button_106.BackgroundColor=[0.96,0.96,0.96];
	        app.Button_105.BackgroundColor=[0.96,0.96,0.96];
            app.Button_104.BackgroundColor=[0.96,0.96,0.96];
            app.Button_77.BackgroundColor=[0.96,0.96,0.96];
            app.Button_103.BackgroundColor=[0.96,0.96,0.96];
            app.Button_76.BackgroundColor=[0.96,0.96,0.96];
            app.Button_74.BackgroundColor=[0.96,0.96,0.96];
            app.Button_109.BackgroundColor=[0.96,0.96,0.96];
            app.Button_113.BackgroundColor=[0.96,0.96,0.96];
            app.Button_75.BackgroundColor=[0.96,0.96,0.96];
            app.Button_110.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_106
        function Button_106ValueChanged(app, event)
            value = app.Button_106.Value;
            A66=5;
            B66=cellstr('Mediana física con un ancho ≥ 5 m a < 10 m');
            c66=cellstr('app.Button_106');
            app.Matriz2(10)=c66;
            app.Matriz1(10)=A66;
            app.Matriz3(10)=B66;
            
            app.Button_108.BackgroundColor=[0.96,0.96,0.96];
            app.Button_107.BackgroundColor=[0.96,0.96,0.96];
            app.Button_112.BackgroundColor=[0.96,0.96,0.96];
            app.Button_111.BackgroundColor=[0.96,0.96,0.96];
            app.Button_106.BackgroundColor=[1.00,1.00,0.00];
	        app.Button_105.BackgroundColor=[0.96,0.96,0.96];
            app.Button_104.BackgroundColor=[0.96,0.96,0.96];
            app.Button_77.BackgroundColor=[0.96,0.96,0.96];
            app.Button_103.BackgroundColor=[0.96,0.96,0.96];
            app.Button_76.BackgroundColor=[0.96,0.96,0.96];
            app.Button_74.BackgroundColor=[0.96,0.96,0.96];
            app.Button_109.BackgroundColor=[0.96,0.96,0.96];
            app.Button_113.BackgroundColor=[0.96,0.96,0.96];
            app.Button_75.BackgroundColor=[0.96,0.96,0.96];
            app.Button_110.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_105
        function Button_105ValueChanged(app, event)
            value = app.Button_105.Value;
            A67=6;
            B67=cellstr('Mediana física con un ancho ≥ 1 m a < 5 m');
            c67=cellstr('app.Button_105');
            app.Matriz2(10)=c67;
            app.Matriz1(10)=A67;
            app.Matriz3(10)=B67;
            
            app.Button_108.BackgroundColor=[0.96,0.96,0.96];
            app.Button_107.BackgroundColor=[0.96,0.96,0.96];
            app.Button_112.BackgroundColor=[0.96,0.96,0.96];
            app.Button_111.BackgroundColor=[0.96,0.96,0.96];
            app.Button_106.BackgroundColor=[0.96,0.96,0.96];
	        app.Button_105.BackgroundColor=[1.00,1.00,0.00];
            app.Button_104.BackgroundColor=[0.96,0.96,0.96];
            app.Button_77.BackgroundColor=[0.96,0.96,0.96];
            app.Button_103.BackgroundColor=[0.96,0.96,0.96];
            app.Button_76.BackgroundColor=[0.96,0.96,0.96];
            app.Button_74.BackgroundColor=[0.96,0.96,0.96];
            app.Button_109.BackgroundColor=[0.96,0.96,0.96];
            app.Button_113.BackgroundColor=[0.96,0.96,0.96];
            app.Button_75.BackgroundColor=[0.96,0.96,0.96];
            app.Button_110.BackgroundColor=[0.96,0.96,0.96];

        end

        % Value changed function: Button_104
        function Button_104ValueChanged(app, event)
            value = app.Button_104.Value;
            A68=7;
            B68=cellstr('Mediana física con un ancho ≥ 0 m a < 1 m');
            c68=cellstr('app.Button_104');
            app.Matriz2(10)=c68;
            app.Matriz1(10)=A68;
            app.Matriz3(10)=B68;
            
            app.Button_108.BackgroundColor=[0.96,0.96,0.96];
            app.Button_107.BackgroundColor=[0.96,0.96,0.96];
            app.Button_112.BackgroundColor=[0.96,0.96,0.96];
            app.Button_111.BackgroundColor=[0.96,0.96,0.96];
            app.Button_106.BackgroundColor=[0.96,0.96,0.96];
	        app.Button_105.BackgroundColor=[0.96,0.96,0.96];
            app.Button_104.BackgroundColor=[1.00,1.00,0.00];
            app.Button_77.BackgroundColor=[0.96,0.96,0.96];
            app.Button_103.BackgroundColor=[0.96,0.96,0.96];
            app.Button_76.BackgroundColor=[0.96,0.96,0.96];
            app.Button_74.BackgroundColor=[0.96,0.96,0.96];
            app.Button_109.BackgroundColor=[0.96,0.96,0.96];
            app.Button_113.BackgroundColor=[0.96,0.96,0.96];
            app.Button_75.BackgroundColor=[0.96,0.96,0.96];
            app.Button_110.BackgroundColor=[0.96,0.96,0.96];

        end

        % Value changed function: Button_77
        function Button_77ValueChanged(app, event)
            value = app.Button_77.Value;
            A69=8;
            B69=cellstr('Carril central continuo para giro');
            c69=cellstr('app.Button_77');
            app.Matriz2(10)=c69;
            app.Matriz1(10)=A69;
            app.Matriz3(10)=B69;
            
            app.Button_108.BackgroundColor=[0.96,0.96,0.96];
            app.Button_107.BackgroundColor=[0.96,0.96,0.96];
            app.Button_112.BackgroundColor=[0.96,0.96,0.96];
            app.Button_111.BackgroundColor=[0.96,0.96,0.96];
            app.Button_106.BackgroundColor=[0.96,0.96,0.96];
	        app.Button_105.BackgroundColor=[0.96,0.96,0.96];
            app.Button_104.BackgroundColor=[0.96,0.96,0.96];
            app.Button_77.BackgroundColor=[1.00,1.00,0.00];
            app.Button_103.BackgroundColor=[0.96,0.96,0.96];
            app.Button_76.BackgroundColor=[0.96,0.96,0.96];
            app.Button_74.BackgroundColor=[0.96,0.96,0.96];
            app.Button_109.BackgroundColor=[0.96,0.96,0.96];
            app.Button_113.BackgroundColor=[0.96,0.96,0.96];
            app.Button_75.BackgroundColor=[0.96,0.96,0.96];
            app.Button_110.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_103
        function Button_103ValueChanged(app, event)
            value = app.Button_103.Value;
            A70=9;
            B70=cellstr('Delineadores flexibles');
            c70=cellstr('app.Button_103');
            app.Matriz2(10)=c70;
            app.Matriz1(10)=A70;
            app.Matriz3(10)=B70;
            
            app.Button_108.BackgroundColor=[0.96,0.96,0.96];
            app.Button_107.BackgroundColor=[0.96,0.96,0.96];
            app.Button_112.BackgroundColor=[0.96,0.96,0.96];
            app.Button_111.BackgroundColor=[0.96,0.96,0.96];
            app.Button_106.BackgroundColor=[0.96,0.96,0.96];
	        app.Button_105.BackgroundColor=[0.96,0.96,0.96];
            app.Button_104.BackgroundColor=[0.96,0.96,0.96];
            app.Button_77.BackgroundColor=[0.96,0.96,0.96];
            app.Button_103.BackgroundColor=[1.00,1.00,0.00];
            app.Button_76.BackgroundColor=[0.96,0.96,0.96];
            app.Button_74.BackgroundColor=[0.96,0.96,0.96];
            app.Button_109.BackgroundColor=[0.96,0.96,0.96];
            app.Button_113.BackgroundColor=[0.96,0.96,0.96];
            app.Button_75.BackgroundColor=[0.96,0.96,0.96];
            app.Button_110.BackgroundColor=[0.96,0.96,0.96];

        end

        % Value changed function: Button_76
        function Button_76ValueChanged(app, event)
            value = app.Button_76.Value;
            A71=10;
            B71=cellstr('Raya separadora de sentidos de circulación doble > 1 m y rayas diagonales a 45°');
            c71=cellstr('app.Button_76');
            app.Matriz2(10)=c71;
            app.Matriz1(10)=A71;
            app.Matriz3(10)=B71;
            
            app.Button_108.BackgroundColor=[0.96,0.96,0.96];
            app.Button_107.BackgroundColor=[0.96,0.96,0.96];
            app.Button_112.BackgroundColor=[0.96,0.96,0.96];
            app.Button_111.BackgroundColor=[0.96,0.96,0.96];
            app.Button_106.BackgroundColor=[0.96,0.96,0.96];
	        app.Button_105.BackgroundColor=[0.96,0.96,0.96];
            app.Button_104.BackgroundColor=[0.96,0.96,0.96];
            app.Button_77.BackgroundColor=[0.96,0.96,0.96];
            app.Button_103.BackgroundColor=[0.96,0.96,0.96];
            app.Button_76.BackgroundColor=[1.00,1.00,0.00];
            app.Button_74.BackgroundColor=[0.96,0.96,0.96];
            app.Button_109.BackgroundColor=[0.96,0.96,0.96];
            app.Button_113.BackgroundColor=[0.96,0.96,0.96];
            app.Button_75.BackgroundColor=[0.96,0.96,0.96];
            app.Button_110.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_74
        function Button_74ValueChanged(app, event)
            value = app.Button_74.Value;
            A72=11;
            B72=cellstr('Línea central');
            c72=cellstr('app.Button_74');
            app.Matriz2(10)=c72;
            app.Matriz1(10)=A72;
            app.Matriz3(10)=B72;
            
            app.Button_108.BackgroundColor=[0.96,0.96,0.96];
            app.Button_107.BackgroundColor=[0.96,0.96,0.96];
            app.Button_112.BackgroundColor=[0.96,0.96,0.96];
            app.Button_111.BackgroundColor=[0.96,0.96,0.96];
            app.Button_106.BackgroundColor=[0.96,0.96,0.96];
	        app.Button_105.BackgroundColor=[0.96,0.96,0.96];
            app.Button_104.BackgroundColor=[0.96,0.96,0.96];
            app.Button_77.BackgroundColor=[0.96,0.96,0.96];
            app.Button_103.BackgroundColor=[0.96,0.96,0.96];
	        app.Button_76.BackgroundColor=[0.96,0.96,0.96];
            app.Button_74.BackgroundColor=[1.00,1.00,0.00];
            app.Button_109.BackgroundColor=[0.96,0.96,0.96];
            app.Button_113.BackgroundColor=[0.96,0.96,0.96];
            app.Button_75.BackgroundColor=[0.96,0.96,0.96];
            app.Button_110.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_109
        function Button_109ValueChanged(app, event)
            value = app.Button_109.Value;
            A73=12;
            B73=cellstr('Barrera de seguridad - amigable para motociclistas');
            c73=cellstr('app.Button_109');
            app.Matriz2(10)=c73;
            app.Matriz1(10)=A73;
            app.Matriz3(10)=B73;
            
            app.Button_108.BackgroundColor=[0.96,0.96,0.96];
            app.Button_107.BackgroundColor=[0.96,0.96,0.96];
            app.Button_112.BackgroundColor=[0.96,0.96,0.96];
            app.Button_111.BackgroundColor=[0.96,0.96,0.96];
            app.Button_106.BackgroundColor=[0.96,0.96,0.96];
	        app.Button_105.BackgroundColor=[0.96,0.96,0.96];
            app.Button_104.BackgroundColor=[0.96,0.96,0.96];
            app.Button_77.BackgroundColor=[0.96,0.96,0.96];
            app.Button_103.BackgroundColor=[0.96,0.96,0.96];
	        app.Button_76.BackgroundColor=[0.96,0.96,0.96];
            app.Button_74.BackgroundColor=[0.96,0.96,0.96];
            app.Button_109.BackgroundColor=[1.00,1.00,0.00];
            app.Button_113.BackgroundColor=[0.96,0.96,0.96];
            app.Button_75.BackgroundColor=[0.96,0.96,0.96];
            app.Button_110.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_113
        function Button_113ValueChanged(app, event)
            value = app.Button_113.Value;
            A74=13;
            B74=cellstr('Un solo sentido');
            c74=cellstr('app.Button_113');
            app.Matriz2(10)=c74;
            app.Matriz1(10)=A74;
            app.Matriz3(10)=B74;
            
            app.Button_108.BackgroundColor=[0.96,0.96,0.96];
            app.Button_107.BackgroundColor=[0.96,0.96,0.96];
            app.Button_112.BackgroundColor=[0.96,0.96,0.96];
            app.Button_111.BackgroundColor=[0.96,0.96,0.96];
            app.Button_106.BackgroundColor=[0.96,0.96,0.96];
	        app.Button_105.BackgroundColor=[0.96,0.96,0.96];
            app.Button_104.BackgroundColor=[0.96,0.96,0.96];
            app.Button_77.BackgroundColor=[0.96,0.96,0.96];
            app.Button_103.BackgroundColor=[0.96,0.96,0.96];
	        app.Button_76.BackgroundColor=[0.96,0.96,0.96];
            app.Button_74.BackgroundColor=[0.96,0.96,0.96];
            app.Button_109.BackgroundColor=[0.96,0.96,0.96];
            app.Button_113.BackgroundColor=[1.00,1.00,0.00];
            app.Button_75.BackgroundColor=[0.96,0.96,0.96];
            app.Button_110.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_75
        function Button_75ValueChanged(app, event)
            value = app.Button_75.Value;
            A75=14;
            B75=cellstr('Raya separadora de sentidos de circulación doble (0,3 m a 1 m) y rayas diagonales a 45°');
            c75=cellstr('app.Button_75');
            app.Matriz2(10)=c75;
            app.Matriz1(10)=A75;
            app.Matriz3(10)=B75;
            
            app.Button_108.BackgroundColor=[0.96,0.96,0.96];
            app.Button_107.BackgroundColor=[0.96,0.96,0.96];
            app.Button_112.BackgroundColor=[0.96,0.96,0.96];
            app.Button_111.BackgroundColor=[0.96,0.96,0.96];
            app.Button_106.BackgroundColor=[0.96,0.96,0.96];
	        app.Button_105.BackgroundColor=[0.96,0.96,0.96];
            app.Button_104.BackgroundColor=[0.96,0.96,0.96];
            app.Button_77.BackgroundColor=[0.96,0.96,0.96];
            app.Button_103.BackgroundColor=[0.96,0.96,0.96];
	        app.Button_76.BackgroundColor=[0.96,0.96,0.96];
            app.Button_74.BackgroundColor=[0.96,0.96,0.96];
            app.Button_109.BackgroundColor=[0.96,0.96,0.96];
            app.Button_113.BackgroundColor=[0.96,0.96,0.96];
            app.Button_75.BackgroundColor=[1.00,1.00,0.00];
            app.Button_110.BackgroundColor=[0.96,0.96,0.96];

        end

        % Value changed function: Button_110
        function Button_110ValueChanged(app, event)
            value = app.Button_110.Value;
            A76=15;
            B76=cellstr('Barrera de seguridad - cable de acero');
            c76=cellstr('app.Button_110');
            app.Matriz2(10)=c76;
            app.Matriz1(10)=A76;
            app.Matriz3(10)=B76;
            
            app.Button_108.BackgroundColor=[0.96,0.96,0.96];
            app.Button_107.BackgroundColor=[0.96,0.96,0.96];
            app.Button_112.BackgroundColor=[0.96,0.96,0.96];
            app.Button_111.BackgroundColor=[0.96,0.96,0.96];
            app.Button_106.BackgroundColor=[0.96,0.96,0.96];
	        app.Button_105.BackgroundColor=[0.96,0.96,0.96];
            app.Button_104.BackgroundColor=[0.96,0.96,0.96];
            app.Button_77.BackgroundColor=[0.96,0.96,0.96];
            app.Button_103.BackgroundColor=[0.96,0.96,0.96];
	        app.Button_76.BackgroundColor=[0.96,0.96,0.96];
            app.Button_74.BackgroundColor=[0.96,0.96,0.96];
            app.Button_109.BackgroundColor=[0.96,0.96,0.96];
            app.Button_113.BackgroundColor=[0.96,0.96,0.96];
            app.Button_75.BackgroundColor=[0.96,0.96,0.96];
            app.Button_110.BackgroundColor=[1.00,1.00,0.00];
        end

        % Value changed function: Button_557
        function Button_557ValueChanged(app, event)
            value = app.Button_557.Value;
            A77=1;
            B77=cellstr('Ausente');
            c77=cellstr('app.Button_557');
            app.Matriz2(11)=c77;
            app.Matriz1(11)=A77;
            app.Matriz3(11)=B77;
            
            app.Button_557.BackgroundColor=[1.00,1.00,0.00];
            app.SiButton_2.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: SiButton_2
        function SiButton_2ValueChanged(app, event)
            value = app.SiButton_2.Value;
            A78=2;
            B78=cellstr('Presente');
            c78=cellstr('app.SiButton_2');
            app.Matriz2(11)=c78;
            app.Matriz1(11)=A78;
            app.Matriz3(11)=B78;
            
            app.Button_557.BackgroundColor=[0.96,0.96,0.96];
            app.SiButton_2.BackgroundColor=[1.00,1.00,0.00];
        end

        % Value changed function: Button_661
        function Button_661ValueChanged(app, event)
            value = app.Button_661.Value;
            A80=2;
            B80=cellstr('Dos');
            c80=cellstr('app.Button_661');
            app.Matriz2(12)=c80;
            app.Matriz1(12)=A80;
            app.Matriz3(12)=B80;
            
            app.Button_660.BackgroundColor=[0.96,0.96,0.96];
            app.Button_661.BackgroundColor=[1.00,1.00,0.00];
            app.Button_121.BackgroundColor=[0.96,0.96,0.96];
            app.Button_122.BackgroundColor=[0.96,0.96,0.96];
	        app.Button_662.BackgroundColor=[0.96,0.96,0.96];
            app.Button_559.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_121
        function Button_121ValueChanged(app, event)
            value = app.Button_121.Value;
            A81=3;
            B81=cellstr('Tres');
            c81=cellstr('app.Button_121');
            app.Matriz2(12)=c81;
            app.Matriz1(12)=A81;
            app.Matriz3(12)=B81;
            
            app.Button_660.BackgroundColor=[0.96,0.96,0.96];
            app.Button_661.BackgroundColor=[0.96,0.96,0.96];
            app.Button_121.BackgroundColor=[1.00,1.00,0.00];
            app.Button_122.BackgroundColor=[0.96,0.96,0.96];
	        app.Button_662.BackgroundColor=[0.96,0.96,0.96];
            app.Button_559.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_122
        function Button_122ValueChanged(app, event)
            value = app.Button_122.Value;
            A82=4;
            B82=cellstr('Cuatro o más');
            c82=cellstr('app.Button_122');
            app.Matriz2(12)=c82;
            app.Matriz1(12)=A82;
            app.Matriz3(12)=B82;
            
            app.Button_660.BackgroundColor=[0.96,0.96,0.96];
            app.Button_661.BackgroundColor=[0.96,0.96,0.96];
            app.Button_121.BackgroundColor=[0.96,0.96,0.96];
            app.Button_122.BackgroundColor=[1.00,1.00,0.00];
	        app.Button_662.BackgroundColor=[0.96,0.96,0.96];
            app.Button_559.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_662
        function Button_662ValueChanged(app, event)
            value = app.Button_662.Value;
            A83=5;
            B83=cellstr('Dos + uno');
            c83=cellstr('app.Button_662');
            app.Matriz2(12)=c83;
            app.Matriz1(12)=A83;
            app.Matriz3(12)=B83;
            
            app.Button_660.BackgroundColor=[0.96,0.96,0.96];
            app.Button_661.BackgroundColor=[0.96,0.96,0.96];
            app.Button_121.BackgroundColor=[0.96,0.96,0.96];
            app.Button_122.BackgroundColor=[0.96,0.96,0.96];
	        app.Button_662.BackgroundColor=[1.00,1.00,0.00];
            app.Button_559.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_559
        function Button_559ValueChanged(app, event)
            value = app.Button_559.Value;
            A84=6;
            B84=cellstr('Tres + dos');
            c84=cellstr('app.Button_559');
            app.Matriz2(12)=c84;
            app.Matriz1(12)=A84;
            app.Matriz3(12)=B84;
            
            app.Button_660.BackgroundColor=[0.96,0.96,0.96];
            app.Button_661.BackgroundColor=[0.96,0.96,0.96];
            app.Button_121.BackgroundColor=[0.96,0.96,0.96];
            app.Button_122.BackgroundColor=[0.96,0.96,0.96];
	        app.Button_662.BackgroundColor=[0.96,0.96,0.96];
            app.Button_559.BackgroundColor=[1.00,1.00,0.00];
        end

        % Value changed function: Button_116
        function Button_116ValueChanged(app, event)
            value = app.Button_116.Value;
            A88=1;
            B88=cellstr('Recto o ligeramente curvado');
            c88=cellstr('app.Button_116');
            app.Matriz2(14)=c88;
            app.Matriz1(14)=A88;
            app.Matriz3(14)=B88;
            
            app.Button_116.BackgroundColor=[1.00,1.00,0.00];
            app.Button_115.BackgroundColor=[0.96,0.96,0.96];
            app.Button_79.BackgroundColor=[0.96,0.96,0.96];
	        app.Button_78.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_115
        function Button_115ValueChanged(app, event)
            value = app.Button_115.Value;
            A89=2;
            B89=cellstr('Moderada');
            c89=cellstr('app.Button_115');
            app.Matriz2(14)=c89;
            app.Matriz1(14)=A89;
            app.Matriz3(14)=B89;
            
            app.Button_116.BackgroundColor=[0.96,0.96,0.96];
            app.Button_115.BackgroundColor=[1.00,1.00,0.00];
            app.Button_79.BackgroundColor=[0.96,0.96,0.96];
	        app.Button_78.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_79
        function Button_79ValueChanged(app, event)
            value = app.Button_79.Value;
            A90=3;
            B90=cellstr('Cerrada');
            c90=cellstr('app.Button_79');
            app.Matriz2(14)=c90;
            app.Matriz1(14)=A90;
            app.Matriz3(14)=B90;
            
            app.Button_116.BackgroundColor=[0.96,0.96,0.96];
            app.Button_115.BackgroundColor=[0.96,0.96,0.96];
            app.Button_79.BackgroundColor=[1.00,1.00,0.00];
	        app.Button_78.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_78
        function Button_78ValueChanged(app, event)
            value = app.Button_78.Value;
            A91=4;
            B91=cellstr('Muy cerrada');
            c91=cellstr('app.Button_78');
            app.Matriz2(14)=c91;
            app.Matriz1(14)=A91;
            app.Matriz3(14)=B91;
            
            app.Button_116.BackgroundColor=[0.96,0.96,0.96];
            app.Button_115.BackgroundColor=[0.96,0.96,0.96];
            app.Button_79.BackgroundColor=[0.96,0.96,0.96];
	        app.Button_78.BackgroundColor=[1.00,1.00,0.00];
        end

        % Value changed function: Button_92
        function Button_92ValueChanged(app, event)
            value = app.Button_92.Value;
            A92=1;
            B92=cellstr('Adecuada');
            c92=cellstr('app.Button_92');
            app.Matriz2(15)=c92;
            app.Matriz1(15)=A92;
            app.Matriz3(15)=B92;
            
            app.Button_92.BackgroundColor=[1.00,1.00,0.00];
            app.Button_93.BackgroundColor=[0.96,0.96,0.96];
            app.Button_94.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_93
        function Button_93ValueChanged(app, event)
            value = app.Button_93.Value;
            A93=2;
            B93=cellstr('Deficiente');
            c93=cellstr('app.Button_93');
            app.Matriz2(15)=c93;
            app.Matriz1(15)=A93;
            app.Matriz3(15)=B93;
            
            app.Button_92.BackgroundColor=[0.96,0.96,0.96];
            app.Button_93.BackgroundColor=[1.00,1.00,0.00];
            app.Button_94.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_94
        function Button_94ValueChanged(app, event)
            value = app.Button_94.Value;
            A94=3;
            B94=cellstr('No aplica');
            c94=cellstr('app.Button_94');
            app.Matriz2(15)=c94;
            app.Matriz1(15)=A94;
            app.Matriz3(15)=B94;
            
            app.Button_92.BackgroundColor=[0.96,0.96,0.96];
            app.Button_93.BackgroundColor=[0.96,0.96,0.96];
            app.Button_94.BackgroundColor=[1.00,1.00,0.00];


        end

        % Value changed function: Button_98
        function Button_98ValueChanged(app, event)
            value = app.Button_98.Value;
            A95=1;
            B95=cellstr('0 % a < 7,5 %');
            c95=cellstr('app.Button_98');
            app.Matriz2(16)=c95;
            app.Matriz1(16)=A95;
            app.Matriz3(16)=B95;
            
            app.Button_98.BackgroundColor=[1.00,1.00,0.00];
            app.Button_97.BackgroundColor=[0.96,0.96,0.96];
            app.Button_96.BackgroundColor=[0.96,0.96,0.96];
            
            
        end

        % Value changed function: Button_97
        function Button_97ValueChanged(app, event)
            value = app.Button_97.Value;
            A96=2;
            B96=cellstr('7,5 % a 10 %');
            c96=cellstr('app.Button_97');
            app.Matriz2(16)=c96;
            app.Matriz1(16)=A96;
            app.Matriz3(16)=B96;
            
            app.Button_98.BackgroundColor=[0.96,0.96,0.96];
            app.Button_97.BackgroundColor=[1.00,1.00,0.00];
            app.Button_96.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_96
        function Button_96ValueChanged(app, event)
            value = app.Button_96.Value;
            A97=3;
            B97=cellstr('≥ 10 %');
            c97=cellstr('app.Button_96');
            app.Matriz2(16)=c97;
            app.Matriz1(16)=A97;
            app.Matriz3(16)=B97;
            
            app.Button_98.BackgroundColor=[0.96,0.96,0.96];
            app.Button_97.BackgroundColor=[0.96,0.96,0.96];
            app.Button_96.BackgroundColor=[1.00,1.00,0.00];

        end

        % Value changed function: Button_562
        function Button_562ValueChanged(app, event)
            value = app.Button_562.Value;
            A98=1;
            B98=cellstr('Buena');
            c98=cellstr('app.Button_562');
            app.Matriz2(17)=c98;
            app.Matriz1(17)=A98;
            app.Matriz3(17)=B98;
            
            app.Button_562.BackgroundColor=[1.00,1.00,0.00];
            app.Button_561.BackgroundColor=[0.96,0.96,0.96];
            app.Button_560.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_561
        function Button_561ValueChanged(app, event)
            value = app.Button_561.Value;
            A99=2;
            B99=cellstr('Regular');
            c99=cellstr('app.Button_561');
            app.Matriz2(17)=c99;
            app.Matriz1(17)=A99;
            app.Matriz3(17)=B99;
            
            app.Button_562.BackgroundColor=[0.96,0.96,0.96];
            app.Button_561.BackgroundColor=[1.00,1.00,0.00];
            app.Button_560.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_560
        function Button_560ValueChanged(app, event)
            value = app.Button_560.Value;
            A100=3;
            B100=cellstr('Mala');
            c100=cellstr('app.Button_560');
            app.Matriz2(17)=c100;
            app.Matriz1(17)=A100;
            app.Matriz3(17)=B100;
            
            app.Button_562.BackgroundColor=[0.96,0.96,0.96];
            app.Button_561.BackgroundColor=[0.96,0.96,0.96];
            app.Button_560.BackgroundColor=[1.00,1.00,0.00];
        end

        % Value changed function: Button_126
        function Button_126ValueChanged(app, event)
            value = app.Button_126.Value;
            A101=1;
            B101=cellstr('Pavimentado – adecuado');
            c101=cellstr('app.Button_126');
            app.Matriz2(18)=c101;
            app.Matriz1(18)=A101;
            app.Matriz3(18)=B101;
            
            
            app.Button_126.BackgroundColor=[1.00,1.00,0.00];
            app.Button_101.BackgroundColor=[0.96,0.96,0.96];
            app.Button_127.BackgroundColor=[0.96,0.96,0.96];
            app.Button_100.BackgroundColor=[0.96,0.96,0.96];
            app.Button_128.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_101
        function Button_101ValueChanged(app, event)
            value = app.Button_101.Value;
            A102=2;
            B102=cellstr('Pavimentado – regular');
            c102=cellstr('app.Button_101');
            app.Matriz2(18)=c102;
            app.Matriz1(18)=A102;
            app.Matriz3(18)=B102;
            
            app.Button_126.BackgroundColor=[0.96,0.96,0.96];
            app.Button_101.BackgroundColor=[1.00,1.00,0.00];
            app.Button_127.BackgroundColor=[0.96,0.96,0.96];
            app.Button_100.BackgroundColor=[0.96,0.96,0.96];
            app.Button_128.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_127
        function Button_127ValueChanged(app, event)
            value = app.Button_127.Value;
            A103=3;
            B103=cellstr('Pavimentado – pobre');
            c103=cellstr('app.Button_127');
            app.Matriz2(18)=c103;
            app.Matriz1(18)=A103;
            app.Matriz3(18)=B103;
            
            app.Button_126.BackgroundColor=[0.96,0.96,0.96];
            app.Button_101.BackgroundColor=[0.96,0.96,0.96];
            app.Button_127.BackgroundColor=[1.00,1.00,0.00];
            app.Button_100.BackgroundColor=[0.96,0.96,0.96];
            app.Button_128.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_100
        function Button_100ValueChanged(app, event)
            value = app.Button_100.Value;
            A104=4;
            B104=cellstr('Sin pavimentar – adecuado');
            c104=cellstr('app.Button_100');
            app.Matriz2(18)=c104;
            app.Matriz1(18)=A104;
            app.Matriz3(18)=B104;
            
            
            app.Button_126.BackgroundColor=[0.96,0.96,0.96];
            app.Button_101.BackgroundColor=[0.96,0.96,0.96];
            app.Button_127.BackgroundColor=[0.96,0.96,0.96];
            app.Button_100.BackgroundColor=[1.00,1.00,0.00];
            app.Button_128.BackgroundColor=[0.96,0.96,0.96];
            
        end

        % Value changed function: Button_128
        function Button_128ValueChanged(app, event)
            value = app.Button_128.Value;
            A105=5;
            B105=cellstr('Sin pavimentar – pobre');
            c105=cellstr('app.Button_128');
            app.Matriz2(18)=c105;
            app.Matriz1(18)=A105;
            app.Matriz3(18)=B105;
            
            app.Button_126.BackgroundColor=[0.96,0.96,0.96];
            app.Button_101.BackgroundColor=[0.96,0.96,0.96];
            app.Button_127.BackgroundColor=[0.96,0.96,0.96];
            app.Button_100.BackgroundColor=[0.96,0.96,0.96];
            app.Button_128.BackgroundColor=[1.00,1.00,0.00];
        end

        % Value changed function: Button_564
        function Button_564ValueChanged(app, event)
            value = app.Button_564.Value;
            A106=1;
            B106=cellstr('Adecuada');
            c106=cellstr('app.Button_564');
            app.Matriz2(19)=c106;
            app.Matriz1(19)=A106;
            app.Matriz3(19)=B106;
            
            app.Button_564.BackgroundColor=[1.00,1.00,0.00];
            app.Button_563.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_563
        function Button_563ValueChanged(app, event)
            value = app.Button_563.Value;
            A107=2;
            B107=cellstr('Deficiente');
            c107=cellstr('app.Button_563');
            app.Matriz2(19)=c107;
            app.Matriz1(19)=A107;
            app.Matriz3(19)=B107;
            
            app.Button_564.BackgroundColor=[0.96,0.96,0.96];
            app.Button_563.BackgroundColor=[1.00,1.00,0.00];
        end

        % Value changed function: Button_566
        function Button_566ValueChanged(app, event)
            value = app.Button_566.Value;
            A108=1;
            B108=cellstr('Ausente');
            c108=cellstr('app.Button_566');
            app.Matriz2(20)=c108;
            app.Matriz1(20)=A108;
            app.Matriz3(20)=B108;
            
            app.Button_565.BackgroundColor=[0.96,0.96,0.96];
            app.Button_566.BackgroundColor=[1.00,1.00,0.00];
        end

        % Value changed function: Button_565
        function Button_565ValueChanged(app, event)
            value = app.Button_565.Value;
            A109=2;
            B109=cellstr('Presente');
            c109=cellstr('app.Button_565');
            app.Matriz2(20)=c109;
            app.Matriz1(20)=A109;
            app.Matriz3(20)=B109;
            
            app.Button_565.BackgroundColor=[1.00,1.00,0.00];
            app.Button_566.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_151
        function Button_151ValueChanged(app, event)
            value = app.Button_151.Value;
            A110=1;
            B110=cellstr('Ninguno');
            c110=cellstr('app.Button_151');
            app.Matriz2(21)=c110;
            app.Matriz1(21)=A110;
            app.Matriz3(21)=B110;
            
            app.Button_151.BackgroundColor=[1.00,1.00,0.00];
            app.Button_150.BackgroundColor=[0.96,0.96,0.96];
            app.Button_567.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_150
        function Button_150ValueChanged(app, event)
            value = app.Button_150.Value;
            A111=2;
            B111=cellstr('Un lado');
            c111=cellstr('app.Button_150');
            app.Matriz2(21)=c111;
            app.Matriz1(21)=A111;
            app.Matriz3(21)=B111;
            
            app.Button_151.BackgroundColor=[0.96,0.96,0.96];
            app.Button_150.BackgroundColor=[1.00,1.00,0.00];
            app.Button_567.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_567
        function Button_567ValueChanged(app, event)
            value = app.Button_567.Value;
            A112=3;
            B112=cellstr('Dos lados');
            c112=cellstr('app.Button_567');
            app.Matriz2(21)=c112;
            app.Matriz1(21)=A112;
            app.Matriz3(21)=B112;
            
            app.Button_151.BackgroundColor=[0.96,0.96,0.96];
            app.Button_150.BackgroundColor=[0.96,0.96,0.96];
            app.Button_567.BackgroundColor=[1.00,1.00,0.00];
        end

        % Value changed function: Button_139
        function Button_139ValueChanged(app, event)
            value = app.Button_139.Value;
            A115=1;
            B115=cellstr('Sin obras');
            c115=cellstr('app.Button_139');
            app.Matriz2(23)=c115;
            app.Matriz1(23)=A115;
            app.Matriz3(23)=B115;
            
            app.Button_139.BackgroundColor=[1.00,1.00,0.00];
            app.Button_141.BackgroundColor=[0.96,0.96,0.96];
            app.Button_138.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_141
        function Button_141ValueChanged(app, event)
            value = app.Button_141.Value;
            A116=2;
            B116=cellstr('Obra menor en proceso');
            c116=cellstr('app.Button_141');
            app.Matriz2(23)=c116;
            app.Matriz1(23)=A116;
            app.Matriz3(23)=B116;
            
            app.Button_139.BackgroundColor=[0.96,0.96,0.96];
            app.Button_141.BackgroundColor=[1.00,1.00,0.00];
            app.Button_138.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_138
        function Button_138ValueChanged(app, event)
            value = app.Button_138.Value;
            A117=3;
            B117=cellstr('Obra mayor en proceso');
            c117=cellstr('app.Button_138');
            app.Matriz2(23)=c117;
            app.Matriz1(23)=A117;
            app.Matriz3(23)=B117;
            
            app.Button_139.BackgroundColor=[0.96,0.96,0.96];
            app.Button_141.BackgroundColor=[0.96,0.96,0.96];
            app.Button_138.BackgroundColor=[1.00,1.00,0.00];
        end

        % Value changed function: Button_145
        function Button_145ValueChanged(app, event)
            value = app.Button_145.Value;
            A118=1;
            B118=cellstr('Adecuada');
            c118=cellstr('app.Button_145');
            app.Matriz2(24)=c118;
            app.Matriz1(24)=A118;
            app.Matriz3(24)=B118;
            
            app.Button_145.BackgroundColor=[1.00,1.00,0.00];
            app.Button_144.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_144
        function Button_144ValueChanged(app, event)
            value = app.Button_144.Value;
            A119=2;
            B119=cellstr('Deficiente');
            c119=cellstr('app.Button_144');
            app.Matriz2(24)=c119;
            app.Matriz1(24)=A119;
            app.Matriz3(24)=B119;
            
            app.Button_145.BackgroundColor=[0.96,0.96,0.96];
            app.Button_144.BackgroundColor=[1.00,1.00,0.00];
        end

        % Value changed function: NoButton_19
        function NoButton_19ValueChanged(app, event)
            value = app.NoButton_19.Value;
            A113=1;
            B113=cellstr('Ausente');
            c113=cellstr('app.NoButton_19');
            app.Matriz2(22)=c113;
            app.Matriz1(22)=A113;
            app.Matriz3(22)=B113;
            
            app.SiButton_18.BackgroundColor=[0.96,0.96,0.96];
            app.NoButton_19.BackgroundColor=[1.00,1.00,0.00];
        end

        % Value changed function: SiButton_18
        function SiButton_18ValueChanged(app, event)
            value = app.SiButton_18.Value;
            A114=2;
            B114=cellstr('Presente');
            c114=cellstr('app.SiButton_18');
            app.Matriz2(22)=c114;
            app.Matriz1(22)=A114;
            app.Matriz3(22)=B114;
            
            app.SiButton_18.BackgroundColor=[1.00,1.00,0.00];
            app.NoButton_19.BackgroundColor=[0.96,0.96,0.96];
        end

        % Value changed function: Button_712
        function Button_712ValueChanged2(app, event)
            value = app.Button_712.Value;
            A156_9=10;
            B156_9=cellstr('100 %');
            c156_9=cellstr('app.Button_712');
            app.Matriz2(31)=c156_9; 
            app.Matriz1(31)=A156_9;
            app.Matriz3(31)=B156_9;
            
            app.NAButton.BackgroundColor=[0.96,0.96,0.96];
            app.Button_270.BackgroundColor=[0.96,0.96,0.96];
            app.Button_271.BackgroundColor=[0.96,0.96,0.96];
            app.Button_263.BackgroundColor=[0.96,0.96,0.96];
            app.Button_264.BackgroundColor=[0.96,0.96,0.96];
            app.Button_261.BackgroundColor=[0.96,0.96,0.96];
            app.Button_262.BackgroundColor=[0.96,0.96,0.96];
            app.Button_275.BackgroundColor=[0.96,0.96,0.96];
            app.Button_276.BackgroundColor=[0.96,0.96,0.96];
            app.Button_712.BackgroundColor=[1.00,1.00,0.00];
        end

        % Value changed function: CarrilG_2
        function CarrilG_2ValueChanged(app, event)
            value = app.CarrilG_2.Value;
            A85=1;
            B85=cellstr('Ancho (≥ 3,25 m)');
            c85=cellstr('app.CarrilG_2');
            app.Matriz2(13)=c85;
            app.Matriz1(13)=A85;
            app.Matriz3(13)=B85;
            
            app.CarrilG_2.BackgroundColor=[1.00,1.00,0.00];
            app.CarrilM.BackgroundColor=[0.96,0.96,0.96];
            app.CarrilP.BackgroundColor=[0.96,0.96,0.96];
        end

        % Callback function: PDF, ResultadoTab
        function CalcularButtonPushed(app, event)

%Reporte
import mlreportgen.report.*
import mlreportgen.dom.*
import mlreportgen.ppt.*
rpt_name = strcat('Reporte Detallado - Tramo', int2str(app.v1));
rpt = Report(rpt_name,'pdf');
Infinicial=load('../mat/Inf_proyecto.mat');
%rpt = Report(' Reporte Final', 'pdf');

%Portada
titlepg = TitlePage;
titlepg.Title = 'CLASIFICACIÓN POR ESTRELLAS';
titlepg.Subtitle = 'Reporte detallado';
titlepg.Author = 'UNIVERSIDAD TÉCNICA PARTICULAR DE LOJA-SISTEMA DE EVALUACIÓN DE SEGURIDAD VIAL';
add(rpt,titlepg);

%Tabla de contenidos
add(rpt,TableOfContents);

%Capítulos
%Capítulo 1
chap1 = Chapter;
chap1.Title ='Descargo de responsabilidad';
p=Paragraph('Este descargo de responsabilidad es un acuerdo entre la UTPL y usted el usuario.'); %,HAlign,'justify'
p.FontFamilyName='Gotham';
p.HAlign='justify';
add(chap1,p);
k1=Paragraph('El software SIEC y toda la documentación relacionada, son y deben ser siempre propiedad intelectual de la UTPL. Nada de lo contenido en este acuerdo debe ser considerado por el usuario para darle título de propiedad al software o de la documentación relacionada. ');
k1.FontFamilyName='Gotham';
k1.HAlign='justify';
add(chap1,k1);
k2=Paragraph('Este software fue desarrollado para analizar el nivel de seguridad vial de las carreteras rurales del Ecuador. Incluye dos funcionalidades principales. La primera funcionalidad hace uso del archivo VBOX donde los radios de las curvas horizontales son aproximaciones del diseño horizontal, dado que se obtuvieron con el uso de los datos de posición del Video VBOX Lite. Así mismo, la pendiente fue calculada mediante los datos del perfil del mismo equipo utilizando el promedio de dos métodos: método a y método b. El ancho del carril, espaldón y la separación de objetos de la calzada fueron calculados a partir de una imagen del video del mismo archivo. La velocidad de operación fue calculada en base a las ecuaciones de predicción locales. El resto de elementos han sido ingresados de manera manual por el usuario. La segunda funcionalidad hace uso sólo de fotografías, en la cual se eliminará lo que no corresponde con el Video VBOX Lite. El usuario debe comprender explícitamente los supuestos de la metodología, análisis y resultados obtenidos mediante el software. La información producida por el software debe ser comprobada por un ingeniero calificado y con experiencia. El ingeniero debe verificar independientemente los resultados y tomar profesional responsabilidad por la información que se utiliza.');
k2.FontFamilyName='Gotham';
k2.HAlign='justify';
add(chap1,k2);
k3=Paragraph('Este programa ha sido probado y verificado exhaustivamente, sin embargo, como ocurre con todo software complejo, es posible que estos programas no estén completamente libres de errores y que no sean aplicables en todos los casos. El usuario acepta y entiende que no hay garantía expresa o implícitas por los desarrolladores o los distribuidores sobre la precisión o la fiabilidad de este producto. En ningún caso la Universidad Técnica Particular de Loja. Será responsable por daños directos, indirectos, especiales, incidentales o consecuentes que surjan del uso del programa y / o documentación asociada. Además, la reproducción y difusión del material generado por el software son solo para fines educativos e informativos, los fines comerciales sin el permiso por escrito de los titulares de los derechos de autor está prohibida. ');
k3.FontFamilyName='Gotham';
k3.HAlign='justify';
add(chap1,k3);
k4=Paragraph('Si no está satisfecho con alguna parte del software SIEC o con alguno de estos términos de uso, su único y exclusivo recurso es dejar de usar el programa.');
k4.FontFamilyName='Gotham';
k4.HAlign='justify';
add(chap1,k4);
add(rpt,chap1);

%Capítulo 2
            chap2 = Chapter('Información del archivo');
            
            Ubi1='Nombre del proyecto:';
            Ubi2=Infinicial.T_proyecto;
            p1=Paragraph(strcat(Ubi1," ",Ubi2));
            p1.FontFamilyName='Gotham';
            add(chap2,p1);
            
            Ubi1='Ubicación del proyecto:';
            Ubi2=Infinicial.Lugar;
            p1=Paragraph(strcat(Ubi1," ",Ubi2));
            p1.FontFamilyName='Gotham';
            add(chap2,p1);

            Tramo1='Tramo analizado:';
            Tramo2=num2str(app.v1);
            p1=Paragraph(strcat(Tramo1," ",Tramo2));
            p1.FontFamilyName='Gotham';
            add(chap2,p1);
            fecha1='Fecha de elaboración del análisis:';
            fecha2=num2str(date());
            p1=Paragraph(strcat(fecha1," ",fecha2));
            p1.FontFamilyName='Gotham';
            add(chap2,p1);
            
            Des1='Descripción del proyecto:';
            Des2=Infinicial.Descrip;
            p1=Paragraph(strcat(Des1," ",Des2));
            p1.FontFamilyName='Gotham';
            add(chap2,p1);
            add(rpt,chap2);


%Capítulo 3
chap3 = Chapter;
chap3.Title ='Atributos vial codificados';
p2 = Paragraph('Resumen de atributos');
p2.Bold=1;
add(chap3,p2);
%MAGEN
p1 = Paragraph('Imagen del tramo');
p1.Bold=1;
add(chap3,p1);
%clear image
%image = mlreportgen.report.FormalImage();
%image.Image = app.jpgFilename;
%add(chap3,image)
%Grafico tramo
p9 = Paragraph('Tramo');
p9.Bold=1;
add(chap3,p9);
%Grafico
load('../mat/preproceso_tramo.mat');
%app.Image.Visible='off';
            B=sum(arrayfun(@(tramo) ~isempty(tramo.Tramos),tramo));
           
            
app.TramodeanlisisSpinner.Limits=[1 B];
app.v1=app.TramodeanlisisSpinner.Value;
app.Image.Visible='on';

fig1 = figure('visible','off');
Tramos=[tramo(app.v1).Tramos];
Distancia=[tramo(app.v1).Distancia];
Xt=([tramo(app.v1).Xt]);
yt=([tramo(app.v1).yt]);
plot(Xt,yt)
%Tramos=[tramo(app.v1).Tramos];
%Distancia=[tramo(app.v1).Distancia];
%Xt=([tramo(app.v1).Xt]);
%yt=([tramo(app.v1).yt]);
%met_cord=size(Xt,1)
%   if met_cord>1
%      plot(app.UIAxes,Xt,yt)
%   else
%      sin_coord = imread('../img/blancoo.jpg')
%      imshow(sin_coord,'Parent',app.UIAxes)
%    end
xlabel("Longitud");
ylabel('Latitud');
title("Tramo analizado");
add(chap3,Figure(fig1))

%b=load('../mat/preproceso_tramo');
%fig1 = figure('visible','off');
%x=b.preproceso_tramo.tramo.Xt(1,5);
%x=b.preproceso_tramo.tramo.yt(1,6);
%plot(x,y);
%add(chap3,Figure(fig1))
%add(chap3,Paragraph('Costado de la vía:'));
p3 = Paragraph('Costado de la via');
p3.Bold=1;
add(chap3,p3);
a=load('../mat/Datos_interfaz_tres_proceso');
x1=a.Datos_interfaz_tres.Severidad_lateral_distancia_al_objeto_lado_del_conductor(1,1);
x2='Severidad lateral – distancia al objeto lado del conductor:  ';
s = strcat(x2," ",x1)
add(chap3,s)
x3='Severidad lateral – distancia al objeto (lado del copiloto):  ';
x4=a.Datos_interfaz_tres.Severidad_lateral_distancia_al_objeto_lado_del_copiloto(1,1);
s1 = strcat(x3," ",x4);
add(chap3,s1)
x5='Severidad lateral – objeto (lado del conductor):  ';
x6=a.Datos_interfaz_tres.Severidad_lateral_objeto_lado_del_conductor(1,1);
s2 = strcat(x5," ",x6);
add(chap3,s2)
x7='Severidad lateral – objeto (lado del copiloto):  ';
x8=a.Datos_interfaz_tres.Severidad_lateral_objeto_lado_del_copiloto(1,1);
s3 = strcat(x7," ",x8);
add(chap3,s3)
x9='Bandas sonoras en el espaldón:  ';
x10=a.Datos_interfaz_tres.Bandas_sonoras_en_el_espaldon(1,1);
s4 = strcat(x9," ",x10);
add(chap3,s4)
x11='Ancho de espaldón pavimentado (lado del conductor):  ';
x12=a.Datos_interfaz_tres.Ancho_de_espaldon_pavimentado_lado_del_conductor(1,1);
s5 = strcat(x11," ",x12);
add(chap3,s5)
x13='Ancho de espaldón pavimentado (lado del copiloto):  ';
x14=a.Datos_interfaz_tres.Ancho_de_espaldon_pavimentado_lado_del_copiloto(1,1);
s6 = strcat(x13," ",x14);
add(chap3,s6)

p4 = Paragraph('Mediana:');
p4.Bold=1;
add(chap3,p4);

x15='Tipo de carretera:  '; 
x16=a.Datos_interfaz_tres.Tipo_de_carretera(1,1);
s7=strcat(x15," ",x16);
add(chap3,s7);
x17='Costo de modernización:  ';
x18=a.Datos_interfaz_tres.Costo_de_modernizacion(1,1);
s8=strcat(x17," ",x18);
add(chap3,s8);
x19='Tipo de mediana:  ';
x20=a.Datos_interfaz_tres.Tipo_de_mediana(1,1);
s9=strcat(x19," ",x20);
add(chap3,s9);
x21='Bandas sonoras centrales:  ';
x22=a.Datos_interfaz_tres.Bandas_sonoras_centrales(1,1);
s10=strcat(x21," ",x22);
add(chap3,s10);
x23='Número de carriles:  ';
x24=a.Datos_interfaz_tres.Numero_de_carriles(1,1);
s11=strcat(x23," ",x24);
add(chap3,s11);
x25='Ancho de carril:  ';
x26=a.Datos_interfaz_tres.Ancho_de_carril(1,1);
s12=strcat(x25," ",x26);
add(chap3,s12);
x27='Curvatura:  ';
x28=a.Datos_interfaz_tres.Curvatura(1,1)
s13=strcat(x27," ",x28);
add(chap3,s13);
x29='Calidad de la curva:  ';
x30=a.Datos_interfaz_tres.Calidad_de_la_curva(1,1);
s14=strcat(x29," ",x30);
add(chap3,s14);
x31='Pendiente:  ';
x32=a.Datos_interfaz_tres.Pendiente(1,1);
s15=strcat(x31," ",x32);
add(chap3,s15);
x33='Estado de la superficie de la vía:  ';
x34=a.Datos_interfaz_tres.Estado_de_la_superficie_de_la_via(1,1);
s16=strcat(x33," ",x34);
add(chap3,s16);
x35='Resistencia al deslizamiento:  ';
x36=a.Datos_interfaz_tres.Resistencia_al_deslizamiento(1,1);
s17=strcat(x35," ",x36);
add(chap3,s17);
x37='Delineación:  ';
x38=a.Datos_interfaz_tres.Delineacion(1,1);
s18=strcat(x37," ",x38);
add(chap3,s18);
x39='Alumbrado público:  ';
x40=a.Datos_interfaz_tres.Alumbrado_publico(1,1);
s19=strcat(x39," ",x40);
add(chap3,s19);
x41='Estacionamiento para vehículos: ';
x42=a.Datos_interfaz_tres.Estacionamiento_para_vehiculos(1,1);
s20=strcat(x41," ",x42);
add(chap3,s20);
x43='Vía de servicio:  ';
x44=a.Datos_interfaz_tres.Via_de_servicio(1,1);
s21=strcat(x43," ",x44);
add(chap3,s21);
x45='Obras en la carretera:  ';
x46=a.Datos_interfaz_tres.Obras_en_la_carretera(1,1);
s22=strcat(x45," ",x46);
add(chap3,s22);
x47='Distancia de visibilidad:  ';
x48=a.Datos_interfaz_tres.Distancia_de_visibilidad(1,1);
s23=strcat(x47," ",x48);
add(chap3,s23);

p5 = Paragraph('Intersecciones:');
p5.Bold=1;
add(chap3,p5);

x49='Tipo de intersección:  '; 
x50=a.Datos_interfaz_tres.Tipo_de_interseccion(1,1);
s24=strcat(x49," ",x50);
add(chap3,s24);
x51='Canalización de la intersección:  ';
x52=a.Datos_interfaz_tres.Canalizacion_de_la_interseccion(1,1);
s25=strcat(x51," ",x52);
add(chap3,s25);
x53='Volumen de la intersección:  '; 
x54=a.Datos_interfaz_tres.Volumen_de_la_interseccion(1,1);
s26=strcat(x53," ",x54);
add(chap3,s26);
x55='Calidad de la intersección:  '; 
x56=a.Datos_interfaz_tres.Calidad_de_la_interseccion(1,1);
s27=strcat(x55," ",x56);
add(chap3,s27);
x57='Puntos de acceso a la propiedad:  ';
x58=a.Datos_interfaz_tres.Puntos_de_acceso_a_la_propiedad(1,1);
s28=strcat(x57," ",x58);
add(chap3,s28);

p6 = Paragraph('Flujo:');
p6.Bold=1;
add(chap3,p6);

x59='Flujo vehicular (TDPA):  ';
x60=a.Datos_interfaz_tres.Flujo_vehicular(1,1);
s29=strcat(x59," ",x60);
add(chap3,s29);
x61='% Porcentaje motocicletas:  ';
x62=a.Datos_interfaz_tres.Porcentaje_de_motocicletas(1,1);
s30=strcat(x61," ",x62);
add(chap3,s30);
x63='Flujo peatonal en hora pico que cruzan la carretera:  ';
x64=a.Datos_interfaz_tres.Flujo_peatonal_en_hora_pico_que_cruza_la_carretera(1,1);
s31=strcat(x63," ",x64);
add(chap3,s31);
x65='Flujo peatonal en hora pico a lo largo de la carretera (lado del conductor):  ';
x66=a.Datos_interfaz_tres.Flujo_peatonal_en_hora_pico_a_lo_largo_de_la_carretera_Cond(1,1);
s32=strcat(x65," ",x66);
add(chap3,s32);
x67='Flujo peatonal en hora pico a lo largo de la carretera (lado del copiloto):  ';
x68=a.Datos_interfaz_tres.Flujo_peatonal_en_hora_pico_a_lo_largo_de_la_carretera_Cop(1,1);
s33=strcat(x67," ",x68);
add(chap3,s33);
x69='Flujo de bicicletas en hora pico:  ';
x70=a.Datos_interfaz_tres.Flujo_de_bicicletas_en_hora_pico(1,1);
s34=strcat(x69," ",x70);
add(chap3,s34);

p7 = Paragraph('Infraestructura para usuarios vulnerables y uso del suelo:');
p7.Bold=1;
add(chap3,p7);

x71='Uso de suelo (lado del conductor):  ';
x72=a.Datos_interfaz_tres.Uso_de_suelo_lado_del_conductor(1,1);
s35=strcat(x71," ",x72);
add(chap3,s35);
x73='Uso de suelo (lado del copiloto):  ';
x74=a.Datos_interfaz_tres.Uso_de_suelo_lado_del_copiloto(1,1);
s36=strcat(x73," ",x74);
add(chap3,s36);
x75='Tipo de área:  ';
x76=a.Datos_interfaz_tres.Tipo_de_area(1,1);
s37=strcat(x75," ",x76);
add(chap3,s37);
x77='Infraestructura de cruce peatonal en el tramo analizado:  ';
x78=a.Datos_interfaz_tres.Infraestructura_de_cruce_peatonal_en_el_tramo_analizado(1,1);
s38=strcat(x77," ",x78);
add(chap3,s38);
x79='Calidad de cruce peatonal:  ';
x80=a.Datos_interfaz_tres.Calidad_de_cruce_peatonal(1,1);
s39=strcat(x79," ",x80);
add(chap3,s39);
x81='Infraestructura de cruce peatonal en la intersección:  ';
x82=a.Datos_interfaz_tres.Infraestructura_de_cruce_peatonal_en_la_interseccion(1,1);
s40=strcat(x81," ",x82);
add(chap3,s40);
x83='Vallas peatonales:  ';
x84=a.Datos_interfaz_tres.Vallas_peatonales(1,1);
s41=strcat(x83," ",x84);
add(chap3,s41);
x85='Acera (lado del conductor):  ';
x86=a.Datos_interfaz_tres.Acera_lado_del_conductor(1,1);
s42=strcat(x85," ",x86);
add(chap3,s42);
x87='Acera (lado del copiloto):  ';
x88=a.Datos_interfaz_tres.Acera_lado_del_copiloto(1,1);
s43=strcat(x87," ",x88);
add(chap3,s43);
x89='Infraestructura para motocicletas:  ';
x90=a.Datos_interfaz_tres.Infraestructura_para_motocicletas(1,1);
s44=strcat(x89," ",x90);
add(chap3,s44);
x91='Infraestructura para bicicletas:  ';
x92=a.Datos_interfaz_tres.Infraestructura_para_bicicletas(1,1);
s45=strcat(x91," ",x92);
add(chap3,s45);
x93='Advertencia de zona escolar: ';
x94=a.Datos_interfaz_tres.Advertencia_de_zona_escolar(1,1);
s46=strcat(x93," ",x94);
add(chap3,s46);
x95='Supervisor en el cruce de zona escolar:  ';
x96=a.Datos_interfaz_tres.Supervisor_en_el_cruce_de_zona_escolar(1,1);
s47=strcat(x95," ",x96);
add(chap3,s47);

p8 = Paragraph('Velocidades'); 
p8.Bold=1;
add(chap3,p8);

x97='Límite de velocidad:  ';
x98=a.Datos_interfaz_tres.Limite_de_velocidad(1,1);
s48=strcat(x97," ",x98);
add(chap3,s48);
x99='Diferencial de límites de velocidad:  ';
x100=a.Datos_interfaz_tres.Diferencial_de_limites_de_velocidad(1,1);
s49=strcat(x99," ",x100);
add(chap3,s49);
x101='Gestión de la velocidad/calmante del tráfico:  ';
x102=a.Datos_interfaz_tres.Gestion_de_la_velocidad_calmante_del_trafico  (1,1);
s50=strcat(x101," ",x102);
add(chap3,s50);
x103='Velocidad de operación (percentil 85):  ';
x104=a.Datos_interfaz_tres.Velocidad_de_operacion_percentil_85  (1,1);
s51=strcat(x103," ",x104);
add(chap3,s51);
add(rpt,chap3);

%Capitulo 4 %Se detalla el procedimiento del calculo para todo
chap4 = Chapter;
chap4.Title ='Procedimiento de cálculo';
sec4_1 = Section; 
%CLASIFICACIÓN POR ESTRELLAS PARA OCUPANTE DEL VEHÍCULO
sec4_1.Title = 'Clasificación por Estrellas para Ocupante del vehículo';
k5=Paragraph('El SRS representa el riesgo relativo de fallecimiento o de lesiones serias para un usuario de carretera en particular. Para calcular las puntuaciones para la clasificación por estrellas (Star Rating Scores - SRS), es necesario estimar las puntuaciones parciales por tipo de siniestro. Los siniestros que intervienen son salida del camino (lado del conductor), salida del camino (lado del copiloto), choque frontal (pérdida de control), choque frontal (adelantamiento), siniestros en intersecciones y en acceso a propiedades.'); 
k5.FontFamilyName='Gotham';
k5.HAlign='justify';
add(chap4,k5);
add(chap4,sec4_1);
p10 = Paragraph('a) Salida del camino (lado del conductor)');
p10.Bold=1;
add(chap4,p10)
sec4_5 = Section;
tWavy = Text(' Probabilidad de los atributos viales');
tWavy.Style = {Underline('single')};
add(chap4,tWavy);
k6=Paragraph('Los factores de riesgo, asociados a la probabilidad del siniestro, para el ocupante del vehículo y salida del camino se describen a continuación:');
k6.FontFamilyName='Gotham';
k6.HAlign='justify';
add(chap4,k6);
add(chap4,sec4_5);
x1='El atributo vial ancho del carril es  ';
a=load('../mat/Datos_interfaz_tres_proceso');
b=load('../mat/Coeficientes_Veh')
x2=a.Datos_interfaz_tres.Ancho_de_carril(1,1);
x3='en una zona' 
x4=  a.Datos_interfaz_tres.Tipo_de_area(1,1);
x5=', entonces, el factor de riesgo es   '
x6=b.Coeficientes_Veh.Salida_lado_conductor(1,1);
x6_1=string(x6);
x7='(tabla 2)';
%s1=strcat('El atributo vial ancho del carril es'," ",int2str(x2)," ",'en una zona'," ",int2str(x4)," ",', entonces el factor de riesgo es'," ",int2str(x6_1)," ",'(tabla 2).');
s1=Paragraph(strcat(x1," ",x2," ",x3," ",x4," ",x5," ",x6_1," ",x7));
s1.FontFamilyName='Gotham';
s1.HAlign='justify';
add(chap4,s1);
x8='El atributo vial curvatura es  ';
x9= a.Datos_interfaz_tres.Curvatura(1,1);
x10='  , por lo tanto, el factor de riesgo es  '
x11=b.Coeficientes_Veh.Salida_lado_conductor(1,2);
x11_1=string(x11);
x12='  (tabla 3).';
s2=Paragraph(strcat(x8," ",x9," ",x10," ",x11_1," ",x12));
s2.FontFamilyName='Gotham';
s2.HAlign='justify';
add(chap4,s2);
x13='El atributo vial calidad de la curva es  ';
x14=a.Datos_interfaz_tres.Calidad_de_la_curva(1,1);
x15='  , entonces, el factor de riesgo es  ';
x16=b.Coeficientes_Veh.Salida_lado_conductor(1,3);
x16_1=string(x16);
x17='  (tabla 4).';
s3=Paragraph(strcat(x13," ",x14," ",x15," ",x16_1," ",x17));
s3.FontFamilyName='Gotham';
s3.HAlign='justify';
add(chap4,s3);
x18='El atributo vial delineación es   ';
x19=a.Datos_interfaz_tres.Delineacion(1,1);
x20='  , en consecuencia, el factor de riesgo es   ';
x21=b.Coeficientes_Veh.Salida_lado_conductor(1,4);
x21_1=string(x21);
x22='  (tabla 5).';
s4=Paragraph(strcat(x18," ",x19," ",x20," ",x21_1," ",x22));
s4.FontFamilyName='Gotham';
s4.HAlign='justify';
add(chap4,s4);
x23='El atributo vial bandas sonoras en el espaldón es   ';
x24=a.Datos_interfaz_tres.Bandas_sonoras_en_el_espaldon(1,1);
x25='  , con lo que el factor de riesgo es    ';
x26=b.Coeficientes_Veh.Salida_lado_conductor(1,5);
x26_1=string(x26)
x27='  (tabla 6).';
s5=Paragraph(strcat(x23," ",x24," ",x25," ",x26_1," ",x27));
s5.FontFamilyName='Gotham';
s5.HAlign='justify';
add(chap4,s5);
x28='El atributo vial condición de la vía es   ';
x29=a.Datos_interfaz_tres.Estado_de_la_superficie_de_la_via(1,1);
x30='  , por lo tanto, el factor de riesgo es     ';
x31=b.Coeficientes_Veh.Salida_lado_conductor(1,6);
x31_1=string(x31);
x32='  (tabla 7).';
s6=Paragraph(strcat(x28," ",x29," ",x30," ",x31_1," ",x32));
s6.FontFamilyName='Gotham';
s6.HAlign='justify';
add(chap4,s6);
x33='El atributo vial pendiente es   ';
x34=a.Datos_interfaz_tres.Pendiente(1,1);
x35='  , entonces, el factor de riesgo es     ';
x36=b.Coeficientes_Veh.Salida_lado_conductor(1,7); 
x36_1=string(x36);
x37='  (tabla 8).';
s7=Paragraph(strcat(x33," ",x34," ",x35," ",x36_1," ",x37));
s7.FontFamilyName='Gotham';
s7.HAlign='justify';
add(chap4,s7);
x38='El atributo vial resistencia al deslizamiento es   ';
x39=a.Datos_interfaz_tres.Resistencia_al_deslizamiento(1,1);
x40='  , en consecuencia, el factor de riesgo es     ';
x41=b.Coeficientes_Veh.Salida_lado_conductor(1,8);
x41_1=string(x41);
x42='  para ocupante del vehículo, salida del camino e intersección (tabla 9).';
s8=Paragraph(strcat(x38," ",x39," ",x40," ",x41_1," ",x42));
s8.FontFamilyName='Gotham';
s8.HAlign='justify';
add(chap4,s8);
sec4_6 = Section;
k17=Paragraph('La probabilidad de siniestro vial en el lado del conductor se obtiene multiplicando los factores de riesgo previamente obtenidos.');
k17.FontFamilyName='Gotham';
k17.HAlign='justify';
add(chap4,k17);
k18=Paragraph('Probabilidad = ancho del carril x curvatura x calidad de la curva x delineación x bandas sonoras en el espaldón x condición de la vía x pendiente x resistencia al deslizamiento');
k18.FontFamilyName='Gotham';
k18.HAlign='justify';
add(chap4,k18);
add(chap4,sec4_6);
x43='Probabilidad =';
s9=Paragraph(strcat(x43,x6_1,'x',x11_1,'x',x16_1,'x',x21_1,'x',x26_1,'x',x31_1,'x',x36_1,'x',x41_1));
s9.FontFamilyName='Gotham';
s9.HAlign='justify';
add(chap4,s9);
x44=b.Coeficientes_Veh.Salida_lado_conductor(1,15);
x44_1=string(x44);
s10=strcat(x43,x44_1);
add(chap4,s10);
sec4_7 = Section;
tWavy = Text(' Severidad de los atributos viales');
tWavy.Style = {Underline('single')};
add(chap4,tWavy);
k7=Paragraph('Los factores de riesgo, asociados a la gravedad/severidad del siniestro, para el ocupante del vehículo y salida del camino se describen a continuación:');
k7.FontFamilyName='Gotham';
k7.HAlign='justify';
add(chap4,k7);
add(chap4,sec4_7);
x43='El atributo vial severidad lateral – distancia al objeto (lado del conductor) es ';
x44=a.Datos_interfaz_tres.Severidad_lateral_distancia_al_objeto_lado_del_conductor(1,1);
x45=' , por lo que el factor de riesgo es ';
x46=b.Coeficientes_Veh.Salida_lado_conductor(1,9);
x46_1=string(x46);
x47=' (tabla 10).';
s11=Paragraph(strcat(x43," ",x44," ",x45," ",x46_1," ",x47));
s11.FontFamilyName='Gotham';
s11.HAlign='justify';
add(chap4,s11);
x48='El atributo vial severidad lateral – objeto (lado del conductor) es ';
x49=a.Datos_interfaz_tres.Severidad_lateral_objeto_lado_del_conductor(1,1);
x50=' , en consecuencia, el factor de riesgo es ';
x51=b.Coeficientes_Veh.Salida_lado_conductor(1,10);
x51_1=string(x51);
x52=' (tabla 11).';
s12=Paragraph(strcat(x48," ",x49," ",x50," ",x51_1," ",x52));
s12.FontFamilyName='Gotham';
s12.HAlign='justify';
add(chap4,s12)
x53='El atributo vial ancho de espaldón pavimentado (lado del conductor) es ';
x54=a.Datos_interfaz_tres.Ancho_de_espaldon_pavimentado_lado_del_conductor(1,1);
x55=' , por lo tanto el factor de riesgo es ';
x56=b.Coeficientes_Veh.Salida_lado_conductor(1,11);
x56_1=string(x56);
x57=' (tabla 12).';
s13=Paragraph(strcat(x53," ",x54," ",x55," ",x56_1," ",x57));
s13.FontFamilyName='Gotham';
s13.HAlign='justify';
add(chap4,s13);
sec4_8 = Section;
k15=Paragraph('La gravedad de siniestro vial en el lado del conductor se obtiene multiplicando los factores de riesgo previamente determinados.');
k15.FontFamilyName='Gotham';
k15.HAlign='justify'
add(chap4,k15);
k16=Paragraph('Severidad = severidad lateral – distancia al objeto (lado del conductor) x severidad lateral – objeto (lado del conductor) x ancho de espaldón pavimentado (lado del conductor)');
k16.FontFamilyName='Gotham';
k16.HAlign='justify';
add(chap4,k16);
add(chap4,sec4_8);
x58='Severidad =';
s14=strcat(x58,x46_1,'x',x51_1,'x',x56_1);
add(chap4,s14);
x59=b.Coeficientes_Veh.Salida_lado_conductor(1,16);
x59_1=string(x59);
s15=strcat(x58,x59_1);
add(chap4,s15);

tWavy = Text(' Influencia del flujo');
tWavy.Style = {Underline('single')};
add(chap4,tWavy);
x59='El atributo vial flujo vehicular (TDPA) es ';
x60=a.Datos_interfaz_tres.Flujo_vehicular(1,1);
x61='vehículos por día, entonces, el factor de riesgo es ';
x62=b.Coeficientes_Veh.Salida_lado_conductor(1,12);
x62_1=string(x62);
s16=Paragraph(strcat(x59," ",x60," ",x61," ",x62_1));
s16.FontFamilyName='Gotham';
s16.HAlign='justify';
add(chap4,s16);

tWavy = Text(' Transitabilidad de la mediana');
tWavy.Style = {Underline('single')};
add(chap4,tWavy);
x63='El atributo vial transitabilidad de la mediana es ';
x64=a.Datos_interfaz_tres.Transitabilidad(1,1);
x65=' , por lo tanto, el factor de riesgo es ';
x66=b.Coeficientes_Veh.Salida_lado_conductor(1,13);
x66_1=string(x66)
s17=Paragraph(strcat(x63," ",x64," ",x65," ",x66_1));
s17.FontFamilyName='Gotham';
s17.HAlign='justify';
add(chap4,s17);

tWavy = Text(' Velocidad de operación (percentil 85)');
tWavy.Style = {Underline('single')};
add(chap4,tWavy);
x67='El atributo vial velocidad de operación (percentil 85) es ';
x68=a.Datos_interfaz_tres.Velocidad_de_operacion_percentil_85 (1,1);
x69=' para ocupantes de vehículos y motociclistas (caminos divididos, no divididos y rurales, de frente, de salida e intersección), en consecuencia, el factor de riesgo es ';
x70=b.Coeficientes_Veh.Salida_lado_conductor(1,14);
x70_1=string(x70);
x71='(tabla 15).';
s18=Paragraph(strcat(x67," ",x68," ",x69," ",x70_1," ",x71));
s18.FontFamilyName='Gotham';
s18.HAlign='justify';
add(chap4,s18);

sec4_9 = Section;
tWavy = Text(' Puntaje por salida del camino (lado del conductor)');
tWavy.Style = {Underline('single')};
add(chap4,tWavy);
k8=Paragraph('El puntaje por salida del camino (lado del conductor) se obtiene multiplicando entre sí los factores de riesgos parciales.');
k8.FontFamilyName='Gotham';
k8.HAlign='justify';
add(chap4,k8);
k9=Paragraph('Puntaje = probabilidad x severidad x influencia del flujo x transitabilidad de la media x velocidad de operación (percentil 85)');
k9.FontFamilyName='Gotham';
k9.HAlign='justify';
add(chap4,k9);
add(chap4,sec4_9);
x72='Puntaje =';
s19=strcat(x72,'x',x44_1,'x',x59_1,'x',x62_1,'x',x66_1,'x',x70_1);
add(chap4,s19);
x73=b.Coeficientes_Veh.Salida_lado_conductor(1,17);
x73_1=string(x73);
s20=strcat(x72,x73_1);
add(chap4,s20);

p10 = Paragraph('b) Salida del camino (lado del copiloto)');
p10.Bold=1;
add(chap4,p10)
sec4_10 = Section;
tWavy = Text(' Probabilidad de los atributos viales');
tWavy.Style = {Underline('single')};
add(chap4,tWavy);
k10=Paragraph('Los factores de riesgo, asociados a la probabilidad del siniestro, para el ocupante del vehículo y salida del camino se describen a continuación:');
k10.FontFamilyName='Gotham';
k10.HAlign='justify';
add(chap4,k10);
add(chap4,sec4_10);
x1='El atributo vial ancho del carril es  ';
a=load('../mat/Datos_interfaz_tres_proceso');
b=load('../mat/Coeficientes_Veh')
x2=a.Datos_interfaz_tres.Ancho_de_carril(1,1);
x3='en una zona' 
x4=  a.Datos_interfaz_tres.Tipo_de_area(1,1);
x5=', entonces, el factor de riesgo es   '
x6=b.Coeficientes_Veh.Salida_lado_copiloto(1,1);
x6_1=string(x6);
x7='(tabla 2)';
s1=Paragraph(strcat(x1," ",x2," ",x3," ",x4," ",x5," ",x6_1," ",x7));
s1.FontFamilyName='Gotham';
s1.HAlign='justify';
add(chap4,s1);
x8='El atributo vial curvatura es  ';
x9= a.Datos_interfaz_tres.Curvatura(1,1);
x10='  , por lo tanto, el factor de riesgo es  '
x11=b.Coeficientes_Veh.Salida_lado_copiloto(1,2);
x11_1=string(x11);
x12='  (tabla 3).';
s2=Paragraph(strcat(x8," ",x9," ",x10," ",x11_1," ",x12));
s2.FontFamilyName='Gotham';
s2.HAlign='justify';
add(chap4,s2);
x13='El atributo vial calidad de la curva es  ';
x14=a.Datos_interfaz_tres.Calidad_de_la_curva(1,1);
x15='  , entonces, el factor de riesgo es  ';
x16=b.Coeficientes_Veh.Salida_lado_copiloto(1,3);
x16_1=string(x16);
x17='  (tabla 4).';
s3=Paragraph(strcat(x13," ",x14," ",x15," ",x16_1," ",x17));
s3.FontFamilyName='Gotham';
s3.HAlign='justify';
add(chap4,s3);
x18='El atributo vial delineación es   ';
x19=a.Datos_interfaz_tres.Delineacion(1,1);
x20='  , en consecuencia, el factor de riesgo es   ';
x21=b.Coeficientes_Veh.Salida_lado_copiloto(1,4);
x21_1=string(x21);
x22='  (tabla 5).';
s4=Paragraph(strcat(x18," ",x19," ",x20," ",x21_1," ",x22));
s4.FontFamilyName='Gotham';
s4.HAlign='justify';
add(chap4,s4);
x23='El atributo vial bandas sonoras en el espaldón es   ';
x24=a.Datos_interfaz_tres.Bandas_sonoras_en_el_espaldon(1,1);
x25='  , con lo que el factor de riesgo es    ';
x26=b.Coeficientes_Veh.Salida_lado_copiloto(1,5);
x26_1=string(x26)
x27='  (tabla 6).';
s5=Paragraph(strcat(x23," ",x24," ",x25," ",x26_1," ",x27));
s5.FontFamilyName='Gotham';
s5.HAlign='justify';
add(chap4,s5);
x28='El atributo vial condición de la vía es   ';
x29=a.Datos_interfaz_tres.Estado_de_la_superficie_de_la_via(1,1);
x30='  , por lo tanto, el factor de riesgo es     ';
x31=b.Coeficientes_Veh.Salida_lado_copiloto(1,6);
x31_1=string(x31);
x32='  (tabla 7).';
s6=Paragraph(strcat(x28," ",x29," ",x30," ",x31_1," ",x32));
s6.FontFamilyName='Gotham';
s6.HAlign='justify';
add(chap4,s6);
x33='El atributo vial pendiente es   ';
x34=a.Datos_interfaz_tres.Pendiente(1,1);
x35='  , entonces, el factor de riesgo es     ';
x36=b.Coeficientes_Veh.Salida_lado_copiloto(1,7); 
x36_1=string(x36);
x37='  (tabla 8).';
s7=Paragraph(strcat(x33," ",x34," ",x35," ",x36_1," ",x37));
s7.FontFamilyName='Gotham';
s7.HAlign='justify';
add(chap4,s7);
x38='El atributo vial resistencia al deslizamiento es   ';
x39=a.Datos_interfaz_tres.Resistencia_al_deslizamiento(1,1);
x40='  , en consecuencia, el factor de riesgo es     ';
x41=b.Coeficientes_Veh.Salida_lado_copiloto(1,8);
x41_1=string(x41);
x42='  para ocupante del vehículo, salida del camino e intersección (tabla 9).';
s8=Paragraph(strcat(x38," ",x39," ",x40," ",x41_1," ",x42));
s8.FontFamilyName='Gotham';
s8.HAlign='justify';
add(chap4,s8);
sec4_11 = Section;
k11=Paragraph('La probabilidad de siniestro vial en el lado del conductor se obtiene multiplicando los factores de riesgo previamente obtenidos.');
k11.FontFamilyName='Gotham';
k11.HAlign='justify';
add(chap4,k11);
k12=Paragraph('Probabilidad = ancho del carril x curvatura x calidad de la curva x delineación x bandas sonoras en el espaldón x condición de la vía x pendiente x resistencia al deslizamiento');
k12.FontFamilyName='Gotham';
k12.HAlign='justify';
add(chap4,k12);
add(chap4,sec4_11);
x43='Probabilidad =';
s9=strcat(x43,x6_1,'x',x11_1,'x',x16_1,'x',x21_1,'x',x26_1,'x',x31_1,'x',x36_1,'x',x41_1);
add(chap4,s9);
x44=b.Coeficientes_Veh.Salida_lado_copiloto(1,14);
x44_1=string(x44);
s10=strcat(x43,x44_1);
add(chap4,s10);
sec4_12 = Section;
tWavy = Text(' Severidad de los atributos viales');
tWavy.Style = {Underline('single')};
add(chap4,tWavy);
k13=Paragraph('Los factores de riesgo, asociados a la gravedad/severidad del siniestro, para el ocupante del vehículo y salida del camino se describen a continuación:');
k13.FontFamilyName='Gotham';
k13.HAlign='justify';
add(chap4,k13);
add(chap4,sec4_12);
x43='El atributo vial severidad lateral – distancia al objeto (lado del copiloto) es ';
x44=a.Datos_interfaz_tres.Severidad_lateral_distancia_al_objeto_lado_del_copiloto(1,1);
x45=' , por lo que el factor de riesgo es ';
x46=b.Coeficientes_Veh.Salida_lado_copiloto(1,9);
x46_1=string(x46);
x47=' (tabla 10).';
s11=Paragraph(strcat(x43," ",x44," ",x45," ",x46_1," ",x47));
s11.FontFamilyName='Gotham';
s11.HAlign='justify';
add(chap4,s11);
x48='El atributo vial severidad lateral – objeto (lado del copiloto) es ';
x49=a.Datos_interfaz_tres.Severidad_lateral_objeto_lado_del_copiloto(1,1);
x50=' , en consecuencia, el factor de riesgo es ';
x51=b.Coeficientes_Veh.Salida_lado_conductor(1,10);
x51_1=string(x51);
x52=' (tabla 11).';
s12=Paragraph(strcat(x48," ",x49," ",x50," ",x51_1," ",x52));
s12.FontFamilyName='Gotham';
s12.HAlign='justify';
add(chap4,s12)
x53='El atributo vial ancho de espaldón pavimentado (lado del copiloto) es ';
x54=a.Datos_interfaz_tres.Ancho_de_espaldon_pavimentado_lado_del_copiloto(1,1);
x55=' , por lo tanto el factor de riesgo es ';
x56=b.Coeficientes_Veh.Salida_lado_copiloto(1,11);
x56_1=string(x56);
x57=' (tabla 12).';
s13=Paragraph(strcat(x53," ",x54," ",x55," ",x56_1," ",x57));
s13.FontFamilyName='Gotham';
s13.HAlign='justify';
add(chap4,s13);
sec4_13 = Section;
k14=Paragraph('La gravedad de siniestro vial en el lado del copiloto se obtiene multiplicando los factores de riesgo previamente determinados.');
k14.FontFamilyName='Gotham';
k14.HAlign='justify';
add(chap4,k14);
k19=Paragraph('Severidad = severidad lateral – distancia al objeto (lado del copiloto) x severidad lateral – objeto (lado del copiloto) x ancho de espaldón pavimentado (lado del copiloto)');
k19.FontFamilyName='Gotham';
k19.HAlign='justify';
add(chap4,k19);
add(chap4,sec4_13);
x58='Severidad =';
s14=strcat(x58,x46_1,'x',x51_1,'x',x56_1);
add(chap4,s14);
x59=b.Coeficientes_Veh.Salida_lado_copiloto(1,15);
x59_1=string(x59);
s15=strcat(x58,x59_1);
add(chap4,s15);

tWavy = Text(' Influencia del flujo');
tWavy.Style = {Underline('single')};
add(chap4,tWavy);
x59='El atributo vial flujo vehicular (TDPA) es ';
x60=a.Datos_interfaz_tres.Flujo_vehicular(1,1);
x61='vehículos por día, entonces, el factor de riesgo es ';
x62=b.Coeficientes_Veh.Salida_lado_copiloto(1,12);
x62_1=string(x62);
s16=Paragraph(strcat(x59," ",x60," ",x61," ",x62_1));
s16.FontFamilyName='Gotham';
s16.HAlign='justify';
add(chap4,s16);

tWavy = Text(' Velocidad de operación (percentil 85)');
tWavy.Style = {Underline('single')};
add(chap4,tWavy);
x67='El atributo vial velocidad de operación (percentil 85) es ';
x68=a.Datos_interfaz_tres.Velocidad_de_operacion_percentil_85 (1,1);
x69=' para ocupantes de vehículos y motociclistas (caminos divididos, no divididos y rurales, de frente, de salida e intersección), en consecuencia, el factor de riesgo es ';
x70=b.Coeficientes_Veh.Salida_lado_copiloto(1,13);
x70_1=string(x70);
x71='(tabla 15).';
s18=Paragraph(strcat(x67," ",x68," ",x69," ",x70_1," ",x71));
s18.FontFamilyName='Gotham';
s18.HAlign='justify';
add(chap4,s18);

sec4_14 = Section;
tWavy = Text(' Puntaje por salida del camino (lado del copiloto)');
tWavy.Style = {Underline('single')};
add(chap4,tWavy);
k20=Paragraph('El puntaje por salida del camino (lado del conductor) se obtiene multiplicando entre sí los factores de riesgos parciales.');
k20.FontFamilyName='Gotham';
k20.HAlign='justify';
add(chap4,k20);
k21=Paragraph('Puntaje = probabilidad x severidad x influencia del flujo x  velocidad de operación (percentil 85)');
k21.FontFamilyName='Gotham';
k21.HAlign='justify';
add(chap4,k21);
add(chap4,sec4_14);
x72='Puntaje =';
s19=strcat(x72,x44_1,'x',x59_1,'x',x62_1,'x',x70_1);
add(chap4,s19);
x73=b.Coeficientes_Veh.Salida_lado_copiloto(1,16);
x73_1=string(x73);
s20=strcat(x72,x73_1);
add(chap4,s20);

p12 = Paragraph('c) Choque frontal (pérdida del control)');
p12.Bold=1;
add(chap4,p12)
sec4_15 = Section;
tWavy = Text(' Probabilidad de los atributos viales');
tWavy.Style = {Underline('single')};
add(chap4,tWavy);
k22=Paragraph('Los factores de riesgo, asociados a la probabilidad del siniestro, para el ocupante del vehículo y choque frontal (pérdida del control) se describen a continuación: ');
k22.FontFamilyName='Gotham';
k22.HAlign='justify'
add(chap4,k22);
add(chap4,sec4_15);
x1='El atributo vial ancho del carril es ';
a=load('../mat/Datos_interfaz_tres_proceso');
b=load('../mat/Coeficientes_Veh')
x2= a.Datos_interfaz_tres.Ancho_de_carril(1,1);
x3=' en una zona ' 
x4= a.Datos_interfaz_tres.Tipo_de_area(1,1);
x5=' , entonces, el factor de riesgo es '
x6=b.Coeficientes_Veh.Perdida_de_control(1,1);
x6_1=string(x6);
x7=' (tabla 2)';
s1=Paragraph(strcat(x1," ",x2," ",x3," ",x4," ",x5," ",x6_1," ",x7));
s1.FontFamilyName='Gotham';
s1.HAlign='justify';
add(chap4,s1);
x8='El atributo vial curvatura es  ';
x9= a.Datos_interfaz_tres.Curvatura(1,1);
x10='  , por lo tanto, el factor de riesgo es  '
x11=b.Coeficientes_Veh.Perdida_de_control(1,2);
x11_1=string(x11);
x12='  (tabla 3).';
s2=Paragraph(strcat(x8," ",x9," ",x10," ",x11_1," ",x12));
s2.FontFamilyName='Gotham';
s2.HAlign='justify';
add(chap4,s2);
x13='El atributo vial calidad de la curva es  ';
x14=a.Datos_interfaz_tres.Calidad_de_la_curva(1,1);
x15='  , entonces, el factor de riesgo es  ';
x16=b.Coeficientes_Veh.Perdida_de_control(1,3);
x16_1=string(x16);
x17='  (tabla 4).';
s3=Paragraph(strcat(x13," ",x14," ",x15," ",x16_1," ",x17));
s3.FontFamilyName='Gotham';
s3.HAlign='justify';
add(chap4,s3);
x18='El atributo vial delineación es   ';
x19=a.Datos_interfaz_tres.Delineacion(1,1);
x20='  , en consecuencia, el factor de riesgo es   ';
x21=b.Coeficientes_Veh.Perdida_de_control(1,4);
x21_1=string(x21);
x22='  (tabla 5).';
s4=Paragraph(strcat(x18," ",x19," ",x20," ",x21_1," ",x22));
s4.FontFamilyName='Gotham';
s4.HAlign='justify';
add(chap4,s4);
x23='El atributo vial bandas sonoras centrales es ';
x24=a.Datos_interfaz_tres.Bandas_sonoras_centrales(1,1);
x25=' , con lo que el factor de riesgo es ';
x26=b.Coeficientes_Veh.Perdida_de_control(1,5);
x26_1=string(x26);
x27=' (tabla 16).';
s5=Paragraph(strcat(x23," ",x24," ",x25," ",x26_1," ",x27));
s5.FontFamilyName='Gotham';
s5.HAlign='justify';
add(chap4,s5);
x28='El atributo vial condición de la vía es ';
x29=a.Datos_interfaz_tres.Estado_de_la_superficie_de_la_via(1,1);
x30=' , por lo tanto, el factor de riesgo es ';
x31=b.Coeficientes_Veh.Perdida_de_control(1,6);
x31_1=string(x31);
x32=' (tabla 7).';
s6=Paragraph(strcat(x28," ",x29," ",x30," ",x31_1," ",x32));
s6.FontFamilyName='Gotham';
s6.HAlign='justify';
add(chap4,s6);
x33='El atributo vial pendiente es ';
x34=a.Datos_interfaz_tres.Pendiente(1,1);
x35=' , entonces, el factor de riesgo es ';
x36=b.Coeficientes_Veh.Perdida_de_control(1,7);
x36_1=string(x36);
x37=' (tabla 8).';
s7=Paragraph(strcat(x33," ",x34," ",x35," ",x36_1," ",x37));
s7.FontFamilyName='Gotham';
s7.HAlign='justify';
add(chap4,s7);
x38='El atributo vial resistencia al deslizamiento es ';
x39=a.Datos_interfaz_tres.Resistencia_al_deslizamiento(1,1);
x40=' , en consecuencia, el factor de riesgo es ';
x41=b.Coeficientes_Veh.Perdida_de_control(1,8);
x41_1=string(x41);
x42=' para ocupante del vehículo, salida del camino e intersección (tabla 9).';
s8=Paragraph(strcat(x38," ",x39," ",x40," ",x41_1," ",x42));
s8.FontFamilyName='Gotham';
s8.HAlign='justify';
add(chap4,s8);
sec4_16 = Section;
k22=Paragraph('La probabilidad de siniestro vial por pérdida del control se obtiene multiplicando los factores de riesgo previamente obtenidos.');
k22.FontFamilyName='Gotham';
k22.HAlign='justify';
add(chap4,k22);
k23=Paragraph('Probabilidad = ancho del carril x curvatura x calidad de la curva x delineación x bandas sonoras centrales x condición de la vía x pendiente x resistencia al deslizamiento');
k23.FontFamilyName='Gotham';
k23.HAlign='justify';
add(chap4,k23);
add(chap4,sec4_16);
x43='Probabilidad =';
s9=strcat(x43,"x",x6_1,"x",x11_1,"x",x16_1,"x",x21_1,"x",x26_1,"x",x31_1,"x",x41_1);
add(chap4,s9);
x44=b.Coeficientes_Veh.Perdida_de_control(1,13);
x44_1=string(x44);
s10=strcat(x43,x44_1);
add(chap4,s10);

sec4_17 = Section;
tWavy = Text(' Severidad de los atributos viales');
tWavy.Style = {Underline('single')};
add(chap4,tWavy);
k24=Paragraph('Los factores de riesgo, asociados a la gravedad/severidad del siniestro, para el ocupante del vehículo y choque frontal (pérdida del control) se describen a continuación: ');
k24.FontFamilyName='Gotham';
k24.HAlign='justify';
add(chap4,k24);
add(chap4,sec4_17);
x43='El atributo vial tipo de mediana es ';
x44=a.Datos_interfaz_tres.Tipo_de_mediana(1,1);
x45=' , por lo que el factor de riesgo es ';
x46=b.Coeficientes_Veh.Perdida_de_control(1,9);
x46_1=string(x46);
x47=' (tabla 17).';
s11=Paragraph(strcat(x43," ",x44,"x",x45,"x",x46_1,"x",x47));
s11.FontFamilyName='Gotham';
s11.HAlign='justify';
add(chap4,s11);
sec4_18 = Section;
k25=Paragraph('La gravedad de siniestro vial por pérdida de control se obtiene multiplicando los factores de riesgo previamente determinados.');
k25.FontFamilyName='Gotham';
k25.HAlign='justify';
add(chap4,k25);
add(sec4_18,Paragraph('Severidad = tipo de mediana'));
add(chap4,sec4_18);
x48='Severidad =';
s12=strcat(x48,x46_1);
add(chap4,s12);

tWavy = Text(' Influencia del flujo');
tWavy.Style = {Underline('single')};
add(chap4,tWavy);
x49='El atributo vial flujo vehicular (TDPA) es ';
x50=a.Datos_interfaz_tres.Flujo_vehicular(1,1);
x51='vehículos por día, entonces, el factor de riesgo es ';
x52=b.Coeficientes_Veh.Perdida_de_control(1,10);
x52_1=string(x52);
s13=Paragraph(strcat(x49," ",x50," ",x51," ",x52_1));
s13.FontFamilyName='Gotham';
s13.HAlign='justify';
add(chap4,s13);

tWavy = Text(' Transitabilidad de la mediana');
tWavy.Style = {Underline('single')};
add(chap4,tWavy);
x53='El atributo vial transitabilidad de la mediana es ';
x54=a.Datos_interfaz_tres.Transitabilidad(1,1);
x55=' , por lo tanto, el factor de riesgo es ';
x56=b.Coeficientes_Veh.Perdida_de_control(1,11);
x56_1=string(x56);
s14=Paragraph(strcat(x53," ",x54," ",x55," ",x56_1));
s14.FontFamilyName='Gotham';
s14.HAlign='justify';
add(chap4,s14);

tWavy = Text(' Velocidad de operación (percentil 85)');
tWavy.Style = {Underline('single')};
add(chap4,tWavy);
x57='El atributo vial velocidad de operación (percentil 85) es ';
x58=a.Datos_interfaz_tres.Velocidad_de_operacion_percentil_85 (1,1);
x59=' para ocupantes de vehículos y motociclistas (caminos divididos, no divididos y rurales, de frente, de salida e intersección), en consecuencia, el factor de riesgo es ';
x60=b.Coeficientes_Veh.Perdida_de_control(1,12);
x60_1=string(x60);
x61='(tabla 15).';
s15=Paragraph(strcat(x57," ",x58," ",x59," ",x60_1," ",x61));
s15.FontFamilyName='Gotham';
s15.HAlign='justify';
add(chap4,s15);

sec4_19 = Section;
tWavy = Text('Puntaje por choque frontal (pérdida del control)');
tWavy.Style = {Underline('single')};
add(chap4,tWavy);
k26=Paragraph('El puntaje por choque frontal (pérdida del control) se obtiene multiplicando entre sí los factores de riesgos parciales.');
k26.FontFamilyName='Gotham';
k26.HAlign='justify';
add(chap4,k26);
k27=Paragraph('Puntaje = probabilidad x severidad x influencia del flujo x transitabilidad de la media x velocidad de operación (percentil 85)');
k27.FontFamilyName='Gotham';
k27.HAlign='justify';
add(chap4,k27);
add(chap4,sec4_19);
x62='Puntaje= ';
s16=strcat(x62,x44_1,'x',x46_1,'x',x52_1,'x',x56_1,'x',x60_1);
add(chap4,s16);
x63=b.Coeficientes_Veh.Perdida_de_control(1,14);
x63_1=string(x63);
s17=strcat(x62,x63_1);
add(chap4,s17);

p13 = Paragraph('d) Choque frontal (adelantamiento))');
p13.Bold=1;
add(chap4,p13)
sec4_20 = Section;
tWavy = Text('Probabilidad de los atributos viales');
tWavy.Style = {Underline('single')};
add(chap4,tWavy);
k28=Paragraph('Los factores de riesgo, asociados a la probabilidad del siniestro, para el ocupante del vehículo y choque frontal (adelantamiento) se describen a continuación: ');
k28.FontFamilyName='Gotham';
k28.HAlign='justify';
add(chap4,k28);
add(chap4,sec4_20);
x1='El atributo vial pendiente es ';
a=load('../mat/Datos_interfaz_tres_proceso');
b=load('../mat/Coeficientes_Veh')
x2= a.Datos_interfaz_tres.Pendiente(1,1);
x3=' , entonces, el factor de riesgo es ' 
x4=b.Coeficientes_Veh.Adelantamiento(1,1);
x4_1=string(x4);
x5=' (tabla 8)';
s1=Paragraph(strcat(x1," ",x2," ",x3," ",x4_1," ",x5));
s1.FontFamilyName='Gotham';
s1.HAlign='justify';
add(chap4,s1);
x6='El atributo vial resistencia al deslizamiento es ';
x7= a.Datos_interfaz_tres.Resistencia_al_deslizamiento(1,1);
x8=' , en consecuencia, el factor de riesgo es '
x9=b.Coeficientes_Veh.Adelantamiento(1,2);
x9_1=string(x9)
x10=' para ocupante del vehículo, salida del camino e intersección (tabla 9).';
s2=Paragraph(strcat(x6," ",x7," ",x8," ",x9_1," ",x10));
s2.FontFamilyName='Gotham';
s2.HAlign='justify';
add(chap4,s2);
x13='El atributo vial diferencial de límites de velocidad es ';
x14=a.Datos_interfaz_tres.Limite_de_velocidad(1,1);
x15=' , entonces, el factor de riesgo es ';
x16=b.Coeficientes_Veh.Adelantamiento(1,3);
x16_1=string(x16);
x17=' (tabla 18).';
s3=Paragraph(strcat(x13," ",x14," ",x15," ",x16_1," ",x17));
s3.FontFamilyName='Gotham';
s3.HAlign='justify';
add(chap4,s3);
x18='El atributo vial número de carriles es ';
x19=a.Datos_interfaz_tres.Numero_de_carriles(1,1);
x20=' , por lo tanto, el factor de riesgo es ';
x21=b.Coeficientes_Veh.Adelantamiento(1,4);
x21_1=string(x21);
x22=' (tabla 19).';
s4=Paragraph(strcat(x18," ",x19," ",x20," ",x21_1," ",x22));
s4.FontFamilyName='Gotham';
s4.HAlign='justify';
add(chap4,s4);
sec4_21 = Section;
k29=Paragraph('La probabilidad de siniestro vial por adelantamiento se obtiene multiplicando los factores de riesgo previamente obtenidos.');
k29.FontFamilyName='Gotham';
k29.HAlign='justify'
add(chap4,k29);
k30=Paragraph('Probabilidad = pendiente x resistencia al deslizamiento x diferencial de límites de velocidad x número de carriles');
k30.FontFamilyName='Gotham';
k30.HAlign='justify';
add(chap4,k30);
add(chap4,sec4_21);
x23='Probabilidad =';
s5=strcat(x23,x4_1,'x',x9_1,'x',x16_1,'x',x21_1);
add(chap4,s5);
x24=b.Coeficientes_Veh.Adelantamiento(1,8);
x24_1=string(x24);
s6=strcat(x23,x24_1);
add(chap4,s6);
sec4_22 = Section;
tWavy = Text('Severidad de los atributos viales');
tWavy.Style = {Underline('single')};
add(chap4,tWavy);
k31=Paragraph('Los factores de riesgo, asociados a la gravedad/severidad del siniestro, para el ocupante del vehículo y choque frontal (adelantamiento) se describen a continuación:'); 
k31.FontFamilyName='Gotham';
k31.HAlign='justify';
add(chap4,k31);
add(chap4,sec4_22);
x24='El atributo vial tipo de mediana es ';
x25=a.Datos_interfaz_tres.Tipo_de_mediana(1,1);
x26=', por lo que el factor de riesgo es ';
x27=b.Coeficientes_Veh.Adelantamiento(1,5);
x27_1=string(x27);
x28='(tabla 17).';
s6=Paragraph(strcat(x24," ",x25," ",x26," ",x27_1," ",x28));
s6.FontFamilyName='Gotham';
s6.HAlign='justify';
add(chap4,s6);
sec4_23 = Section;
k32=Paragraph('La gravedad de siniestro vial por pérdida de control se obtiene multiplicando los factores de riesgo previamente determinados.');
k32.FontFamilyName='Gotham';
k32.HAlign='justify';
add(chap4,k32);
add(sec4_23,Paragraph('Severidad = tipo de mediana'));
add(chap4,sec4_23);
x29='Severidad =';
s7=strcat(x29,x27_1);
add(chap4,s7);

tWavy = Text('Influencia del flujo');
tWavy.Style = {Underline('single')};
add(chap4,tWavy);
x49='El atributo vial flujo vehicular (TDPA) es ';
x50=a.Datos_interfaz_tres.Flujo_vehicular(1,1);
x51='vehículos por día, entonces, el factor de riesgo es ';
x52=b.Coeficientes_Veh.Adelantamiento(1,6);
x52_1=string(x52);
s13=Paragraph(strcat(x49," ",x50," ",x51," ",x52_1));
s13.FontFamilyName='Gotham';
s13.HAlign='justify';
add(chap4,s13);

tWavy = Text('Velocidad de operación (percentil 85)');
tWavy.Style = {Underline('single')};
add(chap4,tWavy);
x57='El atributo vial velocidad de operación (percentil 85) es ';
x58=a.Datos_interfaz_tres.Velocidad_de_operacion_percentil_85 (1,1);
x59=' para ocupantes de vehículos y motociclistas (caminos divididos, no divididos y rurales, de frente, de salida e intersección), en consecuencia, el factor de riesgo es ';
x60=b.Coeficientes_Veh.Adelantamiento(1,7);
x60_1=string(x60);
x61='(tabla 15).';
s15=Paragraph(strcat(x57," ",x58," ",x59," ",x60_1," ",x61));
s15.FontFamilyName='Gotham';
s15.HAlign='justify';
add(chap4,s15);

sec4_24 = Section;
tWavy = Text('Puntaje por choque frontal (adelantamiento)');
tWavy.Style = {Underline('single')};
add(chap4,tWavy);
k33=Paragraph('El puntaje por choque frontal (adelantamiento) se obtiene multiplicando entre sí los factores de riesgos parciales.');
k33.FontFamilyName='Gotham';
k33.HAlign='justify';
add(chap4,k33);
k34=Paragraph('Puntaje = probabilidad x severidad x influencia del flujo x velocidad de operación (percentil 85)');
k34.FontFamilyName='Gotham';
k34.HAlign='justify';
add(chap4,k34);
add(chap4,sec4_24);
x62='Puntaje = ';
s16=strcat(x62,x24_1,'x',x27_1,'x',x52_1,'x',x60_1);
add(chap4,s16);
x63=b.Coeficientes_Veh.Adelantamiento(1,9);
x63_1=string(x63);
s17=strcat(x62,x63_1);
add(chap4,s17);

p14 = Paragraph('e) Intersecciones');
p14.Bold=1;
add(chap4,p14)
sec4_25 = Section;
tWavy = Text('Probabilidad de los atributos viales');
tWavy.Style = {Underline('single')};
add(chap4,tWavy)
k35=Paragraph('Los factores de riesgo, asociados a la probabilidad del siniestro, para el ocupante del vehículo e intersecciones se describen a continuación: ');
k35.FontFamilyName='Gotham';
k35.HAlign='justify';
add(chap4,k35);
add(chap4,sec4_25);
x1='El atributo vial tipo de intersección es ';
a=load('../mat/Datos_interfaz_tres_proceso');
b=load('../mat/Coeficientes_Veh')
x2= a.Datos_interfaz_tres.Tipo_de_interseccion(1,1);
x3=' , entonces, el factor de riesgo es ' 
x4=b.Coeficientes_Veh.Interseccion(1,1);
x4_1=string(x4);
x5=' (tabla 20)';
s1=Paragraph(strcat(x1," ",x2," ",x3," ",x4_1," ",x5));
s1.FontFamilyName='Gotham';
s1.HAlign='justify'
add(chap4,s1);
x6='El atributo vial calidad de la intersección es ';
x7= a.Datos_interfaz_tres.Calidad_de_la_interseccion(1,1);
x8=' , por lo tanto, el factor de riesgo es '
x9=b.Coeficientes_Veh.Interseccion(1,2);
x9_1=string(x9);
x10=' (tabla 22).';
s2=Paragraph(strcat(x6," ",x7," ",x8," ",x9_1," ",x10));
s2.FontFamilyName='Gotham';
s2.HAlign='justify';
add(chap4,s2);
x13='El atributo vial pendiente es ';
x14=a.Datos_interfaz_tres.Pendiente(1,1);
x15=' , entonces, el factor de riesgo es ';
x16=b.Coeficientes_Veh.Interseccion(1,3);
x16_1=string(x16);
x17=' (tabla 8).';
s3=Paragraph(strcat(x13," ",x14," ",x15," ",x16_1," ",x17));
s3.FontFamilyName='Gotham';
s3.HAlign='justify';
add(chap4,s3);
x18='El atributo vial alumbrado público es ';
x19=a.Datos_interfaz_tres.Alumbrado_publico(1,1);
x20=' , en consecuencia, el factor de riesgo es ';
x21=b.Coeficientes_Veh.Interseccion(1,4);
x21_1=string(x21);
x22=' (tabla 23).';
s4=Paragraph(strcat(x18," ",x19," ",x20," ",x21_1," ",x22));
s4.FontFamilyName='Gotham';
s4.HAlign='justify'
add(chap4,s4);
x23='El atributo vial resistencia al deslizamiento es ';
x24=a.Datos_interfaz_tres.Resistencia_al_deslizamiento(1,1);
x25=', en consecuencia, el factor de riesgo es'
x26=b.Coeficientes_Veh.Interseccion(1,5);
x26_1=string(x26);
x27='para ocupante del vehículo, salida del camino e intersección (tabla 9).';
s5=Paragraph(strcat(x23," ",x24," ",x25," ",x26_1," ",x27));
s5.FontFamilyName='Gotham';
s5.HAlign='justify';
add(chap4,s5);
x28='El atributo vial distancia de visibilidad es ';
x29=a.Datos_interfaz_tres.Distancia_de_visibilidad(1,1);
x30=', entonces, el factor de riesgo es ';
x31=b.Coeficientes_Veh.Interseccion(1,6);
x31_1=string(x31);
x32='(tabla 24).';
s6=Paragraph(strcat(x28," ",x29," ",x30," ",x31_1," ",x32));
s6.FontFamilyName='Gotham';
s6.HAlign='justify';
add(chap4,s6);
x33='El atributo vial canalización de la intersección es ';
x34=a.Datos_interfaz_tres.Canalizacion_de_la_interseccion(1,1);
x35=', por lo tanto, el factor de riesgo es';
x36=b.Coeficientes_Veh.Interseccion(1,7);
x36_1=string(x36);
x37='(tabla 25).';
s7=Paragraph(strcat(x33," ",x34," ",x35," ",x36_1," ",x37));
s7.FontFamilyName='Gotham';
s7.HAlign='justify'
add(chap4,s7);
x38='El atributo vial gestión de la velocidad/calmante del tráfico es';
x39=a.Datos_interfaz_tres.Gestion_de_la_velocidad_calmante_del_trafico(1,1);
x40=', entonces, el factor de riesgo es';
x41=b.Coeficientes_Veh.Interseccion(1,8);
x41_1=string(x41);
x42='(tabla 26).';
s8=Paragraph(strcat(x38," ",x39," ",x40," ",x41_1," ",x42));
s8.FontFamilyName='Gotham';
s8.HAlign='justify';
add(chap4,s8);
sec4_26 = Section;
k36=Paragraph('La probabilidad de siniestro vial en las intersecciones se obtiene multiplicando los factores de riesgo previamente obtenidos.');
k36.FontFamilyName='Gotham';
k36.HAlign='justify';
add(chap4,k36);
k37=Paragraph('Probabilidad = tipo de intersección x calidad de la intersección x pendiente x alumbrado público x resistencia al deslizamiento x distancia de visibilidad x canalización de la intersección x gestión de la velocidad/calmante del tráfico ');
k37.FontFamilyName='Gotham';
k37.HAlign='justify';
add(chap4,k37);
add(chap4,sec4_26);
x43='Probabilidad =';
s9=strcat(x43,x4_1,'x',x9_1,'x',x16_1,'x',x21_1,'x',x26_1,'x',x31_1,'x',x36_1,'x',x41_1);
add(chap4,s9);
x44=b.Coeficientes_Veh.Interseccion(1,12);
x44_1=string(x44);
s10=strcat(x43,x44_1);

sec4_27 = Section;
tWavy = Text('Severidad de los atributos viales');
tWavy.Style = {Underline('single')};
add(chap4,tWavy)
k38=Paragraph('Los factores de riesgo, asociados a la gravedad/severidad del siniestro, para el ocupante del vehículo e intersecciones se describen a continuación: ');
k38.FontFamilyName='Gotham';
k38.HAlign='justify';
add(chap4,k38);
add(chap4,sec4_27);
x44='El atributo vial tipo de intersección es';
x45=a.Datos_interfaz_tres.Tipo_de_interseccion(1,1);
x46=', entonces, el factor de riesgo es';
x47=b.Coeficientes_Veh.Interseccion(1,9);
x47_1=string(x47);
x48='tabla(20).';
s10=Paragraph(strcat(x44," ",x45," ",x46," ",x47_1," ",x48));
s10.FontFamilyName='Gotham';
s10.HAlign='justify';
add(chap4,s10);
sec4_28 = Section;
k39=Paragraph('La gravedad de siniestro vial en las intersecciones se obtiene multiplicando los factores de riesgo previamente determinados.');
k39.FontFamilyName='Gotham';
k39.HAlign='justify';
add(chap4,k39)
add(sec4_28,Paragraph('Severidad = tipo de intersección'));
add(chap4,sec4_28);
x49='Severidad=';
s11=strcat(x49,x47_1);
add(chap4,s11);

tWavy = Text('Influencia del flujo');
tWavy.Style = {Underline('single')};
add(chap4,tWavy)
x50='El atributo vial flujo vehicular (TDPA) es ';
x51=a.Datos_interfaz_tres.Flujo_vehicular(1,1);
x52='vehículos por día, entonces, el factor de riesgo es';
x53=b.Coeficientes_Veh.Interseccion(1,10);
x53_1=string(x53)
s12=Paragraph(strcat(x50," ",x51," ",x52," ",x53_1));
s12.FontFamilyName='Gotham';
s12.HAlign='justify';
add(chap4,s12);

tWavy = Text('Velocidad de operación (percentil 85)');
tWavy.Style = {Underline('single')};
add(chap4,tWavy)
x54='El atributo vial velocidad de operación (percentil 85) es ';
x55=a.Datos_interfaz_tres.Velocidad_de_operacion_percentil_85(1,1);
x56='para ocupantes de vehículos y motociclistas (caminos divididos, no divididos y rurales, de frente, de salida e intersección), en consecuencia, el factor de riesgo es';
x57=b.Coeficientes_Veh.Interseccion(1,11);
x57_1=string(x57);
x58='(tabla 15).';
s13=Paragraph(strcat(x54," ",x55," ",x56," ",x57_1," ",x58));
s13.FontFamilyName='Gotham';
s13.HAlign='justify';
add(chap4,s13);

sec4_29 = Section;
tWavy = Text('Puntaje por intersecciones');
tWavy.Style = {Underline('single')};
add(chap4,tWavy)
k40=Paragraph('El puntaje por intersecciones se obtiene multiplicando entre sí los factores de riesgos parciales.');
k40.FontFamilyName='Gotham';
k40.HAlign='justify';
add(chap4,k40);
k41=Paragraph('Puntaje = probabilidad x severidad x influencia del flujo x velocidad de operación (percentil 85)');
k41.FontFamilyName='Gotham';
k41.HAlign='justify';
add(chap4,k41);
add(chap4,sec4_29);
x59='Puntaje =';
s14=strcat(x59,x44_1,'x',x47_1,'x',x53_1,'x',x57_1);
add(chap4,s14);
x60=b.Coeficientes_Veh.Interseccion(1,13);
x60_1=string(x60);
s15=strcat(x59,x60_1);
add(chap4,s15);

p15 = Paragraph('f) Acceso a propiedades');
p15.Bold=1;
add(chap4,p15)
sec4_30 = Section;
tWavy = Text('Probabilidad de los atributos viales');
tWavy.Style = {Underline('single')};
add(chap4,tWavy)
k42=Paragraph('Los factores de riesgo, asociados a la probabilidad del siniestro, para el ocupante del vehículo y acceso a propiedades se describen a continuación: ');
k42.FontFamilyName='Gotham';
k42.HAlign='justify';
add(chap4,k42);
add(chap4,sec4_30);
x1='El atributo vial puntos de acceso a la propiedad es ';
a=load('../mat/Datos_interfaz_tres_proceso');
b=load('../mat/Coeficientes_Veh')
x2= a.Datos_interfaz_tres.Puntos_de_acceso_a_la_propiedad(1,1);
x3=' , entonces, el factor de riesgo es ' 
x4=b.Coeficientes_Veh.Acceso_a_Propiedades(1,1);
x4_1=string(x4);
x5=' (tabla 27)';
s1=Paragraph(strcat(x1," ",x2," ",x3," ",x4_1," ",x5));
s1.FontFamilyName='Gotham';
s1.HAlign='justify';
add(chap4,s1);
x6='El atributo vial tipo de mediana es';
x7=a.Datos_interfaz_tres.Tipo_de_mediana(1,1);
x8=', por lo tanto, el factor de riesgo es';
x9=b.Coeficientes_Veh.Acceso_a_Propiedades(1,2);
x9_1=string(x9);
x10='(tabla 17).';
s2=Paragraph(strcat(x6," ",x7," ",x8," ",x9_1," ",x10));
s2.FontFamilyName='Gotham';
s2.HAlign='justify';
add(chap4,s2);
x11='El atributo vial vía de servicio es ';
x12=a.Datos_interfaz_tres.Via_de_servicio(1,1);
x13=', entonces, el factor de riesgo es ';
x14=b.Coeficientes_Veh.Acceso_a_Propiedades(1,3);
x14_1=string(x14);
x15='(tabla 28).';
s3=Paragraph(strcat(x11," ",x12," ",x13," ",x14_1," ",x15));
s3.FontFamilyName='Gotham';
s3.HAlign='justify';
add(chap4,s3);
sec4_31 = Section;
k43=Paragraph('La probabilidad de siniestro vial en los accesos a propiedades se obtiene multiplicando los factores de riesgo previamente obtenidos.');
k43.FontFamilyName='Gotham';
k43.HAlign='justify';
add(chap4,k43);
k44=Paragraph('Probabilidad = puntos de acceso a la propiedad x tipo de mediana x vía de servicio ');
k44.FontFamilyName='Gotham';
k44.HAlign='justify';
add(chap4,k44);
add(chap4,sec4_31);
x16='Probabilidad = ';
s4=strcat(x16,x4_1,'x',x9_1,'x',x14_1);
add(chap4,s4);
x17=b.Coeficientes_Veh.Acceso_a_Propiedades(1,7);
x17_1=string(x17);
s5=strcat(x16,x17_1);
add(chap4,s5);

sec4_32 = Section;
tWavy = Text('Severidad de los atributos viales');
tWavy.Style = {Underline('single')};
add(chap4,tWavy)
k45=Paragraph('Los factores de riesgo, asociados a la gravedad/severidad del siniestro, para el ocupante del vehículo y acceso a propiedades se describen a continuación:');
k45.FontFamilyName='Gotham';
k45.HAlign='justify';
add(chap4,k45);
add(chap4,sec4_32);
x17='El atributo vial puntos de acceso a la propiedad es';
x18=a.Datos_interfaz_tres.Puntos_de_acceso_a_la_propiedad(1,1);
x19=', entonces, el factor de riesgo es';
x20=b.Coeficientes_Veh.Acceso_a_Propiedades(1,4);
x20_1=string(x20);
x21='(tabla 27).';
s5=Paragraph(strcat(x17," ",x18," ",x19," ",x20_1));
s5.FontFamilyName='Gotham';
s5.HAlign='justify';
add(chap4,s5);
sec4_33 = Section;
k46=Paragraph('La gravedad de siniestro vial en los accesos a propiedades se obtiene multiplicando los factores de riesgo previamente determinados.');
k46.FontFamilyName='Gotham';
k46.HAlign='justify';
add(chap4,k46);
add(sec4_33,Paragraph('Severidad = puntos de acceso a la propiedad'));
add(chap4,sec4_33);
x21='Severidad=';
s6=strcat(x21,x20_1);
add(chap4,s6);


tWavy = Text('Influencia del flujo');
tWavy.Style = {Underline('single')};
add(chap4,tWavy)
x22='El atributo vial flujo vehicular (TDPA) es ';
x23=a.Datos_interfaz_tres.Flujo_vehicular(1,1);
x24='vehículos por día, entonces, el factor de riesgo es';
x25=b.Coeficientes_Veh.Acceso_a_Propiedades(1,5);
x25_1=string(x25);
s12=Paragraph(strcat(x22," ",x23," ",x24," ",x25_1));
s12.FontFamilyName='Gotham';
s12.HAlign='justify';
add(chap4,s12);


tWavy = Text('Velocidad de operación (percentil 85)');
tWavy.Style = {Underline('single')};
add(chap4,tWavy)
x54='El atributo vial velocidad de operación (percentil 85) es ';
x55=a.Datos_interfaz_tres.Velocidad_de_operacion_percentil_85(1,1);
x56='para ocupantes de vehículos y motociclistas (caminos divididos, no divididos y rurales, de frente, de salida e intersección), en consecuencia, el factor de riesgo es';
x57=b.Coeficientes_Veh.Acceso_a_Propiedades(1,6);
x57_1=string(x57);
x58='(tabla 15).';
s13=Paragraph(strcat(x54," ",x55," ",x56," ",x57_1," ",x58));
s13.FontFamilyName='Gotham';
s13.HAlign='justify';
add(chap4,s13);

sec4_34 = Section;
tWavy = Text('Puntaje por acceso a propiedades');
tWavy.Style = {Underline('single')};
add(chap4,tWavy)
k47=Paragraph('El puntaje por acceso a propiedades se obtiene multiplicando entre sí los factores de riesgos parciales.');
k47.FontFamilyName='Gotham';
k47.HAlign='justify';
add(chap4,k47);
k48=Paragraph('Puntaje = probabilidad x severidad x influencia del flujo x velocidad de operación (percentil 85)');
k48.FontFamilyName='Gotham';
k48.HAlign='justify';
add(chap4,k48);
add(chap4,sec4_34);
x59='Puntaje =';
s14=strcat(x59,x17_1,'x',x20_1,'x',x25_1,'x',x57_1);
add(chap4,s14);
x60=b.Coeficientes_Veh.Acceso_a_Propiedades(1,8);
x60_1=string(x60);
s15=strcat(x59,x60_1)
add(chap4,s15);

p16 = Paragraph('SRS para ocupante del vehículo y clasificación por estrellas')
p16.Bold=1;
add(chap4,p16);
sec4_35 = Section;
k49=Paragraph('Las puntuaciones para la clasificación por estrellas (SRS) para el ocupante del vehículo es la suma de los puntajes previos por cada tipo de siniestro.');
k49.FontFamilyName='Gotham';
k49.HAlign='justify';
add(chap4,k49);
k50=Paragraph('Puntaje de clasificación por estrellas (SRS) = salida del camino (lado del conductor) + salida del camino (lado del copiloto) + choque frontal (pérdida de control) + choque frontal (adelantamiento) + siniestros en intersecciones + siniestros en acceso a propiedades.');
k50.FontFamilyName='Gotham';
k50.HAlign='justify';
add(chap4,k50);
add(chap4,sec4_35);
x62='Puntaje de clasificación por estrellas (SRS) = ';
b=load('../mat/Coeficientes_Veh')
X1=b.Coeficientes_Veh.Salida_lado_conductor(1,17);
X2=b.Coeficientes_Veh.Salida_lado_copiloto(1,16);
X3=b.Coeficientes_Veh.Perdida_de_control(1,14);
X4=b.Coeficientes_Veh.Adelantamiento(1,9);
X5=b.Coeficientes_Veh.Interseccion(1,13);
X6=b.Coeficientes_Veh.Acceso_a_Propiedades(1,8);
X1_1=string(X1);
X2_1=string(X2);
X3_1=string(X3);
X4_1=string(X4);
X5_1=string(X5);
X6_1=string(X6);
s16=strcat(x62,X1_1,'+',X2_1,'+',X3_1,'+',X4_1,'+',X5_1,'+',X6_1);
add(chap4,s16);
x63=b.Coeficientes_Veh.Acceso_a_Propiedades(1,9);
x63_1=string(x63);
s17=strcat(x62,x63_1);
add(chap4,s17);
a=load('../mat/Datos_interfaz_tres_proceso');
x64='Al SRS de';
x65='le corresponde';
x66=a.Datos_interfaz_tres.Ocupante_del_vehiculo(1,1);
x66_1=string(x66);
x67='estrellas para ocupante del vehículo (tabla 37).';
s16=Paragraph(strcat(x64," ",x63_1," ",x65," ",x66_1," ",x67));
s16.FontFamilyName='Gotham';
s16.HAlign='justify';
add(chap4,s16);

%CLASIFICACIÓN POR ESTRELLAS PARA MOTOCICLISTAS
sec4_2 = Section;
sec4_2.Title = 'Clasificación por Estrellas para Motocicletas';
k51=Paragraph('El SRS representa el riesgo relativo de fallecimiento o de lesiones serias para un usuario de carretera en particular. Para calcular las puntuaciones para la clasificación por estrellas (Star Rating Scores - SRS), es necesario estimar las puntuaciones parciales por tipo de siniestro. Los siniestros que intervienen son salida del camino (lado del conductor), salida del camino (lado del copiloto), choque frontal (pérdida de control), choque frontal (adelantamiento), siniestros en intersecciones, en acceso a propiedades y a lo largo de la vía. '); 
k51.FontFamilyName='Gotham';
k51.HAlign='justify';
add(chap4,k51);
add(chap4,sec4_2);


p10 = Paragraph('a) Salida del camino (lado del conductor)');
p10.Bold=1;
add(chap4,p10)
sec4_37 = Section;
tWavy = Text(' Probabilidad de los atributos viales');
tWavy.Style = {Underline('single')};
add(chap4,tWavy);
k52=Paragraph('Los factores de riesgo, asociados a la probabilidad del siniestro, para motociclistas y salida del camino se describen a continuación: ');
k52.FontFamilyName='Gotham';
k52.HAlign='justify';
add(chap4,k52);
add(chap4,sec4_37);
x1='El atributo vial ancho del carril es  ';
a=load('../mat/Datos_interfaz_tres_proceso');
b=load('../mat/Coeficientes_Mot')
x2=a.Datos_interfaz_tres.Ancho_de_carril(1,1);
x3='en una zona' 
x4=  a.Datos_interfaz_tres.Tipo_de_area(1,1);
x5=', entonces, el factor de riesgo es   '
%x6='1,1';
x6=b.Coeficientes_Mot.Salida_lado_conductor(1,1);
x6_1=string(x6);
x7='(tabla 2)';
s1=Paragraph(strcat(x1," ",x2," ",x3," ",x4," ",x5," ",x6_1," ",x7));
s1.FontFamilyName='Gotham';
s1.HAlign='justify';
add(chap4,s1);
x8='El atributo vial curvatura es  ';
x9= a.Datos_interfaz_tres.Curvatura(1,1);
x10='  , por lo tanto, el factor de riesgo es  '
x11=b.Coeficientes_Mot.Salida_lado_conductor(1,2);
x11_1=string(x11);
x12='  (tabla 3).';
s2=Paragraph(strcat(x8," ",x9," ",x10," ",x11_1," ",x12));
s2.FontFamilyName='Gotham';
s2.HAlign='justify';
add(chap4,s2);
x13='El atributo vial calidad de la curva es  ';
x14=a.Datos_interfaz_tres.Calidad_de_la_curva(1,1);
x15='  , entonces, el factor de riesgo es  ';
x16=b.Coeficientes_Mot.Salida_lado_conductor(1,3);
x16_1=string(x16);
x17='  (tabla 4).';
s3=Paragraph(strcat(x13," ",x14," ",x15," ",x16_1," ",x17));
s3.FontFamilyName='Gotham';
s3.HAlign='justify';
add(chap4,s3);
x18='El atributo vial delineación es   ';
x19=a.Datos_interfaz_tres.Delineacion(1,1);
x20='  , en consecuencia, el factor de riesgo es   ';
x21=b.Coeficientes_Mot.Salida_lado_conductor(1,4);
x21_1=string(x21);
x22='  (tabla 5).';
s4=Paragraph(strcat(x18," ",x19," ",x20," ",x21_1," ",x22));
s4.FontFamilyName='Gotham';
s4.HAlign='justify';
add(chap4,s4);
x23='El atributo vial bandas sonoras en el espaldón es   ';
x24=a.Datos_interfaz_tres.Bandas_sonoras_en_el_espaldon(1,1);
x25='  , con lo que el factor de riesgo es    ';
x26=b.Coeficientes_Mot.Salida_lado_conductor(1,5);
x26_1=string(x26)
x27='  (tabla 6).';
s5=Paragraph(strcat(x23," ",x24," ",x25," ",x26_1," ",x27));
s5.FontFamilyName='Gotham';
s5.HAlign='justify';
add(chap4,s5);
x28='El atributo vial condición de la vía es   ';
x29=a.Datos_interfaz_tres.Estado_de_la_superficie_de_la_via(1,1);
x30='  , por lo tanto, el factor de riesgo es     ';
x31=b.Coeficientes_Mot.Salida_lado_conductor(1,6);
x31_1=string(x31);
x32='  (tabla 7).';
s6=Paragraph(strcat(x28," ",x29," ",x30," ",x31_1," ",x32));
s6.FontFamilyName='Gotham';
s6.HAlign='justify';
add(chap4,s6);
x33='El atributo vial pendiente es   ';
x34=a.Datos_interfaz_tres.Pendiente(1,1);
x35='  , entonces, el factor de riesgo es     ';
x36=b.Coeficientes_Mot.Salida_lado_conductor(1,7); 
x36_1=string(x36);
x37='  (tabla 8).';
s7=Paragraph(strcat(x33," ",x34," ",x35," ",x36_1," ",x37));
s7.FontFamilyName='Gotham';
s7.HAlign='justify';
add(chap4,s7);
x38='El atributo vial resistencia al deslizamiento es   ';
x39=a.Datos_interfaz_tres.Resistencia_al_deslizamiento(1,1);
x40='  , en consecuencia, el factor de riesgo es     ';
x41=b.Coeficientes_Mot.Salida_lado_conductor(1,8);
x41_1=string(x41);
x42='  para ocupante del vehículo, salida del camino e intersección (tabla 9).';
s8=Paragraph(strcat(x38," ",x39," ",x40," ",x41_1," ",x42));
s8.FontFamilyName='Gotham';
s8.HAlign='justify';
add(chap4,s8);
sec4_38 = Section;
k53=Paragraph('La probabilidad de siniestro vial en el lado del conductor se obtiene multiplicando los factores de riesgo previamente obtenidos.');
k53.FontFamilyName='Gotham';
k53.HAlign='justify';
add(chap4,k52);
k54=Paragraph('Probabilidad = ancho del carril x curvatura x calidad de la curva x delineación x bandas sonoras en el espaldón x condición de la vía x pendiente x resistencia al deslizamiento');
k54.FontFamilyName='Gotham';
k54.HAlign='justify';
add(chap4,k54);
add(chap4,sec4_38);
x43='Probabilidad =';
s9=strcat(x43,x6_1,'x',x11_1,'x',x16_1,'x',x21_1,'x',x26_1,'x',x31_1,'x',x36_1,'x',x41_1);
add(chap4,s9);
x44=b.Coeficientes_Mot.Salida_lado_conductor(1,15);
x44_1=string(x44);
s10=strcat(x43,x44_1);
add(chap4,s10);
sec4_39 = Section;
tWavy = Text(' Severidad de los atributos viales');
tWavy.Style = {Underline('single')};
add(chap4,tWavy);
k55=Paragraph('Los factores de riesgo, asociados a la gravedad/severidad del siniestro, para el ocupante del vehículo y salida del camino se describen a continuación:');
k55.FontFamilyName='Gotham';
k55.HAlign='justify';
add(chap4,k55);
add(chap4,sec4_39);
x43='El atributo vial severidad lateral – distancia al objeto (lado del conductor) es ';
x44=a.Datos_interfaz_tres.Severidad_lateral_distancia_al_objeto_lado_del_conductor(1,1);
x45=' , por lo que el factor de riesgo es ';
x46=b.Coeficientes_Mot.Salida_lado_conductor(1,9);
x46_1=string(x46);
x47=' (tabla 10).';
s11=Paragraph(strcat(x43," ",x44," ",x45," ",x46_1," ",x47));
s11.FontFamilyName='Gotham';
s11.HAlign='justify';
add(chap4,s11);
x48='El atributo vial severidad lateral – objeto (lado del conductor) es ';
x49=a.Datos_interfaz_tres.Severidad_lateral_objeto_lado_del_conductor(1,1);
x50=' , en consecuencia, el factor de riesgo es ';
x51=b.Coeficientes_Mot.Salida_lado_conductor(1,10);
x51_1=string(x51);
x52=' (tabla 11).';
s12=Paragraph(strcat(x48," ",x49," ",x50," ",x51_1," ",x52));
s12.FontFamilyName='Gotham';
s12.HAlign='justify';
add(chap4,s12)
x53='El atributo vial ancho de espaldón pavimentado (lado del conductor) es ';
x54=a.Datos_interfaz_tres.Ancho_de_espaldon_pavimentado_lado_del_conductor(1,1);
x55=' , por lo tanto el factor de riesgo es ';
x56=b.Coeficientes_Mot.Salida_lado_conductor(1,11);
x56_1=string(x56);
x57=' (tabla 12).';
s13=Paragraph(strcat(x53," ",x54," ",x55," ",x56_1," ",x57));
s13.FontFamilyName='Gotham';
s13.HAlign='justify';
add(chap4,s13);
sec4_40 = Section;
k56=Paragraph('La gravedad de siniestro vial en el lado del conductor se obtiene multiplicando los factores de riesgo previamente determinados.');
k56.FontFamilyName='Gotham';
k56.HAlign='justify';
add(chap4,k56);
k57=Paragraph('Severidad = severidad lateral – distancia al objeto (lado del conductor) x severidad lateral – objeto (lado del conductor) x ancho de espaldón pavimentado (lado del conductor)');
k57.FontFamilyName='Gotham';
k57.HAlign='justify';
add(chap4,k57);
add(chap4,sec4_40);
x58='Severidad =';
s14=strcat(x58,x46_1,'x',x51_1,'x',x56_1);
add(chap4,s14);
x59=b.Coeficientes_Mot.Salida_lado_conductor(1,16);
x59_1=string(x59);
s15=strcat(x58,x59_1);
add(chap4,s15);

tWavy = Text(' Influencia del flujo');
tWavy.Style = {Underline('single')};
add(chap4,tWavy);
x59='El atributo vial flujo vehicular (TDPA) es ';
x60=a.Datos_interfaz_tres.Flujo_vehicular(1,1);
x61='vehículos por día, entonces, el factor de riesgo es ';
x62=b.Coeficientes_Mot.Salida_lado_conductor(1,12);
x62_1=string(x62);
s16=Paragraph(strcat(x59," ",x60," ",x61," ",x62_1));
s16.FontFamilyName='Gotham';
s16.HAlign='justify';
add(chap4,s16);

tWavy = Text(' Transitabilidad de la mediana');
tWavy.Style = {Underline('single')};
add(chap4,tWavy);
x63='El atributo vial transitabilidad de la mediana es ';
x64=a.Datos_interfaz_tres.Transitabilidad(1,1);
x65=' , por lo tanto, el factor de riesgo es ';
x66=b.Coeficientes_Mot.Salida_lado_conductor(1,13);
x66_1=string(x66)
s17=Paragraph(strcat(x63," ",x64," ",x65," ",x66_1));
s17.FontFamilyName='Gotham';
s17.HAlign='justify';
add(chap4,s17);

tWavy = Text(' Velocidad de operación (percentil 85)');
tWavy.Style = {Underline('single')};
add(chap4,tWavy);
x67='El atributo vial velocidad de operación (percentil 85) es ';
x68=a.Datos_interfaz_tres.Velocidad_de_operacion_percentil_85 (1,1);
x69=' para ocupantes de vehículos y motociclistas (caminos divididos, no divididos y rurales, de frente, de salida e intersección), en consecuencia, el factor de riesgo es ';
x70=b.Coeficientes_Mot.Salida_lado_conductor(1,14);
x70_1=string(x70);
x71='(tabla 15).';
s18=Paragraph(strcat(x67," ",x68," ",x69," ",x70_1," ",x71));
s18.FontFamilyName='Gotham';
s18.HAlign='justify';
add(chap4,s18);

sec4_41 = Section;
tWavy = Text(' Puntaje por salida del camino (lado del conductor)');
tWavy.Style = {Underline('single')};
add(chap4,tWavy);
k58=Paragraph('El puntaje por salida del camino (lado del conductor) se obtiene multiplicando entre sí los factores de riesgos parciales.');
k58.FontFamilyName='Gotham';
k58.HAlign='justify';
add(chap4,k58);
k59=Paragraph('Puntaje = probabilidad x severidad x influencia del flujo x transitabilidad de la media x velocidad de operación (percentil 85)');
k59.FontFamilyName='Gotham';
k59.HAlign='justify';
add(chap4,k59);
add(chap4,sec4_41);
x72='Puntaje =';
s19=strcat(x72,'x',x44_1,'x',x59_1,'x',x62_1,'x',x66_1,'x',x70_1);
add(chap4,s19);
x73=b.Coeficientes_Mot.Salida_lado_conductor(1,17);
x73_1=string(x73);
s20=strcat(x72,x73_1);
add(chap4,s20);

p10 = Paragraph('b) Salida del camino (lado del copiloto)');
p10.Bold=1;
add(chap4,p10)
sec4_42 = Section;
tWavy = Text(' Probabilidad de los atributos viales');
tWavy.Style = {Underline('single')};
add(chap4,tWavy);
k60=Paragraph('Los factores de riesgo, asociados a la probabilidad del siniestro, para el motociclista y salida del camino se describen a continuación: ');
k60.FontFamilyName='Gotham';
k60.HAlign='justify';
add(chap4,k60);
add(chap4,sec4_42);
x1='El atributo vial ancho del carril es  ';
a=load('../mat/Datos_interfaz_tres_proceso');
b=load('../mat/Coeficientes_Mot')
x2=a.Datos_interfaz_tres.Ancho_de_carril(1,1);
x3='en una zona' 
x4=  a.Datos_interfaz_tres.Tipo_de_area(1,1);
x5=', entonces, el factor de riesgo es   '
%x6='1,1';
x6=b.Coeficientes_Mot.Salida_lado_copiloto(1,1);
x6_1=string(x6);
x7='(tabla 2)';
s1=Paragraph(strcat(x1," ",x2," ",x3," ",x4," ",x5," ",x6_1," ",x7));
s1.FontFamilyName='Gotham';
s1.HAlign='justify';
add(chap4,s1);
x8='El atributo vial curvatura es  ';
x9= a.Datos_interfaz_tres.Curvatura(1,1);
x10='  , por lo tanto, el factor de riesgo es  '
x11=b.Coeficientes_Mot.Salida_lado_copiloto(1,2);
x11_1=string(x11);
x12='  (tabla 3).';
s2=Paragraph(strcat(x8," ",x9," ",x10," ",x11_1," ",x12));
s2.FontFamilyName='Gotham';
s2.HAlign='justify';
add(chap4,s2);
x13='El atributo vial calidad de la curva es  ';
x14=a.Datos_interfaz_tres.Calidad_de_la_curva(1,1);
x15='  , entonces, el factor de riesgo es  ';
x16=b.Coeficientes_Mot.Salida_lado_copiloto(1,3);
x16_1=string(x16);
x17='  (tabla 4).';
s3=Paragraph(strcat(x13," ",x14," ",x15," ",x16_1," ",x17));
s3.FontFamilyName='Gotham';
s3.HAlign='justify';
add(chap4,s3);
x18='El atributo vial delineación es   ';
x19=a.Datos_interfaz_tres.Delineacion(1,1);
x20='  , en consecuencia, el factor de riesgo es   ';
x21=b.Coeficientes_Mot.Salida_lado_copiloto(1,4);
x21_1=string(x21);
x22='  (tabla 5).';
s4=Paragraph(strcat(x18," ",x19," ",x20," ",x21_1," ",x22));
s4.FontFamilyName='Gotham';
s4.HAlign='justify';
add(chap4,s4);
x23='El atributo vial bandas sonoras en el espaldón es   ';
x24=a.Datos_interfaz_tres.Bandas_sonoras_en_el_espaldon(1,1);
x25='  , con lo que el factor de riesgo es    ';
x26=b.Coeficientes_Mot.Salida_lado_copiloto(1,5);
x26_1=string(x26)
x27='  (tabla 6).';
s5=Paragraph(strcat(x23," ",x24," ",x25," ",x26_1," ",x27));
s5.FontFamilyName='Gotham';
s5.HAlign='justify';
add(chap4,s5);
x28='El atributo vial condición de la vía es   ';
x29=a.Datos_interfaz_tres.Estado_de_la_superficie_de_la_via(1,1);
x30='  , por lo tanto, el factor de riesgo es     ';
x31=b.Coeficientes_Mot.Salida_lado_copiloto(1,6);
x31_1=string(x31);
x32='  (tabla 7).';
s6=Paragraph(strcat(x28," ",x29," ",x30," ",x31_1," ",x32));
s6.FontFamilyName='Gotham';
s6.HAlign='justify';
add(chap4,s6);
x33='El atributo vial pendiente es   ';
x34=a.Datos_interfaz_tres.Pendiente(1,1);
x35='  , entonces, el factor de riesgo es     ';
x36=b.Coeficientes_Mot.Salida_lado_copiloto(1,7); 
x36_1=string(x36);
x37='  (tabla 8).';
s7=Paragraph(strcat(x33," ",x34," ",x35," ",x36_1," ",x37));
s7.FontFamilyName='Gotham';
s7.HAlign='justify';
add(chap4,s7);
x38='El atributo vial resistencia al deslizamiento es   ';
x39=a.Datos_interfaz_tres.Resistencia_al_deslizamiento(1,1);
x40='  , en consecuencia, el factor de riesgo es     ';
x41=b.Coeficientes_Mot.Salida_lado_copiloto(1,8);
x41_1=string(x41);
x42='  para ocupante del vehículo, salida del camino e intersección (tabla 9).';
s8=Paragraph(strcat(x38," ",x39," ",x40," ",x41_1," ",x42));
s8.FontFamilyName='Gotham';
s8.HAlign='justify';
add(chap4,s8);
sec4_43 = Section;
k61=Paragraph('La probabilidad de siniestro vial en el lado del conductor se obtiene multiplicando los factores de riesgo previamente obtenidos.');
k61.FontFamilyName='Gotham';
k61.HAlign='justify';
add(chap4,k61);
k62=Paragraph('Probabilidad = ancho del carril x curvatura x calidad de la curva x delineación x bandas sonoras en el espaldón x condición de la vía x pendiente x resistencia al deslizamiento');
k62.FontFamilyName='Gotham';
k62.HAlign='justify';
add(chap4,k62);
add(chap4,sec4_43);
x43='Probabilidad =';
s9=strcat(x43,x6_1,'x',x11_1,'x',x16_1,'x',x21_1,'x',x26_1,'x',x31_1,'x',x36_1,'x',x41_1);
add(chap4,s9);
x44=b.Coeficientes_Mot.Salida_lado_copiloto(1,14);
x44_1=string(x44);
s10=strcat(x43,x44_1);
add(chap4,s10);
sec4_44 = Section;
tWavy = Text(' Severidad de los atributos viales');
tWavy.Style = {Underline('single')};
add(chap4,tWavy);
k63=Paragraph('Los factores de riesgo, asociados a la gravedad/severidad del siniestro, para el ocupante del vehículo y salida del camino se describen a continuación:');
k63.FontFamilyName='Gotham';
k63.HAlign='justify';
add(chap4,k63);
add(chap4,sec4_44);
x43='El atributo vial severidad lateral – distancia al objeto (lado del copiloto) es ';
x44=a.Datos_interfaz_tres.Severidad_lateral_distancia_al_objeto_lado_del_copiloto(1,1);
x45=' , por lo que el factor de riesgo es ';
x46=b.Coeficientes_Mot.Salida_lado_copiloto(1,9);
x46_1=string(x46);
x47=' (tabla 10).';
s11=Paragraph(strcat(x43," ",x44," ",x45," ",x46_1," ",x47));
s11.FontFamilyName='Gotham';
s11.HAlign='justify';
add(chap4,s11);
x48='El atributo vial severidad lateral – objeto (lado del copiloto) es ';
x49=a.Datos_interfaz_tres.Severidad_lateral_objeto_lado_del_copiloto(1,1);
x50=' , en consecuencia, el factor de riesgo es ';
x51=b.Coeficientes_Mot.Salida_lado_conductor(1,10);
x51_1=string(x51);
x52=' (tabla 11).';
s12=Paragraph(strcat(x48," ",x49," ",x50," ",x51_1," ",x52));
s12.FontFamilyName='Gotham';
s12.HAlign='justify';
add(chap4,s12)
x53='El atributo vial ancho de espaldón pavimentado (lado del copiloto) es ';
x54=a.Datos_interfaz_tres.Ancho_de_espaldon_pavimentado_lado_del_copiloto(1,1);
x55=' , por lo tanto el factor de riesgo es ';
x56=b.Coeficientes_Mot.Salida_lado_copiloto(1,11);
x56_1=string(x56);
x57=' (tabla 12).';
s13=Paragraph(strcat(x53," ",x54," ",x55," ",x56_1," ",x57));
s13.FontFamilyName='Gotham';
s13.HAlign='justify';
add(chap4,s13);
sec4_45 = Section;
k64=Paragraph('La gravedad de siniestro vial en el lado del copiloto se obtiene multiplicando los factores de riesgo previamente determinados.');
k64.FontFamilyName='Gotham';
k64.HAlign='justify';
add(chap4,k64);
k65=Paragraph('Severidad = severidad lateral – distancia al objeto (lado del copiloto) x severidad lateral – objeto (lado del copiloto) x ancho de espaldón pavimentado (lado del copiloto)');
k65.FontFamilyName='Gotham';
k65.HAlign='justify';
add(chap4,k65);
add(chap4,sec4_45);
x58='Severidad =';
s14=strcat(x58,x46_1,'x',x51_1,'x',x56_1);
add(chap4,s14);
x59=b.Coeficientes_Mot.Salida_lado_copiloto(1,15);
x59_1=string(x59);
s15=strcat(x58,x59_1);
add(chap4,s15);

tWavy = Text(' Influencia del flujo');
tWavy.Style = {Underline('single')};
add(chap4,tWavy);
x59='El atributo vial flujo vehicular (TDPA) es ';
x60=a.Datos_interfaz_tres.Flujo_vehicular(1,1);
x61='vehículos por día, entonces, el factor de riesgo es ';
x62=b.Coeficientes_Mot.Salida_lado_copiloto(1,12);
x62_1=string(x62);
s16=Paragraph(strcat(x59," ",x60," ",x61," ",x62_1));
s16.FontFamilyName='Gotham';
s16.HAlign='justify';
add(chap4,s16);

tWavy = Text(' Velocidad de operación (percentil 85)');
tWavy.Style = {Underline('single')};
add(chap4,tWavy);
x67='El atributo vial velocidad de operación (percentil 85) es ';
x68=a.Datos_interfaz_tres.Velocidad_de_operacion_percentil_85 (1,1);
x69=' para ocupantes de vehículos y motociclistas (caminos divididos, no divididos y rurales, de frente, de salida e intersección), en consecuencia, el factor de riesgo es ';
x70=b.Coeficientes_Mot.Salida_lado_copiloto(1,13);
x70_1=string(x70);
x71='(tabla 15).';
s18=Paragraph(strcat(x67," ",x68," ",x69," ",x70_1," ",x71));
s18.FontFamilyName='Gotham';
s18.HAlign='justify';
add(chap4,s18);

sec4_46 = Section;
tWavy = Text(' Puntaje por salida del camino (lado del copiloto)');
tWavy.Style = {Underline('single')};
add(chap4,tWavy);
k66=Paragraph('El puntaje por salida del camino (lado del conductor) se obtiene multiplicando entre sí los factores de riesgos parciales.');
k66.FontFamilyName='Gotham';
k66.HAlign='justify';
add(chap4,k66);
k67=Paragraph('Puntaje = probabilidad x severidad x influencia del flujo x  velocidad de operación (percentil 85)');
k67.FontFamilyName='Gotham';
k67.HAlign='justify';
add(chap4,k67);
add(chap4,sec4_46);
x72='Puntaje =';
s19=strcat(x72,x44_1,'x',x59_1,'x',x62_1,'x',x70_1);
add(chap4,s19);
x73=b.Coeficientes_Mot.Salida_lado_copiloto(1,16);
x73_1=string(x73);
s20=strcat(x72,x73_1);
add(chap4,s20);

p12 = Paragraph('c) Choque frontal (pérdida del control)');
p12.Bold=1;
add(chap4,p12)
sec4_47 = Section;
tWavy = Text(' Probabilidad de los atributos viales');
tWavy.Style = {Underline('single')};
add(chap4,tWavy);
k68=Paragraph('Los factores de riesgo, asociados a la probabilidad del siniestro, para motociclistas y choque frontal (pérdida del control) se describen a continuación: ');
k68.FontFamilyName='Gotham';
k68.HAlign='justify';
add(chap4,k68);
add(chap4,sec4_47);
x1='El atributo vial ancho del carril es ';
a=load('../mat/Datos_interfaz_tres_proceso');
b=load('../mat/Coeficientes_Mot')
x2= a.Datos_interfaz_tres.Ancho_de_carril(1,1);
x3=' en una zona ' 
x4= a.Datos_interfaz_tres.Tipo_de_area(1,1);
x5=' , entonces, el factor de riesgo es '
x6=b.Coeficientes_Mot.Perdida_de_control(1,1);
x6_1=string(x6);
x7=' (tabla 2)';
s1=Paragraph(strcat(x1," ",x2," ",x3," ",x4," ",x5," ",x6_1," ",x7));
s1.FontFamilyName='Gotham';
s1.HAlign='justify';
add(chap4,s1);
x8='El atributo vial curvatura es  ';
x9= a.Datos_interfaz_tres.Curvatura(1,1);
x10='  , por lo tanto, el factor de riesgo es  '
x11=b.Coeficientes_Mot.Perdida_de_control(1,2);
x11_1=string(x11);
x12='  (tabla 3).';
s2=Paragraph(strcat(x8," ",x9," ",x10," ",x11_1," ",x12));
s2.FontFamilyName='Gotham';
s2.HAlign='justify';
add(chap4,s2);
x13='El atributo vial calidad de la curva es  ';
x14=a.Datos_interfaz_tres.Calidad_de_la_curva(1,1);
x15='  , entonces, el factor de riesgo es  ';
x16=b.Coeficientes_Mot.Perdida_de_control(1,3);
x16_1=string(x16);
x17='  (tabla 4).';
s3=Paragraph(strcat(x13," ",x14," ",x15," ",x16_1," ",x17));
s3.FontFamilyName='Gotham';
s3.HAlign='justify';
add(chap4,s3);
x18='El atributo vial delineación es   ';
x19=a.Datos_interfaz_tres.Delineacion(1,1);
x20='  , en consecuencia, el factor de riesgo es   ';
x21=b.Coeficientes_Mot.Perdida_de_control(1,4);
x21_1=string(x21);
x22='  (tabla 5).';
s4=Paragraph(strcat(x18," ",x19," ",x20," ",x21_1," ",x22));
s4.FontFamilyName='Gotham';
s4.HAlign='justify';

add(chap4,s4);
x23='El atributo vial bandas sonoras centrales es ';
x24=a.Datos_interfaz_tres.Bandas_sonoras_centrales(1,1);
x25=' , con lo que el factor de riesgo es ';
x26=b.Coeficientes_Mot.Perdida_de_control(1,5);
x26_1=string(x26);
x27=' (tabla 16).';
s5=Paragraph(strcat(x23," ",x24," ",x25," ",x26_1," ",x27));
s5.FontFamilyName='Gotham';
s5.HAlign='justify';
add(chap4,s5);
x28='El atributo vial condición de la vía es ';
x29=a.Datos_interfaz_tres.Estado_de_la_superficie_de_la_via(1,1);
x30=' , por lo tanto, el factor de riesgo es ';
x31=b.Coeficientes_Mot.Perdida_de_control(1,6);
x31_1=string(x31);
x32=' (tabla 7).';
s6=Paragraph(strcat(x28," ",x29," ",x30," ",x31_1," ",x32));
S6.FontFamilyName='Gotham';
s6.HAlign='justify';
add(chap4,s6);
x33='El atributo vial pendiente es ';
x34=a.Datos_interfaz_tres.Pendiente(1,1);
x35=' , entonces, el factor de riesgo es ';
x36=b.Coeficientes_Mot.Perdida_de_control(1,7);
x36_1=string(x36);
x37=' (tabla 8).';
s7=Paragraph(strcat(x33," ",x34," ",x35," ",x36_1," ",x37));
s7.FontFamilyName='Gotham';
s7.HAlign='justify';
add(chap4,s7);
x38='El atributo vial resistencia al deslizamiento es ';
x39=a.Datos_interfaz_tres.Resistencia_al_deslizamiento(1,1);
x40=' , en consecuencia, el factor de riesgo es ';
x41=b.Coeficientes_Mot.Perdida_de_control(1,8);
x41_1=string(x41);
x42=' para ocupante del vehículo, salida del camino e intersección (tabla 9).';
s8=Paragraph(strcat(x38," ",x39," ",x40," ",x41_1," ",x42));
s8.FontFamilyName='Gotham';
s8.HAlign='justify';
add(chap4,s8);
sec4_48 = Section;
k69=Paragraph('La probabilidad de siniestro vial por pérdida del control se obtiene multiplicando los factores de riesgo previamente obtenidos.');
k69.FontFamilyName='Gotham';
k69.HAlign='justify';
add(chap4,k69);
k70=Paragraph('Probabilidad = ancho del carril x curvatura x calidad de la curva x delineación x bandas sonoras centrales x condición de la vía x pendiente x resistencia al deslizamiento');
k70.FontFamilyName='Gotham';
k70.HAlign='justify';
add(chap4,k70);
add(chap4,sec4_48);
x43='Probabilidad =';
s9=strcat(x43,"x",x6_1,"x",x11_1,"x",x16_1,"x",x21_1,"x",x26_1,"x",x31_1,"x",x41_1);
add(chap4,s9);
x44=b.Coeficientes_Mot.Perdida_de_control(1,13);
x44_1=string(x44);
s10=strcat(x43,x44_1);
add(chap4,s10);

sec4_49 = Section;
tWavy = Text(' Severidad de los atributos viales');
tWavy.Style = {Underline('single')};
add(chap4,tWavy);
k71=Paragraph('Los factores de riesgo, asociados a la gravedad/severidad del siniestro, para el ocupante del vehículo y choque frontal (pérdida del control) se describen a continuación: ');
k71.FontFamilyName='Gotham';
k71.HAlign='justify';
add(chap4,k71);
add(chap4,sec4_49);
x43='El atributo vial tipo de mediana es ';
x44=a.Datos_interfaz_tres.Tipo_de_mediana(1,1);
x45=' , por lo que el factor de riesgo es ';
x46=b.Coeficientes_Mot.Perdida_de_control(1,9);
x46_1=string(x46);
x47=' (tabla 17).';
s11=Paragraph(strcat(x43," ",x44,"x",x45,"x",x46_1,"x",x47));
s11.FontFamilyName='Gotham';
s11.HAlign='justify';
add(chap4,s11);
sec4_50 = Section;
k72=Paragraph('La gravedad de siniestro vial por pérdida de control se obtiene multiplicando los factores de riesgo previamente determinados.');
k72.FontFamilyName='Gotham';
k72.HAlign='justify';
add(chap4,k72);
add(sec4_50,Paragraph('Severidad = tipo de mediana'));
add(chap4,sec4_50);
x48='Severidad =';
s12=strcat(x48,x46_1);
add(chap4,s12);

tWavy = Text(' Influencia del flujo');
tWavy.Style = {Underline('single')};
add(chap4,tWavy);
x49='El atributo vial flujo vehicular (TDPA) es ';
x50=a.Datos_interfaz_tres.Flujo_vehicular(1,1);
x51='vehículos por día, entonces, el factor de riesgo es ';
x52=b.Coeficientes_Mot.Perdida_de_control(1,10);
x52_1=string(x52);
s13=Paragraph(strcat(x49," ",x50," ",x51," ",x52_1));
s13.FontFamilyName='Gotham';
s13.HAlign='justify';
add(chap4,s13);

tWavy = Text(' Transitabilidad de la mediana');
tWavy.Style = {Underline('single')};
add(chap4,tWavy);
x53='El atributo vial transitabilidad de la mediana es ';
x54=a.Datos_interfaz_tres.Transitabilidad(1,1);
x55=' , por lo tanto, el factor de riesgo es ';
x56=b.Coeficientes_Mot.Perdida_de_control(1,11);
x56_1=string(x56);
s14=Paragraph(strcat(x53," ",x54," ",x55," ",x56_1));
s14.FontFamilyName='Gotham';
s14.HAlign='justify';
add(chap4,s14);

tWavy = Text(' Velocidad de operación (percentil 85)');
tWavy.Style = {Underline('single')};
add(chap4,tWavy);
x57='El atributo vial velocidad de operación (percentil 85) es ';
x58=a.Datos_interfaz_tres.Velocidad_de_operacion_percentil_85 (1,1);
x59=' para ocupantes de vehículos y motociclistas (caminos divididos, no divididos y rurales, de frente, de salida e intersección), en consecuencia, el factor de riesgo es ';
x60=b.Coeficientes_Mot.Perdida_de_control(1,12);
x60_1=string(x60);
x61='(tabla 15).';
s15=Paragraph(strcat(x57," ",x58," ",x59," ",x60_1," ",x61));
s15.FontFamilyName='Gotham';
s15.HAlign='justify';
add(chap4,s15);

sec4_51 = Section;
tWavy = Text('Puntaje por choque frontal (pérdida del control)');
tWavy.Style = {Underline('single')};
add(chap4,tWavy);
k73=Paragraph('El puntaje por choque frontal (pérdida del control) se obtiene multiplicando entre sí los factores de riesgos parciales.');
k73.FontFamilyName='Gotham';
k73.HAlign='justify';
add(chap4,k73);
k74=Paragraph('Puntaje = probabilidad x severidad x influencia del flujo x transitabilidad de la media x velocidad de operación (percentil 85)');
k74.FontFamilyName='Gotham';
k74.HAlign='justify';
add(chap4,k74);
add(chap4,sec4_51);
x62='Puntaje= ';
s16=strcat(x62,x44_1,'x',x46_1,'x',x52_1,'x',x56_1,'x',x60_1);
add(chap4,s16);
x63=b.Coeficientes_Mot.Perdida_de_control(1,14);
x63_1=string(x63);
s17=strcat(x62,x63_1);
add(chap4,s17);

p13 = Paragraph('d) Choque frontal (adelantamiento))');
p13.Bold=1;
add(chap4,p13)
sec4_52 = Section;
tWavy = Text('Probabilidad de los atributos viales');
tWavy.Style = {Underline('single')};
add(chap4,tWavy);
k75=Paragraph('Los factores de riesgo, asociados a la probabilidad del siniestro, para motociclistas y choque frontal (adelantamiento) se describen a continuación: ');
k75.FontFamilyName='Gotham';
k75.HAlign='justify';
add(chap4,k75);
add(chap4,sec4_52);
x1='El atributo vial pendiente es ';
a=load('../mat/Datos_interfaz_tres_proceso');
b=load('../mat/Coeficientes_Mot')
x2= a.Datos_interfaz_tres.Pendiente(1,1);
x3=' , entonces, el factor de riesgo es ' 
x4=b.Coeficientes_Mot.Adelantamiento(1,1);
x4_1=string(x4);
x5=' (tabla 8)';
s1=Paragraph(strcat(x1," ",x2," ",x3," ",x4_1," ",x5));
s1.FontFamilyName='Gotham';
s1.HAlign='justify';
add(chap4,s1);
x6='El atributo vial resistencia al deslizamiento es ';
x7= a.Datos_interfaz_tres.Resistencia_al_deslizamiento(1,1);
x8=' , en consecuencia, el factor de riesgo es '
x9=b.Coeficientes_Mot.Adelantamiento(1,2);
x9_1=string(x9)
x10=' para ocupante del vehículo, salida del camino e intersección (tabla 9).';
s2=Paragraph(strcat(x6," ",x7," ",x8," ",x9_1," ",x10));
s2.FontFamilyName='Gotham';
s2.HAlign='justify';
add(chap4,s2);
x13='El atributo vial diferencial de límites de velocidad es ';
x14=a.Datos_interfaz_tres.Limite_de_velocidad(1,1);
x15=' , entonces, el factor de riesgo es ';
x16=b.Coeficientes_Mot.Adelantamiento(1,3);
x16_1=string(x16);
x17=' (tabla 18).';
s3=Paragraph(strcat(x13," ",x14," ",x15," ",x16_1," ",x17));
s3.FontFamilyName='Gotham';
s3.HAlign='justify';
add(chap4,s3);
x18='El atributo vial número de carriles es ';
x19=a.Datos_interfaz_tres.Numero_de_carriles(1,1);
x20=' , por lo tanto, el factor de riesgo es ';
x21=b.Coeficientes_Mot.Adelantamiento(1,4);
x21_1=string(x21);
x22=' (tabla 19).';
s4=Paragraph(strcat(x18," ",x19," ",x20," ",x21_1," ",x22));
s4.FontFamilyName='Gotham';
s4.HAlign='justify';
add(chap4,s4);
sec4_53 = Section;
k76=Paragraph('La probabilidad de siniestro vial por adelantamiento se obtiene multiplicando los factores de riesgo previamente obtenidos.');
k76.FontFamilyName='Gotham';
k76.HAlign='justify';
add(chap4,k76);
k77=Paragraph('Probabilidad = pendiente x resistencia al deslizamiento x diferencial de límites de velocidad x número de carriles');
k77.FontFamilyName='Gotham';
k77.HAlign='justify';
add(chap4,k77);
add(chap4,sec4_53);
x23='Probabilidad =';
s5=strcat(x23,x4_1,'x',x9_1,'x',x16_1,'x',x21_1);
add(chap4,s5);
x24=b.Coeficientes_Mot.Adelantamiento(1,8);
x24_1=string(x24);
s6=strcat(x23,x24_1);
add(chap4,s6);
sec4_54 = Section;
tWavy = Text('Severidad de los atributos viales');
tWavy.Style = {Underline('single')};
add(chap4,tWavy);
k78=Paragraph('Los factores de riesgo, asociados a la gravedad/severidad del siniestro, para el ocupante del vehículo y choque frontal (adelantamiento) se describen a continuación:'); 
k78.FontFamilyName='Gotham';
k78.HAlign='justify';
add(chap4,k78);
add(chap4,sec4_54);
x24='El atributo vial tipo de mediana es ';
x25=a.Datos_interfaz_tres.Tipo_de_mediana(1,1);
x26=', por lo que el factor de riesgo es ';
x27=b.Coeficientes_Mot.Adelantamiento(1,5);
x27_1=string(x27);
x28='(tabla 17).';
s6=Paragraph(strcat(x24," ",x25," ",x26," ",x27_1," ",x28));
s6.FontFamilyName='Gotham';
s6.HAlign='justify';
add(chap4,s6);
sec4_55 = Section;
k79=Paragraph('La gravedad de siniestro vial por pérdida de control se obtiene multiplicando los factores de riesgo previamente determinados.');
k79.FontFamilyName='Gotham';
k79.HAlign='justify';
add(chap4,k79);
add(sec4_55,Paragraph('Severidad = tipo de mediana'));
add(chap4,sec4_55);
x29='Severidad =';
s7=strcat(x29,x27_1);
add(chap4,s7);

tWavy = Text('Influencia del flujo');
tWavy.Style = {Underline('single')};
add(chap4,tWavy);
x49='El atributo vial flujo vehicular (TDPA) es ';
x50=a.Datos_interfaz_tres.Flujo_vehicular(1,1);
x51='vehículos por día, entonces, el factor de riesgo es ';
x52=b.Coeficientes_Mot.Adelantamiento(1,6);
x52_1=string(x52);
s13=Paragraph(strcat(x49," ",x50," ",x51," ",x52_1));
s13.FontFamilyName='Gotham';
s13.HAlign='justify';

add(chap4,s13);

tWavy = Text('Velocidad de operación (percentil 85)');
tWavy.Style = {Underline('single')};
add(chap4,tWavy);
x57='El atributo vial velocidad de operación (percentil 85) es ';
x58=a.Datos_interfaz_tres.Velocidad_de_operacion_percentil_85 (1,1);
x59=' para ocupantes de vehículos y motociclistas (caminos divididos, no divididos y rurales, de frente, de salida e intersección), en consecuencia, el factor de riesgo es ';
x60=b.Coeficientes_Mot.Adelantamiento(1,7);
x60_1=string(x60);
x61='(tabla 15).';
s15=Paragraph(strcat(x57," ",x58," ",x59," ",x60_1," ",x61));
s15.FontFamilyName='Gotham';
s15.HAlign='justify';
add(chap4,s15);

sec4_56 = Section;
tWavy = Text('Puntaje por choque frontal (adelantamiento)');
tWavy.Style = {Underline('single')};
add(chap4,tWavy);
k80=Paragraph('El puntaje por choque frontal (adelantamiento) se obtiene multiplicando entre sí los factores de riesgos parciales.');
k80.FontFamilyName='Gotham';
k80.HAlign='justify';
add(chap4,k80);
k81=Paragraph('Puntaje = probabilidad x severidad x influencia del flujo x velocidad de operación (percentil 85)');
k81.FontFamilyName='Gotham';
k81.HAlign='justify';
add(chap4,k81);
add(chap4,sec4_56);
x62='Puntaje = ';
s16=strcat(x62,x24_1,'x',x27_1,'x',x52_1,'x',x60_1);
add(chap4,s16);
x63=b.Coeficientes_Mot.Adelantamiento(1,9);
x63_1=string(x63);
s17=strcat(x62,x63_1);
add(chap4,s17);

p14 = Paragraph('e) Intersecciones');
p14.Bold=1;
add(chap4,p14)
sec4_57 = Section;
tWavy = Text('Probabilidad de los atributos viales');
tWavy.Style = {Underline('single')};
add(chap4,tWavy)
k82=Paragraph('Los factores de riesgo, asociados a la probabilidad del siniestro, para motociclistas e intersecciones se describen a continuación: ');
k82.FontFamilyName='Gotham';
k82.HAlign='justify';
add(chap4,k82);
add(chap4,sec4_57);
x1='El atributo vial tipo de intersección es ';
a=load('../mat/Datos_interfaz_tres_proceso');
b=load('../mat/Coeficientes_Mot')
x2= a.Datos_interfaz_tres.Tipo_de_interseccion(1,1);
x3=' , entonces, el factor de riesgo es ' 
x4=b.Coeficientes_Mot.Interseccion(1,1);
x4_1=string(x4);
x5=' (tabla 20)';
s1=Paragraph(strcat(x1," ",x2," ",x3," ",x4_1," ",x5));
s1.FontFamilyName='Gotham';
s1.HAlign='justify';
add(chap4,s1);
x6='El atributo vial calidad de la intersección es ';
x7= a.Datos_interfaz_tres.Calidad_de_la_interseccion(1,1);
x8=' , por lo tanto, el factor de riesgo es '
x9=b.Coeficientes_Mot.Interseccion(1,2);
x9_1=string(x9);
x10=' (tabla 22).';
s2=Paragraph(strcat(x6," ",x7," ",x8," ",x9_1," ",x10));
s2.FontFamilyName='Gotham';
s2.HAlign='justify';
add(chap4,s2);
x13='El atributo vial pendiente es ';
x14=a.Datos_interfaz_tres.Pendiente(1,1);
x15=' , entonces, el factor de riesgo es ';
x16=b.Coeficientes_Mot.Interseccion(1,3);
x16_1=string(x16);
x17=' (tabla 8).';
s3=Paragraph(strcat(x13," ",x14," ",x15," ",x16_1," ",x17));
s3.FontFamilyName='Gotham';
s3.HAlign='justify';
add(chap4,s3);
x18='El atributo vial alumbrado público es ';
x19=a.Datos_interfaz_tres.Alumbrado_publico(1,1);
x20=' , en consecuencia, el factor de riesgo es ';
x21=b.Coeficientes_Mot.Interseccion(1,4);
x21_1=string(x21);
x22=' (tabla 23).';
s4=Paragraph(strcat(x18," ",x19," ",x20," ",x21_1," ",x22));
s4.FontFamilyName='Gotham';
s4.HAlign='justify';
add(chap4,s4);
x23='El atributo vial resistencia al deslizamiento es ';
x24=a.Datos_interfaz_tres.Resistencia_al_deslizamiento(1,1);
x25=', en consecuencia, el factor de riesgo es'
x26=b.Coeficientes_Mot.Interseccion(1,5);
x26_1=string(x26);
x27='para ocupante del vehículo, salida del camino e intersección (tabla 9).';
s5=Paragraph(strcat(x23," ",x24," ",x25," ",x26_1," ",x27));
s5.FontFamilyName='Gotham';
s5.HAlign='justify';
add(chap4,s5);
x28='El atributo vial distancia de visibilidad es ';
x29=a.Datos_interfaz_tres.Distancia_de_visibilidad(1,1);
x30=', entonces, el factor de riesgo es ';
x31=b.Coeficientes_Mot.Interseccion(1,6);
x31_1=string(x31);
x32='(tabla 24).';
s6=Paragraph(strcat(x28," ",x29," ",x30," ",x31_1," ",x32));
s6.FontFamilyName='Gotham';
s6.HAlign='justify';
add(chap4,s6);
x33='El atributo vial canalización de la intersección es ';
x34=a.Datos_interfaz_tres.Canalizacion_de_la_interseccion(1,1);
x35=', por lo tanto, el factor de riesgo es';
x36=b.Coeficientes_Mot.Interseccion(1,7);
x36_1=string(x36);
x37='(tabla 25).';
s7=Paragraph(strcat(x33," ",x34," ",x35," ",x36_1," ",x37));
s7.FontFamilyName='Gotham';
s7.HAlign='justify';
add(chap4,s7);
x38='El atributo vial gestión de la velocidad/calmante del tráfico es';
x39=a.Datos_interfaz_tres.Gestion_de_la_velocidad_calmante_del_trafico(1,1);
x40=', entonces, el factor de riesgo es';
x41=b.Coeficientes_Mot.Interseccion(1,8);
x41_1=string(x41);
x42='(tabla 26).';
s8=Paragraph(strcat(x38," ",x39," ",x40," ",x41_1," ",x42));
s8.FontFamilyName='Gotham';
s8.HAlign='justify';
add(chap4,s8);
sec4_58 = Section;

k83=Paragraph('La probabilidad de siniestro vial en las intersecciones se obtiene multiplicando los factores de riesgo previamente obtenidos.');
k83.FontFamilyName='Gotham';
k83.HAlign='justify';
add(chap4,k83);
k84=Paragraph('Probabilidad = tipo de intersección x calidad de la intersección x pendiente x alumbrado público x resistencia al deslizamiento x distancia de visibilidad x canalización de la intersección x gestión de la velocidad/calmante del tráfico ');
k84.FontFamilyName='Gotham';
k84.HAlign='justify';
add(chap4,k84);
add(chap4,sec4_58);
x43='Probabilidad =';
s9=strcat(x43,x4_1,'x',x9_1,'x',x16_1,'x',x21_1,'x',x26_1,'x',x31_1,'x',x36_1,'x',x41_1);
add(chap4,s9);
x44=b.Coeficientes_Mot.Interseccion(1,12);
x44_1=string(x44);
s10=strcat(x43,x44_1);

sec4_59 = Section;
tWavy = Text('Severidad de los atributos viales');
tWavy.Style = {Underline('single')};
add(chap4,tWavy)
k85=Paragraph('Los factores de riesgo, asociados a la gravedad/severidad del siniestro, para el ocupante del vehículo e intersecciones se describen a continuación: ');
k85.FontFamilyName='Gotham';
k85.HAlign='justify';
add(chap4,k85);
add(chap4,sec4_59);
x44='El atributo vial tipo de intersección es';
x45=a.Datos_interfaz_tres.Tipo_de_interseccion(1,1);
x46=', entonces, el factor de riesgo es';
x47=b.Coeficientes_Mot.Interseccion(1,9);
x47_1=string(x47);
x48='tabla(20).';
s10=Paragraph(strcat(x44," ",x45," ",x46," ",x47_1," ",x48));
s10.FontFamilyName='Gotham';
s10.HAlign='justify';
add(chap4,s10);
sec4_60 = Section;
k86=Paragraph('La gravedad de siniestro vial en las intersecciones se obtiene multiplicando los factores de riesgo previamente determinados.');
k86.FontFamilyName='Gotham';
k86.HAlign='justify';
add(chap4,k86);
add(sec4_60,Paragraph('Severidad = tipo de intersección'));
add(chap4,sec4_60);
x49='Severidad=';
s11=strcat(x49,x47_1);
add(chap4,s11);

tWavy = Text('Influencia del flujo');
tWavy.Style = {Underline('single')};
add(chap4,tWavy)
x50='El atributo vial flujo vehicular (TDPA) es ';
x51=a.Datos_interfaz_tres.Flujo_vehicular(1,1);
x52='vehículos por día, entonces, el factor de riesgo es';
x53=b.Coeficientes_Mot.Interseccion(1,10);
x53_1=string(x53)
s12=Paragraph(strcat(x50," ",x51," ",x52," ",x53_1));
s12.FontFamilyName='Gotham';
s12.HAlign='justify';
add(chap4,s12);

tWavy = Text('Velocidad de operación (percentil 85)');
tWavy.Style = {Underline('single')};
add(chap4,tWavy)
x54='El atributo vial velocidad de operación (percentil 85) es ';
x55=a.Datos_interfaz_tres.Velocidad_de_operacion_percentil_85(1,1);
x56='para ocupantes de vehículos y motociclistas (caminos divididos, no divididos y rurales, de frente, de salida e intersección), en consecuencia, el factor de riesgo es';
x57=b.Coeficientes_Mot.Interseccion(1,11);
x57_1=string(x57);
x58='(tabla 15).';
s13=Paragraph(strcat(x54," ",x55," ",x56," ",x57_1," ",x58));
s13.FontFamilyName='Gotham';
s13.HAlign='justify';
add(chap4,s13);

sec4_61 = Section;
tWavy = Text('Puntaje por intersecciones');
tWavy.Style = {Underline('single')};
add(chap4,tWavy)
k87=Paragraph('El puntaje por intersecciones se obtiene multiplicando entre sí los factores de riesgos parciales.');
k87.FontFamilyName='Gotham';
k87.HAlign='justify';
add(chap4,k87);
k88=Paragraph('Puntaje = probabilidad x severidad x influencia del flujo x velocidad de operación (percentil 85)');
k88.FontFamilyName='Gotham';
k88.HAlign='justify';
add(chap4,k88);
add(chap4,sec4_61);
x59='Puntaje =';
s14=strcat(x59,x44_1,'x',x47_1,'x',x53_1,'x',x57_1);
add(chap4,s14);
x60=b.Coeficientes_Mot.Interseccion(1,13);
x60_1=string(x60);
s15=strcat(x59,x60_1);
add(chap4,s15);

p15 = Paragraph('f) Acceso a propiedades');
p15.Bold=1;
add(chap4,p15)
sec4_62 = Section;
tWavy = Text('Probabilidad de los atributos viales');
tWavy.Style = {Underline('single')};
add(chap4,tWavy)
k89=Paragraph('Los factores de riesgo, asociados a la probabilidad del siniestro, para motociclistas y acceso a propiedades se describen a continuación: ');
k89.FontFamilyName='Gotham';
k89.HAlign='justify';
add(chap4,k89);
add(chap4,sec4_62);
x1='El atributo vial puntos de acceso a la propiedad es ';
a=load('../mat/Datos_interfaz_tres_proceso');
b=load('../mat/Coeficientes_Mot')
x2= a.Datos_interfaz_tres.Puntos_de_acceso_a_la_propiedad(1,1);
x3=' , entonces, el factor de riesgo es ' 
x4=b.Coeficientes_Mot.Acceso_a_Propiedades(1,1);
x4_1=string(x4);
x5=' (tabla 27)';
s1=strcat(x1," ",x2," ",x3," ",x4_1," ",x5);
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,s1);
x11='El atributo vial vía de servicio es ';
x12=a.Datos_interfaz_tres.Via_de_servicio(1,1);
x13=', entonces, el factor de riesgo es ';
x14=b.Coeficientes_Mot.Acceso_a_Propiedades(1,2);
x14_1=string(x14);
x15='(tabla 28).';
s3=strcat(x11," ",x12," ",x13," ",x14_1," ",x15);
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,s3);
sec4_63 = Section;
add(sec4_63,Paragraph('La probabilidad de siniestro vial en los accesos a propiedades se obtiene multiplicando los factores de riesgo previamente obtenidos.'));
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(sec4_63,Paragraph('Probabilidad = puntos de acceso a la propiedad x tipo de mediana x vía de servicio '));
%%k41.FontFamilyName='Gotham';
%41.HAlign='justify';
%add(chap4,k41);
add(chap4,sec4_63);
x16='Probabilidad = ';
s4=strcat(x16,x4_1,'x',x14_1);
add(chap4,s4);
x17=b.Coeficientes_Mot.Acceso_a_Propiedades(1,6);
x17_1=string(x17);
s5=strcat(x16,x17_1);
add(chap4,s5);

sec4_64 = Section;
tWavy = Text('Severidad de los atributos viales');
tWavy.Style = {Underline('single')};
add(chap4,tWavy)
add(sec4_64,Paragraph('Los factores de riesgo, asociados a la gravedad/severidad del siniestro, para el ocupante del vehículo y acceso a propiedades se describen a continuación:'));
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,sec4_64);
x17='El atributo vial puntos de acceso a la propiedad es';
x18=a.Datos_interfaz_tres.Puntos_de_acceso_a_la_propiedad(1,1);
x19=', entonces, el factor de riesgo es';
x20=b.Coeficientes_Mot.Acceso_a_Propiedades(1,3);
x20_1=string(x20);
x21='(tabla 27).';
s5=strcat(x17," ",x18," ",x19," ",x20_1);
add(chap4,s5);
sec4_65 = Section;
add(sec4_65,Paragraph('La gravedad de siniestro vial en los accesos a propiedades se obtiene multiplicando los factores de riesgo previamente determinados.'));
%k41.FontFamilyName='Gotham';
%%k41.HAlign='justify';
%add(chap4,k41);
add(sec4_65,Paragraph('Severidad = puntos de acceso a la propiedad'));
add(chap4,sec4_65);
x21='Severidad=';
s6=strcat(x21,x20_1);
add(chap4,s6);


tWavy = Text('Influencia del flujo');
tWavy.Style = {Underline('single')};
add(chap4,tWavy)
x22='El atributo vial flujo vehicular (TDPA) es ';
x23=a.Datos_interfaz_tres.Flujo_vehicular(1,1);
x24='vehículos por día, entonces, el factor de riesgo es';
x25=b.Coeficientes_Mot.Acceso_a_Propiedades(1,4);
x25_1=string(x25);
s12=strcat(x22," ",x23," ",x24," ",x25_1);
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,s12);


tWavy = Text('Velocidad de operación (percentil 85)');
tWavy.Style = {Underline('single')};
add(chap4,tWavy)
x54='El atributo vial velocidad de operación (percentil 85) es ';
x55=a.Datos_interfaz_tres.Velocidad_de_operacion_percentil_85(1,1);
x56='para ocupantes de vehículos y motociclistas (caminos divididos, no divididos y rurales, de frente, de salida e intersección), en consecuencia, el factor de riesgo es';
x57=b.Coeficientes_Mot.Acceso_a_Propiedades(1,5);
x57_1=string(x57);
x58='(tabla 15).';
s13=strcat(x54," ",x55," ",x56," ",x57_1," ",x58);
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,s13);

sec4_66 = Section;
tWavy = Text('Puntaje por acceso a propiedades');
tWavy.Style = {Underline('single')};
add(chap4,tWavy)
add(sec4_66,Paragraph('El puntaje por acceso a propiedades se obtiene multiplicando entre sí los factores de riesgos parciales.'));
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(sec4_66,Paragraph('Puntaje = probabilidad x severidad x influencia del flujo x velocidad de operación (percentil 85)'));
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,sec4_66);
x59='Puntaje =';
s14=strcat(x59,x17_1,'x',x20_1,'x',x25_1,'x',x57_1);
add(chap4,s14);
x60=b.Coeficientes_Mot.Acceso_a_Propiedades(1,7);
x60_1=string(x60);
s15=strcat(x59,x60_1)
add(chap4,s15);

p15 = Paragraph('g) A lo largo');
p15.Bold=1;
add(chap4,p15)
sec4_67 = Section;
tWavy = Text('Probabilidad de los atributos viales');
tWavy.Style = {Underline('single')};
add(chap4,tWavy)
add(sec4_67,Paragraph('Los factores de riesgo, asociados a la probabilidad del siniestro, para motociclistas y a lo largo se describen a continuación: '));
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,sec4_67);
x1='El atributo vial infraestructura para motocicletas es';
a=load('../mat/Datos_interfaz_tres_proceso');
b=load('../mat/Coeficientes_Mot')
x2= a.Datos_interfaz_tres.Infraestructura_para_motocicletas(1,1);
x3=' , entonces, el factor de riesgo es ' 
x4=b.Coeficientes_Mot.A_lo_largo(1,1);
x4_1=string(x4);
x5=' (tabla 27)';
s1=strcat(x1," ",x2," ",x3," ",x4_1," ",x5);
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,s1);
sec4_68 = Section;
add(sec4_68,Paragraph('La probabilidad de siniestro vial a lo largo de la vía se obtiene multiplicando los factores de riesgo previamente obtenidos.'));
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(sec4_68,Paragraph('Probabilidad = infraestructura para motocicletas'));
add(chap4,sec4_68);
x6='Probabilidad='
s2=strcat(x6,x4_1);
add(chap4,s2);

sec4_69 = Section;
tWavy = Text('Severidad de los atributos viales');
tWavy.Style = {Underline('single')};
add(chap4,tWavy)
add(sec4_69,Paragraph('Los factores de riesgo, asociados a la gravedad/severidad del siniestro, para motociclistas y a lo largo se describen a continuación: '));
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,sec4_69);
x7='El atributo vial infraestructura para motocicletas es';
a=load('../mat/Datos_interfaz_tres_proceso');
b=load('../mat/Coeficientes_Mot')
x8= a.Datos_interfaz_tres.Infraestructura_para_motocicletas(1,1);
x9=' , entonces, el factor de riesgo es ' 
x10=b.Coeficientes_Mot.A_lo_largo(1,2);
x10_1=string(x4);
x11=' (tabla 27)';
s3=strcat(x7," ",x8," ",x9," ",x10_1," ",x11);
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,s3);
sec4_70 = Section;
add(sec4_70,Paragraph('La gravedad de siniestro vial a lo largo de la vía se obtiene multiplicando los factores de riesgo previamente determinados.'));
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(sec4_70,Paragraph('Severidad = infraestructura para motocicletas'));
add(chap4,sec4_70);
x12='Severidad='
s4=strcat(x12,x10_1);
add(chap4,s4);

tWavy = Text('Influencia del flujo');
tWavy.Style = {Underline('single')};
add(chap4,tWavy)
x13='El atributo vial flujo vehicular (TDPA) es ';
x14='Influencia del flujo externo – flujo predeterminado – Sin TPDA de motocicletas (M/C) por carril'
x15=', entonces, el factor de riesgo es'
a=load('../mat/Datos_interfaz_tres_proceso');
b=load('../mat/Coeficientes_Mot')
x16=b.Coeficientes_Mot.A_lo_largo(1,3);
x16_1=string(x16);
s5=strcat(x13," ",x14," ",x15," ",x16_1);
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,s5);

tWavy = Text('Velocidad de operación (percentil 85)');
tWavy.Style = {Underline('single')};
add(chap4,tWavy)
x17='El atributo vial velocidad de operación (percentil 85) es';
x18=a.Datos_interfaz_tres.Velocidad_de_operacion_percentil_85(1,1);
x19='para ocupantes de vehículos y motociclistas (caminos divididos, no divididos y rurales, de frente, de salida e intersección), en consecuencia, el factor de riesgo es';
x20=b.Coeficientes_Mot.A_lo_largo(1,4);
x20_1=string(x20);
x21='(tabla 15).'
s6=strcat(x17," ",x18," ",x19," ",x20_1);
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,s6);

sec4_71 = Section;
tWavy = Text('Puntaje a lo largo');
tWavy.Style = {Underline('single')};
add(chap4,tWavy)
add(sec4_71,Paragraph('El puntaje a lo largo se obtiene multiplicando entre sí los factores de riesgos parciales.'));
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(sec4_71,Paragraph('Puntaje = probabilidad x severidad x influencia del flujo x velocidad de operación (percentil 85)'));
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,sec4_71);
x22='Puntaje=';
s7=strcat(x22,x4_1,'x',x10_1,'x',x16_1,'x',x20_1);
add(chap4,s7);
x23=b.Coeficientes_Mot.A_lo_largo(1,5);
x23_1=string(x23);
s8=strcat(x22,x23_1);
add(chap4,s8);

p16 = Paragraph('SRS para ocupante del vehículo y clasificación por estrellas')
p16.Bold=1;
add(chap4,p16);
sec4_72 = Section;
add(sec4_72,Paragraph('Las puntuaciones para la clasificación por estrellas (SRS) para el ocupante del vehículo es la suma de los puntajes previos por cada tipo de siniestro.'));
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(sec4_72,Paragraph('Puntaje de clasificación por estrellas (SRS) = salida del camino (lado del conductor) + salida del camino (lado del copiloto) + choque frontal (pérdida de control) + choque frontal (adelantamiento) + siniestros en intersecciones + siniestros en acceso a propiedades.'));
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,sec4_72);
x62='Puntaje de clasificación por estrellas (SRS) = ';
b=load('../mat/Coeficientes_Mot');
X1=b.Coeficientes_Mot.Salida_lado_conductor(1,17);
X2=b.Coeficientes_Mot.Salida_lado_copiloto(1,16);
X3=b.Coeficientes_Mot.Perdida_de_control(1,14);
X4=b.Coeficientes_Mot.Adelantamiento(1,9);
X5=b.Coeficientes_Mot.Interseccion(1,13);
X6=b.Coeficientes_Mot.Acceso_a_Propiedades(1,7);
X7=b.Coeficientes_Mot.A_lo_largo(1,5);
X1_1=string(X1);
X2_1=string(X2);
X3_1=string(X3);
X4_1=string(X4);
X5_1=string(X5);
X6_1=string(X6);
X7_1=string(X7);
s16=strcat(x62,X1_1,'+',X2_1,'+',X3_1,'+',X4_1,'+',X5_1,'+',X6_1,'+',X7_1);
add(chap4,s16);
x63=b.Coeficientes_Mot.A_lo_largo(1,6);
x63_1=string(x63);
s17=strcat(x62,x63_1);
add(chap4,s17);
a=load('../mat/Datos_interfaz_tres_proceso');
x64='Al SRS de';
x65='le corresponde';
x66=a.Datos_interfaz_tres.Motociclista(1,1);
x66_1=string(x66);
x67='estrellas para motocicletas (tabla 37).';
s16=strcat(x64," ",x63_1," ",x65," ",x66_1," ",x67);
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,s16);

%CLASIFICACION POR ESTRELLAS PARA CICLISTAS
sec4_3 = Section;
sec4_3.Title = 'Clasificación por Estrellas para ciclistas';
add(sec4_3,Paragraph('El SRS representa el riesgo relativo de fallecimiento o de lesiones serias para un usuario de carretera en particular. Para calcular las puntuaciones para la clasificación por estrellas (Star Rating Scores - SRS), es necesario estimar las puntuaciones parciales por tipo de siniestro. Los siniestros que intervienen son a lo largo de la vía, salida del camino y siniestros en intersecciones. ')); 
add(chap4,sec4_3);

p10 = Paragraph('a) A lo largo');
p10.Bold=1;
add(chap4,p10)
sec4_74 = Section;
tWavy = Text(' Probabilidad de los atributos viales');
tWavy.Style = {Underline('single')};
add(chap4,tWavy);
add(sec4_74,Paragraph('Los factores de riesgo, asociados a la probabilidad del siniestro, para ciclistas y a lo largo se describen a continuación: '));
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,sec4_74);
a=load('../mat/Datos_interfaz_tres_proceso');
b=load('../mat/Coeficientes_Ci');
x1='El atributo vial infraestructura para bicicletas es';
x2=a.Datos_interfaz_tres.Infraestructura_para_bicicletas(1,1);
x3=', entonces, el factor de riesgo es';
x4=b.Coeficientes_Ci.A_lo_largo(1,1);
x4_1=string(x4);
x5='(tabla 30)';
s1=strcat(x1," ",x2," ",x3," ",x4_1," ",x5);
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,s1);
x6='El atributo vial curvatura es ';
x7=a.Datos_interfaz_tres.Calidad_de_la_curva(1,1);
x8=', por lo tanto, el factor de riesgo es';
x9=b.Coeficientes_Ci.A_lo_largo(1,2);
x9_1=string(x9);
x10='(tabla 3).';
s2=strcat(x6," ",x7," ",x8," ",x9_1," ",x10);
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,s2);
x11='El atributo vial calidad de la curva es';
x12=a.Datos_interfaz_tres.Calidad_de_la_curva(1,1);
x13=', entonces, el factor de riesgo es';
x14=b.Coeficientes_Ci.A_lo_largo(1,3);
x14_1=string(x14);
x15='(tabla 4).';
s3=strcat(x11," ",x12," ",x13," ",x14_1," ",x15);
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,s3);
x16='El atributo vial distancia de visibilidad es';
x17=a.Datos_interfaz_tres.Distancia_de_visibilidad(1,1);
x18=', en consecuencia, el factor de riesgo es';
x19=b.Coeficientes_Ci.A_lo_largo(1,4);
x19_1=string(x19);
x20='(tabla 24).';
s4=strcat(x16," ",x17," ",x18," ",x19_1," ",x20);
%k41.FontFamilyName='Gotham';
%%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,s4);
x21='El atributo vial ancho del carril es'
x22=a.Datos_interfaz_tres.Ancho_de_carril(1,1);
x23='en una zona'
x24=a.Datos_interfaz_tres.Tipo_de_area(1,1);
x25=', entonces, el factor de riesgo es'
x26=b.Coeficientes_Ci.A_lo_largo(1,5);
x26_1=string(x26);
x27='(tabla 2).';
s5=strcat(x21," ",x22," ",x23," ",x24," ",x25," ",x26_1," ",x27);
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,s5);
x28='El atributo vial delineación es   ';
x29=a.Datos_interfaz_tres.Delineacion(1,1);
x30='  , en consecuencia, el factor de riesgo es   ';
x31=b.Coeficientes_Ci.A_lo_largo(1,6);
x31_1=string(x21);
x32='  (tabla 5).';
s6=strcat(x28," ",x29," ",x30," ",x31_1," ",x32);
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,s6);
x33='El atributo vial pendiente es ';
x34=a.Datos_interfaz_tres.Pendiente(1,1);
x35=' , entonces, el factor de riesgo es ';
x36=b.Coeficientes_Ci.A_lo_largo(1,7); 
x36_1=string(x36);
x37=' (tabla 8).';
s7=strcat(x33," ",x34," ",x35," ",x36_1," ",x37);
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,s7);
x38='El atributo vial condición de la vía es   ';
x39=a.Datos_interfaz_tres.Estado_de_la_superficie_de_la_via(1,1);
x40='  , por lo tanto, el factor de riesgo es     ';
x41=b.Coeficientes_Ci.A_lo_largo(1,8);
x41_1=string(x31);
x42=' (tabla 7).';
s8=strcat(x38," ",x39," ",x40," ",x41_1," ",x42);
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,s8);
x43='El atributo vial gestión de la velocidad/calmante del tráfico es';
x44=a.Datos_interfaz_tres.Gestion_de_la_velocidad_calmante_del_trafico(1,1);
x45=', entonces, el factor de riesgo es';
x46=b.Coeficientes_Ci.A_lo_largo(1,9);
x46_1=string(x41);
x47='(tabla 26).';
s9=strcat(x43," ",x44," ",x45," ",x46_1," ",x47);
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,s9);
x48='El atributo vial bandas sonoras en el espaldón es   ';
x49=a.Datos_interfaz_tres.Bandas_sonoras_en_el_espaldon(1,1);
x50='  , con lo que el factor de riesgo es    ';
x51=b.Coeficientes_Ci.A_lo_largo(1,10);
x51_1=string(x26)
x52='  (tabla 6).';
s10=strcat(x48," ",x49," ",x50," ",x51_1," ",x52);
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,s10);
x53='El atributo vial estacionamiento para vehículos es'
x54=a.Datos_interfaz_tres.Estacionamiento_para_vehiculos(1,1);
x55=', entonces, el factor de riesgo es'
x56=b.Coeficientes_Ci.A_lo_largo(1,11);
x56_1=string(x56);
x57='(tabla 31).';
s11=strcat(x53," ",x54," ",x55," ",x56_1," ",x57);
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,s11);
x58='El atributo vial resistencia al deslizamiento es';
x59=a.Datos_interfaz_tres.Resistencia_al_deslizamiento(1,1);
x60=', en consecuencia, el factor de riesgo es';
x61=b.Coeficientes_Ci.A_lo_largo(1,12);
x61_1=string(x61);
x62='(tabla 9).';
s12=strcat(x58," ",x59," ",x60," ",x61_1," ",x62);
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,s12);
x63='El atributo vial alumbrado público es';
x64=a.Datos_interfaz_tres.Alumbrado_publico(1,1);
x65=', en consecuencia, el factor de riesgo es';
x66=b.Coeficientes_Ci.A_lo_largo(1,13);
x66_1=string(x66);
x67='(tabla 23).';
s13=strcat(x63," ",x64," ",x65," ",x66_1," ",x67);
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,%k41);
add(chap4,s13);

sec4_75 = Section;
add(sec4_75,Paragraph('La probabilidad de siniestro vial a lo largo de la vía se obtiene multiplicando los factores de riesgo previamente obtenidos.'));
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(sec4_75,Paragraph('Probabilidad = infraestructura para bicicletas x curvatura x calidad de la curva x distancia de visibilidad x ancho de carril x delineación x pendiente x condición de la vía x gestión de la velocidad/calmante del tráfico x estacionamiento para vehículos x resistencia al deslizamiento x alumbrado público'));
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,sec4_75);
x68='Probabilidad=';
s14=strcat(x68,x4_1,'x',x9_1,'x',x14_1,'x',x19_1,'x',x26_1,'x',x31_1,'x',x36_1,'x',x41_1,'x',x46_1,'x',x51_1,'x',x56_1,'x',x61_1,'x',x66_1);
add(chap4,s14);
x69=b.Coeficientes_Ci.A_lo_largo(1,17);
x69_1=string(x69);
s15=strcat(x68,x69_1);
add(chap4,s15);

sec4_76 = Section;
tWavy = Text(' Severidad de los atributos viales');
tWavy.Style = {Underline('single')};
add(chap4,tWavy);
add(sec4_76,Paragraph('Los factores de riesgo, asociados a la gravedad/severidad del siniestro, para ciclistas y a lo largo se describen a continuación: '));
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,sec4_76);
x70='El atributo vial infraestructura para bicicletas es'
x71=a.Datos_interfaz_tres.Infraestructura_para_bicicletas(1,1);
x72=', entonces, el factor de riesgo es';
x73=b.Coeficientes_Ci.A_lo_largo(1,14);
x73_1=string(x73);
x74='(tabla 30).';
s16=strcat(x70," ",x71," ",x72," ",x73_1," ",x74);
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,s16);
sec4_77 = Section;
add(sec4_77,Paragraph('La gravedad de siniestro vial a lo largo de la vía se obtiene multiplicando los factores de riesgo previamente determinados.'));
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(sec4_77,Paragraph('Severidad = infraestructura para bicicletas'));
add(chap4,sec4_77);
x75='Severidad=';
s17=strcat(x75,x73_1);
add(chap4,s17);

tWavy = Text(' Influencia del flujo');
tWavy.Style = {Underline('single')};
add(chap4,tWavy);
x76='El atributo vial flujo vehicular (TDPA) es ';
x77=a.Datos_interfaz_tres.Flujo_vehicular(1,1);
x78='vehículos por día, entonces, el factor de riesgo es ';
x79=b.Coeficientes_Ci.A_lo_largo(1,15);
x79_1=string(x62);
s18=strcat(x76," ",x77," ",x78," ",x79_1);
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,s18);

tWavy = Text(' Velocidad de operación (percentil 85)');
tWavy.Style = {Underline('single')};
add(chap4,tWavy);
x80='El atributo vial velocidad de operación (percentil 85) es ';
x81=a.Datos_interfaz_tres.Velocidad_de_operacion_percentil_85 (1,1);
x82=' para ocupantes de ciclistas (caminos divididos, no divididos y rurales, de frente, de salida e intersección), en consecuencia, el factor de riesgo es ';
x83=b.Coeficientes_Ci.A_lo_largo(1,16);
x83_1=string(x70);
x84='(tabla 15).';
s19=strcat(x80," ",x81," ",x82," ",x83_1," ",x84);
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,s19);

sec4_78 = Section;
tWavy = Text(' Puntaje a lo largo de la vía');
tWavy.Style = {Underline('single')};
add(chap4,tWavy);
add(sec4_78,Paragraph('El puntaje a lo largo de la vía se obtiene multiplicando entre sí los factores de riesgos parciales.'));
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(sec4_78,Paragraph('Puntaje = probabilidad x severidad x influencia del flujo x velocidad de operación (percentil 85)'));
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,sec4_78);
x85='Puntaje=';
s20=strcat(x85,'x',x69_1,'x',x73_1,'x',x79_1,'x',x83_1);
add(chap4,s20);
x86=b.Coeficientes_Ci.A_lo_largo(1,18);
x86_1=string(x86);
s21=strcat(x85,x86_1);
add(chap4,s21);

p10 = Paragraph('b) Salida del camino');
p10.Bold=1;
add(chap4,p10)
sec4_79 = Section;
tWavy = Text(' Probabilidad de los atributos viales');
tWavy.Style = {Underline('single')};
add(chap4,tWavy);
add(sec4_79,Paragraph('Los factores de riesgo, asociados a la probabilidad del siniestro, para ciclistas y salida del camino se describen a continuación: '));
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,sec4_79);
a=load('../mat/Datos_interfaz_tres_proceso');
b=load('../mat/Coeficientes_Ci');

x21='El atributo vial ancho del carril es'
x22=a.Datos_interfaz_tres.Ancho_de_carril(1,1);
x23='en una zona'
x24=a.Datos_interfaz_tres.Tipo_de_area(1,1);
x25=', entonces, el factor de riesgo es'
x26=b.Coeficientes_Ci.Salida_camino(1,1);
x26_1=string(x26);
x27='(tabla 2).';
s5=strcat(x21," ",x22," ",x23," ",x24," ",x25," ",x26_1," ",x27);
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,s5);
x6='El atributo vial curvatura es ';
x7=a.Datos_interfaz_tres.Calidad_de_la_curva(1,1);
x8=', por lo tanto, el factor de riesgo es';
x9=b.Coeficientes_Ci.Salida_camino(1,2);
x9_1=string(x9);
x10='(tabla 3).';
s2=strcat(x6," ",x7," ",x8," ",x9_1," ",x10);
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,s2);
x11='El atributo vial calidad de la curva es';
x12=a.Datos_interfaz_tres.Calidad_de_la_curva(1,1);
x13=', entonces, el factor de riesgo es';
x14=b.Coeficientes_Ci.Salida_camino(1,3);
x14_1=string(x14);
x15='(tabla 4).';
s3=strcat(x11," ",x12," ",x13," ",x14_1," ",x15);
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,s3);
x28='El atributo vial delineación es   ';
x29=a.Datos_interfaz_tres.Delineacion(1,1);
x30='  , en consecuencia, el factor de riesgo es   ';
x31=b.Coeficientes_Ci.Salida_camino(1,4);
x31_1=string(x21);
x32='  (tabla 5).';
s6=strcat(x28," ",x29," ",x30," ",x31_1," ",x32);
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,s6);
x63='El atributo vial alumbrado público es';
x64=a.Datos_interfaz_tres.Alumbrado_publico(1,1);
x65=', en consecuencia, el factor de riesgo es';
x66=b.Coeficientes_Ci.Salida_camino(1,5);
x66_1=string(x66);
x67='(tabla 23).';
s13=strcat(x63," ",x64," ",x65," ",x66_1," ",x67);
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,s13);
x38='El atributo vial condición de la vía es   ';
x39=a.Datos_interfaz_tres.Estado_de_la_superficie_de_la_via(1,1);
x40='  , por lo tanto, el factor de riesgo es     ';
x41=b.Coeficientes_Ci.A_lo_largo(1,6);
x41_1=string(x31);
x42=' (tabla 7).';
s8=strcat(x38," ",x39," ",x40," ",x41_1," ",x42);
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,s8);
x33='El atributo vial pendiente es ';
x34=a.Datos_interfaz_tres.Pendiente(1,1);
x35=' , entonces, el factor de riesgo es ';
x36=b.Coeficientes_Ci.Salida_camino(1,7); 
x36_1=string(x36);
x37=' (tabla 8).';
s7=strcat(x33," ",x34," ",x35," ",x36_1," ",x37);
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,s7);
x58='El atributo vial resistencia al deslizamiento es';
x59=a.Datos_interfaz_tres.Resistencia_al_deslizamiento(1,1);
x60=', en consecuencia, el factor de riesgo es';
x61=b.Coeficientes_Ci.Salida_camino(1,8);
x61_1=string(x61);
x62='(tabla 9).';
s12=strcat(x58," ",x59," ",x60," ",x61_1," ",x62);
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,s12);

sec4_80 = Section;
add(sec4_80,Paragraph('La probabilidad de siniestro vial salida del camino se obtiene multiplicando los factores de riesgo previamente obtenidos.'));
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(sec4_80,Paragraph('Probabilidad = ancho del carril x curvatura x calidad de la curva x delineación x alumbrado pública x condición de la vía x pendiente x resistencia al deslizamiento'));
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,sec4_80);
x68='Probabilidad=';
s14=strcat(x68,x26_1,'x',x9_1,'x',x14_1,'x',x31_1,'x',x66_1,'x',x41_1,'x',x36_1,'x',x61_1);
add(chap4,s14);
x69=b.Coeficientes_Ci.Salida_camino(1,15);
x69_1=string(x69);
s15=strcat(x68,x69_1);
add(chap4,s15);

sec4_81 = Section;
tWavy = Text(' Severidad de los atributos viales');
tWavy.Style = {Underline('single')};
add(chap4,tWavy);
add(sec4_81,Paragraph('Los factores de riesgo, asociados a la gravedad/severidad del siniestro, para ciclistas y salida del camino se describen a continuación: '));
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,sec4_81);
x70='El atributo vial severidad lateral – distancia al objeto (lado del conductor) es';
x71=a.Datos_interfaz_tres.Severidad_lateral_distancia_al_objeto_lado_del_conductor(1,1);
x72=', por lo que el factor de riesgo es';
x73=b.Coeficientes_Ci.Salida_camino(1,9);
x73_1=string(x73);
x74='(tabla 10).';
s16=strcat(x70," ",x71," ",x72," ",x73_1," ",x74);
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,s16);
x75='El atributo vial severidad lateral – objeto (lado del conductor) es'
x76=a.Datos_interfaz_tres.Severidad_lateral_objeto_lado_del_conductor(1,1);
x77=', en consecuencia, el factor de riesgo es';
x78=b.Coeficientes_Ci.Salida_camino(1,10);
x78_1=string(x78);
x79='(tabla 11).';
s17=strcat(x75," ",x76," ",x77," ",x78_1," ",x79);
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,s17);
x80='El atributo vial severidad lateral – distancia al objeto (lado del copiloto) es';
x81=a.Datos_interfaz_tres.Severidad_lateral_distancia_al_objeto_lado_del_copiloto(1,1);
x82=', por lo que el factor de riesgo es';
x83=b.Coeficientes_Ci.Salida_camino(1,11);
x83_1=string(x83);
x84='(tabla 10).';
s18=strcat(x80," ",x81," ",x82," ",x83_1," ",x84);
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,s18);
x85='El atributo vial severidad lateral – objeto (lado del copiloto) es'
x86=a.Datos_interfaz_tres.Severidad_lateral_objeto_lado_del_copiloto(1,1);
x87=', en consecuencia, el factor de riesgo es';
x88=b.Coeficientes_Ci.Salida_camino(1,12);
x88_1=string(x88);
x89='(tabla 11).';
s19=strcat(x85," ",x86," ",x87," ",x88_1," ",x89);
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,s19);

sec4_82 = Section;
add(sec4_82,Paragraph('La gravedad de siniestro vial en la salida del camino se obtiene multiplicando los factores de riesgo previamente determinados.'));
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(sec4_82,Paragraph('Severidad = ((severidad lateral – distancia al objeto (lado del conductor) x severidad lateral – objeto (lado del conductor)) + (severidad lateral – distancia al objeto (lado del copiloto) x severidad lateral – objeto (lado del copiloto)))/2 '));
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,sec4_82);
x75='Severidad=';
s20=strcat(x75,'((',x73_1,'x',x78_1,')','+','(',x83_1,'x',x88_1,'))','/','2');
add(chap4,s20);
x76=b.Coeficientes_Ci.Salida_camino(1,16);
x76_1=string(x76);
s21=strcat(x75,x76_1);
add(chap4,s21);

tWavy = Text(' Influencia del flujo');
tWavy.Style = {Underline('single')};
add(chap4,tWavy);
x77='El atributo vial flujo vehicular (TDPA) es ';
x78=a.Datos_interfaz_tres.Flujo_vehicular(1,1);
x79='vehículos por día, entonces, el factor de riesgo es ';
x80=b.Coeficientes_Ci.Salida_camino(1,13);
x80_1=string(x80);
s22=strcat(x77," ",x78," ",x79," ",x80_1);
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,s22);

tWavy = Text(' Velocidad de operación (percentil 85)');
tWavy.Style = {Underline('single')};
add(chap4,tWavy);
x81='El atributo vial velocidad de operación (percentil 85) es ';
x82=a.Datos_interfaz_tres.Velocidad_de_operacion_percentil_85 (1,1);
x83=' para ocupantes de ciclistas (caminos divididos, no divididos y rurales, de frente, de salida e intersección), en consecuencia, el factor de riesgo es ';
x84=b.Coeficientes_Ci.Salida_camino(1,14);
x84_1=string(x84);
x85='(tabla 15).';
s23=strcat(x81," ",x82," ",x83," ",x84_1," ",x85);
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,s23);

sec4_83 = Section;
tWavy = Text(' Puntaje por salida del camino');
tWavy.Style = {Underline('single')};
add(chap4,tWavy);
add(sec4_83,Paragraph('El puntaje por salida del camino se obtiene multiplicando entre sí los factores de riesgos parciales.'));
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(sec4_83,Paragraph('Puntaje = probabilidad x severidad x influencia del flujo x velocidad de operación (percentil 85)'));
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,sec4_83);
x86='Puntaje=';
s24=strcat(x86,x20_1,'x',x76_1,'x',x80_1,'x',x84_1);
add(chap4,s24);
x87=b.Coeficientes_Ci.Salida_camino(1,17);
x87_1=string(x87);
s25=strcat(x86,x87_1);
add(chap4,s25);

p14 = Paragraph('c) Interseccion');
p14.Bold=1;
add(chap4,p14)
sec4_84 = Section;
tWavy = Text('Probabilidad de los atributos viales');
tWavy.Style = {Underline('single')};
add(chap4,tWavy)
add(sec4_84,Paragraph('Los factores de riesgo, asociados a la probabilidad del siniestro, para ciclistas e intersecciones se describen a continuación: '));
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,sec4_84);
x1='El atributo vial tipo de intersección es ';
a=load('../mat/Datos_interfaz_tres_proceso');
b=load('../mat/Coeficientes_Ci')
x2= a.Datos_interfaz_tres.Tipo_de_interseccion(1,1);
x3=' , entonces, el factor de riesgo es ' 
x4=b.Coeficientes_Ci.Interseccion(1,1);
x4_1=string(x4);
x5=' (tabla 20)';
s1=strcat(x1," ",x2," ",x3," ",x4_1," ",x5);
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,s1);
x6='El atributo vial calidad de la intersección es ';
x7= a.Datos_interfaz_tres.Calidad_de_la_interseccion(1,1);
x8=' , por lo tanto, el factor de riesgo es '
x9=b.Coeficientes_Ci.Interseccion(1,2);
x9_1=string(x9);
x10=' (tabla 22).';
s2=strcat(x6," ",x7," ",x8," ",x9_1," ",x10);
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,s2);
x13='El atributo vial pendiente es ';
x14=a.Datos_interfaz_tres.Pendiente(1,1);
x15=' , entonces, el factor de riesgo es ';
x16=b.Coeficientes_Ci.Interseccion(1,3);
x16_1=string(x16);
x17=' (tabla 8).';
s3=strcat(x13," ",x14," ",x15," ",x16_1," ",x17);
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,s3);
x18='El atributo vial resistencia al deslizamiento es ';
x19=a.Datos_interfaz_tres.Resistencia_al_deslizamiento(1,1);
x20=', en consecuencia, el factor de riesgo es'
x21=b.Coeficientes_Ci.Interseccion(1,5);
x21_1=string(x26);
x22='para ocupante del vehículo, salida del camino e intersección (tabla 9).';
s4=strcat(x18," ",x19," ",x20," ",x21_1," ",x22);
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,s4);
x23='El atributo vial infraestructura para bicicletas es'
x24=a.Datos_interfaz_tres.Infraestructura_para_bicicletas(1,1);
x25=', entonces, el factor de riesgo es';
x26=b.Coeficientes_Ci.Interseccion(1,5);
x26_1=string(x26);
x27='(tabla 30)';
s5=strcat(x23," ",x24," ",x25," ",x26_1," ",x27);
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,s5);
x28='El atributo vial alumbrado público es ';
x29=a.Datos_interfaz_tres.Alumbrado_publico(1,1);
x30=' , en consecuencia, el factor de riesgo es ';
x31=b.Coeficientes_Ci.Interseccion(1,6);
x31_1=string(x21);
x32=' (tabla 23).';
s6=strcat(x28," ",x29," ",x30," ",x31_1," ",x32);
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,s6);
x33='El atributo vial distancia de visibilidad es ';
x34=a.Datos_interfaz_tres.Distancia_de_visibilidad(1,1);
x35=', entonces, el factor de riesgo es ';
x36=b.Coeficientes_Ci.Interseccion(1,7);
x36_1=string(x31);
x37='(tabla 24).';
s7=strcat(x33," ",x34," ",x35," ",x36_1," ",x37);
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,s7);
x38='El atributo vial canalización de la intersección es ';
x39=a.Datos_interfaz_tres.Canalizacion_de_la_interseccion(1,1);
x40=', por lo tanto, el factor de riesgo es';
x41=b.Coeficientes_Ci.Interseccion(1,8);
x41_1=string(x36);
x42='(tabla 25).';
s8=strcat(x38," ",x39," ",x40," ",x41_1," ",x42);
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,s8);
x43='El atributo vial gestión de la velocidad/calmante del tráfico es';
x44=a.Datos_interfaz_tres.Gestion_de_la_velocidad_calmante_del_trafico(1,1);
x45=', entonces, el factor de riesgo es';
x46=b.Coeficientes_Ci.Interseccion(1,9);
x46_1=string(x41);
x47='(tabla 26).';
s9=strcat(x43," ",x44," ",x45," ",x46_1," ",x47);
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,s9);
x48='El atributo infraestructura para cruce peatonal - Vía inspeccionada es'
x49=a.Datos_interfaz_tres.Infraestructura_de_cruce_peatonal_en_el_tramo_analizado(1,1);
x50=', entonces, el factor de riesgo es';
x51=b.Coeficientes_Ci.Interseccion(1,10);
x51_1=string(x51);
x52='para supervisor en el cruce de  zona escolar';
x53=a.Datos_interfaz_tres.Supervisor_en_el_cruce_de_zona_escolar(1,1);
x54='(tabla 32).';
s10=strcat(x48," ",x49," ",x50," ",x51_1," ",x52," ",x53," ",x54);
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,s10);

sec4_85 = Section;
add(sec4_85,Paragraph('La probabilidad de siniestro vial en las intersecciones se obtiene multiplicando los factores de riesgo previamente obtenidos.'));
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(sec4_85,Paragraph('Probabilidad = tipo de intersección x calidad de la intersección x pendiente x resistencia al deslizamiento x instalación para bicicletas x alumbrado público x distancia de visibilidad x canalización de la intersección x gestión de la velocidad/calmante del tráfico x infraestructura para cruce peatonal - Vía inspeccionada'));
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,sec4_85);
x43='Probabilidad =';
s11=strcat(x43,x4_1,'x',x9_1,'x',x16_1,'x',x21_1,'x',x26_1,'x',x31_1,'x',x36_1,'x',x41_1,'x',x46_1,'x',x51_1);
add(chap4,s11);
x44=b.Coeficientes_Ci.Interseccion(1,14);
x44_1=string(x44);
s12=strcat(x43,x44_1);
add(chap4,s12);

sec4_86 = Section;
tWavy = Text('Severidad de los atributos viales');
tWavy.Style = {Underline('single')};
add(chap4,tWavy)
add(sec4_86,Paragraph('Los factores de riesgo, asociados a la gravedad/severidad del siniestro, para ciclistas e intersecciones se describen a continuación: '));
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,sec4_86);
x45='El atributo vial tipo de intersección es';
x46=a.Datos_interfaz_tres.Tipo_de_interseccion(1,1);
x47=', entonces, el factor de riesgo es';
x48=b.Coeficientes_Ci.Interseccion(1,11);
x48_1=string(x48);
x49='tabla(20).';
s13=strcat(x45," ",x46," ",x47," ",x48_1," ",x49);
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,s13);
sec4_87 = Section;
add(sec4_87,Paragraph('La gravedad de siniestro vial en las intersecciones se obtiene multiplicando los factores de riesgo previamente determinados.'));
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(sec4_87,Paragraph('Severidad = tipo de intersección'));
add(chap4,sec4_87);
x50='Severidad=';
s14=strcat(x50,x48_1);
add(chap4,s14);

tWavy = Text('Influencia del flujo');
tWavy.Style = {Underline('single')};
add(chap4,tWavy)
x51='El atributo vial flujo vehicular (TDPA) es ';
x52=a.Datos_interfaz_tres.Flujo_vehicular(1,1);
x53='vehículos por día, entonces, el factor de riesgo es';
x54=b.Coeficientes_Ci.Interseccion(1,12);
x54_1=string(x54)
s15=strcat(x51," ",x52," ",x53," ",x54_1);
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,s15);

tWavy = Text('Velocidad de operación (percentil 85)');
tWavy.Style = {Underline('single')};
add(chap4,tWavy)
x55='El atributo vial velocidad de operación (percentil 85) es ';
x56=a.Datos_interfaz_tres.Velocidad_de_operacion_percentil_85(1,1);
x57='para ocupantes de vehículos y motociclistas (caminos divididos, no divididos y rurales, de frente, de salida e intersección), en consecuencia, el factor de riesgo es';
x58=b.Coeficientes_Ci.Interseccion(1,13);
x58_1=string(x58);
x59='(tabla 15).';
s16=strcat(x55," ",x56," ",x57," ",x58_1," ",x59);
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,s16);

sec4_88 = Section;
tWavy = Text('Puntaje por intersecciones');
tWavy.Style = {Underline('single')};
add(chap4,tWavy)
add(sec4_88,Paragraph('El puntaje por intersecciones se obtiene multiplicando entre sí los factores de riesgos parciales.'));
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(sec4_88,Paragraph('Puntaje = probabilidad x severidad x influencia del flujo x velocidad de operación (percentil 85)'));
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,sec4_88);
x60='Puntaje =';
s17=strcat(x60,x44_1,'x',x48_1,'x',x54_1,'x',x58_1);
add(chap4,s17);
x61=b.Coeficientes_Ci.Interseccion(1,15);
x61_1=string(x61);
s17=strcat(x60,x61_1);

add(chap4,s17);

p16 = Paragraph('SRS para ciclistas y clasificación por estrellas')
p16.Bold=1;
add(chap4,p16);
sec4_89 = Section;
add(sec4_89,Paragraph('Las puntuaciones para la clasificación por estrellas (SRS) para ciclistas es la suma de los puntajes previos por cada tipo de siniestro.'));
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(sec4_89,Paragraph('Puntaje de clasificación por estrellas (SRS) = a lo largo + salida del camino  +   intersecciones '));
%k41.FontFamilyName='Gotham';
%k41.HAlign='justify';
%add(chap4,k41);
add(chap4,sec4_89);
x62='Puntaje de clasificación por estrellas (SRS) = ';
b=load('../mat/Coeficientes_Ci');
X1=b.Coeficientes_Ci.A_lo_largo(1,18);
X2=b.Coeficientes_Ci.Salida_camino(1,17);
X3=b.Coeficientes_Ci.Interseccion(1,15);
X1_1=string(X1);
X2_1=string(X2);
X3_1=string(X3);
s18=strcat(x62,X1_1,'+',X2_1,'+',X3_1);
add(chap4,s18);
x63=b.Coeficientes_Ci.Interseccion(1,16);
x63_1=string(x63);
s19=strcat(x62,x63_1);
add(chap4,s19);
a=load('../mat/Datos_interfaz_tres_proceso');
x64='Al SRS de';
x65='le corresponde';
x66=a.Datos_interfaz_tres.Ciclista(1,1);
x66_1=string(x66);
x67='estrellas para ciclistas (tabla 37).';
s20=strcat(x64," ",x63_1," ",x65," ",x66_1," ",x67);
add(chap4,s20);

%CLASIFICACION POR ESTRELLAS PARA PEATONES
sec4_4 = Section;
sec4_4.Title = 'Clasificación por Estrellas para peatones';

add(sec4_4,Paragraph('El SRS representa el riesgo relativo de fallecimiento o de lesiones serias para un usuario de carretera en particular. Para calcular las puntuaciones para la clasificación por estrellas (Star Rating Scores - SRS), es necesario estimar las puntuaciones parciales por tipo de siniestro. Los siniestros que intervienen son a lo largo de la vía (lado del conductor), a lo largo de la vía (lado del copiloto), siniestros en el cruce peatonal (tramo analizado) y siniestros en el cruce peatonal (intersección). ')); 
add(chap4,sec4_4);

p10 = Paragraph('a) A lo largo (lado del conductor)');
p10.Bold=1;
add(chap4,p10)
sec4_91 = Section;
tWavy = Text(' Probabilidad de los atributos viales');
tWavy.Style = {Underline('single')};
add(chap4,tWavy);
add(sec4_91,Paragraph('Los factores de riesgo, asociados a la probabilidad del siniestro, para peatones y a lo largo se describen a continuación: '));
add(chap4,sec4_91);
a=load('../mat/Datos_interfaz_tres_proceso');
b=load('../mat/Coeficientes_Pe');
x1='El atributo vial acera (lado del conductor) es ';
x2=a.Datos_interfaz_tres.Acera_lado_del_conductor(1,1);
x3=', entonces, el factor de riesgo es';
x4=b.Coeficientes_Pe.A_lo_largo_conductor(1,1);
x4_1=string(x4);
x5='(tabla 33)';
s1=strcat(x1," ",x2," ",x3," ",x4_1," ",x5);
add(chap4,s1);
x6='El atributo vial curvatura es ';
x7=a.Datos_interfaz_tres.Calidad_de_la_curva(1,1);
x8=', por lo tanto, el factor de riesgo es';
x9=b.Coeficientes_Pe.A_lo_largo_conductor(1,2);
x9_1=string(x9);
x10='(tabla 3).';
s2=strcat(x6," ",x7," ",x8," ",x9_1," ",x10);
add(chap4,s2);
x11='El atributo vial calidad de la curva es';
x12=a.Datos_interfaz_tres.Calidad_de_la_curva(1,1);
x13=', entonces, el factor de riesgo es';
x14=b.Coeficientes_Pe.A_lo_largo_conductor(1,3);
x14_1=string(x14);
x15='(tabla 4).';
s3=strcat(x11," ",x12," ",x13," ",x14_1," ",x15);
add(chap4,s3);
x16='El atributo vial distancia de visibilidad es';
x17=a.Datos_interfaz_tres.Distancia_de_visibilidad(1,1);
x18=', en consecuencia, el factor de riesgo es';
x19=b.Coeficientes_Pe.A_lo_largo_conductor(1,4);
x19_1=string(x19);
x20='(tabla 24).';
s4=strcat(x16," ",x17," ",x18," ",x19_1," ",x20);
add(chap4,s4);
x21='El atributo vial ancho del carril es'
x22=a.Datos_interfaz_tres.Ancho_de_carril(1,1);
x23='en una zona'
x24=a.Datos_interfaz_tres.Tipo_de_area(1,1);
x25=', entonces, el factor de riesgo es'
x26=b.Coeficientes_Pe.A_lo_largo_conductor(1,5);
x26_1=string(x26);
x27='(tabla 2).';
s5=strcat(x21," ",x22," ",x23," ",x24," ",x25," ",x26_1," ",x27);
add(chap4,s5);
x28='El atributo vial delineación es   ';
x29=a.Datos_interfaz_tres.Delineacion(1,1);
x30='  , en consecuencia, el factor de riesgo es   ';
x31=b.Coeficientes_Pe.A_lo_largo_conductor(1,6);
x31_1=string(x21);
x32='  (tabla 5).';
s6=strcat(x28," ",x29," ",x30," ",x31_1," ",x32);
add(chap4,s6);
x33='El atributo vial pendiente es ';
x34=a.Datos_interfaz_tres.Pendiente(1,1);
x35=' , entonces, el factor de riesgo es ';
x36=b.Coeficientes_Pe.A_lo_largo_conductor(1,7); 
x36_1=string(x36);
x37=' (tabla 8).';
s7=strcat(x33," ",x34," ",x35," ",x36_1," ",x37);
add(chap4,s7);
x38='El atributo vial condición de la vía es   ';
x39=a.Datos_interfaz_tres.Estado_de_la_superficie_de_la_via(1,1);
x40='  , por lo tanto, el factor de riesgo es     ';
x41=b.Coeficientes_Pe.A_lo_largo_conductor(1,8);
x41_1=string(x31);
x42=' (tabla 7).';
s8=strcat(x38," ",x39," ",x40," ",x41_1," ",x42);
add(chap4,s8);
x43='El atributo vial gestión de la velocidad/calmante del tráfico es';
x44=a.Datos_interfaz_tres.Gestion_de_la_velocidad_calmante_del_trafico(1,1);
x45=', entonces, el factor de riesgo es';
x46=b.Coeficientes_Pe.A_lo_largo_conductor(1,9);
x46_1=string(x41);
x47='(tabla 26).';
s9=strcat(x43," ",x44," ",x45," ",x46_1," ",x47);
add(chap4,s9);
x48='El atributo vial estacionamiento para vehículos es'
x49=a.Datos_interfaz_tres.Estacionamiento_para_vehiculos(1,1);
x50=', entonces, el factor de riesgo es'
x51=b.Coeficientes_Pe.A_lo_largo_conductor(1,10);
x51_1=string(x56);
x52='(tabla 31).';
s11=strcat(x48," ",x49," ",x50," ",x51_1," ",x52);
add(chap4,s11);
x53='El atributo vial bandas sonoras en el espaldón es   ';
x54=a.Datos_interfaz_tres.Bandas_sonoras_en_el_espaldon(1,1);
x55='  , con lo que el factor de riesgo es    ';
x56=b.Coeficientes_Pe.A_lo_largo_conductor(1,11);
x56_1=string(x26)
x57='  (tabla 6).';
s10=strcat(x53," ",x54," ",x55," ",x56_1," ",x57);
add(chap4,s10);
x58='El atributo vial resistencia al deslizamiento es';
x59=a.Datos_interfaz_tres.Resistencia_al_deslizamiento(1,1);
x60=', en consecuencia, el factor de riesgo es';
x61=b.Coeficientes_Pe.A_lo_largo_conductor(1,12);
x61_1=string(x61);
x62='(tabla 9).';
s12=strcat(x58," ",x59," ",x60," ",x61_1," ",x62);
add(chap4,s12);
x63='El atributo vial alumbrado público es';
x64=a.Datos_interfaz_tres.Alumbrado_publico(1,1);
x65=', en consecuencia, el factor de riesgo es';
x66=b.Coeficientes_Pe.A_lo_largo_conductor(1,13);
x66_1=string(x66);
x67='(tabla 23).';
s13=strcat(x63," ",x64," ",x65," ",x66_1," ",x67);
add(chap4,s13);

sec4_92 = Section;
add(sec4_92,Paragraph('La probabilidad de siniestro vial en el lado del conductor se obtiene multiplicando los factores de riesgo previamente obtenidos.'));
add(sec4_92,Paragraph('Probabilidad = acera (lado del conductor) x curvatura x calidad de la curva x distancia de visibilidad x ancho de carril x delineación x pendiente x condición de la vía x gestión de la velocidad/calmante del tráfico x estacionamiento para vehículos x bandas sonoras en el espaldón x resistencia al deslizamiento x alumbrado público'));
add(chap4,sec4_92);
x68='Probabilidad=';
s14=strcat(x68,x4_1,'x',x9_1,'x',x14_1,'x',x19_1,'x',x26_1,'x',x31_1,'x',x36_1,'x',x41_1,'x',x46_1,'x',x51_1,'x',x56_1,'x',x61_1,'x',x66_1);
add(chap4,s14);
x69=b.Coeficientes_Pe.A_lo_largo_conductor(1,17);
x69_1=string(x69);
s15=strcat(x68,x69_1);
add(chap4,s15);

sec4_93 = Section;
tWavy = Text(' Severidad de los atributos viales');
tWavy.Style = {Underline('single')};
add(chap4,tWavy);
add(sec4_93,Paragraph('Los factores de riesgo, asociados a la gravedad/severidad del siniestro, para peatones y a lo largo se describen a continuación: '));
add(chap4,sec4_93);
x70='El atributo vial acera (lado del conductor) es '
x71=a.Datos_interfaz_tres.Acera_lado_del_conductor(1,1);
x72=', entonces, el factor de riesgo es';
x73=b.Coeficientes_Pe.A_lo_largo_conductor(1,14);
x73_1=string(x73);
x74='(tabla 34).';
s16=strcat(x70," ",x71," ",x72," ",x73_1," ",x74);
add(chap4,s16);
sec4_94 = Section;
add(sec4_94,Paragraph('La gravedad de siniestro vial a lo largo de la vía se obtiene multiplicando los factores de riesgo previamente determinados.'));
add(sec4_94,Paragraph('Severidad = acera(lado del conductor)'));
add(chap4,sec4_94);
x75='Severidad=';
s17=strcat(x75,x73_1);
add(chap4,s17);

tWavy = Text(' Influencia del flujo');
tWavy.Style = {Underline('single')};
add(chap4,tWavy);
x76='El atributo vial flujo vehicular (TDPA) es ';
x77=a.Datos_interfaz_tres.Flujo_vehicular(1,1);
x78='vehículos por día, entonces, el factor de riesgo es ';
x79=b.Coeficientes_Pe.A_lo_largo_conductor(1,15);
x79_1=string(x62);
s18=strcat(x76," ",x77," ",x78," ",x79_1);
add(chap4,s18);

tWavy = Text(' Velocidad de operación (percentil 85)');
tWavy.Style = {Underline('single')};
add(chap4,tWavy);
x80='El atributo vial velocidad de operación (percentil 85) es ';
x81=a.Datos_interfaz_tres.Velocidad_de_operacion_percentil_85 (1,1);
x82=' para ocupantes de ciclistas (caminos divididos, no divididos y rurales, de frente, de salida e intersección), en consecuencia, el factor de riesgo es ';
x83=b.Coeficientes_Pe.A_lo_largo_conductor(1,16);
x83_1=string(x70);
x84='(tabla 15).';
s19=strcat(x80," ",x81," ",x82," ",x83_1," ",x84);
add(chap4,s19);

sec4_95 = Section;
tWavy = Text(' Puntaje a lo largo de la vía');
tWavy.Style = {Underline('single')};
add(chap4,tWavy);
add(sec4_95,Paragraph('El puntaje a lo largo de la vía se obtiene multiplicando entre sí los factores de riesgos parciales.'));
add(sec4_95,Paragraph('Puntaje = probabilidad x severidad x influencia del flujo x velocidad de operación (percentil 85)'));
add(chap4,sec4_95);
x85='Puntaje=';
s20=strcat(x85,'x',x69_1,'x',x73_1,'x',x79_1,'x',x83_1);
add(chap4,s20);
x86=b.Coeficientes_Pe.A_lo_largo_conductor(1,18);
x86_1=string(x86);
s21=strcat(x85,x86_1);
add(chap4,s21);

p10 = Paragraph('b) A lo largo (lado del copiloto)');
p10.Bold=1;
add(chap4,p10)
sec4_96 = Section;
tWavy = Text(' Probabilidad de los atributos viales');
tWavy.Style = {Underline('single')};
add(chap4,tWavy);
add(sec4_96,Paragraph('Los factores de riesgo, asociados a la probabilidad del siniestro, para peatones y a lo largo se describen a continuación: '));
add(chap4,sec4_96);
a=load('../mat/Datos_interfaz_tres_proceso');
b=load('../mat/Coeficientes_Pe');
x1='El atributo vial acera (lado del copiloto) es ';
x2=a.Datos_interfaz_tres.Acera_lado_del_copiloto(1,1);
x3=', entonces, el factor de riesgo es';
x4=b.Coeficientes_Pe.A_lo_largo_copiloto(1,1);
x4_1=string(x4);
x5='(tabla 33)';
s1=strcat(x1," ",x2," ",x3," ",x4_1," ",x5);
add(chap4,s1);
x6='El atributo vial curvatura es ';
x7=a.Datos_interfaz_tres.Calidad_de_la_curva(1,1);
x8=', por lo tanto, el factor de riesgo es';
x9=b.Coeficientes_Pe.A_lo_largo_copiloto(1,2);
x9_1=string(x9);
x10='(tabla 3).';
s2=strcat(x6," ",x7," ",x8," ",x9_1," ",x10);
add(chap4,s2);
x11='El atributo vial calidad de la curva es';
x12=a.Datos_interfaz_tres.Calidad_de_la_curva(1,1);
x13=', entonces, el factor de riesgo es';
x14=b.Coeficientes_Pe.A_lo_largo_copiloto(1,3);
x14_1=string(x14);
x15='(tabla 4).';
s3=strcat(x11," ",x12," ",x13," ",x14_1," ",x15);
add(chap4,s3);
x16='El atributo vial distancia de visibilidad es';
x17=a.Datos_interfaz_tres.Distancia_de_visibilidad(1,1);
x18=', en consecuencia, el factor de riesgo es';
x19=b.Coeficientes_Pe.A_lo_largo_copiloto(1,4);
x19_1=string(x19);
x20='(tabla 24).';
s4=strcat(x16," ",x17," ",x18," ",x19_1," ",x20);
add(chap4,s4);
x21='El atributo vial ancho del carril es'
x22=a.Datos_interfaz_tres.Ancho_de_carril(1,1);
x23='en una zona'
x24=a.Datos_interfaz_tres.Tipo_de_area(1,1);
x25=', entonces, el factor de riesgo es'
x26=b.Coeficientes_Pe.A_lo_largo_copiloto(1,5);
x26_1=string(x26);
x27='(tabla 2).';
s5=strcat(x21," ",x22," ",x23," ",x24," ",x25," ",x26_1," ",x27);
add(chap4,s5);
x28='El atributo vial delineación es   ';
x29=a.Datos_interfaz_tres.Delineacion(1,1);
x30='  , en consecuencia, el factor de riesgo es   ';
x31=b.Coeficientes_Pe.A_lo_largo_copiloto(1,6);
x31_1=string(x21);
x32='  (tabla 5).';
s6=strcat(x28," ",x29," ",x30," ",x31_1," ",x32);
add(chap4,s6);
x33='El atributo vial pendiente es ';
x34=a.Datos_interfaz_tres.Pendiente(1,1);
x35=' , entonces, el factor de riesgo es ';
x36=b.Coeficientes_Pe.A_lo_largo_copiloto(1,7); 
x36_1=string(x36);
x37=' (tabla 8).';
s7=strcat(x33," ",x34," ",x35," ",x36_1," ",x37);
add(chap4,s7);
x38='El atributo vial condición de la vía es   ';
x39=a.Datos_interfaz_tres.Estado_de_la_superficie_de_la_via(1,1);
x40='  , por lo tanto, el factor de riesgo es     ';
x41=b.Coeficientes_Pe.A_lo_largo_copiloto(1,8);
x41_1=string(x31);
x42=' (tabla 7).';
s8=strcat(x38," ",x39," ",x40," ",x41_1," ",x42);
add(chap4,s8);
x43='El atributo vial gestión de la velocidad/calmante del tráfico es';
x44=a.Datos_interfaz_tres.Gestion_de_la_velocidad_calmante_del_trafico(1,1);
x45=', entonces, el factor de riesgo es';
x46=b.Coeficientes_Pe.A_lo_largo_copiloto(1,9);
x46_1=string(x41);
x47='(tabla 26).';
s9=strcat(x43," ",x44," ",x45," ",x46_1," ",x47);
add(chap4,s9);
x48='El atributo vial estacionamiento para vehículos es'
x49=a.Datos_interfaz_tres.Estacionamiento_para_vehiculos(1,1);
x50=', entonces, el factor de riesgo es'
x51=b.Coeficientes_Pe.A_lo_largo_copiloto(1,10);
x51_1=string(x56);
x52='(tabla 31).';
s11=strcat(x48," ",x49," ",x50," ",x51_1," ",x52);
add(chap4,s11);
x53='El atributo vial bandas sonoras en el espaldón es   ';
x54=a.Datos_interfaz_tres.Bandas_sonoras_en_el_espaldon(1,1);
x55='  , con lo que el factor de riesgo es    ';
x56=b.Coeficientes_Pe.A_lo_largo_copiloto(1,11);
x56_1=string(x26)
x57='  (tabla 6).';
s10=strcat(x53," ",x54," ",x55," ",x56_1," ",x57);
add(chap4,s10);
x58='El atributo vial resistencia al deslizamiento es';
x59=a.Datos_interfaz_tres.Resistencia_al_deslizamiento(1,1);
x60=', en consecuencia, el factor de riesgo es';
x61=b.Coeficientes_Pe.A_lo_largo_copiloto(1,12);
x61_1=string(x61);
x62='(tabla 9).';
s12=strcat(x58," ",x59," ",x60," ",x61_1," ",x62);
add(chap4,s12);
x63='El atributo vial alumbrado público es';
x64=a.Datos_interfaz_tres.Alumbrado_publico(1,1);
x65=', en consecuencia, el factor de riesgo es';
x66=b.Coeficientes_Pe.A_lo_largo_copiloto(1,13);
x66_1=string(x66);
x67='(tabla 23).';
s13=strcat(x63," ",x64," ",x65," ",x66_1," ",x67);
add(chap4,s13);

sec4_97 = Section;
add(sec4_97,Paragraph('La probabilidad de siniestro vial en el lado del copiloto se obtiene multiplicando los factores de riesgo previamente obtenidos.'));
add(sec4_97,Paragraph('Probabilidad = acera (lado del copiloto) x curvatura x calidad de la curva x distancia de visibilidad x ancho de carril x delineación x pendiente x condición de la vía x gestión de la velocidad/calmante del tráfico x estacionamiento para vehículos x bandas sonoras en el espaldón x resistencia al deslizamiento x alumbrado público'));
add(chap4,sec4_97);
x68='Probabilidad=';
s14=strcat(x68,x4_1,'x',x9_1,'x',x14_1,'x',x19_1,'x',x26_1,'x',x31_1,'x',x36_1,'x',x41_1,'x',x46_1,'x',x51_1,'x',x56_1,'x',x61_1,'x',x66_1);
add(chap4,s14);
x69=b.Coeficientes_Pe.A_lo_largo_copiloto(1,17);
x69_1=string(x69);
s15=strcat(x68,x69_1);
add(chap4,s15);

sec4_98 = Section;
tWavy = Text(' Severidad de los atributos viales');
tWavy.Style = {Underline('single')};
add(chap4,tWavy);
add(sec4_98,Paragraph('Los factores de riesgo, asociados a la gravedad/severidad del siniestro, para peatones y a lo largo se describen a continuación: '));
add(chap4,sec4_98);
x70='El atributo vial acera (lado del copiloto) es '
x71=a.Datos_interfaz_tres.Acera_lado_del_copiloto(1,1);
x72=', entonces, el factor de riesgo es';
x73=b.Coeficientes_Pe.A_lo_largo_copiloto(1,14);
x73_1=string(x73);
x74='(tabla 34).';
s16=strcat(x70," ",x71," ",x72," ",x73_1," ",x74);
add(chap4,s16);
sec4_99 = Section;
add(sec4_99,Paragraph('La gravedad de siniestro vial a lo largo de la vía se obtiene multiplicando los factores de riesgo previamente determinados.'));
add(sec4_99,Paragraph('Severidad = acera(lado del copiloto)'));
add(chap4,sec4_99);
x75='Severidad=';
s17=strcat(x75,x73_1);
add(chap4,s17);

tWavy = Text(' Influencia del flujo');
tWavy.Style = {Underline('single')};
add(chap4,tWavy);
x76='El atributo vial flujo vehicular (TDPA) es ';
x77=a.Datos_interfaz_tres.Flujo_vehicular(1,1);
x78='vehículos por día, entonces, el factor de riesgo es ';
x79=b.Coeficientes_Pe.A_lo_largo_copiloto(1,15);
x79_1=string(x62);
s18=strcat(x76," ",x77," ",x78," ",x79_1);
add(chap4,s18);

tWavy = Text(' Velocidad de operación (percentil 85)');
tWavy.Style = {Underline('single')};
add(chap4,tWavy);
x80='El atributo vial velocidad de operación (percentil 85) es ';
x81=a.Datos_interfaz_tres.Velocidad_de_operacion_percentil_85 (1,1);
x82=' para ocupantes de ciclistas (caminos divididos, no divididos y rurales, de frente, de salida e intersección), en consecuencia, el factor de riesgo es ';
x83=b.Coeficientes_Pe.A_lo_largo_copiloto(1,16);
x83_1=string(x70);
x84='(tabla 15).';
s19=strcat(x80," ",x81," ",x82," ",x83_1," ",x84);
add(chap4,s19);

sec4_100 = Section;
tWavy = Text(' Puntaje a lo largo de la vía');
tWavy.Style = {Underline('single')};
add(chap4,tWavy);
add(sec4_100,Paragraph('El puntaje a lo largo de la vía se obtiene multiplicando entre sí los factores de riesgos parciales.'));
add(sec4_100,Paragraph('Puntaje = probabilidad x severidad x influencia del flujo x velocidad de operación (percentil 85)'));
add(chap4,sec4_100);
x85='Puntaje=';
s20=strcat(x85,'x',x69_1,'x',x73_1,'x',x79_1,'x',x83_1);
add(chap4,s20);
x86=b.Coeficientes_Pe.A_lo_largo_copiloto(1,18);
x86_1=string(x86);
s21=strcat(x85,x86_1);
add(chap4,s21);


p14 = Paragraph('c) Cruce peatonal (tramo analizado)');
p14.Bold=1;
add(chap4,p14)
sec4_101 = Section;
tWavy = Text('Probabilidad de los atributos viales');
tWavy.Style = {Underline('single')};
add(chap4,tWavy)
add(sec4_101,Paragraph('Los factores de riesgo, asociados a la probabilidad del siniestro, para peatones y en el cruce peatonal se describen a continuación: '));
add(chap4,sec4_101);
a=load('../mat/Datos_interfaz_tres_proceso');
b=load('../mat/Coeficientes_Pe');
x1='El atributo vial número de carriles';
x2=a.Datos_interfaz_tres.Numero_de_carriles(1,1);
x3=',por lo tanto, el factor de riesgo es';
x4=b.Coeficientes_Pe.Cruce_peatonal_inspeccionada(1,1);
x4_1=string(x4);
x5='(tabla 19).';
s1=strcat(x1," ",x2," ",x3," ",x4_1," ",x5);
add(chap4,s1);
x6='El atributo vial tipo de mediana/separador central';
x7=a.Datos_interfaz_tres.Tipo_de_mediana(1,1);
x8=',por lo que el factor de riesgo es';
x9=b.Coeficientes_Pe.Cruce_peatonal_inspeccionada(1,2);
x9_1=string(x9);
x10='(tabla 19).';
s2=strcat(x6," ",x7," ",x8," ",x9_1," ",x10);
add(chap4,s2);
x11='El atributo infraestructura de cruce peatonal en el tramo analizado es'
x12=a.Datos_interfaz_tres.Infraestructura_de_cruce_peatonal_en_el_tramo_analizado(1,1);
x13=', entonces, el factor de riesgo es';
x14=b.Coeficientes_Pe.Cruce_peatonal_inspeccionada(1,3);
x14_1=string(x14);
x15='para supervisor en el cruce de  zona escolar';
x16=a.Datos_interfaz_tres.Supervisor_en_el_cruce_de_zona_escolar(1,1);
x17='(tabla 32).';
s3=strcat(x11," ",x12," ",x13," ",x14_1," ",x15," ",x16," ",x17);
add(chap4,s3);
x18='El atributo vial calidad del cruce peatonal es'
x19=a.Datos_interfaz_tres.Calidad_de_cruce_peatonal(1,1);
x20=', entonces, el factor de riesgo es';
x21=b.Coeficientes_Pe.Cruce_peatonal_inspeccionada(1,4);
x21_1=string(x21);
x22='(tabla 35).';
s4=strcat(x18," ",x19," ",x20," ",x21_1," ",x22);
add(chap4,s4);
x23='El atributo vial tipo de intersección es ';
x24= a.Datos_interfaz_tres.Tipo_de_interseccion(1,1);
x25=' , entonces, el factor de riesgo es ' 
x26=b.Coeficientes_Pe.Cruce_peatonal_inspeccionada(1,5);
x26_1=string(x26);
x27=' (tabla 20)';
s5=strcat(x23," ",x24," ",x25," ",x26_1," ",x27);
add(chap4,s5);
x28='El atributo vial calidad de la intersección es ';
x29= a.Datos_interfaz_tres.Calidad_de_la_interseccion(1,1);
x30=' , por lo tanto, el factor de riesgo es '
x31=b.Coeficientes_Pe.Cruce_peatonal_inspeccionada(1,6);
x31_1=string(x31);
x32=' (tabla 22).';
s6=strcat(x28," ",x29," ",x30," ",x31_1," ",x32);
add(chap4,s6);
x33='El atributo vial vallas peatonales es'
x34=a.Datos_interfaz_tres.Vallas_peatonales(1,1);
x35=', en consecuencia, el factor de riesgo es'
x36=b.Coeficientes_Pe.Cruce_peatonal_inspeccionada(1,7);
x36_1=string(x36);
x37='(tabla 36).';
s7=strcat(x33," ",x34," ",x35," ",x36_1," ",x37);
add(chap4,s7);
x38='El atributo vial resistencia al deslizamiento es ';
x39=a.Datos_interfaz_tres.Resistencia_al_deslizamiento(1,1);
x40=', en consecuencia, el factor de riesgo es'
x41=b.Coeficientes_Pe.Cruce_peatonal_inspeccionada(1,8);
x41_1=string(x41);
x42=' (tabla 9).';
s8=strcat(x38," ",x39," ",x40," ",x41_1," ",x42);
add(chap4,s8);
x43='El atributo vial alumbrado público es ';
x44=a.Datos_interfaz_tres.Alumbrado_publico(1,1);
x45=' , en consecuencia, el factor de riesgo es ';
x46=b.Coeficientes_Pe.Cruce_peatonal_inspeccionada(1,9);
x46_1=string(x46);
x47=' (tabla 23).';
s9=strcat(x43," ",x44," ",x45," ",x46_1," ",x47);
add(chap4,s9);
x48='El atributo vial distancia de visibilidad es ';
x49=a.Datos_interfaz_tres.Distancia_de_visibilidad(1,1);
x50=', entonces, el factor de riesgo es ';
x51=b.Coeficientes_Pe.Cruce_peatonal_inspeccionada(1,10);
x51_1=string(x51);
x52='(tabla 24).';
s10=strcat(x48," ",x49," ",x50," ",x51_1," ",x52);
add(chap4,s10);
x53='El atributo vial estacionamiento para vehículos es'
x54=a.Datos_interfaz_tres.Estacionamiento_para_vehiculos(1,1);
x55=', entonces, el factor de riesgo es'
x56=b.Coeficientes_Pe.Cruce_peatonal_inspeccionada(1,11);
x56_1=string(x56);
x57='(tabla 31).';
s11=strcat(x53," ",x54," ",x55," ",x56_1," ",x57);
add(chap4,s11);
x58='El atributo vial gestión de la velocidad/calmante del tráfico es';
x59=a.Datos_interfaz_tres.Gestion_de_la_velocidad_calmante_del_trafico(1,1);
x60=', entonces, el factor de riesgo es';
x61=b.Coeficientes_Pe.Cruce_peatonal_inspeccionada(1,12);
x61_1=string(x61);
x62='(tabla 26).';
s12=strcat(x58," ",x59," ",x60," ",x61_1," ",x62);
add(chap4,s21);

sec4_102 = Section;
add(sec4_102,Paragraph('La probabilidad de siniestro vial en el cruce peatonal se obtiene multiplicando los factores de riesgo previamente obtenidos.'));
add(sec4_102,Paragraph('Probabilidad = número de carriles x tipo de mediana x infraestructura de cruce peatonal en el tramo x calidad de cruce peatonal x tipo de intersección x calidad de la intersección x vallas peatonales x resistencia al deslizamiento x alumbrado público x distancia de visibilidad x estacionamiento para vehículos x gestión de la velocidad/calmante del tráfico'));
add(chap4,sec4_102);
x63='Probabilidad =';
s13=strcat(x63,x4_1,'x',x9_1,'x',x14_1,'x',x21_1,'x',x26_1,'x',x31_1,'x',x36_1,'x',x41_1,'x',x46_1,'x',x51_1,'x',x56_1,'x',x61_1);
add(chap4,s13);
x64=b.Coeficientes_Pe.Cruce_peatonal_inspeccionada(1,16);
x64_1=string(x64);
s14=strcat(x43,x64_1);
add(chap4,s14);

sec4_103 = Section;
tWavy = Text('Severidad de los atributos viales');
tWavy.Style = {Underline('single')};
add(chap4,tWavy)
add(sec4_103,Paragraph('Los factores de riesgo, asociados a la gravedad/severidad del siniestro, para peatones y en el cruce peatonal se describen a continuación: '));
add(chap4,sec4_103);
x65='El atributo vial infraestructura de cruce peatonal en el tramo analizado es';
x66=a.Datos_interfaz_tres.Infraestructura_de_cruce_peatonal_en_el_tramo_analizado(1,1);
x67=', entonces, el factor de riesgo es';
x68=b.Coeficientes_Pe.Cruce_peatonal_inspeccionada(1,13);
x68_1=string(x68);
x69='tabla(20).';
s15=strcat(x65," ",x66," ",x67," ",x68_1," ",x69);
add(chap4,s15);
sec4_104 = Section;
add(sec4_104,Paragraph('La gravedad de siniestro vial en las intersecciones se obtiene multiplicando los factores de riesgo previamente determinados.'));
add(sec4_104,Paragraph('Severidad = insfraestructura de cruce peatonal en el tramo analizado'));
add(chap4,sec4_104);
x70='Severidad=';
s16=strcat(x70,x68_1);
add(chap4,s16);

tWavy = Text('Influencia del flujo');
tWavy.Style = {Underline('single')};
add(chap4,tWavy)
x71='El atributo vial flujo vehicular (TDPA) es ';
x72=a.Datos_interfaz_tres.Flujo_vehicular(1,1);
x73='vehículos por día, entonces, el factor de riesgo es';
x74=b.Coeficientes_Pe.Cruce_peatonal_inspeccionada(1,14);
x74_1=string(x74)
s17=strcat(x71," ",x72," ",x73," ",x74_1);
add(chap4,s17);

tWavy = Text('Velocidad de operación (percentil 85)');
tWavy.Style = {Underline('single')};
add(chap4,tWavy)
x75='El atributo vial velocidad de operación (percentil 85) es ';
x76=a.Datos_interfaz_tres.Velocidad_de_operacion_percentil_85(1,1);
x77='para ocupantes de vehículos y motociclistas (caminos divididos, no divididos y rurales, de frente, de salida e intersección), en consecuencia, el factor de riesgo es';
x78=b.Coeficientes_Pe.Cruce_peatonal_inspeccionada(1,15);
x78_1=string(x78);
x79='(tabla 15).';
s18=strcat(x75," ",x76," ",x77," ",x78_1," ",x79);
add(chap4,s18);

sec4_105 = Section;
tWavy = Text('Puntaje por cruce peatonal en el tramo analizado');
tWavy.Style = {Underline('single')};
add(chap4,tWavy)
add(sec4_105,Paragraph('El puntaje por cruce peatonal en el tramo analizado se obtiene multiplicando entre sí los factores de riesgos parciales.'));
add(sec4_105,Paragraph('Puntaje = probabilidad x severidad x influencia del flujo x velocidad de operación (percentil 85)'));
add(chap4,sec4_105);
x80='Puntaje =';
s19=strcat(x80,x64_1,'x',x68_1,'x',x74_1,'x',x78_1);
add(chap4,s19);
x81=b.Coeficientes_Pe.Cruce_peatonal_inspeccionada(1,17);
x81_1=string(x81);
s20=strcat(x80,x81_1);
add(chap4,s20);

p14 = Paragraph('d) Cruce peatonal (intersección)');
p14.Bold=1;
add(chap4,p14)
sec4_106 = Section;
tWavy = Text('Probabilidad de los atributos viales');
tWavy.Style = {Underline('single')};
add(chap4,tWavy)
add(sec4_106,Paragraph('Los factores de riesgo, asociados a la probabilidad del siniestro, para peatones y en el cruce peatonal se describen a continuación: '));
add(chap4,sec4_106);
a=load('../mat/Datos_interfaz_tres_proceso');
b=load('../mat/Coeficientes_Pe');
x1='El atributo vial número de carriles';
x2=a.Datos_interfaz_tres.Numero_de_carriles(1,1);
x3=',por lo tanto, el factor de riesgo es';
x4=b.Coeficientes_Pe.Cruce_peatonal_lateral(1,1);
x4_1=string(x4);
x5='(tabla 19).';
s1=strcat(x1," ",x2," ",x3," ",x4_1," ",x5);
add(chap4,s1);
x6='El atributo vial tipo de mediana/separador central';
x7=a.Datos_interfaz_tres.Tipo_de_mediana(1,1);
x8=',por lo que el factor de riesgo es';
x9=b.Coeficientes_Pe.Cruce_peatonal_lateral(1,2);
x9_1=string(x9);
x10='(tabla 19).';
s2=strcat(x6," ",x7," ",x8," ",x9_1," ",x10);
add(chap4,s2);
x11='El atributo infraestructura de cruce peatonal en la intersección es'
x12=a.Datos_interfaz_tres.Infraestructura_de_cruce_peatonal_en_la_interseccion(1,1);
x13=', entonces, el factor de riesgo es';
x14=b.Coeficientes_Pe.Cruce_peatonal_lateral(1,3);
x14_1=string(x14);
x15='para supervisor en el cruce de  zona escolar';
x16=a.Datos_interfaz_tres.Supervisor_en_el_cruce_de_zona_escolar(1,1);
x17='(tabla 32).';
s3=strcat(x11," ",x12," ",x13," ",x14_1," ",x15," ",x16," ",x17);
add(chap4,s3);
x18='El atributo vial calidad del cruce peatonal es'
x19=a.Datos_interfaz_tres.Calidad_de_cruce_peatonal(1,1);
x20=', entonces, el factor de riesgo es';
x21=b.Coeficientes_Pe.Cruce_peatonal_lateral(1,4);
x21_1=string(x21);
x22='(tabla 35).';
s4=strcat(x18," ",x19," ",x20," ",x21_1," ",x22);
add(chap4,s4);
x23='El atributo vial tipo de intersección es ';
x24= a.Datos_interfaz_tres.Tipo_de_interseccion(1,1);
x25=' , entonces, el factor de riesgo es ' 
x26=b.Coeficientes_Pe.Cruce_peatonal_lateral(1,5);
x26_1=string(x26);
x27=' (tabla 20)';
s5=strcat(x23," ",x24," ",x25," ",x26_1," ",x27);
add(chap4,s5);
x28='El atributo vial calidad de la intersección es ';
x29= a.Datos_interfaz_tres.Calidad_de_la_interseccion(1,1);
x30=' , por lo tanto, el factor de riesgo es '
x31=b.Coeficientes_Pe.Cruce_peatonal_lateral(1,6);
x31_1=string(x31);
x32=' (tabla 22).';
s6=strcat(x28," ",x29," ",x30," ",x31_1," ",x32);
add(chap4,s6);
x33='El atributo vial vallas peatonales es'
x34=a.Datos_interfaz_tres.Vallas_peatonales(1,1);
x35=', en consecuencia, el factor de riesgo es'
x36=b.Coeficientes_Pe.Cruce_peatonal_lateral(1,7);
x36_1=string(x36);
x37='(tabla 36).';
s7=strcat(x33," ",x34," ",x35," ",x36_1," ",x37);
add(chap4,s7);
x38='El atributo vial resistencia al deslizamiento es ';
x39=a.Datos_interfaz_tres.Resistencia_al_deslizamiento(1,1);
x40=', en consecuencia, el factor de riesgo es'
x41=b.Coeficientes_Pe.Cruce_peatonal_lateral(1,8);
x41_1=string(x41);
x42='(tabla 9).';
s8=strcat(x38," ",x39," ",x40," ",x41_1," ",x42);
add(chap4,s8);
x43='El atributo vial alumbrado público es ';
x44=a.Datos_interfaz_tres.Alumbrado_publico(1,1);
x45=' , en consecuencia, el factor de riesgo es ';
x46=b.Coeficientes_Pe.Cruce_peatonal_lateral(1,9);
x46_1=string(x46);
x47=' (tabla 23).';
s9=strcat(x43," ",x44," ",x45," ",x46_1," ",x47);
add(chap4,s9);
x48='El atributo vial distancia de visibilidad es ';
x49=a.Datos_interfaz_tres.Distancia_de_visibilidad(1,1);
x50=', entonces, el factor de riesgo es ';
x51=b.Coeficientes_Pe.Cruce_peatonal_lateral(1,10);
x51_1=string(x51);
x52='(tabla 24).';
s10=strcat(x48," ",x49," ",x50," ",x51_1," ",x52);
add(chap4,s10);
x53='El atributo vial estacionamiento para vehículos es'
x54=a.Datos_interfaz_tres.Estacionamiento_para_vehiculos(1,1);
x55=', entonces, el factor de riesgo es'
x56=b.Coeficientes_Pe.Cruce_peatonal_lateral(1,11);
x56_1=string(x56);
x57='(tabla 31).';
s11=strcat(x53," ",x54," ",x55," ",x56_1," ",x57);
add(chap4,s11);
x58='El atributo vial gestión de la velocidad/calmante del tráfico es';
x59=a.Datos_interfaz_tres.Gestion_de_la_velocidad_calmante_del_trafico(1,1);
x60=', entonces, el factor de riesgo es';
x61=b.Coeficientes_Pe.Cruce_peatonal_lateral(1,12);
x61_1=string(x61);
x62='(tabla 26).';
s12=strcat(x58," ",x59," ",x60," ",x61_1," ",x62);
add(chap4,s12);

sec4_107 = Section;
add(sec4_107,Paragraph('La probabilidad de siniestro vial en el cruce peatonal se obtiene multiplicando los factores de riesgo previamente obtenidos.'));
add(sec4_107,Paragraph('Probabilidad = número de carriles x tipo de mediana x infraestructura de cruce peatonal en la intersección x calidad de cruce peatonal x tipo de intersección x calidad de la intersección x vallas peatonales x resistencia al deslizamiento x alumbrado público x distancia de visibilidad x estacionamiento para vehículos x gestión de la velocidad/calmante del tráfico'));
add(chap4,sec4_107);
x63='Probabilidad =';
s13=strcat(x63,x4_1,'x',x9_1,'x',x14_1,'x',x21_1,'x',x26_1,'x',x31_1,'x',x36_1,'x',x41_1,'x',x46_1,'x',x51_1,'x',x56_1,'x',x61_1);
add(chap4,s13);
x64=b.Coeficientes_Pe.Cruce_peatonal_lateral(1,16);
x64_1=string(x64);
s14=strcat(x43,x64_1);
add(chap4,s14);

sec4_108 = Section;
tWavy = Text('Severidad de los atributos viales');
tWavy.Style = {Underline('single')};
add(chap4,tWavy)
add(sec4_108,Paragraph('Los factores de riesgo, asociados a la gravedad/severidad del siniestro, para peatones y en el cruce peatonal se describen a continuación: '));
add(chap4,sec4_108);
x65='El atributo vial infraestructura de cruce peatonal en la intersección es';
x66=a.Datos_interfaz_tres.Infraestructura_de_cruce_peatonal_en_la_interseccion(1,1);
x67=', entonces, el factor de riesgo es';
x68=b.Coeficientes_Pe.Cruce_peatonal_lateral(1,13);
x68_1=string(x68);
x69='tabla(20).';
s15=strcat(x65," ",x66," ",x67," ",x68_1," ",x69);
add(chap4,s15);
sec4_109 = Section;
add(sec4_109,Paragraph('La gravedad de siniestro vial en las intersecciones se obtiene multiplicando los factores de riesgo previamente determinados.'));
add(sec4_109,Paragraph('Severidad = insfraestructura de cruce peatonal en la intersección'));
add(chap4,sec4_109);
x70='Severidad=';
s16=strcat(x70,x68_1);
add(chap4,s16);

tWavy = Text('Influencia del flujo');
tWavy.Style = {Underline('single')};
add(chap4,tWavy)
x71='El atributo vial flujo vehicular (TDPA) es ';
x72=a.Datos_interfaz_tres.Flujo_vehicular(1,1);
x73='vehículos por día, entonces, el factor de riesgo es';
x74=b.Coeficientes_Pe.Cruce_peatonal_lateral(1,14);
x74_1=string(x74)
s17=strcat(x71," ",x72," ",x73," ",x74_1);
add(chap4,s17);

tWavy = Text('Velocidad de operación (percentil 85)');
tWavy.Style = {Underline('single')};
add(chap4,tWavy)
x75='El atributo vial velocidad de operación (percentil 85) es ';
x76=a.Datos_interfaz_tres.Velocidad_de_operacion_percentil_85(1,1);
x77='para ocupantes de vehículos y motociclistas (caminos divididos, no divididos y rurales, de frente, de salida e intersección), en consecuencia, el factor de riesgo es';
x78=b.Coeficientes_Pe.Cruce_peatonal_lateral(1,15);
x78_1=string(x78);
x79='(tabla 15).';
s18=strcat(x75," ",x76," ",x77," ",x78_1," ",x79);
add(chap4,s18);

sec4_110 = Section;
tWavy = Text('Puntaje por cruce peatonal en el tramo analizado');
tWavy.Style = {Underline('single')};
add(chap4,tWavy)
add(sec4_110,Paragraph('El puntaje por cruce peatonal en el tramo analizado se obtiene multiplicando entre sí los factores de riesgos parciales.'));
add(sec4_110,Paragraph('Puntaje = probabilidad x severidad x influencia del flujo x velocidad de operación (percentil 85)'));
add(chap4,sec4_110);
x80='Puntaje =';
s19=strcat(x80,x64_1,'x',x68_1,'x',x74_1,'x',x78_1);
add(chap4,s19);
x81=b.Coeficientes_Pe.Cruce_peatonal_lateral(1,17);
x81_1=string(x81);
s20=strcat(x80,x81_1);
add(chap4,s20);

p16 = Paragraph('SRS para ciclistas y clasificación por estrellas')
p16.Bold=1;
add(chap4,p16);
sec4_111 = Section;
add(sec4_111,Paragraph('Las puntuaciones para la clasificación por estrellas (SRS) para peatones es la suma de los puntajes previos por cada tipo de siniestro.'));
add(sec4_111,Paragraph('Puntaje de clasificación por estrellas (SRS) = siniestros a lo largo de la vía (lado del conductor) + siniestros a lo largo de la vía (lado del copiloto) + promedio de los siniestros a lo largo de la vía (lado del conductor y copiloto) + siniestros en el cruce peatonal (tramo analizado) + siniestros en el cruce peatonal (intersección) '));
add(chap4,sec4_111);
x62='Puntaje de clasificación por estrellas (SRS) = ';
b=load('../mat/Coeficientes_Pe');
X1=b.Coeficientes_Pe.A_lo_largo_conductor(1,18);
X2=b.Coeficientes_Pe.A_lo_largo_copiloto(1,18);
X3=b.Coeficientes_Pe.Cruce_peatonal_inspeccionada(1,17);
X4=b.Coeficientes_Pe.Cruce_peatonal_lateral(1,17)
X1_1=string(X1);
X2_1=string(X2);
X3_1=string(X3);
X4_1=string(X4);
x63='Promedio(A lo largo conductor y copiloto=';
x64=b.Coeficientes_Pe.Cruce_peatonal_lateral(1,19);
X64_1=string(x64);
s21=strcat(x63,'(',X1_1,'+',X2_1,')','/','2');
add(chap4,s21);
s22=strcat(x63,X64_1);
add(chap4,s22);
s23=strcat(x62,X64_1,'+',X3_1,'+',X4_1);
add(chap4,s23);
x65=b.Coeficientes_Pe.Cruce_peatonal_lateral(1,18);
x65_1=string(x65);
s24=strcat(x62,x65_1);
add(chap4,s24);
a=load('../mat/Datos_interfaz_tres_proceso');
x66='Al SRS de';
x67='le corresponde';
x68=a.Datos_interfaz_tres.Peaton(1,1);
x68_1=string(x68);
x69='estrellas para peatones (tabla 37).';
s25=strcat(x66," ",x65_1," ",x67," ",x68_1," ",x69);
add(chap4,s25);

add(rpt,chap4);

%Paso Final
close(rpt);
rptview(rpt);
        end

        % Value changed function: TramodeanlisisSpinner
        function TramodeanlisisSpinnerValueChanged(app, event)
            value = app.TramodeanlisisSpinner.Value;
            load('../mat/preproceso_tramo.mat');
            app.Image.Visible='off';
            
 
            B=sum(arrayfun(@(tramo) ~isempty(tramo.Tramos),tramo));
           
            app.TramodeanlisisSpinner.Limits=[1 B];
            app.v1=app.TramodeanlisisSpinner.Value;
            app.Image.Visible='on';
            
            %Calculo V85
            R = tramo(app.v1).Radio;
            K= tramo(app.v1).k;
            F= tramo(app.v1).Pendiente;
            L=tramo(app.v1).Distancia;
            app.Button_635.BackgroundColor=[0.96,0.96,0.96];
            
            if K==0 %RECTA
                 if F>=6 && F<=10 
                  V85=69.69 
                 elseif F>=4 && F<=5.99
                   V85=0.03*L+69.52
                 elseif F>=0 && F<=3.99
                   V85=0.04*L+73.69 
                 elseif F>=-3.99 && F<0
                   V85=0.05*L+73.65 
                 elseif F>=-5.99 && F<=-4
                  V85=0.04*L+72.68 
                 elseif F>=-10 && F<=-6
                 V85=0.07*L+66.09 
                 end
             elseif K==1 %CURVA
                 if F>=6 && F<=10
                  V85=74.95-794.59/R 
                 elseif F>=4 && F<=5.99
                  V85=78.33-740.66/R
                 elseif F>=0 && F<=3.99
                  V85=91.42-2039.59/R 
                 elseif F>=-3.99 && F<0
                  V85=94.59-2366.42/R  
                 elseif F>=-5.99 && F<=-4
                  V85=86.44-1433.64/R
                 elseif F>=-10 && F<=-6
                 V85=81.10-1304.97/R
                 end
             end
            
             if V85<=30
                 app.Button_631.BackgroundColor=[1.00,1.00,0.00];
                 app.Button_632.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_633.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_634.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_635.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_636.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_637.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_638.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_639.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_640.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_641.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_642.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_643.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_644.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_645.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_646.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_647.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_648.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_649.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_650.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_651.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_652.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_653.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_654.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_655.BackgroundColor=[0.96,0.96,0.96];
                 app.Matriz1(52)=30;
                 app.Matriz3(52)=cellstr('< 30 km/h');
             else if V85>30 && V85<=35
                 app.Button_631.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_632.BackgroundColor=[1.00,1.00,0.00];
                 app.Button_633.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_634.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_635.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_636.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_637.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_638.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_639.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_640.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_641.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_642.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_643.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_644.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_645.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_646.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_647.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_648.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_649.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_650.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_651.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_652.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_653.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_654.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_655.BackgroundColor=[0.96,0.96,0.96];
                 app.Matriz1(52)=35;
                 app.Matriz3(52)=cellstr('35 km/h');
             else if V85>35 && V85<=40
                 
                 app.Button_631.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_632.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_633.BackgroundColor=[1.00,1.00,0.00];
                 app.Button_634.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_635.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_636.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_637.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_638.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_639.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_640.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_641.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_642.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_643.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_644.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_645.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_646.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_647.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_648.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_649.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_650.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_651.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_652.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_653.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_654.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_655.BackgroundColor=[0.96,0.96,0.96];
                 app.Matriz1(52)=40;
                 app.Matriz3(52)=cellstr('40 km/h'); 
            else if V85>40 && V85<=45
                 
                 app.Button_631.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_632.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_633.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_634.BackgroundColor=[1.00,1.00,0.00];
                 app.Button_635.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_636.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_637.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_638.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_639.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_640.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_641.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_642.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_643.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_644.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_645.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_646.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_647.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_648.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_649.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_650.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_651.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_652.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_653.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_654.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_655.BackgroundColor=[0.96,0.96,0.96];
                 app.Matriz1(52)=45;
                 app.Matriz3(52)=cellstr('45 km/h');
            else if V85>45 && V85<=50
                 
                 app.Button_631.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_632.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_633.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_634.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_635.BackgroundColor=[1.00,1.00,0.00];
                 app.Button_636.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_637.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_638.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_639.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_640.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_641.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_642.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_643.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_644.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_645.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_646.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_647.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_648.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_649.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_650.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_651.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_652.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_653.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_654.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_655.BackgroundColor=[0.96,0.96,0.96];
                 app.Matriz1(52)=50;
                 app.Matriz3(52)=cellstr('50 km/h');
            else if V85>50 && V85<=55
                 
                 app.Button_631.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_632.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_633.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_634.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_635.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_636.BackgroundColor=[1.00,1.00,0.00];
                 app.Button_637.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_638.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_639.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_640.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_641.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_642.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_643.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_644.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_645.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_646.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_647.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_648.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_649.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_650.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_651.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_652.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_653.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_654.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_655.BackgroundColor=[0.96,0.96,0.96];
                 app.Matriz1(52)=55;
                 app.Matriz3(52)=cellstr('55 km/h');
            else if V85>55 && V85<=60
                 
                 app.Button_631.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_632.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_633.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_634.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_635.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_636.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_637.BackgroundColor=[1.00,1.00,0.00];
                 app.Button_638.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_639.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_640.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_641.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_642.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_643.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_644.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_645.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_646.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_647.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_648.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_649.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_650.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_651.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_652.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_653.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_654.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_655.BackgroundColor=[0.96,0.96,0.96];
                 app.Matriz1(52)=60;
                 app.Matriz3(52)=cellstr('60 km/h');
            else if V85>60 && V85<=65
                 
                 app.Button_631.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_632.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_633.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_634.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_635.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_636.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_637.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_638.BackgroundColor=[1.00,1.00,0.00];
                 app.Button_639.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_640.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_641.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_642.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_643.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_644.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_645.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_646.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_647.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_648.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_649.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_650.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_651.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_652.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_653.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_654.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_655.BackgroundColor=[0.96,0.96,0.96];
                 app.Matriz1(52)=65;
                 app.Matriz3(52)=cellstr('65 km/h');
            else if V85>65 && V85<=70
                 
                 app.Button_631.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_632.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_633.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_634.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_635.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_636.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_637.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_638.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_639.BackgroundColor=[1.00,1.00,0.00];
                 app.Button_640.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_641.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_642.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_643.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_644.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_645.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_646.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_647.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_648.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_649.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_650.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_651.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_652.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_653.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_654.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_655.BackgroundColor=[0.96,0.96,0.96];
                 app.Matriz1(52)=70;
                 app.Matriz3(52)=cellstr('70 km/h');
            else if V85>70 && V85<=75
                 
                 app.Button_631.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_632.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_633.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_634.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_635.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_636.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_637.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_638.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_639.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_640.BackgroundColor=[1.00,1.00,0.00];
                 app.Button_641.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_642.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_643.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_644.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_645.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_646.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_647.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_648.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_649.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_650.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_651.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_652.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_653.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_654.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_655.BackgroundColor=[0.96,0.96,0.96];
                 app.Matriz1(52)=70;
                 app.Matriz3(52)=cellstr('75 km/h');
            else if V85>75 && V85<=80
                 
                 app.Button_631.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_632.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_633.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_634.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_635.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_636.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_637.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_638.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_639.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_640.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_641.BackgroundColor=[1.00,1.00,0.00];
                 app.Button_642.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_643.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_644.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_645.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_646.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_647.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_648.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_649.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_650.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_651.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_652.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_653.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_654.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_655.BackgroundColor=[0.96,0.96,0.96];
                 app.Matriz1(52)=80;
                 app.Matriz3(52)=cellstr('80 km/h');
            else if V85>80 && V85<=85
                 
                 app.Button_631.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_632.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_633.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_634.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_635.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_636.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_637.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_638.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_639.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_640.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_641.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_642.BackgroundColor=[1.00,1.00,0.00];
                 app.Button_643.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_644.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_645.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_646.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_647.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_648.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_649.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_650.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_651.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_652.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_653.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_654.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_655.BackgroundColor=[0.96,0.96,0.96];
                 app.Matriz1(52)=85;
                 app.Matriz3(52)=cellstr('85 km/h');
            else if V85>85 && V85<=90
                 
                 app.Button_631.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_632.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_633.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_634.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_635.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_636.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_637.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_638.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_639.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_640.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_641.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_642.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_643.BackgroundColor=[1.00,1.00,0.00];
                 app.Button_644.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_645.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_646.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_647.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_648.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_649.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_650.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_651.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_652.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_653.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_654.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_655.BackgroundColor=[0.96,0.96,0.96];
                 app.Matriz1(52)=90;
                 app.Matriz3(52)=cellstr('90 km/h');
            else if V85>90 && V85<=95
                 
                 app.Button_631.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_632.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_633.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_634.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_635.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_636.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_637.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_638.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_639.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_640.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_641.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_642.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_643.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_644.BackgroundColor=[1.00,1.00,0.00];
                 app.Button_645.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_646.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_647.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_648.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_649.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_650.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_651.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_652.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_653.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_654.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_655.BackgroundColor=[0.96,0.96,0.96];
                 app.Matriz1(52)=95;
                 app.Matriz3(52)=cellstr('95 km/h');
            else if V85>95 && V85<=100
                 
                 app.Button_631.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_632.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_633.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_634.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_635.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_636.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_637.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_638.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_639.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_640.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_641.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_642.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_643.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_644.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_645.BackgroundColor=[1.00,1.00,0.00];
                 app.Button_646.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_647.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_648.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_649.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_650.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_651.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_652.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_653.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_654.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_655.BackgroundColor=[0.96,0.96,0.96];
                 app.Matriz1(52)=100;
                 app.Matriz3(52)=cellstr('100 km/h');
            else if V85>100 && V85<=105
                 
                 app.Button_631.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_632.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_633.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_634.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_635.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_636.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_637.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_638.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_639.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_640.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_641.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_642.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_643.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_644.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_645.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_646.BackgroundColor=[1.00,1.00,0.00];
                 app.Button_647.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_648.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_649.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_650.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_651.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_652.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_653.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_654.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_655.BackgroundColor=[0.96,0.96,0.96];
                 app.Matriz1(52)=105;
                 app.Matriz3(52)=cellstr('105 km/h');
            else if V85>105 && V85<=110
                 
                 app.Button_631.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_632.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_633.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_634.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_635.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_636.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_637.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_638.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_639.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_640.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_641.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_642.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_643.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_644.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_645.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_646.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_647.BackgroundColor=[1.00,1.00,0.00];
                 app.Button_648.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_649.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_650.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_651.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_652.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_653.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_654.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_655.BackgroundColor=[0.96,0.96,0.96];
                 app.Matriz1(52)=110;
                 app.Matriz3(52)=cellstr('110 km/h');
            else if V85>110 && V85<=115
                 
                 app.Button_631.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_632.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_633.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_634.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_635.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_636.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_637.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_638.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_639.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_640.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_641.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_642.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_643.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_644.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_645.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_646.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_647.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_648.BackgroundColor=[1.00,1.00,0.00];
                 app.Button_649.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_650.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_651.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_652.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_653.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_654.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_655.BackgroundColor=[0.96,0.96,0.96];
                 app.Matriz1(52)=115;
                 app.Matriz3(52)=cellstr('115 km/h');
            else if V85>115 && V85<=120
                 
                 app.Button_631.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_632.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_633.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_634.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_635.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_636.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_637.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_638.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_639.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_640.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_641.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_642.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_643.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_644.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_645.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_646.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_647.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_648.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_649.BackgroundColor=[1.00,1.00,0.00];
                 app.Button_650.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_651.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_652.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_653.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_654.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_655.BackgroundColor=[0.96,0.96,0.96];
                 app.Matriz1(52)=120;
                 app.Matriz3(52)=cellstr('120 km/h');
            else if V85>120 && V85<=125
                 
                 app.Button_631.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_632.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_633.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_634.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_635.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_636.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_637.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_638.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_639.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_640.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_641.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_642.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_643.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_644.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_645.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_646.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_647.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_648.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_649.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_650.BackgroundColor=[1.00,1.00,0.00];
                 app.Button_651.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_652.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_653.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_654.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_655.BackgroundColor=[0.96,0.96,0.96];
                 app.Matriz1(52)=125;
                 app.Matriz3(52)=cellstr('125 km/h');
            else if V85>125 && V85<=130
                 
                 app.Button_631.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_632.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_633.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_634.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_635.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_636.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_637.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_638.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_639.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_640.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_641.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_642.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_643.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_644.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_645.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_646.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_647.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_648.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_649.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_650.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_651.BackgroundColor=[1.00,1.00,0.00];
                 app.Button_652.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_653.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_654.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_655.BackgroundColor=[0.96,0.96,0.96];
                 app.Matriz1(52)=130;
                 app.Matriz3(52)=cellstr('130 km/h');     
            else if V85>130 && V85<=135
                 
                 app.Button_631.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_632.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_633.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_634.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_635.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_636.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_637.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_638.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_639.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_640.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_641.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_642.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_643.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_644.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_645.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_646.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_647.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_648.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_649.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_650.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_651.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_652.BackgroundColor=[1.00,1.00,0.00];
                 app.Button_653.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_654.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_655.BackgroundColor=[0.96,0.96,0.96];
                 app.Matriz1(52)=135;
                 app.Matriz3(52)=cellstr('135 km/h');
            else if V85>135 && V85<=140
                 
                 app.Button_631.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_632.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_633.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_634.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_635.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_636.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_637.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_638.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_639.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_640.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_641.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_642.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_643.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_644.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_645.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_646.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_647.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_648.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_649.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_650.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_651.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_652.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_653.BackgroundColor=[1.00,1.00,0.00];
                 app.Button_654.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_655.BackgroundColor=[0.96,0.96,0.96];
                 app.Matriz1(52)=140;
                 app.Matriz3(52)=cellstr('140 km/h');
            else if V85>140 && V85<=145
                 
                 app.Button_631.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_632.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_633.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_634.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_635.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_636.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_637.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_638.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_639.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_640.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_641.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_642.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_643.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_644.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_645.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_646.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_647.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_648.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_649.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_650.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_651.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_652.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_653.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_654.BackgroundColor=[1.00,1.00,0.00];
                 app.Button_655.BackgroundColor=[0.96,0.96,0.96];
                 app.Matriz1(52)=145;
                 app.Matriz3(52)=cellstr('145 km/h');
            else if V85>145 
                 
                 app.Button_631.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_632.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_633.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_634.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_635.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_636.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_637.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_638.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_639.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_640.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_641.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_642.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_643.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_644.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_645.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_646.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_647.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_648.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_649.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_650.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_651.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_652.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_653.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_654.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_655.BackgroundColor=[1.00,1.00,0.00];
                 app.Matriz1(52)=150;
                 app.Matriz3(52)=cellstr('150 km/h');      
             end
            end
            end
            end
            end
            end
            end
            end
            end
            end
            end
            end
            end
            end
            end
            end
            end
            end
            end
            end
            end
            end
            end
            end
             end
             
             %Eleccion de pendiente
             
             %Curvatura 
             if R>=900
                 app.Button_116.BackgroundColor=[1.00,1.00,0.00];
                 app.Button_115.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_79.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_78.BackgroundColor=[0.96,0.96,0.96];
            app.Matriz1(14)=1;
            app.Matriz3(14)=cellstr('Recto o ligeramente curvado');
             else if R>=500 && R<900
                 app.Button_116.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_115.BackgroundColor=[1.00,1.00,0.00];
                 app.Button_79.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_78.BackgroundColor=[0.96,0.96,0.96];
                 app.Matriz1(14)=2;
            app.Matriz3(14)=cellstr('Moderada');
             else if R>=200 && R<500
                 app.Button_116.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_115.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_79.BackgroundColor=[1.00,1.00,0.00];
                 app.Button_78.BackgroundColor=[0.96,0.96,0.96];
                 app.Matriz1(14)=3;
            app.Matriz3(14)=cellstr('Cerrada');
             else if R<=200 
                 app.Button_116.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_115.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_79.BackgroundColor=[0.96,0.96,0.96];
                 app.Button_78.BackgroundColor=[1.00,1.00,0.00];
                 app.Matriz1(14)=4;
            app.Matriz3(14)=cellstr('Muy Cerrada');
             end
             end
             end
             end
             %Eleccion pendiente
             if F >= 0 && F < 7.5
            app.Button_98.BackgroundColor=[1.00,1.00,0.00];
            app.Button_97.BackgroundColor=[0.96,0.96,0.96];
            app.Button_96.BackgroundColor=[0.96,0.96,0.96];
            app.Matriz1(16)=1;
            app.Matriz3(16)=cellstr('0 % a < 7,5 %');
             end
            
            if F < 0 && F >-7.5
            app.Button_98.BackgroundColor=[1.00,1.00,0.00];
            app.Button_97.BackgroundColor=[0.96,0.96,0.96];
            app.Button_96.BackgroundColor=[0.96,0.96,0.96];
            app.Matriz1(16)=1;
            app.Matriz3(16)=cellstr('0 % a < 7,5 %');
            end
            
            if F >= 7.5 && F < 10 
            app.Button_98.BackgroundColor=[0.96,0.96,0.96];
            app.Button_97.BackgroundColor=[1.00,1.00,0.00];
            app.Button_96.BackgroundColor=[0.96,0.96,0.96];
            app.Matriz1(16)=2;
            app.Matriz3(16)=cellstr('7,5 % a 10 %');
            end 
            if F <= -7.5 && F >- 10 
            app.Button_98.BackgroundColor=[0.96,0.96,0.96];
            app.Button_97.BackgroundColor=[1.00,1.00,0.00];
            app.Button_96.BackgroundColor=[0.96,0.96,0.96];
            app.Matriz1(16)=2;
            app.Matriz3(16)=cellstr('7,5 % a 10 %');
            end
            
            if F >= 10
            app.Button_98.BackgroundColor=[0.96,0.96,0.96];
            app.Button_97.BackgroundColor=[0.96,0.96,0.96];
            app.Button_96.BackgroundColor=[1.00,1.00,0.00];
            app.Matriz1(16)=3;
            app.Matriz3(16)=cellstr('≥ 10 %');
            end
            if F <= -10
            app.Button_98.BackgroundColor=[0.96,0.96,0.96];
            app.Button_97.BackgroundColor=[0.96,0.96,0.96];
            app.Button_96.BackgroundColor=[1.00,1.00,0.00];
            app.Matriz1(16)=3;
            app.Matriz3(16)=cellstr('≥ 10 %');
            end

            %Para el ingreso de datos
            load('../mat/preproceso_tiempo_img')
            k=app.v1
              A='';
              app.jpgFilename = sprintf('%i.jpg',Time_trams(k));
              fullFileName = fullfile('../fotogramas/', app.jpgFilename);
              if exist(fullFileName, 'file')
                imageData = imread(fullFileName);
              else
                warningMessage = sprintf('Advertencia: la imagen no existe en el archivo: \ n% s', fullFileName);
                uiwait(warndlg(warningMessage));
              end
              app.Image.ImageSource=imageData;
            
            %GRAFICAR
            Tramos=[tramo(app.v1).Tramos];
            Distancia=[tramo(app.v1).Distancia];
            Xt=([tramo(app.v1).Xt]);
            yt=([tramo(app.v1).yt]);
            met_cord=size(Xt,1)
            if met_cord>1
                plot(app.UIAxes,Xt,yt)
            else
                sin_coord = imread('../img/blancoo.jpg')
                imshow(sin_coord,'Parent',app.UIAxes)
            end

            
             %CUANDO SPINNER MAYOR A 1
           if app.v1==1
           load('../mat/Datos_atributos_proceso')
           load('../mat/Datos_interfaz_uno_proceso')
           load('../mat/Datos_interfaz_dos_proceso')
           load('../mat/Coeficientes_Veh')
           load('../mat/Coeficientes_Mot')
           load('../mat/Coeficientes_Ci')
           load('../mat/Coeficientes_Pe')
           load('../mat/Datos_interfaz_tres_proceso')
            %Botones seleccionados de partida
            %%COSTADO DE LA VÍA
            %Severidad lateral - distancia al objeto (lado del conductor)
            value = app.Button_4.Value;
            A4=4;
            B4=cellstr('≥ 10 m');
            c4=cellstr('app.Button_4');
            app.Matriz2(1)=c4;
            app.Matriz1(1)=A4;
            app.Matriz3(1)=B4;
            %app.Button_4.Value=1;
            app.Button_4.BackgroundColor=[1.00,1.00,0.00];
            %Severidad lateral - objeto (lado del conductor)
            value = app.Button_520.Value;
            A15=11;
            B15=cellstr('Árbol (≥ 10 cm de diámetro)');
            c15=cellstr('app.Button_520');
            app.Matriz2(2)=c15;
            app.Matriz1(2)=A15;
            app.Matriz3(2)=B15;
            %app.Button_520.Value=1;
            app.Button_520.BackgroundColor=[1.00,1.00,0.00];
            %Severidad lateral - distancia al objeto (lado del copiloto)
            value = app.Button_6.Value;
            A23=2;
            B23=cellstr('1 a < 5 m');
            c23=cellstr('app.Button_6');
            app.Matriz2(3)=c23;
            app.Matriz1(3)=A23;
            app.Matriz3(3)=B23;
            %app.Button_6.Value=1;
            app.Button_6.BackgroundColor=[1.00,1.00,0.00];
            %Severidad lateral - objeto (lado del copiloto)
            value = app.Button_667.Value;
            A36=11;
            B36=cellstr('Árbol (≥ 10 cm de diámetro)');
            c36=cellstr('app.Button_667');
            app.Matriz2(4)=c36;
            app.Matriz1(4)=A36;
            app.Matriz3(4)=B36;
            %app.Button_667.Value=1;
            app.Button_667.BackgroundColor=[1.00,1.00,0.00];
            %Bandas sonoras en el espaldón
            value = app.Button_9.Value;
            A43=1;
            B43=cellstr('Ausente');
            c43=cellstr('app.Button_9');
            app.Matriz2(5)=c43;
            app.Matriz1(5)=A43;
            app.Matriz3(5)=B43;
            %app.Button_9.Value=1;
            app.Button_9.BackgroundColor=[1.00,1.00,0.00];
            %Espaldón del lado conductor
            value = app.Button_14.Value;
            A48=4;
            B48=cellstr('Ninguno');
            c48=cellstr('app.Button_14');
            app.Matriz2(6)=c48;
            app.Matriz1(6)=A48;
            app.Matriz3(6)=B48;
            %app.Button_14.Value=1;
            app.Button_14.BackgroundColor=[1.00,1.00,0.00];
            %Espaldón del lado copiloto
            value = app.Button_686.Value;
            A52=4;
            B52=cellstr('Ninguno');
            c52=cellstr('app.Button_686');
            app.Matriz2(7)=c52;
            app.Matriz1(7)=A52;
            app.Matriz3(7)=B52;
            %app.Button_686.Value=1;
            app.Button_686.BackgroundColor=[1.00,1.00,0.00];
            %%MEDIANA
            %Tipo de carretera 
            value = app.Button_71.Value;
            A55=2;
            B55=cellstr('Calzada B de una calzada dividida');
            c55=cellstr('app.Button_71');
            app.Matriz2(8)=c55;
            app.Matriz1(8)=A55;
            app.Matriz3(8)=B55;
            app.Button_71.Value=1;
            app.Button_71.BackgroundColor=[1.00,1.00,0.00];
            %Costo de modernización 
            value = app.Button_491.Value;
            A59=1;
            B59=cellstr('Bajo');
            c59=cellstr('app.Button_491');
            app.Matriz2(9)=c59;
            app.Matriz1(9)=A59;
            app.Matriz3(9)=B59;
            %app.Button_491.Value=1;
            app.Button_491.BackgroundColor=[1.00,1.00,0.00];
            %Tipo de Mediana
            value = app.Button_104.Value;
            A68=7;
            B68=cellstr('Mediana física con un ancho ≥ 0 m a < 1 m');
            c68=cellstr('app.Button_104');
            app.Matriz2(10)=c68;
            app.Matriz1(10)=A68;
            app.Matriz3(10)=B68;
            %app.Button_104.Value=1;
            app.Button_104.BackgroundColor=[1.00,1.00,0.00];
            %Bandas sonoras centrales
            value = app.Button_557.Value;
            A77=1;
            B77=cellstr('Ausente');
            c77=cellstr('app.Button_557');
            app.Matriz2(11)=c77;
            app.Matriz1(11)=A77;
            app.Matriz3(11)=B77;
            %app.Button_557.Value=1;
            app.Button_557.BackgroundColor=[1.00,1.00,0.00];
            %Número de carriles
            value = app.Button_661.Value;
            A80=2;
            B80=cellstr('Dos');
            c80=cellstr('app.Button_661');
            app.Matriz2(12)=c80;
            app.Matriz1(12)=A80;
            app.Matriz3(12)=B80;
            %app.Button_661.Value=1;
            app.Button_661.BackgroundColor=[1.00,1.00,0.00];
            %Ancho de carril y %Tipo de area
            value = app.Button_611.Value;
            A177=2;
            B177=cellstr('Urbano/ población pequeña o pueblo');
            c177=cellstr('app.Button_611');
            app.Matriz2(38)=c177;
            app.Matriz1(38)=A177;
            app.Matriz3(38)=B177;
            
            app.Button_612.BackgroundColor=[0.96,0.96,0.96];
            app.Button_611.BackgroundColor=[1.00,1.00,0.00];
            
            value = app.CarrilP.Value;
            A87=3;
            B87=cellstr('Estrecho (≥ 0 m a < 2,75 m)');
            c87=cellstr('app.CarrilP');
            app.Matriz2(13)=c87;
            app.Matriz1(13)=A87;
            app.Matriz3(13)=B87;
            %app.CarrilP.Value=1;
            app.CarrilP.BackgroundColor=[1.00,1.00,0.00];
            %Curvatura
            %value = app.Button_116.Value;
            %A88=1;
            %B88=cellstr('Recto o ligeramente curvado');
            %c88=cellstr('app.Button_116');
            %app.Matriz2(14)=c88;
            %%app.Matriz1(14)=A88;
            %app.Matriz3(14)=B88;
            %app.Button_116.Value=1;
            %app.Button_116.BackgroundColor=[1.00,1.00,0.00];
            %Calidad de la curva
            value = app.Button_94.Value;
            A94=3;
            B94=cellstr('No aplica');
            c94=cellstr('app.Button_94');
            app.Matriz2(15)=c94;
            app.Matriz1(15)=A94;
            app.Matriz3(15)=B94;
            %app.Button_94.Value=1;
            app.Button_94.BackgroundColor=[1.00,1.00,0.00];
            %Pendiente
            %value = app.Button_98.Value;
            %A95=1;
            %B95=cellstr('0 % a < 7,5 %');
            %c95=cellstr('app.Button_98');
            %app.Matriz2(16)=c95;
            %app.Matriz1(16)=A95;
            %app.Matriz3(16)=B95;
            %app.Button_98.Value=1;
            %app.Button_98.BackgroundColor=[1.00,1.00,0.00];
            %Estado de la superficie de la vía
            value = app.Button_562.Value;
            A98=1;
            B98=cellstr('Buena');
            c98=cellstr('app.Button_562');
            app.Matriz2(17)=c98;
            app.Matriz1(17)=A98;
            app.Matriz3(17)=B98;
            app.Button_562.BackgroundColor=[1.00,1.00,0.00];
            app.Button_561.BackgroundColor=[0.96,0.96,0.96];
            app.Button_560.BackgroundColor=[0.96,0.96,0.96];
            %Resistencia al deslizamiento
            value = app.Button_101.Value;
            A102=2;
            B102=cellstr('Pavimentado – regular');
            c102=cellstr('app.Button_101');
            app.Matriz2(18)=c102;
            app.Matriz1(18)=A102;
            app.Matriz3(18)=B102;
            
            app.Button_126.BackgroundColor=[0.96,0.96,0.96];
            app.Button_101.BackgroundColor=[1.00,1.00,0.00];
            app.Button_127.BackgroundColor=[0.96,0.96,0.96];
            app.Button_100.BackgroundColor=[0.96,0.96,0.96];
            app.Button_128.BackgroundColor=[0.96,0.96,0.96];
            %Delineación
            value = app.Button_563.Value;
            A107=2;
            B107=cellstr('Deficiente');
            c107=cellstr('app.Button_563');
            app.Matriz2(19)=c107;
            app.Matriz1(19)=A107;
            app.Matriz3(19)=B107;
            
            app.Button_564.BackgroundColor=[0.96,0.96,0.96];
            app.Button_563.BackgroundColor=[1.00,1.00,0.00];
            %Alumbrado público
            value = app.Button_566.Value;
            A108=1;
            B108=cellstr('Ausente');
            c108=cellstr('app.Button_566');
            app.Matriz2(20)=c108;
            app.Matriz1(20)=A108;
            app.Matriz3(20)=B108;
            
            app.Button_565.BackgroundColor=[0.96,0.96,0.96];
            app.Button_566.BackgroundColor=[1.00,1.00,0.00];
            %Estacionamiento para vehículos
            value = app.Button_150.Value;
            A111=2;
            B111=cellstr('Un lado');
            c111=cellstr('app.Button_150');
            app.Matriz2(21)=c111;
            app.Matriz1(21)=A111;
            app.Matriz3(21)=B111;
            
            app.Button_151.BackgroundColor=[0.96,0.96,0.96];
            app.Button_150.BackgroundColor=[1.00,1.00,0.00];
            app.Button_567.BackgroundColor=[0.96,0.96,0.96];
            %Obras en la carretera
            value = app.Button_141.Value;
            A116=2;
            B116=cellstr('Obra menor en proceso');
            c116=cellstr('app.Button_141');
            app.Matriz2(23)=c116;
            app.Matriz1(23)=A116;
            app.Matriz3(23)=B116;
            
            app.Button_139.BackgroundColor=[0.96,0.96,0.96];
            app.Button_141.BackgroundColor=[1.00,1.00,0.00];
            app.Button_138.BackgroundColor=[0.96,0.96,0.96];
            %Distancia de visibilidad
            value = app.Button_145.Value;
            A118=1;
            B118=cellstr('Adecuada');
            c118=cellstr('app.Button_145');
            app.Matriz2(24)=c118;
            app.Matriz1(24)=A118;
            app.Matriz3(24)=B118;
            
            app.Button_145.BackgroundColor=[1.00,1.00,0.00];
            app.Button_144.BackgroundColor=[0.96,0.96,0.96];
            %Vía de servicio
            value = app.SiButton_18.Value;
            A114=2;
            B114=cellstr('Presente');
            c114=cellstr('app.SiButton_18');
            app.Matriz2(22)=c114;
            app.Matriz1(22)=A114;
            app.Matriz3(22)=B114;
            
            app.SiButton_18.BackgroundColor=[1.00,1.00,0.00];
            app.NoButton_19.BackgroundColor=[0.96,0.96,0.96];
            %%INTERSECCIONES
            %Tipo de intersección
            value = app.Button_179.Value;
            A124=4;
            B124=cellstr('3 ramas (no semaforizada) con carril de giro no protegido');
            c124=cellstr('app.Button_179');
            app.Matriz2(25)=c124;
            app.Matriz1(25)=A124;
            app.Matriz3(25)=B124;
            
            app.Button_207.BackgroundColor=[0.96,0.96,0.96];
            app.Button_185.BackgroundColor=[0.96,0.96,0.96];
            app.Button_182.BackgroundColor=[0.96,0.96,0.96];
            app.Button_179.BackgroundColor=[1.00,1.00,0.00];
            app.Button_183.BackgroundColor=[0.96,0.96,0.96];
            app.Button_181.BackgroundColor=[0.96,0.96,0.96];
            app.Button_176.BackgroundColor=[0.96,0.96,0.96];
            app.Button_152.BackgroundColor=[0.96,0.96,0.96];
            app.Button_180.BackgroundColor=[0.96,0.96,0.96];
            app.Button_177.BackgroundColor=[0.96,0.96,0.96];
            app.Button_174.BackgroundColor=[0.96,0.96,0.96];
            app.Button_178.BackgroundColor=[0.96,0.96,0.96];
            app.Button_184.BackgroundColor=[0.96,0.96,0.96];
            app.Button_154.BackgroundColor=[0.96,0.96,0.96];
            app.Button_155.BackgroundColor=[0.96,0.96,0.96];
            app.Button_153.BackgroundColor=[0.96,0.96,0.96];
            %Transitabilidad en la mediana
            %value=app.TransitableButton.Value;
            A1110=1;
            c1110=cellstr('app.TransitableButton');
            b1110=cellstr('Transitable');
            app.Matriz1(53)=A1110;
            app.Matriz2(53)=c1110;
            app.Matriz3(53)=b1110;
            
            app.TransitableButton.BackgroundColor=[1.00,1.00,0.00];
            app.NoTransitableButton.BackgroundColor=[0.96,0.96,0.96];
            %Canalización de la intersección
            value = app.Button_569.Value;
            A138=1;
            B138=cellstr('Ausente');
            c138=cellstr('app.Button_569');
            app.Matriz2(26)=c138;
            app.Matriz1(26)=A138;
            app.Matriz3(26)=B138;
            
            app.Button_569.BackgroundColor=[1.00,1.00,0.00];
            app.Button_568.BackgroundColor=[0.96,0.96,0.96];
            %Volumen de la intersección
            value = app.Button_211.Value;
            A143=4;
            B143=cellstr('1,000 a 5,000 vehículos');
            c143=cellstr('app.Button_211');
            app.Matriz2(27)=c143;
            app.Matriz1(27)=A143;
            app.Matriz3(27)=B143;
            
            app.Button_189.BackgroundColor=[0.96,0.96,0.96];
            app.Button_198.BackgroundColor=[0.96,0.96,0.96];
            app.Button_210.BackgroundColor=[0.96,0.96,0.96];
            app.Button_211.BackgroundColor=[1.00,1.00,0.00];
            app.Button_212.BackgroundColor=[0.96,0.96,0.96];
            app.Button_208.BackgroundColor=[0.96,0.96,0.96];
            app.Button_209.BackgroundColor=[0.96,0.96,0.96];
            %Calidad de la intersección
            value = app.Button_213.Value;
            A148=2;
            B148=cellstr('Deficiente');
            c148=cellstr('app.Button_213');
            app.Matriz2(28)=c148;
            app.Matriz1(28)=A148;
            app.Matriz3(28)=B148;
            
            app.Button_214.BackgroundColor=[0.96,0.96,0.96];
            app.Button_213.BackgroundColor=[1.00,1.00,0.00];
            app.Button_215.BackgroundColor=[0.96,0.96,0.96];
            %Puntos de acceso a la propiedad
            value = app.Button_169.Value;
            A150=1;
            B150=cellstr('Acceso comercial 1+');
            c150=cellstr('app.Button_169');
            app.Matriz2(29)=c150;
            app.Matriz1(29)=A150;
            app.Matriz3(29)=B150;
            
            app.Button_169.BackgroundColor=[1.00,1.00,0.00];
            app.Button_170.BackgroundColor=[0.96,0.96,0.96];
            app.Button_171.BackgroundColor=[0.96,0.96,0.96];
            app.Button_216.BackgroundColor=[0.96,0.96,0.96];
            %%FLUJO
            %Flujo vehicular (TPDA)
            value = 15000;
            app.Matriz1(30)=value;
            if value>=0 & value <8000
                Dato=cellstr('0 - 8000');
            else if value>=8000 & value<16000
                Dato=cellstr('8000 - 16000');
            else if value>=16000&value<24000
                Dato=cellstr('16000 - 24000');
            else if value>=24000&value<32000
                Dato=cellstr('24000 - 32000');
            else if value>=32000 & value<40000
                Dato=cellstr('32000 - 40000');
            else if value>=40000&value<48000
                Dato=cellstr('40000 - 48000');
            else if value>=48000&value<56000
                Dato=cellstr('48000 - 56000');
            else if value>=56000&value<64000
                Dato=cellstr('56000 - 64000');
            else if value>=64000
                Dato=cellstr('64000+');
            end
            end
            end
            end
            end
            end
            end
            end
            end
            app.Matriz3(30)=Dato;
            
            %Porcentaje de motocicletas
            value = app.Button_261.Value;
            A156_5=6;
            B156_5=cellstr('21 % - 40 %');
            c156_5=cellstr('app.Button_261');
            app.Matriz2(31)=c156_5; 
            app.Matriz1(31)=A156_5;
            app.Matriz3(31)=B156_5;
            
            app.NAButton.BackgroundColor=[0.96,0.96,0.96];
            app.Button_270.BackgroundColor=[0.96,0.96,0.96];
            app.Button_271.BackgroundColor=[0.96,0.96,0.96];
            app.Button_263.BackgroundColor=[0.96,0.96,0.96];
            app.Button_264.BackgroundColor=[0.96,0.96,0.96];
            app.Button_261.BackgroundColor=[1.00,1.00,0.00];
            app.Button_262.BackgroundColor=[0.96,0.96,0.96];
            app.Button_275.BackgroundColor=[0.96,0.96,0.96];
            app.Button_276.BackgroundColor=[0.96,0.96,0.96];
            app.Button_712.BackgroundColor=[0.96,0.96,0.96];
            %Flujo peatonal en hora pico que cruza la carretera
            value = app.Button_277.Value;
            A157_3=4;
            B157_3=cellstr('26 a 50');
            c157_3=cellstr('app.Button_277');
            app.Matriz2(32)=c157_3; 
            app.Matriz1(32)=A157_3;
            app.Matriz3(32)=B157_3;
            
            app.Button_265.BackgroundColor=[0.96,0.96,0.96];
            app.Button_266.BackgroundColor=[0.96,0.96,0.96];
            app.Button_267.BackgroundColor=[0.96,0.96,0.96];
            app.Button_277.BackgroundColor=[1.00,1.00,0.00];
            app.Button_278.BackgroundColor=[0.96,0.96,0.96];
            app.Button_279.BackgroundColor=[0.96,0.96,0.96];
            app.Button_280.BackgroundColor=[0.96,0.96,0.96];
            app.Button_281.BackgroundColor=[0.96,0.96,0.96];
            app.Button_282.BackgroundColor=[0.96,0.96,0.96];
            app.Button_283.BackgroundColor=[0.96,0.96,0.96];
            app.Button_284.BackgroundColor=[0.96,0.96,0.96];
            %Flujo peatonal en hora pico a lo largo de la carretera (lado
            %del conductor)%
            value = app.Button_577.Value;
            A158_5=6;
            B158_5=cellstr('101 a 200');
            c158_5=cellstr('app.Button_577');
            app.Matriz2(33)=c158_5; 
            app.Matriz1(33)=A158_5;
            app.Matriz3(33)=B158_5;
            
            app.Button_572.BackgroundColor=[0.96,0.96,0.96];
            app.Button_573.BackgroundColor=[0.96,0.96,0.96];
            app.Button_574.BackgroundColor=[0.96,0.96,0.96];
            app.Button_575.BackgroundColor=[0.96,0.96,0.96];
            app.Button_576.BackgroundColor=[0.96,0.96,0.96];
            app.Button_577.BackgroundColor=[1.00,1.00,0.00];
            app.Button_578.BackgroundColor=[0.96,0.96,0.96];
            app.Button_579.BackgroundColor=[0.96,0.96,0.96];
            app.Button_580.BackgroundColor=[0.96,0.96,0.96];
            app.Button_581.BackgroundColor=[0.96,0.96,0.96];
            app.Button_582.BackgroundColor=[0.96,0.96,0.96];
            %Flujo peatonal en hora pico a lo largo de la carretera (lado
            %del copiloto)%
            value = app.Button_588.Value;
            A159_5=6;
            B159_5=cellstr('101 a 200');
            c159_5=cellstr('app.Button_588');
            app.Matriz2(34)=c159_5;
            app.Matriz1(34)=A159_5;
            app.Matriz3(34)=B159_5;
            
            app.Button_583.BackgroundColor=[0.96,0.96,0.96];
            app.Button_584.BackgroundColor=[0.96,0.96,0.96];
            app.Button_585.BackgroundColor=[0.96,0.96,0.96];
            app.Button_586.BackgroundColor=[0.96,0.96,0.96];
            app.Button_587.BackgroundColor=[0.96,0.96,0.96];
            app.Button_588.BackgroundColor=[1.00,1.00,0.00];
            app.Button_589.BackgroundColor=[0.96,0.96,0.96];
            app.Button_590.BackgroundColor=[0.96,0.96,0.96];
            app.Button_591.BackgroundColor=[0.96,0.96,0.96];
            app.Button_592.BackgroundColor=[0.96,0.96,0.96];
            app.Button_593.BackgroundColor=[0.96,0.96,0.96];
            %Flujo de bicicletas en hora pico
            value = app.Button_598.Value;
            A160_4=5;
            B160_4=cellstr('51 a 100');
            c160_4=cellstr('app.Button_598');
            app.Matriz2(35)=c160_4;
            app.Matriz1(35)=A160_4;
            app.Matriz3(35)=B160_4;
            
            app.Button_594.BackgroundColor=[0.96,0.96,0.96];
            app.Button_595.BackgroundColor=[0.96,0.96,0.96];
            app.Button_596.BackgroundColor=[0.96,0.96,0.96];
            app.Button_597.BackgroundColor=[0.96,0.96,0.96];
            app.Button_598.BackgroundColor=[1.00,1.00,0.00];
            app.Button_599.BackgroundColor=[0.96,0.96,0.96];
            app.Button_600.BackgroundColor=[0.96,0.96,0.96];
            app.Button_601.BackgroundColor=[0.96,0.96,0.96];
            app.Button_602.BackgroundColor=[0.96,0.96,0.96];
            app.Button_603.BackgroundColor=[0.96,0.96,0.96];
            app.Button_604.BackgroundColor=[0.96,0.96,0.96];
            %%USOS
            %Uso de suelo (lado del conductor)
            value = app.Button_318.Value;
            A165=4;
            B165=cellstr('Comercial');
            c165=cellstr('app.Button_318');
            app.Matriz2(36)=c165;
            app.Matriz1(36)=A165;
            app.Matriz3(36)=B165;
            
            
            app.Button_340.BackgroundColor=[0.96,0.96,0.96];
            app.Button_321.BackgroundColor=[0.96,0.96,0.96];
            app.Button_320.BackgroundColor=[0.96,0.96,0.96];
            app.Button_318.BackgroundColor=[1.00,1.00,0.00];
            app.Button_425.BackgroundColor=[0.96,0.96,0.96];
            app.Button_319.BackgroundColor=[0.96,0.96,0.96];
            %Uso de suelo (lado del copiloto)
             value = app.Button_706.Value;
            A172=4;
            B172=cellstr('Comercial');
            c172=cellstr('app.Button_706');
            app.Matriz2(37)=c172;
            app.Matriz1(37)=A172;
            app.Matriz3(37)=B172;

            
            app.Button_710.BackgroundColor=[0.96,0.96,0.96];
            app.Button_709.BackgroundColor=[0.96,0.96,0.96];
            app.Button_708.BackgroundColor=[0.96,0.96,0.96];
            app.Button_706.BackgroundColor=[1.00,1.00,0.00];
            app.Button_711.BackgroundColor=[0.96,0.96,0.96];
            app.Button_107.BackgroundColor=[0.96,0.96,0.96];
            %Infraestructura de cruce peatonal en el tramo analizado
            A184=7;
            B184=cellstr('Sin infraestructura');
            c184=cellstr('app.Button_386');
            app.Matriz2(39)=c184;
            app.Matriz1(39)=A184;
            app.Matriz3(39)=B184;
            
            value = app.Button_386.Value;
            
            app.Button_384.BackgroundColor=[0.96,0.96,0.96];
            app.Button_383.BackgroundColor=[0.96,0.96,0.96];
            app.Button_390.BackgroundColor=[0.96,0.96,0.96];
            app.Button_382.BackgroundColor=[0.96,0.96,0.96];
            app.Button_381.BackgroundColor=[0.96,0.96,0.96];
            app.Button_380.BackgroundColor=[0.96,0.96,0.96];
            app.Button_386.BackgroundColor=[1.00,1.00,0.00];
            app.Button_387.BackgroundColor=[0.96,0.96,0.96];
            app.Button_385.BackgroundColor=[0.96,0.96,0.96];
            app.Button_388.BackgroundColor=[0.96,0.96,0.96];
            app.Button_389.BackgroundColor=[0.96,0.96,0.96];
            %Calidad de cruce peatonal
            A191=3;
            B191=cellstr('No aplica');
            c191=cellstr('app.Button_356');
            app.Matriz2(40)=c191;
            app.Matriz1(40)=A191;
            app.Matriz3(40)=B191;
            
            value = app.Button_356.Value;
            
            app.Button_357.BackgroundColor=[0.96,0.96,0.96];
            app.Button_329.BackgroundColor=[0.96,0.96,0.96];
            app.Button_356.BackgroundColor=[1.00,1.00,0.00];
            %Infraestructura de cruce peatonal en la intersección
            A198=7;
            B198=cellstr('Sin infraestructura');
            c198=cellstr('app.Button_693');
            app.Matriz2(41)=c198;
            app.Matriz1(41)=A198;
            app.Matriz3(41)=B198;
            
            value = app.Button_693.Value;
            
            app.Button_691.BackgroundColor=[0.96,0.96,0.96];
            app.Button_690.BackgroundColor=[0.96,0.96,0.96];
            app.Button_697.BackgroundColor=[0.96,0.96,0.96];
            app.Button_689.BackgroundColor=[0.96,0.96,0.96];
            app.Button_688.BackgroundColor=[0.96,0.96,0.96];
            app.Button_687.BackgroundColor=[0.96,0.96,0.96];
            app.Button_693.BackgroundColor=[1.00,1.00,0.00];
            app.Button_694.BackgroundColor=[0.96,0.96,0.96];
            app.Button_692.BackgroundColor=[0.96,0.96,0.96];
            app.Button_695.BackgroundColor=[0.96,0.96,0.96];
            app.Button_696.BackgroundColor=[0.96,0.96,0.96];
            %Vallas peatonales
            value = app.Button_333.Value;
            A205=2;
            B205=cellstr('Ausente');
            c205=cellstr('app.Button_333');
            app.Matriz2(42)=c205;
            app.Matriz1(42)=A205;
            app.Matriz3(42)=B205;

            app.Button_332.BackgroundColor=[0.96,0.96,0.96];
            app.Button_333.BackgroundColor=[1.00,1.00,0.00];
            %Acera (lado conductor)
            value = app.Button_335.Value;
            A210=5;
            B210=cellstr('Ninguno');
            c210=cellstr('app.Button_335');
            app.Matriz2(43)=c210; 
            app.Matriz1(43)=A210;
            app.Matriz3(43)=B210;
            
            app.Button_403.BackgroundColor=[0.96,0.96,0.96];
            app.Button_402.BackgroundColor=[0.96,0.96,0.96];
            app.Button_337.BackgroundColor=[0.96,0.96,0.96];
            app.Button_336.BackgroundColor=[0.96,0.96,0.96];
            app.Button_335.BackgroundColor=[1.00,1.00,0.00];
            app.Button_404.BackgroundColor=[0.96,0.96,0.96];
            app.Button_405.BackgroundColor=[0.96,0.96,0.96];
            %Acera (lado copiloto)
            A219=7;
            B219=cellstr('Camino informal de 0 m a < 1,0 m');
            c219=cellstr('app.Button_704');
            app.Matriz2(44)=c219;
            app.Matriz1(44)=A219;
            app.Matriz3(44)=B219;
            
            value = app.Button_704.Value;
            
            app.Button_702.BackgroundColor=[0.96,0.96,0.96];
            app.Button_701.BackgroundColor=[0.96,0.96,0.96];
            app.Button_700.BackgroundColor=[0.96,0.96,0.96];
            app.Button_699.BackgroundColor=[0.96,0.96,0.96];
            app.Button_698.BackgroundColor=[0.96,0.96,0.96];
            app.Button_703.BackgroundColor=[0.96,0.96,0.96];
            app.Button_704.BackgroundColor=[1.00,1.00,0.00];
            %Infraestructura para motocicletas
            value = app.Button_413.Value;
            A225=6;
            B225=cellstr('Ninguno');
            c225=cellstr('app.Button_413');
            app.Matriz2(45)=c225;
            app.Matriz1(45)=A225;
            app.Matriz3(45)=B225;

            
            app.Button_416.BackgroundColor=[0.96,0.96,0.96];
            app.Button_415.BackgroundColor=[0.96,0.96,0.96];
            app.Button_418.BackgroundColor=[0.96,0.96,0.96];
            app.Button_417.BackgroundColor=[0.96,0.96,0.96];
            app.Button_414.BackgroundColor=[0.96,0.96,0.96];
            app.Button_413.BackgroundColor=[1.00,1.00,0.00];
            %Infraestructura para bicicletas
            value = app.Button_419.Value;
            A229=4;
            B229=cellstr('Ninguno');
            c229=cellstr('app.Button_419');
            app.Matriz2(46)=c229;
            app.Matriz1(46)=A229;
            app.Matriz3(46)=B229;

            
            app.Button_424.BackgroundColor=[0.96,0.96,0.96];
            app.Button_705.BackgroundColor=[0.96,0.96,0.96];
            app.Button_420.BackgroundColor=[0.96,0.96,0.96];
            app.Button_419.BackgroundColor=[1.00,1.00,0.00];
            app.Button_422.BackgroundColor=[0.96,0.96,0.96];
            app.Button_423.BackgroundColor=[0.96,0.96,0.96];
            app.Button_421.BackgroundColor=[0.96,0.96,0.96];
            %Advertencia de zona escolar
            value = app.Button_429.Value;
            A236=4;
            B236=cellstr('No aplica (no hay escuela en el lugar) ');
            c236=cellstr('app.Button_429');
            app.Matriz2(47)=c236;
            app.Matriz1(47)=A236;
            app.Matriz3(47)=B236;
  
            app.Button_427.BackgroundColor=[0.96,0.96,0.96];
            app.Button_428.BackgroundColor=[0.96,0.96,0.96];
            app.Button_430.BackgroundColor=[0.96,0.96,0.96];
            app.Button_429.BackgroundColor=[1.00,1.00,0.00];
            %Supervisor en el cruce de zona escolar
            value = app.Button_433.Value;
            A239=3;
            B239=cellstr('No aplica (no hay escuela en el lugar)');
            c239=cellstr('app.Button_433');
            app.Matriz2(48)=c239;
            app.Matriz1(48)=A239;
            app.Matriz3(48)=B239;

            app.Button_432.BackgroundColor=[0.96,0.96,0.96];
            app.Button_431.BackgroundColor=[0.96,0.96,0.96];
            app.Button_433.BackgroundColor=[1.00,1.00,0.00];
            %%VELOCIDADES
            %Límite de velocidad
            A247=60;
            B247=cellstr('60 km/h');
            c247=cellstr('app.Button_447');
            app.Matriz2(49)=c247;
            app.Matriz1(49)=A247;
            app.Matriz3(49)=B247;
            
            value = app.Button_447.Value;
            app.Button_434.BackgroundColor=[0.96,0.96,0.96];
            app.Button_435.BackgroundColor=[0.96,0.96,0.96];
            app.Button_436.BackgroundColor=[0.96,0.96,0.96];
            app.Button_437.BackgroundColor=[0.96,0.96,0.96];
            app.Button_438.BackgroundColor=[0.96,0.96,0.96];
            app.Button_446.BackgroundColor=[0.96,0.96,0.96];
            app.Button_447.BackgroundColor=[1.00,1.00,0.00];
            app.Button_448.BackgroundColor=[0.96,0.96,0.96];
            app.Button_449.BackgroundColor=[0.96,0.96,0.96];
            app.Button_450.BackgroundColor=[0.96,0.96,0.96];
            app.Button_451.BackgroundColor=[0.96,0.96,0.96];
            app.Button_452.BackgroundColor=[0.96,0.96,0.96];
            app.Button_453.BackgroundColor=[0.96,0.96,0.96];
            app.Button_454.BackgroundColor=[0.96,0.96,0.96];
            app.Button_455.BackgroundColor=[0.96,0.96,0.96];
            app.Button_456.BackgroundColor=[0.96,0.96,0.96];
            app.Button_457.BackgroundColor=[0.96,0.96,0.96];
            app.Button_458.BackgroundColor=[0.96,0.96,0.96];
            app.Button_459.BackgroundColor=[0.96,0.96,0.96];
            app.Button_460.BackgroundColor=[0.96,0.96,0.96];
            app.Button_461.BackgroundColor=[0.96,0.96,0.96];
            app.Button_462.BackgroundColor=[0.96,0.96,0.96];
            app.Button_463.BackgroundColor=[0.96,0.96,0.96];
            app.Button_464.BackgroundColor=[0.96,0.96,0.96];
            app.Button_465.BackgroundColor=[0.96,0.96,0.96];
            %Diferencial de límite de velocidad
            value = app.Button_659.Value;
            A266=1;
            B266=cellstr('Ausente');
            c266=cellstr('app.Button_659');
            app.Matriz2(50)=c266;
            app.Matriz1(50)=A266;
            app.Matriz3(50)=B266;

            app.Button_658.BackgroundColor=[0.96,0.96,0.96];
            app.Button_659.BackgroundColor=[1.00,1.00,0.00];
            %Velocidad de operación (percentil 85)
            %A272=50;
            %B272=cellstr('50 km/h');
            %c272=cellstr('app.Button_635');
            %app.Matriz2(52)=c272;
            %app.Matriz1(52)=A272;
            %app.Matriz3(52)=B272;
            
            %value = app.Button_635.Value;
            %app.Button_631.BackgroundColor=[0.96,0.96,0.96];
            %app.Button_632.BackgroundColor=[0.96,0.96,0.96];
            %app.Button_633.BackgroundColor=[0.96,0.96,0.96];
            %app.Button_634.BackgroundColor=[0.96,0.96,0.96];
            %app.Button_636.BackgroundColor=[0.96,0.96,0.96];
            %app.Button_637.BackgroundColor=[0.96,0.96,0.96];
            %app.Button_638.BackgroundColor=[0.96,0.96,0.96];
            %app.Button_639.BackgroundColor=[0.96,0.96,0.96];
            %app.Button_640.BackgroundColor=[0.96,0.96,0.96];
            %app.Button_641.BackgroundColor=[0.96,0.96,0.96];
            %app.Button_642.BackgroundColor=[0.96,0.96,0.96];
            %app.Button_643.BackgroundColor=[0.96,0.96,0.96];
            %app.Button_644.BackgroundColor=[0.96,0.96,0.96];
            %app.Button_645.BackgroundColor=[0.96,0.96,0.96];
            %app.Button_646.BackgroundColor=[0.96,0.96,0.96];
            %app.Button_647.BackgroundColor=[0.96,0.96,0.96];
            %app.Button_648.BackgroundColor=[0.96,0.96,0.96];
            %app.Button_649.BackgroundColor=[0.96,0.96,0.96];
            %app.Button_650.BackgroundColor=[0.96,0.96,0.96];
            %app.Button_651.BackgroundColor=[0.96,0.96,0.96];
            %app.Button_652.BackgroundColor=[0.96,0.96,0.96];
            %app.Button_653.BackgroundColor=[0.96,0.96,0.96];
            %app.Button_655.BackgroundColor=[0.96,0.96,0.96];
            %Gestión de la velocidad/calmante del tráfico
            value = app.Button_656.Value;
            A293=1;
            B293=cellstr('Ausente');
            c293=cellstr('app.Button_656');
            app.Matriz2(51)=c293;
            app.Matriz1(51)=A293;
            app.Matriz3(51)=B293;

            app.Button_656.BackgroundColor=[1.00,1.00,0.00];
            app.Button_665.BackgroundColor=[0.96,0.96,0.96];
            
                            %CALCULO 
                %Se carga la funcion para extraer y mostrar los datos
           
           [cosalidaconductorveh,cosalidacopilotoveh,coperdidacontrolveh,coadelantamientoveh,cointerseccionveh,coaccesopropiedadesveh,cosalidaconductormot,cosalidacopilotomot,coperdidacontrolmot,coadelantamientomot,cointerseccionmot,coaccesopropiedadesmot,colargomot,colargoci,cosalidaci,cointerseccionci,colargoconpe,colargocoppe,coinsppe,colatpe,EstVehiculo,EstMotocicleta,EstCiclista,EstPeaton,EstVehicul,EstMoto,EstCicl,EstPeato]=Calculo_estrellas_VehIntentoFinal(app.Matriz1);

           app.VehculoEditField.Value=EstVehiculo;
           app.MotociclistaEditField.Value=EstMotocicleta;
           app.CiclistaEditField.Value=EstCiclista;
           app.PeatnEditField.Value=EstPeaton;
           app.ValorEstVeh.Value=EstVehicul;
           app.ValorEstPeaton.Value=EstPeato;
           app.ValorEstCicli.Value=EstCicl;
           app.ValorEstMoto.Value=EstMoto;

           
           %Se carga la matriz proveniente del pre-proceso

            load('../mat/preproceso_tramo')
            
            %Para graficar 
            %GRAFICAR
            Tramos=[tramo(app.v1).Tramos];
            Distancia=[tramo(app.v1).Distancia];
            Xt=([tramo(app.v1).Xt]);
            yt=([tramo(app.v1).yt]);
            met_cord=size(Xt,1)
            if met_cord>1
                plot(app.UIAxes,Xt,yt)
            else
                sin_coord = imread('../img/blancoo.jpg')
                imshow(sin_coord,'Parent',app.UIAxes)
            end
            
            %Atributos Matriz 1
            Atributo_elegido(app.v1).Atributo=app.Matriz1
           
            %Datos interfaz uno Matriz 2
            Datos_interfaz_uno(app.v1).Tramos=Tramos;
            Datos_interfaz_uno(app.v1).Distancia=Distancia;
            Datos_interfaz_uno(app.v1).Xt=Xt;
            Datos_interfaz_uno(app.v1).yt=yt;
            Datos_interfaz_uno(app.v1).Ocupante_del_vehiculo = app.VehculoEditField.Value
            Datos_interfaz_uno(app.v1).Motociclista = app.MotociclistaEditField.Value
            Datos_interfaz_uno(app.v1).Ciclista = app.CiclistaEditField.Value
            Datos_interfaz_uno(app.v1).Peaton = app.PeatnEditField.Value
            Datos_interfaz_uno(app.v1).Puntuacion_Ocupante_del_vehiculo = app.ValorEstVeh.Value
            Datos_interfaz_uno(app.v1).Puntuacion_Motociclista = app.ValorEstMoto.Value
            Datos_interfaz_uno(app.v1).Puntuacion_Ciclista = app.ValorEstCicli.Value
            Datos_interfaz_uno(app.v1).Puntuacion_Peaton = app.ValorEstPeaton.Value
            Datos_interfaz_uno(app.v1).Severidad_lateral_distancia_al_objeto_lado_del_conductor = app.Matriz2(1)
            Datos_interfaz_uno(app.v1).Severidad_lateral_objeto_lado_del_conductor= app.Matriz2(2)
            Datos_interfaz_uno(app.v1).Severidad_lateral_distancia_al_objeto_lado_del_copiloto = app.Matriz2(3)
            Datos_interfaz_uno(app.v1).Severidad_lateral_objeto_lado_del_copiloto = app.Matriz2(4)
            Datos_interfaz_uno(app.v1).Bandas_sonoras_en_el_espaldon = app.Matriz2(5)
            Datos_interfaz_uno(app.v1).Ancho_de_espaldon_pavimentado_lado_del_conductor = app.Matriz2(6)
            Datos_interfaz_uno(app.v1).Ancho_de_espaldon_pavimentado_lado_del_copiloto = app.Matriz2(7)
            Datos_interfaz_uno(app.v1).Tipo_de_carretera = app.Matriz2(8)
            Datos_interfaz_uno(app.v1).Costo_de_modernizacion= app.Matriz2(9)
            Datos_interfaz_uno(app.v1).Tipo_de_mediana = app.Matriz2(10)
            Datos_interfaz_uno(app.v1).Bandas_sonoras_centrales = app.Matriz2(11)
            Datos_interfaz_uno(app.v1).Numero_de_carriles = app.Matriz2(12)
            Datos_interfaz_uno(app.v1).Ancho_de_carril = app.Matriz2(13)
            Datos_interfaz_uno(app.v1).Curvatura = app.Matriz2(14)
            Datos_interfaz_uno(app.v1).Calidad_de_la_curva = app.Matriz2(15)
            Datos_interfaz_uno(app.v1).Pendiente= app.Matriz2(16)
            Datos_interfaz_uno(app.v1).Estado_de_la_superficie_de_la_via = app.Matriz2(17)
            Datos_interfaz_uno(app.v1).Resistencia_al_deslizamiento = app.Matriz2(18)
            Datos_interfaz_uno(app.v1).Delineacion = app.Matriz2(19)
            Datos_interfaz_uno(app.v1).Alumbrado_publico = app.Matriz2(20)
            Datos_interfaz_uno(app.v1).Estacionamiento_para_vehiculos = app.Matriz2(21)
            Datos_interfaz_uno(app.v1).Via_de_servicio = app.Matriz2(22)
            Datos_interfaz_uno(app.v1).Obras_en_la_carretera = app.Matriz2(23)
            Datos_interfaz_uno(app.v1).Distancia_de_visibilidad = app.Matriz2(24)
            Datos_interfaz_uno(app.v1).Tipo_de_interseccion = app.Matriz2(25)
            Datos_interfaz_uno(app.v1).Canalizacion_de_la_interseccion= app.Matriz2(26)
            Datos_interfaz_uno(app.v1).Volumen_de_la_interseccion = app.Matriz2(27)
            Datos_interfaz_uno(app.v1).Calidad_de_la_interseccion = app.Matriz2(28)
            Datos_interfaz_uno(app.v1).Puntos_de_acceso_a_la_propiedad = app.Matriz2(29)
            Datos_interfaz_uno(app.v1).Flujo_vehicular = app.Matriz2(30)
            Datos_interfaz_uno(app.v1).Porcentaje_de_motocicletas = app.Matriz2(31)
            Datos_interfaz_uno(app.v1).Flujo_peatonal_en_hora_pico_que_cruza_la_carretera = app.Matriz2(32)
            Datos_interfaz_uno(app.v1).Flujo_peatonal_en_hora_pico_a_lo_largo_de_la_carretera_Cond = app.Matriz2(33)
            Datos_interfaz_uno(app.v1).Flujo_peatonal_en_hora_pico_a_lo_largo_de_la_carretera_Cop = app.Matriz2(34)
            Datos_interfaz_uno(app.v1).Flujo_de_bicicletas_en_hora_pico = app.Matriz2(35)
            Datos_interfaz_uno(app.v1).Uso_de_suelo_lado_del_conductor= app.Matriz2(36)
            Datos_interfaz_uno(app.v1).Uso_de_suelo_lado_del_copiloto = app.Matriz2(37)
            Datos_interfaz_uno(app.v1).Tipo_de_area = app.Matriz2(38)
            Datos_interfaz_uno(app.v1).Infraestructura_de_cruce_peatonal_en_el_tramo_analizado = app.Matriz2(39)
            Datos_interfaz_uno(app.v1).Calidad_de_cruce_peatonal = app.Matriz2(40)
            Datos_interfaz_uno(app.v1).Infraestructura_de_cruce_peatonal_en_la_interseccion = app.Matriz2(41)
            Datos_interfaz_uno(app.v1).Vallas_peatonales= app.Matriz2(42)
            Datos_interfaz_uno(app.v1).Acera_lado_del_conductor = app.Matriz2(43)
            Datos_interfaz_uno(app.v1).Acera_lado_del_copiloto = app.Matriz2(44)
            Datos_interfaz_uno(app.v1).Infraestructura_para_motocicletas = app.Matriz2(45)
            Datos_interfaz_uno(app.v1).Infraestructura_para_bicicletas = app.Matriz2(46)
            Datos_interfaz_uno(app.v1).Advertencia_de_zona_escolar = app.Matriz2(47)
            Datos_interfaz_uno(app.v1).Supervisor_en_el_cruce_de_zona_escolar= app.Matriz2(48)
            Datos_interfaz_uno(app.v1).Limite_de_velocidad = app.Matriz2(49)
            Datos_interfaz_uno(app.v1).Diferencial_de_limites_de_velocidad = app.Matriz2(50)
            Datos_interfaz_uno(app.v1).Gestion_de_la_velocidad_calmante_del_trafico = app.Matriz2(51)
            Datos_interfaz_uno(app.v1).Velocidad_de_operacion_percentil_85 = app.Matriz2(52)
            Datos_interfaz_uno(app.v1).Transitabilidad = app.Matriz2(53)
            
            %Datos interfaz dos Matriz 3
            Datos_interfaz_dos(app.v1).Tramos=Tramos;
            Datos_interfaz_dos(app.v1).Distancia=Distancia;
            Datos_interfaz_dos(app.v1).Xt=Xt;
            Datos_interfaz_dos(app.v1).yt=yt;
            Datos_interfaz_dos(app.v1).Ocupante_del_vehiculo = app.VehculoEditField.Value
            Datos_interfaz_dos(app.v1).Motociclista = app.MotociclistaEditField.Value
            Datos_interfaz_dos(app.v1).Ciclista = app.CiclistaEditField.Value
            Datos_interfaz_dos(app.v1).Peaton = app.PeatnEditField.Value
            Datos_interfaz_dos(app.v1).Puntuacion_Ocupante_del_vehiculo = app.ValorEstVeh.Value
            Datos_interfaz_dos(app.v1).Puntuacion_Motociclista = app.ValorEstMoto.Value
            Datos_interfaz_dos(app.v1).Puntuacion_Ciclista = app.ValorEstCicli.Value
            Datos_interfaz_dos(app.v1).Puntuacion_Peaton = app.ValorEstPeaton.Value
            Datos_interfaz_dos(app.v1).Severidad_lateral_distancia_al_objeto_lado_del_conductor = app.Matriz3(1)
            Datos_interfaz_dos(app.v1).Severidad_lateral_objeto_lado_del_conductor= app.Matriz3(2)
            Datos_interfaz_dos(app.v1).Severidad_lateral_distancia_al_objeto_lado_del_copiloto = app.Matriz3(3)
            Datos_interfaz_dos(app.v1).Severidad_lateral_objeto_lado_del_copiloto = app.Matriz3(4)
            Datos_interfaz_dos(app.v1).Bandas_sonoras_en_el_espaldon = app.Matriz3(5)
            Datos_interfaz_dos(app.v1).Ancho_de_espaldon_pavimentado_lado_del_conductor = app.Matriz3(6)
            Datos_interfaz_dos(app.v1).Ancho_de_espaldon_pavimentado_lado_del_copiloto = app.Matriz3(7)
            Datos_interfaz_dos(app.v1).Tipo_de_carretera = app.Matriz3(8)
            Datos_interfaz_dos(app.v1).Costo_de_modernizacion= app.Matriz3(9)
            Datos_interfaz_dos(app.v1).Tipo_de_mediana = app.Matriz3(10)
            Datos_interfaz_dos(app.v1).Bandas_sonoras_centrales = app.Matriz3(11)
            Datos_interfaz_dos(app.v1).Numero_de_carriles = app.Matriz3(12)
            Datos_interfaz_dos(app.v1).Ancho_de_carril = app.Matriz3(13)
            Datos_interfaz_dos(app.v1).Curvatura = app.Matriz3(14)
            Datos_interfaz_dos(app.v1).Calidad_de_la_curva = app.Matriz3(15)
            Datos_interfaz_dos(app.v1).Pendiente= app.Matriz3(16)
            Datos_interfaz_dos(app.v1).Estado_de_la_superficie_de_la_via = app.Matriz3(17)
            Datos_interfaz_dos(app.v1).Resistencia_al_deslizamiento = app.Matriz3(18)
            Datos_interfaz_dos(app.v1).Delineacion = app.Matriz3(19)
            Datos_interfaz_dos(app.v1).Alumbrado_publico = app.Matriz3(20)
            Datos_interfaz_dos(app.v1).Estacionamiento_para_vehiculos = app.Matriz3(21)
            Datos_interfaz_dos(app.v1).Via_de_servicio = app.Matriz3(22)
            Datos_interfaz_dos(app.v1).Obras_en_la_carretera = app.Matriz3(23)
            Datos_interfaz_dos(app.v1).Distancia_de_visibilidad = app.Matriz3(24)
            Datos_interfaz_dos(app.v1).Tipo_de_interseccion = app.Matriz3(25)
            Datos_interfaz_dos(app.v1).Canalizacion_de_la_interseccion= app.Matriz3(26)
            Datos_interfaz_dos(app.v1).Volumen_de_la_interseccion = app.Matriz3(27)
            Datos_interfaz_dos(app.v1).Calidad_de_la_interseccion = app.Matriz3(28)
            Datos_interfaz_dos(app.v1).Puntos_de_acceso_a_la_propiedad = app.Matriz3(29)
            Datos_interfaz_dos(app.v1).Flujo_vehicular = app.Matriz3(30)
            Datos_interfaz_dos(app.v1).Porcentaje_de_motocicletas = app.Matriz3(31)
            Datos_interfaz_dos(app.v1).Flujo_peatonal_en_hora_pico_que_cruza_la_carretera = app.Matriz3(32)
            Datos_interfaz_dos(app.v1).Flujo_peatonal_en_hora_pico_a_lo_largo_de_la_carretera_Cond = app.Matriz3(33)
            Datos_interfaz_dos(app.v1).Flujo_peatonal_en_hora_pico_a_lo_largo_de_la_carretera_Cop = app.Matriz3(34)
            Datos_interfaz_dos(app.v1).Flujo_de_bicicletas_en_hora_pico = app.Matriz3(35)
            Datos_interfaz_dos(app.v1).Uso_de_suelo_lado_del_conductor= app.Matriz3(36)
            Datos_interfaz_dos(app.v1).Uso_de_suelo_lado_del_copiloto = app.Matriz3(37)
            Datos_interfaz_dos(app.v1).Tipo_de_area = app.Matriz3(38)
            Datos_interfaz_dos(app.v1).Infraestructura_de_cruce_peatonal_en_el_tramo_analizado = app.Matriz3(39)
            Datos_interfaz_dos(app.v1).Calidad_de_cruce_peatonal = app.Matriz3(40)
            Datos_interfaz_dos(app.v1).Infraestructura_de_cruce_peatonal_en_la_interseccion = app.Matriz3(41)
            Datos_interfaz_dos(app.v1).Vallas_peatonales= app.Matriz3(42)
            Datos_interfaz_dos(app.v1).Acera_lado_del_conductor = app.Matriz3(43)
            Datos_interfaz_dos(app.v1).Acera_lado_del_copiloto = app.Matriz3(44)
            Datos_interfaz_dos(app.v1).Infraestructura_para_motocicletas = app.Matriz3(45)
            Datos_interfaz_dos(app.v1).Infraestructura_para_bicicletas = app.Matriz3(46)
            Datos_interfaz_dos(app.v1).Advertencia_de_zona_escolar = app.Matriz3(47)
            Datos_interfaz_dos(app.v1).Supervisor_en_el_cruce_de_zona_escolar= app.Matriz3(48)
            Datos_interfaz_dos(app.v1).Limite_de_velocidad = app.Matriz3(49)
            Datos_interfaz_dos(app.v1).Diferencial_de_limites_de_velocidad = app.Matriz3(50)
            Datos_interfaz_dos(app.v1).Gestion_de_la_velocidad_calmante_del_trafico = app.Matriz3(51)
            Datos_interfaz_dos(app.v1).Velocidad_de_operacion_percentil_85 = app.Matriz3(52)
            Datos_interfaz_dos(app.v1).Transitabilidad = app.Matriz3(53)
           
            Datos_interfaz_dos.Ocupante_del_vehiculo
            Datos_interfaz_dos.Motociclista
            Datos_interfaz_dos.Ciclista
            Datos_interfaz_dos.Peaton
            

            %Matriz 8 Nombre atributo una sola fila
            %Datos_interfaz_dos Matriz 3
            Datos_interfaz_tres(1).Tramos=Tramos;
            Datos_interfaz_tres(1).Distancia=Distancia;
            Datos_interfaz_tres(1).Xt=Xt;
            Datos_interfaz_tres(1).yt=yt;
            Datos_interfaz_tres(1).Ocupante_del_vehiculo = app.VehculoEditField.Value
            Datos_interfaz_tres(1).Motociclista = app.MotociclistaEditField.Value
            Datos_interfaz_tres(1).Ciclista = app.CiclistaEditField.Value
            Datos_interfaz_tres(1).Peaton = app.PeatnEditField.Value
            Datos_interfaz_tres(1).Puntuacion_Ocupante_del_vehiculo = app.ValorEstVeh.Value
            Datos_interfaz_tres(1).Puntuacion_Motociclista = app.ValorEstMoto.Value
            Datos_interfaz_tres(1).Puntuacion_Ciclista = app.ValorEstCicli.Value
            Datos_interfaz_tres(1).Puntuacion_Peaton = app.ValorEstPeaton.Value
            Datos_interfaz_tres(1).Severidad_lateral_distancia_al_objeto_lado_del_conductor = app.Matriz3(1)
            Datos_interfaz_tres(1).Severidad_lateral_objeto_lado_del_conductor= app.Matriz3(2)
            Datos_interfaz_tres(1).Severidad_lateral_distancia_al_objeto_lado_del_copiloto = app.Matriz3(3)
            Datos_interfaz_tres(1).Severidad_lateral_objeto_lado_del_copiloto = app.Matriz3(4)
            Datos_interfaz_tres(1).Bandas_sonoras_en_el_espaldon = app.Matriz3(5)
            Datos_interfaz_tres(1).Ancho_de_espaldon_pavimentado_lado_del_conductor = app.Matriz3(6)
            Datos_interfaz_tres(1).Ancho_de_espaldon_pavimentado_lado_del_copiloto = app.Matriz3(7)
            Datos_interfaz_tres(1).Tipo_de_carretera = app.Matriz3(8)
            Datos_interfaz_tres(1).Costo_de_modernizacion= app.Matriz3(9)
            Datos_interfaz_tres(1).Tipo_de_mediana = app.Matriz3(10)
            Datos_interfaz_tres(1).Bandas_sonoras_centrales = app.Matriz3(11)
            Datos_interfaz_tres(1).Numero_de_carriles = app.Matriz3(12)
            Datos_interfaz_tres(1).Ancho_de_carril = app.Matriz3(13)
            Datos_interfaz_tres(1).Curvatura = app.Matriz3(14)
            Datos_interfaz_tres(1).Calidad_de_la_curva = app.Matriz3(15)
            Datos_interfaz_tres(1).Pendiente= app.Matriz3(16)
            Datos_interfaz_tres(1).Estado_de_la_superficie_de_la_via = app.Matriz3(17)
            Datos_interfaz_tres(1).Resistencia_al_deslizamiento = app.Matriz3(18)
            Datos_interfaz_tres(1).Delineacion = app.Matriz3(19)
            Datos_interfaz_tres(1).Alumbrado_publico = app.Matriz3(20)
            Datos_interfaz_tres(1).Estacionamiento_para_vehiculos = app.Matriz3(21)
            Datos_interfaz_tres(1).Via_de_servicio = app.Matriz3(22)
            Datos_interfaz_tres(1).Obras_en_la_carretera = app.Matriz3(23)
            Datos_interfaz_tres(1).Distancia_de_visibilidad = app.Matriz3(24)
            Datos_interfaz_tres(1).Tipo_de_interseccion = app.Matriz3(25)
            Datos_interfaz_tres(1).Canalizacion_de_la_interseccion= app.Matriz3(26)
            Datos_interfaz_tres(1).Volumen_de_la_interseccion = app.Matriz3(27)
            Datos_interfaz_tres(1).Calidad_de_la_interseccion = app.Matriz3(28)
            Datos_interfaz_tres(1).Puntos_de_acceso_a_la_propiedad = app.Matriz3(29)
            Datos_interfaz_tres(1).Flujo_vehicular = app.Matriz3(30)
            Datos_interfaz_tres(1).Porcentaje_de_motocicletas = app.Matriz3(31)
            Datos_interfaz_tres(1).Flujo_peatonal_en_hora_pico_que_cruza_la_carretera = app.Matriz3(32)
            Datos_interfaz_tres(1).Flujo_peatonal_en_hora_pico_a_lo_largo_de_la_carretera_Cond = app.Matriz3(33)
            Datos_interfaz_tres(1).Flujo_peatonal_en_hora_pico_a_lo_largo_de_la_carretera_Cop = app.Matriz3(34)
            Datos_interfaz_tres(1).Flujo_de_bicicletas_en_hora_pico = app.Matriz3(35)
            Datos_interfaz_tres(1).Uso_de_suelo_lado_del_conductor= app.Matriz3(36)
            Datos_interfaz_tres(1).Uso_de_suelo_lado_del_copiloto = app.Matriz3(37)
            Datos_interfaz_tres(1).Tipo_de_area = app.Matriz3(38)
            Datos_interfaz_tres(1).Infraestructura_de_cruce_peatonal_en_el_tramo_analizado = app.Matriz3(39)
            Datos_interfaz_tres(1).Calidad_de_cruce_peatonal = app.Matriz3(40)
            Datos_interfaz_tres(1).Infraestructura_de_cruce_peatonal_en_la_interseccion = app.Matriz3(41)
            Datos_interfaz_tres(1).Vallas_peatonales= app.Matriz3(42)
            Datos_interfaz_tres(1).Acera_lado_del_conductor = app.Matriz3(43)
            Datos_interfaz_tres(1).Acera_lado_del_copiloto = app.Matriz3(44)
            Datos_interfaz_tres(1).Infraestructura_para_motocicletas = app.Matriz3(45)
            Datos_interfaz_tres(1).Infraestructura_para_bicicletas = app.Matriz3(46)
            Datos_interfaz_tres(1).Advertencia_de_zona_escolar = app.Matriz3(47)
            Datos_interfaz_tres(1).Supervisor_en_el_cruce_de_zona_escolar= app.Matriz3(48)
            Datos_interfaz_tres(1).Limite_de_velocidad = app.Matriz3(49)
            Datos_interfaz_tres(1).Diferencial_de_limites_de_velocidad = app.Matriz3(50)
            Datos_interfaz_tres(1).Gestion_de_la_velocidad_calmante_del_trafico = app.Matriz3(51)
            Datos_interfaz_tres(1).Velocidad_de_operacion_percentil_85 = app.Matriz3(52)
            Datos_interfaz_tres(1).Transitabilidad = app.Matriz3(53)
            
            %Matrices de los Coeficientes
            %Matriz 4
            Coeficientes_Veh(1).Salida_lado_conductor=cosalidaconductorveh;
            %Matriz 4.1
            Coeficientes_Veh(1).Salida_lado_copiloto=cosalidacopilotoveh;
            %Matriz 4.2
            Coeficientes_Veh(1).Perdida_de_control=coperdidacontrolveh;
            %Matriz 4.3
            Coeficientes_Veh(1).Adelantamiento=coadelantamientoveh;
            %Matriz 4.4
            Coeficientes_Veh(1).Interseccion=cointerseccionveh;
            %Matriz 4.5
            Coeficientes_Veh(1).Acceso_a_Propiedades=coaccesopropiedadesveh;
            %Matriz 5
            Coeficientes_Mot(1).Salida_lado_conductor=cosalidaconductormot;
            %Matriz 5.1
            Coeficientes_Mot(1).Salida_lado_copiloto=cosalidacopilotomot;
            %Matriz 5.2
            Coeficientes_Mot(1).Perdida_de_control=coperdidacontrolmot;
            %Matriz 5.3
            Coeficientes_Mot(1).Adelantamiento=coadelantamientomot;
            %Matriz 5.4
            Coeficientes_Mot(1).Interseccion=cointerseccionmot;
            %Matriz 5.5
            Coeficientes_Mot(1).Acceso_a_Propiedades=coaccesopropiedadesmot;
            %Matriz 5.6
            Coeficientes_Mot(1).A_lo_largo=colargomot;
            %Matriz 6
            Coeficientes_Ci(1).A_lo_largo=colargoci;
            %Matriz 6.1
            Coeficientes_Ci(1).Salida_camino=cosalidaci;
            %Matriz 6.2
            Coeficientes_Ci(1).Interseccion=cointerseccionci;
            %Matriz 7
            Coeficientes_Pe(1).A_lo_largo_conductor=colargoconpe;
            %Matriz 7.1
            Coeficientes_Pe(1).A_lo_largo_copiloto=colargocoppe;
            %Matriz 7.2
            Coeficientes_Pe(1).Cruce_peatonal_inspeccionada=coinsppe;
            %Matriz 7.3
            Coeficientes_Pe(1).Cruce_peatonal_lateral=colatpe;
            
            %Se guardan las mapp.v1atrices
            save('../mat/Datos_atributos_proceso',"Atributo_elegido")
            save('../mat/Datos_interfaz_uno_proceso',"Datos_interfaz_uno")
            save('../mat/Datos_interfaz_dos_proceso',"Datos_interfaz_dos")
            save('../mat/Coeficientes_Veh',"Coeficientes_Veh")
            save('../mat/Coeficientes_Mot',"Coeficientes_Mot")
            save('../mat/Coeficientes_Ci',"Coeficientes_Ci")
            save('../mat/Coeficientes_Pe',"Coeficientes_Pe")
            save('../mat/Datos_interfaz_tres_proceso',"Datos_interfaz_tres")
            
            %Estrellas vehiculo
            if app.VehculoEditField.Value==5
            imagen2=imread('E55.jpg')
            imshow(imagen2,'Parent',app.UIAxes2)
            else if app.VehculoEditField.Value==4
            imagen2=imread('E44.jpg')
            imshow(imagen2,'Parent',app.UIAxes2)
            else if app.VehculoEditField.Value==3
            imagen2=imread('E33.jpg')
            imshow(imagen2,'Parent',app.UIAxes2)
            else if app.VehculoEditField.Value==2
            imagen2=imread('E22.jpg')
            imshow(imagen2,'Parent',app.UIAxes2)
            else if app.VehculoEditField.Value==1
            imagen2=imread('E11.jpg')
            imshow(imagen2,'Parent',app.UIAxes2)
            else if app.VehculoEditField.Value==0
            imagen2=imread('E0.jpg')
            imshow(imagen2,'Parent',app.UIAxes2)
            end
            end
            end
            end
            end
            end
            %Estrellas Motociclista
            if app.MotociclistaEditField.Value==5
            imagen3=imread('E55.jpg')
            imshow(imagen3,'Parent',app.UIAxes4)
            else if app.MotociclistaEditField.Value==4
            imagen3=imread('E44.jpg')
            imshow(imagen3,'Parent',app.UIAxes4)
            else if app.MotociclistaEditField.Value==3
            imagen3=imread('E33.jpg')
            imshow(imagen3,'Parent',app.UIAxes4)
            else if app.MotociclistaEditField.Value==2
            imagen3=imread('E22.jpg')
            imshow(imagen3,'Parent',app.UIAxes4)
            else if app.MotociclistaEditField.Value==1
            imagen3=imread('E11.jpg')
            imshow(imagen3,'Parent',app.UIAxes4)
            else if app.MotociclistaEditField.Value==0
            imagen3=imread('E0.jpg')
            imshow(imagen3,'Parent',app.UIAxes4)
            end
            end
            end
            end
            end
            end
            %Estrellas Ciclista
            if app.CiclistaEditField.Value==5
            imagen4=imread('E55.jpg')
            imshow(imagen4,'Parent',app.UIAxes3)
            else if app.CiclistaEditField.Value==4
            imagen4=imread('E44.jpg')
            imshow(imagen4,'Parent',app.UIAxes3)
            else if app.CiclistaEditField.Value==3
            imagen4=imread('E33.jpg')
            imshow(imagen4,'Parent',app.UIAxes3)
            else if app.CiclistaEditField.Value==2
            imagen4=imread('E22.jpg')
            imshow(imagen4,'Parent',app.UIAxes3)
            else if app.CiclistaEditField.Value==1
            imagen4=imread('E11.jpg')
            imshow(imagen4,'Parent',app.UIAxes3)
            else if app.CiclistaEditField.Value==0
            imagen4=imread('E0.jpg')
            imshow(imagen4,'Parent',app.UIAxes3)
            end
            end
            end
            end
            end
            end
            %Estrellas Peaton
            if app.PeatnEditField.Value==5
            imagen5=imread('E55.jpg')
            imshow(imagen5,'Parent',app.UIAxes5)
            else if app.PeatnEditField.Value==4
            imagen5=imread('E44.jpg')
            imshow(imagen5,'Parent',app.UIAxes5)
            else if app.PeatnEditField.Value==3
            imagen5=imread('E33.jpg')
            imshow(imagen5,'Parent',app.UIAxes5)
            else if app.PeatnEditField.Value==2
            imagen5=imread('E22.jpg')
            imshow(imagen5,'Parent',app.UIAxes5)
            else if app.PeatnEditField.Value==1
            imagen5=imread('E11.jpg')
            imshow(imagen5,'Parent',app.UIAxes5)
            else if app.PeatnEditField.Value==0
            imagen5=imread('E0.jpg')
            imshow(imagen4,'Parent',app.UIAxes5)
            end
            end
            end
            end
            end
            end
           end
           
             %CONDICIONES CUANDO SPINNER MAYOR A 1
            if app.v1>1
           load('../mat/Datos_atributos_proceso')
           load('../mat/Datos_interfaz_uno_proceso')
           load('../mat/Datos_interfaz_dos_proceso')
           load('../mat/Coeficientes_Veh')
           load('../mat/Coeficientes_Mot')
           load('../mat/Coeficientes_Ci')
           load('../mat/Coeficientes_Pe')
           load('../mat/Datos_interfaz_tres_proceso')
                %Severidad lateral - distancia al objeto (lado del
                %conductor)
                x1=string(Datos_interfaz_uno(app.v1-1).Severidad_lateral_distancia_al_objeto_lado_del_conductor);
                x2=strcat(x1,'.BackgroundColor=[1.00,1.00,0.00]')
                eval([x2])
                x_1=cellstr(x1)
                app.Matriz2(1)=x_1;
                y1=string(Atributo_elegido(app.v1-1).Atributo(1));
                app.Matriz1(1)=y1;
                z1=Datos_interfaz_dos(app.v1-1).Severidad_lateral_distancia_al_objeto_lado_del_conductor;
                app.Matriz3(1)=z1;
                %Severidad lateral - objeto (lado del conductor)
                x3=string(Datos_interfaz_uno(app.v1-1).Severidad_lateral_objeto_lado_del_conductor);
                x4=strcat(x3,'.BackgroundColor=[1.00,1.00,0.00]')
                eval([x4])
                x_3=cellstr(x3)
                app.Matriz2(2)=x_3;
                y2=string(Atributo_elegido(app.v1-1).Atributo(2));
                app.Matriz1(2)=y2;
                z2=Datos_interfaz_dos(app.v1-1).Severidad_lateral_objeto_lado_del_conductor;
                app.Matriz3(2)=z2;
                %Severidad lateral - distancia al objeto (lado del
                %copiloto)
                x5=string(Datos_interfaz_uno(app.v1-1).Severidad_lateral_distancia_al_objeto_lado_del_copiloto);
                x6=strcat(x5,'.BackgroundColor=[1.00,1.00,0.00]')
                eval([x6])
                x_5=cellstr(x5)
                app.Matriz2(3)=x_5;
                y3=string(Atributo_elegido(app.v1-1).Atributo(3));
                app.Matriz1(3)=y3;
                z3=Datos_interfaz_dos(app.v1-1).Severidad_lateral_distancia_al_objeto_lado_del_copiloto;
                app.Matriz3(3)=z3;
                %Severidad lateral - objeto (lado del copiloto)
                x7=string(Datos_interfaz_uno(app.v1-1).Severidad_lateral_objeto_lado_del_copiloto);
                x8=strcat(x7,'.BackgroundColor=[1.00,1.00,0.00]')
                eval([x8])
                x_7=cellstr(x7)
                app.Matriz2(4)=x_7;
                y4=string(Atributo_elegido(app.v1-1).Atributo(4));
                app.Matriz1(4)=y4;
                z4=Datos_interfaz_dos(app.v1-1).Severidad_lateral_objeto_lado_del_copiloto;
                app.Matriz3(4)=z4;
                %Bandas sonoras en el espaldon
                x104=string(Datos_interfaz_uno(app.v1-1).Bandas_sonoras_en_el_espaldon);
                x105=strcat(x104,'.BackgroundColor=[1.00,1.00,0.00]')
                eval([x105])
                x_104=cellstr(x104)
                app.Matriz2(5)=x_104;
                y5=string(Atributo_elegido(app.v1-1).Atributo(5));
                app.Matriz1(5)=y5;
                z5=Datos_interfaz_dos(app.v1-1).Bandas_sonoras_en_el_espaldon;
                app.Matriz3(5)=z5;
                %Espaldon del lado conductor
                x9=string(Datos_interfaz_uno(app.v1-1).Ancho_de_espaldon_pavimentado_lado_del_conductor);
                x10=strcat(x9,'.BackgroundColor=[1.00,1.00,0.00]')
                eval([x10])
                x_9=cellstr(x9)
                app.Matriz2(6)=x_9;
                y6=string(Atributo_elegido(app.v1-1).Atributo(6));
                app.Matriz1(6)=y6;
                z6=Datos_interfaz_dos(app.v1-1).Ancho_de_espaldon_pavimentado_lado_del_conductor;
                app.Matriz3(6)=z6;
                %Espaldon del lado pasajero
                x11=string(Datos_interfaz_uno(app.v1-1).Ancho_de_espaldon_pavimentado_lado_del_copiloto);
                x12=strcat(x11,'.BackgroundColor=[1.00,1.00,0.00]')
                eval([x12])
                x_11=cellstr(x11)
                app.Matriz2(7)=x_11;
                y7=string(Atributo_elegido(app.v1-1).Atributo(7));
                app.Matriz1(7)=y7;
                z7=Datos_interfaz_dos(app.v1-1).Ancho_de_espaldon_pavimentado_lado_del_copiloto;
                app.Matriz3(7)=z7;
                %Tipo de carretera
                x13=string(Datos_interfaz_uno(app.v1-1).Tipo_de_carretera);
                x14=strcat(x13,'.BackgroundColor=[1.00,1.00,0.00]')
                eval([x14])
                x_13=cellstr(x13)
                app.Matriz2(8)=x_13;
                y8=string(Atributo_elegido(app.v1-1).Atributo(8));
                app.Matriz1(8)=y8;
                z8=Datos_interfaz_dos(app.v1-1).Tipo_de_carretera;
                app.Matriz3(8)=z8;
                %Costo de modernizacion
                x15=string(Datos_interfaz_uno(app.v1-1).Costo_de_modernizacion);
                x16=strcat(x15,'.BackgroundColor=[1.00,1.00,0.00]')
                eval([x16])
                x_15=cellstr(x15)
                app.Matriz2(9)=x_13;
                y9=string(Atributo_elegido(app.v1-1).Atributo(9));
                app.Matriz1(9)=y9;
                z9=Datos_interfaz_dos(app.v1-1).Costo_de_modernizacion;
                app.Matriz3(9)=z9;
                %Tipo de mediana
                x17=string(Datos_interfaz_uno(app.v1-1).Tipo_de_mediana);
                x18=strcat(x17,'.BackgroundColor=[1.00,1.00,0.00]')
                eval([x18])
                x_17=cellstr(x17)
                app.Matriz2(10)=x_17;
                y10=string(Atributo_elegido(app.v1-1).Atributo(10));
                app.Matriz1(10)=y10;
                z10=Datos_interfaz_dos(app.v1-1).Tipo_de_mediana;
                app.Matriz3(10)=z10;
                %Bandas sonoras centrales
                x19=string(Datos_interfaz_uno(app.v1-1).Bandas_sonoras_centrales);
                x20=strcat(x19,'.BackgroundColor=[1.00,1.00,0.00]')
                eval([x20])
                x_19=cellstr(x19)
                app.Matriz2(11)=x_19;
                y11=string(Atributo_elegido(app.v1-1).Atributo(11));
                app.Matriz1(11)=y11;
                z11=Datos_interfaz_dos(app.v1-1).Bandas_sonoras_centrales;
                app.Matriz3(11)=z11;
                %Numero de carriles
                x21=string(Datos_interfaz_uno(app.v1-1).Numero_de_carriles);
                x22=strcat(x21,'.BackgroundColor=[1.00,1.00,0.00]')
                eval([x22])
                x_21=cellstr(x21)
                app.Matriz2(12)=x_21;
                y12=string(Atributo_elegido(app.v1-1).Atributo(12));
                app.Matriz1(12)=y12;
                z12=Datos_interfaz_dos(app.v1-1).Numero_de_carriles;
                app.Matriz3(12)=z12;
                %Ancho de carril
                x23=string(Datos_interfaz_uno(app.v1-1).Ancho_de_carril);
                x24=strcat(x23,'.BackgroundColor=[1.00,1.00,0.00]')
                eval([x24])
                x_23=cellstr(x23)
                app.Matriz2(13)=x_23;
                y13=string(Atributo_elegido(app.v1-1).Atributo(13));
                app.Matriz1(13)=y13;
                z13=Datos_interfaz_dos(app.v1-1).Ancho_de_carril;
                app.Matriz3(13)=z13;
                %Curvatura
                %x25=string(Datos_interfaz_uno(app.v1-1).Curvatura);
                %x26=strcat(x25,'.BackgroundColor=[1.00,1.00,0.00]')
                %eval([x26])
                %x_25=cellstr(x25)
                %app.Matriz2(14)=x_25;
                %y14=string(Atributo_elegido(app.v1-1).Atributo(14));
                %app.Matriz1(14)=y14;
                %z14=Datos_interfaz_dos(app.v1-1).Curvatura;
                %app.Matriz3(14)=z14;
                %Calidad de la curva
                x27=string(Datos_interfaz_uno(app.v1-1).Calidad_de_la_curva);
                x28=strcat(x27,'.BackgroundColor=[1.00,1.00,0.00]')
                eval([x28])
                x_27=cellstr(x27)
                app.Matriz2(15)=x_27;
                y15=string(Atributo_elegido(app.v1-1).Atributo(15));
                app.Matriz1(15)=y15;
                z15=Datos_interfaz_dos(app.v1-1).Calidad_de_la_curva;
                app.Matriz3(15)=z15;
                %Pendiente
                %x29=string(Datos_interfaz_uno(app.v1-1).Pendiente);
                %30=strcat(x29,'.BackgroundColor=[1.00,1.00,0.00]')
                %eval([x30])
                %x_29=cellstr(x29)
                %app.Matriz2(16)=x_29;
                %y16=string(Atributo_elegido(app.v1-1).Atributo(16));
                %app.Matriz1(16)=y16;
                %z16=Datos_interfaz_dos(app.v1-1).Pendiente;
                %app.Matriz3(16)=z16;
                %Estado de la superficie de la via
                x31=string(Datos_interfaz_uno(app.v1-1).Estado_de_la_superficie_de_la_via);
                x32=strcat(x31,'.BackgroundColor=[1.00,1.00,0.00]')
                eval([x32])
                x_31=cellstr(x31)
                app.Matriz2(17)=x_31;
                y17=string(Atributo_elegido(app.v1-1).Atributo(17));
                app.Matriz1(17)=y17;
                z17=Datos_interfaz_dos(app.v1-1).Estado_de_la_superficie_de_la_via;
                app.Matriz3(17)=z17;
                %Resistencia al deslizamiento
                x33=string(Datos_interfaz_uno(app.v1-1).Resistencia_al_deslizamiento);
                x34=strcat(x33,'.BackgroundColor=[1.00,1.00,0.00]')
                eval([x34])
                x_33=cellstr(x33)
                app.Matriz2(18)=x_33;
                y18=string(Atributo_elegido(app.v1-1).Atributo(18));
                app.Matriz1(18)=y18;
                z18=Datos_interfaz_dos(app.v1-1).Resistencia_al_deslizamiento;
                app.Matriz3(18)=z18;
                %Delineacion
                x35=string(Datos_interfaz_uno(app.v1-1).Delineacion);
                x36=strcat(x35,'.BackgroundColor=[1.00,1.00,0.00]')
                eval([x36])
                x_35=cellstr(x35)
                app.Matriz2(19)=x_35;
                y19=string(Atributo_elegido(app.v1-1).Atributo(19));
                app.Matriz1(19)=y19;
                z19=Datos_interfaz_dos(app.v1-1).Delineacion;
                app.Matriz3(19)=z19;
                %Alumbrado publico
                x37=string(Datos_interfaz_uno(app.v1-1).Alumbrado_publico);
                x38=strcat(x37,'.BackgroundColor=[1.00,1.00,0.00]')
                eval([x38])
                x_37=cellstr(x37)
                app.Matriz2(20)=x_37;
                y20=string(Atributo_elegido(app.v1-1).Atributo(20));
                app.Matriz1(20)=y20;
                z20=Datos_interfaz_dos(app.v1-1).Alumbrado_publico;
                app.Matriz3(20)=z20;
                %Estacionamiento para vehiculos
                x39=string(Datos_interfaz_uno(app.v1-1).Estacionamiento_para_vehiculos);
                x40=strcat(x39,'.BackgroundColor=[1.00,1.00,0.00]')
                eval([x40])
                x_39=cellstr(x39)
                app.Matriz2(21)=x_39;
                y21=string(Atributo_elegido(app.v1-1).Atributo(21));
                app.Matriz1(21)=y21;
                z21=Datos_interfaz_dos(app.v1-1).Estacionamiento_para_vehiculos;
                app.Matriz3(21)=z21;
                %Obras en la carretera 
                x41=string(Datos_interfaz_uno(app.v1-1).Obras_en_la_carretera);
                x42=strcat(x41,'.BackgroundColor=[1.00,1.00,0.00]')
                eval([x42])
                x_41=cellstr(x41)
                app.Matriz2(23)=x_41;
                y23=string(Atributo_elegido(app.v1-1).Atributo(23));
                app.Matriz1(23)=y23;
                z23=Datos_interfaz_dos(app.v1-1).Obras_en_la_carretera;
                app.Matriz3(23)=z23;
                %Distancia de visibilidad
                x43=string(Datos_interfaz_uno(app.v1-1).Distancia_de_visibilidad);
                x44=strcat(x43,'.BackgroundColor=[1.00,1.00,0.00]')
                eval([x44])
                x_43=cellstr(x43)
                app.Matriz2(24)=x_43;
                y24=string(Atributo_elegido(app.v1-1).Atributo(24));
                app.Matriz1(24)=y24;
                z24=Datos_interfaz_dos(app.v1-1).Distancia_de_visibilidad;
                app.Matriz3(24)=z24;
                %Via de servicio
                x45=string(Datos_interfaz_uno(app.v1-1).Via_de_servicio);
                x46=strcat(x45,'.BackgroundColor=[1.00,1.00,0.00]')
                eval([x46])
                x_45=cellstr(x45)
                app.Matriz2(22)=x_45;
                y22=string(Atributo_elegido(app.v1-1).Atributo(22));
                app.Matriz1(22)=y22;
                z22=Datos_interfaz_dos(app.v1-1).Via_de_servicio;
                app.Matriz3(22)=z22;
                %Tipo de interseccion
                x47=string(Datos_interfaz_uno(app.v1-1).Tipo_de_mediana);
                x48=strcat(x47,'.BackgroundColor=[1.00,1.00,0.00]')
                eval([x48])
                x_47=cellstr(x47)
                app.Matriz2(25)=x_47;
                y25=string(Atributo_elegido(app.v1-1).Atributo(25));
                app.Matriz1(25)=y25;
                z25=Datos_interfaz_dos(app.v1-1).Tipo_de_mediana;
                app.Matriz3(25)=z25;
                %Transitabilidad Mediana
                x49=string(Datos_interfaz_uno(app.v1-1).Transitabilidad);
                x50=strcat(x49,'.BackgroundColor=[1.00,1.00,0.00]')
                eval([x50])
                x_49=cellstr(x49)
                app.Matriz2(53)=x_49;
                y53=string(Atributo_elegido(app.v1-1).Atributo(53));
                app.Matriz1(53)=y53;
                z53=Datos_interfaz_dos(app.v1-1).Transitabilidad;
                app.Matriz3(53)=z53;
                %Canalizacion de la interseccion
                x51=string(Datos_interfaz_uno(app.v1-1).Canalizacion_de_la_interseccion);
                x52=strcat(x51,'.BackgroundColor=[1.00,1.00,0.00]')
                eval([x52])
                x_51=cellstr(x51)
                app.Matriz2(26)=x_51;
                y26=string(Atributo_elegido(app.v1-1).Atributo(26));
                app.Matriz1(26)=y26;
                z26=Datos_interfaz_dos(app.v1-1).Canalizacion_de_la_interseccion;
                app.Matriz3(26)=z26;
                %Volumen de la interseccion
                x53=string(Datos_interfaz_uno(app.v1-1).Volumen_de_la_interseccion);
                x54=strcat(x53,'.BackgroundColor=[1.00,1.00,0.00]')
                eval([x54])
                x_53=cellstr(x53)
                app.Matriz2(27)=x_53;
                y27=string(Atributo_elegido(app.v1-1).Atributo(27));
                app.Matriz1(27)=y27;
                z27=Datos_interfaz_dos(app.v1-1).Volumen_de_la_interseccion;
                app.Matriz3(27)=z27;
                %Calidad de la interseccion
                x55=string(Datos_interfaz_uno(app.v1-1).Calidad_de_la_interseccion);
                x56=strcat(x55,'.BackgroundColor=[1.00,1.00,0.00]')
                eval([x56])
                x_55=cellstr(x55)
                app.Matriz2(28)=x_55;
                y28=string(Atributo_elegido(app.v1-1).Atributo(28));
                app.Matriz1(28)=y28;
                z28=Datos_interfaz_dos(app.v1-1).Calidad_de_la_interseccion;
                app.Matriz3(28)=z28;
                %Puntos de acceso de la propiedad
                x57=string(Datos_interfaz_uno(app.v1-1).Puntos_de_acceso_a_la_propiedad);
                x58=strcat(x57,'.BackgroundColor=[1.00,1.00,0.00]')
                eval([x58])
                x_57=cellstr(x57)
                app.Matriz2(29)=x_57;
                y29=string(Atributo_elegido(app.v1-1).Atributo(29));
                app.Matriz1(29)=y29;
                z29=Datos_interfaz_dos(app.v1-1).Puntos_de_acceso_a_la_propiedad;
                app.Matriz3(29)=z29;
                %Flujo vehicular (TPDA)
                x103=string(Atributo_elegido(app.v1-1).Atributo(30));
                y30=Datos_interfaz_uno(app.v1-1).Flujo_vehicular;
                app.Matriz2(30)=y30;
                z30=Datos_interfaz_dos(app.v1-1).Flujo_vehicular;
                app.Matriz3(30)=z30;
                %Porcentaje motocicletas
                x59=string(Datos_interfaz_uno(app.v1-1).Porcentaje_de_motocicletas);
                x60=strcat(x59,'.BackgroundColor=[1.00,1.00,0.00]')
                eval([x60])
                x_59=cellstr(x59)
                app.Matriz2(31)=x_59;
                y31=string(Atributo_elegido(app.v1-1).Atributo(31));
                app.Matriz1(31)=y31;
                z31=Datos_interfaz_dos(app.v1-1).Porcentaje_de_motocicletas;
                app.Matriz3(31)=z31;
                %Flujo peatonal en hora pico que cruza la carretera
                x61=string(Datos_interfaz_uno(app.v1-1).Flujo_peatonal_en_hora_pico_que_cruza_la_carretera);
                x62=strcat(x61,'.BackgroundColor=[1.00,1.00,0.00]')
                eval([x62])
                x_61=cellstr(x61)
                app.Matriz2(32)=x_61;
                y32=string(Atributo_elegido(app.v1-1).Atributo(32));
                app.Matriz1(32)=y32;
                z32=Datos_interfaz_dos(app.v1-1).Flujo_peatonal_en_hora_pico_que_cruza_la_carretera;
                app.Matriz3(32)=z32;
                %Flujo peatonal en hora pico a lo largo de la carretera
                %(conductor)
                x63=string(Datos_interfaz_uno(app.v1-1).Flujo_peatonal_en_hora_pico_a_lo_largo_de_la_carretera_Cond);
                x64=strcat(x63,'.BackgroundColor=[1.00,1.00,0.00]')
                eval([x64])
                x_63=cellstr(x63)
                app.Matriz2(33)=x_63;
                y33=string(Atributo_elegido(app.v1-1).Atributo(33));
                app.Matriz1(33)=y33;
                z33=Datos_interfaz_dos(app.v1-1).Flujo_peatonal_en_hora_pico_a_lo_largo_de_la_carretera_Cond;
                app.Matriz3(33)=z33;
                %Flujo peatonal en hora pico a lo largo de la carretera
                %(copiloto)
                x65=string(Datos_interfaz_uno(app.v1-1).Flujo_peatonal_en_hora_pico_a_lo_largo_de_la_carretera_Cop);
                x66=strcat(x65,'.BackgroundColor=[1.00,1.00,0.00]')
                eval([x66])
                x_65=cellstr(x65)
                app.Matriz2(34)=x_65;
                y34=string(Atributo_elegido(app.v1-1).Atributo(34));
                app.Matriz1(34)=y34;
                z34=Datos_interfaz_dos(app.v1-1).Flujo_peatonal_en_hora_pico_a_lo_largo_de_la_carretera_Cop;
                app.Matriz3(34)=z34;
                %Flujo de bicicletas en hora pico
                x67=string(Datos_interfaz_uno(app.v1-1).Flujo_de_bicicletas_en_hora_pico);
                x68=strcat(x67,'.BackgroundColor=[1.00,1.00,0.00]')
                eval([x68])
                x_67=cellstr(x67)
                app.Matriz2(35)=x_67;
                y35=string(Atributo_elegido(app.v1-1).Atributo(35));
                app.Matriz1(35)=y35;
                z35=Datos_interfaz_dos(app.v1-1).Flujo_de_bicicletas_en_hora_pico;
                app.Matriz3(35)=z35;
                %Uso de suelo (lado conductor)
                x69=string(Datos_interfaz_uno(app.v1-1).Uso_de_suelo_lado_del_conductor);
                x70=strcat(x69,'.BackgroundColor=[1.00,1.00,0.00]')
                eval([x70])
                x_69=cellstr(x69)
                app.Matriz2(36)=x_69;
                y36=string(Atributo_elegido(app.v1-1).Atributo(36));
                app.Matriz1(36)=y36;
                z36=Datos_interfaz_dos(app.v1-1).Uso_de_suelo_lado_del_conductor;
                app.Matriz3(36)=z36;
                %Uso de suelo (lado copiloto)
                x71=string(Datos_interfaz_uno(app.v1-1).Uso_de_suelo_lado_del_copiloto);
                x72=strcat(x71,'.BackgroundColor=[1.00,1.00,0.00]')
                eval([x72])
                x_71=cellstr(x71)
                app.Matriz2(37)=x_71;
                y37=string(Atributo_elegido(app.v1-1).Atributo(37));
                app.Matriz1(37)=y37;
                z37=Datos_interfaz_dos(app.v1-1).Uso_de_suelo_lado_del_copiloto;
                app.Matriz3(37)=z37;
                %Tipo de area
                x73=string(Datos_interfaz_uno(app.v1-1).Tipo_de_area);
                x74=strcat(x73,'.BackgroundColor=[1.00,1.00,0.00]')
                eval([x74])
                x_73=cellstr(x73)
                app.Matriz2(38)=x_73;
                y38=string(Atributo_elegido(app.v1-1).Atributo(38));
                app.Matriz1(38)=y38;
                z38=Datos_interfaz_dos(app.v1-1).Tipo_de_area;
                app.Matriz3(38)=z38;
                %Infraestructura de cruce peatonal en el tramo analizado
                x75=string(Datos_interfaz_uno(app.v1-1).Infraestructura_de_cruce_peatonal_en_el_tramo_analizado);
                x76=strcat(x75,'.BackgroundColor=[1.00,1.00,0.00]')
                eval([x76])
                x_75=cellstr(x75)
                app.Matriz2(39)=x_75;
                y39=string(Atributo_elegido(app.v1-1).Atributo(39));
                app.Matriz1(39)=y39;
                z39=Datos_interfaz_dos(app.v1-1).Infraestructura_de_cruce_peatonal_en_el_tramo_analizado;
                app.Matriz3(39)=z39;
                %Calidad de cruce peatonal
                x77=string(Datos_interfaz_uno(app.v1-1).Calidad_de_cruce_peatonal);
                x78=strcat(x77,'.BackgroundColor=[1.00,1.00,0.00]')
                eval([x78])
                x_77=cellstr(x77)
                app.Matriz2(40)=x_77;
                y40=string(Atributo_elegido(app.v1-1).Atributo(40));
                app.Matriz1(40)=y40;
                z40=Datos_interfaz_dos(app.v1-1).Calidad_de_cruce_peatonal;
                app.Matriz3(40)=z40;
                %Infraestructura de cruce peatonal en la interseccion
                x79=string(Datos_interfaz_uno(app.v1-1).Infraestructura_de_cruce_peatonal_en_la_interseccion);
                x80=strcat(x79,'.BackgroundColor=[1.00,1.00,0.00]')
                eval([x80])
                x_79=cellstr(x79)
                app.Matriz2(41)=x_79;
                y41=string(Atributo_elegido(app.v1-1).Atributo(41));
                app.Matriz1(41)=y41;
                z41=Datos_interfaz_dos(app.v1-1).Infraestructura_de_cruce_peatonal_en_la_interseccion;
                app.Matriz3(41)=z41;
                %Vallas peatonales
                x81=string(Datos_interfaz_uno(app.v1-1).Vallas_peatonales);
                x82=strcat(x81,'.BackgroundColor=[1.00,1.00,0.00]')
                eval([x82])
                x_81=cellstr(x81)
                app.Matriz2(42)=x_81;
                y42=string(Atributo_elegido(app.v1-1).Atributo(42));
                app.Matriz1(42)=y42;
                z42=Datos_interfaz_dos(app.v1-1).Vallas_peatonales;
                app.Matriz3(42)=z42;
                %Acera(lado conductor)
                x83=string(Datos_interfaz_uno(app.v1-1).Acera_lado_del_conductor);
                x84=strcat(x83,'.BackgroundColor=[1.00,1.00,0.00]')
                eval([x84])
                x_83=cellstr(x83)
                app.Matriz2(43)=x_83;
                y43=string(Atributo_elegido(app.v1-1).Atributo(43));
                app.Matriz1(43)=y43;
                z43=Datos_interfaz_dos(app.v1-1).Acera_lado_del_conductor;
                app.Matriz3(43)=z43;
                %Acera(lado copiloto)
                x85=string(Datos_interfaz_uno(app.v1-1).Acera_lado_del_copiloto);
                x86=strcat(x85,'.BackgroundColor=[1.00,1.00,0.00]')
                eval([x86])
                x_85=cellstr(x85)
                app.Matriz2(44)=x_85;
                y44=string(Atributo_elegido(app.v1-1).Atributo(44));
                app.Matriz1(44)=y44;
                z44=Datos_interfaz_dos(app.v1-1).Acera_lado_del_copiloto;
                app.Matriz3(44)=z44;
                %Infraestructura para motocicletas
                x87=string(Datos_interfaz_uno(app.v1-1).Infraestructura_para_motocicletas);
                x88=strcat(x87,'.BackgroundColor=[1.00,1.00,0.00]')
                eval([x88])
                x_87=cellstr(x87)
                app.Matriz2(45)=x_87;
                y45=string(Atributo_elegido(app.v1-1).Atributo(45));
                app.Matriz1(45)=y45;
                z45=Datos_interfaz_dos(app.v1-1).Infraestructura_para_motocicletas;
                app.Matriz3(45)=z45;
                %Infraestructura para bicicletas
                x89=string(Datos_interfaz_uno(app.v1-1).Infraestructura_para_bicicletas);
                x90=strcat(x89,'.BackgroundColor=[1.00,1.00,0.00]')
                eval([x90])
                x_89=cellstr(x89)
                app.Matriz2(46)=x_89;
                y46=string(Atributo_elegido(app.v1-1).Atributo(46));
                app.Matriz1(46)=y46;
                z46=Datos_interfaz_dos(app.v1-1).Infraestructura_para_bicicletas;
                app.Matriz3(46)=z46;
                %Advertencia de zona escolar
                x91=string(Datos_interfaz_uno(app.v1-1).Advertencia_de_zona_escolar);
                x92=strcat(x91,'.BackgroundColor=[1.00,1.00,0.00]')
                eval([x92])
                x_91=cellstr(x91)
                app.Matriz2(47)=x_91;
                y47=string(Atributo_elegido(app.v1-1).Atributo(47));
                app.Matriz1(47)=y47;
                z47=Datos_interfaz_dos(app.v1-1).Advertencia_de_zona_escolar;
                app.Matriz3(47)=z47;
                %Supervisor en el cruce de zona escolar
                x93=string(Datos_interfaz_uno(app.v1-1).Supervisor_en_el_cruce_de_zona_escolar);
                x94=strcat(x93,'.BackgroundColor=[1.00,1.00,0.00]')
                eval([x94])
                x_93=cellstr(x93)
                app.Matriz2(48)=x_93;
                y48=string(Atributo_elegido(app.v1-1).Atributo(48));
                app.Matriz1(48)=y48;
                z48=Datos_interfaz_dos(app.v1-1).Supervisor_en_el_cruce_de_zona_escolar;
                app.Matriz3(48)=z48;
                %Limite de velocidad
                x95=string(Datos_interfaz_uno(app.v1-1).Limite_de_velocidad);
                x96=strcat(x95,'.BackgroundColor=[1.00,1.00,0.00]')
                eval([x96])
                x_95=cellstr(x95)
                app.Matriz2(49)=x_95;
                y49=string(Atributo_elegido(app.v1-1).Atributo(49));
                app.Matriz1(49)=y49;
                z49=Datos_interfaz_dos(app.v1-1).Limite_de_velocidad;
                app.Matriz3(49)=z49;
                %Diferencial de limites de velocidades
                x97=string(Datos_interfaz_uno(app.v1-1).Diferencial_de_limites_de_velocidad);
                x98=strcat(x97,'.BackgroundColor=[1.00,1.00,0.00]')
                eval([x98])
                x_97=cellstr(x97)
                app.Matriz2(50)=x_97;
                y50=string(Atributo_elegido(app.v1-1).Atributo(50));
                app.Matriz1(50)=y50;
                z50=Datos_interfaz_dos(app.v1-1).Diferencial_de_limites_de_velocidad;
                app.Matriz3(50)=z50;
                %Velocidad de operaccion (percentil 85)
                %99=string(Datos_interfaz_uno(app.v1-1).Velocidad_de_operacion_percentil_85);
                %x100=strcat(x99,'.BackgroundColor=[1.00,1.00,0.00]')
                %eval([x100])
                %x_99=cellstr(x99)
                %app.Matriz2(52)=x_99;
                %y52=string(Atributo_elegido(app.v1-1).Atributo(52));
                %app.Matriz1(52)=y52;
                %z52=Datos_interfaz_dos(app.v1-1).Velocidad_de_operacion_percentil_85;
                %app.Matriz3(52)=z52;
                %Gestion de la velocidad/calmante de trafico
                x101=string(Datos_interfaz_uno(app.v1-1).Gestion_de_la_velocidad_calmante_del_trafico);
                x102=strcat(x101,'.BackgroundColor=[1.00,1.00,0.00]')
                eval([x102])
                x_101=cellstr(x101)
                app.Matriz2(51)=x_101;
                y51=string(Atributo_elegido(app.v1-1).Atributo(51));
                app.Matriz1(51)=y51;
                z51=Datos_interfaz_dos(app.v1-1).Gestion_de_la_velocidad_calmante_del_trafico;
                app.Matriz3(51)=z51;
                            %CALCULO 
                %Se carga la funcion para extraer y mostrar los datos
           
           [cosalidaconductorveh,cosalidacopilotoveh,coperdidacontrolveh,coadelantamientoveh,cointerseccionveh,coaccesopropiedadesveh,cosalidaconductormot,cosalidacopilotomot,coperdidacontrolmot,coadelantamientomot,cointerseccionmot,coaccesopropiedadesmot,colargomot,colargoci,cosalidaci,cointerseccionci,colargoconpe,colargocoppe,coinsppe,colatpe,EstVehiculo,EstMotocicleta,EstCiclista,EstPeaton,EstVehicul,EstMoto,EstCicl,EstPeato]=Calculo_estrellas_VehIntentoFinal(app.Matriz1);

           app.VehculoEditField.Value=EstVehiculo;
           app.MotociclistaEditField.Value=EstMotocicleta;
           app.CiclistaEditField.Value=EstCiclista;
           app.PeatnEditField.Value=EstPeaton;
           app.ValorEstVeh.Value=EstVehicul;
           app.ValorEstPeaton.Value=EstPeato;
           app.ValorEstCicli.Value=EstCicl;
           app.ValorEstMoto.Value=EstMoto;

           
           %Se carga la matriz proveniente del pre-proceso

            load('../mat/preproceso_tramo')
            
            %Para graficar 
            %GRAFICAR
            Tramos=[tramo(app.v1).Tramos];
            Distancia=[tramo(app.v1).Distancia];
            Xt=([tramo(app.v1).Xt]);
            yt=([tramo(app.v1).yt]);
            met_cord=size(Xt,1)
            if met_cord>1
                plot(app.UIAxes,Xt,yt)
            else
                sin_coord = imread('../img/blancoo.jpg')
                imshow(sin_coord,'Parent',app.UIAxes)
            end
            
            %Atributos Matriz 1
            Atributo_elegido(app.v1).Atributo=app.Matriz1
           
            %Datos interfaz uno Matriz 2
            Datos_interfaz_uno(app.v1).Tramos=Tramos;
            Datos_interfaz_uno(app.v1).Distancia=Distancia;
            Datos_interfaz_uno(app.v1).Xt=Xt;
            Datos_interfaz_uno(app.v1).yt=yt;
            Datos_interfaz_uno(app.v1).Ocupante_del_vehiculo = app.VehculoEditField.Value
            Datos_interfaz_uno(app.v1).Motociclista = app.MotociclistaEditField.Value
            Datos_interfaz_uno(app.v1).Ciclista = app.CiclistaEditField.Value
            Datos_interfaz_uno(app.v1).Peaton = app.PeatnEditField.Value
            Datos_interfaz_uno(app.v1).Puntuacion_Ocupante_del_vehiculo = app.ValorEstVeh.Value
            Datos_interfaz_uno(app.v1).Puntuacion_Motociclista = app.ValorEstMoto.Value
            Datos_interfaz_uno(app.v1).Puntuacion_Ciclista = app.ValorEstCicli.Value
            Datos_interfaz_uno(app.v1).Puntuacion_Peaton = app.ValorEstPeaton.Value
            Datos_interfaz_uno(app.v1).Severidad_lateral_distancia_al_objeto_lado_del_conductor = app.Matriz2(1)
            Datos_interfaz_uno(app.v1).Severidad_lateral_objeto_lado_del_conductor= app.Matriz2(2)
            Datos_interfaz_uno(app.v1).Severidad_lateral_distancia_al_objeto_lado_del_copiloto = app.Matriz2(3)
            Datos_interfaz_uno(app.v1).Severidad_lateral_objeto_lado_del_copiloto = app.Matriz2(4)
            Datos_interfaz_uno(app.v1).Bandas_sonoras_en_el_espaldon = app.Matriz2(5)
            Datos_interfaz_uno(app.v1).Ancho_de_espaldon_pavimentado_lado_del_conductor = app.Matriz2(6)
            Datos_interfaz_uno(app.v1).Ancho_de_espaldon_pavimentado_lado_del_copiloto = app.Matriz2(7)
            Datos_interfaz_uno(app.v1).Tipo_de_carretera = app.Matriz2(8)
            Datos_interfaz_uno(app.v1).Costo_de_modernizacion= app.Matriz2(9)
            Datos_interfaz_uno(app.v1).Tipo_de_mediana = app.Matriz2(10)
            Datos_interfaz_uno(app.v1).Bandas_sonoras_centrales = app.Matriz2(11)
            Datos_interfaz_uno(app.v1).Numero_de_carriles = app.Matriz2(12)
            Datos_interfaz_uno(app.v1).Ancho_de_carril = app.Matriz2(13)
            Datos_interfaz_uno(app.v1).Curvatura = app.Matriz2(14)
            Datos_interfaz_uno(app.v1).Calidad_de_la_curva = app.Matriz2(15)
            Datos_interfaz_uno(app.v1).Pendiente= app.Matriz2(16)
            Datos_interfaz_uno(app.v1).Estado_de_la_superficie_de_la_via = app.Matriz2(17)
            Datos_interfaz_uno(app.v1).Resistencia_al_deslizamiento = app.Matriz2(18)
            Datos_interfaz_uno(app.v1).Delineacion = app.Matriz2(19)
            Datos_interfaz_uno(app.v1).Alumbrado_publico = app.Matriz2(20)
            Datos_interfaz_uno(app.v1).Estacionamiento_para_vehiculos = app.Matriz2(21)
            Datos_interfaz_uno(app.v1).Via_de_servicio = app.Matriz2(22)
            Datos_interfaz_uno(app.v1).Obras_en_la_carretera = app.Matriz2(23)
            Datos_interfaz_uno(app.v1).Distancia_de_visibilidad = app.Matriz2(24)
            Datos_interfaz_uno(app.v1).Tipo_de_interseccion = app.Matriz2(25)
            Datos_interfaz_uno(app.v1).Canalizacion_de_la_interseccion= app.Matriz2(26)
            Datos_interfaz_uno(app.v1).Volumen_de_la_interseccion = app.Matriz2(27)
            Datos_interfaz_uno(app.v1).Calidad_de_la_interseccion = app.Matriz2(28)
            Datos_interfaz_uno(app.v1).Puntos_de_acceso_a_la_propiedad = app.Matriz2(29)
            Datos_interfaz_uno(app.v1).Flujo_vehicular = app.Matriz2(30)
            Datos_interfaz_uno(app.v1).Porcentaje_de_motocicletas = app.Matriz2(31)
            Datos_interfaz_uno(app.v1).Flujo_peatonal_en_hora_pico_que_cruza_la_carretera = app.Matriz2(32)
            Datos_interfaz_uno(app.v1).Flujo_peatonal_en_hora_pico_a_lo_largo_de_la_carretera_Cond = app.Matriz2(33)
            Datos_interfaz_uno(app.v1).Flujo_peatonal_en_hora_pico_a_lo_largo_de_la_carretera_Cop = app.Matriz2(34)
            Datos_interfaz_uno(app.v1).Flujo_de_bicicletas_en_hora_pico = app.Matriz2(35)
            Datos_interfaz_uno(app.v1).Uso_de_suelo_lado_del_conductor= app.Matriz2(36)
            Datos_interfaz_uno(app.v1).Uso_de_suelo_lado_del_copiloto = app.Matriz2(37)
            Datos_interfaz_uno(app.v1).Tipo_de_area = app.Matriz2(38)
            Datos_interfaz_uno(app.v1).Infraestructura_de_cruce_peatonal_en_el_tramo_analizado = app.Matriz2(39)
            Datos_interfaz_uno(app.v1).Calidad_de_cruce_peatonal = app.Matriz2(40)
            Datos_interfaz_uno(app.v1).Infraestructura_de_cruce_peatonal_en_la_interseccion = app.Matriz2(41)
            Datos_interfaz_uno(app.v1).Vallas_peatonales= app.Matriz2(42)
            Datos_interfaz_uno(app.v1).Acera_lado_del_conductor = app.Matriz2(43)
            Datos_interfaz_uno(app.v1).Acera_lado_del_copiloto = app.Matriz2(44)
            Datos_interfaz_uno(app.v1).Infraestructura_para_motocicletas = app.Matriz2(45)
            Datos_interfaz_uno(app.v1).Infraestructura_para_bicicletas = app.Matriz2(46)
            Datos_interfaz_uno(app.v1).Advertencia_de_zona_escolar = app.Matriz2(47)
            Datos_interfaz_uno(app.v1).Supervisor_en_el_cruce_de_zona_escolar= app.Matriz2(48)
            Datos_interfaz_uno(app.v1).Limite_de_velocidad = app.Matriz2(49)
            Datos_interfaz_uno(app.v1).Diferencial_de_limites_de_velocidad = app.Matriz2(50)
            Datos_interfaz_uno(app.v1).Gestion_de_la_velocidad_calmante_del_trafico = app.Matriz2(51)
            Datos_interfaz_uno(app.v1).Velocidad_de_operacion_percentil_85 = app.Matriz2(52)
            Datos_interfaz_uno(app.v1).Transitabilidad = app.Matriz2(53)
            
            %Datos interfaz dos Matriz 3
            Datos_interfaz_dos(app.v1).Tramos=Tramos;
            Datos_interfaz_dos(app.v1).Distancia=Distancia;
            Datos_interfaz_dos(app.v1).Xt=Xt;
            Datos_interfaz_dos(app.v1).yt=yt;
            Datos_interfaz_dos(app.v1).Ocupante_del_vehiculo = app.VehculoEditField.Value
            Datos_interfaz_dos(app.v1).Motociclista = app.MotociclistaEditField.Value
            Datos_interfaz_dos(app.v1).Ciclista = app.CiclistaEditField.Value
            Datos_interfaz_dos(app.v1).Peaton = app.PeatnEditField.Value
            Datos_interfaz_dos(app.v1).Puntuacion_Ocupante_del_vehiculo = app.ValorEstVeh.Value
            Datos_interfaz_dos(app.v1).Puntuacion_Motociclista = app.ValorEstMoto.Value
            Datos_interfaz_dos(app.v1).Puntuacion_Ciclista = app.ValorEstCicli.Value
            Datos_interfaz_dos(app.v1).Puntuacion_Peaton = app.ValorEstPeaton.Value
            Datos_interfaz_dos(app.v1).Severidad_lateral_distancia_al_objeto_lado_del_conductor = app.Matriz3(1)
            Datos_interfaz_dos(app.v1).Severidad_lateral_objeto_lado_del_conductor= app.Matriz3(2)
            Datos_interfaz_dos(app.v1).Severidad_lateral_distancia_al_objeto_lado_del_copiloto = app.Matriz3(3)
            Datos_interfaz_dos(app.v1).Severidad_lateral_objeto_lado_del_copiloto = app.Matriz3(4)
            Datos_interfaz_dos(app.v1).Bandas_sonoras_en_el_espaldon = app.Matriz3(5)
            Datos_interfaz_dos(app.v1).Ancho_de_espaldon_pavimentado_lado_del_conductor = app.Matriz3(6)
            Datos_interfaz_dos(app.v1).Ancho_de_espaldon_pavimentado_lado_del_copiloto = app.Matriz3(7)
            Datos_interfaz_dos(app.v1).Tipo_de_carretera = app.Matriz3(8)
            Datos_interfaz_dos(app.v1).Costo_de_modernizacion= app.Matriz3(9)
            Datos_interfaz_dos(app.v1).Tipo_de_mediana = app.Matriz3(10)
            Datos_interfaz_dos(app.v1).Bandas_sonoras_centrales = app.Matriz3(11)
            Datos_interfaz_dos(app.v1).Numero_de_carriles = app.Matriz3(12)
            Datos_interfaz_dos(app.v1).Ancho_de_carril = app.Matriz3(13)
            Datos_interfaz_dos(app.v1).Curvatura = app.Matriz3(14)
            Datos_interfaz_dos(app.v1).Calidad_de_la_curva = app.Matriz3(15)
            Datos_interfaz_dos(app.v1).Pendiente= app.Matriz3(16)
            Datos_interfaz_dos(app.v1).Estado_de_la_superficie_de_la_via = app.Matriz3(17)
            Datos_interfaz_dos(app.v1).Resistencia_al_deslizamiento = app.Matriz3(18)
            Datos_interfaz_dos(app.v1).Delineacion = app.Matriz3(19)
            Datos_interfaz_dos(app.v1).Alumbrado_publico = app.Matriz3(20)
            Datos_interfaz_dos(app.v1).Estacionamiento_para_vehiculos = app.Matriz3(21)
            Datos_interfaz_dos(app.v1).Via_de_servicio = app.Matriz3(22)
            Datos_interfaz_dos(app.v1).Obras_en_la_carretera = app.Matriz3(23)
            Datos_interfaz_dos(app.v1).Distancia_de_visibilidad = app.Matriz3(24)
            Datos_interfaz_dos(app.v1).Tipo_de_interseccion = app.Matriz3(25)
            Datos_interfaz_dos(app.v1).Canalizacion_de_la_interseccion= app.Matriz3(26)
            Datos_interfaz_dos(app.v1).Volumen_de_la_interseccion = app.Matriz3(27)
            Datos_interfaz_dos(app.v1).Calidad_de_la_interseccion = app.Matriz3(28)
            Datos_interfaz_dos(app.v1).Puntos_de_acceso_a_la_propiedad = app.Matriz3(29)
            Datos_interfaz_dos(app.v1).Flujo_vehicular = app.Matriz3(30)
            Datos_interfaz_dos(app.v1).Porcentaje_de_motocicletas = app.Matriz3(31)
            Datos_interfaz_dos(app.v1).Flujo_peatonal_en_hora_pico_que_cruza_la_carretera = app.Matriz3(32)
            Datos_interfaz_dos(app.v1).Flujo_peatonal_en_hora_pico_a_lo_largo_de_la_carretera_Cond = app.Matriz3(33)
            Datos_interfaz_dos(app.v1).Flujo_peatonal_en_hora_pico_a_lo_largo_de_la_carretera_Cop = app.Matriz3(34)
            Datos_interfaz_dos(app.v1).Flujo_de_bicicletas_en_hora_pico = app.Matriz3(35)
            Datos_interfaz_dos(app.v1).Uso_de_suelo_lado_del_conductor= app.Matriz3(36)
            Datos_interfaz_dos(app.v1).Uso_de_suelo_lado_del_copiloto = app.Matriz3(37)
            Datos_interfaz_dos(app.v1).Tipo_de_area = app.Matriz3(38)
            Datos_interfaz_dos(app.v1).Infraestructura_de_cruce_peatonal_en_el_tramo_analizado = app.Matriz3(39)
            Datos_interfaz_dos(app.v1).Calidad_de_cruce_peatonal = app.Matriz3(40)
            Datos_interfaz_dos(app.v1).Infraestructura_de_cruce_peatonal_en_la_interseccion = app.Matriz3(41)
            Datos_interfaz_dos(app.v1).Vallas_peatonales= app.Matriz3(42)
            Datos_interfaz_dos(app.v1).Acera_lado_del_conductor = app.Matriz3(43)
            Datos_interfaz_dos(app.v1).Acera_lado_del_copiloto = app.Matriz3(44)
            Datos_interfaz_dos(app.v1).Infraestructura_para_motocicletas = app.Matriz3(45)
            Datos_interfaz_dos(app.v1).Infraestructura_para_bicicletas = app.Matriz3(46)
            Datos_interfaz_dos(app.v1).Advertencia_de_zona_escolar = app.Matriz3(47)
            Datos_interfaz_dos(app.v1).Supervisor_en_el_cruce_de_zona_escolar= app.Matriz3(48)
            Datos_interfaz_dos(app.v1).Limite_de_velocidad = app.Matriz3(49)
            Datos_interfaz_dos(app.v1).Diferencial_de_limites_de_velocidad = app.Matriz3(50)
            Datos_interfaz_dos(app.v1).Gestion_de_la_velocidad_calmante_del_trafico = app.Matriz3(51)
            Datos_interfaz_dos(app.v1).Velocidad_de_operacion_percentil_85 = app.Matriz3(52)
            Datos_interfaz_dos(app.v1).Transitabilidad = app.Matriz3(53)
           
            Datos_interfaz_dos.Ocupante_del_vehiculo
            Datos_interfaz_dos.Motociclista
            Datos_interfaz_dos.Ciclista
            Datos_interfaz_dos.Peaton
            
            %Matriz 8 Nombre atributo una sola fila
            %Datos_interfaz_dos Matriz 3
            Datos_interfaz_tres(1).Tramos=Tramos;
            Datos_interfaz_tres(1).Distancia=Distancia;
            Datos_interfaz_tres(1).Xt=Xt;
            Datos_interfaz_tres(1).yt=yt;
            Datos_interfaz_tres(1).Ocupante_del_vehiculo = app.VehculoEditField.Value
            Datos_interfaz_tres(1).Motociclista = app.MotociclistaEditField.Value
            Datos_interfaz_tres(1).Ciclista = app.CiclistaEditField.Value
            Datos_interfaz_tres(1).Peaton = app.PeatnEditField.Value
            Datos_interfaz_tres(1).Puntuacion_Ocupante_del_vehiculo = app.ValorEstVeh.Value
            Datos_interfaz_tres(1).Puntuacion_Motociclista = app.ValorEstMoto.Value
            Datos_interfaz_tres(1).Puntuacion_Ciclista = app.ValorEstCicli.Value
            Datos_interfaz_tres(1).Puntuacion_Peaton = app.ValorEstPeaton.Value
            Datos_interfaz_tres(1).Severidad_lateral_distancia_al_objeto_lado_del_conductor = app.Matriz3(1)
            Datos_interfaz_tres(1).Severidad_lateral_objeto_lado_del_conductor= app.Matriz3(2)
            Datos_interfaz_tres(1).Severidad_lateral_distancia_al_objeto_lado_del_copiloto = app.Matriz3(3)
            Datos_interfaz_tres(1).Severidad_lateral_objeto_lado_del_copiloto = app.Matriz3(4)
            Datos_interfaz_tres(1).Bandas_sonoras_en_el_espaldon = app.Matriz3(5)
            Datos_interfaz_tres(1).Ancho_de_espaldon_pavimentado_lado_del_conductor = app.Matriz3(6)
            Datos_interfaz_tres(1).Ancho_de_espaldon_pavimentado_lado_del_copiloto = app.Matriz3(7)
            Datos_interfaz_tres(1).Tipo_de_carretera = app.Matriz3(8)
            Datos_interfaz_tres(1).Costo_de_modernizacion= app.Matriz3(9)
            Datos_interfaz_tres(1).Tipo_de_mediana = app.Matriz3(10)
            Datos_interfaz_tres(1).Bandas_sonoras_centrales = app.Matriz3(11)
            Datos_interfaz_tres(1).Numero_de_carriles = app.Matriz3(12)
            Datos_interfaz_tres(1).Ancho_de_carril = app.Matriz3(13)
            Datos_interfaz_tres(1).Curvatura = app.Matriz3(14)
            Datos_interfaz_tres(1).Calidad_de_la_curva = app.Matriz3(15)
            Datos_interfaz_tres(1).Pendiente= app.Matriz3(16)
            Datos_interfaz_tres(1).Estado_de_la_superficie_de_la_via = app.Matriz3(17)
            Datos_interfaz_tres(1).Resistencia_al_deslizamiento = app.Matriz3(18)
            Datos_interfaz_tres(1).Delineacion = app.Matriz3(19)
            Datos_interfaz_tres(1).Alumbrado_publico = app.Matriz3(20)
            Datos_interfaz_tres(1).Estacionamiento_para_vehiculos = app.Matriz3(21)
            Datos_interfaz_tres(1).Via_de_servicio = app.Matriz3(22)
            Datos_interfaz_tres(1).Obras_en_la_carretera = app.Matriz3(23)
            Datos_interfaz_tres(1).Distancia_de_visibilidad = app.Matriz3(24)
            Datos_interfaz_tres(1).Tipo_de_interseccion = app.Matriz3(25)
            Datos_interfaz_tres(1).Canalizacion_de_la_interseccion= app.Matriz3(26)
            Datos_interfaz_tres(1).Volumen_de_la_interseccion = app.Matriz3(27)
            Datos_interfaz_tres(1).Calidad_de_la_interseccion = app.Matriz3(28)
            Datos_interfaz_tres(1).Puntos_de_acceso_a_la_propiedad = app.Matriz3(29)
            Datos_interfaz_tres(1).Flujo_vehicular = app.Matriz3(30)
            Datos_interfaz_tres(1).Porcentaje_de_motocicletas = app.Matriz3(31)
            Datos_interfaz_tres(1).Flujo_peatonal_en_hora_pico_que_cruza_la_carretera = app.Matriz3(32)
            Datos_interfaz_tres(1).Flujo_peatonal_en_hora_pico_a_lo_largo_de_la_carretera_Cond = app.Matriz3(33)
            Datos_interfaz_tres(1).Flujo_peatonal_en_hora_pico_a_lo_largo_de_la_carretera_Cop = app.Matriz3(34)
            Datos_interfaz_tres(1).Flujo_de_bicicletas_en_hora_pico = app.Matriz3(35)
            Datos_interfaz_tres(1).Uso_de_suelo_lado_del_conductor= app.Matriz3(36)
            Datos_interfaz_tres(1).Uso_de_suelo_lado_del_copiloto = app.Matriz3(37)
            Datos_interfaz_tres(1).Tipo_de_area = app.Matriz3(38)
            Datos_interfaz_tres(1).Infraestructura_de_cruce_peatonal_en_el_tramo_analizado = app.Matriz3(39)
            Datos_interfaz_tres(1).Calidad_de_cruce_peatonal = app.Matriz3(40)
            Datos_interfaz_tres(1).Infraestructura_de_cruce_peatonal_en_la_interseccion = app.Matriz3(41)
            Datos_interfaz_tres(1).Vallas_peatonales= app.Matriz3(42)
            Datos_interfaz_tres(1).Acera_lado_del_conductor = app.Matriz3(43)
            Datos_interfaz_tres(1).Acera_lado_del_copiloto = app.Matriz3(44)
            Datos_interfaz_tres(1).Infraestructura_para_motocicletas = app.Matriz3(45)
            Datos_interfaz_tres(1).Infraestructura_para_bicicletas = app.Matriz3(46)
            Datos_interfaz_tres(1).Advertencia_de_zona_escolar = app.Matriz3(47)
            Datos_interfaz_tres(1).Supervisor_en_el_cruce_de_zona_escolar= app.Matriz3(48)
            Datos_interfaz_tres(1).Limite_de_velocidad = app.Matriz3(49)
            Datos_interfaz_tres(1).Diferencial_de_limites_de_velocidad = app.Matriz3(50)
            Datos_interfaz_tres(1).Gestion_de_la_velocidad_calmante_del_trafico = app.Matriz3(51)
            Datos_interfaz_tres(1).Velocidad_de_operacion_percentil_85 = app.Matriz3(52)
            Datos_interfaz_tres(1).Transitabilidad = app.Matriz3(53)
            
            %Matrices de los Coeficientes
            %Matriz 4
            Coeficientes_Veh(1).Salida_lado_conductor=cosalidaconductorveh;
            %Matriz 4.1
            Coeficientes_Veh(1).Salida_lado_copiloto=cosalidacopilotoveh;
            %Matriz 4.2
            Coeficientes_Veh(1).Perdida_de_control=coperdidacontrolveh;
            %Matriz 4.3
            Coeficientes_Veh(1).Adelantamiento=coadelantamientoveh;
            %Matriz 4.4
            Coeficientes_Veh(1).Interseccion=cointerseccionveh;
            %Matriz 4.5
            Coeficientes_Veh(1).Acceso_a_Propiedades=coaccesopropiedadesveh;
            %Matriz 5
            Coeficientes_Mot(1).Salida_lado_conductor=cosalidaconductormot;
            %Matriz 5.1
            Coeficientes_Mot(1).Salida_lado_copiloto=cosalidacopilotomot;
            %Matriz 5.2
            Coeficientes_Mot(1).Perdida_de_control=coperdidacontrolmot;
            %Matriz 5.3
            Coeficientes_Mot(1).Adelantamiento=coadelantamientomot;
            %Matriz 5.4
            Coeficientes_Mot(1).Interseccion=cointerseccionmot;
            %Matriz 5.5
            Coeficientes_Mot(1).Acceso_a_Propiedades=coaccesopropiedadesmot;
            %Matriz 5.6
            Coeficientes_Mot(1).A_lo_largo=colargomot;
            %Matriz 6
            Coeficientes_Ci(1).A_lo_largo=colargoci;
            %Matriz 6.1
            Coeficientes_Ci(1).Salida_camino=cosalidaci;
            %Matriz 6.2
            Coeficientes_Ci(1).Interseccion=cointerseccionci;
            %Matriz 7
            Coeficientes_Pe(1).A_lo_largo_conductor=colargoconpe;
            %Matriz 7.1
            Coeficientes_Pe(1).A_lo_largo_copiloto=colargocoppe;
            %Matriz 7.2
            Coeficientes_Pe(1).Cruce_peatonal_inspeccionada=coinsppe;
            %Matriz 7.3
            Coeficientes_Pe(1).Cruce_peatonal_lateral=colatpe;
            
            %Se guardan las mapp.v1atrices
            save('../mat/Datos_atributos_proceso',"Atributo_elegido")
            save('../mat/Datos_interfaz_uno_proceso',"Datos_interfaz_uno")
            save('../mat/Datos_interfaz_dos_proceso',"Datos_interfaz_dos")
            save('../mat/Coeficientes_Veh',"Coeficientes_Veh")
            save('../mat/Coeficientes_Mot',"Coeficientes_Mot")
            save('../mat/Coeficientes_Ci',"Coeficientes_Ci")
            save('../mat/Coeficientes_Pe',"Coeficientes_Pe")
            save('../mat/Datos_interfaz_tres_proceso',"Datos_interfaz_tres")
            
            %Estrellas vehiculo
            if app.VehculoEditField.Value==5
            imagen2=imread('E55.jpg')
            imshow(imagen2,'Parent',app.UIAxes2)
            else if app.VehculoEditField.Value==4
            imagen2=imread('E44.jpg')
            imshow(imagen2,'Parent',app.UIAxes2)
            else if app.VehculoEditField.Value==3
            imagen2=imread('E33.jpg')
            imshow(imagen2,'Parent',app.UIAxes2)
            else if app.VehculoEditField.Value==2
            imagen2=imread('E22.jpg')
            imshow(imagen2,'Parent',app.UIAxes2)
            else if app.VehculoEditField.Value==1
            imagen2=imread('E11.jpg')
            imshow(imagen2,'Parent',app.UIAxes2)
            else if app.VehculoEditField.Value==0
            imagen2=imread('E0.jpg')
            imshow(imagen2,'Parent',app.UIAxes2)
            end
            end
            end
            end
            end
            end
            %Estrellas Motociclista
            if app.MotociclistaEditField.Value==5
            imagen3=imread('E55.jpg')
            imshow(imagen3,'Parent',app.UIAxes4)
            else if app.MotociclistaEditField.Value==4
            imagen3=imread('E44.jpg')
            imshow(imagen3,'Parent',app.UIAxes4)
            else if app.MotociclistaEditField.Value==3
            imagen3=imread('E33.jpg')
            imshow(imagen3,'Parent',app.UIAxes4)
            else if app.MotociclistaEditField.Value==2
            imagen3=imread('E22.jpg')
            imshow(imagen3,'Parent',app.UIAxes4)
            else if app.MotociclistaEditField.Value==1
            imagen3=imread('E11.jpg')
            imshow(imagen3,'Parent',app.UIAxes4)
            else if app.MotociclistaEditField.Value==0
            imagen3=imread('E0.jpg')
            imshow(imagen3,'Parent',app.UIAxes4)
            end
            end
            end
            end
            end
            end
            %Estrellas Ciclista
            if app.CiclistaEditField.Value==5
            imagen4=imread('E55.jpg')
            imshow(imagen4,'Parent',app.UIAxes3)
            else if app.CiclistaEditField.Value==4
            imagen4=imread('E44.jpg')
            imshow(imagen4,'Parent',app.UIAxes3)
            else if app.CiclistaEditField.Value==3
            imagen4=imread('E33.jpg')
            imshow(imagen4,'Parent',app.UIAxes3)
            else if app.CiclistaEditField.Value==2
            imagen4=imread('E22.jpg')
            imshow(imagen4,'Parent',app.UIAxes3)
            else if app.CiclistaEditField.Value==1
            imagen4=imread('E11.jpg')
            imshow(imagen4,'Parent',app.UIAxes3)
            else if app.CiclistaEditField.Value==0
            imagen4=imread('E0.jpg')
            imshow(imagen4,'Parent',app.UIAxes3)
            end
            end
            end
            end
            end
            end
            %Estrellas Peaton
            if app.PeatnEditField.Value==5
            imagen5=imread('E55.jpg')
            imshow(imagen5,'Parent',app.UIAxes5)
            else if app.PeatnEditField.Value==4
            imagen5=imread('E44.jpg')
            imshow(imagen5,'Parent',app.UIAxes5)
            else if app.PeatnEditField.Value==3
            imagen5=imread('E33.jpg')
            imshow(imagen5,'Parent',app.UIAxes5)
            else if app.PeatnEditField.Value==2
            imagen5=imread('E22.jpg')
            imshow(imagen5,'Parent',app.UIAxes5)
            else if app.PeatnEditField.Value==1
            imagen5=imread('E11.jpg')
            imshow(imagen5,'Parent',app.UIAxes5)
            else if app.PeatnEditField.Value==0
            imagen5=imread('E0.jpg')
            imshow(imagen4,'Parent',app.UIAxes5)
            end
            end
            end
            end
            end
            end
                
            end


        end

        % Button pushed function: SiguientetramoButton
        function SiguientetramoButtonPushed(app, event)
            app.Matriz1=[];
            Button_10ValueChanged(app)
            Button_9ValueChanged(app)
            
            %Hago que vuelva a la pantalla inicial "Costado de la via"
            app.TabGroup.SelectedTab = app.CostadodelavaTab;
            
            %Datos de ingreso
            app.UIAxes2.Visible='off';
            imagen2.Visible='off'
            app.UIAxes3.Visible='off';
            imagen3.Visible='off'
            app.UIAxes4.Visible='off';
            imagen4.Visible='off'
            app.UIAxes5.Visible='off';
            imagen5.Visible='off'
            app.Image.Visible='off';


            %Botones de costado de vía
app.Button.BackgroundColor=[0.96,0.96,0.96];
app.Button_2.BackgroundColor=[0.96,0.96,0.96];
app.Button_3.BackgroundColor=[0.96,0.96,0.96];
app.Button_4.BackgroundColor=[0.96,0.96,0.96];
app.Button_5.BackgroundColor=[0.96,0.96,0.96];
app.Button_6.BackgroundColor=[0.96,0.96,0.96];
app.Button_7.BackgroundColor=[0.96,0.96,0.96];
app.Button_8.BackgroundColor=[0.96,0.96,0.96];
app.Button_9.BackgroundColor=[0.96,0.96,0.96];
app.Button_10.BackgroundColor=[0.96,0.96,0.96];
app.Button_11.BackgroundColor=[0.96,0.96,0.96];
app.Button_12.BackgroundColor=[0.96,0.96,0.96];
app.Button_13.BackgroundColor=[0.96,0.96,0.96];
app.Button_14.BackgroundColor=[0.96,0.96,0.96];
app.Button_519.BackgroundColor=[0.96,0.96,0.96];
app.Button_520.BackgroundColor=[0.96,0.96,0.96];
app.Button_521.BackgroundColor=[0.96,0.96,0.96];
app.Button_522.BackgroundColor=[0.96,0.96,0.96];
app.Button_523.BackgroundColor=[0.96,0.96,0.96];
app.Button_524.BackgroundColor=[0.96,0.96,0.96];
app.Button_525.BackgroundColor=[0.96,0.96,0.96];
app.Button_526.BackgroundColor=[0.96,0.96,0.96];
app.Button_527.BackgroundColor=[0.96,0.96,0.96];
app.Button_528.BackgroundColor=[0.96,0.96,0.96];
app.Button_529.BackgroundColor=[0.96,0.96,0.96];
app.Button_530.BackgroundColor=[0.96,0.96,0.96];
app.Button_531.BackgroundColor=[0.96,0.96,0.96];
app.Button_532.BackgroundColor=[0.96,0.96,0.96];
app.Button_533.BackgroundColor=[0.96,0.96,0.96];
app.Button_534.BackgroundColor=[0.96,0.96,0.96];
            app.Button_535.BackgroundColor=[0.96,0.96,0.96];
            
            app.Button_666.BackgroundColor=[0.96,0.96,0.96];
            app.Button_667.BackgroundColor=[0.96,0.96,0.96];
            app.Button_668.BackgroundColor=[0.96,0.96,0.96];
            app.Button_669.BackgroundColor=[0.96,0.96,0.96];
            app.Button_670.BackgroundColor=[0.96,0.96,0.96];
            app.Button_671.BackgroundColor=[0.96,0.96,0.96];
            app.Button_672.BackgroundColor=[0.96,0.96,0.96];
            app.Button_673.BackgroundColor=[0.96,0.96,0.96];
            app.Button_674.BackgroundColor=[0.96,0.96,0.96];
            app.Button_675.BackgroundColor=[0.96,0.96,0.96];
            app.Button_676.BackgroundColor=[0.96,0.96,0.96];
            app.Button_677.BackgroundColor=[0.96,0.96,0.96];
            app.Button_678.BackgroundColor=[0.96,0.96,0.96];
            app.Button_679.BackgroundColor=[0.96,0.96,0.96];
            app.Button_680.BackgroundColor=[0.96,0.96,0.96];
            app.Button_681.BackgroundColor=[0.96,0.96,0.96];
            app.Button_682.BackgroundColor=[0.96,0.96,0.96];
            
            app.Button_683.BackgroundColor=[0.96,0.96,0.96];
            app.Button_684.BackgroundColor=[0.96,0.96,0.96];
            app.Button_685.BackgroundColor=[0.96,0.96,0.96];
            app.Button_686.BackgroundColor=[0.96,0.96,0.96];
            
            %Botones de Mediana
            app.Button_70.BackgroundColor=[0.96,0.96,0.96];
            app.Button_71.BackgroundColor=[0.96,0.96,0.96];
            app.Button_72.BackgroundColor=[0.96,0.96,0.96];
            app.Button_73.BackgroundColor=[0.96,0.96,0.96];
            app.Button_102.BackgroundColor=[0.96,0.96,0.96];
            
            app.Button_491.BackgroundColor=[0.96,0.96,0.96];
            app.Button_492.BackgroundColor=[0.96,0.96,0.96];
            app.Button_493.BackgroundColor=[0.96,0.96,0.96];
            
            app.Button_74.BackgroundColor=[0.96,0.96,0.96];
            app.Button_75.BackgroundColor=[0.96,0.96,0.96];
            app.Button_76.BackgroundColor=[0.96,0.96,0.96];
            app.Button_77.BackgroundColor=[0.96,0.96,0.96];
            app.Button_103.BackgroundColor=[0.96,0.96,0.96];
            app.Button_104.BackgroundColor=[0.96,0.96,0.96];
            app.Button_105.BackgroundColor=[0.96,0.96,0.96];
            app.Button_106.BackgroundColor=[0.96,0.96,0.96];
            app.Button_107.BackgroundColor=[0.96,0.96,0.96];
            app.Button_108.BackgroundColor=[0.96,0.96,0.96];
            app.Button_109.BackgroundColor=[0.96,0.96,0.96];
            app.Button_110.BackgroundColor=[0.96,0.96,0.96];
            app.Button_111.BackgroundColor=[0.96,0.96,0.96];
            app.Button_112.BackgroundColor=[0.96,0.96,0.96];
            app.Button_113.BackgroundColor=[0.96,0.96,0.96];
            
            app.Button_557.BackgroundColor=[0.96,0.96,0.96];
            app.SiButton_2.BackgroundColor=[0.96,0.96,0.96];
            
            app.Button_559.BackgroundColor=[0.96,0.96,0.96];
            app.Button_660.BackgroundColor=[0.96,0.96,0.96];
            app.Button_661.BackgroundColor=[0.96,0.96,0.96];
            app.Button_662.BackgroundColor=[0.96,0.96,0.96];
            app.Button_121.BackgroundColor=[0.96,0.96,0.96];
            app.Button_122.BackgroundColor=[0.96,0.96,0.96];
            
            app.CarrilG_2.BackgroundColor=[0.96,0.96,0.96];
            app.CarrilM.BackgroundColor=[0.96,0.96,0.96];
            app.CarrilP.BackgroundColor=[0.96,0.96,0.96];
            
            app.Button_116.BackgroundColor=[0.96,0.96,0.96];
            app.Button_115.BackgroundColor=[0.96,0.96,0.96];
            app.Button_79.BackgroundColor=[0.96,0.96,0.96];
            app.Button_78.BackgroundColor=[0.96,0.96,0.96];
            
            app.Button_92.BackgroundColor=[0.96,0.96,0.96];
            app.Button_93.BackgroundColor=[0.96,0.96,0.96];
            app.Button_94.BackgroundColor=[0.96,0.96,0.96];
            
            app.Button_98.BackgroundColor=[0.96,0.96,0.96];
            app.Button_97.BackgroundColor=[0.96,0.96,0.96];
            app.Button_96.BackgroundColor=[0.96,0.96,0.96];
            
            app.Button_562.BackgroundColor=[0.96,0.96,0.96];
            app.Button_561.BackgroundColor=[0.96,0.96,0.96];
            app.Button_560.BackgroundColor=[0.96,0.96,0.96];
            
            app.Button_126.BackgroundColor=[0.96,0.96,0.96];
            app.Button_101.BackgroundColor=[0.96,0.96,0.96];
            app.Button_127.BackgroundColor=[0.96,0.96,0.96];
            app.Button_100.BackgroundColor=[0.96,0.96,0.96];
            app.Button_128.BackgroundColor=[0.96,0.96,0.96];
            
            app.Button_563.BackgroundColor=[0.96,0.96,0.96];
            app.Button_564.BackgroundColor=[0.96,0.96,0.96];
            
            app.Button_565.BackgroundColor=[0.96,0.96,0.96];
            app.Button_566.BackgroundColor=[0.96,0.96,0.96];
            
            app.Button_150.BackgroundColor=[0.96,0.96,0.96];
            app.Button_151.BackgroundColor=[0.96,0.96,0.96];
            app.Button_567.BackgroundColor=[0.96,0.96,0.96];
            
            app.Button_139.BackgroundColor=[0.96,0.96,0.96];
            app.Button_138.BackgroundColor=[0.96,0.96,0.96];
app.Button_141.BackgroundColor=[0.96,0.96,0.96];
app.Button_145.BackgroundColor=[0.96,0.96,0.96];
app.Button_144.BackgroundColor=[0.96,0.96,0.96];
app.SiButton_18.BackgroundColor=[0.96,0.96,0.96];
app.NoButton_19.BackgroundColor=[0.96,0.96,0.96];
%Intersecciones
app.Button_207.BackgroundColor=[0.96,0.96,0.96];
app.Button_185.BackgroundColor=[0.96,0.96,0.96];
app.Button_182.BackgroundColor=[0.96,0.96,0.96];
app.Button_179.BackgroundColor=[0.96,0.96,0.96];
app.Button_183.BackgroundColor=[0.96,0.96,0.96];
app.Button_181.BackgroundColor=[0.96,0.96,0.96];
app.Button_176.BackgroundColor=[0.96,0.96,0.96];
app.Button_152.BackgroundColor=[0.96,0.96,0.96];
app.Button_180.BackgroundColor=[0.96,0.96,0.96];
app.Button_177.BackgroundColor=[0.96,0.96,0.96];
app.Button_174.BackgroundColor=[0.96,0.96,0.96];
app.Button_178.BackgroundColor=[0.96,0.96,0.96];
app.Button_184.BackgroundColor=[0.96,0.96,0.96];
app.Button_154.BackgroundColor=[0.96,0.96,0.96];
app.Button_155.BackgroundColor=[0.96,0.96,0.96];
app.Button_153.BackgroundColor=[0.96,0.96,0.96];
app.Button_569.BackgroundColor=[0.96,0.96,0.96];
app.Button_568.BackgroundColor=[0.96,0.96,0.96];
app.Button_189.BackgroundColor=[0.96,0.96,0.96];
app.Button_198.BackgroundColor=[0.96,0.96,0.96];
app.Button_210.BackgroundColor=[0.96,0.96,0.96];
app.Button_211.BackgroundColor=[0.96,0.96,0.96];
app.Button_212.BackgroundColor=[0.96,0.96,0.96];
app.Button_208.BackgroundColor=[0.96,0.96,0.96];
app.Button_209.BackgroundColor=[0.96,0.96,0.96];
app.Button_214.BackgroundColor=[0.96,0.96,0.96];
app.Button_215.BackgroundColor=[0.96,0.96,0.96];
app.Button_213.BackgroundColor=[0.96,0.96,0.96];
app.Button_169.BackgroundColor=[0.96,0.96,0.96];
app.Button_170.BackgroundColor=[0.96,0.96,0.96];
app.Button_171.BackgroundColor=[0.96,0.96,0.96];
app.Button_216.BackgroundColor=[0.96,0.96,0.96];
%Flujo
app.FlujovehicularTDPAEditField.Value=0;
app.NAButton.BackgroundColor=[0.96,0.96,0.96];
app.Button_270.BackgroundColor=[0.96,0.96,0.96];
app.Button_271.BackgroundColor=[0.96,0.96,0.96];
app.Button_263.BackgroundColor=[0.96,0.96,0.96];
app.Button_264.BackgroundColor=[0.96,0.96,0.96];
app.Button_261.BackgroundColor=[0.96,0.96,0.96];
app.Button_262.BackgroundColor=[0.96,0.96,0.96];
app.Button_275.BackgroundColor=[0.96,0.96,0.96];
app.Button_276.BackgroundColor=[0.96,0.96,0.96];
app.Button_712.BackgroundColor=[0.96,0.96,0.96];
app.Button_265.BackgroundColor=[0.96,0.96,0.96];
app.Button_266.BackgroundColor=[0.96,0.96,0.96];
app.Button_267.BackgroundColor=[0.96,0.96,0.96];
app.Button_277.BackgroundColor=[0.96,0.96,0.96];
app.Button_278.BackgroundColor=[0.96,0.96,0.96];
app.Button_279.BackgroundColor=[0.96,0.96,0.96];
app.Button_280.BackgroundColor=[0.96,0.96,0.96];
app.Button_281.BackgroundColor=[0.96,0.96,0.96];
app.Button_282.BackgroundColor=[0.96,0.96,0.96];
app.Button_283.BackgroundColor=[0.96,0.96,0.96];
app.Button_284.BackgroundColor=[0.96,0.96,0.96];
app.Button_572.BackgroundColor=[0.96,0.96,0.96];
app.Button_573.BackgroundColor=[0.96,0.96,0.96];
app.Button_574.BackgroundColor=[0.96,0.96,0.96];
app.Button_575.BackgroundColor=[0.96,0.96,0.96];
app.Button_576.BackgroundColor=[0.96,0.96,0.96];
app.Button_577.BackgroundColor=[0.96,0.96,0.96];
app.Button_578.BackgroundColor=[0.96,0.96,0.96];
app.Button_579.BackgroundColor=[0.96,0.96,0.96];
app.Button_580.BackgroundColor=[0.96,0.96,0.96];
app.Button_581.BackgroundColor=[0.96,0.96,0.96];
app.Button_582.BackgroundColor=[0.96,0.96,0.96];
app.Button_583.BackgroundColor=[0.96,0.96,0.96];
app.Button_584.BackgroundColor=[0.96,0.96,0.96];
app.Button_585.BackgroundColor=[0.96,0.96,0.96];
app.Button_586.BackgroundColor=[0.96,0.96,0.96];
app.Button_587.BackgroundColor=[0.96,0.96,0.96];
app.Button_588.BackgroundColor=[0.96,0.96,0.96];
app.Button_589.BackgroundColor=[0.96,0.96,0.96];
app.Button_590.BackgroundColor=[0.96,0.96,0.96];
app.Button_591.BackgroundColor=[0.96,0.96,0.96];
app.Button_592.BackgroundColor=[0.96,0.96,0.96];
app.Button_593.BackgroundColor=[0.96,0.96,0.96];
app.Button_594.BackgroundColor=[0.96,0.96,0.96];
app.Button_595.BackgroundColor=[0.96,0.96,0.96];
app.Button_596.BackgroundColor=[0.96,0.96,0.96];
app.Button_597.BackgroundColor=[0.96,0.96,0.96];
app.Button_598.BackgroundColor=[0.96,0.96,0.96];
app.Button_599.BackgroundColor=[0.96,0.96,0.96];
app.Button_600.BackgroundColor=[0.96,0.96,0.96];
app.Button_601.BackgroundColor=[0.96,0.96,0.96];
app.Button_602.BackgroundColor=[0.96,0.96,0.96];
app.Button_603.BackgroundColor=[0.96,0.96,0.96];
app.Button_604.BackgroundColor=[0.96,0.96,0.96];
%Infraestructuras
app.Button_340.BackgroundColor=[0.96,0.96,0.96];
app.Button_321.BackgroundColor=[0.96,0.96,0.96];
app.Button_320.BackgroundColor=[0.96,0.96,0.96];
app.Button_318.BackgroundColor=[0.96,0.96,0.96];
app.Button_425.BackgroundColor=[0.96,0.96,0.96];
app.Button_319.BackgroundColor=[0.96,0.96,0.96];
app.Button_710.BackgroundColor=[0.96,0.96,0.96];
app.Button_709.BackgroundColor=[0.96,0.96,0.96];
app.Button_708.BackgroundColor=[0.96,0.96,0.96];
app.Button_706.BackgroundColor=[0.96,0.96,0.96];
app.Button_711.BackgroundColor=[0.96,0.96,0.96];
app.Button_707.BackgroundColor=[0.96,0.96,0.96];
app.Button_612.BackgroundColor=[0.96,0.96,0.96];
app.Button_611.BackgroundColor=[0.96,0.96,0.96];
app.Button_384.BackgroundColor=[0.96,0.96,0.96];
app.Button_383.BackgroundColor=[0.96,0.96,0.96];
app.Button_390.BackgroundColor=[0.96,0.96,0.96];
app.Button_382.BackgroundColor=[0.96,0.96,0.96];
app.Button_381.BackgroundColor=[0.96,0.96,0.96];
app.Button_380.BackgroundColor=[0.96,0.96,0.96];
app.Button_386.BackgroundColor=[0.96,0.96,0.96];
app.Button_387.BackgroundColor=[0.96,0.96,0.96];
app.Button_385.BackgroundColor=[0.96,0.96,0.96];
app.Button_388.BackgroundColor=[0.96,0.96,0.96];
app.Button_389.BackgroundColor=[0.96,0.96,0.96];
app.Button_357.BackgroundColor=[0.96,0.96,0.96];
app.Button_329.BackgroundColor=[0.96,0.96,0.96];
app.Button_356.BackgroundColor=[0.96,0.96,0.96];
app.Button_691.BackgroundColor=[0.96,0.96,0.96];
app.Button_690.BackgroundColor=[0.96,0.96,0.96];
app.Button_697.BackgroundColor=[0.96,0.96,0.96];
app.Button_689.BackgroundColor=[0.96,0.96,0.96];
app.Button_688.BackgroundColor=[0.96,0.96,0.96];
app.Button_687.BackgroundColor=[0.96,0.96,0.96];
app.Button_693.BackgroundColor=[0.96,0.96,0.96];
app.Button_694.BackgroundColor=[0.96,0.96,0.96];
app.Button_692.BackgroundColor=[0.96,0.96,0.96];
app.Button_695.BackgroundColor=[0.96,0.96,0.96];
app.Button_696.BackgroundColor=[0.96,0.96,0.96];
app.Button_332.BackgroundColor=[0.96,0.96,0.96];
app.Button_333.BackgroundColor=[0.96,0.96,0.96];
app.Button_403.BackgroundColor=[0.96,0.96,0.96];
app.Button_402.BackgroundColor=[0.96,0.96,0.96];
app.Button_337.BackgroundColor=[0.96,0.96,0.96];
app.Button_336.BackgroundColor=[0.96,0.96,0.96];
app.Button_335.BackgroundColor=[0.96,0.96,0.96];
app.Button_404.BackgroundColor=[0.96,0.96,0.96];
app.Button_405.BackgroundColor=[0.96,0.96,0.96];
app.Button_702.BackgroundColor=[0.96,0.96,0.96];
app.Button_701.BackgroundColor=[0.96,0.96,0.96];
app.Button_700.BackgroundColor=[0.96,0.96,0.96];
app.Button_699.BackgroundColor=[0.96,0.96,0.96];
app.Button_698.BackgroundColor=[0.96,0.96,0.96];
app.Button_703.BackgroundColor=[0.96,0.96,0.96];
app.Button_704.BackgroundColor=[0.96,0.96,0.96];
app.Button_416.BackgroundColor=[0.96,0.96,0.96];
app.Button_415.BackgroundColor=[0.96,0.96,0.96];
app.Button_418.BackgroundColor=[0.96,0.96,0.96];
app.Button_417.BackgroundColor=[0.96,0.96,0.96];
app.Button_414.BackgroundColor=[0.96,0.96,0.96];
app.Button_413.BackgroundColor=[0.96,0.96,0.96];
app.Button_424.BackgroundColor=[0.96,0.96,0.96];
app.Button_705.BackgroundColor=[0.96,0.96,0.96];
app.Button_420.BackgroundColor=[0.96,0.96,0.96];
app.Button_419.BackgroundColor=[0.96,0.96,0.96];
app.Button_422.BackgroundColor=[0.96,0.96,0.96];
app.Button_423.BackgroundColor=[0.96,0.96,0.96];
app.Button_421.BackgroundColor=[0.96,0.96,0.96];
app.Button_427.BackgroundColor=[0.96,0.96,0.96];
app.Button_428.BackgroundColor=[0.96,0.96,0.96];
app.Button_429.BackgroundColor=[0.96,0.96,0.96];
app.Button_430.BackgroundColor=[0.96,0.96,0.96];
app.Button_432.BackgroundColor=[0.96,0.96,0.96];
app.Button_431.BackgroundColor=[0.96,0.96,0.96];
app.Button_433.BackgroundColor=[0.96,0.96,0.96];
%Velocidades
app.Button_434.BackgroundColor=[0.96,0.96,0.96];
app.Button_435.BackgroundColor=[0.96,0.96,0.96];
app.Button_436.BackgroundColor=[0.96,0.96,0.96];
app.Button_437.BackgroundColor=[0.96,0.96,0.96];
app.Button_438.BackgroundColor=[0.96,0.96,0.96];
app.Button_446.BackgroundColor=[0.96,0.96,0.96];
app.Button_447.BackgroundColor=[0.96,0.96,0.96];
app.Button_448.BackgroundColor=[0.96,0.96,0.96];
app.Button_449.BackgroundColor=[0.96,0.96,0.96];
app.Button_450.BackgroundColor=[0.96,0.96,0.96];
app.Button_451.BackgroundColor=[0.96,0.96,0.96];
app.Button_452.BackgroundColor=[0.96,0.96,0.96];
app.Button_453.BackgroundColor=[0.96,0.96,0.96];
app.Button_454.BackgroundColor=[0.96,0.96,0.96];
app.Button_455.BackgroundColor=[0.96,0.96,0.96];
app.Button_456.BackgroundColor=[0.96,0.96,0.96];
app.Button_457.BackgroundColor=[0.96,0.96,0.96];
app.Button_458.BackgroundColor=[0.96,0.96,0.96];
app.Button_459.BackgroundColor=[0.96,0.96,0.96];
app.Button_460.BackgroundColor=[0.96,0.96,0.96];
app.Button_461.BackgroundColor=[0.96,0.96,0.96];
app.Button_462.BackgroundColor=[0.96,0.96,0.96];
app.Button_463.BackgroundColor=[0.96,0.96,0.96];
app.Button_464.BackgroundColor=[0.96,0.96,0.96];
app.Button_465.BackgroundColor=[0.96,0.96,0.96];
app.Button_659.BackgroundColor=[0.96,0.96,0.96];
app.Button_658.BackgroundColor=[0.96,0.96,0.96];
app.Button_631.BackgroundColor=[0.96,0.96,0.96];
app.Button_632.BackgroundColor=[0.96,0.96,0.96];
app.Button_633.BackgroundColor=[0.96,0.96,0.96];
app.Button_634.BackgroundColor=[0.96,0.96,0.96];
app.Button_635.BackgroundColor=[0.96,0.96,0.96];
app.Button_636.BackgroundColor=[0.96,0.96,0.96];
app.Button_637.BackgroundColor=[0.96,0.96,0.96];
app.Button_638.BackgroundColor=[0.96,0.96,0.96];
app.Button_639.BackgroundColor=[0.96,0.96,0.96];
app.Button_640.BackgroundColor=[0.96,0.96,0.96];
app.Button_641.BackgroundColor=[0.96,0.96,0.96];
app.Button_642.BackgroundColor=[0.96,0.96,0.96];
app.Button_643.BackgroundColor=[0.96,0.96,0.96];
app.Button_644.BackgroundColor=[0.96,0.96,0.96];
app.Button_645.BackgroundColor=[0.96,0.96,0.96];
app.Button_646.BackgroundColor=[0.96,0.96,0.96];
app.Button_647.BackgroundColor=[0.96,0.96,0.96];
app.Button_648.BackgroundColor=[0.96,0.96,0.96];
app.Button_649.BackgroundColor=[0.96,0.96,0.96];
app.Button_650.BackgroundColor=[0.96,0.96,0.96];
app.Button_651.BackgroundColor=[0.96,0.96,0.96];
app.Button_652.BackgroundColor=[0.96,0.96,0.96];
app.Button_653.BackgroundColor=[0.96,0.96,0.96];
app.Button_654.BackgroundColor=[0.96,0.96,0.96];
app.Button_655.BackgroundColor=[0.96,0.96,0.96];
app.Button_656.BackgroundColor=[0.96,0.96,0.96];
app.Button_665.BackgroundColor=[0.96,0.96,0.96];
%Resultados
app.VehculoEditField.Value=0;
app.MotociclistaEditField.Value=0;
app.CiclistaEditField.Value=0;
app.PeatnEditField.Value=0;

            
        end

        % Callback function
        function ResultadoTabSizeChanged(app, event)
             position = app.ResultadoTab.Position;
           [EstVehiculo,EstMotocicleta,EstCiclista,EstPeaton]=Calculo_estrellas_VehIntentoFinal(app.Matriz1);
         
           app.VehculoEditField.Value=EstVehiculo;
           app.MotociclistaEditField.Value=EstMotocicleta;
           app.CiclistaEditField.Value=EstCiclista;
           app.PeatnEditField.Value=EstPeaton;
           
           %Matriz generada
            %load('./mat/Matriz_proceso')
            %load('./mat/Matriz2_proceso')
            
            Atributo_elegido(app.v1).Atributo=app.Matriz1;
            %save('./mat/Matriz_proceso',"Atributo_elegido")
            
            Datos_interfaz_2(app.v1).Ocupante_del_vehiculo = app.VehculoEditField.Value
            Datos_interfaz_2(app.v1).Motociclista = app.MotociclistaEditField.Value
            Datos_interfaz_2(app.v1).Ciclista = app.CiclistaEditField.Value
            Datos_interfaz_2(app.v1).Peaton = app.PeatnEditField.Value
            
            Datos_interfaz_2.Ocupante_del_vehiculo
            Datos_interfaz_2.Motociclista
            Datos_interfaz_2.Ciclista
            Datos_interfaz_2.Ciclista
            
            %save('./mat/Matriz2_proceso',"Datos_interfaz_2")
            %fin
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Callback function
        function MedianaTabSizeChanged(app, event)
           
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            %Se cargan las matrices guardadas al iniciar la aplicacion
           %load('../mat/Datos_atributos_proceso')
           %load('../mat/Datos_interfaz_uno_proceso')
           %load('../mat/Datos_interfaz_dos_proceso')
           %load('../mat/Coeficientes_Veh')
           %load('../mat/Coeficientes_Mot')
           %load('../mat/Coeficientes_Ci')
           %load('../mat/Coeficientes_Pe')
           %load('../mat/Datos_interfaz_tres_proceso')
           
           
           %CUANDO SPINNER MAYOR A 1
           if app.v1==1
           load('../mat/Datos_atributos_proceso')
           load('../mat/Datos_interfaz_uno_proceso')
           load('../mat/Datos_interfaz_dos_proceso')
           load('../mat/Coeficientes_Veh')
           load('../mat/Coeficientes_Mot')
           load('../mat/Coeficientes_Ci')
           load('../mat/Coeficientes_Pe')
           load('../mat/Datos_interfaz_tres_proceso')
            
                            %CALCULO 
                %Se carga la funcion para extraer y mostrar los datos
            if strcmp(selectedTab.Title,'Resultado')
           [cosalidaconductorveh,cosalidacopilotoveh,coperdidacontrolveh,coadelantamientoveh,cointerseccionveh,coaccesopropiedadesveh,cosalidaconductormot,cosalidacopilotomot,coperdidacontrolmot,coadelantamientomot,cointerseccionmot,coaccesopropiedadesmot,colargomot,colargoci,cosalidaci,cointerseccionci,colargoconpe,colargocoppe,coinsppe,colatpe,EstVehiculo,EstMotocicleta,EstCiclista,EstPeaton,EstVehicul,EstMoto,EstCicl,EstPeato]=Calculo_estrellas_VehIntentoFinal(app.Matriz1);

           app.VehculoEditField.Value=EstVehiculo;
           app.MotociclistaEditField.Value=EstMotocicleta;
           app.CiclistaEditField.Value=EstCiclista;
           app.PeatnEditField.Value=EstPeaton;
           app.ValorEstVeh.Value=EstVehicul;
           app.ValorEstPeaton.Value=EstPeato;
           app.ValorEstCicli.Value=EstCicl;
           app.ValorEstMoto.Value=EstMoto;

           
           %Se carga la matriz proveniente del pre-proceso

            load('../mat/preproceso_tramo')
            
            %Para graficar 
            %GRAFICAR
            Tramos=[tramo(app.v1).Tramos];
            Distancia=[tramo(app.v1).Distancia];
            Xt=([tramo(app.v1).Xt]);
            yt=([tramo(app.v1).yt]);
            met_cord=size(Xt,1)
            if met_cord>1
                plot(app.UIAxes,Xt,yt)
            else
                sin_coord = imread('../img/blancoo.jpg')
                imshow(sin_coord,'Parent',app.UIAxes)
            end
            
            %Atributos Matriz 1
            Atributo_elegido(app.v1).Atributo=app.Matriz1
           
            %Datos interfaz uno Matriz 2
            Datos_interfaz_uno(app.v1).Tramos=Tramos;
            Datos_interfaz_uno(app.v1).Distancia=Distancia;
            Datos_interfaz_uno(app.v1).Xt=Xt;
            Datos_interfaz_uno(app.v1).yt=yt;
            Datos_interfaz_uno(app.v1).Ocupante_del_vehiculo = app.VehculoEditField.Value
            Datos_interfaz_uno(app.v1).Motociclista = app.MotociclistaEditField.Value
            Datos_interfaz_uno(app.v1).Ciclista = app.CiclistaEditField.Value
            Datos_interfaz_uno(app.v1).Peaton = app.PeatnEditField.Value
            Datos_interfaz_uno(app.v1).Puntuacion_Ocupante_del_vehiculo = app.ValorEstVeh.Value
            Datos_interfaz_uno(app.v1).Puntuacion_Motociclista = app.ValorEstMoto.Value
            Datos_interfaz_uno(app.v1).Puntuacion_Ciclista = app.ValorEstCicli.Value
            Datos_interfaz_uno(app.v1).Puntuacion_Peaton = app.ValorEstPeaton.Value
            Datos_interfaz_uno(app.v1).Severidad_lateral_distancia_al_objeto_lado_del_conductor = app.Matriz2(1)
            Datos_interfaz_uno(app.v1).Severidad_lateral_objeto_lado_del_conductor= app.Matriz2(2)
            Datos_interfaz_uno(app.v1).Severidad_lateral_distancia_al_objeto_lado_del_copiloto = app.Matriz2(3)
            Datos_interfaz_uno(app.v1).Severidad_lateral_objeto_lado_del_copiloto = app.Matriz2(4)
            Datos_interfaz_uno(app.v1).Bandas_sonoras_en_el_espaldon = app.Matriz2(5)
            Datos_interfaz_uno(app.v1).Ancho_de_espaldon_pavimentado_lado_del_conductor = app.Matriz2(6)
            Datos_interfaz_uno(app.v1).Ancho_de_espaldon_pavimentado_lado_del_copiloto = app.Matriz2(7)
            Datos_interfaz_uno(app.v1).Tipo_de_carretera = app.Matriz2(8)
            Datos_interfaz_uno(app.v1).Costo_de_modernizacion= app.Matriz2(9)
            Datos_interfaz_uno(app.v1).Tipo_de_mediana = app.Matriz2(10)
            Datos_interfaz_uno(app.v1).Bandas_sonoras_centrales = app.Matriz2(11)
            Datos_interfaz_uno(app.v1).Numero_de_carriles = app.Matriz2(12)
            Datos_interfaz_uno(app.v1).Ancho_de_carril = app.Matriz2(13)
            Datos_interfaz_uno(app.v1).Curvatura = app.Matriz2(14)
            Datos_interfaz_uno(app.v1).Calidad_de_la_curva = app.Matriz2(15)
            Datos_interfaz_uno(app.v1).Pendiente= app.Matriz2(16)
            Datos_interfaz_uno(app.v1).Estado_de_la_superficie_de_la_via = app.Matriz2(17)
            Datos_interfaz_uno(app.v1).Resistencia_al_deslizamiento = app.Matriz2(18)
            Datos_interfaz_uno(app.v1).Delineacion = app.Matriz2(19)
            Datos_interfaz_uno(app.v1).Alumbrado_publico = app.Matriz2(20)
            Datos_interfaz_uno(app.v1).Estacionamiento_para_vehiculos = app.Matriz2(21)
            Datos_interfaz_uno(app.v1).Via_de_servicio = app.Matriz2(22)
            Datos_interfaz_uno(app.v1).Obras_en_la_carretera = app.Matriz2(23)
            Datos_interfaz_uno(app.v1).Distancia_de_visibilidad = app.Matriz2(24)
            Datos_interfaz_uno(app.v1).Tipo_de_interseccion = app.Matriz2(25)
            Datos_interfaz_uno(app.v1).Canalizacion_de_la_interseccion= app.Matriz2(26)
            Datos_interfaz_uno(app.v1).Volumen_de_la_interseccion = app.Matriz2(27)
            Datos_interfaz_uno(app.v1).Calidad_de_la_interseccion = app.Matriz2(28)
            Datos_interfaz_uno(app.v1).Puntos_de_acceso_a_la_propiedad = app.Matriz2(29)
            Datos_interfaz_uno(app.v1).Flujo_vehicular = app.Matriz2(30)
            Datos_interfaz_uno(app.v1).Porcentaje_de_motocicletas = app.Matriz2(31)
            Datos_interfaz_uno(app.v1).Flujo_peatonal_en_hora_pico_que_cruza_la_carretera = app.Matriz2(32)
            Datos_interfaz_uno(app.v1).Flujo_peatonal_en_hora_pico_a_lo_largo_de_la_carretera_Cond = app.Matriz2(33)
            Datos_interfaz_uno(app.v1).Flujo_peatonal_en_hora_pico_a_lo_largo_de_la_carretera_Cop = app.Matriz2(34)
            Datos_interfaz_uno(app.v1).Flujo_de_bicicletas_en_hora_pico = app.Matriz2(35)
            Datos_interfaz_uno(app.v1).Uso_de_suelo_lado_del_conductor= app.Matriz2(36)
            Datos_interfaz_uno(app.v1).Uso_de_suelo_lado_del_copiloto = app.Matriz2(37)
            Datos_interfaz_uno(app.v1).Tipo_de_area = app.Matriz2(38)
            Datos_interfaz_uno(app.v1).Infraestructura_de_cruce_peatonal_en_el_tramo_analizado = app.Matriz2(39)
            Datos_interfaz_uno(app.v1).Calidad_de_cruce_peatonal = app.Matriz2(40)
            Datos_interfaz_uno(app.v1).Infraestructura_de_cruce_peatonal_en_la_interseccion = app.Matriz2(41)
            Datos_interfaz_uno(app.v1).Vallas_peatonales= app.Matriz2(42)
            Datos_interfaz_uno(app.v1).Acera_lado_del_conductor = app.Matriz2(43)
            Datos_interfaz_uno(app.v1).Acera_lado_del_copiloto = app.Matriz2(44)
            Datos_interfaz_uno(app.v1).Infraestructura_para_motocicletas = app.Matriz2(45)
            Datos_interfaz_uno(app.v1).Infraestructura_para_bicicletas = app.Matriz2(46)
            Datos_interfaz_uno(app.v1).Advertencia_de_zona_escolar = app.Matriz2(47)
            Datos_interfaz_uno(app.v1).Supervisor_en_el_cruce_de_zona_escolar= app.Matriz2(48)
            Datos_interfaz_uno(app.v1).Limite_de_velocidad = app.Matriz2(49)
            Datos_interfaz_uno(app.v1).Diferencial_de_limites_de_velocidad = app.Matriz2(50)
            Datos_interfaz_uno(app.v1).Gestion_de_la_velocidad_calmante_del_trafico = app.Matriz2(51)
            Datos_interfaz_uno(app.v1).Velocidad_de_operacion_percentil_85 = app.Matriz2(52)
            Datos_interfaz_uno(app.v1).Transitabilidad = app.Matriz2(53)
            
            %Datos interfaz dos Matriz 3
            Datos_interfaz_dos(app.v1).Tramos=Tramos;
            Datos_interfaz_dos(app.v1).Distancia=Distancia;
            Datos_interfaz_dos(app.v1).Xt=Xt;
            Datos_interfaz_dos(app.v1).yt=yt;
            Datos_interfaz_dos(app.v1).Ocupante_del_vehiculo = app.VehculoEditField.Value
            Datos_interfaz_dos(app.v1).Motociclista = app.MotociclistaEditField.Value
            Datos_interfaz_dos(app.v1).Ciclista = app.CiclistaEditField.Value
            Datos_interfaz_dos(app.v1).Peaton = app.PeatnEditField.Value
            Datos_interfaz_dos(app.v1).Puntuacion_Ocupante_del_vehiculo = app.ValorEstVeh.Value
            Datos_interfaz_dos(app.v1).Puntuacion_Motociclista = app.ValorEstMoto.Value
            Datos_interfaz_dos(app.v1).Puntuacion_Ciclista = app.ValorEstCicli.Value
            Datos_interfaz_dos(app.v1).Puntuacion_Peaton = app.ValorEstPeaton.Value
            Datos_interfaz_dos(app.v1).Severidad_lateral_distancia_al_objeto_lado_del_conductor = app.Matriz3(1)
            Datos_interfaz_dos(app.v1).Severidad_lateral_objeto_lado_del_conductor= app.Matriz3(2)
            Datos_interfaz_dos(app.v1).Severidad_lateral_distancia_al_objeto_lado_del_copiloto = app.Matriz3(3)
            Datos_interfaz_dos(app.v1).Severidad_lateral_objeto_lado_del_copiloto = app.Matriz3(4)
            Datos_interfaz_dos(app.v1).Bandas_sonoras_en_el_espaldon = app.Matriz3(5)
            Datos_interfaz_dos(app.v1).Ancho_de_espaldon_pavimentado_lado_del_conductor = app.Matriz3(6)
            Datos_interfaz_dos(app.v1).Ancho_de_espaldon_pavimentado_lado_del_copiloto = app.Matriz3(7)
            Datos_interfaz_dos(app.v1).Tipo_de_carretera = app.Matriz3(8)
            Datos_interfaz_dos(app.v1).Costo_de_modernizacion= app.Matriz3(9)
            Datos_interfaz_dos(app.v1).Tipo_de_mediana = app.Matriz3(10)
            Datos_interfaz_dos(app.v1).Bandas_sonoras_centrales = app.Matriz3(11)
            Datos_interfaz_dos(app.v1).Numero_de_carriles = app.Matriz3(12)
            Datos_interfaz_dos(app.v1).Ancho_de_carril = app.Matriz3(13)
            Datos_interfaz_dos(app.v1).Curvatura = app.Matriz3(14)
            Datos_interfaz_dos(app.v1).Calidad_de_la_curva = app.Matriz3(15)
            Datos_interfaz_dos(app.v1).Pendiente= app.Matriz3(16)
            Datos_interfaz_dos(app.v1).Estado_de_la_superficie_de_la_via = app.Matriz3(17)
            Datos_interfaz_dos(app.v1).Resistencia_al_deslizamiento = app.Matriz3(18)
            Datos_interfaz_dos(app.v1).Delineacion = app.Matriz3(19)
            Datos_interfaz_dos(app.v1).Alumbrado_publico = app.Matriz3(20)
            Datos_interfaz_dos(app.v1).Estacionamiento_para_vehiculos = app.Matriz3(21)
            Datos_interfaz_dos(app.v1).Via_de_servicio = app.Matriz3(22)
            Datos_interfaz_dos(app.v1).Obras_en_la_carretera = app.Matriz3(23)
            Datos_interfaz_dos(app.v1).Distancia_de_visibilidad = app.Matriz3(24)
            Datos_interfaz_dos(app.v1).Tipo_de_interseccion = app.Matriz3(25)
            Datos_interfaz_dos(app.v1).Canalizacion_de_la_interseccion= app.Matriz3(26)
            Datos_interfaz_dos(app.v1).Volumen_de_la_interseccion = app.Matriz3(27)
            Datos_interfaz_dos(app.v1).Calidad_de_la_interseccion = app.Matriz3(28)
            Datos_interfaz_dos(app.v1).Puntos_de_acceso_a_la_propiedad = app.Matriz3(29)
            Datos_interfaz_dos(app.v1).Flujo_vehicular = app.Matriz3(30)
            Datos_interfaz_dos(app.v1).Porcentaje_de_motocicletas = app.Matriz3(31)
            Datos_interfaz_dos(app.v1).Flujo_peatonal_en_hora_pico_que_cruza_la_carretera = app.Matriz3(32)
            Datos_interfaz_dos(app.v1).Flujo_peatonal_en_hora_pico_a_lo_largo_de_la_carretera_Cond = app.Matriz3(33)
            Datos_interfaz_dos(app.v1).Flujo_peatonal_en_hora_pico_a_lo_largo_de_la_carretera_Cop = app.Matriz3(34)
            Datos_interfaz_dos(app.v1).Flujo_de_bicicletas_en_hora_pico = app.Matriz3(35)
            Datos_interfaz_dos(app.v1).Uso_de_suelo_lado_del_conductor= app.Matriz3(36)
            Datos_interfaz_dos(app.v1).Uso_de_suelo_lado_del_copiloto = app.Matriz3(37)
            Datos_interfaz_dos(app.v1).Tipo_de_area = app.Matriz3(38)
            Datos_interfaz_dos(app.v1).Infraestructura_de_cruce_peatonal_en_el_tramo_analizado = app.Matriz3(39)
            Datos_interfaz_dos(app.v1).Calidad_de_cruce_peatonal = app.Matriz3(40)
            Datos_interfaz_dos(app.v1).Infraestructura_de_cruce_peatonal_en_la_interseccion = app.Matriz3(41)
            Datos_interfaz_dos(app.v1).Vallas_peatonales= app.Matriz3(42)
            Datos_interfaz_dos(app.v1).Acera_lado_del_conductor = app.Matriz3(43)
            Datos_interfaz_dos(app.v1).Acera_lado_del_copiloto = app.Matriz3(44)
            Datos_interfaz_dos(app.v1).Infraestructura_para_motocicletas = app.Matriz3(45)
            Datos_interfaz_dos(app.v1).Infraestructura_para_bicicletas = app.Matriz3(46)
            Datos_interfaz_dos(app.v1).Advertencia_de_zona_escolar = app.Matriz3(47)
            Datos_interfaz_dos(app.v1).Supervisor_en_el_cruce_de_zona_escolar= app.Matriz3(48)
            Datos_interfaz_dos(app.v1).Limite_de_velocidad = app.Matriz3(49)
            Datos_interfaz_dos(app.v1).Diferencial_de_limites_de_velocidad = app.Matriz3(50)
            Datos_interfaz_dos(app.v1).Gestion_de_la_velocidad_calmante_del_trafico = app.Matriz3(51)
            Datos_interfaz_dos(app.v1).Velocidad_de_operacion_percentil_85 = app.Matriz3(52)
            Datos_interfaz_dos(app.v1).Transitabilidad = app.Matriz3(53)
           
            Datos_interfaz_dos.Ocupante_del_vehiculo
            Datos_interfaz_dos.Motociclista
            Datos_interfaz_dos.Ciclista
            Datos_interfaz_dos.Peaton
            
            %Matriz 8 Nombre atributo una sola fila
            %Datos_interfaz_dos Matriz 3
            Datos_interfaz_tres(1).Tramos=Tramos;
            Datos_interfaz_tres(1).Distancia=Distancia;
            Datos_interfaz_tres(1).Xt=Xt;
            Datos_interfaz_tres(1).yt=yt;
            Datos_interfaz_tres(1).Ocupante_del_vehiculo = app.VehculoEditField.Value
            Datos_interfaz_tres(1).Motociclista = app.MotociclistaEditField.Value
            Datos_interfaz_tres(1).Ciclista = app.CiclistaEditField.Value
            Datos_interfaz_tres(1).Peaton = app.PeatnEditField.Value
            Datos_interfaz_tres(1).Puntuacion_Ocupante_del_vehiculo = app.ValorEstVeh.Value
            Datos_interfaz_tres(1).Puntuacion_Motociclista = app.ValorEstMoto.Value
            Datos_interfaz_tres(1).Puntuacion_Ciclista = app.ValorEstCicli.Value
            Datos_interfaz_tres(1).Puntuacion_Peaton = app.ValorEstPeaton.Value
            Datos_interfaz_tres(1).Severidad_lateral_distancia_al_objeto_lado_del_conductor = app.Matriz3(1)
            Datos_interfaz_tres(1).Severidad_lateral_objeto_lado_del_conductor= app.Matriz3(2)
            Datos_interfaz_tres(1).Severidad_lateral_distancia_al_objeto_lado_del_copiloto = app.Matriz3(3)
            Datos_interfaz_tres(1).Severidad_lateral_objeto_lado_del_copiloto = app.Matriz3(4)
            Datos_interfaz_tres(1).Bandas_sonoras_en_el_espaldon = app.Matriz3(5)
            Datos_interfaz_tres(1).Ancho_de_espaldon_pavimentado_lado_del_conductor = app.Matriz3(6)
            Datos_interfaz_tres(1).Ancho_de_espaldon_pavimentado_lado_del_copiloto = app.Matriz3(7)
            Datos_interfaz_tres(1).Tipo_de_carretera = app.Matriz3(8)
            Datos_interfaz_tres(1).Costo_de_modernizacion= app.Matriz3(9)
            Datos_interfaz_tres(1).Tipo_de_mediana = app.Matriz3(10)
            Datos_interfaz_tres(1).Bandas_sonoras_centrales = app.Matriz3(11)
            Datos_interfaz_tres(1).Numero_de_carriles = app.Matriz3(12)
            Datos_interfaz_tres(1).Ancho_de_carril = app.Matriz3(13)
            Datos_interfaz_tres(1).Curvatura = app.Matriz3(14)
            Datos_interfaz_tres(1).Calidad_de_la_curva = app.Matriz3(15)
            Datos_interfaz_tres(1).Pendiente= app.Matriz3(16)
            Datos_interfaz_tres(1).Estado_de_la_superficie_de_la_via = app.Matriz3(17)
            Datos_interfaz_tres(1).Resistencia_al_deslizamiento = app.Matriz3(18)
            Datos_interfaz_tres(1).Delineacion = app.Matriz3(19)
            Datos_interfaz_tres(1).Alumbrado_publico = app.Matriz3(20)
            Datos_interfaz_tres(1).Estacionamiento_para_vehiculos = app.Matriz3(21)
            Datos_interfaz_tres(1).Via_de_servicio = app.Matriz3(22)
            Datos_interfaz_tres(1).Obras_en_la_carretera = app.Matriz3(23)
            Datos_interfaz_tres(1).Distancia_de_visibilidad = app.Matriz3(24)
            Datos_interfaz_tres(1).Tipo_de_interseccion = app.Matriz3(25)
            Datos_interfaz_tres(1).Canalizacion_de_la_interseccion= app.Matriz3(26)
            Datos_interfaz_tres(1).Volumen_de_la_interseccion = app.Matriz3(27)
            Datos_interfaz_tres(1).Calidad_de_la_interseccion = app.Matriz3(28)
            Datos_interfaz_tres(1).Puntos_de_acceso_a_la_propiedad = app.Matriz3(29)
            Datos_interfaz_tres(1).Flujo_vehicular = app.Matriz3(30)
            Datos_interfaz_tres(1).Porcentaje_de_motocicletas = app.Matriz3(31)
            Datos_interfaz_tres(1).Flujo_peatonal_en_hora_pico_que_cruza_la_carretera = app.Matriz3(32)
            Datos_interfaz_tres(1).Flujo_peatonal_en_hora_pico_a_lo_largo_de_la_carretera_Cond = app.Matriz3(33)
            Datos_interfaz_tres(1).Flujo_peatonal_en_hora_pico_a_lo_largo_de_la_carretera_Cop = app.Matriz3(34)
            Datos_interfaz_tres(1).Flujo_de_bicicletas_en_hora_pico = app.Matriz3(35)
            Datos_interfaz_tres(1).Uso_de_suelo_lado_del_conductor= app.Matriz3(36)
            Datos_interfaz_tres(1).Uso_de_suelo_lado_del_copiloto = app.Matriz3(37)
            Datos_interfaz_tres(1).Tipo_de_area = app.Matriz3(38)
            Datos_interfaz_tres(1).Infraestructura_de_cruce_peatonal_en_el_tramo_analizado = app.Matriz3(39)
            Datos_interfaz_tres(1).Calidad_de_cruce_peatonal = app.Matriz3(40)
            Datos_interfaz_tres(1).Infraestructura_de_cruce_peatonal_en_la_interseccion = app.Matriz3(41)
            Datos_interfaz_tres(1).Vallas_peatonales= app.Matriz3(42)
            Datos_interfaz_tres(1).Acera_lado_del_conductor = app.Matriz3(43)
            Datos_interfaz_tres(1).Acera_lado_del_copiloto = app.Matriz3(44)
            Datos_interfaz_tres(1).Infraestructura_para_motocicletas = app.Matriz3(45)
            Datos_interfaz_tres(1).Infraestructura_para_bicicletas = app.Matriz3(46)
            Datos_interfaz_tres(1).Advertencia_de_zona_escolar = app.Matriz3(47)
            Datos_interfaz_tres(1).Supervisor_en_el_cruce_de_zona_escolar= app.Matriz3(48)
            Datos_interfaz_tres(1).Limite_de_velocidad = app.Matriz3(49)
            Datos_interfaz_tres(1).Diferencial_de_limites_de_velocidad = app.Matriz3(50)
            Datos_interfaz_tres(1).Gestion_de_la_velocidad_calmante_del_trafico = app.Matriz3(51)
            Datos_interfaz_tres(1).Velocidad_de_operacion_percentil_85 = app.Matriz3(52)
            Datos_interfaz_tres(1).Transitabilidad = app.Matriz3(53)
            
            %Matrices de los Coeficientes
            %Matriz 4
            Coeficientes_Veh(1).Salida_lado_conductor=cosalidaconductorveh;
            %Matriz 4.1
            Coeficientes_Veh(1).Salida_lado_copiloto=cosalidacopilotoveh;
            %Matriz 4.2
            Coeficientes_Veh(1).Perdida_de_control=coperdidacontrolveh;
            %Matriz 4.3
            Coeficientes_Veh(1).Adelantamiento=coadelantamientoveh;
            %Matriz 4.4
            Coeficientes_Veh(1).Interseccion=cointerseccionveh;
            %Matriz 4.5
            Coeficientes_Veh(1).Acceso_a_Propiedades=coaccesopropiedadesveh;
            %Matriz 5
            Coeficientes_Mot(1).Salida_lado_conductor=cosalidaconductormot;
            %Matriz 5.1
            Coeficientes_Mot(1).Salida_lado_copiloto=cosalidacopilotomot;
            %Matriz 5.2
            Coeficientes_Mot(1).Perdida_de_control=coperdidacontrolmot;
            %Matriz 5.3
            Coeficientes_Mot(1).Adelantamiento=coadelantamientomot;
            %Matriz 5.4
            Coeficientes_Mot(1).Interseccion=cointerseccionmot;
            %Matriz 5.5
            Coeficientes_Mot(1).Acceso_a_Propiedades=coaccesopropiedadesmot;
            %Matriz 5.6
            Coeficientes_Mot(1).A_lo_largo=colargomot;
            %Matriz 6
            Coeficientes_Ci(1).A_lo_largo=colargoci;
            %Matriz 6.1
            Coeficientes_Ci(1).Salida_camino=cosalidaci;
            %Matriz 6.2
            Coeficientes_Ci(1).Interseccion=cointerseccionci;
            %Matriz 7
            Coeficientes_Pe(1).A_lo_largo_conductor=colargoconpe;
            %Matriz 7.1
            Coeficientes_Pe(1).A_lo_largo_copiloto=colargocoppe;
            %Matriz 7.2
            Coeficientes_Pe(1).Cruce_peatonal_inspeccionada=coinsppe;
            %Matriz 7.3
            Coeficientes_Pe(1).Cruce_peatonal_lateral=colatpe;
            
            %Se guardan las mapp.v1atrices
            save('../mat/Datos_atributos_proceso',"Atributo_elegido")
            save('../mat/Datos_interfaz_uno_proceso',"Datos_interfaz_uno")
            save('../mat/Datos_interfaz_dos_proceso',"Datos_interfaz_dos")
            save('../mat/Coeficientes_Veh',"Coeficientes_Veh")
            save('../mat/Coeficientes_Mot',"Coeficientes_Mot")
            save('../mat/Coeficientes_Ci',"Coeficientes_Ci")
            save('../mat/Coeficientes_Pe',"Coeficientes_Pe")
            save('../mat/Datos_interfaz_tres_proceso',"Datos_interfaz_tres")
            
            %Estrellas vehiculo
            if app.VehculoEditField.Value==5
            imagen2=imread('E55.jpg')
            imshow(imagen2,'Parent',app.UIAxes2)
            else if app.VehculoEditField.Value==4
            imagen2=imread('E44.jpg')
            imshow(imagen2,'Parent',app.UIAxes2)
            else if app.VehculoEditField.Value==3
            imagen2=imread('E33.jpg')
            imshow(imagen2,'Parent',app.UIAxes2)
            else if app.VehculoEditField.Value==2
            imagen2=imread('E22.jpg')
            imshow(imagen2,'Parent',app.UIAxes2)
            else if app.VehculoEditField.Value==1
            imagen2=imread('E11.jpg')
            imshow(imagen2,'Parent',app.UIAxes2)
            else if app.VehculoEditField.Value==0
            imagen2=imread('E0.jpg')
            imshow(imagen2,'Parent',app.UIAxes2)
            end
            end
            end
            end
            end
            end
            %Estrellas Motociclista
            if app.MotociclistaEditField.Value==5
            imagen3=imread('E55.jpg')
            imshow(imagen3,'Parent',app.UIAxes4)
            else if app.MotociclistaEditField.Value==4
            imagen3=imread('E44.jpg')
            imshow(imagen3,'Parent',app.UIAxes4)
            else if app.MotociclistaEditField.Value==3
            imagen3=imread('E33.jpg')
            imshow(imagen3,'Parent',app.UIAxes4)
            else if app.MotociclistaEditField.Value==2
            imagen3=imread('E22.jpg')
            imshow(imagen3,'Parent',app.UIAxes4)
            else if app.MotociclistaEditField.Value==1
            imagen3=imread('E11.jpg')
            imshow(imagen3,'Parent',app.UIAxes4)
            else if app.MotociclistaEditField.Value==0
            imagen3=imread('E0.jpg')
            imshow(imagen3,'Parent',app.UIAxes4)
            end
            end
            end
            end
            end
            end
            %Estrellas Ciclista
            if app.CiclistaEditField.Value==5
            imagen4=imread('E55.jpg')
            imshow(imagen4,'Parent',app.UIAxes3)
            else if app.CiclistaEditField.Value==4
            imagen4=imread('E44.jpg')
            imshow(imagen4,'Parent',app.UIAxes3)
            else if app.CiclistaEditField.Value==3
            imagen4=imread('E33.jpg')
            imshow(imagen4,'Parent',app.UIAxes3)
            else if app.CiclistaEditField.Value==2
            imagen4=imread('E22.jpg')
            imshow(imagen4,'Parent',app.UIAxes3)
            else if app.CiclistaEditField.Value==1
            imagen4=imread('E11.jpg')
            imshow(imagen4,'Parent',app.UIAxes3)
            else if app.CiclistaEditField.Value==0
            imagen4=imread('E0.jpg')
            imshow(imagen4,'Parent',app.UIAxes3)
            end
            end
            end
            end
            end
            end
            %Estrellas Peaton
            if app.PeatnEditField.Value==5
            imagen5=imread('E55.jpg')
            imshow(imagen5,'Parent',app.UIAxes5)
            else if app.PeatnEditField.Value==4
            imagen5=imread('E44.jpg')
            imshow(imagen5,'Parent',app.UIAxes5)
            else if app.PeatnEditField.Value==3
            imagen5=imread('E33.jpg')
            imshow(imagen5,'Parent',app.UIAxes5)
            else if app.PeatnEditField.Value==2
            imagen5=imread('E22.jpg')
            imshow(imagen5,'Parent',app.UIAxes5)
            else if app.PeatnEditField.Value==1
            imagen5=imread('E11.jpg')
            imshow(imagen5,'Parent',app.UIAxes5)
            else if app.PeatnEditField.Value==0
            imagen5=imread('E0.jpg')
            imshow(imagen4,'Parent',app.UIAxes5)
            end
            end
            end
            end
            end
            end
            end
           end
           
           %CUANDO SPINNER MAYOR A 1
           if app.v1>1
           load('../mat/Datos_atributos_proceso')
           load('../mat/Datos_interfaz_uno_proceso')
           load('../mat/Datos_interfaz_dos_proceso')
           load('../mat/Coeficientes_Veh')
           load('../mat/Coeficientes_Mot')
           load('../mat/Coeficientes_Ci')
           load('../mat/Coeficientes_Pe')
           load('../mat/Datos_interfaz_tres_proceso')
               
                
                %CALCULO 
                %Se carga la funcion para extraer y mostrar los datos
            if strcmp(selectedTab.Title,'Resultado')
           [cosalidaconductorveh,cosalidacopilotoveh,coperdidacontrolveh,coadelantamientoveh,cointerseccionveh,coaccesopropiedadesveh,cosalidaconductormot,cosalidacopilotomot,coperdidacontrolmot,coadelantamientomot,cointerseccionmot,coaccesopropiedadesmot,colargomot,colargoci,cosalidaci,cointerseccionci,colargoconpe,colargocoppe,coinsppe,colatpe,EstVehiculo,EstMotocicleta,EstCiclista,EstPeaton,EstVehicul,EstMoto,EstCicl,EstPeato]=Calculo_estrellas_VehIntentoFinal(app.Matriz1);

           app.VehculoEditField.Value=EstVehiculo;
           app.MotociclistaEditField.Value=EstMotocicleta;
           app.CiclistaEditField.Value=EstCiclista;
           app.PeatnEditField.Value=EstPeaton;
           app.ValorEstVeh.Value=EstVehicul;
           app.ValorEstPeaton.Value=EstPeato;
           app.ValorEstCicli.Value=EstCicl;
           app.ValorEstMoto.Value=EstMoto;

           
           %Se carga la matriz proveniente del pre-proceso

            load('../mat/preproceso_tramo')
            
            %Para graficar 
            %GRAFICAR
            Tramos=[tramo(app.v1).Tramos];
            Distancia=[tramo(app.v1).Distancia];
            Xt=([tramo(app.v1).Xt]);
            yt=([tramo(app.v1).yt]);
            met_cord=size(Xt,1)
            if met_cord>1
                plot(app.UIAxes,Xt,yt)
            else
                sin_coord = imread('../img/blancoo.jpg')
                imshow(sin_coord,'Parent',app.UIAxes)
            end
            
            %Atributos Matriz 1
            Atributo_elegido(app.v1).Atributo=app.Matriz1
           
            %Datos interfaz uno Matriz 2
            Datos_interfaz_uno(app.v1).Tramos=Tramos;
            Datos_interfaz_uno(app.v1).Distancia=Distancia;
            Datos_interfaz_uno(app.v1).Xt=Xt;
            Datos_interfaz_uno(app.v1).yt=yt;
            Datos_interfaz_uno(app.v1).Ocupante_del_vehiculo = app.VehculoEditField.Value
            Datos_interfaz_uno(app.v1).Motociclista = app.MotociclistaEditField.Value
            Datos_interfaz_uno(app.v1).Ciclista = app.CiclistaEditField.Value
            Datos_interfaz_uno(app.v1).Peaton = app.PeatnEditField.Value
            Datos_interfaz_uno(app.v1).Puntuacion_Ocupante_del_vehiculo = app.ValorEstVeh.Value
            Datos_interfaz_uno(app.v1).Puntuacion_Motociclista = app.ValorEstMoto.Value
            Datos_interfaz_uno(app.v1).Puntuacion_Ciclista = app.ValorEstCicli.Value
            Datos_interfaz_uno(app.v1).Puntuacion_Peaton = app.ValorEstPeaton.Value
            Datos_interfaz_uno(app.v1).Severidad_lateral_distancia_al_objeto_lado_del_conductor = app.Matriz2(1)
            Datos_interfaz_uno(app.v1).Severidad_lateral_objeto_lado_del_conductor= app.Matriz2(2)
            Datos_interfaz_uno(app.v1).Severidad_lateral_distancia_al_objeto_lado_del_copiloto = app.Matriz2(3)
            Datos_interfaz_uno(app.v1).Severidad_lateral_objeto_lado_del_copiloto = app.Matriz2(4)
            Datos_interfaz_uno(app.v1).Bandas_sonoras_en_el_espaldon = app.Matriz2(5)
            Datos_interfaz_uno(app.v1).Ancho_de_espaldon_pavimentado_lado_del_conductor = app.Matriz2(6)
            Datos_interfaz_uno(app.v1).Ancho_de_espaldon_pavimentado_lado_del_copiloto = app.Matriz2(7)
            Datos_interfaz_uno(app.v1).Tipo_de_carretera = app.Matriz2(8)
            Datos_interfaz_uno(app.v1).Costo_de_modernizacion= app.Matriz2(9)
            Datos_interfaz_uno(app.v1).Tipo_de_mediana = app.Matriz2(10)
            Datos_interfaz_uno(app.v1).Bandas_sonoras_centrales = app.Matriz2(11)
            Datos_interfaz_uno(app.v1).Numero_de_carriles = app.Matriz2(12)
            Datos_interfaz_uno(app.v1).Ancho_de_carril = app.Matriz2(13)
            Datos_interfaz_uno(app.v1).Curvatura = app.Matriz2(14)
            Datos_interfaz_uno(app.v1).Calidad_de_la_curva = app.Matriz2(15)
            Datos_interfaz_uno(app.v1).Pendiente= app.Matriz2(16)
            Datos_interfaz_uno(app.v1).Estado_de_la_superficie_de_la_via = app.Matriz2(17)
            Datos_interfaz_uno(app.v1).Resistencia_al_deslizamiento = app.Matriz2(18)
            Datos_interfaz_uno(app.v1).Delineacion = app.Matriz2(19)
            Datos_interfaz_uno(app.v1).Alumbrado_publico = app.Matriz2(20)
            Datos_interfaz_uno(app.v1).Estacionamiento_para_vehiculos = app.Matriz2(21)
            Datos_interfaz_uno(app.v1).Via_de_servicio = app.Matriz2(22)
            Datos_interfaz_uno(app.v1).Obras_en_la_carretera = app.Matriz2(23)
            Datos_interfaz_uno(app.v1).Distancia_de_visibilidad = app.Matriz2(24)
            Datos_interfaz_uno(app.v1).Tipo_de_interseccion = app.Matriz2(25)
            Datos_interfaz_uno(app.v1).Canalizacion_de_la_interseccion= app.Matriz2(26)
            Datos_interfaz_uno(app.v1).Volumen_de_la_interseccion = app.Matriz2(27)
            Datos_interfaz_uno(app.v1).Calidad_de_la_interseccion = app.Matriz2(28)
            Datos_interfaz_uno(app.v1).Puntos_de_acceso_a_la_propiedad = app.Matriz2(29)
            Datos_interfaz_uno(app.v1).Flujo_vehicular = app.Matriz2(30)
            Datos_interfaz_uno(app.v1).Porcentaje_de_motocicletas = app.Matriz2(31)
            Datos_interfaz_uno(app.v1).Flujo_peatonal_en_hora_pico_que_cruza_la_carretera = app.Matriz2(32)
            Datos_interfaz_uno(app.v1).Flujo_peatonal_en_hora_pico_a_lo_largo_de_la_carretera_Cond = app.Matriz2(33)
            Datos_interfaz_uno(app.v1).Flujo_peatonal_en_hora_pico_a_lo_largo_de_la_carretera_Cop = app.Matriz2(34)
            Datos_interfaz_uno(app.v1).Flujo_de_bicicletas_en_hora_pico = app.Matriz2(35)
            Datos_interfaz_uno(app.v1).Uso_de_suelo_lado_del_conductor= app.Matriz2(36)
            Datos_interfaz_uno(app.v1).Uso_de_suelo_lado_del_copiloto = app.Matriz2(37)
            Datos_interfaz_uno(app.v1).Tipo_de_area = app.Matriz2(38)
            Datos_interfaz_uno(app.v1).Infraestructura_de_cruce_peatonal_en_el_tramo_analizado = app.Matriz2(39)
            Datos_interfaz_uno(app.v1).Calidad_de_cruce_peatonal = app.Matriz2(40)
            Datos_interfaz_uno(app.v1).Infraestructura_de_cruce_peatonal_en_la_interseccion = app.Matriz2(41)
            Datos_interfaz_uno(app.v1).Vallas_peatonales= app.Matriz2(42)
            Datos_interfaz_uno(app.v1).Acera_lado_del_conductor = app.Matriz2(43)
            Datos_interfaz_uno(app.v1).Acera_lado_del_copiloto = app.Matriz2(44)
            Datos_interfaz_uno(app.v1).Infraestructura_para_motocicletas = app.Matriz2(45)
            Datos_interfaz_uno(app.v1).Infraestructura_para_bicicletas = app.Matriz2(46)
            Datos_interfaz_uno(app.v1).Advertencia_de_zona_escolar = app.Matriz2(47)
            Datos_interfaz_uno(app.v1).Supervisor_en_el_cruce_de_zona_escolar= app.Matriz2(48)
            Datos_interfaz_uno(app.v1).Limite_de_velocidad = app.Matriz2(49)
            Datos_interfaz_uno(app.v1).Diferencial_de_limites_de_velocidad = app.Matriz2(50)
            Datos_interfaz_uno(app.v1).Gestion_de_la_velocidad_calmante_del_trafico = app.Matriz2(51)
            Datos_interfaz_uno(app.v1).Velocidad_de_operacion_percentil_85 = app.Matriz2(52)
            Datos_interfaz_uno(app.v1).Transitabilidad = app.Matriz2(53)
            
            %Datos interfaz dos Matriz 3
            Datos_interfaz_dos(app.v1).Tramos=Tramos;
            Datos_interfaz_dos(app.v1).Distancia=Distancia;
            Datos_interfaz_dos(app.v1).Xt=Xt;
            Datos_interfaz_dos(app.v1).yt=yt;
            Datos_interfaz_dos(app.v1).Ocupante_del_vehiculo = app.VehculoEditField.Value
            Datos_interfaz_dos(app.v1).Motociclista = app.MotociclistaEditField.Value
            Datos_interfaz_dos(app.v1).Ciclista = app.CiclistaEditField.Value
            Datos_interfaz_dos(app.v1).Peaton = app.PeatnEditField.Value
            Datos_interfaz_dos(app.v1).Puntuacion_Ocupante_del_vehiculo = app.ValorEstVeh.Value
            Datos_interfaz_dos(app.v1).Puntuacion_Motociclista = app.ValorEstMoto.Value
            Datos_interfaz_dos(app.v1).Puntuacion_Ciclista = app.ValorEstCicli.Value
            Datos_interfaz_dos(app.v1).Puntuacion_Peaton = app.ValorEstPeaton.Value
            Datos_interfaz_dos(app.v1).Severidad_lateral_distancia_al_objeto_lado_del_conductor = app.Matriz3(1)
            Datos_interfaz_dos(app.v1).Severidad_lateral_objeto_lado_del_conductor= app.Matriz3(2)
            Datos_interfaz_dos(app.v1).Severidad_lateral_distancia_al_objeto_lado_del_copiloto = app.Matriz3(3)
            Datos_interfaz_dos(app.v1).Severidad_lateral_objeto_lado_del_copiloto = app.Matriz3(4)
            Datos_interfaz_dos(app.v1).Bandas_sonoras_en_el_espaldon = app.Matriz3(5)
            Datos_interfaz_dos(app.v1).Ancho_de_espaldon_pavimentado_lado_del_conductor = app.Matriz3(6)
            Datos_interfaz_dos(app.v1).Ancho_de_espaldon_pavimentado_lado_del_copiloto = app.Matriz3(7)
            Datos_interfaz_dos(app.v1).Tipo_de_carretera = app.Matriz3(8)
            Datos_interfaz_dos(app.v1).Costo_de_modernizacion= app.Matriz3(9)
            Datos_interfaz_dos(app.v1).Tipo_de_mediana = app.Matriz3(10)
            Datos_interfaz_dos(app.v1).Bandas_sonoras_centrales = app.Matriz3(11)
            Datos_interfaz_dos(app.v1).Numero_de_carriles = app.Matriz3(12)
            Datos_interfaz_dos(app.v1).Ancho_de_carril = app.Matriz3(13)
            Datos_interfaz_dos(app.v1).Curvatura = app.Matriz3(14)
            Datos_interfaz_dos(app.v1).Calidad_de_la_curva = app.Matriz3(15)
            Datos_interfaz_dos(app.v1).Pendiente= app.Matriz3(16)
            Datos_interfaz_dos(app.v1).Estado_de_la_superficie_de_la_via = app.Matriz3(17)
            Datos_interfaz_dos(app.v1).Resistencia_al_deslizamiento = app.Matriz3(18)
            Datos_interfaz_dos(app.v1).Delineacion = app.Matriz3(19)
            Datos_interfaz_dos(app.v1).Alumbrado_publico = app.Matriz3(20)
            Datos_interfaz_dos(app.v1).Estacionamiento_para_vehiculos = app.Matriz3(21)
            Datos_interfaz_dos(app.v1).Via_de_servicio = app.Matriz3(22)
            Datos_interfaz_dos(app.v1).Obras_en_la_carretera = app.Matriz3(23)
            Datos_interfaz_dos(app.v1).Distancia_de_visibilidad = app.Matriz3(24)
            Datos_interfaz_dos(app.v1).Tipo_de_interseccion = app.Matriz3(25)
            Datos_interfaz_dos(app.v1).Canalizacion_de_la_interseccion= app.Matriz3(26)
            Datos_interfaz_dos(app.v1).Volumen_de_la_interseccion = app.Matriz3(27)
            Datos_interfaz_dos(app.v1).Calidad_de_la_interseccion = app.Matriz3(28)
            Datos_interfaz_dos(app.v1).Puntos_de_acceso_a_la_propiedad = app.Matriz3(29)
            Datos_interfaz_dos(app.v1).Flujo_vehicular = app.Matriz3(30)
            Datos_interfaz_dos(app.v1).Porcentaje_de_motocicletas = app.Matriz3(31)
            Datos_interfaz_dos(app.v1).Flujo_peatonal_en_hora_pico_que_cruza_la_carretera = app.Matriz3(32)
            Datos_interfaz_dos(app.v1).Flujo_peatonal_en_hora_pico_a_lo_largo_de_la_carretera_Cond = app.Matriz3(33)
            Datos_interfaz_dos(app.v1).Flujo_peatonal_en_hora_pico_a_lo_largo_de_la_carretera_Cop = app.Matriz3(34)
            Datos_interfaz_dos(app.v1).Flujo_de_bicicletas_en_hora_pico = app.Matriz3(35)
            Datos_interfaz_dos(app.v1).Uso_de_suelo_lado_del_conductor= app.Matriz3(36)
            Datos_interfaz_dos(app.v1).Uso_de_suelo_lado_del_copiloto = app.Matriz3(37)
            Datos_interfaz_dos(app.v1).Tipo_de_area = app.Matriz3(38)
            Datos_interfaz_dos(app.v1).Infraestructura_de_cruce_peatonal_en_el_tramo_analizado = app.Matriz3(39)
            Datos_interfaz_dos(app.v1).Calidad_de_cruce_peatonal = app.Matriz3(40)
            Datos_interfaz_dos(app.v1).Infraestructura_de_cruce_peatonal_en_la_interseccion = app.Matriz3(41)
            Datos_interfaz_dos(app.v1).Vallas_peatonales= app.Matriz3(42)
            Datos_interfaz_dos(app.v1).Acera_lado_del_conductor = app.Matriz3(43)
            Datos_interfaz_dos(app.v1).Acera_lado_del_copiloto = app.Matriz3(44)
            Datos_interfaz_dos(app.v1).Infraestructura_para_motocicletas = app.Matriz3(45)
            Datos_interfaz_dos(app.v1).Infraestructura_para_bicicletas = app.Matriz3(46)
            Datos_interfaz_dos(app.v1).Advertencia_de_zona_escolar = app.Matriz3(47)
            Datos_interfaz_dos(app.v1).Supervisor_en_el_cruce_de_zona_escolar= app.Matriz3(48)
            Datos_interfaz_dos(app.v1).Limite_de_velocidad = app.Matriz3(49)
            Datos_interfaz_dos(app.v1).Diferencial_de_limites_de_velocidad = app.Matriz3(50)
            Datos_interfaz_dos(app.v1).Gestion_de_la_velocidad_calmante_del_trafico = app.Matriz3(51)
            Datos_interfaz_dos(app.v1).Velocidad_de_operacion_percentil_85 = app.Matriz3(52)
            Datos_interfaz_dos(app.v1).Transitabilidad = app.Matriz3(53)
           
            Datos_interfaz_dos.Ocupante_del_vehiculo
            Datos_interfaz_dos.Motociclista
            Datos_interfaz_dos.Ciclista
            Datos_interfaz_dos.Peaton
            
            %Matriz 8 Nombre atributo una sola fila
            %Datos_interfaz_dos Matriz 3
            Datos_interfaz_tres(1).Tramos=Tramos;
            Datos_interfaz_tres(1).Distancia=Distancia;
            Datos_interfaz_tres(1).Xt=Xt;
            Datos_interfaz_tres(1).yt=yt;
            Datos_interfaz_tres(1).Ocupante_del_vehiculo = app.VehculoEditField.Value
            Datos_interfaz_tres(1).Motociclista = app.MotociclistaEditField.Value
            Datos_interfaz_tres(1).Ciclista = app.CiclistaEditField.Value
            Datos_interfaz_tres(1).Peaton = app.PeatnEditField.Value
            Datos_interfaz_tres(1).Puntuacion_Ocupante_del_vehiculo = app.ValorEstVeh.Value
            Datos_interfaz_tres(1).Puntuacion_Motociclista = app.ValorEstMoto.Value
            Datos_interfaz_tres(1).Puntuacion_Ciclista = app.ValorEstCicli.Value
            Datos_interfaz_tres(1).Puntuacion_Peaton = app.ValorEstPeaton.Value
            Datos_interfaz_tres(1).Severidad_lateral_distancia_al_objeto_lado_del_conductor = app.Matriz3(1)
            Datos_interfaz_tres(1).Severidad_lateral_objeto_lado_del_conductor= app.Matriz3(2)
            Datos_interfaz_tres(1).Severidad_lateral_distancia_al_objeto_lado_del_copiloto = app.Matriz3(3)
            Datos_interfaz_tres(1).Severidad_lateral_objeto_lado_del_copiloto = app.Matriz3(4)
            Datos_interfaz_tres(1).Bandas_sonoras_en_el_espaldon = app.Matriz3(5)
            Datos_interfaz_tres(1).Ancho_de_espaldon_pavimentado_lado_del_conductor = app.Matriz3(6)
            Datos_interfaz_tres(1).Ancho_de_espaldon_pavimentado_lado_del_copiloto = app.Matriz3(7)
            Datos_interfaz_tres(1).Tipo_de_carretera = app.Matriz3(8)
            Datos_interfaz_tres(1).Costo_de_modernizacion= app.Matriz3(9)
            Datos_interfaz_tres(1).Tipo_de_mediana = app.Matriz3(10)
            Datos_interfaz_tres(1).Bandas_sonoras_centrales = app.Matriz3(11)
            Datos_interfaz_tres(1).Numero_de_carriles = app.Matriz3(12)
            Datos_interfaz_tres(1).Ancho_de_carril = app.Matriz3(13)
            Datos_interfaz_tres(1).Curvatura = app.Matriz3(14)
            Datos_interfaz_tres(1).Calidad_de_la_curva = app.Matriz3(15)
            Datos_interfaz_tres(1).Pendiente= app.Matriz3(16)
            Datos_interfaz_tres(1).Estado_de_la_superficie_de_la_via = app.Matriz3(17)
            Datos_interfaz_tres(1).Resistencia_al_deslizamiento = app.Matriz3(18)
            Datos_interfaz_tres(1).Delineacion = app.Matriz3(19)
            Datos_interfaz_tres(1).Alumbrado_publico = app.Matriz3(20)
            Datos_interfaz_tres(1).Estacionamiento_para_vehiculos = app.Matriz3(21)
            Datos_interfaz_tres(1).Via_de_servicio = app.Matriz3(22)
            Datos_interfaz_tres(1).Obras_en_la_carretera = app.Matriz3(23)
            Datos_interfaz_tres(1).Distancia_de_visibilidad = app.Matriz3(24)
            Datos_interfaz_tres(1).Tipo_de_interseccion = app.Matriz3(25)
            Datos_interfaz_tres(1).Canalizacion_de_la_interseccion= app.Matriz3(26)
            Datos_interfaz_tres(1).Volumen_de_la_interseccion = app.Matriz3(27)
            Datos_interfaz_tres(1).Calidad_de_la_interseccion = app.Matriz3(28)
            Datos_interfaz_tres(1).Puntos_de_acceso_a_la_propiedad = app.Matriz3(29)
            Datos_interfaz_tres(1).Flujo_vehicular = app.Matriz3(30)
            Datos_interfaz_tres(1).Porcentaje_de_motocicletas = app.Matriz3(31)
            Datos_interfaz_tres(1).Flujo_peatonal_en_hora_pico_que_cruza_la_carretera = app.Matriz3(32)
            Datos_interfaz_tres(1).Flujo_peatonal_en_hora_pico_a_lo_largo_de_la_carretera_Cond = app.Matriz3(33)
            Datos_interfaz_tres(1).Flujo_peatonal_en_hora_pico_a_lo_largo_de_la_carretera_Cop = app.Matriz3(34)
            Datos_interfaz_tres(1).Flujo_de_bicicletas_en_hora_pico = app.Matriz3(35)
            Datos_interfaz_tres(1).Uso_de_suelo_lado_del_conductor= app.Matriz3(36)
            Datos_interfaz_tres(1).Uso_de_suelo_lado_del_copiloto = app.Matriz3(37)
            Datos_interfaz_tres(1).Tipo_de_area = app.Matriz3(38)
            Datos_interfaz_tres(1).Infraestructura_de_cruce_peatonal_en_el_tramo_analizado = app.Matriz3(39)
            Datos_interfaz_tres(1).Calidad_de_cruce_peatonal = app.Matriz3(40)
            Datos_interfaz_tres(1).Infraestructura_de_cruce_peatonal_en_la_interseccion = app.Matriz3(41)
            Datos_interfaz_tres(1).Vallas_peatonales= app.Matriz3(42)
            Datos_interfaz_tres(1).Acera_lado_del_conductor = app.Matriz3(43)
            Datos_interfaz_tres(1).Acera_lado_del_copiloto = app.Matriz3(44)
            Datos_interfaz_tres(1).Infraestructura_para_motocicletas = app.Matriz3(45)
            Datos_interfaz_tres(1).Infraestructura_para_bicicletas = app.Matriz3(46)
            Datos_interfaz_tres(1).Advertencia_de_zona_escolar = app.Matriz3(47)
            Datos_interfaz_tres(1).Supervisor_en_el_cruce_de_zona_escolar= app.Matriz3(48)
            Datos_interfaz_tres(1).Limite_de_velocidad = app.Matriz3(49)
            Datos_interfaz_tres(1).Diferencial_de_limites_de_velocidad = app.Matriz3(50)
            Datos_interfaz_tres(1).Gestion_de_la_velocidad_calmante_del_trafico = app.Matriz3(51)
            Datos_interfaz_tres(1).Velocidad_de_operacion_percentil_85 = app.Matriz3(52)
            Datos_interfaz_tres(1).Transitabilidad = app.Matriz3(53)
            
            %Matrices de los Coeficientes
            %Matriz 4
            Coeficientes_Veh(1).Salida_lado_conductor=cosalidaconductorveh;
            %Matriz 4.1
            Coeficientes_Veh(1).Salida_lado_copiloto=cosalidacopilotoveh;
            %Matriz 4.2
            Coeficientes_Veh(1).Perdida_de_control=coperdidacontrolveh;
            %Matriz 4.3
            Coeficientes_Veh(1).Adelantamiento=coadelantamientoveh;
            %Matriz 4.4
            Coeficientes_Veh(1).Interseccion=cointerseccionveh;
            %Matriz 4.5
            Coeficientes_Veh(1).Acceso_a_Propiedades=coaccesopropiedadesveh;
            %Matriz 5
            Coeficientes_Mot(1).Salida_lado_conductor=cosalidaconductormot;
            %Matriz 5.1
            Coeficientes_Mot(1).Salida_lado_copiloto=cosalidacopilotomot;
            %Matriz 5.2
            Coeficientes_Mot(1).Perdida_de_control=coperdidacontrolmot;
            %Matriz 5.3
            Coeficientes_Mot(1).Adelantamiento=coadelantamientomot;
            %Matriz 5.4
            Coeficientes_Mot(1).Interseccion=cointerseccionmot;
            %Matriz 5.5
            Coeficientes_Mot(1).Acceso_a_Propiedades=coaccesopropiedadesmot;
            %Matriz 5.6
            Coeficientes_Mot(1).A_lo_largo=colargomot;
            %Matriz 6
            Coeficientes_Ci(1).A_lo_largo=colargoci;
            %Matriz 6.1
            Coeficientes_Ci(1).Salida_camino=cosalidaci;
            %Matriz 6.2
            Coeficientes_Ci(1).Interseccion=cointerseccionci;
            %Matriz 7
            Coeficientes_Pe(1).A_lo_largo_conductor=colargoconpe;
            %Matriz 7.1
            Coeficientes_Pe(1).A_lo_largo_copiloto=colargocoppe;
            %Matriz 7.2
            Coeficientes_Pe(1).Cruce_peatonal_inspeccionada=coinsppe;
            %Matriz 7.3
            Coeficientes_Pe(1).Cruce_peatonal_lateral=colatpe;
            
            %Se guardan las mapp.v1atrices
            save('../mat/Datos_atributos_proceso',"Atributo_elegido")
            save('../mat/Datos_interfaz_uno_proceso',"Datos_interfaz_uno")
            save('../mat/Datos_interfaz_dos_proceso',"Datos_interfaz_dos")
            save('../mat/Coeficientes_Veh',"Coeficientes_Veh")
            save('../mat/Coeficientes_Mot',"Coeficientes_Mot")
            save('../mat/Coeficientes_Ci',"Coeficientes_Ci")
            save('../mat/Coeficientes_Pe',"Coeficientes_Pe")
            save('../mat/Datos_interfaz_tres_proceso',"Datos_interfaz_tres")
            
            %Estrellas vehiculo
            if app.VehculoEditField.Value==5
            imagen2=imread('E55.jpg')
            imshow(imagen2,'Parent',app.UIAxes2)
            else if app.VehculoEditField.Value==4
            imagen2=imread('E44.jpg')
            imshow(imagen2,'Parent',app.UIAxes2)
            else if app.VehculoEditField.Value==3
            imagen2=imread('E33.jpg')
            imshow(imagen2,'Parent',app.UIAxes2)
            else if app.VehculoEditField.Value==2
            imagen2=imread('E22.jpg')
            imshow(imagen2,'Parent',app.UIAxes2)
            else if app.VehculoEditField.Value==1
            imagen2=imread('E11.jpg')
            imshow(imagen2,'Parent',app.UIAxes2)
            else if app.VehculoEditField.Value==0
            imagen2=imread('E0.jpg')
            imshow(imagen2,'Parent',app.UIAxes2)
            end
            end
            end
            end
            end
            end
            %Estrellas Motociclista
            if app.MotociclistaEditField.Value==5
            imagen3=imread('E55.jpg')
            imshow(imagen3,'Parent',app.UIAxes4)
            else if app.MotociclistaEditField.Value==4
            imagen3=imread('E44.jpg')
            imshow(imagen3,'Parent',app.UIAxes4)
            else if app.MotociclistaEditField.Value==3
            imagen3=imread('E33.jpg')
            imshow(imagen3,'Parent',app.UIAxes4)
            else if app.MotociclistaEditField.Value==2
            imagen3=imread('E22.jpg')
            imshow(imagen3,'Parent',app.UIAxes4)
            else if app.MotociclistaEditField.Value==1
            imagen3=imread('E11.jpg')
            imshow(imagen3,'Parent',app.UIAxes4)
            else if app.MotociclistaEditField.Value==0
            imagen3=imread('E0.jpg')
            imshow(imagen3,'Parent',app.UIAxes4)
            end
            end
            end
            end
            end
            end
            %Estrellas Ciclista
            if app.CiclistaEditField.Value==5
            imagen4=imread('E55.jpg')
            imshow(imagen4,'Parent',app.UIAxes3)
            else if app.CiclistaEditField.Value==4
            imagen4=imread('E44.jpg')
            imshow(imagen4,'Parent',app.UIAxes3)
            else if app.CiclistaEditField.Value==3
            imagen4=imread('E33.jpg')
            imshow(imagen4,'Parent',app.UIAxes3)
            else if app.CiclistaEditField.Value==2
            imagen4=imread('E22.jpg')
            imshow(imagen4,'Parent',app.UIAxes3)
            else if app.CiclistaEditField.Value==1
            imagen4=imread('E11.jpg')
            imshow(imagen4,'Parent',app.UIAxes3)
            else if app.CiclistaEditField.Value==0
            imagen4=imread('E0.jpg')
            imshow(imagen4,'Parent',app.UIAxes3)
            end
            end
            end
            end
            end
            end
            %Estrellas Peaton
            if app.PeatnEditField.Value==5
            imagen5=imread('E55.jpg')
            imshow(imagen5,'Parent',app.UIAxes5)
            else if app.PeatnEditField.Value==4
            imagen5=imread('E44.jpg')
            imshow(imagen5,'Parent',app.UIAxes5)
            else if app.PeatnEditField.Value==3
            imagen5=imread('E33.jpg')
            imshow(imagen5,'Parent',app.UIAxes5)
            else if app.PeatnEditField.Value==2
            imagen5=imread('E22.jpg')
            imshow(imagen5,'Parent',app.UIAxes5)
            else if app.PeatnEditField.Value==1
            imagen5=imread('E11.jpg')
            imshow(imagen5,'Parent',app.UIAxes5)
            else if app.PeatnEditField.Value==0
            imagen5=imread('E0.jpg')
            imshow(imagen4,'Parent',app.UIAxes5)
            end
            end
            end
            end
            end
            end
            end
           end
           
           load('../mat/Datos_atributos_proceso')
           load('../mat/Datos_interfaz_uno_proceso')
           load('../mat/Datos_interfaz_dos_proceso')
           load('../mat/Coeficientes_Veh')
           load('../mat/Coeficientes_Mot')
           load('../mat/Coeficientes_Ci')
           load('../mat/Coeficientes_Pe')
           load('../mat/Datos_interfaz_tres_proceso')
           
           %CALCULO 
                %Se carga la funcion para extraer y mostrar los datos
            if strcmp(selectedTab.Title,'Resultado')
           [cosalidaconductorveh,cosalidacopilotoveh,coperdidacontrolveh,coadelantamientoveh,cointerseccionveh,coaccesopropiedadesveh,cosalidaconductormot,cosalidacopilotomot,coperdidacontrolmot,coadelantamientomot,cointerseccionmot,coaccesopropiedadesmot,colargomot,colargoci,cosalidaci,cointerseccionci,colargoconpe,colargocoppe,coinsppe,colatpe,EstVehiculo,EstMotocicleta,EstCiclista,EstPeaton,EstVehicul,EstMoto,EstCicl,EstPeato]=Calculo_estrellas_VehIntentoFinal(app.Matriz1);

           app.VehculoEditField.Value=EstVehiculo;
           app.MotociclistaEditField.Value=EstMotocicleta;
           app.CiclistaEditField.Value=EstCiclista;
           app.PeatnEditField.Value=EstPeaton;
           app.ValorEstVeh.Value=EstVehicul;
           app.ValorEstPeaton.Value=EstPeato;
           app.ValorEstCicli.Value=EstCicl;
           app.ValorEstMoto.Value=EstMoto;

           
           %Se carga la matriz proveniente del pre-proceso

            load('../mat/preproceso_tramo')
            
            %Para graficar 
            Tramos=[tramo(app.v1).Tramos];
            Distancia=[tramo(app.v1).Distancia];
            Xt=([tramo(app.v1).Xt]);
            yt=([tramo(app.v1).yt]);
            
            %Atributos Matriz 1
            Atributo_elegido(app.v1).Atributo=app.Matriz1
           
            %Datos interfaz uno Matriz 2
            Datos_interfaz_uno(app.v1).Tramos=Tramos;
            Datos_interfaz_uno(app.v1).Distancia=Distancia;
            Datos_interfaz_uno(app.v1).Xt=Xt;
            Datos_interfaz_uno(app.v1).yt=yt;
            Datos_interfaz_uno(app.v1).Ocupante_del_vehiculo = app.VehculoEditField.Value
            Datos_interfaz_uno(app.v1).Motociclista = app.MotociclistaEditField.Value
            Datos_interfaz_uno(app.v1).Ciclista = app.CiclistaEditField.Value
            Datos_interfaz_uno(app.v1).Peaton = app.PeatnEditField.Value
            Datos_interfaz_uno(app.v1).Puntuacion_Ocupante_del_vehiculo = app.ValorEstVeh.Value
            Datos_interfaz_uno(app.v1).Puntuacion_Motociclista = app.ValorEstMoto.Value
            Datos_interfaz_uno(app.v1).Puntuacion_Ciclista = app.ValorEstCicli.Value
            Datos_interfaz_uno(app.v1).Puntuacion_Peaton = app.ValorEstPeaton.Value
            Datos_interfaz_uno(app.v1).Severidad_lateral_distancia_al_objeto_lado_del_conductor = app.Matriz2(1)
            Datos_interfaz_uno(app.v1).Severidad_lateral_objeto_lado_del_conductor= app.Matriz2(2)
            Datos_interfaz_uno(app.v1).Severidad_lateral_distancia_al_objeto_lado_del_copiloto = app.Matriz2(3)
            Datos_interfaz_uno(app.v1).Severidad_lateral_objeto_lado_del_copiloto = app.Matriz2(4)
            Datos_interfaz_uno(app.v1).Bandas_sonoras_en_el_espaldon = app.Matriz2(5)
            Datos_interfaz_uno(app.v1).Ancho_de_espaldon_pavimentado_lado_del_conductor = app.Matriz2(6)
            Datos_interfaz_uno(app.v1).Ancho_de_espaldon_pavimentado_lado_del_copiloto = app.Matriz2(7)
            Datos_interfaz_uno(app.v1).Tipo_de_carretera = app.Matriz2(8)
            Datos_interfaz_uno(app.v1).Costo_de_modernizacion= app.Matriz2(9)
            Datos_interfaz_uno(app.v1).Tipo_de_mediana = app.Matriz2(10)
            Datos_interfaz_uno(app.v1).Bandas_sonoras_centrales = app.Matriz2(11)
            Datos_interfaz_uno(app.v1).Numero_de_carriles = app.Matriz2(12)
            Datos_interfaz_uno(app.v1).Ancho_de_carril = app.Matriz2(13)
            Datos_interfaz_uno(app.v1).Curvatura = app.Matriz2(14)
            Datos_interfaz_uno(app.v1).Calidad_de_la_curva = app.Matriz2(15)
            Datos_interfaz_uno(app.v1).Pendiente= app.Matriz2(16)
            Datos_interfaz_uno(app.v1).Estado_de_la_superficie_de_la_via = app.Matriz2(17)
            Datos_interfaz_uno(app.v1).Resistencia_al_deslizamiento = app.Matriz2(18)
            Datos_interfaz_uno(app.v1).Delineacion = app.Matriz2(19)
            Datos_interfaz_uno(app.v1).Alumbrado_publico = app.Matriz2(20)
            Datos_interfaz_uno(app.v1).Estacionamiento_para_vehiculos = app.Matriz2(21)
            Datos_interfaz_uno(app.v1).Via_de_servicio = app.Matriz2(22)
            Datos_interfaz_uno(app.v1).Obras_en_la_carretera = app.Matriz2(23)
            Datos_interfaz_uno(app.v1).Distancia_de_visibilidad = app.Matriz2(24)
            Datos_interfaz_uno(app.v1).Tipo_de_interseccion = app.Matriz2(25)
            Datos_interfaz_uno(app.v1).Canalizacion_de_la_interseccion= app.Matriz2(26)
            Datos_interfaz_uno(app.v1).Volumen_de_la_interseccion = app.Matriz2(27)
            Datos_interfaz_uno(app.v1).Calidad_de_la_interseccion = app.Matriz2(28)
            Datos_interfaz_uno(app.v1).Puntos_de_acceso_a_la_propiedad = app.Matriz2(29)
            Datos_interfaz_uno(app.v1).Flujo_vehicular = app.Matriz2(30)
            Datos_interfaz_uno(app.v1).Porcentaje_de_motocicletas = app.Matriz2(31)
            Datos_interfaz_uno(app.v1).Flujo_peatonal_en_hora_pico_que_cruza_la_carretera = app.Matriz2(32)
            Datos_interfaz_uno(app.v1).Flujo_peatonal_en_hora_pico_a_lo_largo_de_la_carretera_Cond = app.Matriz2(33)
            Datos_interfaz_uno(app.v1).Flujo_peatonal_en_hora_pico_a_lo_largo_de_la_carretera_Cop = app.Matriz2(34)
            Datos_interfaz_uno(app.v1).Flujo_de_bicicletas_en_hora_pico = app.Matriz2(35)
            Datos_interfaz_uno(app.v1).Uso_de_suelo_lado_del_conductor= app.Matriz2(36)
            Datos_interfaz_uno(app.v1).Uso_de_suelo_lado_del_copiloto = app.Matriz2(37)
            Datos_interfaz_uno(app.v1).Tipo_de_area = app.Matriz2(38)
            Datos_interfaz_uno(app.v1).Infraestructura_de_cruce_peatonal_en_el_tramo_analizado = app.Matriz2(39)
            Datos_interfaz_uno(app.v1).Calidad_de_cruce_peatonal = app.Matriz2(40)
            Datos_interfaz_uno(app.v1).Infraestructura_de_cruce_peatonal_en_la_interseccion = app.Matriz2(41)
            Datos_interfaz_uno(app.v1).Vallas_peatonales= app.Matriz2(42)
            Datos_interfaz_uno(app.v1).Acera_lado_del_conductor = app.Matriz2(43)
            Datos_interfaz_uno(app.v1).Acera_lado_del_copiloto = app.Matriz2(44)
            Datos_interfaz_uno(app.v1).Infraestructura_para_motocicletas = app.Matriz2(45)
            Datos_interfaz_uno(app.v1).Infraestructura_para_bicicletas = app.Matriz2(46)
            Datos_interfaz_uno(app.v1).Advertencia_de_zona_escolar = app.Matriz2(47)
            Datos_interfaz_uno(app.v1).Supervisor_en_el_cruce_de_zona_escolar= app.Matriz2(48)
            Datos_interfaz_uno(app.v1).Limite_de_velocidad = app.Matriz2(49)
            Datos_interfaz_uno(app.v1).Diferencial_de_limites_de_velocidad = app.Matriz2(50)
            Datos_interfaz_uno(app.v1).Gestion_de_la_velocidad_calmante_del_trafico = app.Matriz2(51)
            Datos_interfaz_uno(app.v1).Velocidad_de_operacion_percentil_85 = app.Matriz2(52)
            Datos_interfaz_uno(app.v1).Transitabilidad = app.Matriz2(53)
            
            %Datos interfaz dos Matriz 3
            Datos_interfaz_dos(app.v1).Tramos=Tramos;
            Datos_interfaz_dos(app.v1).Distancia=Distancia;
            Datos_interfaz_dos(app.v1).Xt=Xt;
            Datos_interfaz_dos(app.v1).yt=yt;
            Datos_interfaz_dos(app.v1).Ocupante_del_vehiculo = app.VehculoEditField.Value
            Datos_interfaz_dos(app.v1).Motociclista = app.MotociclistaEditField.Value
            Datos_interfaz_dos(app.v1).Ciclista = app.CiclistaEditField.Value
            Datos_interfaz_dos(app.v1).Peaton = app.PeatnEditField.Value
            Datos_interfaz_dos(app.v1).Puntuacion_Ocupante_del_vehiculo = app.ValorEstVeh.Value
            Datos_interfaz_dos(app.v1).Puntuacion_Motociclista = app.ValorEstMoto.Value
            Datos_interfaz_dos(app.v1).Puntuacion_Ciclista = app.ValorEstCicli.Value
            Datos_interfaz_dos(app.v1).Puntuacion_Peaton = app.ValorEstPeaton.Value
            Datos_interfaz_dos(app.v1).Severidad_lateral_distancia_al_objeto_lado_del_conductor = app.Matriz3(1)
            Datos_interfaz_dos(app.v1).Severidad_lateral_objeto_lado_del_conductor= app.Matriz3(2)
            Datos_interfaz_dos(app.v1).Severidad_lateral_distancia_al_objeto_lado_del_copiloto = app.Matriz3(3)
            Datos_interfaz_dos(app.v1).Severidad_lateral_objeto_lado_del_copiloto = app.Matriz3(4)
            Datos_interfaz_dos(app.v1).Bandas_sonoras_en_el_espaldon = app.Matriz3(5)
            Datos_interfaz_dos(app.v1).Ancho_de_espaldon_pavimentado_lado_del_conductor = app.Matriz3(6)
            Datos_interfaz_dos(app.v1).Ancho_de_espaldon_pavimentado_lado_del_copiloto = app.Matriz3(7)
            Datos_interfaz_dos(app.v1).Tipo_de_carretera = app.Matriz3(8)
            Datos_interfaz_dos(app.v1).Costo_de_modernizacion= app.Matriz3(9)
            Datos_interfaz_dos(app.v1).Tipo_de_mediana = app.Matriz3(10)
            Datos_interfaz_dos(app.v1).Bandas_sonoras_centrales = app.Matriz3(11)
            Datos_interfaz_dos(app.v1).Numero_de_carriles = app.Matriz3(12)
            Datos_interfaz_dos(app.v1).Ancho_de_carril = app.Matriz3(13)
            Datos_interfaz_dos(app.v1).Curvatura = app.Matriz3(14)
            Datos_interfaz_dos(app.v1).Calidad_de_la_curva = app.Matriz3(15)
            Datos_interfaz_dos(app.v1).Pendiente= app.Matriz3(16)
            Datos_interfaz_dos(app.v1).Estado_de_la_superficie_de_la_via = app.Matriz3(17)
            Datos_interfaz_dos(app.v1).Resistencia_al_deslizamiento = app.Matriz3(18)
            Datos_interfaz_dos(app.v1).Delineacion = app.Matriz3(19)
            Datos_interfaz_dos(app.v1).Alumbrado_publico = app.Matriz3(20)
            Datos_interfaz_dos(app.v1).Estacionamiento_para_vehiculos = app.Matriz3(21)
            Datos_interfaz_dos(app.v1).Via_de_servicio = app.Matriz3(22)
            Datos_interfaz_dos(app.v1).Obras_en_la_carretera = app.Matriz3(23)
            Datos_interfaz_dos(app.v1).Distancia_de_visibilidad = app.Matriz3(24)
            Datos_interfaz_dos(app.v1).Tipo_de_interseccion = app.Matriz3(25)
            Datos_interfaz_dos(app.v1).Canalizacion_de_la_interseccion= app.Matriz3(26)
            Datos_interfaz_dos(app.v1).Volumen_de_la_interseccion = app.Matriz3(27)
            Datos_interfaz_dos(app.v1).Calidad_de_la_interseccion = app.Matriz3(28)
            Datos_interfaz_dos(app.v1).Puntos_de_acceso_a_la_propiedad = app.Matriz3(29)
            Datos_interfaz_dos(app.v1).Flujo_vehicular = app.Matriz3(30)
            Datos_interfaz_dos(app.v1).Porcentaje_de_motocicletas = app.Matriz3(31)
            Datos_interfaz_dos(app.v1).Flujo_peatonal_en_hora_pico_que_cruza_la_carretera = app.Matriz3(32)
            Datos_interfaz_dos(app.v1).Flujo_peatonal_en_hora_pico_a_lo_largo_de_la_carretera_Cond = app.Matriz3(33)
            Datos_interfaz_dos(app.v1).Flujo_peatonal_en_hora_pico_a_lo_largo_de_la_carretera_Cop = app.Matriz3(34)
            Datos_interfaz_dos(app.v1).Flujo_de_bicicletas_en_hora_pico = app.Matriz3(35)
            Datos_interfaz_dos(app.v1).Uso_de_suelo_lado_del_conductor= app.Matriz3(36)
            Datos_interfaz_dos(app.v1).Uso_de_suelo_lado_del_copiloto = app.Matriz3(37)
            Datos_interfaz_dos(app.v1).Tipo_de_area = app.Matriz3(38)
            Datos_interfaz_dos(app.v1).Infraestructura_de_cruce_peatonal_en_el_tramo_analizado = app.Matriz3(39)
            Datos_interfaz_dos(app.v1).Calidad_de_cruce_peatonal = app.Matriz3(40)
            Datos_interfaz_dos(app.v1).Infraestructura_de_cruce_peatonal_en_la_interseccion = app.Matriz3(41)
            Datos_interfaz_dos(app.v1).Vallas_peatonales= app.Matriz3(42)
            Datos_interfaz_dos(app.v1).Acera_lado_del_conductor = app.Matriz3(43)
            Datos_interfaz_dos(app.v1).Acera_lado_del_copiloto = app.Matriz3(44)
            Datos_interfaz_dos(app.v1).Infraestructura_para_motocicletas = app.Matriz3(45)
            Datos_interfaz_dos(app.v1).Infraestructura_para_bicicletas = app.Matriz3(46)
            Datos_interfaz_dos(app.v1).Advertencia_de_zona_escolar = app.Matriz3(47)
            Datos_interfaz_dos(app.v1).Supervisor_en_el_cruce_de_zona_escolar= app.Matriz3(48)
            Datos_interfaz_dos(app.v1).Limite_de_velocidad = app.Matriz3(49)
            Datos_interfaz_dos(app.v1).Diferencial_de_limites_de_velocidad = app.Matriz3(50)
            Datos_interfaz_dos(app.v1).Gestion_de_la_velocidad_calmante_del_trafico = app.Matriz3(51)
            Datos_interfaz_dos(app.v1).Velocidad_de_operacion_percentil_85 = app.Matriz3(52)
            Datos_interfaz_dos(app.v1).Transitabilidad = app.Matriz3(53)
           
            Datos_interfaz_dos.Ocupante_del_vehiculo
            Datos_interfaz_dos.Motociclista
            Datos_interfaz_dos.Ciclista
            Datos_interfaz_dos.Peaton
            
            %Matriz 8 Nombre atributo una sola fila
            %Datos_interfaz_dos Matriz 3
            Datos_interfaz_tres(1).Tramos=Tramos;
            Datos_interfaz_tres(1).Distancia=Distancia;
            Datos_interfaz_tres(1).Xt=Xt;
            Datos_interfaz_tres(1).yt=yt;
            Datos_interfaz_tres(1).Ocupante_del_vehiculo = app.VehculoEditField.Value
            Datos_interfaz_tres(1).Motociclista = app.MotociclistaEditField.Value
            Datos_interfaz_tres(1).Ciclista = app.CiclistaEditField.Value
            Datos_interfaz_tres(1).Peaton = app.PeatnEditField.Value
            Datos_interfaz_tres(1).Puntuacion_Ocupante_del_vehiculo = app.ValorEstVeh.Value
            Datos_interfaz_tres(1).Puntuacion_Motociclista = app.ValorEstMoto.Value
            Datos_interfaz_tres(1).Puntuacion_Ciclista = app.ValorEstCicli.Value
            Datos_interfaz_tres(1).Puntuacion_Peaton = app.ValorEstPeaton.Value
            Datos_interfaz_tres(1).Severidad_lateral_distancia_al_objeto_lado_del_conductor = app.Matriz3(1)
            Datos_interfaz_tres(1).Severidad_lateral_objeto_lado_del_conductor= app.Matriz3(2)
            Datos_interfaz_tres(1).Severidad_lateral_distancia_al_objeto_lado_del_copiloto = app.Matriz3(3)
            Datos_interfaz_tres(1).Severidad_lateral_objeto_lado_del_copiloto = app.Matriz3(4)
            Datos_interfaz_tres(1).Bandas_sonoras_en_el_espaldon = app.Matriz3(5)
            Datos_interfaz_tres(1).Ancho_de_espaldon_pavimentado_lado_del_conductor = app.Matriz3(6)
            Datos_interfaz_tres(1).Ancho_de_espaldon_pavimentado_lado_del_copiloto = app.Matriz3(7)
            Datos_interfaz_tres(1).Tipo_de_carretera = app.Matriz3(8)
            Datos_interfaz_tres(1).Costo_de_modernizacion= app.Matriz3(9)
            Datos_interfaz_tres(1).Tipo_de_mediana = app.Matriz3(10)
            Datos_interfaz_tres(1).Bandas_sonoras_centrales = app.Matriz3(11)
            Datos_interfaz_tres(1).Numero_de_carriles = app.Matriz3(12)
            Datos_interfaz_tres(1).Ancho_de_carril = app.Matriz3(13)
            Datos_interfaz_tres(1).Curvatura = app.Matriz3(14)
            Datos_interfaz_tres(1).Calidad_de_la_curva = app.Matriz3(15)
            Datos_interfaz_tres(1).Pendiente= app.Matriz3(16)
            Datos_interfaz_tres(1).Estado_de_la_superficie_de_la_via = app.Matriz3(17)
            Datos_interfaz_tres(1).Resistencia_al_deslizamiento = app.Matriz3(18)
            Datos_interfaz_tres(1).Delineacion = app.Matriz3(19)
            Datos_interfaz_tres(1).Alumbrado_publico = app.Matriz3(20)
            Datos_interfaz_tres(1).Estacionamiento_para_vehiculos = app.Matriz3(21)
            Datos_interfaz_tres(1).Via_de_servicio = app.Matriz3(22)
            Datos_interfaz_tres(1).Obras_en_la_carretera = app.Matriz3(23)
            Datos_interfaz_tres(1).Distancia_de_visibilidad = app.Matriz3(24)
            Datos_interfaz_tres(1).Tipo_de_interseccion = app.Matriz3(25)
            Datos_interfaz_tres(1).Canalizacion_de_la_interseccion= app.Matriz3(26)
            Datos_interfaz_tres(1).Volumen_de_la_interseccion = app.Matriz3(27)
            Datos_interfaz_tres(1).Calidad_de_la_interseccion = app.Matriz3(28)
            Datos_interfaz_tres(1).Puntos_de_acceso_a_la_propiedad = app.Matriz3(29)
            Datos_interfaz_tres(1).Flujo_vehicular = app.Matriz3(30)
            Datos_interfaz_tres(1).Porcentaje_de_motocicletas = app.Matriz3(31)
            Datos_interfaz_tres(1).Flujo_peatonal_en_hora_pico_que_cruza_la_carretera = app.Matriz3(32)
            Datos_interfaz_tres(1).Flujo_peatonal_en_hora_pico_a_lo_largo_de_la_carretera_Cond = app.Matriz3(33)
            Datos_interfaz_tres(1).Flujo_peatonal_en_hora_pico_a_lo_largo_de_la_carretera_Cop = app.Matriz3(34)
            Datos_interfaz_tres(1).Flujo_de_bicicletas_en_hora_pico = app.Matriz3(35)
            Datos_interfaz_tres(1).Uso_de_suelo_lado_del_conductor= app.Matriz3(36)
            Datos_interfaz_tres(1).Uso_de_suelo_lado_del_copiloto = app.Matriz3(37)
            Datos_interfaz_tres(1).Tipo_de_area = app.Matriz3(38)
            Datos_interfaz_tres(1).Infraestructura_de_cruce_peatonal_en_el_tramo_analizado = app.Matriz3(39)
            Datos_interfaz_tres(1).Calidad_de_cruce_peatonal = app.Matriz3(40)
            Datos_interfaz_tres(1).Infraestructura_de_cruce_peatonal_en_la_interseccion = app.Matriz3(41)
            Datos_interfaz_tres(1).Vallas_peatonales= app.Matriz3(42)
            Datos_interfaz_tres(1).Acera_lado_del_conductor = app.Matriz3(43)
            Datos_interfaz_tres(1).Acera_lado_del_copiloto = app.Matriz3(44)
            Datos_interfaz_tres(1).Infraestructura_para_motocicletas = app.Matriz3(45)
            Datos_interfaz_tres(1).Infraestructura_para_bicicletas = app.Matriz3(46)
            Datos_interfaz_tres(1).Advertencia_de_zona_escolar = app.Matriz3(47)
            Datos_interfaz_tres(1).Supervisor_en_el_cruce_de_zona_escolar= app.Matriz3(48)
            Datos_interfaz_tres(1).Limite_de_velocidad = app.Matriz3(49)
            Datos_interfaz_tres(1).Diferencial_de_limites_de_velocidad = app.Matriz3(50)
            Datos_interfaz_tres(1).Gestion_de_la_velocidad_calmante_del_trafico = app.Matriz3(51)
            Datos_interfaz_tres(1).Velocidad_de_operacion_percentil_85 = app.Matriz3(52)
            Datos_interfaz_tres(1).Transitabilidad = app.Matriz3(53)
            
            %Matrices de los Coeficientes
            %Matriz 4
            Coeficientes_Veh(1).Salida_lado_conductor=cosalidaconductorveh;
            %Matriz 4.1
            Coeficientes_Veh(1).Salida_lado_copiloto=cosalidacopilotoveh;
            %Matriz 4.2
            Coeficientes_Veh(1).Perdida_de_control=coperdidacontrolveh;
            %Matriz 4.3
            Coeficientes_Veh(1).Adelantamiento=coadelantamientoveh;
            %Matriz 4.4
            Coeficientes_Veh(1).Interseccion=cointerseccionveh;
            %Matriz 4.5
            Coeficientes_Veh(1).Acceso_a_Propiedades=coaccesopropiedadesveh;
            %Matriz 5
            Coeficientes_Mot(1).Salida_lado_conductor=cosalidaconductormot;
            %Matriz 5.1
            Coeficientes_Mot(1).Salida_lado_copiloto=cosalidacopilotomot;
            %Matriz 5.2
            Coeficientes_Mot(1).Perdida_de_control=coperdidacontrolmot;
            %Matriz 5.3
            Coeficientes_Mot(1).Adelantamiento=coadelantamientomot;
            %Matriz 5.4
            Coeficientes_Mot(1).Interseccion=cointerseccionmot;
            %Matriz 5.5
            Coeficientes_Mot(1).Acceso_a_Propiedades=coaccesopropiedadesmot;
            %Matriz 5.6
            Coeficientes_Mot(1).A_lo_largo=colargomot;
            %Matriz 6
            Coeficientes_Ci(1).A_lo_largo=colargoci;
            %Matriz 6.1
            Coeficientes_Ci(1).Salida_camino=cosalidaci;
            %Matriz 6.2
            Coeficientes_Ci(1).Interseccion=cointerseccionci;
            %Matriz 7
            Coeficientes_Pe(1).A_lo_largo_conductor=colargoconpe;
            %Matriz 7.1
            Coeficientes_Pe(1).A_lo_largo_copiloto=colargocoppe;
            %Matriz 7.2
            Coeficientes_Pe(1).Cruce_peatonal_inspeccionada=coinsppe;
            %Matriz 7.3
            Coeficientes_Pe(1).Cruce_peatonal_lateral=colatpe;
            
            %Se guardan las mapp.v1atrices
            save('../mat/Datos_atributos_proceso',"Atributo_elegido")
            save('../mat/Datos_interfaz_uno_proceso',"Datos_interfaz_uno")
            save('../mat/Datos_interfaz_dos_proceso',"Datos_interfaz_dos")
            save('../mat/Coeficientes_Veh',"Coeficientes_Veh")
            save('../mat/Coeficientes_Mot',"Coeficientes_Mot")
            save('../mat/Coeficientes_Ci',"Coeficientes_Ci")
            save('../mat/Coeficientes_Pe',"Coeficientes_Pe")
            save('../mat/Datos_interfaz_tres_proceso',"Datos_interfaz_tres")
            
            %Estrellas vehiculo
            if app.VehculoEditField.Value==5
            imagen2=imread('E55.jpg')
            imshow(imagen2,'Parent',app.UIAxes2)
            else if app.VehculoEditField.Value==4
            imagen2=imread('E44.jpg')
            imshow(imagen2,'Parent',app.UIAxes2)
            else if app.VehculoEditField.Value==3
            imagen2=imread('E33.jpg')
            imshow(imagen2,'Parent',app.UIAxes2)
            else if app.VehculoEditField.Value==2
            imagen2=imread('E22.jpg')
            imshow(imagen2,'Parent',app.UIAxes2)
            else if app.VehculoEditField.Value==1
            imagen2=imread('E11.jpg')
            imshow(imagen2,'Parent',app.UIAxes2)
            else if app.VehculoEditField.Value==0
            imagen2=imread('E0.jpg')
            imshow(imagen2,'Parent',app.UIAxes2)
            end
            end
            end
            end
            end
            end
            %Estrellas Motociclista
            if app.MotociclistaEditField.Value==5
            imagen3=imread('E55.jpg')
            imshow(imagen3,'Parent',app.UIAxes4)
            else if app.MotociclistaEditField.Value==4
            imagen3=imread('E44.jpg')
            imshow(imagen3,'Parent',app.UIAxes4)
            else if app.MotociclistaEditField.Value==3
            imagen3=imread('E33.jpg')
            imshow(imagen3,'Parent',app.UIAxes4)
            else if app.MotociclistaEditField.Value==2
            imagen3=imread('E22.jpg')
            imshow(imagen3,'Parent',app.UIAxes4)
            else if app.MotociclistaEditField.Value==1
            imagen3=imread('E11.jpg')
            imshow(imagen3,'Parent',app.UIAxes4)
            else if app.MotociclistaEditField.Value==0
            imagen3=imread('E0.jpg')
            imshow(imagen3,'Parent',app.UIAxes4)
            end
            end
            end
            end
            end
            end
            %Estrellas Ciclista
            if app.CiclistaEditField.Value==5
            imagen4=imread('E55.jpg')
            imshow(imagen4,'Parent',app.UIAxes3)
            else if app.CiclistaEditField.Value==4
            imagen4=imread('E44.jpg')
            imshow(imagen4,'Parent',app.UIAxes3)
            else if app.CiclistaEditField.Value==3
            imagen4=imread('E33.jpg')
            imshow(imagen4,'Parent',app.UIAxes3)
            else if app.CiclistaEditField.Value==2
            imagen4=imread('E22.jpg')
            imshow(imagen4,'Parent',app.UIAxes3)
            else if app.CiclistaEditField.Value==1
            imagen4=imread('E11.jpg')
            imshow(imagen4,'Parent',app.UIAxes3)
            else if app.CiclistaEditField.Value==0
            imagen4=imread('E0.jpg')
            imshow(imagen4,'Parent',app.UIAxes3)
            end
            end
            end
            end
            end
            end
            %Estrellas Peaton
            if app.PeatnEditField.Value==5
            imagen5=imread('E55.jpg')
            imshow(imagen5,'Parent',app.UIAxes5)
            else if app.PeatnEditField.Value==4
            imagen5=imread('E44.jpg')
            imshow(imagen5,'Parent',app.UIAxes5)
            else if app.PeatnEditField.Value==3
            imagen5=imread('E33.jpg')
            imshow(imagen5,'Parent',app.UIAxes5)
            else if app.PeatnEditField.Value==2
            imagen5=imread('E22.jpg')
            imshow(imagen5,'Parent',app.UIAxes5)
            else if app.PeatnEditField.Value==1
            imagen5=imread('E11.jpg')
            imshow(imagen5,'Parent',app.UIAxes5)
            else if app.PeatnEditField.Value==0
            imagen5=imread('E0.jpg')
            imshow(imagen4,'Parent',app.UIAxes5)
            end
            end
            end
            end
            end
            end
            end


        end

        % Size changed function: RECORRIDODELTRAMOANALIZADOPanel
        function RECORRIDODELTRAMOANALIZADOPanelSizeChanged(app, event)
            position = app.RECORRIDODELTRAMOANALIZADOPanel.Position;
            
        end

        % Button pushed function: ReporteFinalButton
        function ReporteFinalButtonPushed(app, event)
            app_Reporte
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.IMAGENDELTRAMOANALIZADOPanel = uipanel(app.UIFigure);
            app.IMAGENDELTRAMOANALIZADOPanel.Title = 'IMAGEN DEL TRAMO ANALIZADO';
            app.Image = uiimage(app.IMAGENDELTRAMOANALIZADOPanel);
            app.RECORRIDODELTRAMOANALIZADOPanel = uipanel(app.UIFigure);
            app.RECORRIDODELTRAMOANALIZADOPanel.Title = 'RECORRIDO DEL TRAMO ANALIZADO';
            app.RECORRIDODELTRAMOANALIZADOPanel.SizeChangedFcn = createCallbackFcn(app, @RECORRIDODELTRAMOANALIZADOPanelSizeChanged, true);
            app.TramodeanlisisSpinnerLabel = uilabel(app.RECORRIDODELTRAMOANALIZADOPanel);
            app.TramodeanlisisSpinnerLabel.Text = 'Tramo de análisis';
            app.TramodeanlisisSpinner = uispinner(app.RECORRIDODELTRAMOANALIZADOPanel);
            app.TramodeanlisisSpinner.ValueChangedFcn = createCallbackFcn(app, @TramodeanlisisSpinnerValueChanged, true);
            app.UIAxes = uiaxes(app.RECORRIDODELTRAMOANALIZADOPanel);
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.CLASIFICACINPORESTRELLASPanel = uipanel(app.UIFigure);
            app.CLASIFICACINPORESTRELLASPanel.Title = 'CLASIFICACIÓN POR ESTRELLAS';
            app.Label = uilabel(app.CLASIFICACINPORESTRELLASPanel);
            app.Label.Text = '';
            app.TabGroup = uitabgroup(app.CLASIFICACINPORESTRELLASPanel);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.CostadodelavaTab = uitab(app.TabGroup);
            app.CostadodelavaTab.Title = 'Costado de la vía';
            app.SeveridadlateraldistanciaalobjetoladodelconductorLabel = uilabel(app.CostadodelavaTab);
            app.SeveridadlateraldistanciaalobjetoladodelconductorLabel.Text = 'Severidad lateral - distancia al objeto (lado del conductor)';
            app.Button = uibutton(app.CostadodelavaTab, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Tooltip = {'0 a < 1 m'};
            app.Button.Icon = '0-1.png';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.CostadodelavaTab, 'state');
            app.Button_2.ValueChangedFcn = createCallbackFcn(app, @Button_2ValueChanged, true);
            app.Button_2.Tooltip = {'1 a < 5 m'};
            app.Button_2.Icon = '1-5.png';
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.CostadodelavaTab, 'state');
            app.Button_3.ValueChangedFcn = createCallbackFcn(app, @Button_3ValueChanged, true);
            app.Button_3.Tooltip = {'5 a < 10 m'};
            app.Button_3.Icon = '5-10.png';
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.CostadodelavaTab, 'state');
            app.Button_4.ValueChangedFcn = createCallbackFcn(app, @Button_4ValueChanged, true);
            app.Button_4.Tooltip = {'≥ 10 m'};
            app.Button_4.Icon = '10+.png';
            app.Button_4.Text = '';
            app.SeveridadlateralobjetoladodelconductorLabel = uilabel(app.CostadodelavaTab);
            app.SeveridadlateralobjetoladodelconductorLabel.Text = 'Severidad lateral - objeto (lado del conductor)';
            app.SeveridadlateraldistanciaalobjetoladodelcopilotoLabel = uilabel(app.CostadodelavaTab);
            app.SeveridadlateraldistanciaalobjetoladodelcopilotoLabel.Text = 'Severidad lateral - distancia al objeto (lado del copiloto)';
            app.Button_5 = uibutton(app.CostadodelavaTab, 'state');
            app.Button_5.ValueChangedFcn = createCallbackFcn(app, @Button_5ValueChanged, true);
            app.Button_5.Tooltip = {'0 a < 1 m'};
            app.Button_5.Icon = '0-1.png';
            app.Button_5.Text = '';
            app.Button_6 = uibutton(app.CostadodelavaTab, 'state');
            app.Button_6.ValueChangedFcn = createCallbackFcn(app, @Button_6ValueChanged, true);
            app.Button_6.Tooltip = {'1 a < 5 m'};
            app.Button_6.Icon = '1-5.png';
            app.Button_6.Text = '';
            app.Button_7 = uibutton(app.CostadodelavaTab, 'state');
            app.Button_7.ValueChangedFcn = createCallbackFcn(app, @Button_7ValueChanged, true);
            app.Button_7.Tooltip = {'5 a < 10 m'};
            app.Button_7.Icon = '5-10.png';
            app.Button_7.Text = '';
            app.Button_8 = uibutton(app.CostadodelavaTab, 'state');
            app.Button_8.ValueChangedFcn = createCallbackFcn(app, @Button_8ValueChanged, true);
            app.Button_8.Tooltip = {'≥ 10 m'};
            app.Button_8.Icon = '10+.png';
            app.Button_8.Text = '';
            app.SeveridadlateralobjetoladodelcopilotoLabel = uilabel(app.CostadodelavaTab);
            app.SeveridadlateralobjetoladodelcopilotoLabel.Text = 'Severidad lateral - objeto (lado del copiloto)';
            app.Button_519 = uibutton(app.CostadodelavaTab, 'state');
            app.Button_519.ValueChangedFcn = createCallbackFcn(app, @Button_519ValueChanged, true);
            app.Button_519.Tooltip = {'Precipicio'};
            app.Button_519.Icon = 'CLIFF.png';
            app.Button_519.Text = '';
            app.Button_520 = uibutton(app.CostadodelavaTab, 'state');
            app.Button_520.ValueChangedFcn = createCallbackFcn(app, @Button_520ValueChanged, true);
            app.Button_520.Tooltip = {'Árbol (≥ 10 cm de diámetro)'};
            app.Button_520.Icon = 'Land use - passenger-side LAND.png';
            app.Button_520.Text = '';
            app.Button_521 = uibutton(app.CostadodelavaTab, 'state');
            app.Button_521.ValueChangedFcn = createCallbackFcn(app, @Button_521ValueChanged, true);
            app.Button_521.Tooltip = {'Letrero / poste / barra rígidos (≥ 10 cm de diámetro)'};
            app.Button_521.Icon = 'POLE.png';
            app.Button_521.Text = '';
            app.Button_522 = uibutton(app.CostadodelavaTab, 'state');
            app.Button_522.ValueChangedFcn = createCallbackFcn(app, @Button_522ValueChanged, true);
            app.Button_522.Tooltip = {'Extremo de barrera desprotegido'};
            app.Button_522.Icon = 'END.png';
            app.Button_522.Text = '';
            app.Button_526 = uibutton(app.CostadodelavaTab, 'state');
            app.Button_526.ValueChangedFcn = createCallbackFcn(app, @Button_526ValueChanged, true);
            app.Button_526.Tooltip = {'Cara vertical agresiva en el talud en corte'};
            app.Button_526.Icon = 'ROCKFACE.png';
            app.Button_526.Text = '';
            app.Button_525 = uibutton(app.CostadodelavaTab, 'state');
            app.Button_525.ValueChangedFcn = createCallbackFcn(app, @Button_525ValueChanged, true);
            app.Button_525.Tooltip = {'Pendiente ascendente del talud en corte (15° a 75°)'};
            app.Button_525.Icon = 'ROLL.png';
            app.Button_525.Text = '';
            app.Button_524 = uibutton(app.CostadodelavaTab, 'state');
            app.Button_524.ValueChangedFcn = createCallbackFcn(app, @Button_524ValueChanged, true);
            app.Button_524.Tooltip = {'Cuneta profunda'};
            app.Button_524.Icon = 'DITCH.png';
            app.Button_524.Text = '';
            app.Button_523 = uibutton(app.CostadodelavaTab, 'state');
            app.Button_523.ValueChangedFcn = createCallbackFcn(app, @Button_523ValueChanged, true);
            app.Button_523.Tooltip = {'Terraplén'};
            app.Button_523.Icon = 'SLOPE.png';
            app.Button_523.Text = '';
            app.Button_527 = uibutton(app.CostadodelavaTab, 'state');
            app.Button_527.ValueChangedFcn = createCallbackFcn(app, @Button_527ValueChanged, true);
            app.Button_527.Tooltip = {'Estructura / puente / edificio rígidos'};
            app.Button_527.Icon = 'Rigid structure.png';
            app.Button_527.Text = '';
            app.Button_528 = uibutton(app.CostadodelavaTab, 'state');
            app.Button_528.ValueChangedFcn = createCallbackFcn(app, @Button_528ValueChanged, true);
            app.Button_528.Tooltip = {'Estructura /edificio colapsable'};
            app.Button_528.Icon = 'Semi-rigid structure.png';
            app.Button_528.Text = '';
            app.Button_529 = uibutton(app.CostadodelavaTab, 'state');
            app.Button_529.ValueChangedFcn = createCallbackFcn(app, @Button_529ValueChanged, true);
            app.Button_529.Tooltip = {'Barrera de seguridad – hormigón'};
            app.Button_529.Icon = 'CONCRETE.png';
            app.Button_529.Text = '';
            app.Button_530 = uibutton(app.CostadodelavaTab, 'state');
            app.Button_530.ValueChangedFcn = createCallbackFcn(app, @Button_530ValueChanged, true);
            app.Button_530.Tooltip = {'Barrera de seguridad – metal'};
            app.Button_530.Icon = 'METAL.png';
            app.Button_530.Text = '';
            app.Button_532 = uibutton(app.CostadodelavaTab, 'state');
            app.Button_532.ValueChangedFcn = createCallbackFcn(app, @Button_532ValueChanged, true);
            app.Button_532.Tooltip = {'Barrera de seguridad – cable de acero'};
            app.Button_532.Icon = 'wire rope.png';
            app.Button_532.Text = '';
            app.Button_533 = uibutton(app.CostadodelavaTab, 'state');
            app.Button_533.ValueChangedFcn = createCallbackFcn(app, @Button_533ValueChanged, true);
            app.Button_533.Tooltip = {'Barrera de seguridad – amigable para motocicletas'};
            app.Button_533.Icon = 'motorcycle friendly.png';
            app.Button_533.Text = '';
            app.Button_534 = uibutton(app.CostadodelavaTab, 'state');
            app.Button_534.ValueChangedFcn = createCallbackFcn(app, @Button_534ValueChanged, true);
            app.Button_534.Tooltip = {'Pendiente empinada del talud en corte (> 75°)'};
            app.Button_534.Icon = 'NO ROLL.png';
            app.Button_534.Text = '';
            app.Button_531 = uibutton(app.CostadodelavaTab, 'state');
            app.Button_531.ValueChangedFcn = createCallbackFcn(app, @Button_531ValueChanged, true);
            app.Button_531.Tooltip = {'Ninguno (u objeto > 20 m de la carretera)'};
            app.Button_531.Icon = 'NO OBJECT.png';
            app.Button_531.Text = '';
            app.Button_535 = uibutton(app.CostadodelavaTab, 'state');
            app.Button_535.ValueChangedFcn = createCallbackFcn(app, @Button_535ValueChanged, true);
            app.Button_535.Tooltip = {'Grandes rocas (≥ 20 cm de altura)'};
            app.Button_535.Icon = 'ROCK.png';
            app.Button_535.Text = '';
            app.BandassonorasenelespaldnLabel = uilabel(app.CostadodelavaTab);
            app.BandassonorasenelespaldnLabel.Text = 'Bandas sonoras en el espaldón';
            app.Button_9 = uibutton(app.CostadodelavaTab, 'state');
            app.Button_9.ValueChangedFcn = createCallbackFcn(app, @Button_9ValueChanged, true);
            app.Button_9.Tooltip = {'Ausente'};
            app.Button_9.Icon = 'NOT PRESENT.png';
            app.Button_9.Text = '';
            app.Button_10 = uibutton(app.CostadodelavaTab, 'state');
            app.Button_10.ValueChangedFcn = createCallbackFcn(app, @Button_10ValueChanged, true);
            app.Button_10.Tooltip = {'Presente'};
            app.Button_10.Icon = 'SHOULDER RUMBLE.png';
            app.Button_10.Text = '';
            app.EspaldndelladoconductorLabel = uilabel(app.CostadodelavaTab);
            app.EspaldndelladoconductorLabel.Text = 'Espaldón del lado conductor';
            app.Button_11 = uibutton(app.CostadodelavaTab, 'state');
            app.Button_11.ValueChangedFcn = createCallbackFcn(app, @Button_11ValueChanged, true);
            app.Button_11.Tooltip = {'Estrecho (≥ 0 m a 1,0 m)'};
            app.Button_11.Icon = 'PAVED 0-1.png';
            app.Button_11.Text = '';
            app.Button_12 = uibutton(app.CostadodelavaTab, 'state');
            app.Button_12.ValueChangedFcn = createCallbackFcn(app, @Button_12ValueChanged, true);
            app.Button_12.Tooltip = {'Medio (≥ 1,0 m a 2,4 m)'};
            app.Button_12.Icon = 'PAVED 1-2.4.png';
            app.Button_12.Text = '';
            app.Button_13 = uibutton(app.CostadodelavaTab, 'state');
            app.Button_13.ValueChangedFcn = createCallbackFcn(app, @Button_13ValueChanged, true);
            app.Button_13.Tooltip = {'Ancho (≥ 2,4 m)'};
            app.Button_13.Icon = 'PAVED 2.4+.png';
            app.Button_13.Text = '';
            app.Button_14 = uibutton(app.CostadodelavaTab, 'state');
            app.Button_14.ValueChangedFcn = createCallbackFcn(app, @Button_14ValueChanged, true);
            app.Button_14.Tooltip = {'Ninguno'};
            app.Button_14.Icon = 'NONE.png';
            app.Button_14.Text = '';
            app.EspaldndelladopasajeroLabel = uilabel(app.CostadodelavaTab);
            app.EspaldndelladopasajeroLabel.Text = 'Espaldón del lado pasajero';
            app.Button_666 = uibutton(app.CostadodelavaTab, 'state');
            app.Button_666.ValueChangedFcn = createCallbackFcn(app, @Button_666ValueChanged, true);
            app.Button_666.Tooltip = {'Precipicio'};
            app.Button_666.Icon = 'CLIFF.png';
            app.Button_666.Text = '';
            app.Button_667 = uibutton(app.CostadodelavaTab, 'state');
            app.Button_667.ValueChangedFcn = createCallbackFcn(app, @Button_667ValueChanged, true);
            app.Button_667.Tooltip = {'Árbol (≥ 10 cm de diámetro)'};
            app.Button_667.Icon = 'Land use - passenger-side LAND.png';
            app.Button_667.Text = '';
            app.Button_668 = uibutton(app.CostadodelavaTab, 'state');
            app.Button_668.ValueChangedFcn = createCallbackFcn(app, @Button_668ValueChanged, true);
            app.Button_668.Tooltip = {'Letrero / poste / barra rígidos (≥ 10 cm de diámetro)'};
            app.Button_668.Icon = 'POLE.png';
            app.Button_668.Text = '';
            app.Button_669 = uibutton(app.CostadodelavaTab, 'state');
            app.Button_669.ValueChangedFcn = createCallbackFcn(app, @Button_669ValueChanged, true);
            app.Button_669.Tooltip = {'Extremo de barrera desprotegido'};
            app.Button_669.Icon = 'END.png';
            app.Button_669.Text = '';
            app.Button_670 = uibutton(app.CostadodelavaTab, 'state');
            app.Button_670.ValueChangedFcn = createCallbackFcn(app, @Button_670ValueChanged, true);
            app.Button_670.Tooltip = {'Cara vertical agresiva en el talud en corte'};
            app.Button_670.Icon = 'ROCKFACE.png';
            app.Button_670.Text = '';
            app.Button_671 = uibutton(app.CostadodelavaTab, 'state');
            app.Button_671.ValueChangedFcn = createCallbackFcn(app, @Button_671ValueChanged, true);
            app.Button_671.Tooltip = {'Pendiente ascendente del talud en corte (15° a 75°)'};
            app.Button_671.Icon = 'ROLL.png';
            app.Button_671.Text = '';
            app.Button_672 = uibutton(app.CostadodelavaTab, 'state');
            app.Button_672.ValueChangedFcn = createCallbackFcn(app, @Button_672ValueChanged, true);
            app.Button_672.Tooltip = {'Cuneta profunda'};
            app.Button_672.Icon = 'DITCH.png';
            app.Button_672.Text = '';
            app.Button_673 = uibutton(app.CostadodelavaTab, 'state');
            app.Button_673.ValueChangedFcn = createCallbackFcn(app, @Button_673ValueChanged, true);
            app.Button_673.Tooltip = {'Terraplén'};
            app.Button_673.Icon = 'SLOPE.png';
            app.Button_673.Text = '';
            app.Button_674 = uibutton(app.CostadodelavaTab, 'state');
            app.Button_674.ValueChangedFcn = createCallbackFcn(app, @Button_674ValueChanged, true);
            app.Button_674.Tooltip = {'Estructura / puente / edificio rígidos'};
            app.Button_674.Icon = 'Rigid structure.png';
            app.Button_674.Text = '';
            app.Button_675 = uibutton(app.CostadodelavaTab, 'state');
            app.Button_675.ValueChangedFcn = createCallbackFcn(app, @Button_675ValueChanged, true);
            app.Button_675.Tooltip = {'Estructura /edificio colapsable'};
            app.Button_675.Icon = 'Semi-rigid structure.png';
            app.Button_675.Text = '';
            app.Button_676 = uibutton(app.CostadodelavaTab, 'state');
            app.Button_676.ValueChangedFcn = createCallbackFcn(app, @Button_676ValueChanged, true);
            app.Button_676.Tooltip = {'Barrera de seguridad – hormigón'};
            app.Button_676.Icon = 'CONCRETE.png';
            app.Button_676.Text = '';
            app.Button_677 = uibutton(app.CostadodelavaTab, 'state');
            app.Button_677.ValueChangedFcn = createCallbackFcn(app, @Button_677ValueChanged, true);
            app.Button_677.Tooltip = {'Barrera de seguridad – metal'};
            app.Button_677.Icon = 'METAL.png';
            app.Button_677.Text = '';
            app.Button_678 = uibutton(app.CostadodelavaTab, 'state');
            app.Button_678.ValueChangedFcn = createCallbackFcn(app, @Button_678ValueChanged, true);
            app.Button_678.Tooltip = {'Barrera de seguridad – cable de acero'};
            app.Button_678.Icon = 'wire rope.png';
            app.Button_678.Text = '';
            app.Button_679 = uibutton(app.CostadodelavaTab, 'state');
            app.Button_679.ValueChangedFcn = createCallbackFcn(app, @Button_679ValueChanged, true);
            app.Button_679.Tooltip = {'Barrera de seguridad: amigable para motocicletas'};
            app.Button_679.Icon = 'motorcycle friendly.png';
            app.Button_679.Text = '';
            app.Button_680 = uibutton(app.CostadodelavaTab, 'state');
            app.Button_680.ValueChangedFcn = createCallbackFcn(app, @Button_680ValueChanged, true);
            app.Button_680.Tooltip = {'Pendiente empinada del talud en corte (> 75°)'};
            app.Button_680.Icon = 'NO ROLL.png';
            app.Button_680.Text = '';
            app.Button_681 = uibutton(app.CostadodelavaTab, 'state');
            app.Button_681.ValueChangedFcn = createCallbackFcn(app, @Button_681ValueChanged, true);
            app.Button_681.Tooltip = {'Ninguno (u objeto > 20 m de la carretera)'};
            app.Button_681.Icon = 'NO OBJECT.png';
            app.Button_681.Text = '';
            app.Button_682 = uibutton(app.CostadodelavaTab, 'state');
            app.Button_682.ValueChangedFcn = createCallbackFcn(app, @Button_682ValueChanged, true);
            app.Button_682.Tooltip = {'Grandes rocas (≥ 20 cm de altura)'};
            app.Button_682.Icon = 'ROCK.png';
            app.Button_682.Text = '';
            app.Button_683 = uibutton(app.CostadodelavaTab, 'state');
            app.Button_683.ValueChangedFcn = createCallbackFcn(app, @Button_683ValueChanged, true);
            app.Button_683.Tooltip = {'Estrecho (≥ 0 m a 1,0 m)'};
            app.Button_683.Icon = 'PAVED 0-1.png';
            app.Button_683.Text = '';
            app.Button_684 = uibutton(app.CostadodelavaTab, 'state');
            app.Button_684.ValueChangedFcn = createCallbackFcn(app, @Button_684ValueChanged, true);
            app.Button_684.Tooltip = {'Medio (≥ 1,0 m a 2,4 m)'};
            app.Button_684.Icon = 'PAVED 1-2.4.png';
            app.Button_684.Text = '';
            app.Button_685 = uibutton(app.CostadodelavaTab, 'state');
            app.Button_685.ValueChangedFcn = createCallbackFcn(app, @Button_685ValueChanged, true);
            app.Button_685.Tooltip = {'Ancho (≥ 2,4 m)'};
            app.Button_685.Icon = 'PAVED 2.4+.png';
            app.Button_685.Text = '';
            app.Button_686 = uibutton(app.CostadodelavaTab, 'state');
            app.Button_686.ValueChangedFcn = createCallbackFcn(app, @Button_686ValueChanged, true);
            app.Button_686.Tooltip = {'Ninguno'};
            app.Button_686.Icon = 'NONE.png';
            app.Button_686.Text = '';
            app.MedianaTab = uitab(app.TabGroup);
            app.MedianaTab.Title = 'Mediana';
            app.TipodecarreteraLabel = uilabel(app.MedianaTab);
            app.TipodecarreteraLabel.Text = 'Tipo de carretera';
            app.Button_70 = uibutton(app.MedianaTab, 'state');
            app.Button_70.ValueChangedFcn = createCallbackFcn(app, @Button_70ValueChanged, true);
            app.Button_70.Tooltip = {'Calzada A de una calzada dividida'};
            app.Button_70.Icon = 'A.png';
            app.Button_70.Text = '';
            app.Button_71 = uibutton(app.MedianaTab, 'state');
            app.Button_71.ValueChangedFcn = createCallbackFcn(app, @Button_71ValueChanged, true);
            app.Button_71.Tooltip = {'Calzada B de una calzada dividida'};
            app.Button_71.Icon = 'B.png';
            app.Button_71.Text = '';
            app.Button_72 = uibutton(app.MedianaTab, 'state');
            app.Button_72.ValueChangedFcn = createCallbackFcn(app, @Button_72ValueChanged, true);
            app.Button_72.Tooltip = {'Carretera no dividida'};
            app.Button_72.Icon = 'UNDIVIDED ROAD.png';
            app.Button_72.Text = '';
            app.Button_73 = uibutton(app.MedianaTab, 'state');
            app.Button_73.ValueChangedFcn = createCallbackFcn(app, @Button_73ValueChanged, true);
            app.Button_73.Tooltip = {'Calzada A de una infraestructura de motocicletas'};
            app.Button_73.Icon = 'mc A.png';
            app.Button_73.Text = '';
            app.CostodemodernizacinLabel = uilabel(app.MedianaTab);
            app.CostodemodernizacinLabel.Text = 'Costo de modernización';
            app.TipodemedianaLabel_2 = uilabel(app.MedianaTab);
            app.TipodemedianaLabel_2.Text = 'Tipo de mediana';
            app.Button_74 = uibutton(app.MedianaTab, 'state');
            app.Button_74.ValueChangedFcn = createCallbackFcn(app, @Button_74ValueChanged, true);
            app.Button_74.Tooltip = {'Línea central'};
            app.Button_74.Icon = 'CENTRE LINE.png';
            app.Button_74.Text = '';
            app.Button_75 = uibutton(app.MedianaTab, 'state');
            app.Button_75.ValueChangedFcn = createCallbackFcn(app, @Button_75ValueChanged, true);
            app.Button_75.Tooltip = {'Raya separadora de sentidos de circulación doble (0,3 m a 1 m) y rayas diagonales a 45°'};
            app.Button_75.Icon = 'Wide centre line.png';
            app.Button_75.Text = '';
            app.Button_76 = uibutton(app.MedianaTab, 'state');
            app.Button_76.ValueChangedFcn = createCallbackFcn(app, @Button_76ValueChanged, true);
            app.Button_76.Tooltip = {'Raya separadora de sentidos de circulación doble > 1 m y rayas diagonales a 45°'};
            app.Button_76.Icon = 'Central hatching.png';
            app.Button_76.Text = '';
            app.Button_77 = uibutton(app.MedianaTab, 'state');
            app.Button_77.ValueChangedFcn = createCallbackFcn(app, @Button_77ValueChanged, true);
            app.Button_77.Tooltip = {'Carril central continuo para giro'};
            app.Button_77.Icon = 'Continuous central turning lane.png';
            app.Button_77.Text = '';
            app.BandassonorascentralesLabel = uilabel(app.MedianaTab);
            app.BandassonorascentralesLabel.Text = 'Bandas sonoras centrales';
            app.SiButton_2 = uibutton(app.MedianaTab, 'state');
            app.SiButton_2.ValueChangedFcn = createCallbackFcn(app, @SiButton_2ValueChanged, true);
            app.SiButton_2.Tooltip = {'Presente'};
            app.SiButton_2.Icon = 'PRESENT.png';
            app.SiButton_2.Text = 'Si';
            app.Button_557 = uibutton(app.MedianaTab, 'state');
            app.Button_557.ValueChangedFcn = createCallbackFcn(app, @Button_557ValueChanged, true);
            app.Button_557.Tooltip = {'Ausente'};
            app.Button_557.Icon = 'CENTRE RUMBLE.png';
            app.Button_557.Text = {''; ''};
            app.Button_491 = uibutton(app.MedianaTab, 'state');
            app.Button_491.ValueChangedFcn = createCallbackFcn(app, @Button_491ValueChanged, true);
            app.Button_491.Tooltip = {'Bajo'};
            app.Button_491.Icon = 'UPGRADE COST LOW.png';
            app.Button_491.Text = '';
            app.Button_492 = uibutton(app.MedianaTab, 'state');
            app.Button_492.ValueChangedFcn = createCallbackFcn(app, @Button_492ValueChanged, true);
            app.Button_492.Tooltip = {'Medio'};
            app.Button_492.Icon = 'MED.png';
            app.Button_492.Text = '';
            app.Button_493 = uibutton(app.MedianaTab, 'state');
            app.Button_493.ValueChangedFcn = createCallbackFcn(app, @Button_493ValueChanged, true);
            app.Button_493.Tooltip = {'Alto'};
            app.Button_493.Icon = 'HIGH.png';
            app.Button_493.Text = '';
            app.CurvaturaLabel_2 = uilabel(app.MedianaTab);
            app.CurvaturaLabel_2.Text = 'Curvatura';
            app.Button_78 = uibutton(app.MedianaTab, 'state');
            app.Button_78.ValueChangedFcn = createCallbackFcn(app, @Button_78ValueChanged, true);
            app.Button_78.Tooltip = {'Muy cerrada'};
            app.Button_78.Icon = 'Very sharp.png';
            app.Button_78.Text = '';
            app.Button_79 = uibutton(app.MedianaTab, 'state');
            app.Button_79.ValueChangedFcn = createCallbackFcn(app, @Button_79ValueChanged, true);
            app.Button_79.Tooltip = {'Cerrada'};
            app.Button_79.Icon = 'Sharp.png';
            app.Button_79.Text = '';
            app.NmerodecarrilesLabel_2 = uilabel(app.MedianaTab);
            app.NmerodecarrilesLabel_2.Text = 'Número de carriles';
            app.AnchodecarrilLabel_2 = uilabel(app.MedianaTab);
            app.AnchodecarrilLabel_2.Text = 'Ancho de carril';
            app.CarrilP = uibutton(app.MedianaTab, 'state');
            app.CarrilP.ValueChangedFcn = createCallbackFcn(app, @CarrilPValueChanged, true);
            app.CarrilP.Tooltip = {'Estrecho (≥ 0 m a < 2,75 m)'};
            app.CarrilP.Icon = 'N LANE.png';
            app.CarrilP.Text = '';
            app.EstadodelasuperficiedelavaLabel = uilabel(app.MedianaTab);
            app.EstadodelasuperficiedelavaLabel.Text = 'Estado de la superficie de la vía';
            app.Button_560 = uibutton(app.MedianaTab, 'state');
            app.Button_560.ValueChangedFcn = createCallbackFcn(app, @Button_560ValueChanged, true);
            app.Button_560.Tooltip = {'Mala'};
            app.Button_560.Icon = 'Road condition POOR.png';
            app.Button_560.Text = '';
            app.Button_561 = uibutton(app.MedianaTab, 'state');
            app.Button_561.ValueChangedFcn = createCallbackFcn(app, @Button_561ValueChanged, true);
            app.Button_561.Tooltip = {'Regular'};
            app.Button_561.Icon = 'Road condition MED.png';
            app.Button_561.Text = '';
            app.CalidaddelacurvaLabel = uilabel(app.MedianaTab);
            app.CalidaddelacurvaLabel.Text = 'Calidad de la curva';
            app.Button_92 = uibutton(app.MedianaTab, 'state');
            app.Button_92.ValueChangedFcn = createCallbackFcn(app, @Button_92ValueChanged, true);
            app.Button_92.Tooltip = {'Adecuada'};
            app.Button_92.Icon = 'GOOD.png';
            app.Button_92.Text = '';
            app.Button_93 = uibutton(app.MedianaTab, 'state');
            app.Button_93.ValueChangedFcn = createCallbackFcn(app, @Button_93ValueChanged, true);
            app.Button_93.Tooltip = {'Deficiente'};
            app.Button_93.Icon = 'POOR.png';
            app.Button_93.Text = '';
            app.Button_94 = uibutton(app.MedianaTab, 'state');
            app.Button_94.ValueChangedFcn = createCallbackFcn(app, @Button_94ValueChanged, true);
            app.Button_94.Tooltip = {'No aplica'};
            app.Button_94.Icon = 'NOT APPLICABLE.png';
            app.Button_94.Text = '';
            app.PendienteLabel_2 = uilabel(app.MedianaTab);
            app.PendienteLabel_2.Text = 'Pendiente';
            app.Button_96 = uibutton(app.MedianaTab, 'state');
            app.Button_96.ValueChangedFcn = createCallbackFcn(app, @Button_96ValueChanged, true);
            app.Button_96.Tooltip = {'≥ 10 %'};
            app.Button_96.Icon = '10+ GRADE.png';
            app.Button_96.Text = '';
            app.Button_97 = uibutton(app.MedianaTab, 'state');
            app.Button_97.ValueChangedFcn = createCallbackFcn(app, @Button_97ValueChanged, true);
            app.Button_97.Tooltip = {'7,5 % a 10 %'};
            app.Button_97.Icon = '7-10 GRADE.png';
            app.Button_97.Text = '';
            app.Button_98 = uibutton(app.MedianaTab, 'state');
            app.Button_98.ValueChangedFcn = createCallbackFcn(app, @Button_98ValueChanged, true);
            app.Button_98.Tooltip = {'0 % a < 7,5 %'};
            app.Button_98.Icon = '0-7 grade.png';
            app.Button_98.Text = '';
            app.ResistenciaaldeslizamientoLabel_2 = uilabel(app.MedianaTab);
            app.ResistenciaaldeslizamientoLabel_2.Text = 'Resistencia al deslizamiento';
            app.Button_100 = uibutton(app.MedianaTab, 'state');
            app.Button_100.ValueChangedFcn = createCallbackFcn(app, @Button_100ValueChanged, true);
            app.Button_100.Tooltip = {'Sin pavimentar – adecuado'};
            app.Button_100.Icon = 'UNSEAL ADECUATE.png';
            app.Button_100.Text = '';
            app.Button_101 = uibutton(app.MedianaTab, 'state');
            app.Button_101.ValueChangedFcn = createCallbackFcn(app, @Button_101ValueChanged, true);
            app.Button_101.Tooltip = {'Pavimentado – regular'};
            app.Button_101.Icon = 'Sealed - medium.png';
            app.Button_101.Text = '';
            app.Button_102 = uibutton(app.MedianaTab, 'state');
            app.Button_102.ValueChangedFcn = createCallbackFcn(app, @Button_102ValueChanged, true);
            app.Button_102.Tooltip = {'Calzada B de una infraestructura de motocicletas'};
            app.Button_102.Icon = 'mc B.png';
            app.Button_102.Text = '';
            app.Button_103 = uibutton(app.MedianaTab, 'state');
            app.Button_103.ValueChangedFcn = createCallbackFcn(app, @Button_103ValueChanged, true);
            app.Button_103.Tooltip = {'Delineadores flexibles'};
            app.Button_103.Icon = 'FLEXIPOST.png';
            app.Button_103.Text = '';
            app.Button_104 = uibutton(app.MedianaTab, 'state');
            app.Button_104.ValueChangedFcn = createCallbackFcn(app, @Button_104ValueChanged, true);
            app.Button_104.Tooltip = {'Mediana física con un ancho ≥ 0 m a < 1 m'};
            app.Button_104.Icon = 'Physical median width.png';
            app.Button_104.Text = '';
            app.Button_105 = uibutton(app.MedianaTab, 'state');
            app.Button_105.ValueChangedFcn = createCallbackFcn(app, @Button_105ValueChanged, true);
            app.Button_105.Tooltip = {'Mediana física con un ancho ≥ 1 m a < 5 m'};
            app.Button_105.Icon = 'Physical median width 1-5.png';
            app.Button_105.Text = '';
            app.Button_106 = uibutton(app.MedianaTab, 'state');
            app.Button_106.ValueChangedFcn = createCallbackFcn(app, @Button_106ValueChanged, true);
            app.Button_106.Tooltip = {'Mediana física con un ancho ≥ 5 m a < 10 m'};
            app.Button_106.Icon = 'Physical median width 5-10.png';
            app.Button_106.Text = '';
            app.Button_107 = uibutton(app.MedianaTab, 'state');
            app.Button_107.ValueChangedFcn = createCallbackFcn(app, @Button_107ValueChanged, true);
            app.Button_107.Tooltip = {'Barrera de seguridad – concreto'};
            app.Button_107.Icon = 'CONCRETE.png';
            app.Button_107.Text = '';
            app.Button_108 = uibutton(app.MedianaTab, 'state');
            app.Button_108.ValueChangedFcn = createCallbackFcn(app, @Button_108ValueChanged, true);
            app.Button_108.Tooltip = {'Barrera de seguridad – metal'};
            app.Button_108.Icon = 'METAL.png';
            app.Button_108.Text = '';
            app.Button_109 = uibutton(app.MedianaTab, 'state');
            app.Button_109.ValueChangedFcn = createCallbackFcn(app, @Button_109ValueChanged, true);
            app.Button_109.Tooltip = {'Barrera de seguridad - amigable para motociclistas'};
            app.Button_109.Icon = 'motorcycle friendly.png';
            app.Button_109.Text = '';
            app.Button_110 = uibutton(app.MedianaTab, 'state');
            app.Button_110.ValueChangedFcn = createCallbackFcn(app, @Button_110ValueChanged, true);
            app.Button_110.Tooltip = {'Barrera de seguridad - cable de acero'};
            app.Button_110.Icon = 'wire rope.png';
            app.Button_110.Text = '';
            app.Button_111 = uibutton(app.MedianaTab, 'state');
            app.Button_111.ValueChangedFcn = createCallbackFcn(app, @Button_111ValueChanged, true);
            app.Button_111.Tooltip = {'Mediana física con un ancho ≥ 10 m a < 20 m'};
            app.Button_111.Icon = 'Physical median width 10-20.png';
            app.Button_111.Text = '';
            app.Button_112 = uibutton(app.MedianaTab, 'state');
            app.Button_112.ValueChangedFcn = createCallbackFcn(app, @Button_112ValueChanged, true);
            app.Button_112.Tooltip = {'Mediana física con un ancho ≥ 20 m'};
            app.Button_112.Icon = 'Physical median width 20+.png';
            app.Button_112.Text = '';
            app.Button_113 = uibutton(app.MedianaTab, 'state');
            app.Button_113.ValueChangedFcn = createCallbackFcn(app, @Button_113ValueChanged, true);
            app.Button_113.Tooltip = {'Un solo sentido'};
            app.Button_113.Icon = 'one way.png';
            app.Button_113.Text = '';
            app.Button_115 = uibutton(app.MedianaTab, 'state');
            app.Button_115.ValueChangedFcn = createCallbackFcn(app, @Button_115ValueChanged, true);
            app.Button_115.Tooltip = {'Moderada'};
            app.Button_115.Icon = 'Moderate.png';
            app.Button_115.Text = '';
            app.Button_116 = uibutton(app.MedianaTab, 'state');
            app.Button_116.ValueChangedFcn = createCallbackFcn(app, @Button_116ValueChanged, true);
            app.Button_116.Tooltip = {'Recto o ligeramente curvado'};
            app.Button_116.Icon = 'Straight or gently curving.png';
            app.Button_116.Text = '';
            app.CarrilM = uibutton(app.MedianaTab, 'state');
            app.CarrilM.ValueChangedFcn = createCallbackFcn(app, @CarrilMValueChanged, true);
            app.CarrilM.Tooltip = {'Medio (≥ 2,75 m a < 3,25 m)'};
            app.CarrilM.Icon = 'M LANE.png';
            app.CarrilM.Text = '';
            app.Button_121 = uibutton(app.MedianaTab, 'state');
            app.Button_121.ValueChangedFcn = createCallbackFcn(app, @Button_121ValueChanged, true);
            app.Button_121.Tooltip = {'Tres '};
            app.Button_121.Icon = '3 LANE.png';
            app.Button_121.Text = '';
            app.Button_122 = uibutton(app.MedianaTab, 'state');
            app.Button_122.ValueChangedFcn = createCallbackFcn(app, @Button_122ValueChanged, true);
            app.Button_122.Tooltip = {'Cuatro o más'};
            app.Button_122.Icon = '4 LANE.png';
            app.Button_122.Text = '';
            app.Button_562 = uibutton(app.MedianaTab, 'state');
            app.Button_562.ValueChangedFcn = createCallbackFcn(app, @Button_562ValueChanged, true);
            app.Button_562.Tooltip = {'Buena'};
            app.Button_562.Icon = 'Road condition GOOD.png';
            app.Button_562.Text = '';
            app.Button_126 = uibutton(app.MedianaTab, 'state');
            app.Button_126.ValueChangedFcn = createCallbackFcn(app, @Button_126ValueChanged, true);
            app.Button_126.Tooltip = {'Pavimentado – adecuado'};
            app.Button_126.Icon = 'SEALED ADECUATE.png';
            app.Button_126.Text = '';
            app.Button_127 = uibutton(app.MedianaTab, 'state');
            app.Button_127.ValueChangedFcn = createCallbackFcn(app, @Button_127ValueChanged, true);
            app.Button_127.Tooltip = {'Pavimentado – pobre'};
            app.Button_127.Icon = 'SEALED.png';
            app.Button_127.Text = '';
            app.Button_128 = uibutton(app.MedianaTab, 'state');
            app.Button_128.ValueChangedFcn = createCallbackFcn(app, @Button_128ValueChanged, true);
            app.Button_128.Tooltip = {'Sin pavimentar – pobre'};
            app.Button_128.Icon = 'UNSEAL.png';
            app.Button_128.Text = '';
            app.Button_559 = uibutton(app.MedianaTab, 'state');
            app.Button_559.ValueChangedFcn = createCallbackFcn(app, @Button_559ValueChanged, true);
            app.Button_559.Tooltip = {'Tres + dos  '};
            app.Button_559.Icon = '3&2 LANE.png';
            app.Button_559.Text = '';
            app.EstacionamientoparavehculosLabel = uilabel(app.MedianaTab);
            app.EstacionamientoparavehculosLabel.Text = 'Estacionamiento para vehículos';
            app.DelineacinLabel = uilabel(app.MedianaTab);
            app.DelineacinLabel.Text = 'Delineación';
            app.Button_563 = uibutton(app.MedianaTab, 'state');
            app.Button_563.ValueChangedFcn = createCallbackFcn(app, @Button_563ValueChanged, true);
            app.Button_563.Tooltip = {'Deficiente'};
            app.Button_563.Icon = 'DELINEATION POOR.png';
            app.Button_563.Text = '';
            app.Button_564 = uibutton(app.MedianaTab, 'state');
            app.Button_564.ValueChangedFcn = createCallbackFcn(app, @Button_564ValueChanged, true);
            app.Button_564.Tooltip = {'Adecuada'};
            app.Button_564.Icon = 'Adequate.png';
            app.Button_564.Text = '';
            app.AlumbradopblicoLabel = uilabel(app.MedianaTab);
            app.AlumbradopblicoLabel.Text = 'Alumbrado público';
            app.ObrasenlacarreteraLabel = uilabel(app.MedianaTab);
            app.ObrasenlacarreteraLabel.Text = 'Obras en la carretera';
            app.Button_138 = uibutton(app.MedianaTab, 'state');
            app.Button_138.ValueChangedFcn = createCallbackFcn(app, @Button_138ValueChanged, true);
            app.Button_138.Tooltip = {'Obra mayor en proceso'};
            app.Button_138.Icon = 'Minor road works in progress.png';
            app.Button_138.Text = '';
            app.Button_139 = uibutton(app.MedianaTab, 'state');
            app.Button_139.ValueChangedFcn = createCallbackFcn(app, @Button_139ValueChanged, true);
            app.Button_139.Tooltip = {'Sin obras'};
            app.Button_139.Icon = 'NOT ROAD WORKS.png';
            app.Button_139.Text = '';
            app.Button_141 = uibutton(app.MedianaTab, 'state');
            app.Button_141.ValueChangedFcn = createCallbackFcn(app, @Button_141ValueChanged, true);
            app.Button_141.Tooltip = {'Obra menor en proceso'};
            app.Button_141.Icon = 'ROAD WORKS.png';
            app.Button_141.Text = '';
            app.DistanciadevisibilidadLabel = uilabel(app.MedianaTab);
            app.DistanciadevisibilidadLabel.Text = 'Distancia de visibilidad';
            app.Button_144 = uibutton(app.MedianaTab, 'state');
            app.Button_144.ValueChangedFcn = createCallbackFcn(app, @Button_144ValueChanged, true);
            app.Button_144.Tooltip = {'Deficiente'};
            app.Button_144.Icon = 'sight distance POOR.png';
            app.Button_144.Text = '';
            app.Button_145 = uibutton(app.MedianaTab, 'state');
            app.Button_145.ValueChangedFcn = createCallbackFcn(app, @Button_145ValueChanged, true);
            app.Button_145.Tooltip = {'Adecuada'};
            app.Button_145.Icon = 'Sight distance ADEQUATE.png';
            app.Button_145.Text = '';
            app.Button_565 = uibutton(app.MedianaTab, 'state');
            app.Button_565.ValueChangedFcn = createCallbackFcn(app, @Button_565ValueChanged, true);
            app.Button_565.Tooltip = {'Presente'};
            app.Button_565.Icon = 'Present LIGHT.png';
            app.Button_565.Text = '';
            app.Button_566 = uibutton(app.MedianaTab, 'state');
            app.Button_566.ValueChangedFcn = createCallbackFcn(app, @Button_566ValueChanged, true);
            app.Button_566.Tooltip = {'Ausente'};
            app.Button_566.Icon = 'Street lighting  not present.png';
            app.Button_566.Text = '';
            app.VadeservicioLabel = uilabel(app.MedianaTab);
            app.VadeservicioLabel.Text = 'Vía de servicio';
            app.SiButton_18 = uibutton(app.MedianaTab, 'state');
            app.SiButton_18.ValueChangedFcn = createCallbackFcn(app, @SiButton_18ValueChanged, true);
            app.SiButton_18.Tooltip = {'Presente'};
            app.SiButton_18.Text = 'Si';
            app.NoButton_19 = uibutton(app.MedianaTab, 'state');
            app.NoButton_19.ValueChangedFcn = createCallbackFcn(app, @NoButton_19ValueChanged, true);
            app.NoButton_19.Tooltip = {'Ausente'};
            app.NoButton_19.Text = 'No';
            app.Button_567 = uibutton(app.MedianaTab, 'state');
            app.Button_567.ValueChangedFcn = createCallbackFcn(app, @Button_567ValueChanged, true);
            app.Button_567.Tooltip = {'Dos lados'};
            app.Button_567.Icon = 'Two sides.png';
            app.Button_567.Text = '';
            app.Button_150 = uibutton(app.MedianaTab, 'state');
            app.Button_150.ValueChangedFcn = createCallbackFcn(app, @Button_150ValueChanged, true);
            app.Button_150.Tooltip = {'Un lado'};
            app.Button_150.Icon = 'ONE SIDE.png';
            app.Button_150.Text = '';
            app.Button_151 = uibutton(app.MedianaTab, 'state');
            app.Button_151.ValueChangedFcn = createCallbackFcn(app, @Button_151ValueChanged, true);
            app.Button_151.Tooltip = {'Ninguno'};
            app.Button_151.Icon = 'NO PARKING NONE.png';
            app.Button_151.Text = '';
            app.Button_660 = uibutton(app.MedianaTab, 'state');
            app.Button_660.ValueChangedFcn = createCallbackFcn(app, @Button_660ValueChanged, true);
            app.Button_660.Tooltip = {'Uno'};
            app.Button_660.Icon = '1 LANE.png';
            app.Button_660.Text = '';
            app.Button_661 = uibutton(app.MedianaTab, 'state');
            app.Button_661.ValueChangedFcn = createCallbackFcn(app, @Button_661ValueChanged, true);
            app.Button_661.Tooltip = {'Dos '};
            app.Button_661.Icon = '2 LANE.png';
            app.Button_661.Text = '';
            app.Button_662 = uibutton(app.MedianaTab, 'state');
            app.Button_662.ValueChangedFcn = createCallbackFcn(app, @Button_662ValueChanged, true);
            app.Button_662.Tooltip = {'Dos + uno'};
            app.Button_662.Icon = '2&1 LANE.png';
            app.Button_662.Text = '';
            app.CarrilG_2 = uibutton(app.MedianaTab, 'state');
            app.CarrilG_2.ValueChangedFcn = createCallbackFcn(app, @CarrilG_2ValueChanged, true);
            app.CarrilG_2.Tooltip = {'Ancho (≥ 3,25 m)'};
            app.CarrilG_2.Icon = 'W LANE.png';
            app.CarrilG_2.Text = '';
            app.InterseccionesTab = uitab(app.TabGroup);
            app.InterseccionesTab.Title = 'Intersecciones';
            app.TipodeinterseccinLabel = uilabel(app.InterseccionesTab);
            app.TipodeinterseccinLabel.Text = 'Tipo de intersección';
            app.Button_152 = uibutton(app.InterseccionesTab, 'state');
            app.Button_152.ValueChangedFcn = createCallbackFcn(app, @Button_152ValueChanged, true);
            app.Button_152.Tooltip = {'4 ramas (no semaforizada) con carril de giro no protegido'};
            app.Button_152.Icon = '4-leg (unsignalised).png';
            app.Button_152.Text = '';
            app.Button_153 = uibutton(app.InterseccionesTab, 'state');
            app.Button_153.ValueChangedFcn = createCallbackFcn(app, @Button_153ValueChanged, true);
            app.Button_153.Tooltip = {'Mini redondel'};
            app.Button_153.Icon = 'Mini roundabout.png';
            app.Button_153.Text = '';
            app.Button_154 = uibutton(app.InterseccionesTab, 'state');
            app.Button_154.ValueChangedFcn = createCallbackFcn(app, @Button_154ValueChanged, true);
            app.Button_154.Tooltip = {'Punto de cruce de mediana - informal'};
            app.Button_154.Icon = 'Median crossing point - informal.png';
            app.Button_154.Text = '';
            app.Button_155 = uibutton(app.InterseccionesTab, 'state');
            app.Button_155.ValueChangedFcn = createCallbackFcn(app, @Button_155ValueChanged, true);
            app.Button_155.Tooltip = {'Punto de cruce de mediana - formal'};
            app.Button_155.Icon = 'Median crossing point - formal.png';
            app.Button_155.Text = '';
            app.CanalizacindelainterseccinLabel = uilabel(app.InterseccionesTab);
            app.CanalizacindelainterseccinLabel.Text = 'Canalización de la intersección';
            app.VolumendelainterseccinLabel = uilabel(app.InterseccionesTab);
            app.VolumendelainterseccinLabel.Text = 'Volumen de la intersección';
            app.Button_568 = uibutton(app.InterseccionesTab, 'state');
            app.Button_568.ValueChangedFcn = createCallbackFcn(app, @Button_568ValueChanged, true);
            app.Button_568.Tooltip = {'Presente'};
            app.Button_568.Icon = 'Channelization present.png';
            app.Button_568.Text = '';
            app.Button_569 = uibutton(app.InterseccionesTab, 'state');
            app.Button_569.ValueChangedFcn = createCallbackFcn(app, @Button_569ValueChanged, true);
            app.Button_569.Tooltip = {'Ausente'};
            app.Button_569.Icon = 'Intersection channelisation NOT PRESENT.png';
            app.Button_569.Text = '';
            app.PuntosdeaccesoalapropiedadLabel = uilabel(app.InterseccionesTab);
            app.PuntosdeaccesoalapropiedadLabel.Text = 'Puntos de acceso a la propiedad';
            app.CalidaddelainterseccinLabel = uilabel(app.InterseccionesTab);
            app.CalidaddelainterseccinLabel.Text = 'Calidad de la intersección';
            app.Button_169 = uibutton(app.InterseccionesTab, 'state');
            app.Button_169.ValueChangedFcn = createCallbackFcn(app, @Button_169ValueChanged, true);
            app.Button_169.Tooltip = {'Acceso comercial 1+'};
            app.Button_169.Icon = 'Commercial Access 1+.png';
            app.Button_169.Text = '';
            app.Button_170 = uibutton(app.InterseccionesTab, 'state');
            app.Button_170.ValueChangedFcn = createCallbackFcn(app, @Button_170ValueChanged, true);
            app.Button_170.Tooltip = {'Acceso residencial 3+'};
            app.Button_170.Icon = 'Residential Access 3+.png';
            app.Button_170.Text = '';
            app.Button_171 = uibutton(app.InterseccionesTab, 'state');
            app.Button_171.ValueChangedFcn = createCallbackFcn(app, @Button_171ValueChanged, true);
            app.Button_171.Tooltip = {'Acceso residencial 1 o 2'};
            app.Button_171.Icon = 'Residential Access 1 or 2.png';
            app.Button_171.Text = '';
            app.Button_174 = uibutton(app.InterseccionesTab, 'state');
            app.Button_174.ValueChangedFcn = createCallbackFcn(app, @Button_174ValueChanged, true);
            app.Button_174.Tooltip = {'Ninguno'};
            app.Button_174.Icon = 'Intersecting road volume NOT APPLICABLE.png';
            app.Button_174.Text = '';
            app.Button_176 = uibutton(app.InterseccionesTab, 'state');
            app.Button_176.ValueChangedFcn = createCallbackFcn(app, @Button_176ValueChanged, true);
            app.Button_176.Tooltip = {'4 ramas (no semaforizada) con carril de giro protegido'};
            app.Button_176.Icon = 'INTERSECCION.png';
            app.Button_176.Text = '';
            app.Button_177 = uibutton(app.InterseccionesTab, 'state');
            app.Button_177.ValueChangedFcn = createCallbackFcn(app, @Button_177ValueChanged, true);
            app.Button_177.Tooltip = {'4 ramas (semaforizada) con carril de giro no protegido'};
            app.Button_177.Icon = 'INTERSECCION SEMAFORO.png';
            app.Button_177.Text = '';
            app.Button_178 = uibutton(app.InterseccionesTab, 'state');
            app.Button_178.ValueChangedFcn = createCallbackFcn(app, @Button_178ValueChanged, true);
            app.Button_178.Tooltip = {'Cruce de ferrocarril - pasivo (solo señalización vertical)'};
            app.Button_178.Icon = 'INTERSEC PASSIVE.png';
            app.Button_178.Text = '';
            app.Button_179 = uibutton(app.InterseccionesTab, 'state');
            app.Button_179.ValueChangedFcn = createCallbackFcn(app, @Button_179ValueChanged, true);
            app.Button_179.Tooltip = {'3 ramas (no semaforizada) con carril de giro no protegido'};
            app.Button_179.Icon = 'INTERSE.png';
            app.Button_179.Text = '';
            app.Button_180 = uibutton(app.InterseccionesTab, 'state');
            app.Button_180.ValueChangedFcn = createCallbackFcn(app, @Button_180ValueChanged, true);
            app.Button_180.Tooltip = {'4 ramas (semaforizada) con carril de giro protegido'};
            app.Button_180.Icon = 'INTERSECCION DE 4.png';
            app.Button_180.Text = '';
            app.Button_181 = uibutton(app.InterseccionesTab, 'state');
            app.Button_181.ValueChangedFcn = createCallbackFcn(app, @Button_181ValueChanged, true);
            app.Button_181.Tooltip = {'3 ramas (semaforizada) con carril de giro no protegido'};
            app.Button_181.Icon = 'INTERSECCION DE 3.png';
            app.Button_181.Text = '';
            app.Button_182 = uibutton(app.InterseccionesTab, 'state');
            app.Button_182.ValueChangedFcn = createCallbackFcn(app, @Button_182ValueChanged, true);
            app.Button_182.Tooltip = {'3 ramas (no semaforizada) con carril de giro protegido'};
            app.Button_182.Icon = 'INTERSECCION DE 3 BRAZOS sin semaforo.png';
            app.Button_182.Text = '';
            app.Button_183 = uibutton(app.InterseccionesTab, 'state');
            app.Button_183.ValueChangedFcn = createCallbackFcn(app, @Button_183ValueChanged, true);
            app.Button_183.Tooltip = {'3 ramas (semaforizada) con carril de giro protegido'};
            app.Button_183.Icon = 'INTERSECCION DE 3 BRAZOS con semaforo.png';
            app.Button_183.Text = '';
            app.Button_184 = uibutton(app.InterseccionesTab, 'state');
            app.Button_184.ValueChangedFcn = createCallbackFcn(app, @Button_184ValueChanged, true);
            app.Button_184.Tooltip = {'Cruce de ferrocarril - activo (semáforo intermitente / pluma)'};
            app.Button_184.Icon = 'INTERSEC ACTIVE.png';
            app.Button_184.Text = '';
            app.Button_185 = uibutton(app.InterseccionesTab, 'state');
            app.Button_185.ValueChangedFcn = createCallbackFcn(app, @Button_185ValueChanged, true);
            app.Button_185.Tooltip = {'Redondel'};
            app.Button_185.Icon = 'GLORIETA.png';
            app.Button_185.Text = '';
            app.Button_189 = uibutton(app.InterseccionesTab, 'state');
            app.Button_189.ValueChangedFcn = createCallbackFcn(app, @Button_189ValueChanged, true);
            app.Button_189.Tooltip = {'≥ 15,000 vehículos'};
            app.Button_189.Icon = '15K vehicles.png';
            app.Button_189.Text = '';
            app.Button_198 = uibutton(app.InterseccionesTab, 'state');
            app.Button_198.ValueChangedFcn = createCallbackFcn(app, @Button_198ValueChanged, true);
            app.Button_198.Tooltip = {'10,000 a 15,000 vehículos'};
            app.Button_198.Icon = '10-15k vehicles.png';
            app.Button_198.Text = '';
            app.Button_207 = uibutton(app.InterseccionesTab, 'state');
            app.Button_207.ValueChangedFcn = createCallbackFcn(app, @Button_207ValueChanged, true);
            app.Button_207.Tooltip = {'Carril de incorporación'};
            app.Button_207.Icon = 'MARGE LANE.png';
            app.Button_207.Text = '';
            app.Button_208 = uibutton(app.InterseccionesTab, 'state');
            app.Button_208.ValueChangedFcn = createCallbackFcn(app, @Button_208ValueChanged, true);
            app.Button_208.Tooltip = {'1 a 100 vehículos'};
            app.Button_208.Icon = '100 VEHICLES.png';
            app.Button_208.Text = '';
            app.Button_209 = uibutton(app.InterseccionesTab, 'state');
            app.Button_209.ValueChangedFcn = createCallbackFcn(app, @Button_209ValueChanged, true);
            app.Button_209.Tooltip = {'Ninguno'};
            app.Button_209.Icon = 'Intersecting road volume NONE.png';
            app.Button_209.Text = '';
            app.Button_210 = uibutton(app.InterseccionesTab, 'state');
            app.Button_210.ValueChangedFcn = createCallbackFcn(app, @Button_210ValueChanged, true);
            app.Button_210.Tooltip = {'5,000 a 10,000 vehículos'};
            app.Button_210.Icon = '5-10k vehicles.png';
            app.Button_210.Text = '';
            app.Button_211 = uibutton(app.InterseccionesTab, 'state');
            app.Button_211.ValueChangedFcn = createCallbackFcn(app, @Button_211ValueChanged, true);
            app.Button_211.Tooltip = {'1,000 a 5,000 vehículos'};
            app.Button_211.Icon = '1-5K.png';
            app.Button_211.Text = '';
            app.Button_212 = uibutton(app.InterseccionesTab, 'state');
            app.Button_212.ValueChangedFcn = createCallbackFcn(app, @Button_212ValueChanged, true);
            app.Button_212.Tooltip = {'100 a 1,000 vehículos'};
            app.Button_212.Icon = '100-1K vehicles.png';
            app.Button_212.Text = '';
            app.Button_213 = uibutton(app.InterseccionesTab, 'state');
            app.Button_213.ValueChangedFcn = createCallbackFcn(app, @Button_213ValueChanged, true);
            app.Button_213.Tooltip = {'Deficiente'};
            app.Button_213.Icon = 'Intersection design is poor INT.png';
            app.Button_213.Text = '';
            app.Button_214 = uibutton(app.InterseccionesTab, 'state');
            app.Button_214.ValueChangedFcn = createCallbackFcn(app, @Button_214ValueChanged, true);
            app.Button_214.Tooltip = {'Adecuada'};
            app.Button_214.Icon = 'Intersection design is adequate INT.png';
            app.Button_214.Text = '';
            app.Button_215 = uibutton(app.InterseccionesTab, 'state');
            app.Button_215.ValueChangedFcn = createCallbackFcn(app, @Button_215ValueChanged, true);
            app.Button_215.Tooltip = {'No aplica'};
            app.Button_215.Icon = 'Intersecting road volume NOT APPLICABLE.png';
            app.Button_215.Text = '';
            app.Button_216 = uibutton(app.InterseccionesTab, 'state');
            app.Button_216.ValueChangedFcn = createCallbackFcn(app, @Button_216ValueChanged, true);
            app.Button_216.Tooltip = {'Ninguno'};
            app.Button_216.Icon = 'Property access points NONE.png';
            app.Button_216.Text = '';
            app.TransitableButton = uibutton(app.InterseccionesTab, 'push');
            app.TransitableButton.ButtonPushedFcn = createCallbackFcn(app, @TransitableButtonPushed, true);
            app.TransitableButton.Text = 'Transitable';
            app.NoTransitableButton = uibutton(app.InterseccionesTab, 'push');
            app.NoTransitableButton.ButtonPushedFcn = createCallbackFcn(app, @NoTransitableButtonPushed, true);
            app.NoTransitableButton.Text = 'No Transitable';
            app.TransitabilidadmedianaLabel = uilabel(app.InterseccionesTab);
            app.TransitabilidadmedianaLabel.Text = 'Transitabilidad mediana';
            app.FlujoTab_3 = uitab(app.TabGroup);
            app.FlujoTab_3.Title = 'Flujo';
            app.CanalizacindeinterseccionesLabel_4 = uilabel(app.FlujoTab_3);
            app.CanalizacindeinterseccionesLabel_4.Text = {'Flujo peatonal en hora pico a lo largo de la carretera '; '(lado del conductor)'};
            app.motocicletasLabel = uilabel(app.FlujoTab_3);
            app.motocicletasLabel.Text = '% motocicletas';
            app.FlujopeatonalenhorapicoquecruzalacarreteraLabel = uilabel(app.FlujoTab_3);
            app.FlujopeatonalenhorapicoquecruzalacarreteraLabel.Text = {'Flujo peatonal en hora pico que '; 'cruza la carretera'};
            app.Button_261 = uibutton(app.FlujoTab_3, 'state');
            app.Button_261.ValueChangedFcn = createCallbackFcn(app, @Button_261ValueChanged, true);
            app.Button_261.Tooltip = {'21 % - 40 %'};
            app.Button_261.Text = '21-40';
            app.Button_262 = uibutton(app.FlujoTab_3, 'state');
            app.Button_262.ValueChangedFcn = createCallbackFcn(app, @Button_262ValueChanged, true);
            app.Button_262.Tooltip = {'41 % - 60 %'};
            app.Button_262.Text = '41-60';
            app.Button_263 = uibutton(app.FlujoTab_3, 'state');
            app.Button_263.ValueChangedFcn = createCallbackFcn(app, @Button_263ValueChanged, true);
            app.Button_263.Tooltip = {'6 % - 10 %'};
            app.Button_263.Text = '6-10';
            app.Button_264 = uibutton(app.FlujoTab_3, 'state');
            app.Button_264.ValueChangedFcn = createCallbackFcn(app, @Button_264ValueChanged, true);
            app.Button_264.Tooltip = {'11 % - 20 %'};
            app.Button_264.Text = '11-20';
            app.Button_265 = uibutton(app.FlujoTab_3, 'state');
            app.Button_265.ValueChangedFcn = createCallbackFcn(app, @Button_265ValueChanged, true);
            app.Button_265.Tooltip = {'0'};
            app.Button_265.Icon = '0i (1).png';
            app.Button_265.Text = '';
            app.Button_266 = uibutton(app.FlujoTab_3, 'state');
            app.Button_266.ValueChangedFcn = createCallbackFcn(app, @Button_266ValueChanged, true);
            app.Button_266.Tooltip = {'1 a 5'};
            app.Button_266.Icon = '1-5i.png';
            app.Button_266.Text = '';
            app.Button_267 = uibutton(app.FlujoTab_3, 'state');
            app.Button_267.ValueChangedFcn = createCallbackFcn(app, @Button_267ValueChanged, true);
            app.Button_267.Tooltip = {'6 a 25'};
            app.Button_267.Icon = '6-25.png';
            app.Button_267.Text = '';
            app.NAButton = uibutton(app.FlujoTab_3, 'state');
            app.NAButton.ValueChangedFcn = createCallbackFcn(app, @NAButtonValueChanged, true);
            app.NAButton.Tooltip = {'No registrado'};
            app.NAButton.Text = 'N/A';
            app.Button_270 = uibutton(app.FlujoTab_3, 'state');
            app.Button_270.ValueChangedFcn = createCallbackFcn(app, @Button_270ValueChanged, true);
            app.Button_270.Tooltip = {'0 %'};
            app.Button_270.Text = '0';
            app.Button_271 = uibutton(app.FlujoTab_3, 'state');
            app.Button_271.ValueChangedFcn = createCallbackFcn(app, @Button_271ValueChanged, true);
            app.Button_271.Tooltip = {'1 % - 5 %'};
            app.Button_271.Text = '1-5';
            app.Button_275 = uibutton(app.FlujoTab_3, 'state');
            app.Button_275.ValueChangedFcn = createCallbackFcn(app, @Button_275ValueChanged, true);
            app.Button_275.Tooltip = {'61 % - 80 %'};
            app.Button_275.Text = '61-80';
            app.Button_276 = uibutton(app.FlujoTab_3, 'state');
            app.Button_276.ValueChangedFcn = createCallbackFcn(app, @Button_276ValueChanged, true);
            app.Button_276.Tooltip = {'81 % - 99 %'};
            app.Button_276.Icon = '100%.png';
            app.Button_276.Text = '81-99';
            app.Button_277 = uibutton(app.FlujoTab_3, 'state');
            app.Button_277.ValueChangedFcn = createCallbackFcn(app, @Button_277ValueChanged, true);
            app.Button_277.Tooltip = {'26 a 50'};
            app.Button_277.Icon = '26-50.png';
            app.Button_277.Text = '';
            app.Button_278 = uibutton(app.FlujoTab_3, 'state');
            app.Button_278.ValueChangedFcn = createCallbackFcn(app, @Button_278ValueChanged, true);
            app.Button_278.Tooltip = {'51 a 100'};
            app.Button_278.Icon = '51-100.png';
            app.Button_278.Text = '';
            app.Button_279 = uibutton(app.FlujoTab_3, 'state');
            app.Button_279.ValueChangedFcn = createCallbackFcn(app, @Button_279ValueChanged, true);
            app.Button_279.Tooltip = {'101 a 200'};
            app.Button_279.Icon = '101-200.png';
            app.Button_279.Text = '';
            app.Button_280 = uibutton(app.FlujoTab_3, 'state');
            app.Button_280.ValueChangedFcn = createCallbackFcn(app, @Button_280ValueChanged, true);
            app.Button_280.Tooltip = {'201 a 300'};
            app.Button_280.Icon = '201-300.png';
            app.Button_280.Text = '';
            app.Button_281 = uibutton(app.FlujoTab_3, 'state');
            app.Button_281.ValueChangedFcn = createCallbackFcn(app, @Button_281ValueChanged, true);
            app.Button_281.Tooltip = {'301 a 400'};
            app.Button_281.Icon = '301-400.png';
            app.Button_281.Text = '';
            app.Button_282 = uibutton(app.FlujoTab_3, 'state');
            app.Button_282.ValueChangedFcn = createCallbackFcn(app, @Button_282ValueChanged, true);
            app.Button_282.Tooltip = {'401 a 500'};
            app.Button_282.Icon = '401-500.png';
            app.Button_282.Text = '';
            app.Button_283 = uibutton(app.FlujoTab_3, 'state');
            app.Button_283.ValueChangedFcn = createCallbackFcn(app, @Button_283ValueChanged, true);
            app.Button_283.Tooltip = {'501 a 900'};
            app.Button_283.Icon = '501-900.png';
            app.Button_283.Text = '';
            app.Button_284 = uibutton(app.FlujoTab_3, 'state');
            app.Button_284.ValueChangedFcn = createCallbackFcn(app, @Button_284ValueChanged, true);
            app.Button_284.Tooltip = {'900+'};
            app.Button_284.Icon = '900+.png';
            app.Button_284.Text = '';
            app.CanalizacindeinterseccionesLabel_5 = uilabel(app.FlujoTab_3);
            app.CanalizacindeinterseccionesLabel_5.Text = {'Flujo peatonal en hora pico a lo largo de la carretera '; '(lado del copiloto)'};
            app.CanalizacindeinterseccionesLabel_6 = uilabel(app.FlujoTab_3);
            app.CanalizacindeinterseccionesLabel_6.Text = 'Flujo de bicicletas en hora pico';
            app.Button_572 = uibutton(app.FlujoTab_3, 'state');
            app.Button_572.ValueChangedFcn = createCallbackFcn(app, @Button_572ValueChanged, true);
            app.Button_572.Tooltip = {'0'};
            app.Button_572.Icon = '0i (1).png';
            app.Button_572.Text = '';
            app.Button_573 = uibutton(app.FlujoTab_3, 'state');
            app.Button_573.ValueChangedFcn = createCallbackFcn(app, @Button_573ValueChanged, true);
            app.Button_573.Tooltip = {'1 a 5'};
            app.Button_573.Icon = '1-5i (1).png';
            app.Button_573.Text = '';
            app.Button_574 = uibutton(app.FlujoTab_3, 'state');
            app.Button_574.ValueChangedFcn = createCallbackFcn(app, @Button_574ValueChanged, true);
            app.Button_574.Tooltip = {'6 a 25'};
            app.Button_574.Icon = '6-25.png';
            app.Button_574.Text = '';
            app.Button_575 = uibutton(app.FlujoTab_3, 'state');
            app.Button_575.ValueChangedFcn = createCallbackFcn(app, @Button_575ValueChanged, true);
            app.Button_575.Tooltip = {'26 a 50'};
            app.Button_575.Icon = '26-50.png';
            app.Button_575.Text = '';
            app.Button_576 = uibutton(app.FlujoTab_3, 'state');
            app.Button_576.ValueChangedFcn = createCallbackFcn(app, @Button_576ValueChanged, true);
            app.Button_576.Tooltip = {'51 a 100'};
            app.Button_576.Icon = '51-100.png';
            app.Button_576.Text = '';
            app.Button_577 = uibutton(app.FlujoTab_3, 'state');
            app.Button_577.ValueChangedFcn = createCallbackFcn(app, @Button_577ValueChanged, true);
            app.Button_577.Tooltip = {'101 a 200'};
            app.Button_577.Icon = '101-200.png';
            app.Button_577.Text = '';
            app.Button_578 = uibutton(app.FlujoTab_3, 'state');
            app.Button_578.ValueChangedFcn = createCallbackFcn(app, @Button_578ValueChanged, true);
            app.Button_578.Tooltip = {'201 a 300'};
            app.Button_578.Icon = '201-300.png';
            app.Button_578.Text = '';
            app.Button_579 = uibutton(app.FlujoTab_3, 'state');
            app.Button_579.ValueChangedFcn = createCallbackFcn(app, @Button_579ValueChanged, true);
            app.Button_579.Tooltip = {'301 a 400'};
            app.Button_579.Icon = '301-400.png';
            app.Button_579.Text = '';
            app.Button_580 = uibutton(app.FlujoTab_3, 'state');
            app.Button_580.ValueChangedFcn = createCallbackFcn(app, @Button_580ValueChanged, true);
            app.Button_580.Tooltip = {'401 a 500'};
            app.Button_580.Icon = '401-500.png';
            app.Button_580.Text = '';
            app.Button_581 = uibutton(app.FlujoTab_3, 'state');
            app.Button_581.ValueChangedFcn = createCallbackFcn(app, @Button_581ValueChanged, true);
            app.Button_581.Tooltip = {'501 a 900'};
            app.Button_581.Icon = '501-900.png';
            app.Button_581.Text = '';
            app.Button_582 = uibutton(app.FlujoTab_3, 'state');
            app.Button_582.ValueChangedFcn = createCallbackFcn(app, @Button_582ValueChanged, true);
            app.Button_582.Tooltip = {'900+'};
            app.Button_582.Icon = '900+.png';
            app.Button_582.Text = '';
            app.Button_583 = uibutton(app.FlujoTab_3, 'state');
            app.Button_583.ValueChangedFcn = createCallbackFcn(app, @Button_583ValueChanged, true);
            app.Button_583.Tooltip = {'0'};
            app.Button_583.Icon = '0i (1).png';
            app.Button_583.Text = '';
            app.Button_584 = uibutton(app.FlujoTab_3, 'state');
            app.Button_584.ValueChangedFcn = createCallbackFcn(app, @Button_584ValueChanged, true);
            app.Button_584.Tooltip = {'1 a 5'};
            app.Button_584.Icon = '1-5.png';
            app.Button_584.Text = '';
            app.Button_585 = uibutton(app.FlujoTab_3, 'state');
            app.Button_585.ValueChangedFcn = createCallbackFcn(app, @Button_585ValueChanged, true);
            app.Button_585.Tooltip = {'6 a 25'};
            app.Button_585.Icon = '6-25.png';
            app.Button_585.Text = '';
            app.Button_586 = uibutton(app.FlujoTab_3, 'state');
            app.Button_586.ValueChangedFcn = createCallbackFcn(app, @Button_586ValueChanged, true);
            app.Button_586.Tooltip = {'26 a 50'};
            app.Button_586.Icon = '26-50.png';
            app.Button_586.Text = '';
            app.Button_587 = uibutton(app.FlujoTab_3, 'state');
            app.Button_587.ValueChangedFcn = createCallbackFcn(app, @Button_587ValueChanged, true);
            app.Button_587.Tooltip = {'51 a 100'};
            app.Button_587.Icon = '51-100.png';
            app.Button_587.Text = '';
            app.Button_588 = uibutton(app.FlujoTab_3, 'state');
            app.Button_588.ValueChangedFcn = createCallbackFcn(app, @Button_588ValueChanged, true);
            app.Button_588.Tooltip = {'101 a 200'};
            app.Button_588.Icon = '101-200.png';
            app.Button_588.Text = '';
            app.Button_589 = uibutton(app.FlujoTab_3, 'state');
            app.Button_589.ValueChangedFcn = createCallbackFcn(app, @Button_589ValueChanged, true);
            app.Button_589.Tooltip = {'201 a 300'};
            app.Button_589.Icon = '201-300.png';
            app.Button_589.Text = '';
            app.Button_590 = uibutton(app.FlujoTab_3, 'state');
            app.Button_590.ValueChangedFcn = createCallbackFcn(app, @Button_590ValueChanged, true);
            app.Button_590.Tooltip = {'301 a 400'};
            app.Button_590.Icon = '301-400.png';
            app.Button_590.Text = '';
            app.Button_591 = uibutton(app.FlujoTab_3, 'state');
            app.Button_591.ValueChangedFcn = createCallbackFcn(app, @Button_591ValueChanged, true);
            app.Button_591.Tooltip = {'301 a 400'};
            app.Button_591.Icon = '401-500.png';
            app.Button_591.Text = '';
            app.Button_592 = uibutton(app.FlujoTab_3, 'state');
            app.Button_592.ValueChangedFcn = createCallbackFcn(app, @Button_592ValueChanged, true);
            app.Button_592.Tooltip = {'501 a 900'};
            app.Button_592.Icon = '501-900.png';
            app.Button_592.Text = '';
            app.Button_593 = uibutton(app.FlujoTab_3, 'state');
            app.Button_593.ValueChangedFcn = createCallbackFcn(app, @Button_593ValueChanged, true);
            app.Button_593.Tooltip = {'900+'};
            app.Button_593.Icon = '900+.png';
            app.Button_593.Text = '';
            app.Button_594 = uibutton(app.FlujoTab_3, 'state');
            app.Button_594.ValueChangedFcn = createCallbackFcn(app, @Button_594ValueChanged, true);
            app.Button_594.Tooltip = {'0'};
            app.Button_594.Icon = '0i (1).png';
            app.Button_594.Text = '';
            app.Button_595 = uibutton(app.FlujoTab_3, 'state');
            app.Button_595.ValueChangedFcn = createCallbackFcn(app, @Button_595ValueChanged, true);
            app.Button_595.Tooltip = {'1 a 5'};
            app.Button_595.Icon = '1-5.png';
            app.Button_595.Text = '';
            app.Button_596 = uibutton(app.FlujoTab_3, 'state');
            app.Button_596.ValueChangedFcn = createCallbackFcn(app, @Button_596ValueChanged, true);
            app.Button_596.Tooltip = {'6 a 25'};
            app.Button_596.Icon = '6-25.png';
            app.Button_596.Text = '';
            app.Button_597 = uibutton(app.FlujoTab_3, 'state');
            app.Button_597.ValueChangedFcn = createCallbackFcn(app, @Button_597ValueChanged, true);
            app.Button_597.Tooltip = {'6 a 25'};
            app.Button_597.Icon = '26-50.png';
            app.Button_597.Text = '';
            app.Button_598 = uibutton(app.FlujoTab_3, 'state');
            app.Button_598.ValueChangedFcn = createCallbackFcn(app, @Button_598ValueChanged, true);
            app.Button_598.Tooltip = {'51 a 100'};
            app.Button_598.Icon = '51-100.png';
            app.Button_598.Text = '';
            app.Button_599 = uibutton(app.FlujoTab_3, 'state');
            app.Button_599.ValueChangedFcn = createCallbackFcn(app, @Button_599ValueChanged, true);
            app.Button_599.Tooltip = {'101 a 200'};
            app.Button_599.Icon = '101-200.png';
            app.Button_599.Text = '';
            app.Button_600 = uibutton(app.FlujoTab_3, 'state');
            app.Button_600.ValueChangedFcn = createCallbackFcn(app, @Button_600ValueChanged, true);
            app.Button_600.Tooltip = {'201 a 300'};
            app.Button_600.Icon = '201-300.png';
            app.Button_600.Text = '';
            app.Button_601 = uibutton(app.FlujoTab_3, 'state');
            app.Button_601.ValueChangedFcn = createCallbackFcn(app, @Button_601ValueChanged, true);
            app.Button_601.Tooltip = {'301 a 400'};
            app.Button_601.Icon = '301-400.png';
            app.Button_601.Text = '';
            app.Button_602 = uibutton(app.FlujoTab_3, 'state');
            app.Button_602.ValueChangedFcn = createCallbackFcn(app, @Button_602ValueChanged, true);
            app.Button_602.Tooltip = {'401 a 500'};
            app.Button_602.Icon = '401-500.png';
            app.Button_602.Text = '';
            app.Button_603 = uibutton(app.FlujoTab_3, 'state');
            app.Button_603.ValueChangedFcn = createCallbackFcn(app, @Button_603ValueChanged, true);
            app.Button_603.Tooltip = {'501 a 900'};
            app.Button_603.Icon = '501-900.png';
            app.Button_603.Text = '';
            app.Button_604 = uibutton(app.FlujoTab_3, 'state');
            app.Button_604.ValueChangedFcn = createCallbackFcn(app, @Button_604ValueChanged, true);
            app.Button_604.Tooltip = {'900+'};
            app.Button_604.Icon = '900+.png';
            app.Button_604.Text = '';
            app.FlujovehicularTDPAEditFieldLabel = uilabel(app.FlujoTab_3);
            app.FlujovehicularTDPAEditFieldLabel.Text = 'Flujo vehicular (TDPA)';
            app.FlujovehicularTDPAEditField = uieditfield(app.FlujoTab_3, 'numeric');
            app.FlujovehicularTDPAEditField.ValueChangedFcn = createCallbackFcn(app, @FlujovehicularTDPAEditFieldValueChanged, true);
            app.Button_712 = uibutton(app.FlujoTab_3, 'state');
            app.Button_712.ValueChangedFcn = createCallbackFcn(app, @Button_712ValueChanged2, true);
            app.Button_712.Tooltip = {'100 %'};
            app.Button_712.Icon = '100%.png';
            app.Button_712.Text = '100';
            app.UsosTab = uitab(app.TabGroup);
            app.UsosTab.Title = 'Usos';
            app.UsodesueloladodelconductorLabel = uilabel(app.UsosTab);
            app.UsodesueloladodelconductorLabel.Text = 'Uso de suelo (lado del conductor)';
            app.Button_318 = uibutton(app.UsosTab, 'state');
            app.Button_318.ValueChangedFcn = createCallbackFcn(app, @Button_318ValueChanged, true);
            app.Button_318.Tooltip = {'Comercial'};
            app.Button_318.Icon = 'Comercial Land.png';
            app.Button_318.Text = '';
            app.Button_319 = uibutton(app.UsosTab, 'state');
            app.Button_319.ValueChangedFcn = createCallbackFcn(app, @Button_319ValueChanged, true);
            app.Button_319.Tooltip = {'Industrial y manufactura'};
            app.Button_319.Icon = 'Industrial and manufacturing.png';
            app.Button_319.Text = '';
            app.Button_320 = uibutton(app.UsosTab, 'state');
            app.Button_320.ValueChangedFcn = createCallbackFcn(app, @Button_320ValueChanged, true);
            app.Button_320.Tooltip = {'Residencial '};
            app.Button_320.Icon = 'Residential.png';
            app.Button_320.Text = '';
            app.Button_321 = uibutton(app.UsosTab, 'state');
            app.Button_321.ValueChangedFcn = createCallbackFcn(app, @Button_321ValueChanged, true);
            app.Button_321.Tooltip = {'Agricultura y ganadería'};
            app.Button_321.Icon = 'Farming and agricultural.png';
            app.Button_321.Text = '';
            app.InfraestructuradecrucepeatonaleneltramoanalizadoLabel = uilabel(app.UsosTab);
            app.InfraestructuradecrucepeatonaleneltramoanalizadoLabel.Text = {'Infraestructura de cruce peatonal en '; 'el tramo analizado'};
            app.Button_380 = uibutton(app.UsosTab, 'state');
            app.Button_380.ValueChangedFcn = createCallbackFcn(app, @Button_380ValueChanged, true);
            app.Button_380.Tooltip = {'Solo refugio'};
            app.Button_380.Icon = 'Refuge only.png';
            app.Button_380.Text = '';
            app.Button_381 = uibutton(app.UsosTab, 'state');
            app.Button_381.ValueChangedFcn = createCallbackFcn(app, @Button_381ValueChanged, true);
            app.Button_381.Tooltip = {'No semaforizada, señalizada con pintura y sin refugio'};
            app.Button_381.Icon = 'Unsignalised marked crossing without refuge.png';
            app.Button_381.Text = '';
            app.CalidaddecrucepeatonalLabel = uilabel(app.UsosTab);
            app.CalidaddecrucepeatonalLabel.Text = 'Calidad de cruce peatonal';
            app.Button_329 = uibutton(app.UsosTab, 'state');
            app.Button_329.ValueChangedFcn = createCallbackFcn(app, @Button_329ValueChanged, true);
            app.Button_329.Tooltip = {'Pobre'};
            app.Button_329.Icon = 'Rapid or unexpected speed POOR.png';
            app.Button_329.Text = '';
            app.VallaspeatonalesLabel = uilabel(app.UsosTab);
            app.VallaspeatonalesLabel.Text = 'Vallas peatonales';
            app.Button_332 = uibutton(app.UsosTab, 'state');
            app.Button_332.ValueChangedFcn = createCallbackFcn(app, @Button_332ValueChanged, true);
            app.Button_332.Tooltip = {'Presente'};
            app.Button_332.Icon = 'Fencing present sufficient to prevent pedestrian crossing flow.png';
            app.Button_332.Text = '';
            app.Button_333 = uibutton(app.UsosTab, 'state');
            app.Button_333.ValueChangedFcn = createCallbackFcn(app, @Button_333ValueChanged, true);
            app.Button_333.Tooltip = {'Ausente'};
            app.Button_333.Icon = 'Pedestrian fencing NOT PRESENT.png';
            app.Button_333.Text = '';
            app.AceraladodelconductorLabel = uilabel(app.UsosTab);
            app.AceraladodelconductorLabel.Text = 'Acera (lado del conductor)';
            app.Button_335 = uibutton(app.UsosTab, 'state');
            app.Button_335.ValueChangedFcn = createCallbackFcn(app, @Button_335ValueChanged, true);
            app.Button_335.Tooltip = {'Ninguno'};
            app.Button_335.Icon = 'No discernible sidewalk is provided.png';
            app.Button_335.Text = '';
            app.Button_336 = uibutton(app.UsosTab, 'state');
            app.Button_336.ValueChangedFcn = createCallbackFcn(app, @Button_336ValueChanged, true);
            app.Button_336.Tooltip = {'Separación no física 0 m < 1,0 m'};
            app.Button_336.Icon = 'separacion no fisica del transito 0-1.png';
            app.Button_336.Text = '';
            app.Button_337 = uibutton(app.UsosTab, 'state');
            app.Button_337.ValueChangedFcn = createCallbackFcn(app, @Button_337ValueChanged, true);
            app.Button_337.Tooltip = {'Separación no física 1,0 m a 3,0 m'};
            app.Button_337.Icon = 'Non-physical separation 1-3.png';
            app.Button_337.Text = '';
            app.Button_340 = uibutton(app.UsosTab, 'state');
            app.Button_340.ValueChangedFcn = createCallbackFcn(app, @Button_340ValueChanged, true);
            app.Button_340.Tooltip = {'Áreas no desarrolladas'};
            app.Button_340.Icon = 'Land use - passenger-side LAND.png';
            app.Button_340.Text = '';
            app.Button_356 = uibutton(app.UsosTab, 'state');
            app.Button_356.ValueChangedFcn = createCallbackFcn(app, @Button_356ValueChanged, true);
            app.Button_356.Tooltip = {'No aplica'};
            app.Button_356.Icon = 'Pedestrian crossing quality NOT APPLICABLE.png';
            app.Button_356.Text = '';
            app.Button_357 = uibutton(app.UsosTab, 'state');
            app.Button_357.ValueChangedFcn = createCallbackFcn(app, @Button_357ValueChanged, true);
            app.Button_357.Tooltip = {'Adecuado'};
            app.Button_357.Icon = 'Signing, marking  ADEQUATE.png';
            app.Button_357.Text = '';
            app.InfraestructuraparamotocicletasLabel = uilabel(app.UsosTab);
            app.InfraestructuraparamotocicletasLabel.Text = 'Infraestructura para motocicletas';
            app.Button_413 = uibutton(app.UsosTab, 'state');
            app.Button_413.ValueChangedFcn = createCallbackFcn(app, @Button_413ValueChanged, true);
            app.Button_413.Tooltip = {'Ninguno'};
            app.Button_413.Icon = 'Facilities for motorised two wheelers NONE.png';
            app.Button_413.Text = '';
            app.Button_414 = uibutton(app.UsosTab, 'state');
            app.Button_414.ValueChangedFcn = createCallbackFcn(app, @Button_414ValueChanged, true);
            app.Button_414.Tooltip = {'Motovía compartida en la carretera'};
            app.Button_414.Icon = 'Inclusive motorcycle lane on roadway.png';
            app.Button_414.Text = '';
            app.UsodesueloladodelcopilotoLabel = uilabel(app.UsosTab);
            app.UsodesueloladodelcopilotoLabel.Text = 'Uso de suelo (lado del copiloto)';
            app.TipodereaLabel = uilabel(app.UsosTab);
            app.TipodereaLabel.Text = 'Tipo de área';
            app.Button_611 = uibutton(app.UsosTab, 'state');
            app.Button_611.ValueChangedFcn = createCallbackFcn(app, @Button_611ValueChanged, true);
            app.Button_611.Tooltip = {'Urbano/ población pequeña o pueblo'};
            app.Button_611.Icon = 'URBAN RURAL AREA.png';
            app.Button_611.Text = '';
            app.Button_612 = uibutton(app.UsosTab, 'state');
            app.Button_612.ValueChangedFcn = createCallbackFcn(app, @Button_612ValueChanged, true);
            app.Button_612.Tooltip = {'Rural/área abierta'};
            app.Button_612.Icon = 'Rural  open area AREA.png';
            app.Button_612.Text = '';
            app.Button_382 = uibutton(app.UsosTab, 'state');
            app.Button_382.ValueChangedFcn = createCallbackFcn(app, @Button_382ValueChanged, true);
            app.Button_382.Tooltip = {'No semaforizada, señalizada con pintura y con refugio'};
            app.Button_382.Icon = 'Unsignalised marked crossing with refuge.png';
            app.Button_382.Text = '';
            app.Button_383 = uibutton(app.UsosTab, 'state');
            app.Button_383.ValueChangedFcn = createCallbackFcn(app, @Button_383ValueChanged, true);
            app.Button_383.Tooltip = {'Semaforizada con refugio'};
            app.Button_383.Icon = 'Signalised with refuge.png';
            app.Button_383.Text = '';
            app.Button_384 = uibutton(app.UsosTab, 'state');
            app.Button_384.ValueChangedFcn = createCallbackFcn(app, @Button_384ValueChanged, true);
            app.Button_384.Tooltip = {'Infraestructura a desnivel'; ''};
            app.Button_384.Icon = 'Grade separated facility.png';
            app.Button_384.Text = '';
            app.Button_385 = uibutton(app.UsosTab, 'state');
            app.Button_385.ValueChangedFcn = createCallbackFcn(app, @Button_385ValueChanged, true);
            app.Button_385.Tooltip = {'Cruce demarcado elevado, sin semáforo, sin refugio'};
            app.Button_385.Icon = 'Unsignalised raised marked crossing without refuge.png';
            app.Button_385.Text = '';
            app.Button_386 = uibutton(app.UsosTab, 'state');
            app.Button_386.ValueChangedFcn = createCallbackFcn(app, @Button_386ValueChanged, true);
            app.Button_386.Tooltip = {'Sin infraestructura'};
            app.Button_386.Icon = 'Pedestrian crossing facilities - inspected road NO FACILITY.png';
            app.Button_386.Text = '';
            app.Button_387 = uibutton(app.UsosTab, 'state');
            app.Button_387.ValueChangedFcn = createCallbackFcn(app, @Button_387ValueChanged, true);
            app.Button_387.Tooltip = {'Cruce demarcado elevado, sin semáforo, con refugio'};
            app.Button_387.Icon = 'Unsignalised raised marked crossing with refuge.png';
            app.Button_387.Text = '';
            app.Button_388 = uibutton(app.UsosTab, 'state');
            app.Button_388.ValueChangedFcn = createCallbackFcn(app, @Button_388ValueChanged, true);
            app.Button_388.Tooltip = {'Cruce no demarcado elevado, con refugio'};
            app.Button_388.Icon = 'Raised unmarked crossing with refuge.png';
            app.Button_388.Text = '';
            app.Button_389 = uibutton(app.UsosTab, 'state');
            app.Button_389.ValueChangedFcn = createCallbackFcn(app, @Button_389ValueChanged, true);
            app.Button_389.Tooltip = {'Cruce no demarcado elevado, sin refugio'};
            app.Button_389.Icon = 'fig 1.png';
            app.Button_389.Text = '';
            app.Button_390 = uibutton(app.UsosTab, 'state');
            app.Button_390.ValueChangedFcn = createCallbackFcn(app, @Button_390ValueChanged, true);
            app.Button_390.Tooltip = {'Semaforizada sin refugio'};
            app.Button_390.Icon = 'Signalised without refuge.png';
            app.Button_390.Text = '';
            app.InfraestructuradecrucepeatonalenlainterseccinLabel = uilabel(app.UsosTab);
            app.InfraestructuradecrucepeatonalenlainterseccinLabel.Text = {'Infraestructura de cruce peatonal en '; 'la intersección'};
            app.Button_402 = uibutton(app.UsosTab, 'state');
            app.Button_402.ValueChangedFcn = createCallbackFcn(app, @Button_402ValueChanged, true);
            app.Button_402.Tooltip = {'Separación no física ≥ 3,0 m'};
            app.Button_402.Icon = 'Non-physical separation 3+.png';
            app.Button_402.Text = '';
            app.Button_403 = uibutton(app.UsosTab, 'state');
            app.Button_403.ValueChangedFcn = createCallbackFcn(app, @Button_403ValueChanged, true);
            app.Button_403.Tooltip = {'Barrera física'};
            app.Button_403.Icon = 'Physical barrier.png';
            app.Button_403.Text = '';
            app.Button_404 = uibutton(app.UsosTab, 'state');
            app.Button_404.ValueChangedFcn = createCallbackFcn(app, @Button_404ValueChanged, true);
            app.Button_404.Tooltip = {'Camino informal ≥ 1,0 m'};
            app.Button_404.Icon = 'Informal path 1+.png';
            app.Button_404.Text = '';
            app.Button_405 = uibutton(app.UsosTab, 'state');
            app.Button_405.ValueChangedFcn = createCallbackFcn(app, @Button_405ValueChanged, true);
            app.Button_405.Tooltip = {'Camino informal de 0 m a < 1,0 m'};
            app.Button_405.Icon = 'Informal path 0-1.png';
            app.Button_405.Text = '';
            app.AceraladodelcopilotoLabel = uilabel(app.UsosTab);
            app.AceraladodelcopilotoLabel.Text = 'Acera (lado del copiloto)';
            app.Button_415 = uibutton(app.UsosTab, 'state');
            app.Button_415.ValueChangedFcn = createCallbackFcn(app, @Button_415ValueChanged, true);
            app.Button_415.Tooltip = {'Motovía exclusiva de un sentido sin barrera'};
            app.Button_415.Icon = 'Exclusive one-way motorcycle path without barrier.png';
            app.Button_415.Text = '';
            app.Button_416 = uibutton(app.UsosTab, 'state');
            app.Button_416.ValueChangedFcn = createCallbackFcn(app, @Button_416ValueChanged, true);
            app.Button_416.Tooltip = {'Motovía exclusiva de un sentido con barrera'};
            app.Button_416.Icon = 'Exclusive one-way motorcycle path with barrier.png';
            app.Button_416.Text = '';
            app.Button_417 = uibutton(app.UsosTab, 'state');
            app.Button_417.ValueChangedFcn = createCallbackFcn(app, @Button_417ValueChanged, true);
            app.Button_417.Tooltip = {'Motovía exclusiva de dos sentidos sin barrera'};
            app.Button_417.Icon = 'Exclusive two-way motorcycle path without barrier.png';
            app.Button_417.Text = '';
            app.Button_418 = uibutton(app.UsosTab, 'state');
            app.Button_418.ValueChangedFcn = createCallbackFcn(app, @Button_418ValueChanged, true);
            app.Button_418.Tooltip = {'Motovía exclusiva de dos sentidos con barrera'};
            app.Button_418.Icon = 'Exclusive two-way motorcycle path with barrier.png';
            app.Button_418.Text = '';
            app.InfraestructuraparabicicletasLabel = uilabel(app.UsosTab);
            app.InfraestructuraparabicicletasLabel.Text = 'Infraestructura para bicicletas';
            app.Button_419 = uibutton(app.UsosTab, 'state');
            app.Button_419.ValueChangedFcn = createCallbackFcn(app, @Button_419ValueChanged, true);
            app.Button_419.Tooltip = {'Ninguno'};
            app.Button_419.Icon = 'Facilities for bicycles NONE.png';
            app.Button_419.Text = '';
            app.Button_420 = uibutton(app.UsosTab, 'state');
            app.Button_420.ValueChangedFcn = createCallbackFcn(app, @Button_420ValueChanged, true);
            app.Button_420.Tooltip = {'Ciclovía en la carretera'};
            app.Button_420.Icon = 'On-road lane.png';
            app.Button_420.Text = '';
            app.Button_421 = uibutton(app.UsosTab, 'state');
            app.Button_421.ValueChangedFcn = createCallbackFcn(app, @Button_421ValueChanged, true);
            app.Button_421.Tooltip = {'Ciclovía de uso compartido '};
            app.Button_421.Icon = 'Shared use path.png';
            app.Button_421.Text = '';
            app.Button_422 = uibutton(app.UsosTab, 'state');
            app.Button_422.ValueChangedFcn = createCallbackFcn(app, @Button_422ValueChanged, true);
            app.Button_422.Tooltip = {'Carril extra fuera de la calzada (≥  4,2 m)'};
            app.Button_422.Icon = 'WIDE.png';
            app.Button_422.Text = '';
            app.Button_423 = uibutton(app.UsosTab, 'state');
            app.Button_423.ValueChangedFcn = createCallbackFcn(app, @Button_423ValueChanged, true);
            app.Button_423.Tooltip = {'Vía compartida con señalización vertical'};
            app.Button_423.Icon = 'Signed shared roadway.png';
            app.Button_423.Text = '';
            app.Button_424 = uibutton(app.UsosTab, 'state');
            app.Button_424.ValueChangedFcn = createCallbackFcn(app, @Button_424ValueChanged, true);
            app.Button_424.Tooltip = {'Ciclovía fuera de la carretera con barrera'};
            app.Button_424.Icon = 'Off-road path with barrier.png';
            app.Button_424.Text = '';
            app.Button_425 = uibutton(app.UsosTab, 'state');
            app.Button_425.ValueChangedFcn = createCallbackFcn(app, @Button_425ValueChanged, true);
            app.Button_425.Tooltip = {'Educacional'};
            app.Button_425.Icon = 'LAND.png';
            app.Button_425.Text = '';
            app.AdvertenciadezonaescolarLabel = uilabel(app.UsosTab);
            app.AdvertenciadezonaescolarLabel.Text = 'Advertencia de zona escolar';
            app.Button_427 = uibutton(app.UsosTab, 'state');
            app.Button_427.ValueChangedFcn = createCallbackFcn(app, @Button_427ValueChanged, true);
            app.Button_427.Tooltip = {'Balizas intermitentes en zona escolar'};
            app.Button_427.Icon = 'School zone flashing beacons.png';
            app.Button_427.Text = '';
            app.Button_428 = uibutton(app.UsosTab, 'state');
            app.Button_428.ValueChangedFcn = createCallbackFcn(app, @Button_428ValueChanged, true);
            app.Button_428.Tooltip = {'Señalización estática o demarcación horizontal en zona escolar'};
            app.Button_428.Icon = 'School zone static signs or road markings.png';
            app.Button_428.Text = '';
            app.Button_429 = uibutton(app.UsosTab, 'state');
            app.Button_429.ValueChangedFcn = createCallbackFcn(app, @Button_429ValueChanged, true);
            app.Button_429.Tooltip = {'No aplica (no hay escuela en el lugar)'};
            app.Button_429.Icon = 'School zone warning NOT APPLICABLE.png';
            app.Button_429.Text = '';
            app.Button_430 = uibutton(app.UsosTab, 'state');
            app.Button_430.ValueChangedFcn = createCallbackFcn(app, @Button_430ValueChanged, true);
            app.Button_430.Tooltip = {'Sin señal de advertencia de zona escolar'};
            app.Button_430.Icon = 'No school zone warning.png';
            app.Button_430.Text = '';
            app.SupervisorenelcrucedezonaescolarLabel = uilabel(app.UsosTab);
            app.SupervisorenelcrucedezonaescolarLabel.Text = 'Supervisor en el cruce de zona escolar';
            app.Button_431 = uibutton(app.UsosTab, 'state');
            app.Button_431.ValueChangedFcn = createCallbackFcn(app, @Button_431ValueChanged, true);
            app.Button_431.Tooltip = {'Supervisor de cruce en zona escolar no presente'};
            app.Button_431.Icon = 'Crossing supervisor not present.png';
            app.Button_431.Text = '';
            app.Button_432 = uibutton(app.UsosTab, 'state');
            app.Button_432.ValueChangedFcn = createCallbackFcn(app, @Button_432ValueChanged, true);
            app.Button_432.Tooltip = {'Supervisor de cruce en zona escolar presente en horario de entrada y salida de la escuela'};
            app.Button_432.Icon = 'School zone crossing supervisor present at school start and finish times.png';
            app.Button_432.Text = '';
            app.Button_433 = uibutton(app.UsosTab, 'state');
            app.Button_433.ValueChangedFcn = createCallbackFcn(app, @Button_433ValueChanged, true);
            app.Button_433.Tooltip = {'No aplica (no hay escuela en el lugar)'};
            app.Button_433.Icon = 'School zone crossing supervisor NOT APPLICABLE.png';
            app.Button_433.Text = '';
            app.Button_687 = uibutton(app.UsosTab, 'state');
            app.Button_687.ValueChangedFcn = createCallbackFcn(app, @Button_687ValueChanged, true);
            app.Button_687.Tooltip = {'Solo refugio'};
            app.Button_687.Icon = 'Refuge only.png';
            app.Button_687.Text = '';
            app.Button_688 = uibutton(app.UsosTab, 'state');
            app.Button_688.ValueChangedFcn = createCallbackFcn(app, @Button_688ValueChanged, true);
            app.Button_688.Tooltip = {'No semaforizada, señalizada con pintura y sin refugio'};
            app.Button_688.Icon = 'Unsignalised marked crossing without refuge.png';
            app.Button_688.Text = '';
            app.Button_689 = uibutton(app.UsosTab, 'state');
            app.Button_689.ValueChangedFcn = createCallbackFcn(app, @Button_689ValueChanged, true);
            app.Button_689.Tooltip = {'No semaforizada, señalizada con pintura y con refugio'};
            app.Button_689.Icon = 'Unsignalised marked crossing with refuge.png';
            app.Button_689.Text = '';
            app.Button_690 = uibutton(app.UsosTab, 'state');
            app.Button_690.ValueChangedFcn = createCallbackFcn(app, @Button_690ValueChanged, true);
            app.Button_690.Tooltip = {'Semaforizada con refugio'};
            app.Button_690.Icon = 'Signalised with refuge.png';
            app.Button_690.Text = '';
            app.Button_691 = uibutton(app.UsosTab, 'state');
            app.Button_691.ValueChangedFcn = createCallbackFcn(app, @Button_691ValueChanged, true);
            app.Button_691.Tooltip = {'Infraestructura a desnivel'};
            app.Button_691.Icon = 'Grade separated facility.png';
            app.Button_691.Text = '';
            app.Button_692 = uibutton(app.UsosTab, 'state');
            app.Button_692.ValueChangedFcn = createCallbackFcn(app, @Button_692ValueChanged, true);
            app.Button_692.Tooltip = {'Cruce demarcado elevado, sin semáforo, sin refugio'};
            app.Button_692.Icon = 'Unsignalised raised marked crossing without refuge.png';
            app.Button_692.Text = '';
            app.Button_693 = uibutton(app.UsosTab, 'state');
            app.Button_693.ValueChangedFcn = createCallbackFcn(app, @Button_693ValueChanged, true);
            app.Button_693.Tooltip = {'Sin infraestructura'};
            app.Button_693.Icon = 'Pedestrian crossing facilities - inspected road NO FACILITY.png';
            app.Button_693.Text = '';
            app.Button_694 = uibutton(app.UsosTab, 'state');
            app.Button_694.ValueChangedFcn = createCallbackFcn(app, @Button_694ValueChanged, true);
            app.Button_694.Tooltip = {'Cruce demarcado elevado, sin semáforo, con refugio'};
            app.Button_694.Icon = 'Unsignalised raised marked crossing with refuge.png';
            app.Button_694.Text = '';
            app.Button_695 = uibutton(app.UsosTab, 'state');
            app.Button_695.ValueChangedFcn = createCallbackFcn(app, @Button_695ValueChanged, true);
            app.Button_695.Tooltip = {'Cruce no demarcado elevado, con refugio'};
            app.Button_695.Icon = 'Raised unmarked crossing with refuge.png';
            app.Button_695.Text = '';
            app.Button_696 = uibutton(app.UsosTab, 'state');
            app.Button_696.ValueChangedFcn = createCallbackFcn(app, @Button_696ValueChanged, true);
            app.Button_696.Tooltip = {'Cruce no demarcado elevado, sin refugio'};
            app.Button_696.Icon = 'fig 1.png';
            app.Button_696.Text = '';
            app.Button_697 = uibutton(app.UsosTab, 'state');
            app.Button_697.ValueChangedFcn = createCallbackFcn(app, @Button_697ValueChanged, true);
            app.Button_697.Tooltip = {'Semaforizada sin refugio'};
            app.Button_697.Icon = 'Signalised without refuge.png';
            app.Button_697.Text = '';
            app.Button_698 = uibutton(app.UsosTab, 'state');
            app.Button_698.ValueChangedFcn = createCallbackFcn(app, @Button_698ValueChanged, true);
            app.Button_698.Tooltip = {'Ninguno'};
            app.Button_698.Icon = 'No discernible sidewalk is provided.png';
            app.Button_698.Text = '';
            app.Button_699 = uibutton(app.UsosTab, 'state');
            app.Button_699.ValueChangedFcn = createCallbackFcn(app, @Button_699ValueChanged, true);
            app.Button_699.Tooltip = {'Separación no física 0 m < 1,0 m'};
            app.Button_699.Icon = 'separacion no fisica del transito 0-1.png';
            app.Button_699.Text = '';
            app.Button_700 = uibutton(app.UsosTab, 'state');
            app.Button_700.ValueChangedFcn = createCallbackFcn(app, @Button_700ValueChanged, true);
            app.Button_700.Tooltip = {'Separación no física 1,0 m a 3,0 m'};
            app.Button_700.Icon = 'Non-physical separation 1-3.png';
            app.Button_700.Text = '';
            app.Button_701 = uibutton(app.UsosTab, 'state');
            app.Button_701.ValueChangedFcn = createCallbackFcn(app, @Button_701ValueChanged, true);
            app.Button_701.Tooltip = {'Separación no física ≥ 3,0 m'};
            app.Button_701.Icon = 'Non-physical separation 3+.png';
            app.Button_701.Text = '';
            app.Button_702 = uibutton(app.UsosTab, 'state');
            app.Button_702.ValueChangedFcn = createCallbackFcn(app, @Button_702ValueChanged, true);
            app.Button_702.Tooltip = {'Barrera física'};
            app.Button_702.Icon = 'Physical barrier.png';
            app.Button_702.Text = '';
            app.Button_703 = uibutton(app.UsosTab, 'state');
            app.Button_703.ValueChangedFcn = createCallbackFcn(app, @Button_703ValueChanged, true);
            app.Button_703.Tooltip = {'Camino informal ≥ 1,0 m'};
            app.Button_703.Icon = 'Informal path 1+.png';
            app.Button_703.Text = '';
            app.Button_704 = uibutton(app.UsosTab, 'state');
            app.Button_704.ValueChangedFcn = createCallbackFcn(app, @Button_704ValueChanged, true);
            app.Button_704.Tooltip = {'Camino informal de 0 m a < 1,0 m'};
            app.Button_704.Icon = 'Informal path 0-1.png';
            app.Button_704.Text = '';
            app.Button_705 = uibutton(app.UsosTab, 'state');
            app.Button_705.ValueChangedFcn = createCallbackFcn(app, @Button_705ValueChanged, true);
            app.Button_705.Tooltip = {'Ciclovía fuera de la carretera sin barrera'};
            app.Button_705.Icon = 'PATH.png';
            app.Button_705.Text = '';
            app.Button_706 = uibutton(app.UsosTab, 'state');
            app.Button_706.ValueChangedFcn = createCallbackFcn(app, @Button_706ValueChanged, true);
            app.Button_706.Tooltip = {'Comercial'};
            app.Button_706.Icon = 'Comercial Land.png';
            app.Button_706.Text = '';
            app.Button_707 = uibutton(app.UsosTab, 'state');
            app.Button_707.ValueChangedFcn = createCallbackFcn(app, @Button_707ValueChanged, true);
            app.Button_707.Tooltip = {'Industrial y manufactura'};
            app.Button_707.Icon = 'Industrial and manufacturing.png';
            app.Button_707.Text = '';
            app.Button_708 = uibutton(app.UsosTab, 'state');

            app.Button_708.ValueChangedFcn = createCallbackFcn(app, @Button_708ValueChanged, true);
            app.Button_708.Tooltip = {'Residencial '};
            app.Button_708.Icon = 'Residential.png';
            app.Button_708.Text = '';
            app.Button_709 = uibutton(app.UsosTab, 'state');
            app.Button_709.ValueChangedFcn = createCallbackFcn(app, @Button_709ValueChanged, true);
            app.Button_709.Tooltip = {'Agricultura y ganadería'};
            app.Button_709.Icon = 'Farming and agricultural.png';
            app.Button_709.Text = '';
            app.Button_710 = uibutton(app.UsosTab, 'state');
            app.Button_710.ValueChangedFcn = createCallbackFcn(app, @Button_710ValueChanged, true);
            app.Button_710.Tooltip = {'Áreas no desarrolladas'};
            app.Button_710.Icon = 'Land use - passenger-side LAND.png';
            app.Button_710.Text = '';
            app.Button_711 = uibutton(app.UsosTab, 'state');
            app.Button_711.ValueChangedFcn = createCallbackFcn(app, @Button_711ValueChanged, true);
            app.Button_711.Tooltip = {'Educacional'};
            app.Button_711.Icon = 'LAND.png';
            app.Button_711.Text = '';
            app.VelocidadesTab = uitab(app.TabGroup);
            app.VelocidadesTab.Title = 'Velocidades';
            app.LmitedevelocidadLabel = uilabel(app.VelocidadesTab);
            app.LmitedevelocidadLabel.Text = 'Límite de velocidad';
            app.Button_434 = uibutton(app.VelocidadesTab, 'state');
            app.Button_434.ValueChangedFcn = createCallbackFcn(app, @Button_434ValueChanged, true);
            app.Button_434.Tooltip = {'< 30 km/h'};
            app.Button_434.Icon = '30K.png';
            app.Button_434.Text = '';
            app.Button_435 = uibutton(app.VelocidadesTab, 'state');
            app.Button_435.ValueChangedFcn = createCallbackFcn(app, @Button_435ValueChanged, true);
            app.Button_435.Tooltip = {'35 km/h'};
            app.Button_435.Icon = '35K.png';
            app.Button_435.Text = '';
            app.Button_436 = uibutton(app.VelocidadesTab, 'state');
            app.Button_436.ValueChangedFcn = createCallbackFcn(app, @Button_436ValueChanged, true);
            app.Button_436.Tooltip = {'40 km/h'};
            app.Button_436.Icon = '40K.png';
            app.Button_436.Text = '';
            app.Button_437 = uibutton(app.VelocidadesTab, 'state');
            app.Button_437.ValueChangedFcn = createCallbackFcn(app, @Button_437ValueChanged, true);
            app.Button_437.Tooltip = {'45 km/h'};
            app.Button_437.Icon = '45K.png';
            app.Button_437.Text = '';
            app.Button_438 = uibutton(app.VelocidadesTab, 'state');
            app.Button_438.ValueChangedFcn = createCallbackFcn(app, @Button_438ValueChanged, true);
            app.Button_438.Tooltip = {'50 km/h'};
            app.Button_438.Icon = '50K.png';
            app.Button_438.Text = '';
            app.DiferencialdelmitesdevelocidadLabel = uilabel(app.VelocidadesTab);
            app.DiferencialdelmitesdevelocidadLabel.Text = 'Diferencial de límites de velocidad ';
            app.Button_658 = uibutton(app.VelocidadesTab, 'state');
            app.Button_658.ValueChangedFcn = createCallbackFcn(app, @Button_658ValueChanged, true);
            app.Button_658.Tooltip = {'Presente'};
            app.Button_658.Icon = 'Differential speed limits PRESENT.png';
            app.Button_658.Text = '';
            app.Button_659 = uibutton(app.VelocidadesTab, 'state');
            app.Button_659.ValueChangedFcn = createCallbackFcn(app, @Button_659ValueChanged, true);
            app.Button_659.Tooltip = {'Ausente'};
            app.Button_659.Icon = 'Differential speed limits NO PRESENT.png';
            app.Button_659.Text = '';
            app.Button_446 = uibutton(app.VelocidadesTab, 'state');
            app.Button_446.ValueChangedFcn = createCallbackFcn(app, @Button_446ValueChanged, true);
            app.Button_446.Tooltip = {'55 km/h'};
            app.Button_446.Icon = '55.png';
            app.Button_446.Text = '';
            app.Button_447 = uibutton(app.VelocidadesTab, 'state');
            app.Button_447.ValueChangedFcn = createCallbackFcn(app, @Button_447ValueChanged, true);
            app.Button_447.Tooltip = {'60 km/h'};
            app.Button_447.Icon = '60K.png';
            app.Button_447.Text = '';
            app.Button_448 = uibutton(app.VelocidadesTab, 'state');
            app.Button_448.ValueChangedFcn = createCallbackFcn(app, @Button_448ValueChanged, true);
            app.Button_448.Tooltip = {'65 km/h'};
            app.Button_448.Icon = '65K.png';
            app.Button_448.Text = '';
            app.Button_449 = uibutton(app.VelocidadesTab, 'state');
            app.Button_449.ValueChangedFcn = createCallbackFcn(app, @Button_449ValueChanged, true);
            app.Button_449.Tooltip = {'70 km/h'};
            app.Button_449.Icon = '70K.png';
            app.Button_449.Text = '';
            app.Button_450 = uibutton(app.VelocidadesTab, 'state');
            app.Button_450.ValueChangedFcn = createCallbackFcn(app, @Button_450ValueChanged, true);
            app.Button_450.Tooltip = {'75 km/h'};
            app.Button_450.Icon = '75K.png';
            app.Button_450.Text = '';
            app.Button_451 = uibutton(app.VelocidadesTab, 'state');
            app.Button_451.ValueChangedFcn = createCallbackFcn(app, @Button_451ValueChanged, true);
            app.Button_451.Tooltip = {'80 km/h'};
            app.Button_451.Icon = '80K.png';
            app.Button_451.Text = '';
            app.Button_452 = uibutton(app.VelocidadesTab, 'state');
            app.Button_452.ValueChangedFcn = createCallbackFcn(app, @Button_452ValueChanged, true);
            app.Button_452.Tooltip = {'85 km/h'};
            app.Button_452.Icon = '85K.png';
            app.Button_452.Text = '';
            app.Button_453 = uibutton(app.VelocidadesTab, 'state');
            app.Button_453.ValueChangedFcn = createCallbackFcn(app, @Button_453ValueChanged, true);
            app.Button_453.Tooltip = {'90 km/h'};
            app.Button_453.Icon = '90K.png';
            app.Button_453.Text = '';
            app.Button_454 = uibutton(app.VelocidadesTab, 'state');
            app.Button_454.ValueChangedFcn = createCallbackFcn(app, @Button_454ValueChanged, true);
            app.Button_454.Tooltip = {'95 km/h'};
            app.Button_454.Icon = '95.png';
            app.Button_454.Text = '';
            app.Button_455 = uibutton(app.VelocidadesTab, 'state');
            app.Button_455.ValueChangedFcn = createCallbackFcn(app, @Button_455ValueChanged, true);
            app.Button_455.Tooltip = {'100 km/h'};
            app.Button_455.Icon = '100K.png';
            app.Button_455.Text = '';
            app.Button_456 = uibutton(app.VelocidadesTab, 'state');
            app.Button_456.ValueChangedFcn = createCallbackFcn(app, @Button_456ValueChanged, true);
            app.Button_456.Tooltip = {'105 km/h'};
            app.Button_456.Icon = '105K.png';
            app.Button_456.Text = '';
            app.Button_457 = uibutton(app.VelocidadesTab, 'state');
            app.Button_457.ValueChangedFcn = createCallbackFcn(app, @Button_457ValueChanged, true);
            app.Button_457.Tooltip = {'110 km/h'};
            app.Button_457.Icon = '110.png';
            app.Button_457.Text = '';
            app.Button_458 = uibutton(app.VelocidadesTab, 'state');
            app.Button_458.ValueChangedFcn = createCallbackFcn(app, @Button_458ValueChanged, true);
            app.Button_458.Tooltip = {'115 km/h'};
            app.Button_458.Icon = '115K.png';
            app.Button_458.Text = '';
            app.Button_459 = uibutton(app.VelocidadesTab, 'state');
            app.Button_459.ValueChangedFcn = createCallbackFcn(app, @Button_459ValueChanged, true);
            app.Button_459.Tooltip = {'120 km/h'};
            app.Button_459.Icon = '120K.png';
            app.Button_459.Text = '';
            app.Button_460 = uibutton(app.VelocidadesTab, 'state');
            app.Button_460.ValueChangedFcn = createCallbackFcn(app, @Button_460ValueChanged, true);
            app.Button_460.Tooltip = {'125 km/h'};
            app.Button_460.Icon = '125K.png';
            app.Button_460.Text = '';
            app.Button_461 = uibutton(app.VelocidadesTab, 'state');
            app.Button_461.ValueChangedFcn = createCallbackFcn(app, @Button_461ValueChanged, true);
            app.Button_461.Tooltip = {'130 km/h'};
            app.Button_461.Icon = '130K.png';
            app.Button_461.Text = '';
            app.Button_462 = uibutton(app.VelocidadesTab, 'state');
            app.Button_462.ValueChangedFcn = createCallbackFcn(app, @Button_462ValueChanged, true);
            app.Button_462.Tooltip = {'135 km/h'};
            app.Button_462.Icon = '135K.png';
            app.Button_462.Text = '';
            app.Button_463 = uibutton(app.VelocidadesTab, 'state');
            app.Button_463.ValueChangedFcn = createCallbackFcn(app, @Button_463ValueChanged, true);
            app.Button_463.Tooltip = {'140 km/h'};
            app.Button_463.Icon = '140K.png';
            app.Button_463.Text = '';
            app.Button_464 = uibutton(app.VelocidadesTab, 'state');
            app.Button_464.ValueChangedFcn = createCallbackFcn(app, @Button_464ValueChanged, true);
            app.Button_464.Tooltip = {'145 km/h'};
            app.Button_464.Icon = '145K.png';
            app.Button_464.Text = '';
            app.Button_465 = uibutton(app.VelocidadesTab, 'state');
            app.Button_465.ValueChangedFcn = createCallbackFcn(app, @Button_465ValueChanged, true);
            app.Button_465.Tooltip = {'≥ 150 km/h'};
            app.Button_465.Icon = '150K.png';
            app.Button_465.Text = '';
            app.Velocidaddeoperacinpercentil85Label = uilabel(app.VelocidadesTab);
            app.Velocidaddeoperacinpercentil85Label.Text = 'Velocidad de operación (percentil 85)';
            app.GestindelavelocidadcalmantedeltrficoLabel = uilabel(app.VelocidadesTab);
            app.GestindelavelocidadcalmantedeltrficoLabel.Text = 'Gestión de la velocidad/calmante del tráfico';
            app.Button_656 = uibutton(app.VelocidadesTab, 'state');
            app.Button_656.ValueChangedFcn = createCallbackFcn(app, @Button_656ValueChanged, true);
            app.Button_656.Tooltip = {'Ausente'};
            app.Button_656.Icon = 'Speed management  traffic calming NOT PRESENT.png';
            app.Button_656.Text = '';
            app.Button_665 = uibutton(app.VelocidadesTab, 'state');
            app.Button_665.ValueChangedFcn = createCallbackFcn(app, @Button_665ValueChanged, true);
            app.Button_665.Tooltip = {'Presente'};
            app.Button_665.Icon = 'Speed management  traffic calming  PRESENT.png';
            app.Button_665.Text = '';
            app.Button_631 = uibutton(app.VelocidadesTab, 'state');
            app.Button_631.ValueChangedFcn = createCallbackFcn(app, @Button_631ValueChanged, true);
            app.Button_631.Tooltip = {'< 30 km/h'};
            app.Button_631.Icon = '30K.png';
            app.Button_631.Text = '';
            app.Button_632 = uibutton(app.VelocidadesTab, 'state');
            app.Button_632.ValueChangedFcn = createCallbackFcn(app, @Button_632ValueChanged, true);
            app.Button_632.Tooltip = {'35 km/h'};
            app.Button_632.Icon = '35K.png';
            app.Button_632.Text = '';
            app.Button_633 = uibutton(app.VelocidadesTab, 'state');
            app.Button_633.ValueChangedFcn = createCallbackFcn(app, @Button_633ValueChanged, true);
            app.Button_633.Tooltip = {'40 km/h'};
            app.Button_633.Icon = '40K.png';
            app.Button_633.Text = '';
            app.Button_634 = uibutton(app.VelocidadesTab, 'state');
            app.Button_634.ValueChangedFcn = createCallbackFcn(app, @Button_634ValueChanged, true);
            app.Button_634.Tooltip = {'45 km/h'};
            app.Button_634.Icon = '45K.png';
            app.Button_634.Text = '';
            app.Button_635 = uibutton(app.VelocidadesTab, 'state');
            app.Button_635.ValueChangedFcn = createCallbackFcn(app, @Button_635ValueChanged, true);
            app.Button_635.Tooltip = {'50 km/h'};
            app.Button_635.Icon = '50K.png';
            app.Button_635.Text = '';
            app.Button_636 = uibutton(app.VelocidadesTab, 'state');
            app.Button_636.ValueChangedFcn = createCallbackFcn(app, @Button_636ValueChanged, true);
            app.Button_636.Tooltip = {'55 km/h'};
            app.Button_636.Icon = '55.png';
            app.Button_636.Text = '';
            app.Button_637 = uibutton(app.VelocidadesTab, 'state');
            app.Button_637.ValueChangedFcn = createCallbackFcn(app, @Button_637ValueChanged, true);
            app.Button_637.Tooltip = {'60 km/h'};
            app.Button_637.Icon = '60K.png';
            app.Button_637.Text = '';
            app.Button_638 = uibutton(app.VelocidadesTab, 'state');
            app.Button_638.ValueChangedFcn = createCallbackFcn(app, @Button_638ValueChanged, true);
            app.Button_638.Tooltip = {'65 km/h'''};
            app.Button_638.Icon = '65K.png';
            app.Button_638.Text = '';
            app.Button_639 = uibutton(app.VelocidadesTab, 'state');
            app.Button_639.ValueChangedFcn = createCallbackFcn(app, @Button_639ValueChanged, true);
            app.Button_639.Tooltip = {'70 km/h'};
            app.Button_639.Icon = '70K.png';
            app.Button_639.Text = '';
            app.Button_640 = uibutton(app.VelocidadesTab, 'state');
            app.Button_640.ValueChangedFcn = createCallbackFcn(app, @Button_640ValueChanged, true);
            app.Button_640.Tooltip = {'75 km/h'};
            app.Button_640.Icon = '75K.png';
            app.Button_640.Text = '';
            app.Button_641 = uibutton(app.VelocidadesTab, 'state');
            app.Button_641.ValueChangedFcn = createCallbackFcn(app, @Button_641ValueChanged, true);
            app.Button_641.Tooltip = {'80 km/h'};
            app.Button_641.Icon = '80K.png';
            app.Button_641.Text = '';
            app.Button_642 = uibutton(app.VelocidadesTab, 'state');
            app.Button_642.ValueChangedFcn = createCallbackFcn(app, @Button_642ValueChanged, true);
            app.Button_642.Tooltip = {'85 km/h'};
            app.Button_642.Icon = '85K.png';
            app.Button_642.Text = '';
            app.Button_643 = uibutton(app.VelocidadesTab, 'state');
            app.Button_643.ValueChangedFcn = createCallbackFcn(app, @Button_643ValueChanged, true);
            app.Button_643.Tooltip = {'90 km/h'};
            app.Button_643.Icon = '90K.png';
            app.Button_643.Text = '';
            app.Button_644 = uibutton(app.VelocidadesTab, 'state');
            app.Button_644.ValueChangedFcn = createCallbackFcn(app, @Button_644ValueChanged, true);
            app.Button_644.Tooltip = {'95 km/h'};
            app.Button_644.Icon = '95.png';
            app.Button_644.Text = '';
            app.Button_645 = uibutton(app.VelocidadesTab, 'state');
            app.Button_645.ValueChangedFcn = createCallbackFcn(app, @Button_645ValueChanged, true);
            app.Button_645.Tooltip = {'100 km/h'};
            app.Button_645.Icon = '100K.png';
            app.Button_645.Text = '';
            app.Button_646 = uibutton(app.VelocidadesTab, 'state');
            app.Button_646.ValueChangedFcn = createCallbackFcn(app, @Button_646ValueChanged, true);
            app.Button_646.Tooltip = {'105 km/h'};
            app.Button_646.Icon = '105K.png';
            app.Button_646.Text = '';
            app.Button_647 = uibutton(app.VelocidadesTab, 'state');
            app.Button_647.ValueChangedFcn = createCallbackFcn(app, @Button_647ValueChanged, true);
            app.Button_647.Tooltip = {'110 km/h'};
            app.Button_647.Icon = '110.png';
            app.Button_647.Text = '';
            app.Button_648 = uibutton(app.VelocidadesTab, 'state');
            app.Button_648.ValueChangedFcn = createCallbackFcn(app, @Button_648ValueChanged, true);
            app.Button_648.Tooltip = {'115 km/h'};
            app.Button_648.Icon = '115K.png';
            app.Button_648.Text = '';
            app.Button_649 = uibutton(app.VelocidadesTab, 'state');
            app.Button_649.ValueChangedFcn = createCallbackFcn(app, @Button_649ValueChanged, true);
            app.Button_649.Tooltip = {'120 km/h'};
            app.Button_649.Icon = '120K.png';
            app.Button_649.Text = '';
            app.Button_650 = uibutton(app.VelocidadesTab, 'state');
            app.Button_650.ValueChangedFcn = createCallbackFcn(app, @Button_650ValueChanged, true);
            app.Button_650.Tooltip = {'125 km/h'};
            app.Button_650.Icon = '125K.png';
            app.Button_650.Text = '';
            app.Button_651 = uibutton(app.VelocidadesTab, 'state');
            app.Button_651.ValueChangedFcn = createCallbackFcn(app, @Button_651ValueChanged, true);
            app.Button_651.Tooltip = {'130 km/h'};
            app.Button_651.Icon = '130K.png';
            app.Button_651.Text = '';
            app.Button_652 = uibutton(app.VelocidadesTab, 'state');
            app.Button_652.ValueChangedFcn = createCallbackFcn(app, @Button_652ValueChanged, true);
            app.Button_652.Tooltip = {'135 km/h'};
            app.Button_652.Icon = '135K.png';
            app.Button_652.Text = '';
            app.Button_653 = uibutton(app.VelocidadesTab, 'state');
            app.Button_653.ValueChangedFcn = createCallbackFcn(app, @Button_653ValueChanged, true);
            app.Button_653.Tooltip = {'140 km/h'};
            app.Button_653.Icon = '140K.png';
            app.Button_653.Text = '';
            app.Button_654 = uibutton(app.VelocidadesTab, 'state');
            app.Button_654.ValueChangedFcn = createCallbackFcn(app, @Button_654ValueChanged, true);
            app.Button_654.Tooltip = {'145 km/h'};
            app.Button_654.Icon = '145K.png';
            app.Button_654.Text = '';
            app.Button_655 = uibutton(app.VelocidadesTab, 'state');
            app.Button_655.ValueChangedFcn = createCallbackFcn(app, @Button_655ValueChanged, true);
            app.Button_655.Tooltip = {'≥ 150 km/h'};
            app.Button_655.Icon = '150K.png';
            app.Button_655.Text = '';
            app.ResultadoTab = uitab(app.TabGroup);
            app.ResultadoTab.SizeChangedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.ResultadoTab.Title = 'Resultado';
            app.PDF = uibutton(app.ResultadoTab, 'push');
            app.PDF.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.PDF.Icon = 'icono-PDF.png';
            app.PDF.Tooltip = {'Generar reporte del tramo analizado'};
            app.PDF.Text = 'PDF';
            app.VehculoEditFieldLabel = uilabel(app.ResultadoTab);
            app.VehculoEditFieldLabel.Text = 'Vehículo';
            app.VehculoEditField = uieditfield(app.ResultadoTab, 'numeric');
            app.MotociclistaEditFieldLabel = uilabel(app.ResultadoTab);
            app.MotociclistaEditFieldLabel.Text = 'Motociclista';
            app.MotociclistaEditField = uieditfield(app.ResultadoTab, 'numeric');
            app.CiclistaEditFieldLabel = uilabel(app.ResultadoTab);
            app.CiclistaEditFieldLabel.Text = 'Ciclista';
            app.CiclistaEditField = uieditfield(app.ResultadoTab, 'numeric');
            app.PeatnEditFieldLabel = uilabel(app.ResultadoTab);
            app.PeatnEditFieldLabel.Text = 'Peatón';
            app.PeatnEditField = uieditfield(app.ResultadoTab, 'numeric');
            app.SiguientetramoButton = uibutton(app.ResultadoTab, 'push');
            app.SiguientetramoButton.ButtonPushedFcn = createCallbackFcn(app, @SiguientetramoButtonPushed, true);
            app.SiguientetramoButton.Tooltip = {'Continuar al siguiente tramo '};
            app.SiguientetramoButton.Text = 'Siguiente tramo';
            app.ValorEstVeh = uieditfield(app.ResultadoTab, 'numeric');
            app.ValorEstVeh.Tooltip = {'Clasificacion Estrellas'; '5=0 a < 2,5'; '4=2,5 a < 5'; '3=5 a < 12,5'; '2=12,5 a < 22,5'; '1=> 22,5'; ''};
            app.ValorEstMoto = uieditfield(app.ResultadoTab, 'numeric');
            app.ValorEstMoto.Tooltip = {'Clasificacion Estrellas'; '5=0 a < 2,5'; '4=2,5 a < 5'; '3=5 a < 12,5'; '2=12,5 a < 22,5'; '1=> 22,5'; ''};
            app.ValorEstCicli = uieditfield(app.ResultadoTab, 'numeric');
            app.ValorEstCicli.Tooltip = {'Clasificacion Estrellas'; '5=0 a < 5,0'; '4=5 a < 10'; '3=10 a < 30'; '2=30 a < 60'; '1=> 60'; ''};
            app.ValorEstPeaton = uieditfield(app.ResultadoTab, 'numeric');
            app.ValorEstPeaton.Tooltip = {'Clasificacion Estrellas'; '5=0 a < 5,0'; '4=5 a < 15'; '3=15 a < 40'; '2=40 a < 90'; '1=> 90'; ''};
            app.NmerodeestrellasLabel = uilabel(app.ResultadoTab);
            app.NmerodeestrellasLabel.Text = 'Número de estrellas';
            app.ValorLabel = uilabel(app.ResultadoTab);
            app.ValorLabel.Text = 'Valor';
            app.ReporteFinalButton = uibutton(app.ResultadoTab, 'push');
            app.ReporteFinalButton.ButtonPushedFcn = createCallbackFcn(app, @ReporteFinalButtonPushed, true);
            app.ReporteFinalButton.Tooltip = {'Continuar al siguiente tramo '};
            app.ReporteFinalButton.Text = 'Reporte Final';
            app.UIAxes2 = uiaxes(app.ResultadoTab);
            app.UIAxes2.XTick = 0;
            app.UIAxes2.YTick = 0;
            app.UIAxes5 = uiaxes(app.ResultadoTab);
            app.UIAxes5.XTick = 0;
            app.UIAxes5.YTick = 0;
            app.UIAxes4 = uiaxes(app.ResultadoTab);
            app.UIAxes4.XTick = 0;
            app.UIAxes4.YTick = 0;
            app.UIAxes3 = uiaxes(app.ResultadoTab);
            app.UIAxes3.XTick = 0;
            app.UIAxes3.YTick = 0;
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interfaz_Atributos

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end