classdef Interfaz_app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        InformacindeltramoPanel   matlab.ui.container.Panel
        TipoDropDownLabel         matlab.ui.control.Label
        TipoDropDown              matlab.ui.control.DropDown
        PendienteLabel            matlab.ui.control.Label
        PendienteEditField        matlab.ui.control.NumericEditField
        TramoEditFieldLabel       matlab.ui.control.Label
        TramoEditField            matlab.ui.control.NumericEditField
        AbscisamEditFieldLabel    matlab.ui.control.Label
        AbscisamEditField         matlab.ui.control.NumericEditField
        Button                    matlab.ui.control.Button
        RadiomEditFieldLabel      matlab.ui.control.Label
        RadiomEditField           matlab.ui.control.EditField
        Button_6                  matlab.ui.control.Button
        AgregartramoButton        matlab.ui.control.Button
        Button_5                  matlab.ui.control.Button
        ActualizarButton          matlab.ui.control.Button
        UITable                   matlab.ui.control.Table
        TerminarycalificarButton  matlab.ui.control.Button
        Panel_3                   matlab.ui.container.Panel
        NorteEditFieldLabel       matlab.ui.control.Label
        NorteEditField            matlab.ui.control.NumericEditField
        EsteEditFieldLabel        matlab.ui.control.Label
        EsteEditField             matlab.ui.control.NumericEditField
        CoordenadasCheckBox       matlab.ui.control.CheckBox
        CancelarButton            matlab.ui.control.Button
        Button_4                  matlab.ui.control.Button
        ImagendeltramoLabel       matlab.ui.control.Label
        UIAxes1                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        inf_tramo % Información tramo
        ID %Numero de tramo
        
        indicesSel % Description
    end
    
    methods (Access = private)
        
        function clearFields(app)
           app.CoordenadasCheckBox.Value=0;
           app.EsteEditField.Value=0;
           app.NorteEditField.Value=0;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AgregartramoButton
        function AgregartramoButtonPushed(app, event)
            
          
        load('..\mat\estado_carga.mat')
      
           
            %IDENTIFICACÍON DEL TRAMO
            ID = app.TramoEditField.Value;
            %LONGITUD DEL TRAMO
            Longitud = app.AbscisamEditField.Value;
            %TIPOLOGIA
            Tipologi= app.TipoDropDown.Value
            %CURVATURA
            Curva = app.RadiomEditField.Value;
            %PENDIENTE
            Pendiente = app.PendienteEditField.Value;
            %Abscisa
            
            %IMAGEN
            if im == 1
                Imagen = 'Cargado'
            else im = 0
                Imagen= 'No cargado'
            end
            
            %%if app.Button_6.Pushed==1
            %%Imagen='Cargado'
            %%end


             CoordenadaX=app.EsteEditField.Value;
             CoordenadaY=app.NorteEditField.Value;
             
            %AGREGAR INFORMACIÓN
           VALORES_TRAMO = [ID Longitud {Tipologi} Curva Pendiente Imagen CoordenadaY CoordenadaX ];
           app.UITable.Data = [app.inf_tramo;VALORES_TRAMO];
           app.inf_tramo = app.UITable.Data;

           %%VALOR POR DEFECTO  
           % IDENTIFICACÍON DEL TRAMO
            app.TramoEditField.Value = (ID+1);
            %LONGITUD DEL TRAMO
            app.AbscisamEditField.Value = Longitud+100;
            %TIPOLOGIA
            app.TipoDropDown.Value = 'Tangente'
            %CURVATURA
            app.RadiomEditField.Editable = 0;
            app.RadiomEditField.Value = '-';
            %PENDIENTE
            app.PendienteEditField.Value = 0;
            clearFields(app);
            %SIN IMAGEN
            sn_img=imread('..\img\SN_CARGA.png');
            imshow(sn_img,'Parent',app.UIAxes1);
            %GUARDAR FOTOGRAMA
            load('..\mat\infor_img_alt.mat');
            img_tramo = imread(fotograma);
            nom_tramo=sprintf('%d.jpg',ID);
            nombre_img = strcat('..\fotogramas\',nom_tramo);
            imwrite(img_tramo,nombre_img);
            im = 0;
           fotograma= '..\img\SN_CARGA.png';
            save('../mat/estado_carga','im')
            save('../mat/infor_img_alt.mat',"fotograma")
           
           
        end

        % Value changed function: TipoDropDown
        function TipoDropDownValueChanged(app, event)
            %load('../mat/celda')
            %value = app.TipoDropDown.Value;
            if app.TipoDropDown.Value == 'Curva'
                app.RadiomEditField.Editable = 1;
            else app.TipoDropDown.Value == 'Tangente'
                app.RadiomEditField.Editable =0;
            end     
             %ID =  app.TramoEditField.Value;
             %if rowsSelected==1 && app.UITable.Data{ID,3}~=value;          
            %app.ActualizarButton.Enable = 'on'
           %end
        end

        % Value changed function: CoordenadasCheckBox
        function CoordenadasCheckBoxValueChanged(app, event)
            value = app.CoordenadasCheckBox.Value;
            if app.CoordenadasCheckBox.Value == 1; 
                app.EsteEditField.Visible = 'On';
                app.NorteEditField.Visible = 'On';
              
            else
                app.CoordenadasCheckBox.Value=0
                app.EsteEditField.Value=0
                app.NorteEditField.Value=0
            end
               xt=app.EsteEditField.Value;
               yt=app.NorteEditField.Value
               save('../mat/preproceso_coord',"xt","yt")
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)

        %UBICACION Y NOMBRE DEL FOTOGRAMA     
        [Filename, pathname]=uigetfile({ '*.jpg*';'*.png*'},'seleccione los archivos','Multiselect','on');
        ubi_img = pathname;
        numero_archivos=dir(fullfile(ubi_img,'*.jpg'));
        texto_ruta=numero_archivos(1).folder;
        union_nombre= strcat('\',Filename);
        fotograma=strcat(texto_ruta,union_nombre);
        save('..\mat\infor_img_alt',"fotograma");
        img_tramo = imread(fotograma);
        imshow(img_tramo,'Parent',app.UIAxes1);    
        
        if app.Button_6.Enable=='on'
            im= 1
        end
        save('../mat/estado_carga','im')
      
                
        %RUTA DEL FOTOGRAMA  
        
        
        end

        % Button pushed function: TerminarycalificarButton
        function TerminarycalificarButtonPushed(app, event)
     clear('../mat/preproceso_tramo',"tramo")       
            %DIMENSIONAR LA ESTRUCTURA DE GUARDADO
            app.inf_tramo=app.UITable.Data;
            num_filas = size(app.inf_tramo,1);
           
            %ESTRUCTURA DE INFORMACIÓN

            for num=1:num_filas
                tramo(num).Tramos = app.inf_tramo{num,1}
            %
            absini = 1;
            absfin =  num_filas
            absin=app.inf_tramo{num,2}
     
        
            if absin<absfin
               Long=absin-app.inf_tramo{num+1,2}
                
            elseif absini==num
                Long=app.inf_tramo{1,2}
            else absin==absfin
               Long=absin-app.inf_tramo{num-1,2}
          
            end
           
                tramo(num).Distancia = Long
            
                tramo(num).Pendiente = app.inf_tramo{num,5}
                             
                tramo(num).Radio = app.inf_tramo{num,4}
                TIPO = str2double(app.inf_tramo{num,4})
                if TIPO>0
                    tramo(num).Radio=str2double(app.inf_tramo{num,4})
                else 
                    tramo(num).Radio=99999
                    
                end
                tramo(num).Xt=app.inf_tramo{num,8};
              
                tramo(num).yt=app.inf_tramo{num,7};
       
                if TIPO>0
                    k = 1
                    tramo(num).k= k
                else 
                    k = 0
                    tramo(num).k= k
                end
          
           Time_trams(num)=app.inf_tramo{num,1}
            end
          save('../mat/preproceso_tramo',"tramo")
          save("../mat/preproceso_tiempo_img","Time_trams")

       Interfaz_Atributos
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
           load('../mat/celda')
           if rowsSelected==-1
                 msgbox('seleccione el tramo','Información');
           end
            
            rn=rowsSelected;
           app.UITable.Data(rn,:)=[];
           app.inf_tramo=app.UITable.Data
           num_filas = size(app.inf_tramo,1);
           
           for num=1:num_filas
               app.UITable.Data(num,1)={num}
           end
            nom_tramo=sprintf('%d.jpg',rn)
            nombre_img = strcat('..\fotogramas\',nom_tramo);
           if exist(nombre_img)
               delete(nombre_img)
           
           end
           app.inf_tramo=app.UITable.Data
           
           app.TramoEditField.Value=num_filas+1
           clear('../mat/celda')
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
   
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            m=msgbox('En tramos rectos se toman valores de radio como infinitos(99999)','Información');
        end

        % Callback function
        function Button_3Pushed(app, event)
            app.AgregartramoButton.Enable = 'on';
            app.inf_tramo=app.UITable.Data;
            num_filas = size(app.inf_tramo,1);
            app.TramoEditField.Value=num_filas+1
            Longitud = app.UITable.Data{num_filas,2};
            ID = app.TramoEditField.Value;
           %LONGITUD DEL TRAMO
            app.AbscisamEditField.Value = Longitud+100;
            %TIPOLOGIA
            app.TipoDropDown.Value = 'Tangente'
            %CURVATURA
            app.RadiomEditField.Editable = 0;
            app.RadiomEditField.Value = '-';
            %PENDIENTE
            app.PendienteEditField.Value = 0;
            app.ActualizarButton.Enable = 'off'
            app.Button_3.Enable = 'off'
           %SIN IMAGEN
            sn_img=imread('..\img\SN_CARGA.png');
            imshow(sn_img,'Parent',app.UIAxes1);
            im = 0
            
            %GUARDAR FOTOGRAMA
            load('..\mat\infor_img_alt.mat')
            img_tramo = imread(fotograma);
            nom_tramo=sprintf('%d.jpg',ID)
            nombre_img = strcat('..\fotogramas\',nom_tramo);
            imwrite(img_tramo,nombre_img);

           
            save('../mat/estado_carga','im')
            
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            
            app.indicesSel = event.Indices;
            app.AgregartramoButton.Enable = 'off' 
            app.ActualizarButton.Enable = 'on'
            %app.Button_3.Enable = 'on'
           rowsSelected=app.indicesSel(:,1);
           tr=app.UITable.Data{rowsSelected,1};
           abs=app.UITable.Data{rowsSelected,2};
           tip=app.UITable.Data{rowsSelected,3};
           radi=app.UITable.Data{rowsSelected,4};
           pend=app.UITable.Data{rowsSelected,5};
           imgn=app.UITable.Data{rowsSelected,6};
           CN=app.UITable.Data{rowsSelected,7};
           CE=app.UITable.Data{rowsSelected,8};
           
           app.TramoEditField.Value=tr;
           app.AbscisamEditField.Value=abs;
           app.TipoDropDown.Value=tip;
           app.RadiomEditField.Value=radi;
           app.PendienteEditField.Value=pend;
           app.EsteEditField.Value=CE;
           app.NorteEditField.Value=CN;
            ID =  app.TramoEditField.Value;
          % if (app.AbscisamEditField.Value~=app.UITable.Data{ID,2}) && (app.TipoDropDown.Value~=app.UITable.Data{ID,3});

           %app.AgregartramoButton.Enable == 'off'
          
                      
          % end


           nom_tramo=sprintf('%d.jpg',tr)
           nombre_img = strcat('..\fotogramas\',nom_tramo);
           if exist(nombre_img)
                imshow(nombre_img,'Parent',app.UIAxes1);
           else            
            sn_img=imread('..\img\SN_CARGA.png');
            imshow(sn_img,'Parent',app.UIAxes1);
           end
          
            save('../mat/celda',"rowsSelected")
        end

        % Button pushed function: ActualizarButton
        function ActualizarButtonPushed(app, event)
                       ID =  app.TramoEditField.Value;
             app.UITable.Data{ID,1}=app.TramoEditField.Value;
            app.UITable.Data{ID,2}=app.AbscisamEditField.Value;
            app.UITable.Data{ID,3}=app.TipoDropDown.Value;
            app.UITable.Data{ID,4}=app.RadiomEditField.Value;
            app.UITable.Data{ID,5}=app.PendienteEditField.Value;
            app.UITable.Data{ID,8}=app.EsteEditField.Value;
             app.UITable.Data{ID,7}=app.NorteEditField.Value;
            %clear('../mat/celda')
             app.AgregartramoButton.Enable = 'on';
            app.inf_tramo=app.UITable.Data;
            num_filas = size(app.inf_tramo,1);
            app.TramoEditField.Value=num_filas+1
            Longitud = app.UITable.Data{num_filas,2};
            ID = app.TramoEditField.Value;
           %LONGITUD DEL TRAMO
            app.AbscisamEditField.Value = Longitud+100;
            %TIPOLOGIA
            app.TipoDropDown.Value = 'Tangente'
            %CURVATURA
            app.RadiomEditField.Editable = 0;
            app.RadiomEditField.Value = '-';
            %PENDIENTE
            app.PendienteEditField.Value = 0;
            app.ActualizarButton.Enable = 'off'
            %app.Button_3.Enable = 'off'
           %SIN IMAGEN
            sn_img=imread('..\img\SN_CARGA.png');
            imshow(sn_img,'Parent',app.UIAxes1);
            im = 0
            
            %GUARDAR FOTOGRAMA
            load('..\mat\infor_img_alt.mat')
            img_tramo = imread(fotograma);
            nom_tramo=sprintf('%d.jpg',ID)
            nombre_img = strcat('..\fotogramas\',nom_tramo);
            imwrite(img_tramo,nombre_img);

           
            save('../mat/estado_carga','im')

        end

        % Value changed function: AbscisamEditField
        function AbscisamEditFieldValueChanged(app, event)
             %load('../mat/celda')
            % ID =  app.TramoEditField.Value;
           % value = app.AbscisamEditField.Value;
          % if rowsSelected==1 && app.UITable.Data{ID,2}~=value;          
            %app.ActualizarButton.Enable = 'on'
          %end
             
        end

        % Value changed function: RadiomEditField
        function RadiomEditFieldValueChanged(app, event)
           %load('../mat/celda')
           % ID =  app.TramoEditField.Value; 
          % value = app.RadiomEditField.Value;
                         
             %if rowsSelected==1 && app.UITable.Data{ID,4}~=value;          
            %app.ActualizarButton.Enable = 'on'
           %end
        end

        % Value changed function: PendienteEditField
        function PendienteEditFieldValueChanged(app, event)
             %load('../mat/celda')
             % ID =  app.TramoEditField.Value;
            %value = app.PendienteEditField.Value;

            % if rowsSelected==1 && app.UITable.Data{ID,5}~=value       
            %app.ActualizarButton.Enable = 'on'
           %end
        end

        % Value changed function: NorteEditField
        function NorteEditFieldValueChanged(app, event)
            %load('../mat/celda')
            %ID =  app.TramoEditField.Value;
            %value = app.NorteEditField.Value;
                    
            % if rowsSelected==1 && app.UITable.Data{ID,7}~=value;          
            %app.ActualizarButton.Enable = 'on'
           %end
        end

        % Value changed function: EsteEditField
        function EsteEditFieldValueChanged(app, event)
            %value = app.EsteEditField.Value;
            %load('../mat/celda')
            % ID =  app.TramoEditField.Value;
            % if rowsSelected==1 && app.UITable.Data{ID,8}~=value;          
            %app.ActualizarButton.Enable = 'on'
           %end
        end

        % Button pushed function: CancelarButton
        function CancelarButtonPushed(app, event)
            FASES
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InformacindeltramoPanel = uipanel(app.UIFigure);
            app.InformacindeltramoPanel.Title = 'Información del tramo';
            app.TipoDropDownLabel = uilabel(app.InformacindeltramoPanel);
            app.TipoDropDownLabel.Text = 'Tipo:';
            app.TipoDropDown = uidropdown(app.InformacindeltramoPanel);
            app.TipoDropDown.Items = {'Tangente', 'Curva'};
            app.TipoDropDown.ValueChangedFcn = createCallbackFcn(app, @TipoDropDownValueChanged, true);
            app.TipoDropDown.Value = 'Tangente';
            app.PendienteLabel = uilabel(app.InformacindeltramoPanel);
            app.PendienteLabel.Text = 'Pendiente (%):';
            app.PendienteEditField = uieditfield(app.InformacindeltramoPanel, 'numeric');
            app.PendienteEditField.ValueChangedFcn = createCallbackFcn(app, @PendienteEditFieldValueChanged, true);
            app.TramoEditFieldLabel = uilabel(app.InformacindeltramoPanel);
            app.TramoEditFieldLabel.Text = 'Tramo:';
            app.TramoEditField = uieditfield(app.InformacindeltramoPanel, 'numeric');
            app.TramoEditField.Editable = 'off';
            app.TramoEditField.Value = 1;
            app.AbscisamEditFieldLabel = uilabel(app.InformacindeltramoPanel);
            app.AbscisamEditFieldLabel.Text = 'Abscisa (m):';
            app.AbscisamEditField = uieditfield(app.InformacindeltramoPanel, 'numeric');
            app.AbscisamEditField.ValueDisplayFormat = '%.0f';
            app.AbscisamEditField.ValueChangedFcn = createCallbackFcn(app, @AbscisamEditFieldValueChanged, true);
            app.AbscisamEditField.Value = 100;
            app.Button = uibutton(app.InformacindeltramoPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '?';
            app.RadiomEditFieldLabel = uilabel(app.InformacindeltramoPanel);
            app.RadiomEditFieldLabel.Text = 'Radio (m):';
            app.RadiomEditField = uieditfield(app.InformacindeltramoPanel, 'text');
            app.RadiomEditField.ValueChangedFcn = createCallbackFcn(app, @RadiomEditFieldValueChanged, true);
            app.RadiomEditField.Editable = 'off';
            app.RadiomEditField.Value = '-';
            app.Button_6 = uibutton(app.InformacindeltramoPanel, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Icon = 'AGR_IMG1.png';
            app.Button_6.Text = '';
            app.AgregartramoButton = uibutton(app.InformacindeltramoPanel, 'push');
            app.AgregartramoButton.ButtonPushedFcn = createCallbackFcn(app, @AgregartramoButtonPushed, true);
            app.AgregartramoButton.Text = 'Agregar tramo';
            app.Button_5 = uibutton(app.InformacindeltramoPanel, 'push');
            app.Button_5.Tooltip = {'Agregar imagen al tramo. (Opcional)'};
            app.Button_5.Text = '?';
            app.ActualizarButton = uibutton(app.InformacindeltramoPanel, 'push');
            app.ActualizarButton.ButtonPushedFcn = createCallbackFcn(app, @ActualizarButtonPushed, true);
            app.ActualizarButton.Enable = 'off';
            app.ActualizarButton.Text = 'Actualizar';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Tramo'; 'Abscisa (m)'; 'Tipo'; 'Radio (m)'; 'Pendiente (%)'; 'Imágen'; 'Coordenada N'; 'Coordenada E'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false false true true false false false false];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.TerminarycalificarButton = uibutton(app.UIFigure, 'push');
            app.TerminarycalificarButton.ButtonPushedFcn = createCallbackFcn(app, @TerminarycalificarButtonPushed, true);
            app.TerminarycalificarButton.Text = 'Terminar y calificar';
            app.Panel_3 = uipanel(app.UIFigure);
            app.NorteEditFieldLabel = uilabel(app.Panel_3);
            app.NorteEditFieldLabel.Enable = 'off';
            app.NorteEditFieldLabel.Text = 'Norte:';
            app.NorteEditField = uieditfield(app.Panel_3, 'numeric');
            app.NorteEditField.ValueChangedFcn = createCallbackFcn(app, @NorteEditFieldValueChanged, true);
            app.NorteEditField.Visible = 'off';
            app.EsteEditFieldLabel = uilabel(app.Panel_3);
            app.EsteEditFieldLabel.Enable = 'off';
            app.EsteEditFieldLabel.Text = 'Este:';
            app.EsteEditField = uieditfield(app.Panel_3, 'numeric');
            app.EsteEditField.ValueChangedFcn = createCallbackFcn(app, @EsteEditFieldValueChanged, true);
            app.EsteEditField.Visible = 'off';
            app.CoordenadasCheckBox = uicheckbox(app.Panel_3);
            app.CoordenadasCheckBox.ValueChangedFcn = createCallbackFcn(app, @CoordenadasCheckBoxValueChanged, true);
            app.CoordenadasCheckBox.Text = 'Coordenadas';
            app.CancelarButton = uibutton(app.UIFigure, 'push');
            app.CancelarButton.ButtonPushedFcn = createCallbackFcn(app, @CancelarButtonPushed, true);
            app.CancelarButton.Text = 'Cancelar';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '-';
            app.ImagendeltramoLabel = uilabel(app.UIFigure);
            app.ImagendeltramoLabel.Text = 'Imagen del tramo.';
            app.UIAxes1 = uiaxes(app.UIFigure);
            zlabel(app.UIAxes1, 'Z')
            app.UIAxes1.XTick = [];
            app.UIAxes1.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interfaz_app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end