classdef MLG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MuertosylesionadosgravesMLGEditFieldLabel  matlab.ui.control.Label
        MuertosylesionadosgravesMLGEditField  matlab.ui.control.NumericEditField
        CalcularButton                  matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        OcupantedevehculosTab           matlab.ui.container.Tab
        SalidadelcaminoLadodelconductorEditFieldLabel  matlab.ui.control.Label
        SalidadelcaminoLadodelconductorEditField  matlab.ui.control.NumericEditField
        SalidadelcaminoLadodelcopilotoEditFieldLabel  matlab.ui.control.Label
        SalidadelcaminoLadodelcopilotoEditField  matlab.ui.control.NumericEditField
        ChoquefrontalprdidadecontrolEditFieldLabel  matlab.ui.control.Label
        ChoquefrontalprdidadecontrolEditField  matlab.ui.control.NumericEditField
        ChoquefrontaladelantamientoEditFieldLabel  matlab.ui.control.Label
        ChoquefrontaladelantamientoEditField  matlab.ui.control.NumericEditField
        InterseccionesEditFieldLabel    matlab.ui.control.Label
        InterseccionesEditField         matlab.ui.control.NumericEditField
        PuntosdeaccesoEditFieldLabel    matlab.ui.control.Label
        PuntosdeaccesoEditField         matlab.ui.control.NumericEditField
        ReduccindeMLGLabel              matlab.ui.control.Label
        ReduccindeMLGLabel_2            matlab.ui.control.Label
        ReduccindeMLGLabel_3            matlab.ui.control.Label
        ReduccindeMLGLabel_4            matlab.ui.control.Label
        ReduccindeMLGLabel_5            matlab.ui.control.Label
        ReduccindeMLGLabel_6            matlab.ui.control.Label
        MotociclistasTab                matlab.ui.container.Tab
        ReduccindeMLGLabel_7            matlab.ui.control.Label
        ReduccindeMLGLabel_8            matlab.ui.control.Label
        ReduccindeMLGLabel_9            matlab.ui.control.Label
        ReduccindeMLGLabel_10           matlab.ui.control.Label
        ReduccindeMLGLabel_11           matlab.ui.control.Label
        ReduccindeMLGLabel_12           matlab.ui.control.Label
        SalidadelcaminoLadodelconductorEditField_2Label  matlab.ui.control.Label
        SalidadelcaminoLadodelconductorEditField_2  matlab.ui.control.NumericEditField
        SalidadelcaminoLadodelcopilotoEditField_2Label  matlab.ui.control.Label
        SalidadelcaminoLadodelcopilotoEditField_2  matlab.ui.control.NumericEditField
        ChoquefrontalprdidadecontrolEditField_2Label  matlab.ui.control.Label
        ChoquefrontalprdidadecontrolEditField_2  matlab.ui.control.NumericEditField
        ChoquefrontaladelantamientoEditField_2Label  matlab.ui.control.Label
        ChoquefrontaladelantamientoEditField_2  matlab.ui.control.NumericEditField
        InterseccionesEditField_2Label  matlab.ui.control.Label
        InterseccionesEditField_2       matlab.ui.control.NumericEditField
        PuntosdeaccesoEditField_2Label  matlab.ui.control.Label
        PuntosdeaccesoEditField_2       matlab.ui.control.NumericEditField
        ReduccindeMLGLabel_13           matlab.ui.control.Label
        AlolargoEditFieldLabel          matlab.ui.control.Label
        AlolargoEditField               matlab.ui.control.NumericEditField
        CiclistasTab                    matlab.ui.container.Tab
        ReduccindeMLGLabel_14           matlab.ui.control.Label
        ReduccindeMLGLabel_15           matlab.ui.control.Label
        ReduccindeMLGLabel_16           matlab.ui.control.Label
        AlolargoEditField_2Label        matlab.ui.control.Label
        AlolargoEditField_2             matlab.ui.control.NumericEditField
        SalidadelcaminoEditFieldLabel   matlab.ui.control.Label
        SalidadelcaminoEditField        matlab.ui.control.NumericEditField
        InterseccionesEditField_3Label  matlab.ui.control.Label
        InterseccionesEditField_3       matlab.ui.control.NumericEditField
        PeatnTab                        matlab.ui.container.Tab
        ReduccindeMLGLabel_17           matlab.ui.control.Label
        ReduccindeMLGLabel_18           matlab.ui.control.Label
        ReduccindeMLGLabel_19           matlab.ui.control.Label
        ReduccindeMLGLabel_20           matlab.ui.control.Label
        AlolargoLadodelconductorEditFieldLabel  matlab.ui.control.Label
        AlolargoLadodelconductorEditField  matlab.ui.control.NumericEditField
        AlolargoLadodelcopilotoEditFieldLabel  matlab.ui.control.Label
        AlolargoLadodelcopilotoEditField  matlab.ui.control.NumericEditField
        CrucepeatonalvainspeccionadaEditFieldLabel  matlab.ui.control.Label
        CrucepeatonalvainspeccionadaEditField  matlab.ui.control.NumericEditField
        CrucepeatonalvalateralEditFieldLabel  matlab.ui.control.Label
        CrucepeatonalvalateralEditField  matlab.ui.control.NumericEditField
        GuardarButton                   matlab.ui.control.Button
        GuardarResultadosLabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
        Matriz % Description
        Matriz2
        Contador
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Intervalo)
            app.Contador=Intervalo;
            load('../mat/Datos_interfaz_dos_proceso.mat');
            
            Intervalo1=sum(arrayfun(@(Datos_interfaz_dos) ~isempty(Datos_interfaz_dos.Tramos),Datos_interfaz_dos));

            load('../mat/Matriz_factores_contramedidas_postproceso.mat')
            app.Matriz2=Matriz_y(Intervalo);
            
            load('../mat/Matriz_contramedidas_postproceso.mat')
            app.Matriz=Matriz_x1(Intervalo).Factor;
            
            %Matriz para guaradr los resultados de los MLG
             Matriz_z(Intervalo1).Accidente1=[]
             Matriz_z(Intervalo1).Accidente2=[]
             Matriz_z(Intervalo1).Accidente3=[]
             Matriz_z(Intervalo1).Accidente4=[]
             Matriz_z(Intervalo1).Accidente5=[]
             Matriz_z(Intervalo1).Accidente6=[]
             Matriz_z(Intervalo1).Accidente7=[]
             Matriz_z(Intervalo1).Accidente8=[]
             Matriz_z(Intervalo1).Accidente9=[]
             Matriz_z(Intervalo1).Accidente10=[]
             Matriz_z(Intervalo1).Accidente11=[]
             Matriz_z(Intervalo1).Accidente12=[]
             Matriz_z(Intervalo1).Accidente13=[]
             Matriz_z(Intervalo1).Accidente14=[]
             Matriz_z(Intervalo1).Accidente15=[]
             Matriz_z(Intervalo1).Accidente16=[]
             Matriz_z(Intervalo1).Accidente17=[]
             Matriz_z(Intervalo1).Accidente18=[]
             Matriz_z(Intervalo1).Accidente19=[]
             Matriz_z(Intervalo1).Accidente20=[]
             
            save('../mat/Matriz_Resultados_MGL_postproceso','Matriz_z')
            
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
       b=app.MuertosylesionadosgravesMLGEditField.Value;
            
       [Reduccion_total_MGL,Reduccion_total_MGL1,Reduccion_total_MGL2,Reduccion_total_MGL3,Reduccion_total_MGL4,Reduccion_total_MGL5,Reduccion_total_MGL6,Reduccion_total_MGL7,Reduccion_total_MGL8,Reduccion_total_MGL9,Reduccion_total_MGL10,Reduccion_total_MGL11,Reduccion_total_MGL12,Reduccion_total_MGL13,Reduccion_total_MGL14,Reduccion_total_MGL15,Reduccion_total_MGL16,Reduccion_total_MGL17,Reduccion_total_MGL18,Reduccion_total_MGL19]=calculo_MLG(app.Matriz,app.Matriz2,b)
            app.SalidadelcaminoLadodelconductorEditField.Value=Reduccion_total_MGL;
            app.SalidadelcaminoLadodelcopilotoEditField.Value=Reduccion_total_MGL1;
            app.ChoquefrontalprdidadecontrolEditField.Value=Reduccion_total_MGL2;
            app.ChoquefrontaladelantamientoEditField.Value=Reduccion_total_MGL3;
            app.InterseccionesEditField.Value=Reduccion_total_MGL4;
            app.PuntosdeaccesoEditField.Value=Reduccion_total_MGL5;
            
            app.SalidadelcaminoLadodelconductorEditField_2.Value=Reduccion_total_MGL6;
            app.SalidadelcaminoLadodelcopilotoEditField_2.Value=Reduccion_total_MGL7;
            app.ChoquefrontalprdidadecontrolEditField_2.Value=Reduccion_total_MGL8;
            app.ChoquefrontaladelantamientoEditField_2.Value=Reduccion_total_MGL9;
            app.InterseccionesEditField_2.Value=Reduccion_total_MGL10;
            app.PuntosdeaccesoEditField_2.Value=Reduccion_total_MGL11;
            app.AlolargoEditField.Value=Reduccion_total_MGL12;
            
            app.AlolargoEditField_2.Value=Reduccion_total_MGL13;
            app.SalidadelcaminoEditField.Value=Reduccion_total_MGL14;
            app.InterseccionesEditField_3.Value=Reduccion_total_MGL15;
            
            app.AlolargoLadodelconductorEditField.Value=Reduccion_total_MGL16;
            app.AlolargoLadodelcopilotoEditField.Value=Reduccion_total_MGL17;
            app.CrucepeatonalvainspeccionadaEditField.Value=Reduccion_total_MGL18;
            app.CrucepeatonalvalateralEditField.Value=Reduccion_total_MGL19;
        end

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
            load('../mat/Matriz_Resultados_MGL_postproceso.mat')
            
             Matriz_z(app.Contador).Accidente1=app.SalidadelcaminoLadodelconductorEditField.Value;
             Matriz_z(app.Contador).Accidente2=app.SalidadelcaminoLadodelcopilotoEditField.Value;
             Matriz_z(app.Contador).Accidente3=app.ChoquefrontalprdidadecontrolEditField.Value;
             Matriz_z(app.Contador).Accidente4=app.ChoquefrontaladelantamientoEditField.Value;
             Matriz_z(app.Contador).Accidente5=app.InterseccionesEditField.Value;
             Matriz_z(app.Contador).Accidente6=app.PuntosdeaccesoEditField.Value;
             Matriz_z(app.Contador).Accidente7=app.SalidadelcaminoLadodelconductorEditField_2.Value;
             Matriz_z(app.Contador).Accidente8=app.SalidadelcaminoLadodelcopilotoEditField_2.Value;
             Matriz_z(app.Contador).Accidente9=app.ChoquefrontalprdidadecontrolEditField_2.Value;
             Matriz_z(app.Contador).Accidente10=app.ChoquefrontaladelantamientoEditField_2.Value;
             Matriz_z(app.Contador).Accidente11=app.InterseccionesEditField_2.Value;
             Matriz_z(app.Contador).Accidente12=app.PuntosdeaccesoEditField_2.Value;
             Matriz_z(app.Contador).Accidente13=app.AlolargoEditField.Value;
             Matriz_z(app.Contador).Accidente14=app.AlolargoEditField_2.Value;
             Matriz_z(app.Contador).Accidente15=app.SalidadelcaminoEditField.Value;
             Matriz_z(app.Contador).Accidente16=app.InterseccionesEditField_3.Value;
             Matriz_z(app.Contador).Accidente17=app.AlolargoLadodelconductorEditField.Value;
             Matriz_z(app.Contador).Accidente18=app.AlolargoLadodelcopilotoEditField.Value;
             Matriz_z(app.Contador).Accidente19=app.CrucepeatonalvainspeccionadaEditField.Value;
             Matriz_z(app.Contador).Accidente20=app.CrucepeatonalvalateralEditField.Value;

             save('../mat/Matriz_Resultados_MGL_postproceso.mat','Matriz_z')
             delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MuertosylesionadosgravesMLGEditFieldLabel = uilabel(app.UIFigure);
            app.MuertosylesionadosgravesMLGEditFieldLabel.Text = 'Muertos y lesionados graves (MLG)';
            app.MuertosylesionadosgravesMLGEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.OcupantedevehculosTab = uitab(app.TabGroup);
            app.OcupantedevehculosTab.Title = 'Ocupante de vehículos';
            app.SalidadelcaminoLadodelconductorEditFieldLabel = uilabel(app.OcupantedevehculosTab);
            app.SalidadelcaminoLadodelconductorEditFieldLabel.Text = 'Salida del camino (Lado del conductor)';
            app.SalidadelcaminoLadodelconductorEditField = uieditfield(app.OcupantedevehculosTab, 'numeric');
            app.SalidadelcaminoLadodelcopilotoEditFieldLabel = uilabel(app.OcupantedevehculosTab);
            app.SalidadelcaminoLadodelcopilotoEditFieldLabel.Text = 'Salida del camino (Lado del copiloto)';
            app.SalidadelcaminoLadodelcopilotoEditField = uieditfield(app.OcupantedevehculosTab, 'numeric');
            app.ChoquefrontalprdidadecontrolEditFieldLabel = uilabel(app.OcupantedevehculosTab);
            app.ChoquefrontalprdidadecontrolEditFieldLabel.Text = 'Choque frontal (pérdida de control)';
            app.ChoquefrontalprdidadecontrolEditField = uieditfield(app.OcupantedevehculosTab, 'numeric');
            app.ChoquefrontaladelantamientoEditFieldLabel = uilabel(app.OcupantedevehculosTab);
            app.ChoquefrontaladelantamientoEditFieldLabel.Text = 'Choque frontal (adelantamiento)';
            app.ChoquefrontaladelantamientoEditField = uieditfield(app.OcupantedevehculosTab, 'numeric');
            app.InterseccionesEditFieldLabel = uilabel(app.OcupantedevehculosTab);
            app.InterseccionesEditFieldLabel.Text = 'Intersecciones';
            app.InterseccionesEditField = uieditfield(app.OcupantedevehculosTab, 'numeric');
            app.PuntosdeaccesoEditFieldLabel = uilabel(app.OcupantedevehculosTab);
            app.PuntosdeaccesoEditFieldLabel.Text = 'Puntos de acceso';
            app.PuntosdeaccesoEditField = uieditfield(app.OcupantedevehculosTab, 'numeric');
            app.ReduccindeMLGLabel = uilabel(app.OcupantedevehculosTab);
            app.ReduccindeMLGLabel.Text = 'Reducción de MLG';
            app.ReduccindeMLGLabel_2 = uilabel(app.OcupantedevehculosTab);
            app.ReduccindeMLGLabel_2.Text = 'Reducción de MLG';
            app.ReduccindeMLGLabel_3 = uilabel(app.OcupantedevehculosTab);
            app.ReduccindeMLGLabel_3.Text = 'Reducción de MLG';
            app.ReduccindeMLGLabel_4 = uilabel(app.OcupantedevehculosTab);
            app.ReduccindeMLGLabel_4.Text = 'Reducción de MLG';
            app.ReduccindeMLGLabel_5 = uilabel(app.OcupantedevehculosTab);
            app.ReduccindeMLGLabel_5.Text = 'Reducción de MLG';
            app.ReduccindeMLGLabel_6 = uilabel(app.OcupantedevehculosTab);
            app.ReduccindeMLGLabel_6.Text = 'Reducción de MLG';
            app.MotociclistasTab = uitab(app.TabGroup);
            app.MotociclistasTab.Title = 'Motociclistas';
            app.ReduccindeMLGLabel_7 = uilabel(app.MotociclistasTab);
            app.ReduccindeMLGLabel_7.Text = 'Reducción de MLG';
            app.ReduccindeMLGLabel_8 = uilabel(app.MotociclistasTab);
            app.ReduccindeMLGLabel_8.Text = 'Reducción de MLG';
            app.ReduccindeMLGLabel_9 = uilabel(app.MotociclistasTab);
            app.ReduccindeMLGLabel_9.Text = 'Reducción de MLG';
            app.ReduccindeMLGLabel_10 = uilabel(app.MotociclistasTab);
            app.ReduccindeMLGLabel_10.Text = 'Reducción de MLG';
            app.ReduccindeMLGLabel_11 = uilabel(app.MotociclistasTab);
            app.ReduccindeMLGLabel_11.Text = 'Reducción de MLG';
            app.ReduccindeMLGLabel_12 = uilabel(app.MotociclistasTab);
            app.ReduccindeMLGLabel_12.Text = 'Reducción de MLG';
            app.SalidadelcaminoLadodelconductorEditField_2Label = uilabel(app.MotociclistasTab);
            app.SalidadelcaminoLadodelconductorEditField_2Label.Text = 'Salida del camino (Lado del conductor)';
            app.SalidadelcaminoLadodelconductorEditField_2 = uieditfield(app.MotociclistasTab, 'numeric');
            app.SalidadelcaminoLadodelcopilotoEditField_2Label = uilabel(app.MotociclistasTab);
            app.SalidadelcaminoLadodelcopilotoEditField_2Label.Text = 'Salida del camino (Lado del copiloto)';
            app.SalidadelcaminoLadodelcopilotoEditField_2 = uieditfield(app.MotociclistasTab, 'numeric');
            app.ChoquefrontalprdidadecontrolEditField_2Label = uilabel(app.MotociclistasTab);
            app.ChoquefrontalprdidadecontrolEditField_2Label.Text = 'Choque frontal (pérdida de control)';
            app.ChoquefrontalprdidadecontrolEditField_2 = uieditfield(app.MotociclistasTab, 'numeric');
            app.ChoquefrontaladelantamientoEditField_2Label = uilabel(app.MotociclistasTab);
            app.ChoquefrontaladelantamientoEditField_2Label.Text = 'Choque frontal (adelantamiento)';
            app.ChoquefrontaladelantamientoEditField_2 = uieditfield(app.MotociclistasTab, 'numeric');
            app.InterseccionesEditField_2Label = uilabel(app.MotociclistasTab);
            app.InterseccionesEditField_2Label.Text = 'Intersecciones';
            app.InterseccionesEditField_2 = uieditfield(app.MotociclistasTab, 'numeric');
            app.PuntosdeaccesoEditField_2Label = uilabel(app.MotociclistasTab);
            app.PuntosdeaccesoEditField_2Label.Text = 'Puntos de acceso';
            app.PuntosdeaccesoEditField_2 = uieditfield(app.MotociclistasTab, 'numeric');
            app.ReduccindeMLGLabel_13 = uilabel(app.MotociclistasTab);
            app.ReduccindeMLGLabel_13.Text = 'Reducción de MLG';
            app.AlolargoEditFieldLabel = uilabel(app.MotociclistasTab);
            app.AlolargoEditFieldLabel.Text = 'A lo largo';
            app.AlolargoEditField = uieditfield(app.MotociclistasTab, 'numeric');
            app.CiclistasTab = uitab(app.TabGroup);
            app.CiclistasTab.Title = 'Ciclistas';
            app.ReduccindeMLGLabel_14 = uilabel(app.CiclistasTab);
            app.ReduccindeMLGLabel_14.Text = 'Reducción de MLG';
            app.ReduccindeMLGLabel_15 = uilabel(app.CiclistasTab);
            app.ReduccindeMLGLabel_15.Text = 'Reducción de MLG';
            app.ReduccindeMLGLabel_16 = uilabel(app.CiclistasTab);
            app.ReduccindeMLGLabel_16.Text = 'Reducción de MLG';
            app.AlolargoEditField_2Label = uilabel(app.CiclistasTab);
            app.AlolargoEditField_2Label.Text = 'A lo largo';
            app.AlolargoEditField_2 = uieditfield(app.CiclistasTab, 'numeric');
            app.SalidadelcaminoEditFieldLabel = uilabel(app.CiclistasTab);
            app.SalidadelcaminoEditFieldLabel.Text = 'Salida del camino';
            app.SalidadelcaminoEditField = uieditfield(app.CiclistasTab, 'numeric');
            app.InterseccionesEditField_3Label = uilabel(app.CiclistasTab);
            app.InterseccionesEditField_3Label.Text = 'Intersecciones';
            app.InterseccionesEditField_3 = uieditfield(app.CiclistasTab, 'numeric');
            app.PeatnTab = uitab(app.TabGroup);
            app.PeatnTab.Title = 'Peatón';
            app.ReduccindeMLGLabel_17 = uilabel(app.PeatnTab);
            app.ReduccindeMLGLabel_17.Text = 'Reducción de MLG';
            app.ReduccindeMLGLabel_18 = uilabel(app.PeatnTab);
            app.ReduccindeMLGLabel_18.Text = 'Reducción de MLG';
            app.ReduccindeMLGLabel_19 = uilabel(app.PeatnTab);
            app.ReduccindeMLGLabel_19.Text = 'Reducción de MLG';
            app.ReduccindeMLGLabel_20 = uilabel(app.PeatnTab);
            app.ReduccindeMLGLabel_20.Text = 'Reducción de MLG';
            app.AlolargoLadodelconductorEditFieldLabel = uilabel(app.PeatnTab);
            app.AlolargoLadodelconductorEditFieldLabel.Text = 'A lo largo (Lado del conductor)';
            app.AlolargoLadodelconductorEditField = uieditfield(app.PeatnTab, 'numeric');
            app.AlolargoLadodelcopilotoEditFieldLabel = uilabel(app.PeatnTab);
            app.AlolargoLadodelcopilotoEditFieldLabel.Text = 'A lo largo (Lado del copiloto)';
            app.AlolargoLadodelcopilotoEditField = uieditfield(app.PeatnTab, 'numeric');
            app.CrucepeatonalvainspeccionadaEditFieldLabel = uilabel(app.PeatnTab);
            app.CrucepeatonalvainspeccionadaEditFieldLabel.Text = 'Cruce peatonal (vía inspeccionada)';
            app.CrucepeatonalvainspeccionadaEditField = uieditfield(app.PeatnTab, 'numeric');
            app.CrucepeatonalvalateralEditFieldLabel = uilabel(app.PeatnTab);
            app.CrucepeatonalvalateralEditFieldLabel.Text = 'Cruce peatonal (vía lateral)';
            app.CrucepeatonalvalateralEditField = uieditfield(app.PeatnTab, 'numeric');
            app.GuardarButton = uibutton(app.UIFigure, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Text = 'Guardar';
            app.GuardarResultadosLabel = uilabel(app.UIFigure);
            app.GuardarResultadosLabel.Text = 'Guardar Resultados';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MLG(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end