classdef app_Aplicacion_contramedidas < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Reporte                     matlab.ui.Figure
        TabGroup2                   matlab.ui.container.TabGroup
        ReportedetalladoTab         matlab.ui.container.Tab
        TabGroup_2                  matlab.ui.container.TabGroup
        ClasificacindeestrellasTab  matlab.ui.container.Tab
        UITable_9                   matlab.ui.control.Table
        UsuariosvialesButtonGroup   matlab.ui.container.ButtonGroup
        OcupantedevehculosButton    matlab.ui.control.RadioButton
        MotociclistaButton          matlab.ui.control.RadioButton
        CiclistaButton              matlab.ui.control.RadioButton
        PeatnButton                 matlab.ui.control.RadioButton
        PDFButton_3                 matlab.ui.control.Button
        UbicacindelosSRSalolargodelacarreteraenanlisisPanel  matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
        PuntajedeclasificacinSRSalolargodelacarreteraenanlisisPanel  matlab.ui.container.Panel
        UIAxes_2                    matlab.ui.control.UIAxes
        SuavizadoCheckBox           matlab.ui.control.CheckBox
        GuardarArchivosButton       matlab.ui.control.Button
    end

    
    properties (Access = private)
        
        s1 % Description
        s2
        s3
        s4
        s5
        s6
        s7
        Datos_video
        Datos_interfaz_1
        Datos_interfaz_2
        b1
        Tabla_estrellas_Ocupante
        Tabla_estrellas_Motociclista
        Tabla_estrellas_Cilista
        Tabla_estrellas_Peaton
        Tabla_Nuevaestrellas_Ocupante
        Tabla_Nuevaestrellas_Motociclista
        Tabla_Nuevaestrellas_Cilista
        Tabla_Nuevaestrellas_Peaton
        Costado_de_via
        Mediana
        Intersecciones
        Flujo
        Infraestructura
        Velocidades
        Matriz
        Matriz2
        Matriz3
        O_V_SalidaCaminoconductor
        O_V_SalidaCaminocopiloto
        O_V_PerdidaControl 
        O_V_Adelantamiento 
        O_V_Intersecciones
        O_V_Accpropiedad

        Moto_SalidaCaminoconductor
        Moto_SalidaCaminocopiloto
        Moto_PerdidaControl
        Moto_Adelantamiento
        Moto_Intersecciones
        Moto_Accpropiedad 
        Moto_Alolargo
        
        Cicli_Alolargo 
        Cicli_SalidadCamino
        Cicli_Interseccion
        
        Peat_Alolargoconductor
        Peat_Alolargocopiloto
        Peat_CrucepeatonalIns
        Peat_CrucepeatonalLat
        
        Tablacontramedidas
 
    end
    
    methods (Access = private)
        
        function results = Suavizado(app)
            load('../mat/Datos_interfaz_dos_proceso.mat');
            load('../mat/Datos_atributos_proceso.mat');
            load('../mat/Nueva_clasificación_postproceso.mat');
            Intervalo=sum(arrayfun(@(Nuevos_datos) ~isempty(Nuevos_datos.Nueva_Estrella_Ocupante_vehiculo),Nuevos_datos));
            Tramos=[Datos_interfaz_dos(1:Intervalo).Tramos];
            Distancia=[Datos_interfaz_dos(1:Intervalo).Distancia];
            %Xt=([Datos_interfaz_dos(1:Intervalo).Xt]);
            %yt=([Datos_interfaz_dos(1:Intervalo).yt]);
            Datos_suavizado.Tramos=Tramos;
            Datos_suavizado.Distancia=Distancia;
            %Datos_suavizado.Xt=Xt;
            %Datos_suavizado.yt=yt;
            
            %Para ocupante del vehiculo
            %Datos iniciales
            OV1=[Nuevos_datos(1:Intervalo).Nueva_Estrella_Ocupante_vehiculo];
            OV=cumsum([Datos_interfaz_dos(1:Intervalo).Distancia]);
            n21=length(OV1); 
            
            %Suavizacion - Media Movil
            OV2=movmean(OV1,9);
          
            %Suavizacion - Exponencial simple
            %OV3=round(smoothts(OV2,'e',0.1));
            for i=1:n21
                d3(i)= OV2(i);
            end

            alfa=0.1;
            p3(1)= d3(1);
            for i= 2: n21  
                p3(i)= p3(i-1)+ alfa*( d3(i)-p3(i-1));
            end
            p3(i);
            
            %Nuevo # de estrellas
            %EstVehicul=OV3;
            %n=length(OV3);
            EstVehicul=p3(1:end);
            n=length(EstVehicul);
            
            
            for i=1:n    
            if EstVehicul(i)>=22.5
                EstVehiculo(i)=1;
            else if EstVehicul(i)>=12.5 && EstVehicul(i)<22.5
                EstVehiculo(i)=2;
            else if EstVehicul(i)>=5 && EstVehicul(i)<12.5
                EstVehiculo(i)=3;
            else if EstVehicul(i)>=2.5 && EstVehicul(i)<5
                EstVehiculo(i)=4;
            else EstVehicul(i)>=0 && EstVehicul(i)<2.5
                EstVehiculo(i)=5;
                end
                end
                end
            end
            end 
            EstVehiculo(i)
            Datos_suavizado.Puntuacion_Ocupante_del_vehiculo=EstVehicul;
            Datos_suavizado.Ocupante_del_vehiculo=EstVehiculo;
            
            %Para Motociclista
            %Datos iniciales
            M1=[Nuevos_datos(1:Intervalo).Nueva_Estrella_Motociclista];
            M=cumsum([Datos_interfaz_dos(1:Intervalo).Distancia]);
            n111=length(M1); 
            
            %Suavizacion - Media Movil
            for i=1:n111 
                if M1(i)>0
                    M1c(i)=M1(i);
                else
                    M1c(i)=NaN;
                end
            end
                
            M2c = movmean(M1c,9,'omitnan');
            for i=1:n111
                if Nuevos_datos(i).Nueva_Estrella_Motociclista==0
                  M2c(i)=0;  
                end
            end
            
            %Suavizacion - Exponencial simple
            for i=1:n111
                d2(i)= M2c(i);
            end

            alfa=0.1;
            p2(1)= d2(1);
            for i= 2: n111
                if d2(i-1)==0
                p2(i)= d2(i);
                else    
                p2(i)= p2(i-1)+ alfa*( d2(i)-p2(i-1));
                end
            end
            p2(i);
            
            for i=1:n111
                if Nuevos_datos(i).Nueva_Estrella_Motociclista==0
                  p2(i)=0;  
                end
            end
             
            %Nuevo # de estrellas
            EstMoto=p2(1:end);
            n=length(EstMoto);
            
            for i=1:n  
            if EstMoto(i)>=22.5
                EstMotocicleta(i)=1;
            else if EstMoto(i)>=12.5 && EstMoto(i)<22.5
                EstMotocicleta(i)=2;
            else if EstMoto(i)>=5 && EstMoto(i)<12.5
                EstMotocicleta(i)=3;
            else if EstMoto(i)>=2.5 && EstMoto(i)<5
                EstMotocicleta(i)=4;
            else if EstMoto(i)>0 && EstMoto(i)<2.5
                EstMotocicleta(i)=5;
            else EstMoto(i)<=0;
                EstMotocicleta(i)=0;
                end
                end
                end
            end
            end
            end 
            EstMotocicleta(i)
            Datos_suavizado.Puntuacion_Motociclista=EstMoto;
            Datos_suavizado.Motociclista=EstMotocicleta;
             
            %Para Ciclista
            %Datos iniciales
            C1=[Nuevos_datos(1:Intervalo).Nueva_Estrella_Ciclista];
            C=cumsum([Datos_interfaz_dos(1:Intervalo).Distancia]);
            n11=length(C1);
            
            %Suavizacion - Media Movil
            for i=1:n11 
                if C1(i)>0
                    C1c(i)=C1(i);
                else
                    C1c(i)=NaN;
                end
            end
            
            C2c = movmean(C1c,9,'omitnan');
            for i=1:n11
                if Nuevos_datos(i).Nueva_Estrella_Ciclista==0
                  C2c(i)=0;  
                end
            end

            %Suavizacion - Exponencial simple
            
            for i=1:n11
                d1(i)= C2c(i);
            end

            alfa=0.1;
            p1(1)= d1(1);
            for i= 2: n11
                if d1(i-1)==0
                p1(i)= d1(i);
                else    
                p1(i)= p1(i-1)+ alfa*( d1(i)-p1(i-1));
                end
            end
            p1(i);
            
            for i=1:n11
                if Nuevos_datos(i).Nueva_Estrella_Ciclista==0
                  p1(i)=0;  
                end
            end

            
            %Nuevo # de estrellas
            EstCicl=p1(1:end);
            n=length(EstCicl);

            for i=1:n  
            if EstCicl(i)>=60
                EstCiclista(i)=1;
            else if EstCicl(i)>=30 && EstCicl(i)<60
                EstCiclista(i)=2;
            else if EstCicl(i)>=10 && EstCicl(i)<30
                EstCiclista(i)=3;
            else if EstCicl(i)>=5 && EstCicl(i)<10
                EstCiclista(i)=4;
            else if EstCicl(i)>0 && EstCicl(i)<5
                EstCiclista(i)=5;
            else EstCicl(i)<=0;
                EstCiclista(i)=0;
                end
                end
                end
                end
            end
            end 
            EstCiclista(i)
            Datos_suavizado.Puntuacion_Ciclista=EstCicl;
            Datos_suavizado.Ciclista=EstCiclista;
            
            %Para Peatones
            %Datos iniciales
            P1=[Nuevos_datos(1:Intervalo).Nueva_Estrella_Peaton];
            P=cumsum([Datos_interfaz_dos(1:Intervalo).Distancia]);
            n1=length(P1);
            
            %Suavizacion - Media Movil
            for i=1:n1 
                if P1(i)>0
                    P1c(i)=P1(i);
                else
                    P1c(i)=NaN;
                end
            end
            
            P2c = movmean(P1c,9,'omitnan');
            for i=1:n1
                if Nuevos_datos(i).Nueva_Estrella_Peaton==0
                  P2c(i)=0;  
                end
            end

            
            %Suavizacion - Exponencial simple
            
            for i=1:n1
                d(i)= P2c(i);
            end

            alfa=0.1;
            p(1)= d(1);
            for i= 2: n1
                if d(i-1)==0
                p(i)= d(i);
                else    
                p(i)= p(i-1)+ alfa*( d(i)-p(i-1));
                end
            end
            p(i);
            
            for i=1:n1
                if Nuevos_datos(i).Nueva_Estrella_Peaton==0
                  p(i)=0;  
                end
            end

            %Nuevo # de estrellas
            EstPeato=p(1:end);
            
            n=length(EstPeato);
            
            %porpeatoncru=x(32);
            %porpeatoncon=x(33);
            %porpeatoncopi=x(34);
            
            %Atributo_elegido(i).Atributo(32)
            %Atributo_elegido(i).Atributo(33)
            %Atributo_elegido(i).Atributo(34)
            
            for i=1:n 
            if Atributo_elegido(i).Atributo(32)==1 && Atributo_elegido(i).Atributo(33)==1 && Atributo_elegido(i).Atributo(34)==1 
                if EstPeato(i)>=90
                    EstPeaton(i)=1;
                elseif EstPeato(i)>=40 && EstPeato(i)<90
                    EstPeaton(i)=2;
                elseif EstPeato(i)>=15 && EstPeato(i)<40
                    EstPeaton(i)=3;
                elseif EstPeato(i)>=5 && EstPeato(i)<15
                    EstPeaton(i)=4;
                elseif EstPeato(i)>0 && EstPeato(i)<5
                    EstPeaton(i)=5;
                elseif EstPeato(i)<=0
                    EstPeaton(i)=0;
                end
            end
                
            if Atributo_elegido(i).Atributo(32)==1 && Atributo_elegido(i).Atributo(33)>=2 && Atributo_elegido(i).Atributo(34)==1 %A lo largo
                if EstPeato(i)>=15
                    EstPeaton(i)=1;
                elseif EstPeato(i)>=7.5 && EstPeato(i)<15
                    EstPeaton(i)=2;
                elseif EstPeato(i)>=1 && EstPeato(i)<7.5
                    EstPeaton(i)=3;
                elseif EstPeato(i)>=0.2 && EstPeato(i)<1
                    EstPeaton(i)=4;
                elseif EstPeato(i)>0 && EstPeato(i)<0.2
                    EstPeaton(i)=5;
                elseif EstPeato(i)<=0
                    EstPeaton(i)=0;
                end
            end
            
            if Atributo_elegido(i).Atributo(32)==1 && Atributo_elegido(i).Atributo(33)>=2 && Atributo_elegido(i).Atributo(34)>=2 %A lo largo
                if EstPeato(i)>=15
                    EstPeaton(i)=1;
                elseif EstPeato(i)>=7.5 && EstPeato(i)<15
                    EstPeaton(i)=2;
                elseif EstPeato(i)>=1 && EstPeato(i)<7.5
                    EstPeaton(i)=3;
                elseif EstPeato(i)>=0.2 && EstPeato(i)<1
                    EstPeaton(i)=4;
                elseif EstPeato(i)>0 && EstPeato(i)<0.2
                    EstPeaton(i)=5;
                elseif EstPeato(i)<=0
                    EstPeaton(i)=0;
                end
            end
            if Atributo_elegido(i).Atributo(32)==1 && Atributo_elegido(i).Atributo(33)==1 && Atributo_elegido(i).Atributo(34)>= 2 %A lo largo
                if EstPeato(i)>=15
                    EstPeaton(i)=1;
                elseif EstPeato(i)>=7.5 && EstPeato(i)<15
                    EstPeaton(i)=2;
                elseif EstPeato(i)>=1 && EstPeato(i)<7.5
                    EstPeaton(i)=3;
                elseif EstPeato(i)>=0.2 && EstPeato(i)<1
                    EstPeaton(i)=4;
                elseif EstPeato(i)>0 && EstPeato(i)<0.2
                    EstPeaton(i)=5;
                elseif EstPeato(i)<=0
                    EstPeaton(i)=0;
                end
            end
            
            if Atributo_elegido(i).Atributo(32)>=2 && Atributo_elegido(i).Atributo(33)==1 && Atributo_elegido(i).Atributo(34)==1 %Cruce
                if EstPeato(i)>=75
                    EstPeaton(i)=1;
                elseif EstPeato(i)>=32.5 && EstPeato(i)<75
                    EstPeaton(i)=2;
                elseif EstPeato(i)>=14 && EstPeato(i)<32.5
                    EstPeaton(i)=3;
                elseif EstPeato(i)>=4.8 && EstPeato(i)<14
                    EstPeaton(i)=4;
                elseif EstPeato(i)>0 && EstPeato(i)<4.8
                    EstPeaton(i)=5;
                elseif EstPeato(i)<=0
                    EstPeaton(i)=0;
                end
            end
            
            if Atributo_elegido(i).Atributo(32)>=2 && Atributo_elegido(i).Atributo(33)>=2 && Atributo_elegido(i).Atributo(34)==1 %Total
                if EstPeato(i)>=90
                    EstPeaton(i)=1;
                elseif EstPeato(i)>=40 && EstPeato(i)<90
                    EstPeaton(i)=2;
                elseif EstPeato(i)>=15 && EstPeato(i)<40
                    EstPeaton(i)=3;
                elseif EstPeato(i)>=5 && EstPeato(i)<15
                    EstPeaton(i)=4;
                elseif EstPeato(i)>0 && EstPeato(i)<5
                    EstPeaton(i)=5;
                elseif EstPeato(i)<=0
                    EstPeaton(i)=0;
                end
            end
            
            if Atributo_elegido(i).Atributo(32)>=2 && Atributo_elegido(i).Atributo(33)==1 && Atributo_elegido(i).Atributo(34)>=2 %Total
                if EstPeato(i)>=90
                EstPeaton(i)=1;
                elseif EstPeato(i)>=40 && EstPeato(i)<90
                    EstPeaton(i)=2;
                elseif EstPeato(i)>=15 && EstPeato(i)<40
                    EstPeaton(i)=3;
                elseif EstPeato(i)>=5 && EstPeato(i)<15
                    EstPeaton(i)=4;
                elseif EstPeato(i)>0 && EstPeato(i)<5
                    EstPeaton(i)=5;
                elseif EstPeato(i)<=0
                    EstPeaton(i)=0;
                end
            end
            
            if Atributo_elegido(i).Atributo(32)>=2 && Atributo_elegido(i).Atributo(33)>=2 && Atributo_elegido(i).Atributo(34)>=2 %Total
                if EstPeato(i)>=90
                EstPeaton(i)=1;
                elseif EstPeato(i)>=40 && EstPeato(i)<90
                    EstPeaton(i)=2;
                elseif EstPeato(i)>=15 && EstPeato(i)<40
                    EstPeaton(i)=3;
                elseif EstPeato(i)>=5 && EstPeato(i)<15
                    EstPeaton(i)=4;
                elseif EstPeato(i)>0 && EstPeato(i)<5
                    EstPeaton(i)=5;
                elseif EstPeato(i)<=0
                    EstPeaton(i)=0;
                end
            end
            end
            %for i=1:n  
            %if EstPeato(i)>=90
             %   EstPeaton(i)=1;
            %else if EstPeato(i)>=40&EstPeato(i)<90
             %   EstPeaton(i)=2;
            %else if EstPeato(i)>=15&EstPeato(i)<40
             %   EstPeaton(i)=3;
            %else if EstPeato(i)>=5&EstPeato(i)<15
             %   EstPeaton(i)=4;
            %else if EstPeato(i)>0&EstPeato(i)<5
             %   EstPeaton(i)=5;
            %else EstPeato(i)<=0
             %   EstPeaton(i)=0;
              %  end
               % end
                %end
                %end
            %end
            %end 
            EstPeaton(i)
            Datos_suavizado.Puntuacion_Peaton=EstPeato;
            Datos_suavizado.Peaton=EstPeaton;
            
            save('../mat/Datos_suavizado_contramedidas_postproceso','Datos_suavizado')
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Se cargan los datos obtenidos de las interfaces previas

            load('../mat/Datos_interfaz_dos_proceso.mat');
            load('../mat/Nueva_clasificación_postproceso.mat')
            
            Intervalo=sum(arrayfun(@(Nuevos_datos) ~isempty(Nuevos_datos.Nueva_Estrella_Ocupante_vehiculo),Nuevos_datos));
            Tramos=[Datos_interfaz_dos(1:Intervalo).Tramos];
            Distancia=[Datos_interfaz_dos(1:Intervalo).Distancia];
            Ocupante_del_vehiculo=[Nuevos_datos(1:Intervalo).Nuevo_Puntaje_Ocupante_vehiculo];
            Motociclista=[Nuevos_datos(1:Intervalo).Nuevo_Puntaje_Motociclista];
            Ciclista=[Nuevos_datos(1:Intervalo).Nuevo_Puntaje_Ciclista];
            Peaton=[Nuevos_datos(1:Intervalo).Nuevo_Puntaje_Peaton];
            Puntuacion_Ocupante_del_vehiculo=[Nuevos_datos(1:Intervalo).Nueva_Estrella_Ocupante_vehiculo];
            Puntuacion_Motociclista=[Nuevos_datos(1:Intervalo).Nueva_Estrella_Motociclista];
            Puntuacion_Ciclista=[Nuevos_datos(1:Intervalo).Nueva_Estrella_Ciclista];
            Puntuacion_Peaton=[Nuevos_datos(1:Intervalo).Nueva_Estrella_Peaton];
            %Xt=([Datos_interfaz_dos(1:Intervalo).Xt]);
            %yt=([Datos_interfaz_dos(1:Intervalo).yt]);
            Datos_contramedidas.Tramos=Tramos;
            Datos_contramedidas.Distancia=Distancia;
            Datos_contramedidas.Ocupante_del_vehiculo=Ocupante_del_vehiculo;
            Datos_contramedidas.Motociclista=Motociclista;
            Datos_contramedidas.Ciclista=Ciclista;
            Datos_contramedidas.Peaton=Peaton;
            Datos_contramedidas.Puntuacion_Ocupante_del_vehiculo=Puntuacion_Ocupante_del_vehiculo;
            Datos_contramedidas.Puntuacion_Motociclista=Puntuacion_Motociclista;
            Datos_contramedidas.Puntuacion_Ciclista=Puntuacion_Ciclista;
            Datos_contramedidas.Puntuacion_Peaton=Puntuacion_Peaton;

            %Correr la función que realiza el suavizado
            Suavizado(app)
            
            app.Datos_interfaz_2=struct2table(Datos_contramedidas,'AsArray',true);
                       
          
            % Segunda parte de la interfaz - Reporte Detallado
            
                % Estilo de la tabla de la clasificación por estrellas 
            app.s1=uistyle('BackgroundColor',[0, 0.5, 0]);
            addStyle(app.UITable_9,app.s1,'cell',[1,1]);
            app.s2=uistyle('BackgroundColor',[1,1,0]);
            addStyle(app.UITable_9,app.s2,'cell',[2,1]);
            app.s3=uistyle('BackgroundColor',[0.9290, 0.6940, 0.1250]);
            addStyle(app.UITable_9,app.s3,'cell',[3,1]);
            app.s4=uistyle('BackgroundColor',[1,0,0]);
            addStyle(app.UITable_9,app.s4,'cell',[4,1]);
            app.s5=uistyle('BackgroundColor',[0,0,0]);
            addStyle(app.UITable_9,app.s5,'cell',[5,1]);
            app.s6=uistyle('BackgroundColor',[0.85 0.85 0.85]);
            addStyle(app.UITable_9,app.s6,'cell',[6,1]);
            app.s7=uistyle('FontColor',[1,1,1]);
            addStyle(app.UITable_9,app.s7,'cell',[5,1]);
            
            app.UITable_9.ColumnFormat={'','bank','bank'};

                % Valores para el Ocupante del vehículo en la
                    % tabla clasificaión por estrellas que se presentar al
                        % inicial la aplicación 
            [D,P,D01,P01,D02,P02,D03,P03,D1,P1,D2,P2,D3,P3,D4,P4,D5,P5,D6,P6,D7,P7,D8,P8,D9,P9,D10,P10,D11,P11,D12,P12,D13,P13,D14,P14,D15,P15,D16,P16,D17,P17,D18,P18,D19,P19,D20,P20]=Usuarios(app.Datos_interfaz_2); 
            Distancia_Total=(D+D1+D2+D3+D4+D5);
            Porcentaje_Total=(P+P1+P2+P3+P4+P5);
            app.Tabla_estrellas_Ocupante={'5 Estrellas',D5,P5;'4 Estrellas',D4,P4;'3 Estrellas',D3,P3;'2 Estrellas',D2,P2;'1 Estrella',D1,P1;'No aplica',D,P;'Total',Distancia_Total,Porcentaje_Total};
            app.UITable_9.Data=app.Tabla_estrellas_Ocupante;
            plot(app.UIAxes_2,cumsum(app.Datos_interfaz_2.Distancia),app.Datos_interfaz_2.Puntuacion_Ocupante_del_vehiculo);
            
            % Valores iniciales para la gráfica 'Mapa'
            load('../mat/preproceso_identificador.mat');
            if identificador==1
               app.Datos_video=load('../mat/preproceso_datos_video.mat');
               y=app.Datos_video.vbo.channels(3).data;
               x=app.Datos_video.vbo.channels(4).data;
               plot(app.UIAxes,x,y,'b');
               hold(app.UIAxes,"on");
            
            c1=sum(arrayfun(@(Nuevos_datos) ~isempty(Nuevos_datos.Nueva_Estrella_Ocupante_vehiculo),Nuevos_datos));
            for i=1:c1
                if Datos_contramedidas.Ocupante_del_vehiculo(i) ==0    
                plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.85 0.85 0.85],'linewidth',3);
                hold(app.UIAxes,"on");
                elseif Datos_contramedidas.Ocupante_del_vehiculo(i) ==1    
                plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'k','linewidth',3);
                hold(app.UIAxes,"on");
                elseif Datos_contramedidas.Ocupante_del_vehiculo(i) ==2
                plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'r','linewidth',3);
                hold(app.UIAxes,"on");
                elseif Datos_contramedidas.Ocupante_del_vehiculo(i) ==3
                plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.9290, 0.6940, 0.1250],'linewidth',3);
                hold(app.UIAxes,"on");
                elseif Datos_contramedidas.Ocupante_del_vehiculo(i) ==4
                plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'y','linewidth',3);
                hold(app.UIAxes,"on");
                elseif Datos_contramedidas.Ocupante_del_vehiculo(i) ==5
                plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0, 0.5, 0],'linewidth',3);
                hold(app.UIAxes,"on");
                end
            end 
            elseif identificador==2
            c1=sum(arrayfun(@(Nuevos_datos) ~isempty(Nuevos_datos.Nueva_Estrella_Ocupante_vehiculo),Nuevos_datos));
            for i=1:c1
                if Datos_contramedidas.Ocupante_del_vehiculo(i) ==0    
                if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                   scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0.85 0.85 0.85],'MarkerEdgeColor',[0.85 0.85 0.85]);
                    hold(app.UIAxes,"on");
                end
                elseif Datos_contramedidas.Ocupante_del_vehiculo(i) ==1    
                if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                   scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','k','MarkerEdgeColor','k');
                    hold(app.UIAxes,"on");
                end
                elseif Datos_contramedidas.Ocupante_del_vehiculo(i) ==2
                if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                   scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','r','MarkerEdgeColor','r');
                    hold(app.UIAxes,"on");
                end
                elseif Datos_contramedidas.Ocupante_del_vehiculo(i) ==3
                if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                   scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0.9290, 0.6940, 0.1250],'MarkerEdgeColor',[0.9290, 0.6940, 0.1250]);
                    hold(app.UIAxes,"on");
                end
                elseif Datos_contramedidas.Ocupante_del_vehiculo(i) ==4
                if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                   scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','y','MarkerEdgeColor','y');
                    hold(app.UIAxes,"on");
                end
                elseif Datos_contramedidas.Ocupante_del_vehiculo(i) ==5
                if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                   scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0, 0.5, 0],'MarkerEdgeColor',[0, 0.5, 0]);
                    hold(app.UIAxes,"on");
                end
                end
            end
                
            end
            
            save('../mat/Datos_contramedidas_postproceso','Datos_contramedidas')
        end

        % Selection changed function: UsuariosvialesButtonGroup
        function UsuariosvialesButtonGroupSelectionChanged(app, event)
            
            %Para el recorrido (Mapa)
            load('../mat/Datos_interfaz_dos_proceso.mat');
            load('../mat/Datos_contramedidas_postproceso.mat');
            %c1=sum(arrayfun(@(Datos_contramedidas) ~isempty(Datos_contramedidas.Tramos),Datos_contramedidas));
            
            %Datos sin suavizar
            %Y=load('ejemplo3.mat'); 
            [D,P,D01,P01,D02,P02,D03,P03,D1,P1,D2,P2,D3,P3,D4,P4,D5,P5,D6,P6,D7,P7,D8,P8,D9,P9,D10,P10,D11,P11,D12,P12,D13,P13,D14,P14,D15,P15,D16,P16,D17,P17,D18,P18,D19,P19,D20,P20] = Usuarios(app.Datos_interfaz_2);
            %Ocupante del vehículo
            Distancia_Total=(D+D1+D2+D3+D4+D5);
            Porcentaje_Total=(P+P1+P2+P3+P4+P5);
            
            app.Tabla_estrellas_Ocupante={'5 Estrellas',D5,P5;'4 Estrellas',D4,P4;'3 Estrellas',D3,P3;'2 Estrellas',D2,P2;'1 Estrella',D1,P1;'No aplica',D,P;'Total',Distancia_Total,Porcentaje_Total;};

            %Motociclista
            Distancia_Total_1=(D01+D6+D7+D8+D9+D10);
            Porcentaje_Total_1=(P01+P6+P7+P8+P9+P10);
            app.Tabla_estrellas_Motociclista={'5 Estrellas',D10,P10;'4 Estrellas',D9,P9;'3 Estrellas',D8,P8;'2 Estrellas',D7,P7;'1 Estrella',D6,P6;'No aplica',D01,P01;'Total',Distancia_Total_1,Porcentaje_Total_1;};

            %Ciclista
            Distancia_Total_2=(D02+D11+D12+D13+D14+D15);
            Porcentaje_Total_2=(P02+P11+P12+P13+P14+P15);
            app.Tabla_estrellas_Cilista={'5 Estrellas',D15,P15;'4 Estrellas',D14,P14;'3 Estrellas',D13,P13;'2 Estrellas',D12,P12;'1 Estrella',D11,P11;'No aplica',D02,P02;'Total',Distancia_Total_2,Porcentaje_Total_2;};

            %Peatón
            Distancia_Total_3=(D03+D16+D17+D18+D19+D20);
            Porcentaje_Total_3=(P03+P16+P17+P18+P19+P20);
            app.Tabla_estrellas_Peaton={'5 Estrellas',D20,P20;'4 Estrellas',D19,P19;'3 Estrellas',D18,P18;'2 Estrellas',D17,P17;'1 Estrella',D16,P16;'No aplica',D03,P03;'Total',Distancia_Total_3,Porcentaje_Total_3;};       
     
            %Datos suavizados
            load('../mat/Datos_suavizado_contramedidas_postproceso.mat')
            %c1=sum(arrayfun(@(Datos_interfaz_dos) ~isempty(Datos_interfaz_dos.Tramos),Datos_interfaz_dos));
            
            [D,P,D01,P01,D02,P02,D03,P03,D1,P1,D2,P2,D3,P3,D4,P4,D5,P5,D6,P6,D7,P7,D8,P8,D9,P9,D10,P10,D11,P11,D12,P12,D13,P13,D14,P14,D15,P15,D16,P16,D17,P17,D18,P18,D19,P19,D20,P20] = Usuarios(Datos_suavizado);
            %Ocupante del vehículo
            Distancia_Total=(D+D1+D2+D3+D4+D5);
            Porcentaje_Total=(P+P1+P2+P3+P4+P5);
            app.Tabla_Nuevaestrellas_Ocupante={'5 Estrellas',D5,P5;'4 Estrellas',D4,P4;'3 Estrellas',D3,P3;'2 Estrellas',D2,P2;'1 Estrella',D1,P1;'No aplica',D,P;'Total',Distancia_Total,Porcentaje_Total;};

            %Motociclista
            Distancia_Total_1=(D01+D6+D7+D8+D9+D10);
            Porcentaje_Total_1=(P01+P6+P7+P8+P9+P10);
            app.Tabla_Nuevaestrellas_Motociclista={'5 Estrellas',D10,P10;'4 Estrellas',D9,P9;'3 Estrellas',D8,P8;'2 Estrellas',D7,P7;'1 Estrella',D6,P6;'No aplica',D01,P01;'Total',Distancia_Total_1,Porcentaje_Total_1;};

            %Ciclista
            Distancia_Total_2=(D02+D11+D12+D13+D14+D15);
            Porcentaje_Total_2=(P02+P11+P12+P13+P14+P15);
            app.Tabla_Nuevaestrellas_Cilista={'5 Estrellas',D15,P15;'4 Estrellas',D14,P14;'3 Estrellas',D13,P13;'2 Estrellas',D12,P12;'1 Estrella',D11,P11;'No aplica',D02,P02;'Total',Distancia_Total_2,Porcentaje_Total_2;};

            %Peatón
            Distancia_Total_3=(D03+D16+D17+D18+D19+D20);
            Porcentaje_Total_3=(P03+P16+P17+P18+P19+P20);
            app.Tabla_Nuevaestrellas_Peaton={'5 Estrellas',D20,P20;'4 Estrellas',D19,P19;'3 Estrellas',D18,P18;'2 Estrellas',D17,P17;'1 Estrella',D16,P16;'No aplica',D03,P03;'Total',Distancia_Total_3,Porcentaje_Total_3;};       

            %Asignaciones de los botones para cada usuario
            V=app.OcupantedevehculosButton.Value;
            M=app.MotociclistaButton.Value;
            C=app.CiclistaButton.Value;
            P=app.PeatnButton.Value;
            
            %Análisis de las condiciones 
            load('../mat/preproceso_identificador.mat');
            if identificador==1
                
            if app.SuavizadoCheckBox.Value ==1 && app.OcupantedevehculosButton.Value ==1
                   app.UITable_9.Data=app.Tabla_Nuevaestrellas_Ocupante;
                   plot(app.UIAxes_2,cumsum(Datos_suavizado.Distancia),Datos_suavizado.Puntuacion_Ocupante_del_vehiculo);
                   for i=1:length(Datos_suavizado.Ocupante_del_vehiculo)
                    if Datos_suavizado.Ocupante_del_vehiculo(i)==0
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.85 0.85 0.85],'linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_suavizado.Ocupante_del_vehiculo(i)==1
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'k','linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_suavizado.Ocupante_del_vehiculo(i) ==2
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'r','linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_suavizado.Ocupante_del_vehiculo(i) ==3
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.9290, 0.6940, 0.1250],'linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_suavizado.Ocupante_del_vehiculo(i) ==4
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'y','linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_suavizado.Ocupante_del_vehiculo(i) ==5
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0, 0.5, 0],'linewidth',3);
                    hold(app.UIAxes,"on");
                    end
                   end
            end
            if app.SuavizadoCheckBox.Value ==0 && V==1
                app.UITable_9.Data=app.Tabla_estrellas_Ocupante;
                plot(app.UIAxes_2,cumsum(app.Datos_interfaz_2.Distancia),app.Datos_interfaz_2.Puntuacion_Ocupante_del_vehiculo);
                for i=1:length(Datos_contramedidas.Ocupante_del_vehiculo)
                    if Datos_contramedidas.Ocupante_del_vehiculo(i) ==0    
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.85 0.85 0.85],'linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_contramedidas.Ocupante_del_vehiculo(i) ==1    
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'k','linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_contramedidas.Ocupante_del_vehiculo(i) ==2
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'r','linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_contramedidas.Ocupante_del_vehiculo(i) ==3
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.9290, 0.6940, 0.1250],'linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_contramedidas.Ocupante_del_vehiculo(i) ==4
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'y','linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_contramedidas.Ocupante_del_vehiculo(i) ==5
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0, 0.5, 0],'linewidth',3);
                    hold(app.UIAxes,"on");
                    end
                end
            end
            if app.SuavizadoCheckBox.Value ==1 && app.MotociclistaButton.Value ==1
                   app.UITable_9.Data=app.Tabla_Nuevaestrellas_Motociclista;
                   plot(app.UIAxes_2,cumsum(Datos_suavizado.Distancia),Datos_suavizado.Puntuacion_Motociclista);
                   for i=1:length(Datos_suavizado.Motociclista)
                    if Datos_suavizado.Motociclista(i)==0
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.85 0.85 0.85],'linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_suavizado.Motociclista(i)==1
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'k','linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_suavizado.Motociclista(i) ==2
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'r','linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_suavizado.Motociclista(i) ==3
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.9290, 0.6940, 0.1250],'linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_suavizado.Motociclista(i) ==4
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'y','linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_suavizado.Motociclista(i) ==5
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0, 0.5, 0],'linewidth',3);
                    hold(app.UIAxes,"on");
                    end
                   end
                   end
            if app.SuavizadoCheckBox.Value ==0 && M==1
                app.UITable_9.Data=app.Tabla_estrellas_Motociclista;
                plot(app.UIAxes_2,cumsum(app.Datos_interfaz_2.Distancia),app.Datos_interfaz_2.Puntuacion_Motociclista);
                for i=1:length(Datos_contramedidas.Motociclista)
                    if Datos_contramedidas.Motociclista(i) ==0    
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.85 0.85 0.85],'linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_contramedidas.Motociclista(i) ==1    
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'k','linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_contramedidas.Motociclista(i) ==2
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'r','linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_contramedidas.Motociclista(i) ==3
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.9290, 0.6940, 0.1250],'linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_contramedidas.Motociclista(i) ==4
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'y','linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_contramedidas.Motociclista(i) ==5
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0, 0.5, 0],'linewidth',3);
                    hold(app.UIAxes,"on");
                    end
                end
            end
            
            if app.SuavizadoCheckBox.Value ==1 && app.CiclistaButton.Value ==1
                   app.UITable_9.Data=app.Tabla_Nuevaestrellas_Cilista;
                   plot(app.UIAxes_2,cumsum(Datos_suavizado.Distancia),Datos_suavizado.Puntuacion_Ciclista);
                   for i=1:length(Datos_suavizado.Ciclista)
                    if Datos_suavizado.Ciclista(i)==0
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.85 0.85 0.85],'linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_suavizado.Ciclista(i)==1
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'k','linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_suavizado.Ciclista(i) ==2
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'r','linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_suavizado.Ciclista(i) ==3
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.9290, 0.6940, 0.1250],'linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_suavizado.Ciclista(i) ==4
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'y','linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_suavizado.Ciclista(i) ==5
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0, 0.5, 0],'linewidth',3);
                    hold(app.UIAxes,"on");
                    end
                   end     
                   end
            if app.SuavizadoCheckBox.Value ==0 && C==1
                app.UITable_9.Data=app.Tabla_estrellas_Cilista;
                plot(app.UIAxes_2,cumsum(app.Datos_interfaz_2.Distancia),app.Datos_interfaz_2.Puntuacion_Ciclista);
                for i=1:length(Datos_contramedidas.Ciclista)
                    if Datos_contramedidas.Ciclista(i) ==0    
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.85 0.85 0.85],'linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_contramedidas.Ciclista(i) ==1    
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'k','linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_contramedidas.Ciclista(i) ==2
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'r','linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_contramedidas.Ciclista(i) ==3
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.9290, 0.6940, 0.1250],'linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_contramedidas.Ciclista(i) ==4
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'y','linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_contramedidas.Ciclista(i) ==5
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0, 0.5, 0],'linewidth',3);
                    hold(app.UIAxes,"on");
                    end
                end
            end
            
            if app.SuavizadoCheckBox.Value ==1 && app.PeatnButton.Value ==1
                   app.UITable_9.Data=app.Tabla_Nuevaestrellas_Peaton;
                   plot(app.UIAxes_2,cumsum(Datos_suavizado.Distancia),Datos_suavizado.Puntuacion_Peaton); 
                   for i=1:length(Datos_suavizado.Peaton)
                    if Datos_suavizado.Peaton(i)==0
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.85 0.85 0.85],'linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_suavizado.Peaton(i)==1
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'k','linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_suavizado.Peaton(i) ==2
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'r','linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_suavizado.Peaton(i) ==3
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.9290, 0.6940, 0.1250],'linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_suavizado.Peaton(i) ==4
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'y','linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_suavizado.Peaton(i) ==5
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0, 0.5, 0],'linewidth',3);
                    hold(app.UIAxes,"on");
                    end
                   end
                   end 
            
            if app.SuavizadoCheckBox.Value==0 && P==1
                app.UITable_9.Data=app.Tabla_estrellas_Peaton; 
                plot(app.UIAxes_2,cumsum(app.Datos_interfaz_2.Distancia),app.Datos_interfaz_2.Puntuacion_Peaton);
                for i=1:length(Datos_contramedidas.Peaton)
                    if Datos_contramedidas.Peaton(i) ==0    
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.85 0.85 0.85],'linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_contramedidas.Peaton(i) ==1    
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'k','linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_contramedidas.Peaton(i) ==2
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'r','linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_contramedidas.Peaton(i) ==3
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.9290, 0.6940, 0.1250],'linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_contramedidas.Peaton(i) ==4
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'y','linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_contramedidas.Peaton(i) ==5
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0, 0.5, 0],'linewidth',3);
                    hold(app.UIAxes,"on");
                    end
                end
            end
            elseif identificador==2
                if app.SuavizadoCheckBox.Value ==1 && app.OcupantedevehculosButton.Value ==1
                   app.UITable_9.Data=app.Tabla_Nuevaestrellas_Ocupante;
                   plot(app.UIAxes_2,cumsum(Datos_suavizado.Distancia),Datos_suavizado.Puntuacion_Ocupante_del_vehiculo);
                   for i=1:length(Datos_suavizado.Ocupante_del_vehiculo)
                    if Datos_suavizado.Ocupante_del_vehiculo(i)==0
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0.85 0.85 0.85],'MarkerEdgeColor',[0.85 0.85 0.85]);
                        hold(app.UIAxes,"on");
                    end
                    elseif Datos_suavizado.Ocupante_del_vehiculo(i)==1
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','k','MarkerEdgeColor','k');
                        hold(app.UIAxes,"on");
                    end
                    elseif Datos_suavizado.Ocupante_del_vehiculo(i) ==2
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','r','MarkerEdgeColor','r');
                        hold(app.UIAxes,"on");
                    end
                    elseif Datos_suavizado.Ocupante_del_vehiculo(i) ==3
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0.9290, 0.6940, 0.1250],'MarkerEdgeColor',[0.9290, 0.6940, 0.1250]);
                        hold(app.UIAxes,"on");
                    end
                    elseif Datos_suavizado.Ocupante_del_vehiculo(i) ==4
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','y','MarkerEdgeColor','y');
                        hold(app.UIAxes,"on");
                    end
                    elseif Datos_suavizado.Ocupante_del_vehiculo(i) ==5
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0, 0.5, 0],'MarkerEdgeColor',[0, 0.5, 0]);
                        hold(app.UIAxes,"on");
                    end
                    end
                   end
            end
            if app.SuavizadoCheckBox.Value ==0 && V==1
                app.UITable_9.Data=app.Tabla_estrellas_Ocupante;
                plot(app.UIAxes_2,cumsum(app.Datos_interfaz_2.Distancia),app.Datos_interfaz_2.Puntuacion_Ocupante_del_vehiculo);
                for i=1:length(Datos_contramedidas.Ocupante_del_vehiculo)
                    if Datos_contramedidas.Ocupante_del_vehiculo(i) ==0    
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0.85 0.85 0.85],'MarkerEdgeColor',[0.85 0.85 0.85]);
                        hold(app.UIAxes,"on");
                    end
                    elseif Datos_contramedidas.Ocupante_del_vehiculo(i) ==1    
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','k','MarkerEdgeColor','k');
                        hold(app.UIAxes,"on");
                    end
                    elseif Datos_contramedidas.Ocupante_del_vehiculo(i) ==2
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','r','MarkerEdgeColor','r');
                        hold(app.UIAxes,"on");
                    end
                    elseif Datos_contramedidas.Ocupante_del_vehiculo(i) ==3
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0.9290, 0.6940, 0.1250],'MarkerEdgeColor',[0.9290, 0.6940, 0.1250]);
                        hold(app.UIAxes,"on");
                    end
                    elseif Datos_contramedidas.Ocupante_del_vehiculo(i) ==4
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','y','MarkerEdgeColor','y');
                        hold(app.UIAxes,"on");
                    end
                    elseif Datos_contramedidas.Ocupante_del_vehiculo(i) ==5
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0, 0.5, 0],'MarkerEdgeColor',[0, 0.5, 0]);
                        hold(app.UIAxes,"on");
                    end
                    end
                end
            end
            if app.SuavizadoCheckBox.Value ==1 && app.MotociclistaButton.Value ==1
                   app.UITable_9.Data=app.Tabla_Nuevaestrellas_Motociclista;
                   plot(app.UIAxes_2,cumsum(Datos_suavizado.Distancia),Datos_suavizado.Puntuacion_Motociclista);
                   for i=1:length(Datos_suavizado.Motociclista)
                    if Datos_suavizado.Motociclista(i)==0
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0.85 0.85 0.85],'MarkerEdgeColor',[0.85 0.85 0.85]);
                        hold(app.UIAxes,"on");
                    end
                    elseif Datos_suavizado.Motociclista(i)==1
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','k','MarkerEdgeColor','k');
                        hold(app.UIAxes,"on");
                    end
                    elseif Datos_suavizado.Motociclista(i) ==2
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','r','MarkerEdgeColor','r');
                       hold(app.UIAxes,"on");
                    end
                    elseif Datos_suavizado.Motociclista(i) ==3
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0.9290, 0.6940, 0.1250],'MarkerEdgeColor',[0.9290, 0.6940, 0.1250]);
                        hold(app.UIAxes,"on");
                    end
                    elseif Datos_suavizado.Motociclista(i) ==4
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','y','MarkerEdgeColor','y');
                        hold(app.UIAxes,"on");
                    end
                    elseif Datos_suavizado.Motociclista(i) ==5
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0, 0.5, 0],'MarkerEdgeColor',[0, 0.5, 0]);
                        hold(app.UIAxes,"on");
                    end
                    end
                   end
                   end
            if app.SuavizadoCheckBox.Value ==0 && M==1
                app.UITable_9.Data=app.Tabla_estrellas_Motociclista;
                plot(app.UIAxes_2,cumsum(app.Datos_interfaz_2.Distancia),app.Datos_interfaz_2.Puntuacion_Motociclista);
                for i=1:length(Datos_contramedidas.Motociclista)
                    if Datos_contramedidas.Motociclista(i) ==0    
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0.85 0.85 0.85],'MarkerEdgeColor',[0.85 0.85 0.85]);
                        hold(app.UIAxes,"on");
                    end
                    elseif Datos_contramedidas.Motociclista(i) ==1    
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','k','MarkerEdgeColor','k');
                        hold(app.UIAxes,"on");
                    end
                    elseif Datos_contramedidas.Motociclista(i) ==2
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','r','MarkerEdgeColor','r');
                        hold(app.UIAxes,"on");
                    end
                    elseif Datos_contramedidas.Motociclista(i) ==3
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0.9290, 0.6940, 0.1250],'MarkerEdgeColor',[0.9290, 0.6940, 0.1250]);
                        hold(app.UIAxes,"on");
                    end
                    elseif Datos_contramedidas.Motociclista(i) ==4
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','y','MarkerEdgeColor','y');
                        hold(app.UIAxes,"on");
                    end
                    elseif Datos_contramedidas.Motociclista(i) ==5
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0, 0.5, 0],'MarkerEdgeColor',[0, 0.5, 0]);
                        hold(app.UIAxes,"on");
                    end
                    end
                end
            end
            
            if app.SuavizadoCheckBox.Value ==1 && app.CiclistaButton.Value ==1
                   app.UITable_9.Data=app.Tabla_Nuevaestrellas_Cilista;
                   plot(app.UIAxes_2,cumsum(Datos_suavizado.Distancia),Datos_suavizado.Puntuacion_Ciclista);
                   for i=1:length(Datos_suavizado.Ciclista)
                    if Datos_suavizado.Ciclista(i)==0
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0.85 0.85 0.85],'MarkerEdgeColor',[0.85 0.85 0.85]);
                        hold(app.UIAxes,"on");
                    end
                    elseif Datos_suavizado.Ciclista(i)==1
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','k','MarkerEdgeColor','k');
                        hold(app.UIAxes,"on");
                    end
                    elseif Datos_suavizado.Ciclista(i) ==2
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','r','MarkerEdgeColor','r');
                        hold(app.UIAxes,"on");
                    end
                    elseif Datos_suavizado.Ciclista(i) ==3
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0.9290, 0.6940, 0.1250],'MarkerEdgeColor',[0.9290, 0.6940, 0.1250]);
                        hold(app.UIAxes,"on");
                    end
                    elseif Datos_suavizado.Ciclista(i) ==4
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','y','MarkerEdgeColor','y');
                        hold(app.UIAxes,"on");
                    end
                    elseif Datos_suavizado.Ciclista(i) ==5
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0, 0.5, 0],'MarkerEdgeColor',[0, 0.5, 0]);
                        hold(app.UIAxes,"on");
                    end
                    end
                   end     
                   end
            if app.SuavizadoCheckBox.Value ==0 && C==1
                app.UITable_9.Data=app.Tabla_estrellas_Cilista;
                plot(app.UIAxes_2,cumsum(app.Datos_interfaz_2.Distancia),app.Datos_interfaz_2.Puntuacion_Ciclista);
                for i=1:length(Datos_contramedidas.Ciclista)
                    if Datos_contramedidas.Ciclista(i) ==0    
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0.85 0.85 0.85],'MarkerEdgeColor',[0.85 0.85 0.85]);
                        hold(app.UIAxes,"on");
                    end
                    elseif Datos_contramedidas.Ciclista(i) ==1    
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','k','MarkerEdgeColor','k');
                        hold(app.UIAxes,"on");
                    end
                    elseif Datos_contramedidas.Ciclista(i) ==2
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','r','MarkerEdgeColor','r');
                        hold(app.UIAxes,"on");
                    end
                    elseif Datos_contramedidas.Ciclista(i) ==3
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0.9290, 0.6940, 0.1250],'MarkerEdgeColor',[0.9290, 0.6940, 0.1250]);
                        hold(app.UIAxes,"on");
                    end
                    elseif Datos_contramedidas.Ciclista(i) ==4
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','y','MarkerEdgeColor','y');
                        hold(app.UIAxes,"on");
                    end
                    elseif Datos_contramedidas.Ciclista(i) ==5
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0, 0.5, 0],'MarkerEdgeColor',[0, 0.5, 0]);
                        hold(app.UIAxes,"on");
                    end
                    end
                end
            end
            
            if app.SuavizadoCheckBox.Value ==1 && app.PeatnButton.Value ==1
                   app.UITable_9.Data=app.Tabla_Nuevaestrellas_Peaton;
                   plot(app.UIAxes_2,cumsum(Datos_suavizado.Distancia),Datos_suavizado.Puntuacion_Peaton); 
                   for i=1:length(Datos_suavizado.Peaton)
                    if Datos_suavizado.Peaton(i)==0
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0.85 0.85 0.85],'MarkerEdgeColor',[0.85 0.85 0.85]);
                        hold(app.UIAxes,"on");
                    end
                    elseif Datos_suavizado.Peaton(i)==1
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','k','MarkerEdgeColor','k');
                        hold(app.UIAxes,"on");
                    end
                    elseif Datos_suavizado.Peaton(i) ==2
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','r','MarkerEdgeColor','r');
                        hold(app.UIAxes,"on");
                    end
                    elseif Datos_suavizado.Peaton(i) ==3
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0.9290, 0.6940, 0.1250],'MarkerEdgeColor',[0.9290, 0.6940, 0.1250]);
                        hold(app.UIAxes,"on");
                    end
                    elseif Datos_suavizado.Peaton(i) ==4
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','y','MarkerEdgeColor','y');
                        hold(app.UIAxes,"on");
                    end
                    elseif Datos_suavizado.Peaton(i) ==5
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0, 0.5, 0],'MarkerEdgeColor',[0, 0.5, 0]);
                        hold(app.UIAxes,"on");
                    end
                    end
                   end
                   end 
            
            if app.SuavizadoCheckBox.Value==0 && P==1
                app.UITable_9.Data=app.Tabla_estrellas_Peaton; 
                plot(app.UIAxes_2,cumsum(app.Datos_interfaz_2.Distancia),app.Datos_interfaz_2.Puntuacion_Peaton);
                for i=1:length(Datos_contramedidas.Peaton)
                    if Datos_contramedidas.Peaton(i) ==0    
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0.85 0.85 0.85],'MarkerEdgeColor',[0.85 0.85 0.85]);
                        hold(app.UIAxes,"on");
                    end
                    elseif Datos_contramedidas.Peaton(i) ==1    
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','k','MarkerEdgeColor','k');
                        hold(app.UIAxes,"on");
                    end
                    elseif Datos_contramedidas.Peaton(i) ==2
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','r','MarkerEdgeColor','r');
                        hold(app.UIAxes,"on");
                    end
                    elseif Datos_contramedidas.Peaton(i) ==3
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0.9290, 0.6940, 0.1250],'MarkerEdgeColor',[0.9290, 0.6940, 0.1250]);
                        hold(app.UIAxes,"on");
                    end
                    elseif Datos_contramedidas.Peaton(i) ==4
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','y','MarkerEdgeColor','y');
                        hold(app.UIAxes,"on");
                    end
                    elseif Datos_contramedidas.Peaton(i) ==5
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0, 0.5, 0],'MarkerEdgeColor',[0, 0.5, 0]);
                        hold(app.UIAxes,"on");
                    end
                    end
                end
            end
            end
            
        end

        % Button pushed function: PDFButton_3
        function PDFButton_3Pushed(app, event)

            UsuariosvialesButtonGroupSelectionChanged(app)
            SuavizadoCheckBoxValueChanged(app)
            load('../mat/Datos_interfaz_dos_proceso.mat');
            load('../mat/Datos_contramedidas_postproceso.mat');
            %c1=sum(arrayfun(@(Datos_contramedidas) ~isempty(Datos_contramedidas.Tramos),Datos_contramedidas));
            
            
            load('../mat/preproceso_identificador.mat');
            if identificador==1
                load('../mat/preproceso_datos_video.mat');
                y=vbo.channels(3).data;
                x=vbo.channels(4).data;
            end
            
            load('../mat/Datos_suavizado_contramedidas_postproceso.mat');
            Infinicial=load('../mat/Inf_proyecto.mat');
            
            %Para la tabla resumen de las contramedidas
            load('../mat/Matriz_resumen_contramedidas_postproceso.mat');
            
            [Tabla_resumen] = Contramedidas(Datos_Tcontramedidas);
            
            % Reporte 
            import mlreportgen.report.*
            import mlreportgen.dom.*
            rpt = Report('Reporte de la aplicación de las contramedidas', 'pdf');
            
            %Portada
            Tstyle={HAlign('center'),Bold(1),FontFamily('Gotham'),...
             FontSize('20pt'),Color('Black'),...
             OuterMargin('0in','0in','.5in','.5in'),...
             HAlign('center')};             

            titlepg = TitlePage();
            tp = Paragraph('UNIVERSIDAD TÉCNICA PARTICULAR DE LOJA');
            tp.Style=Tstyle;
            titlepg.Title = tp;
            
            a=Paragraph('Sistema Integrado para La Valoración VIAl (SILVVIA)');
            a.Style=Tstyle;
            titlepg.Subtitle = a;
            
            a1=Paragraph('Reporte detallado de los resultados de la clasificación por estrellas aplicadas las contramedidas');
            a1.Style=Tstyle;
            titlepg.Author = a1;
            
            add(rpt,titlepg)
            
            %Tabla de contenidos
            add(rpt,TableOfContents);

            %Capítulos
            %Capítulo 1-Descargo de responsabilidad
            chap0 = Chapter('Descargo de responsabilidad');
            p=Paragraph('El software SILVVIA y su documentación es propiedad intelectual de la Universidad Técnica Particular de Loja (UTPL). SILVVIA fue desarrollado para estimar el nivel de seguridad que ofrece las carreteras rurales utilizando el método iRAP. El usuario comprende los supuestos de la metodología utilizada en el software. La interpretación de los resultados de SILVVIA son de exclusiva responsabilidad del usuario. En ningún caso la UTPL será responsable por daños directos, indirectos, especiales, incidentales o consecuentes que surjan a partir del uso del programa y/o documentación asociada. El usuario comprende que SILVVIA es un software para fines educativos e informativos, los fines comerciales sin el permiso por escrito de los titulares de los derechos de autor está prohibida. Si no está satisfecho con alguna parte del software SILVVIA o con alguno de estos términos de uso, su único y exclusivo recurso es dejar de usar el programa. ');
            p.FontFamilyName='Gotham';
            p.HAlign='justify';
            add(chap0,p)
            add(rpt,chap0);
            
            
            %Capítulo 2-Información del proyecto
            chap = Chapter('Información del archivo');
            
            Ubi1='Nombre del proyecto:';
            Ubi2=Infinicial.T_proyecto;
            p1=Paragraph(strcat(Ubi1," ",Ubi2));
            p1.FontFamilyName='Gotham';
            add(chap,p1);
            
            Ubi1='Ubicación del proyecto:';
            Ubi2=Infinicial.Lugar;
            p1=Paragraph(strcat(Ubi1," ",Ubi2));
            p1.FontFamilyName='Gotham';
            add(chap,p1);

            LonTotal1='Longitud total del proyecto:';
            LonTotal2=num2str(sum(app.Datos_interfaz_2.Distancia)*(1/1000));
            LonTotal3='Kilómetros';
            p1=Paragraph(strcat(LonTotal1," ",LonTotal2," ",LonTotal3));
            p1.FontFamilyName='Gotham';
            add(chap,p1);

            fecha1='Fecha de elaboración del análisis:';
            fecha2=num2str(date());
            p1=Paragraph(strcat(fecha1," ",fecha2));
            p1.FontFamilyName='Gotham';
            add(chap,p1);
            
            Des1='Descripción del proyecto:';
            Des2=Infinicial.Descrip;
            p1=Paragraph(strcat(Des1," ",Des2));
            p1.FontFamilyName='Gotham';
            add(chap,p1);
            add(rpt,chap);
            
            %Estilo de tabla establecido
            tableStyle = { ...
                Width('100%'), ...
                Border('solid','black','1px'), ...
                ColSep('solid','black','1px'), ...
                FontFamily('Gotham'),...
                Hyphenation(false),...
                RowSep('solid','black','1px') ...
                };
            %Ancho de las tablas
            grps(1) = TableColSpecGroup;
            grps(1).Span = 3;
            
            specs(1) = TableColSpec;
            specs(1).Span = 1;
            specs(1).Style = {Width("64%"),HAlign('left')};
            
            specs(2) = TableColSpec;
            specs(2).Span = 2;
            specs(2).Style = {Width("18%"),HAlign('center')};
            
            grps(1).ColSpecs = specs;
            
            %Estilo establecido para los encabezados de las tablas
            HeaderStyle = {Bold(),BackgroundColor('lightgray'),FontSize('10pt'),FontFamily('Gotham')};
            
            %Estilo establecido para el cuerpo de la tabla
            BodyStyle={BackgroundColor('paleturquoise'),Color('black'),FontSize('10pt'),FontFamily('Gotham'),HAlign('left')};
            
            
           %Capítulo 3 - Aplicación de contramedidas
           chap1 = Chapter('Aplicación de contramedidas');
           
           p1=Paragraph('En la siguiente tabla se indica las contramedidas implementadas a lo largo de la carretera analizada');
           p1.FontFamilyName='Gotham';
           p1.HAlign='justify';
           add(chap1,p1);
           add(chap1,Paragraph(''));
           
           Variable1=Tabla_resumen.Contramedidas;
           Variable2=Tabla_resumen.Longitud_total;
           Variable3=Tabla_resumen.Porcentaje_total;
           ContramediasTabla1=table(categorical(Variable1),f(Variable2),f(Variable3));
           ContramediasTabla1.Properties.VariableNames = {'Contramedidas propuestas','Longitud de la carretera (km)','% de la carretera'};
           Tabla2x=FormalTable(ContramediasTabla1);
           Tabla2x.ColSpecGroups = grps;
           Tabla2x.TableEntriesVAlign = 'middle';
           Tabla2x.TableEntriesHAlign = 'center';
           Tabla2x.Style = tableStyle;
           Tabla2x.Header.Children(1).Style =HeaderStyle;
           Tabla2x.Body.Style=BodyStyle;
           add(chap1,Tabla2x);
           add(chap1,Paragraph(''))
           add(rpt,chap1);
            
           %Capítulo 4 - Clasifiación por estrellas de la carretera
           chap2 = Chapter('Clasificación por estrellas de la carretera');
           
            %Ocupantes de vehículos
           
            p1=Paragraph('Ocupantes de vehículos');
            p1.Bold=1;
            p1.FontFamilyName='Gotham';
            add(chap2,p1)
            p1=Paragraph('a) Resumen de la clasificación por estrellas (SR) de la carretera analizada');
            p1.Underline='Single';
            add(chap2,p1);
            p1=Paragraph('En la siguiente tabla se indica la clasificación por estrellas sin suavizar para ');
            p1.FontFamilyName='Gotham';
            p1.HAlign='justify';
            tex1= Text('ocupantes de vehículos.');
            tex1.Style = {Bold(true)};
            append(p1,tex1);
            add(chap2,p1);
            add(chap2,Paragraph(''));
            
            Nombres=app.Tabla_estrellas_Ocupante(1:7,1);
            E=cell2mat(app.Tabla_estrellas_Ocupante(1:7,2));
            F=cell2mat(app.Tabla_estrellas_Ocupante(1:7,3));
            T=table(categorical(Nombres(:,1)),f(E),f(F));
            T.Properties.VariableNames = {'Clasificación por estrellas', 'Longitud (km)', 'Porcentaje (%)'};
            Tablax=FormalTable(T);
            Tablax.TableEntriesHAlign = 'center';
            Tablax.Style = tableStyle;
            Tablax.Header.Children(1).Style =HeaderStyle;
            Tablax.Body.Style=BodyStyle;
            add(chap2,Tablax);
            add(chap2,Paragraph(''))


            p1=Paragraph('En la siguiente tabla se indica la clasificación por estrellas suavizada para ');
            p1.FontFamilyName='Gotham';
            p1.HAlign='justify';
            tex1= Text('ocupantes de vehículos.');
            tex1.Style = {Bold(true)};
            append(p1,tex1);
            add(chap2,p1);
            add(chap2,Paragraph(''))
            
            Nombres1=app.Tabla_Nuevaestrellas_Ocupante(1:7,1);
            E1=cell2mat(app.Tabla_Nuevaestrellas_Ocupante(1:7,2));
            F1=cell2mat(app.Tabla_Nuevaestrellas_Ocupante(1:7,3));
            T1=table(categorical(Nombres1(:,1)),f(E1),f(F1));
            T1.Properties.VariableNames = {'Clasificación por estrellas', 'Longitud (km)', 'Porcentaje (%)'};
            Tablax=FormalTable(T1);
            Tablax.TableEntriesHAlign = 'center';
            Tablax.Style = tableStyle;
            Tablax.Header.Children(1).Style =HeaderStyle;
            Tablax.Body.Style=BodyStyle;
            add(chap2,Tablax);
            add(chap2,Paragraph(''))
           
            p2=Paragraph('b) Puntaje de clasificación por estrellas (SRS) a lo largo de la carretera analizada ');
            p2.Underline='Single';
            p2.FontFamilyName='Gotham';
            add(chap2,p2);
            p2=Paragraph('A continuación, se muestra en la figura, los SRS (línea gris) de cada sección del tramo en análisis. Los SRS suavizados (línea azul) es el promedio de los SRS en distancias más largas que se realizan con el fin de producir resultados más significativos. En la suavización se realizan dos procedimientos, media móvil con un ancho de ventana de 9, y luego una suavización exponencial con un valor alfa de 0,1.');
            p2.FontFamilyName='Gotham';
            p2.HAlign='justify';
            add(chap2,p2);
            
            fig1 = figure('visible','off');
            ax = axes(fig1,"FontSize",10);
            plot(cumsum(app.Datos_interfaz_2.Distancia),app.Datos_interfaz_2.Puntuacion_Ocupante_del_vehiculo,'color',[0.7 0.7 0.7],'LineWidth',1);
            xlabel("Distancia (m)");
            ylabel('Puntaje de clasificación por estrellas (SRS)');
            title("Puntaje de clasificación por estrellas (SRS) a lo largo de la carretera analizada");
            hold on
            plot(cumsum(Datos_suavizado.Distancia),Datos_suavizado.Puntuacion_Ocupante_del_vehiculo,'b','LineWidth',1)
            hold off
            add(chap2,Figure(fig1))
            
            p3=Paragraph('c) Ubicación de los SRS (datos sin suavización y suavizados) en la carretera analizada');
            p3.Underline='Single';
            p3.FontFamilyName='Gotham';
            add(chap2,p3);
            p3=Paragraph('En la siguiente gráfica se muestra el mapa del recorrido de la carretera analizada sin suavizar los datos.');
            p3.FontFamilyName='Gotham';
            p3.HAlign='justify';
            add(chap2,p3);
            
            if identificador==1
            fig = figure('visible','off');
            plot(x,y,'-r','LineWidth',1,...
                'MarkerEdgeColor','k',...
                'MarkerFaceColor','g',...
                'MarkerSize',10)
            xlabel("Longitud");
            ylabel('Latitud');
            title("Ubicación de los SRS sin suavización en la carretera analizada");
            hold on
            for i=1:length(Datos_contramedidas.Ocupante_del_vehiculo)
                if Datos_contramedidas.Ocupante_del_vehiculo(i) ==0    
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.85 0.85 0.85],'linewidth',2);
                elseif Datos_contramedidas.Ocupante_del_vehiculo(i) ==1
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'k','linewidth',2);
                elseif Datos_contramedidas.Ocupante_del_vehiculo(i) ==2
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'r','linewidth',2);
                elseif Datos_contramedidas.Ocupante_del_vehiculo(i) ==3
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.9290, 0.6940, 0.1250],'linewidth',2);
                elseif Datos_contramedidas.Ocupante_del_vehiculo(i) ==4
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'y','linewidth',2);
                elseif Datos_contramedidas.Ocupante_del_vehiculo(i) ==5
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0, 0.5, 0],'linewidth',2);
                end
            end
            hold on 
            h = zeros(6, 1);
            h(1) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1), 'Color',[0.85 0.85 0.85],'visible', 'on');
            h(2) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'k','visible', 'on');
            h(3) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'r','visible', 'on');
            h(4) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1), 'Color',[0.9290, 0.6940, 0.1250],'visible', 'on');
            h(5) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'y','visible', 'on');
            h(6) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'Color',[0, 0.5, 0],'visible', 'on');
            hold off
            legend(h, 'No aplica','1 estrella','2 estrellas','3 estrellas','4 estrellas','5 estrellas','Location','northwest','FontSize',10);
            add(chap2,Figure(fig));
            
            elseif identificador==2
            fig = figure('visible','off');
            xlabel("Longitud");
            ylabel('Latitud');
            title("Ubicación de los SRS sin suavización en la carretera analizada");
            hold on
            for i=1:length(Datos_contramedidas.Ocupante_del_vehiculo)
                    if Datos_contramedidas.Ocupante_del_vehiculo(i) ==0    
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0.85 0.85 0.85],'MarkerEdgeColor',[0.85 0.85 0.85]);
                    end
                    elseif Datos_contramedidas.Ocupante_del_vehiculo(i) ==1    
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','k','MarkerEdgeColor','k');
                    end
                    elseif Datos_contramedidas.Ocupante_del_vehiculo(i) ==2
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','r','MarkerEdgeColor','r');
                    end
                    elseif Datos_contramedidas.Ocupante_del_vehiculo(i) ==3
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0.9290, 0.6940, 0.1250],'MarkerEdgeColor',[0.9290, 0.6940, 0.1250]);
                    end
                    elseif Datos_contramedidas.Ocupante_del_vehiculo(i) ==4
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','y','MarkerEdgeColor','y');
                    end
                    elseif Datos_contramedidas.Ocupante_del_vehiculo(i) ==5
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0, 0.5, 0],'MarkerEdgeColor',[0, 0.5, 0]);
                    end
                    end
            end
            hold on 
            h = zeros(6, 1);
            h(1) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1), 'Color',[0.85 0.85 0.85],'visible', 'on');
            h(2) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'k','visible', 'on');
            h(3) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'r','visible', 'on');
            h(4) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1), 'Color',[0.9290, 0.6940, 0.1250],'visible', 'on');
            h(5) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'y','visible', 'on');
            h(6) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'Color',[0, 0.5, 0],'visible', 'on');
            hold off
            legend(h, 'No aplica','1 estrella','2 estrellas','3 estrellas','4 estrellas','5 estrellas','Location','northwest','FontSize',10);
            add(chap2,Figure(fig));
            end
            
            
            p3=Paragraph('En la siguiente gráfica se muestra el mapa del recorrido de la carretera analizada con los datos suavizados.');
            p3.FontFamilyName='Gotham';
            add(chap2,p3);
            
            if identificador==1
            fig = figure('visible','off');
            plot(x,y,'-r','LineWidth',1,...
                'MarkerEdgeColor','k',...
                'MarkerFaceColor','g',...
                'MarkerSize',10)
            xlabel("Longitud");
            ylabel('Latitud');
            title("Ubicación de los SRS suavizados en la carretera analizada");
            hold on
            for i=1:length(Datos_suavizado.Ocupante_del_vehiculo)
                    if Datos_suavizado.Ocupante_del_vehiculo(i)==0
                    plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.85 0.85 0.85],'linewidth',2);
                    elseif Datos_suavizado.Ocupante_del_vehiculo(i)==1
                    plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'k','linewidth',2);
                    elseif Datos_suavizado.Ocupante_del_vehiculo(i) ==2
                    plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'r','linewidth',2);
                    elseif Datos_suavizado.Ocupante_del_vehiculo(i) ==3
                    plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.9290, 0.6940, 0.1250],'linewidth',2);
                    elseif Datos_suavizado.Ocupante_del_vehiculo(i) ==4
                    plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'y','linewidth',2);
                    elseif Datos_suavizado.Ocupante_del_vehiculo(i) ==5
                    plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0, 0.5, 0],'linewidth',2);
                    end
            end
            hold on 
            h = zeros(6, 1);
            h(1) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1), 'Color',[0.85 0.85 0.85],'visible', 'on');
            h(2) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'k','visible', 'on');
            h(3) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'r','visible', 'on');
            h(4) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1), 'Color',[0.9290, 0.6940, 0.1250],'visible', 'on');
            h(5) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'y','visible', 'on');
            h(6) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'Color',[0, 0.5, 0],'visible', 'on');
            hold off
            legend(h, 'No aplica','1 estrella','2 estrellas','3 estrellas','4 estrellas','5 estrellas','Location','northwest','FontSize',10);
            add(chap2,Figure(fig));
            elseif identificador==2
            fig = figure('visible','off');
            xlabel("Longitud");
            ylabel('Latitud');
            title("Ubicación de los SRS suavizados en la carretera analizada");
            hold on
            for i=1:length(Datos_suavizado.Ocupante_del_vehiculo)
                    if Datos_suavizado.Ocupante_del_vehiculo(i)==0
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0.85 0.85 0.85],'MarkerEdgeColor',[0.85 0.85 0.85]);
                    end
                    elseif Datos_suavizado.Ocupante_del_vehiculo(i)==1
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','k','MarkerEdgeColor','k');
                    end
                    elseif Datos_suavizado.Ocupante_del_vehiculo(i) ==2
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','r','MarkerEdgeColor','r');
                    end
                    elseif Datos_suavizado.Ocupante_del_vehiculo(i) ==3
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0.9290, 0.6940, 0.1250],'MarkerEdgeColor',[0.9290, 0.6940, 0.1250]);
                    end
                    elseif Datos_suavizado.Ocupante_del_vehiculo(i) ==4
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','y','MarkerEdgeColor','y');
                    end
                    elseif Datos_suavizado.Ocupante_del_vehiculo(i) ==5
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0, 0.5, 0],'MarkerEdgeColor',[0, 0.5, 0]);
                    end
                    end
            end
            hold on 
            h = zeros(6, 1);
            h(1) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1), 'Color',[0.85 0.85 0.85],'visible', 'on');
            h(2) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'k','visible', 'on');
            h(3) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'r','visible', 'on');
            h(4) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1), 'Color',[0.9290, 0.6940, 0.1250],'visible', 'on');
            h(5) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'y','visible', 'on');
            h(6) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'Color',[0, 0.5, 0],'visible', 'on');
            hold off
            legend(h, 'No aplica','1 estrella','2 estrellas','3 estrellas','4 estrellas','5 estrellas','Location','northwest','FontSize',10);
            add(chap2,Figure(fig));

            end

            
            %Motociclista
            p3=Paragraph('Motociclistas');
            p3.Bold=1;
            p3.FontFamilyName='Gotham';
            add(chap2,p3)
            p3=Paragraph('a) Resumen de la clasificación por estrellas (SR) de la carretera analizada');
            p3.Underline='Single';
            add(chap2,p3);
            p3=Paragraph('En la siguiente tabla se indica la clasificación por estrellas sin suavizar para los ');
            p3.FontFamilyName='Gotham';
            p3.HAlign='justify';
            tex3= Text('motociclistas.');
            tex3.Style = {Bold(true)};
            append(p3,tex3);
            add(chap2,p3);
            add(chap2,Paragraph(''));
            
            Nombres2=app.Tabla_estrellas_Motociclista(1:7,1);
            E2=cell2mat(app.Tabla_estrellas_Motociclista(1:7,2));
            F2=cell2mat(app.Tabla_estrellas_Motociclista(1:7,3));
            T2=table(categorical(Nombres2(:,1)),f(E2),f(F2));
            T2.Properties.VariableNames = {'Clasificación por estrellas', 'Longitud (km)', 'Porcentaje (%)'};
            Tablay=FormalTable(T2);
            Tablay.TableEntriesHAlign = 'center';
            Tablay.TableEntriesVAlign = 'middle';
            Tablay.Style = tableStyle;
            Tablay.Header.Children(1).Style =HeaderStyle;
            Tablay.Body.Style=BodyStyle;
            add(chap2,Tablay);
            add(chap2,Paragraph(''));
            
            p3=Paragraph('En la siguiente tabla se indica la clasificación por estrellas suavizada para los ');
            p3.FontFamilyName='Gotham';
            p3.HAlign='justify';
            tex3= Text('motociclistas.');
            tex3.Style = {Bold(true)};
            append(p3,tex3);
            add(chap2,p3);
            add(chap2,Paragraph(''))
            
            
            Nombres3=app.Tabla_Nuevaestrellas_Motociclista(1:7,1);
            E3=cell2mat(app.Tabla_Nuevaestrellas_Motociclista(1:7,2));
            F3=cell2mat(app.Tabla_Nuevaestrellas_Motociclista(1:7,3));
            T3=table(categorical(Nombres3(:,1)),f(E3),f(F3));
            T3.Properties.VariableNames = {'Clasificación de estrellas', 'Longitud (km)', 'Porcentaje (%)'};
            Tablay=FormalTable(T3);
            Tablay.TableEntriesHAlign = 'center';
            Tablay.Style = tableStyle;
            Tablay.Header.Children(1).Style =HeaderStyle;
            Tablay.Body.Style=BodyStyle;
            add(chap2,Tablay);
            add(chap2,Paragraph(''));
           
            p4=Paragraph('b) Puntaje de clasificación por estrellas (SRS) a lo largo de la carretera analizada ');
            p4.Underline='Single';
            p4.FontFamilyName='Gotham';
            add(chap2,p4);
            p4=Paragraph('A continuación, se muestra en la figura, los SRS (línea gris) de cada sección del tramo en análisis. Los SRS suavizados (línea azul) es el promedio de los SRS en distancias más largas que se realizan con el fin de producir resultados más significativos. En la suavización se realizan dos procedimientos, media móvil con un ancho de ventana de 9, y luego una suavización exponencial con un valor alfa de 0,1.');
            p4.FontFamilyName='Gotham';
            p4.HAlign='justify';
            add(chap2,p4);
            
            fig2 = figure('visible','off');
            ax = axes(fig2,"FontSize",10);
            plot(cumsum(app.Datos_interfaz_2.Distancia),app.Datos_interfaz_2.Puntuacion_Motociclista,'color',[0.7 0.7 0.7],'LineWidth',1,...
                'MarkerEdgeColor','k',...
                'MarkerFaceColor','g',...
                'MarkerSize',10)
            xlabel("Distancia (m)");
            ylabel('Puntaje de clasificación por estrellas (SRS)');
            title("Puntaje de clasificación por estrellas (SRS) a lo largo de la carretera analizada");
            hold on
            plot(cumsum(Datos_suavizado.Distancia),Datos_suavizado.Puntuacion_Motociclista,'b','LineWidth',1)
            hold off
            add(chap2,Figure(fig2))
            
            p5=Paragraph('c) Ubicación de los SRS (datos sin suavización y suavizados) en la carretera analizada');
            p5.Underline='Single';
            p5.FontFamilyName='Gotham';
            add(chap2,p5);
            p5=Paragraph('En la siguiente gráfica se muestra el mapa del recorrido de la carretera analizada sin suavizar los datos.');
            p5.FontFamilyName='Gotham';
            p5.HAlign='justify';
            add(chap2,p5);
            
            if identificador==1
            fig3 = figure('visible','off');
            plot(x,y,'-r','LineWidth',1,...
                'MarkerEdgeColor','k',...
                'MarkerFaceColor','g',...
                'MarkerSize',10)
            xlabel("Longitud");
            ylabel('Latitud');
            title("Ubicación de los SRS sin suavización en la carretera analizada");
            hold on
            for i=1:length(Datos_contramedidas.Motociclista)
                if Datos_contramedidas.Motociclista(i) ==0    
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.85 0.85 0.85],'linewidth',2);
                elseif Datos_contramedidas.Motociclista(i) ==1 
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'k','linewidth',2);
                elseif Datos_contramedidas.Motociclista(i) ==2
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'r','linewidth',2);
                elseif Datos_contramedidas.Motociclista(i) ==3
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.9290, 0.6940, 0.1250],'linewidth',2);
                elseif Datos_contramedidas.Motociclista(i) ==4
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'y','linewidth',2);
                elseif Datos_contramedidas.Motociclista(i) ==5
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0, 0.5, 0],'linewidth',2);
                end
            end
            hold on 
            h = zeros(6, 1);
            h(1) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1), 'Color',[0.85 0.85 0.85],'visible', 'on');
            h(2) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'k','visible', 'on');
            h(3) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'r','visible', 'on');
            h(4) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1), 'Color',[0.9290, 0.6940, 0.1250],'visible', 'on');
            h(5) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'y','visible', 'on');
            h(6) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'Color',[0, 0.5, 0],'visible', 'on');
            hold off
            legend(h, 'No aplica','1 estrella','2 estrellas','3 estrellas','4 estrellas','5 estrellas','Location','northwest','FontSize',10);
            add(chap2,Figure(fig3));
            elseif identificador==2
            fig3 = figure('visible','off');
            xlabel("Longitud");
            ylabel('Latitud');
            title("Ubicación de los SRS sin suavización en la carretera analizada");
            hold on
            for i=1:length(Datos_contramedidas.Motociclista)
                    if Datos_contramedidas.Motociclista(i) ==0    
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0.85 0.85 0.85],'MarkerEdgeColor',[0.85 0.85 0.85]);
                    end
                    elseif Datos_contramedidas.Motociclista(i) ==1    
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','k','MarkerEdgeColor','k');
                    end
                    elseif Datos_contramedidas.Motociclista(i) ==2
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','r','MarkerEdgeColor','r');
                    end
                    elseif Datos_contramedidas.Motociclista(i) ==3
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0.9290, 0.6940, 0.1250],'MarkerEdgeColor',[0.9290, 0.6940, 0.1250]);
                    end
                    elseif Datos_contramedidas.Motociclista(i) ==4
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','y','MarkerEdgeColor','y');
                    end
                    elseif Datos_contramedidas.Motociclista(i) ==5
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0, 0.5, 0],'MarkerEdgeColor',[0, 0.5, 0]);
                    end
                    end
            end
            hold on 
            h = zeros(6, 1);
            h(1) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1), 'Color',[0.85 0.85 0.85],'visible', 'on');
            h(2) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'k','visible', 'on');
            h(3) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'r','visible', 'on');
            h(4) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1), 'Color',[0.9290, 0.6940, 0.1250],'visible', 'on');
            h(5) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'y','visible', 'on');
            h(6) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'Color',[0, 0.5, 0],'visible', 'on');
            hold off
            legend(h, 'No aplica','1 estrella','2 estrellas','3 estrellas','4 estrellas','5 estrellas','Location','northwest','FontSize',10);
            add(chap2,Figure(fig3));
            end
            
            p5=Paragraph('En la siguiente gráfica se muestra el mapa del recorrido de la carretera analizada con los datos suavizados.');
            p5.FontFamilyName='Gotham';
            add(chap2,p5);
            
            if identificador==1
            fig5 = figure('visible','off');
            plot(x,y,'-r','LineWidth',1,...
                'MarkerEdgeColor','k',...
                'MarkerFaceColor','g',...
                'MarkerSize',10)
            xlabel("Longitud");
            ylabel('Latitud');
            title("Ubicación de los SRS suavizados en la carretera analizada");
            hold on
            for i=1:length(Datos_suavizado.Motociclista)
                    if Datos_suavizado.Motociclista(i)==0
                    plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.85 0.85 0.85],'linewidth',2);
                    elseif Datos_suavizado.Motociclista(i)==1
                    plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'k','linewidth',2);
                    elseif Datos_suavizado.Motociclista(i)==2
                    plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'r','linewidth',2);
                    elseif Datos_suavizado.Motociclista(i)==3
                    plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.9290, 0.6940, 0.1250],'linewidth',2);
                    elseif Datos_suavizado.Motociclista(i)==4
                    plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'y','linewidth',2);
                    elseif Datos_suavizado.Motociclista(i)==5
                    plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0, 0.5, 0],'linewidth',2);
                    end
            end
            hold on 
            h = zeros(6, 1);
            h(1) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1), 'Color',[0.85 0.85 0.85],'visible', 'on');
            h(2) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'k','visible', 'on');
            h(3) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'r','visible', 'on');
            h(4) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1), 'Color',[0.9290, 0.6940, 0.1250],'visible', 'on');
            h(5) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'y','visible', 'on');
            h(6) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'Color',[0, 0.5, 0],'visible', 'on');
            hold off
            legend(h, 'No aplica','1 estrella','2 estrellas','3 estrellas','4 estrellas','5 estrellas','Location','northwest','FontSize',10);
            add(chap2,Figure(fig5));
            elseif identificador==2
            fig5 = figure('visible','off');
            xlabel("Longitud");
            ylabel('Latitud');
            title("Ubicación de los SRS suavizados en la carretera analizada");
            hold on
            for i=1:length(Datos_suavizado.Motociclista)
                    if Datos_suavizado.Motociclista(i)==0
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0.85 0.85 0.85],'MarkerEdgeColor',[0.85 0.85 0.85]);
                    end
                    elseif Datos_suavizado.Motociclista(i)==1
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','k','MarkerEdgeColor','k');
                    end
                    elseif Datos_suavizado.Motociclista(i) ==2
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','r','MarkerEdgeColor','r');
                    end
                    elseif Datos_suavizado.Motociclista(i) ==3
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0.9290, 0.6940, 0.1250],'MarkerEdgeColor',[0.9290, 0.6940, 0.1250]);
                    end
                    elseif Datos_suavizado.Motociclista(i) ==4
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','y','MarkerEdgeColor','y');
                    end
                    elseif Datos_suavizado.Motociclista(i) ==5
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0, 0.5, 0],'MarkerEdgeColor',[0, 0.5, 0]);
                    end
                    end
            end
            hold on 
            h = zeros(6, 1);
            h(1) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1), 'Color',[0.85 0.85 0.85],'visible', 'on');
            h(2) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'k','visible', 'on');
            h(3) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'r','visible', 'on');
            h(4) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1), 'Color',[0.9290, 0.6940, 0.1250],'visible', 'on');
            h(5) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'y','visible', 'on');
            h(6) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'Color',[0, 0.5, 0],'visible', 'on');
            hold off
            legend(h, 'No aplica','1 estrella','2 estrellas','3 estrellas','4 estrellas','5 estrellas','Location','northwest','FontSize',10);
            add(chap2,Figure(fig5));

            end

            
            %Ciclista
            p6=Paragraph('Ciclistas');
            p6.Bold=1;
            p6.FontFamilyName='Gotham';
            add(chap2,p6)
            p6=Paragraph('a) Resumen de la clasificación por estrellas (SR) de la carretera analizada');
            p6.Underline='Single';
            add(chap2,p6);
            p6=Paragraph('En la siguiente tabla se indica la clasificación por estrellas sin suavizar para los ');
            p6.FontFamilyName='Gotham';
            p6.HAlign='justify';
            tex6= Text('ciclistas.');
            tex6.Style = {Bold(true)};
            append(p6,tex6);
            add(chap2,p6);
            add(chap2,Paragraph(''));
            
            Nombres4=app.Tabla_estrellas_Cilista(1:7,1);
            E4=cell2mat(app.Tabla_estrellas_Cilista(1:7,2));
            F4=cell2mat(app.Tabla_estrellas_Cilista(1:7,3));
            T4=table(categorical(Nombres4(:,1)),f(E4),f(F4));
            T4.Properties.VariableNames = {'Clasificación por estrellas', 'Longitud (km)', 'Porcentaje (%)'};
            Tablaz=FormalTable(T4);
            Tablaz.TableEntriesHAlign = 'center';
            Tablaz.Style = tableStyle;
            Tablaz.Header.Children(1).Style =HeaderStyle;
            Tablaz.Body.Style=BodyStyle;
            add(chap2,Tablaz);
            add(chap2,Paragraph(''));
            
            p6=Paragraph('En la siguiente tabla se indica la clasificación por estrellas suavizada para los ');
            p6.FontFamilyName='Gotham';
            p6.HAlign='justify';
            tex6= Text('ciclistas.');
            tex6.Style = {Bold(true)};
            append(p6,tex6);
            add(chap2,p6);
            add(chap2,Paragraph(''))
            
            Nombres5=app.Tabla_Nuevaestrellas_Cilista(1:7,1);
            E5=cell2mat(app.Tabla_Nuevaestrellas_Cilista(1:7,2));
            F5=cell2mat(app.Tabla_Nuevaestrellas_Cilista(1:7,3));
            T5=table(categorical(Nombres5(:,1)),f(E5),f(F5));
            T5.Properties.VariableNames = {'Clasificación por estrellas', 'Longitud (km)', 'Porcentaje (%)'};
            Tablaz=FormalTable(T5);
            Tablaz.TableEntriesHAlign = 'center';
            Tablaz.Style = tableStyle;
            Tablaz.Header.Children(1).Style =HeaderStyle;
            Tablaz.Body.Style=BodyStyle;
            add(chap2,Tablaz);
            add(chap2,Paragraph(''));
            
            p7=Paragraph('b) Puntaje de clasificación por estrellas (SRS) a lo largo de la carretera analizada ');
            p7.Underline='Single';
            p7.FontFamilyName='Gotham';
            add(chap2,p7);
            p7=Paragraph('A continuación, se muestra en la figura, los SRS (línea gris) de cada sección del tramo en análisis. Los SRS suavizados (línea azul) es el promedio de los SRS en distancias más largas que se realizan con el fin de producir resultados más significativos. En la suavización se realizan dos procedimientos, media móvil con un ancho de ventana de 9, y luego una suavización exponencial con un valor alfa de 0,1.');
            p7.FontFamilyName='Gotham';
            p7.HAlign='justify';
            add(chap2,p7);
           
            fig4 = figure('visible','off');
            ax = axes(fig4,"FontSize",10);
            plot(cumsum(app.Datos_interfaz_2.Distancia),app.Datos_interfaz_2.Puntuacion_Ciclista,'color',[0.7 0.7 0.7],'LineWidth',1,...
                'MarkerEdgeColor','k',...
                'MarkerFaceColor','g',...
                'MarkerSize',10)
            xlabel("Distancia (m)");
            ylabel('Puntaje de clasificación por estrellas (SRS)');
            title("Puntaje de clasificación por estrellas (SRS) a lo largo de la carretera analizada");
            hold on
            plot(cumsum(Datos_suavizado.Distancia),Datos_suavizado.Puntuacion_Ciclista,'b','LineWidth',1)
            hold off
            add(chap2,Figure(fig4))
            
            p8=Paragraph('c) Ubicación de los SRS (datos sin suavización y suavizados) en la carretera analizada');
            p8.Underline='Single';
            p8.FontFamilyName='Gotham';
            add(chap2,p8);
            p8=Paragraph('En la siguiente gráfica se muestra el mapa del recorrido de la carretera analizada sin suavizar los datos.');
            p8.FontFamilyName='Gotham';
            p8.HAlign='justify';
            add(chap2,p8);
            
            if identificador==1
            fig5 = figure('visible','off');
            plot(x,y,'-r','LineWidth',1,...
                'MarkerEdgeColor','k',...
                'MarkerFaceColor','g',...
                'MarkerSize',10)
            xlabel("Longitud");
            ylabel('Latitud');
            title("Ubicación de los SRS sin suavización en la carretera analizada");
            hold on
            for i=1:length(Datos_contramedidas.Ciclista)
                if Datos_contramedidas.Ciclista(i) ==0    
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.85 0.85 0.85],'linewidth',2);
                elseif Datos_contramedidas.Ciclista(i) ==1 
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'k','linewidth',2);
                elseif Datos_contramedidas.Ciclista(i) ==2
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'r','linewidth',2);
                elseif Datos_contramedidas.Ciclista(i) ==3
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.9290, 0.6940, 0.1250],'linewidth',2);
                elseif Datos_contramedidas.Ciclista(i) ==4
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'y','linewidth',2);
                elseif Datos_contramedidas.Ciclista(i) ==5
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0, 0.5, 0],'linewidth',2);
                end
            end
            hold on 
            h = zeros(6, 1);
            h(1) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1), 'Color',[0.85 0.85 0.85],'visible', 'on');
            h(2) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'k','visible', 'on');
            h(3) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'r','visible', 'on');
            h(4) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1), 'Color',[0.9290, 0.6940, 0.1250],'visible', 'on');
            h(5) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'y','visible', 'on');
            h(6) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'Color',[0, 0.5, 0],'visible', 'on');
            hold off
            legend(h, 'No aplica','1 estrella','2 estrellas','3 estrellas','4 estrellas','5 estrellas','Location','northwest','FontSize',10);
            add(chap2,Figure(fig5));
            elseif identificador==2
            fig5 = figure('visible','off');
            xlabel("Longitud");
            ylabel('Latitud');
            title("Ubicación de los SRS sin suavización en la carretera analizada");
            hold on
            for i=1:length(Datos_contramedidas.Ciclista)
                    if Datos_contramedidas.Ciclista(i) ==0    
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0.85 0.85 0.85],'MarkerEdgeColor',[0.85 0.85 0.85]);
                    end
                    elseif Datos_contramedidas.Ciclista(i) ==1    
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','k','MarkerEdgeColor','k');
                    end
                    elseif Datos_contramedidas.Ciclista(i) ==2
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','r','MarkerEdgeColor','r');
                    end
                    elseif Datos_contramedidas.Ciclista(i) ==3
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0.9290, 0.6940, 0.1250],'MarkerEdgeColor',[0.9290, 0.6940, 0.1250]);
                    end
                    elseif Datos_contramedidas.Ciclista(i) ==4
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','y','MarkerEdgeColor','y');
                    end
                    elseif Datos_contramedidas.Ciclista(i) ==5
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0, 0.5, 0],'MarkerEdgeColor',[0, 0.5, 0]);
                    end
                    end
            end
            hold on 
            h = zeros(6, 1);
            h(1) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1), 'Color',[0.85 0.85 0.85],'visible', 'on');
            h(2) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'k','visible', 'on');
            h(3) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'r','visible', 'on');
            h(4) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1), 'Color',[0.9290, 0.6940, 0.1250],'visible', 'on');
            h(5) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'y','visible', 'on');
            h(6) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'Color',[0, 0.5, 0],'visible', 'on');
            hold off
            legend(h, 'No aplica','1 estrella','2 estrellas','3 estrellas','4 estrellas','5 estrellas','Location','northwest','FontSize',10);
            add(chap2,Figure(fig5));
            end
                   
            
            p8=Paragraph('En la siguiente gráfica se muestra el mapa del recorrido de la carretera analizada con los datos suavizados.');
            p8.FontFamilyName='Gotham';
            add(chap2,p8);
            
            if identificador==1
            fig5 = figure('visible','off');
            plot(x,y,'-r','LineWidth',1,...
                'MarkerEdgeColor','k',...
                'MarkerFaceColor','g',...
                'MarkerSize',10)
            xlabel("Longitud");
            ylabel('Latitud');
            title("Ubicación de los SRS suavizados en la carretera analizada");
            hold on
            for i=1:length(Datos_suavizado.Ciclista)
                    if Datos_suavizado.Ciclista(i)==0
                    plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.85 0.85 0.85],'linewidth',2);
                    elseif Datos_suavizado.Ciclista(i)==1
                    plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'k','linewidth',2);
                    elseif Datos_suavizado.Ciclista(i)==2
                    plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'r','linewidth',2);
                    elseif Datos_suavizado.Ciclista(i)==3
                    plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.9290, 0.6940, 0.1250],'linewidth',2);
                    elseif Datos_suavizado.Ciclista(i)==4
                    plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'y','linewidth',2);
                    elseif Datos_suavizado.Ciclista(i)==5
                    plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0, 0.5, 0],'linewidth',2);
                    end
            end
            hold on 
            h = zeros(6, 1);
            h(1) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1), 'Color',[0.85 0.85 0.85],'visible', 'on');
            h(2) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'k','visible', 'on');
            h(3) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'r','visible', 'on');
            h(4) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1), 'Color',[0.9290, 0.6940, 0.1250],'visible', 'on');
            h(5) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'y','visible', 'on');
            h(6) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'Color',[0, 0.5, 0],'visible', 'on');
            hold off
            legend(h, 'No aplica','1 estrella','2 estrellas','3 estrellas','4 estrellas','5 estrellas','Location','northwest','FontSize',10);
            add(chap2,Figure(fig5));
            elseif identificador==2
            fig5 = figure('visible','off');
            xlabel("Longitud");
            ylabel('Latitud');
            title("Ubicación de los SRS suavizados en la carretera analizada");
            hold on
             for i=1:length(Datos_suavizado.Ciclista)
                    if Datos_suavizado.Ciclista(i)==0
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0.85 0.85 0.85],'MarkerEdgeColor',[0.85 0.85 0.85]);
                    end
                    elseif Datos_suavizado.Ciclista(i)==1
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','k','MarkerEdgeColor','k');
                    end
                    elseif Datos_suavizado.Ciclista(i) ==2
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','r','MarkerEdgeColor','r');
                    end
                    elseif Datos_suavizado.Ciclista(i) ==3
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0.9290, 0.6940, 0.1250],'MarkerEdgeColor',[0.9290, 0.6940, 0.1250]);
                    end
                    elseif Datos_suavizado.Ciclista(i) ==4
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','y','MarkerEdgeColor','y');
                    end
                    elseif Datos_suavizado.Ciclista(i) ==5
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0, 0.5, 0],'MarkerEdgeColor',[0, 0.5, 0]);
                    end
                    end
             end 
            hold on 
            h = zeros(6, 1);
            h(1) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1), 'Color',[0.85 0.85 0.85],'visible', 'on');
            h(2) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'k','visible', 'on');
            h(3) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'r','visible', 'on');
            h(4) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1), 'Color',[0.9290, 0.6940, 0.1250],'visible', 'on');
            h(5) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'y','visible', 'on');
            h(6) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'Color',[0, 0.5, 0],'visible', 'on');
            hold off
            legend(h, 'No aplica','1 estrella','2 estrellas','3 estrellas','4 estrellas','5 estrellas','Location','northwest','FontSize',10);
            add(chap2,Figure(fig5));
            end
            
            %Peatón
            p9=Paragraph('Peatones');
            p9.Bold=1;
            p9.FontFamilyName='Gotham';
            add(chap2,p9)
            
            p9=Paragraph('a) Resumen de la clasificación por estrellas (SR) de la carretera analizada');
            p9.Underline='Single';
            add(chap2,p9);
            p9=Paragraph('En la siguiente tabla se indica la clasificación por estrellas sin suavizar para los ');
            p9.FontFamilyName='Gotham';
            p9.HAlign='justify';
            tex9= Text('peatones.');
            tex9.Style = {Bold(true)};
            append(p9,tex9);
            add(chap2,p9);
            add(chap2,Paragraph(''));
            
            Nombres6=app.Tabla_estrellas_Peaton(1:7,1);
            E6=cell2mat(app.Tabla_estrellas_Peaton(1:7,2));
            F6=cell2mat(app.Tabla_estrellas_Peaton(1:7,3));
            T6=table(categorical(Nombres6(:,1)),f(E6),f(F6));
            T6.Properties.VariableNames = {'Clasificación por estrellas', 'Longitud (km)', 'Porcentaje (%)'};
            Tablaa=FormalTable(T6);
            Tablaa.TableEntriesHAlign = 'center';
            Tablaa.Style = tableStyle;
            Tablaa.Header.Children(1).Style =HeaderStyle;
            Tablaa.Body.Style=BodyStyle;
            add(chap2,Tablaa);
            add(chap2,Paragraph(''));
            
            
            p9=Paragraph('En la siguiente tabla se indica la clasificación por estrellas suavizada para los ');
            p9.FontFamilyName='Gotham';
            p9.HAlign='justify';
            tex9= Text('peatones.');
            tex9.Style = {Bold(true)};
            append(p9,tex9);
            add(chap2,p9);
            add(chap2,Paragraph(''))
            
            Nombres7=app.Tabla_Nuevaestrellas_Peaton(1:7,1);
            E7=cell2mat(app.Tabla_Nuevaestrellas_Peaton(1:7,2));
            F7=cell2mat(app.Tabla_Nuevaestrellas_Peaton(1:7,3));
            T7=table(categorical(Nombres7(:,1)),f(E7),f(F7));
            T7.Properties.VariableNames = {'Clasificación por estrellas', 'Longitud (km)', 'Porcentaje (%)'};
            Tablaa=FormalTable(T7);
            Tablaa.TableEntriesHAlign = 'center';
            Tablaa.Style = tableStyle;
            Tablaa.Header.Children(1).Style =HeaderStyle;
            Tablaa.Body.Style=BodyStyle;
            add(chap2,Tablaa);
            add(chap2,Paragraph(''));
            
            p10=Paragraph('b) Puntaje de clasificación por estrellas (SRS) a lo largo de la carretera analizada ');
            p10.Underline='Single';
            p10.FontFamilyName='Gotham';
            add(chap2,p10);
            p10=Paragraph('A continuación, se muestra en la figura, los SRS (línea gris) de cada sección del tramo en análisis. Los SRS suavizados (línea azul) es el promedio de los SRS en distancias más largas que se realizan con el fin de producir resultados más significativos. En la suavización se realizan dos procedimientos, media móvil con un ancho de ventana de 9, y luego una suavización exponencial con un valor alfa de 0,1.');
            p10.FontFamilyName='Gotham';
            p10.HAlign='justify';
            add(chap2,p10);
           
            fig6 = figure('visible','off');
            ax = axes(fig6,"FontSize",10);
            plot(cumsum(app.Datos_interfaz_2.Distancia),app.Datos_interfaz_2.Puntuacion_Peaton,'color',[0.7 0.7 0.7],'LineWidth',1,...
                'MarkerEdgeColor','k',...
                'MarkerFaceColor','g',...
                'MarkerSize',10)
            xlabel("Distancia (m)");
            ylabel('Puntaje de clasificación por estrellas (SRS)');
            title("Puntaje de clasificación por estrellas (SRS) a lo largo de la carretera analizada");
            hold on
            plot(cumsum(Datos_suavizado.Distancia),Datos_suavizado.Puntuacion_Peaton,'b','LineWidth',1)
            hold off
            add(chap2,Figure(fig6))
            
            p11=Paragraph('c) Ubicación de los SRS (datos sin suavización y suavizados) en la carretera analizada');
            p11.Underline='Single';
            p11.FontFamilyName='Gotham';
            add(chap2,p11);
            p11=Paragraph('En la siguiente gráfica se muestra el mapa del recorrido de la carretera analizada sin suavizar los datos.');
            p11.FontFamilyName='Gotham';
            add(chap2,p11);
            
            if identificador==1
            fig7 = figure('visible','off');
            plot(x,y,'-r','LineWidth',1,...
                'MarkerEdgeColor','k',...
                'MarkerFaceColor','g',...
                'MarkerSize',10)
            xlabel("Longitud");
            ylabel('Latitud');
            title("Ubicación de los SRS sin suavización en la carretera analizada");
            hold on
            for i=1:length(Datos_contramedidas.Peaton)
                if Datos_contramedidas.Peaton(i) ==0    
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.85 0.85 0.85],'linewidth',2);
                elseif Datos_contramedidas.Peaton(i) ==1 
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'k','linewidth',2);
                elseif Datos_contramedidas.Peaton(i) ==2
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'r','linewidth',2);
                elseif Datos_contramedidas.Peaton(i) ==3
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.9290, 0.6940, 0.1250],'linewidth',2);
                elseif Datos_contramedidas.Peaton(i) ==4
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'y','linewidth',2);
                elseif Datos_contramedidas.Peaton(i) ==5
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0, 0.5, 0],'linewidth',2);
                end
            end
            hold on 
            h = zeros(6, 1);
            h(1) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1), 'Color',[0.85 0.85 0.85],'visible', 'on');
            h(2) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'k','visible', 'on');
            h(3) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'r','visible', 'on');
            h(4) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1), 'Color',[0.9290, 0.6940, 0.1250],'visible', 'on');
            h(5) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'y','visible', 'on');
            h(6) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'Color',[0, 0.5, 0],'visible', 'on');
            hold off
            legend(h, 'No aplica','1 estrella','2 estrellas','3 estrellas','4 estrellas','5 estrellas','Location','northwest','FontSize',10);
            add(chap2,Figure(fig7));
            elseif identificador==2
            fig7 = figure('visible','off');
            xlabel("Longitud");
            ylabel('Latitud');
            title("Ubicación de los SRS sin suavización en la carretera analizada");
            hold on
            for i=1:length(Datos_contramedidas.Peaton)
                    if Datos_contramedidas.Peaton(i) ==0    
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0.85 0.85 0.85],'MarkerEdgeColor',[0.85 0.85 0.85]);
                    end
                    elseif Datos_contramedidas.Peaton(i) ==1    
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','k','MarkerEdgeColor','k');
                    end
                    elseif Datos_contramedidas.Peaton(i) ==2
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','r','MarkerEdgeColor','r');
                    end
                    elseif Datos_contramedidas.Peaton(i) ==3
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0.9290, 0.6940, 0.1250],'MarkerEdgeColor',[0.9290, 0.6940, 0.1250]);
                    end
                    elseif Datos_contramedidas.Peaton(i) ==4
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','y','MarkerEdgeColor','y');
                    end
                    elseif Datos_contramedidas.Peaton(i) ==5
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0, 0.5, 0],'MarkerEdgeColor',[0, 0.5, 0]);
                    end
                    end
            end
            hold on 
            h = zeros(6, 1);
            h(1) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1), 'Color',[0.85 0.85 0.85],'visible', 'on');
            h(2) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'k','visible', 'on');
            h(3) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'r','visible', 'on');
            h(4) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1), 'Color',[0.9290, 0.6940, 0.1250],'visible', 'on');
            h(5) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'y','visible', 'on');
            h(6) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'Color',[0, 0.5, 0],'visible', 'on');
            hold off
            legend(h, 'No aplica','1 estrella','2 estrellas','3 estrellas','4 estrellas','5 estrellas','Location','northwest','FontSize',10);
            add(chap2,Figure(fig7));
            end

            
            p11=Paragraph('En la siguiente gráfica se muestra el mapa del recorrido de la carretera analizada con los datos suavizados.');
            p11.FontFamilyName='Gotham';
            add(chap2,p11);
            
            if identificador==1
            fig15 = figure('visible','off');
            plot(x,y,'-r','LineWidth',1,...
                'MarkerEdgeColor','k',...
                'MarkerFaceColor','g',...
                'MarkerSize',10)
            xlabel("Longitud");
            ylabel('Latitud');
            title("Ubicación de los SRS suavizados en la carretera analizada");
            hold on
            for i=1:length(Datos_suavizado.Peaton)
                    if Datos_suavizado.Peaton(i)==0
                    plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.85 0.85 0.85],'linewidth',2);
                    elseif Datos_suavizado.Peaton(i)==1
                    plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'k','linewidth',2);
                    elseif Datos_suavizado.Peaton(i)==2
                    plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'r','linewidth',2);
                    elseif Datos_suavizado.Peaton(i)==3
                    plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.9290, 0.6940, 0.1250],'linewidth',2);
                    elseif Datos_suavizado.Peaton(i)==4
                    plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'y','linewidth',2);
                    elseif Datos_suavizado.Peaton(i)==5
                    plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0, 0.5, 0],'linewidth',2);
                    end
            end
            hold on 
            h = zeros(6, 1);
            h(1) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1), 'Color',[0.85 0.85 0.85],'visible', 'on');
            h(2) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'k','visible', 'on');
            h(3) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'r','visible', 'on');
            h(4) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1), 'Color',[0.9290, 0.6940, 0.1250],'visible', 'on');
            h(5) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'y','visible', 'on');
            h(6) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'Color',[0, 0.5, 0],'visible', 'on');
            hold off
            legend(h, 'No aplica','1 estrella','2 estrellas','3 estrellas','4 estrellas','5 estrellas','Location','northwest','FontSize',10);
            add(chap2,Figure(fig15));
            elseif identificador==2
            fig15 = figure('visible','off');
            xlabel("Longitud");
            ylabel('Latitud');
            title("Ubicación de los SRS suavizados en la carretera analizada");
            hold on
             for i=1:length(Datos_suavizado.Peaton)
                    if Datos_suavizado.Peaton(i)==0
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0.85 0.85 0.85],'MarkerEdgeColor',[0.85 0.85 0.85]);
                    end
                    elseif Datos_suavizado.Peaton(i)==1
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','k','MarkerEdgeColor','k');
                    end
                    elseif Datos_suavizado.Peaton(i) ==2
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','r','MarkerEdgeColor','r');
                    end
                    elseif Datos_suavizado.Peaton(i) ==3
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0.9290, 0.6940, 0.1250],'MarkerEdgeColor',[0.9290, 0.6940, 0.1250]);
                    end
                    elseif Datos_suavizado.Peaton(i) ==4
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','y','MarkerEdgeColor','y');
                    end
                    elseif Datos_suavizado.Peaton(i) ==5
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                       scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0, 0.5, 0],'MarkerEdgeColor',[0, 0.5, 0]);
                    end
                    end
             end
            hold on 
            h = zeros(6, 1);
            h(1) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1), 'Color',[0.85 0.85 0.85],'visible', 'on');
            h(2) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'k','visible', 'on');
            h(3) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'r','visible', 'on');
            h(4) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1), 'Color',[0.9290, 0.6940, 0.1250],'visible', 'on');
            h(5) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'y','visible', 'on');
            h(6) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'Color',[0, 0.5, 0],'visible', 'on');
            hold off
            legend(h, 'No aplica','1 estrella','2 estrellas','3 estrellas','4 estrellas','5 estrellas','Location','northwest','FontSize',10);
            add(chap2,Figure(fig15));

            end

            
            add(rpt,chap2);

            close(rpt);
            rptview(rpt);
        end

        % Callback function
        function TramosguardadosSpinnerValueChanged(app, event)
            
        end

        % Value changed function: SuavizadoCheckBox
        function SuavizadoCheckBoxValueChanged(app, event)
            UsuariosvialesButtonGroupSelectionChanged(app)


        end

        % Callback function
        function CalcularButtonPushed(app, event)
   
        end

        % Callback function
        function UITable_55CellEdit(app, event)
            
        end

        % Callback function
        function GuardarButtonPushed(app, event)
  
        end

        % Callback function
        function PDFButton_2Pushed(app, event)

     
        end

        % Callback function
        function VerificarButtonPushed(app, event)
            
        end

        % Button pushed function: GuardarArchivosButton
        function GuardarArchivosButtonPushed(app, event)
 
            PDFButton_3Pushed(app)
            mlreportgen.utils.rptviewer.closeAll()
            
            % Seleccionar la ubicación donde guardar
            [name,path] = uiputfile('*.*','Guardar proyecto','SilvviaProj');
            
            if name == 0
               return 
            end
            
            % Ruta de la carpeta de proyecto
            path_projF = strcat(path,name,'\');
            
            % Ruta del archivo de proyecto
            % path_proj = strcat(path_projF,name,'.mat');
            
            % Crear carpeta
            mkdir(path_projF)
            
            %Copiar los archivos en la carpeta creada
            copyfile('Reporte de la aplicación de las contramedidas.pdf',path_projF);
            copyfile('../mat/Datos_atributos_proceso.mat',path_projF);
            copyfile('../mat/Datos_contramedidas_postproceso.mat',path_projF);
            copyfile('../mat/Datos_interfaz_dos_proceso.mat',path_projF);
            copyfile('../mat/Datos_suavizado_contramedidas_postproceso.mat',path_projF);
            copyfile('../mat/Datos_suavizado_postproceso.mat',path_projF);
            copyfile('../mat/Inf_proyecto.mat',path_projF);
            copyfile('../mat/Matriz_contramedidas_seleccionadas_postproceso.mat',path_projF);
            copyfile('../mat/preproceso_datos_video.mat',path_projF);
            copyfile('../mat/preproceso_tiempo_img.mat',path_projF);
            copyfile('../mat/preproceso_tramo.mat',path_projF);
            copyfile('../fotogramas*',path_projF)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Reporte = uifigure('Visible', 'off');
            app.Reporte.Name = 'MATLAB App';
            app.TabGroup2 = uitabgroup(app.Reporte);
            app.ReportedetalladoTab = uitab(app.TabGroup2);
            app.ReportedetalladoTab.Title = 'Reporte detallado';
            app.TabGroup_2 = uitabgroup(app.ReportedetalladoTab);
            app.ClasificacindeestrellasTab = uitab(app.TabGroup_2);
            app.ClasificacindeestrellasTab.Title = 'Clasificación de estrellas';
            app.UITable_9 = uitable(app.ClasificacindeestrellasTab);
            app.UITable_9.ColumnName = {'Clasificación de estrellas'; 'Longitud (Km)'; 'Porcentaje (%)'};
            app.UITable_9.RowName = {};
            app.UsuariosvialesButtonGroup = uibuttongroup(app.ReportedetalladoTab);
            app.UsuariosvialesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @UsuariosvialesButtonGroupSelectionChanged, true);
            app.UsuariosvialesButtonGroup.Title = 'Usuarios viales';
            app.OcupantedevehculosButton = uiradiobutton(app.UsuariosvialesButtonGroup);
            app.OcupantedevehculosButton.Text = 'Ocupante de vehículos';
            app.OcupantedevehculosButton.Value = true;
            app.MotociclistaButton = uiradiobutton(app.UsuariosvialesButtonGroup);
            app.MotociclistaButton.Text = 'Motociclista';
            app.CiclistaButton = uiradiobutton(app.UsuariosvialesButtonGroup);
            app.CiclistaButton.Text = 'Ciclista';
            app.PeatnButton = uiradiobutton(app.UsuariosvialesButtonGroup);
            app.PeatnButton.Text = 'Peatón';
            app.PDFButton_3 = uibutton(app.ReportedetalladoTab, 'push');
            app.PDFButton_3.ButtonPushedFcn = createCallbackFcn(app, @PDFButton_3Pushed, true);
            app.PDFButton_3.Icon = 'icono-PDF.png';
            app.PDFButton_3.Text = 'PDF';
            app.UbicacindelosSRSalolargodelacarreteraenanlisisPanel = uipanel(app.ReportedetalladoTab);
            app.UbicacindelosSRSalolargodelacarreteraenanlisisPanel.Title = 'Ubicación de los SRS a lo largo de la carretera en análisis';
            app.UIAxes = uiaxes(app.UbicacindelosSRSalolargodelacarreteraenanlisisPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'Longitud')
            ylabel(app.UIAxes, 'Latitud')
            app.PuntajedeclasificacinSRSalolargodelacarreteraenanlisisPanel = uipanel(app.ReportedetalladoTab);
            app.PuntajedeclasificacinSRSalolargodelacarreteraenanlisisPanel.Title = 'Puntaje de clasificación (SRS) a lo largo de la carretera en análisis';
            app.UIAxes_2 = uiaxes(app.PuntajedeclasificacinSRSalolargodelacarreteraenanlisisPanel);
            title(app.UIAxes_2, '')
            xlabel(app.UIAxes_2, 'Distancia (m)')
            ylabel(app.UIAxes_2, 'Puntajes de clasificación por estrellas (SRS)')
            app.SuavizadoCheckBox = uicheckbox(app.ReportedetalladoTab);
            app.SuavizadoCheckBox.ValueChangedFcn = createCallbackFcn(app, @SuavizadoCheckBoxValueChanged, true);
            app.SuavizadoCheckBox.Text = 'Suavizado';
            app.GuardarArchivosButton = uibutton(app.ReportedetalladoTab, 'push');
            app.GuardarArchivosButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarArchivosButtonPushed, true);
            app.GuardarArchivosButton.Text = 'Guardar Archivos';
            % Show the figure after all components are created
            app.Reporte.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_Aplicacion_contramedidas

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Reporte)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Reporte)
        end
    end
end