classdef app_Reporte < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Reporte                         matlab.ui.Figure
        TabGroup2                       matlab.ui.container.TabGroup
        ReporteGeneralTab               matlab.ui.container.Tab
        TabGroup                        matlab.ui.container.TabGroup
        CostadodelavaTab                matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        UITable_2                       matlab.ui.control.Table
        UITable_3                       matlab.ui.control.Table
        UITable_4                       matlab.ui.control.Table
        UITable_5                       matlab.ui.control.Table
        UITable_6                       matlab.ui.control.Table
        UITable_7                       matlab.ui.control.Table
        BloquecentralTab                matlab.ui.container.Tab
        UITable_10                      matlab.ui.control.Table
        UITable_11                      matlab.ui.control.Table
        UITable_13                      matlab.ui.control.Table
        UITable_12                      matlab.ui.control.Table
        UITable_14                      matlab.ui.control.Table
        UITable_15                      matlab.ui.control.Table
        UITable_16                      matlab.ui.control.Table
        UITable_17                      matlab.ui.control.Table
        UITable_18                      matlab.ui.control.Table
        UITable_19                      matlab.ui.control.Table
        UITable_20                      matlab.ui.control.Table
        UITable_21                      matlab.ui.control.Table
        UITable_22                      matlab.ui.control.Table
        UITable_23                      matlab.ui.control.Table
        UITable_24                      matlab.ui.control.Table
        UITable_25                      matlab.ui.control.Table
        UITable_26                      matlab.ui.control.Table
        InterseccionesTab               matlab.ui.container.Tab
        UITable_27                      matlab.ui.control.Table
        UITable_28                      matlab.ui.control.Table
        UITable_29                      matlab.ui.control.Table
        UITable_30                      matlab.ui.control.Table
        UITable_31                      matlab.ui.control.Table
        FlujoTab                        matlab.ui.container.Tab
        UITable_32                      matlab.ui.control.Table
        UITable_33                      matlab.ui.control.Table
        UITable_34                      matlab.ui.control.Table
        UITable_35                      matlab.ui.control.Table
        UITable_36                      matlab.ui.control.Table
        UITable_37                      matlab.ui.control.Table
        InfraestructuraparausuariosvulnerablesyusodelsueloTab  matlab.ui.container.Tab
        UITable_38                      matlab.ui.control.Table
        UITable_39                      matlab.ui.control.Table
        UITable_40                      matlab.ui.control.Table
        UITable_41                      matlab.ui.control.Table
        UITable_42                      matlab.ui.control.Table
        UITable_43                      matlab.ui.control.Table
        UITable_44                      matlab.ui.control.Table
        UITable_45                      matlab.ui.control.Table
        UITable_46                      matlab.ui.control.Table
        UITable_47                      matlab.ui.control.Table
        UITable_48                      matlab.ui.control.Table
        UITable_49                      matlab.ui.control.Table
        UITable_50                      matlab.ui.control.Table
        VelocidadesTab                  matlab.ui.container.Tab
        UITable_51                      matlab.ui.control.Table
        UITable_52                      matlab.ui.control.Table
        UITable_53                      matlab.ui.control.Table
        UITable_54                      matlab.ui.control.Table
        ReportedetalladoTab             matlab.ui.container.Tab
        TabGroup_2                      matlab.ui.container.TabGroup
        ClasificacindeestrellasTab      matlab.ui.container.Tab
        UITable_9                       matlab.ui.control.Table
        UsuariosvialesButtonGroup       matlab.ui.container.ButtonGroup
        OcupantedevehculosButton        matlab.ui.control.RadioButton
        MotociclistaButton              matlab.ui.control.RadioButton
        CiclistaButton                  matlab.ui.control.RadioButton
        PeatnButton                     matlab.ui.control.RadioButton
        PDFButton_3                     matlab.ui.control.Button
        UbicacindelosSRSalolargodelacarreteraenanlisisPanel  matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        PuntajedeclasificacinSRSalolargodelacarreteraenanlisisPanel  matlab.ui.container.Panel
        UIAxes_2                        matlab.ui.control.UIAxes
        SuavizadoCheckBox               matlab.ui.control.CheckBox
        MedidasdemejoramientoTab        matlab.ui.container.Tab
        DATOSGENERALESDELACARRETERAPanel  matlab.ui.container.Panel
        NombredelproyectoTextAreaLabel  matlab.ui.control.Label
        NombredelproyectoTextArea       matlab.ui.control.TextArea
        TramosguardadosSpinnerLabel     matlab.ui.control.Label
        TramosguardadosSpinner          matlab.ui.control.Spinner
        CargarButton                    matlab.ui.control.Button
        ATRIBUTOSCODIFICADOSPanel       matlab.ui.container.Panel
        TabGroup3                       matlab.ui.container.TabGroup
        CostadodelavaTab_2              matlab.ui.container.Tab
        UITable_55                      matlab.ui.control.Table
        BloquecentralTab_2              matlab.ui.container.Tab
        UITable_56                      matlab.ui.control.Table
        InterseccionesTab_2             matlab.ui.container.Tab
        UITable_57                      matlab.ui.control.Table
        FlujoTab_2                      matlab.ui.container.Tab
        UITable_58                      matlab.ui.control.Table
        InfraestructuraparausuariosvulnerablesyusodelsueloTab_2  matlab.ui.container.Tab
        UITable_59                      matlab.ui.control.Table
        VelocidadesTab_2                matlab.ui.container.Tab
        UITable_60                      matlab.ui.control.Table
        ClasificacinporestrellasPanel   matlab.ui.container.Panel
        OcupantedevehculosEditField_3Label  matlab.ui.control.Label
        OcupantedevehculosEditField_3   matlab.ui.control.NumericEditField
        MotociclistaEditField_3Label    matlab.ui.control.Label
        MotociclistaEditField_3         matlab.ui.control.NumericEditField
        CiclistaEditField_3Label        matlab.ui.control.Label
        CiclistaEditField_3             matlab.ui.control.NumericEditField
        PeatnEditField_3Label           matlab.ui.control.Label
        PeatnEditField_3                matlab.ui.control.NumericEditField
        PuntajedeclasificacinPanel      matlab.ui.container.Panel
        OcupantedevehculosEditField_4Label  matlab.ui.control.Label
        OcupantedevehculosEditField_4   matlab.ui.control.NumericEditField
        CiclistaEditField_4Label        matlab.ui.control.Label
        CiclistaEditField_4             matlab.ui.control.NumericEditField
        MotociclistaEditField_4Label    matlab.ui.control.Label
        MotociclistaEditField_4         matlab.ui.control.NumericEditField
        PeatnEditField_4Label           matlab.ui.control.Label
        PeatnEditField_4                matlab.ui.control.NumericEditField
        MEDIDASADOPTADASPanel           matlab.ui.container.Panel
        UITable2                        matlab.ui.control.Table
        CalcularButton                  matlab.ui.control.Button
        PDFButton_2                     matlab.ui.control.Button
        PuntajedeclasificacinPanel_2    matlab.ui.container.Panel
        OcupantedevehculosEditField_5Label  matlab.ui.control.Label
        OcupantedevehculosEditField_5   matlab.ui.control.NumericEditField
        CiclistaEditField_5Label        matlab.ui.control.Label
        CiclistaEditField_5             matlab.ui.control.NumericEditField
        MotociclistaEditField_5Label    matlab.ui.control.Label
        MotociclistaEditField_5         matlab.ui.control.NumericEditField
        PeatnEditField_5Label           matlab.ui.control.Label
        PeatnEditField_5                matlab.ui.control.NumericEditField
        ClasificacinporestrellasPanel_2  matlab.ui.container.Panel
        OcupantedevehculosEditField_6Label  matlab.ui.control.Label
        OcupantedevehculosEditField_6   matlab.ui.control.NumericEditField
        MotociclistaEditField_6Label    matlab.ui.control.Label
        MotociclistaEditField_6         matlab.ui.control.NumericEditField
        CiclistaEditField_6Label        matlab.ui.control.Label
        CiclistaEditField_6             matlab.ui.control.NumericEditField
        PeatnEditField_6Label           matlab.ui.control.Label
        PeatnEditField_6                matlab.ui.control.NumericEditField
        GuardarButton                   matlab.ui.control.Button
        ResultadoButton                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        
        s1 % Description
        s2
        s3
        s4
        s5
        s6
        s7
        Datos_video
        Datos_interfaz_1
        Datos_interfaz_2
        b1
        Tabla_estrellas_Ocupante
        Tabla_estrellas_Motociclista
        Tabla_estrellas_Cilista
        Tabla_estrellas_Peaton
        Tabla_Nuevaestrellas_Ocupante
        Tabla_Nuevaestrellas_Motociclista
        Tabla_Nuevaestrellas_Cilista
        Tabla_Nuevaestrellas_Peaton
        Costado_de_via
        Mediana
        Intersecciones
        Flujo
        Infraestructura
        Velocidades
        Matriz
        Matriz2
        Matriz3
        O_V_SalidaCaminoconductor
        O_V_SalidaCaminocopiloto
        O_V_PerdidaControl 
        O_V_Adelantamiento 
        O_V_Intersecciones
        O_V_Accpropiedad

        Moto_SalidaCaminoconductor
        Moto_SalidaCaminocopiloto
        Moto_PerdidaControl
        Moto_Adelantamiento
        Moto_Intersecciones
        Moto_Accpropiedad 
        Moto_Alolargo
        
        Cicli_Alolargo 
        Cicli_SalidadCamino
        Cicli_Interseccion
        
        Peat_Alolargoconductor
        Peat_Alolargocopiloto
        Peat_CrucepeatonalIns
        Peat_CrucepeatonalLat
        
        Tablacontramedidas


    end
    
    methods (Access = private)
        
        function results = buscar(app)
            app.OcupantedevehculosEditField_4.Value=app.Datos_interfaz_2.Puntuacion_Ocupante_del_vehiculo(app.b1);
            app.MotociclistaEditField_4.Value=app.Datos_interfaz_2.Puntuacion_Motociclista(app.b1);
            app.CiclistaEditField_4.Value=app.Datos_interfaz_2.Puntuacion_Ciclista(app.b1);
            app.PeatnEditField_4.Value=app.Datos_interfaz_2.Puntuacion_Peaton(app.b1);
            app.OcupantedevehculosEditField_3.Value=app.Datos_interfaz_2.Ocupante_del_vehiculo(app.b1);
            app.MotociclistaEditField_3.Value=app.Datos_interfaz_2.Motociclista(app.b1);
            app.CiclistaEditField_3.Value=app.Datos_interfaz_2.Ciclista(app.b1);
            app.PeatnEditField_3.Value=app.Datos_interfaz_2.Peaton(app.b1);
        end
        
        function results = buscar_1(app)
            a=app.Datos_interfaz_2;
            a1=cell2mat(a.Severidad_lateral_distancia_al_objeto_lado_del_conductor(app.b1));
            a2=cell2mat(a.Severidad_lateral_objeto_lado_del_conductor(app.b1));
            a3=cell2mat(a.Severidad_lateral_distancia_al_objeto_lado_del_copiloto(app.b1));
            a4=cell2mat(a.Severidad_lateral_objeto_lado_del_copiloto(app.b1));
            a5=cell2mat(a.Bandas_sonoras_en_el_espaldon(app.b1));
            a6=cell2mat(a.Ancho_de_espaldon_pavimentado_lado_del_conductor(app.b1));
            a7=cell2mat(a.Ancho_de_espaldon_pavimentado_lado_del_copiloto(app.b1));
            app.Costado_de_via={'Severidad lateral - distancia al objeto (lado del conductor)',a1;'Severidad lateral - objeto (lado del conductor)',a2;'Severidad lateral - distancia al objeto (lado del copiloto)',a3;'Severidad lateral - objeto (lado del copiloto)',a4;'Bandas sonoras en el espaldón',a5;'Ancho de espaldón pavimentado (lado del conductor)',a6;'Ancho de espaldón pavimentado (lado del copiloto)',a7;};
            app.UITable_55.Data=app.Costado_de_via;
            
            b=app.Datos_interfaz_2;
            B1=cell2mat(b.Tipo_de_carretera(app.b1));
            b2=cell2mat(b.Costo_de_modernizacion(app.b1));
            b3=cell2mat(b.Tipo_de_mediana(app.b1));
            b4=cell2mat(b.Bandas_sonoras_centrales(app.b1));
            b5=cell2mat(b.Numero_de_carriles(app.b1));
            b6=cell2mat(b.Ancho_de_carril(app.b1));
            b7=cell2mat(b.Curvatura(app.b1));
            b8=cell2mat(b.Calidad_de_la_curva(app.b1));
            b9=cell2mat(b.Pendiente(app.b1));
            b10=cell2mat(b.Estado_de_la_superficie_de_la_via(app.b1));
            b11=cell2mat(b.Resistencia_al_deslizamiento(app.b1));
            b12=cell2mat(b.Delineacion(app.b1));
            b13=cell2mat(b.Alumbrado_publico(app.b1));
            b14=cell2mat(b.Estacionamiento_para_vehiculos(app.b1));
            b15=cell2mat(b.Via_de_servicio(app.b1));
            b16=cell2mat(b.Obras_en_la_carretera(app.b1));
            b17=cell2mat(b.Distancia_de_visibilidad(app.b1));
            app.Mediana={'Tipo de carretera',B1;'Costo de modernización',b2;'Tipo de mediana',b3;'Bandas sonoras centrales',b4;'Número de carriles',b5;'Ancho de carril',b6;'Curvatura',b7;'Calidad de la curva',b8;'Pendiente',b9;'Estado de la superficie de la vía',b10;'Resistencia al deslizamiento',b11;'Delineación',b12;'Alumbrado público',b13;'Estacionamiento para vehículos',b14;'Vía de servicio',b15;'Obras en la carretera',b16;'Distancia de visibilidad',b17};
            app.UITable_56.Data=app.Mediana;
            
            c=app.Datos_interfaz_2;
            c1=cell2mat(c.Tipo_de_interseccion(app.b1));
            c2=cell2mat(c.Canalizacion_de_la_interseccion(app.b1));
            c3=cell2mat(c.Volumen_de_la_interseccion(app.b1));
            c4=cell2mat(c.Calidad_de_la_interseccion(app.b1));
            c5=cell2mat(c.Puntos_de_acceso_a_la_propiedad(app.b1));
            app.Intersecciones={'Tipo de intersección',c1;'Canalización de la intersección',c2;'Volumen de la intersección',c3;'Calidad de la intersección',c4;'Puntos de acceso a la propiedad',c5};
            app.UITable_57.Data=app.Intersecciones;
            
            d=app.Datos_interfaz_2;
            d1=cell2mat(d.Flujo_vehicular(app.b1));
            d2=cell2mat(d.Porcentaje_de_motocicletas(app.b1));
            d3=cell2mat(d.Flujo_peatonal_en_hora_pico_que_cruza_la_carretera(app.b1));
            d4=cell2mat(d.Flujo_peatonal_en_hora_pico_a_lo_largo_de_la_carretera_Cond(app.b1));
            d5=cell2mat(d.Flujo_peatonal_en_hora_pico_a_lo_largo_de_la_carretera_Cop(app.b1));
            d6=cell2mat(d.Flujo_de_bicicletas_en_hora_pico(app.b1));
            app.Flujo={'Flujo vehicular (TDPA)',d1;'% motocicletas',d2;'Flujo peatonal en hora pico que cruza la carretera',d3;'Flujo peatonal en hora pico a lo largo de la carretera (lado del conductor)',d4;'Flujo peatonal en hora pico a lo largo de la carretera (lado del copiloto)',d5;'Flujo de bicicletas en hora pico',d6};
            app.UITable_58.Data=app.Flujo;
            
            e=app.Datos_interfaz_2;
            e1=cell2mat(e.Uso_de_suelo_lado_del_conductor(app.b1));
            e2=cell2mat(e.Uso_de_suelo_lado_del_copiloto(app.b1));
            e3=cell2mat(e.Tipo_de_area(app.b1));
            e4=cell2mat(e.Infraestructura_de_cruce_peatonal_en_el_tramo_analizado(app.b1));
            e5=cell2mat(e.Calidad_de_cruce_peatonal(app.b1));
            e6=cell2mat(e.Infraestructura_de_cruce_peatonal_en_la_interseccion(app.b1));
            e7=cell2mat(e.Vallas_peatonales(app.b1));
            e8=cell2mat(e.Acera_lado_del_conductor(app.b1));
            e9=cell2mat(e.Acera_lado_del_copiloto(app.b1));
            e10=cell2mat(e.Infraestructura_para_motocicletas(app.b1));
            e11=cell2mat(e.Infraestructura_para_bicicletas(app.b1));
            e12=cell2mat(e.Advertencia_de_zona_escolar(app.b1));
            e13=cell2mat(e.Supervisor_en_el_cruce_de_zona_escolar(app.b1));
            app.Infraestructura={'Uso de suelo (lado del conductor)',e1;'Uso de suelo (lado del copiloto)',e2;'Tipo de área',e3;'Infraestructura de cruce peatonal en el tramo analizado',e4;'Calidad de cruce peatonal',e5;'Infraestructura de cruce peatonal en la intersección',e6;'Vallas peatonales',e7;'Acera (lado del conductor)',e8;'Acera (lado del copiloto)',e9;'Infraestructura para motocicletas',e10;'Infraestructura para bicicletas',e11;'Advertencia de zona escolar',e12;'Supervisor en el cruce de zona escolar',e13};
            app.UITable_59.Data=app.Infraestructura;
            
            f=app.Datos_interfaz_2;
            f1=cell2mat(f.Limite_de_velocidad(app.b1));
            f2=cell2mat(f.Diferencial_de_limites_de_velocidad(app.b1));
            f3=cell2mat(f.Gestion_de_la_velocidad_calmante_del_trafico(app.b1));
            f4=cell2mat(f.Velocidad_de_operacion_percentil_85(app.b1));
            app.Velocidades={'Límite de velocidad',f1;'Diferencial de límites de velocidad ',f2;'Gestión de la velocidad/calmado el tráfico',f3;'Velocidad de operación (percentil 85)',f4};
            app.UITable_60.Data=app.Velocidades;
        end
        
        function results = Suavizado(app)
            %Carga de los datos  
            load('../mat/Datos_interfaz_dos_proceso.mat');
            load('../mat/Datos_atributos_proceso.mat');
            Intervalo=sum(arrayfun(@(Datos_interfaz_dos) ~isempty(Datos_interfaz_dos.Tramos),Datos_interfaz_dos));
            Tramos=[Datos_interfaz_dos(1:Intervalo).Tramos];
            Distancia=[Datos_interfaz_dos(1:Intervalo).Distancia];
            Datos_suavizado.Tramos=Tramos;
            Datos_suavizado.Distancia=Distancia;
            
            %%Para ocupante del vehiculo
            %Datos iniciales
            OV1=[Datos_interfaz_dos(1:Intervalo).Puntuacion_Ocupante_del_vehiculo];
            n21=length(OV1); 
            
            %Suavizacion - Media Movil
            OV2=movmean(OV1,9);
          
            %Suavizacion - Exponencial simple
            for i=1:n21
                d3(i)= OV2(i);
            end

            alfa=0.1;
            p3(1)= d3(1);
            for i= 2: n21  
                p3(i)= p3(i-1)+ alfa*(d3(i)-p3(i-1));
            end
            p3(i);
            
            %Puntaje de clasificación por estrellas suavizado 
            EstVehicul=p3(1:end);
            n=length(EstVehicul);
            
            %Clasificación por estrellas suavizado 
            for i=1:n    
            if EstVehicul(i)>=22.5
                EstVehiculo(i)=1;
            else if EstVehicul(i)>=12.5 && EstVehicul(i)<22.5
                EstVehiculo(i)=2;
            else if EstVehicul(i)>=5 && EstVehicul(i)<12.5
                EstVehiculo(i)=3;
            else if EstVehicul(i)>=2.5 && EstVehicul(i)<5
                EstVehiculo(i)=4;
            else EstVehicul(i)>=0 && EstVehicul(i)<2.5
                EstVehiculo(i)=5;
                end
                end
                end
            end
            end 
            EstVehiculo(i);
            Datos_suavizado.Puntuacion_Ocupante_del_vehiculo=EstVehicul;
            Datos_suavizado.Ocupante_del_vehiculo=EstVehiculo;
            
            %%Para Motociclista
            %Datos iniciales
            M1=[Datos_interfaz_dos(1:Intervalo).Puntuacion_Motociclista];
            n111=length(M1); 
            
            %Suavizacion - Media Movil
            for i=1:n111 
                if M1(i)>0
                    M1c(i)=M1(i);
                else
                    M1c(i)=NaN;
                end
            end
                
            M2c = movmean(M1c,9,'omitnan');
            for i=1:n111
                if Datos_interfaz_dos(i).Puntuacion_Motociclista==0
                  M2c(i)=0;  
                end
            end
            
            %Suavizacion - Exponencial simple
            for i=1:n111
                d2(i)= M2c(i);
            end

            alfa=0.1;
            p2(1)= d2(1);
            for i= 2: n111
                if d2(i-1)==0
                p2(i)= d2(i);
                else    
                p2(i)= p2(i-1)+ alfa*( d2(i)-p2(i-1));
                end
            end
            p2(i);
            
            for i=1:n111
                if Datos_interfaz_dos(i).Puntuacion_Motociclista==0
                  p2(i)=0;  
                end
            end
             
            %Puntaje de clasificación por estrellas suavizado 
            EstMoto=p2(1:end);
            n=length(EstMoto);
            
            %Clasificación por estrellas suavizado 
            for i=1:n  
            if EstMoto(i)>=22.5
                EstMotocicleta(i)=1;
            else if EstMoto(i)>=12.5 && EstMoto(i)<22.5
                EstMotocicleta(i)=2;
            else if EstMoto(i)>=5 && EstMoto(i)<12.5
                EstMotocicleta(i)=3;
            else if EstMoto(i)>=2.5 && EstMoto(i)<5
                EstMotocicleta(i)=4;
            else if EstMoto(i)>0 && EstMoto(i)<2.5
                EstMotocicleta(i)=5;
            else EstMoto(i)<=0;
                EstMotocicleta(i)=0;
                end
                end
                end
            end
            end
            end 
            EstMotocicleta(i)
            Datos_suavizado.Puntuacion_Motociclista=EstMoto;
            Datos_suavizado.Motociclista=EstMotocicleta;
             
            %%Para Ciclista
            %Datos iniciales
            C1=[Datos_interfaz_dos(1:Intervalo).Puntuacion_Ciclista];
            n11=length(C1);
            
            %Suavizacion - Media Móvil
            for i=1:n11 
                if C1(i)>0
                    C1c(i)=C1(i);
                else
                    C1c(i)=NaN;
                end
            end
            
            C2c = movmean(C1c,9,'omitnan');
            for i=1:n11
                if Datos_interfaz_dos(i).Puntuacion_Ciclista==0
                  C2c(i)=0;  
                end
            end

            %Suavizacion - Exponencial simple
            for i=1:n11
                d1(i)= C2c(i);
            end

            alfa=0.1;
            p1(1)= d1(1);
            for i= 2: n11
                if d1(i-1)==0
                p1(i)= d1(i);
                else    
                p1(i)= p1(i-1)+ alfa*( d1(i)-p1(i-1));
                end
            end
            p1(i);
            
            for i=1:n11
                if Datos_interfaz_dos(i).Puntuacion_Ciclista==0
                  p1(i)=0;  
                end
            end

            %Puntaje de clasificación por estrellas suavizado 
            EstCicl=p1(1:end);
            n=length(EstCicl);

            %Clasificación por estrellas suavizado 
            for i=1:n  
            if EstCicl(i)>=60
                EstCiclista(i)=1;
            else if EstCicl(i)>=30 && EstCicl(i)<60
                EstCiclista(i)=2;
            else if EstCicl(i)>=10 && EstCicl(i)<30
                EstCiclista(i)=3;
            else if EstCicl(i)>=5 && EstCicl(i)<10
                EstCiclista(i)=4;
            else if EstCicl(i)>0 && EstCicl(i)<5
                EstCiclista(i)=5;
            else EstCicl(i)<=0;
                EstCiclista(i)=0;
                end
                end
                end
                end
            end
            end 
            EstCiclista(i)
            Datos_suavizado.Puntuacion_Ciclista=EstCicl;
            Datos_suavizado.Ciclista=EstCiclista;
            
            %%Para Peatones
            %Datos iniciales
            P1=[Datos_interfaz_dos(1:Intervalo).Puntuacion_Peaton];
            n1=length(P1);
            
            %Suavizacion - Media Movil
            for i=1:n1 
                if P1(i)>0
                    P1c(i)=P1(i);
                else
                    P1c(i)=NaN;
                end
            end
            
            P2c = movmean(P1c,9,'omitnan');
            for i=1:n1
                if Datos_interfaz_dos(i).Puntuacion_Peaton==0
                  P2c(i)=0; 
                end
            end

            %Suavizacion - Exponencial simple
            for i=1:n1
                d(i)= P2c(i);
            end

            alfa=0.1;
            p(1)= d(1);
            for i= 2: n1
                if d(i-1)==0
                p(i)= d(i);
                else    
                p(i)= p(i-1)+ alfa*( d(i)-p(i-1));
                end
            end
            p(i)
            
            for i=1:n1
                if Datos_interfaz_dos(i).Puntuacion_Peaton==0
                  p(i)=0;
                end
            end

            %Puntaje de clasificación por estrellas suavizado 
            EstPeato=p(1:end);
            n=length(EstPeato);

            %Clasificación por estrellas suavizado 
            for i=1:n 
            if Atributo_elegido(i).Atributo(32)==1 && Atributo_elegido(i).Atributo(33)==1 && Atributo_elegido(i).Atributo(34)==1 
                if EstPeato(i)>=90
                    EstPeaton(i)=1;
                elseif EstPeato(i)>=40 && EstPeato(i)<90
                    EstPeaton(i)=2;
                elseif EstPeato(i)>=15 && EstPeato(i)<40
                    EstPeaton(i)=3;
                elseif EstPeato(i)>=5 && EstPeato(i)<15
                    EstPeaton(i)=4;
                elseif EstPeato(i)>0 && EstPeato(i)<5
                    EstPeaton(i)=5;
                elseif EstPeato(i)<=0
                    EstPeaton(i)=0;
                end
            end
                
            if Atributo_elegido(i).Atributo(32)==1 && Atributo_elegido(i).Atributo(33)>=2 && Atributo_elegido(i).Atributo(34)==1 %A lo largo
                if EstPeato(i)>=15
                    EstPeaton(i)=1;
                elseif EstPeato(i)>=7.5 && EstPeato(i)<15
                    EstPeaton(i)=2;
                elseif EstPeato(i)>=1 && EstPeato(i)<7.5
                    EstPeaton(i)=3;
                elseif EstPeato(i)>=0.2 && EstPeato(i)<1
                    EstPeaton(i)=4;
                elseif EstPeato(i)>0 && EstPeato(i)<0.2
                    EstPeaton(i)=5;
                elseif EstPeato(i)<=0
                    EstPeaton(i)=0;
                end
            end
            
            if Atributo_elegido(i).Atributo(32)==1 && Atributo_elegido(i).Atributo(33)>=2 && Atributo_elegido(i).Atributo(34)>=2 %A lo largo
                if EstPeato(i)>=15
                    EstPeaton(i)=1;
                elseif EstPeato(i)>=7.5 && EstPeato(i)<15
                    EstPeaton(i)=2;
                elseif EstPeato(i)>=1 && EstPeato(i)<7.5
                    EstPeaton(i)=3;
                elseif EstPeato(i)>=0.2 && EstPeato(i)<1
                    EstPeaton(i)=4;
                elseif EstPeato(i)>0 && EstPeato(i)<0.2
                    EstPeaton(i)=5;
                elseif EstPeato(i)<=0
                    EstPeaton(i)=0;
                end
            end
            if Atributo_elegido(i).Atributo(32)==1 && Atributo_elegido(i).Atributo(33)==1 && Atributo_elegido(i).Atributo(34)>=2 %A lo largo
                if EstPeato(i)>=15
                    EstPeaton(i)=1;
                elseif EstPeato(i)>=7.5 && EstPeato(i)<15
                    EstPeaton(i)=2;
                elseif EstPeato(i)>=1 && EstPeato(i)<7.5
                    EstPeaton(i)=3;
                elseif EstPeato(i)>=0.2 && EstPeato(i)<1
                    EstPeaton(i)=4;
                elseif EstPeato(i)>0 && EstPeato(i)<0.2
                    EstPeaton(i)=5;
                elseif EstPeato(i)<=0
                    EstPeaton(i)=0;
                end
            end
            
            if Atributo_elegido(i).Atributo(32)>=2 && Atributo_elegido(i).Atributo(33)==1 && Atributo_elegido(i).Atributo(34)==1 %Cruce
                if EstPeato(i)>=75
                    EstPeaton(i)=1;
                elseif EstPeato(i)>=32.5 && EstPeato(i)<75
                    EstPeaton(i)=2;
                elseif EstPeato(i)>=14 && EstPeato(i)<32.5
                    EstPeaton(i)=3;
                elseif EstPeato(i)>=4.8 && EstPeato(i)<14
                    EstPeaton(i)=4;
                elseif EstPeato(i)>0 && EstPeato(i)<4.8
                    EstPeaton(i)=5;
                elseif EstPeato(i)<=0
                    EstPeaton(i)=0;
                end
            end
            
            if Atributo_elegido(i).Atributo(32)>=2 && Atributo_elegido(i).Atributo(33)>=2 && Atributo_elegido(i).Atributo(34)==1 %Total
                if EstPeato(i)>=90
                    EstPeaton(i)=1;
                elseif EstPeato(i)>=40 && EstPeato(i)<90
                    EstPeaton(i)=2;
                elseif EstPeato(i)>=15 && EstPeato(i)<40
                    EstPeaton(i)=3;
                elseif EstPeato(i)>=5 && EstPeato(i)<15
                    EstPeaton(i)=4;
                elseif EstPeato(i)>0 && EstPeato(i)<5
                    EstPeaton(i)=5;
                elseif EstPeato(i)<=0
                    EstPeaton(i)=0;
                end
            end
            
            if Atributo_elegido(i).Atributo(32)>=2 && Atributo_elegido(i).Atributo(33)==1 && Atributo_elegido(i).Atributo(34)>=2 %Total
                if EstPeato(i)>=90
                EstPeaton(i)=1;
                elseif EstPeato(i)>=40 && EstPeato(i)<90
                    EstPeaton(i)=2;
                elseif EstPeato(i)>=15 && EstPeato(i)<40
                    EstPeaton(i)=3;
                elseif EstPeato(i)>=5 && EstPeato(i)<15
                    EstPeaton(i)=4;
                elseif EstPeato(i)>0 && EstPeato(i)<5
                    EstPeaton(i)=5;
                elseif EstPeato(i)<=0
                    EstPeaton(i)=0;
                end
            end
            
            if Atributo_elegido(i).Atributo(32)>=2 && Atributo_elegido(i).Atributo(33)>=2 && Atributo_elegido(i).Atributo(34)>=2 %Total
                if EstPeato(i)>=90
                EstPeaton(i)=1;
                elseif EstPeato(i)>=40 && EstPeato(i)<90
                    EstPeaton(i)=2;
                elseif EstPeato(i)>=15 && EstPeato(i)<40
                    EstPeaton(i)=3;
                elseif EstPeato(i)>=5 && EstPeato(i)<15
                    EstPeaton(i)=4;
                elseif EstPeato(i)>0 && EstPeato(i)<5
                    EstPeaton(i)=5;
                elseif EstPeato(i)<=0
                    EstPeaton(i)=0;
                end
            end
            end
            
            EstPeaton(i)
            Datos_suavizado.Puntuacion_Peaton=EstPeato;
            Datos_suavizado.Peaton=EstPeaton;
            
            save('../mat/Datos_suavizado_postproceso','Datos_suavizado')
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Se cargan los datos obtenidos de las interfaces previas

            load('../mat/Datos_interfaz_dos_proceso.mat');
            
            Intervalo=sum(arrayfun(@(Datos_interfaz_dos) ~isempty(Datos_interfaz_dos.Tramos),Datos_interfaz_dos));
            
            Nuevos_datos(Intervalo).Nueva_Estrella_Ocupante_vehiculo=[];
            Nuevos_datos(Intervalo).Nueva_Estrella_Motociclista=[];
            Nuevos_datos(Intervalo).Nueva_Estrella_Ciclista=[];
            Nuevos_datos(Intervalo).Nueva_Estrella_Peaton=[];
            Nuevos_datos(Intervalo).Nuevo_Puntaje_Ocupante_vehiculo=[];
            Nuevos_datos(Intervalo).Nuevo_Puntaje_Motociclista=[];
            Nuevos_datos(Intervalo).Nuevo_Puntaje_Ciclista=[];
            Nuevos_datos(Intervalo).Nuevo_Puntaje_Peaton=[];
            
            save('../mat/Nueva_clasificación_postproceso',"Nuevos_datos")
            
            Matriz_x(Intervalo).Atributos=[];
            save('../mat/Matriz_indices_nuevos_postproceso','Matriz_x')
            
            %Matriz para los contramedidas
             Matriz_y(Intervalo).Factores1=[];
             Matriz_y(Intervalo).Factores2=[];
             Matriz_y(Intervalo).Factores3=[];
             Matriz_y(Intervalo).Factores4=[];
             Matriz_y(Intervalo).Factores5=[];
             Matriz_y(Intervalo).Factores6=[];
             Matriz_y(Intervalo).Factores7=[];
             Matriz_y(Intervalo).Factores8=[];
             Matriz_y(Intervalo).Factores9=[];
             Matriz_y(Intervalo).Factores10=[];
             Matriz_y(Intervalo).Factores11=[];
             Matriz_y(Intervalo).Factores12=[];
             Matriz_y(Intervalo).Factores13=[];
             Matriz_y(Intervalo).Factores14=[];
             Matriz_y(Intervalo).Factores15=[];
             Matriz_y(Intervalo).Factores16=[];
             Matriz_y(Intervalo).Factores17=[];
             Matriz_y(Intervalo).Factores18=[];
             Matriz_y(Intervalo).Factores19=[];
             Matriz_y(Intervalo).Factores20=[];
             
            save('../mat/Matriz_factores_contramedidas_postproceso','Matriz_y')
            
            %Matriz para los atributos seleccionados en las contramedidas
            Matriz_x1(Intervalo).Factor=[];
            save('../mat/Matriz_contramedidas_postproceso','Matriz_x1')
            %Matriz para alamcenar las contramedidas seleccionadas
            Matriz_contramedidas(Intervalo).Resultadoscontramedidas=[];
            save('../mat/Matriz_contramedidas_seleccionadas_postproceso','Matriz_contramedidas')
            
            %Matriz para guaradr los resultados de los MLG
             Matriz_z(Intervalo).Accidente1=[];
             Matriz_z(Intervalo).Accidente2=[];
             Matriz_z(Intervalo).Accidente3=[];
             Matriz_z(Intervalo).Accidente4=[];
             Matriz_z(Intervalo).Accidente5=[];
             Matriz_z(Intervalo).Accidente6=[];
             Matriz_z(Intervalo).Accidente7=[];
             Matriz_z(Intervalo).Accidente8=[];
             Matriz_z(Intervalo).Accidente9=[];
             Matriz_z(Intervalo).Accidente10=[];
             Matriz_z(Intervalo).Accidente11=[];
             Matriz_z(Intervalo).Accidente12=[];
             Matriz_z(Intervalo).Accidente13=[];
             Matriz_z(Intervalo).Accidente14=[];
             Matriz_z(Intervalo).Accidente15=[];
             Matriz_z(Intervalo).Accidente16=[];
             Matriz_z(Intervalo).Accidente17=[];
             Matriz_z(Intervalo).Accidente18=[];
             Matriz_z(Intervalo).Accidente19=[];
             Matriz_z(Intervalo).Accidente20=[];
             
            save('../mat/Matriz_Resultados_MGL_postproceso','Matriz_z')
            
            %Matriz para almacenar las contramedidas seleccionadas
            Datos_Tcontramedidas(Intervalo).Tramos = [];
            Datos_Tcontramedidas(Intervalo).Distancia = [];
            Datos_Tcontramedidas(Intervalo).Contramedida1 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida2 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida3 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida4 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida5 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida6 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida7 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida8 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida9 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida10 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida11 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida12 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida13 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida14 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida15 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida16 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida17 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida18 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida19 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida20 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida21 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida22 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida23 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida24 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida25 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida26 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida27 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida28 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida29 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida30 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida31 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida32 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida33 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida34 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida35 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida36 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida37 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida38 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida39 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida40 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida41 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida42 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida43 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida44 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida45 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida46 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida47 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida48 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida49 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida50 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida51 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida52 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida53 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida54 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida55 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida56 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida57 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida58 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida59 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida60 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida61 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida62 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida63 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida64 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida65 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida66 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida67 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida68 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida69 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida70 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida71 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida72 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida73 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida74 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida75 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida76 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida77 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida78 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida79 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida80 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida81 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida82 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida83 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida84 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida85 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida86 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida87 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida88 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida89 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida90 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida91 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida92 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida93 = {};
            Datos_Tcontramedidas(Intervalo).Contramedida94 = {};
            
            save('../mat/Matriz_resumen_contramedidas_postproceso','Datos_Tcontramedidas')
            
            %Correr la función que realiza el suavizado
            Suavizado(app)
            
            app.Datos_interfaz_2=struct2table(Datos_interfaz_dos,'AsArray',true);
                       
            % Primera parte de la interfaz - Reporte General
                %   COSTADO DE LA VÍA
             %Se usa la función para extraer los elementos del primer atributo vial
            [d1,c1,d2,c2,d3,c3,d4,c4,d5,c5,d6,c6,d7,c7,d8,c8,d9,c9,d10,c10,d11,c11,d12,c12,d13,c13,d14,c14,d15,c15,d16,c16,d17,c17,d18,c18,d19,c19,d20,c20,d21,c21,d22,c22,d23,c23,d24,c24,d25,c25,d26,c26,d27,c27,d28,c28,d29,c29,d30,c30,d31,c31,d32,c32,d33,c33,d34,c34,d35,c35,d36,c36,d37,c37,d38,c38,d39,c39,d40,c40,d41,c41,d42,c42,d43,c43,d44,c44,d45,c45,d46,c46,d47,c47,d48,c48,d49,c49,d50,c50,d51,c51,d52,c52] = leer_tablas(app.Datos_interfaz_2);
            
                %Primer subatributo - Severidad lateral - objeto (lado del conductor)     
            datos={'Barrera de seguridad – metal',c5,d5;'Barrera de seguridad – hormigón',c6,d6;'Barrera de seguridad – amigable para motocicletas',c7,d7;'Barrera de seguridad – cable de acero',c8,d8;'Cara vertical agresiva en el talud en corte',c9,d9;'Pendiente ascendente del talud en corte (15° a 75°)',c10,d10;'Pendiente empinada del talud en corte (> 75°)',c11,d11;'Cuneta profunda',c12,d12;'Terraplén',c13,d13;'Precipicio',c14,d14;'Árbol (≥ 10 cm de diámetro)',c15,d15;'Letrero / poste / barra rígidos (≥ 10 cm de diámetro)',c16,d16;'Estructura / puente / edificio rígidos',c17,d17;'Estructura /edificio colapsable',c18,d18;'Extremo de barrera desprotegido',c19,d19;'Grandes rocas (≥ 20 cm de altura)',c20,d20;'Ninguno (u objeto > 20 m de la carretera)',c21,d21};
            app.UITable.Data=datos;
            app.UITable.ColumnFormat={'','bank','bank'};
                %Segundo subatributo - Severidad lateral - distancia al objeto (lado del conductor)               
            datos={'0 a < 1 m',c1,d1;'1 a < 5 m',c2,d2;'5 a < 10 m',c3,d3;'≥ 10 m',c4,d4};
            app.UITable_2.Data=datos;
            app.UITable_2.ColumnFormat={'','bank','bank'};
                %Tercer subatributo - Severidad lateral - objeto (lado del copiloto)         
            datos={'Barrera de seguridad – metal',c26,d26;'Barrera de seguridad – hormigón',c27,d27;'Barrera de seguridad – amigable para motocicletas',c28,d28;'Barrera de seguridad – cable de acero',c29,d29;'Cara vertical agresiva en el talud en corte',c30,d30;'Pendiente ascendente del talud en corte (15° a 75°)',c31,d31;'Pendiente empinada del talud en corte (> 75°)',c32,d32;'Cuneta profunda',c33,d33;'Terraplén',c34,d34;'Precipicio',c35,d35;'Árbol (≥ 10 cm de diámetro)',c36,d36;'Letrero / poste / barra rígidos (≥ 10 cm de diámetro)',c37,d37;'Estructura / puente / edificio rígidos',c38,d38;'Estructura /edificio colapsable',c39,d39;'Extremo de barrera desprotegido',c40,d40;'Grandes rocas (≥ 20 cm de altura)',c41,d41;'Ninguno (u objeto > 20 m de la carretera)',c42,d42};
            app.UITable_3.Data=datos;
            app.UITable_3.ColumnFormat={'','bank','bank'};
                %Cuarto subatributo - Severidad lateral - distancia al objeto (lado del copiloto)
            datos={'0 a < 1 m',c22,d22;'1 a < 5 m',c23,d23;'5 a < 10 m',c24,d24;'≥ 10 m',c25,d25};
            app.UITable_4.Data=datos;
            app.UITable_4.ColumnFormat={'','bank','bank'};
                %Quinto subatributo - Ancho de espaldón pavimentado (lado del copiloto)
            datos={'Ancho (≥ 2.4 m)',c49,d49;'Medio (≥ 1.0 m a 2.4 m)',c50,d50;'Estrecho (≥ 0 m a 1.0 m)',c51,d51;'Ninguno',c52,d52};
            app.UITable_5.Data=datos;
            app.UITable_5.ColumnFormat={'','bank','bank'};
                %Sexto subatributo - Ancho de espaldón pavimentado (lado del conductor)
            datos={'Ancho (≥ 2.4 m)',c45,d45;'Medio (≥ 1.0 m a 2.4 m)',c46,d46;'Estrecho (≥ 0 m a 1.0 m)',c47,d47;'Ninguno',c48,d48};
            app.UITable_6.Data=datos;
            app.UITable_6.ColumnFormat={'','bank','bank'};
                %Séptimo subatributo - Bandas sonoras en el espaldón
            datos={'Ausente',c43,d43;'Presente',c44,d44};
            app.UITable_7.Data=datos;
            app.UITable_7.ColumnFormat={'','bank','bank'};
            
             %   MEDIANA
             %Se usa la función para extraer los elementos del segundo atributo vial
            [h1,i1,h2,i2,h3,i3,h4,i4,h5,i5,h6,i6,h7,i7,h8,i8,h9,i9,h10,i10,h11,i11,h12,i12,h13,i13,h14,i14,h15,i15,h16,i16,h17,i17,h18,i18,h19,i19,h20,i20,h21,i21,h22,i22,h23,i23,h24,i24,h25,i25,h26,i26,h27,i27,h28,i28,h29,i29,h30,i30,h31,i31,h32,i32,h33,i33,h34,i34,h35,i35,h36,i36,h37,i37,h38,i38,h39,i39,h40,i40,h41,i41,h42,i42,h43,i43,h44,i44,h45,i45,h46,i46,h47,i47,h48,i48,h49,i49,h50,i50,h51,i51,h52,i52,h53,i53,h54,i54,h55,i55,h56,i56,h57,i57,h58,i58,h59,i59,h60,i60,h61,i61,h62,i62,h63,i63,h64,i64,h65,i65,h66,i66] = leer_tablas_1(app.Datos_interfaz_2);
            
                %Primer subatributo - Tipo de carretera
            datos={'Calzada A de una calzada dividida',h1,i1;'Calzada B de una calzada dividida',h2,i2;'Carretera no dividida',h3,i3;'Calzada A de una infraestructura de motocicletas',h4,i4;'Calzada B de una infraestructura de motocicletas',h5,i5};
            app.UITable_10.Data=datos;
            app.UITable_10.ColumnFormat={'','bank','bank'};
                %Segundo subatributo - Costo de modernización
            datos={'Bajo',h6,i6;'Medio',h7,i7;'Alto',h8,i8};
            app.UITable_11.Data=datos;
            app.UITable_11.ColumnFormat={'','bank','bank'};
                %Tercer subatributo - Tipo de mediana
            datos={'Barrera de seguridad – metal',h9,i9;'Barrera de seguridad – concreto',h10,i10;'Mediana física con un ancho ≥ 20 m',h11,i11;'Mediana física con un ancho ≥ 10 m a < 20 m',h12,i12;'Mediana física con un ancho ≥ 5 m a < 10 m',h13,i13;'Mediana física con un ancho ≥ 1 m a < 5 m',h14,i14;'Mediana física con un ancho ≥ 0 m a < 1 m',h15,i15;'Carril central continuo para giro',h16,i16;'Delineadores flexibles',h17,i17;'Raya separadora de sentidos de circulación doble > 1 m y rayas diagonales a 45°',h18,i18;'Línea central',h19,i19;'Barrera de seguridad - amigable para motociclistas',h20,i20;'Un solo sentido',h21,i21;'Raya separadora de sentidos de circulación doble (0.3 m a 1 m) y rayas diagonales a 45°',h22,i22;'Barrera de seguridad - cable de acero',h23,i23};
            app.UITable_12.Data=datos;
            app.UITable_12.ColumnFormat={'','bank','bank'};
                %Cuarto subatributo - Bandas sonoras centrales
            datos={'Ausente',h24,i24;'Presente',h25,i25};
            app.UITable_13.Data=datos;
            app.UITable_13.ColumnFormat={'','bank','bank'};
                %Quinto subatributo - Número de carriles
            datos={'Uno',h26,i26;'Dos',h27,i27;'Tres',h28,i28;'Cuatro o más',h29,i29;'Dos + uno',h30,i30;'Tres + dos',h31,i31};
            app.UITable_14.Data=datos;
            app.UITable_14.ColumnFormat={'','bank','bank'};
                %Sexto subatributo - Ancho de carril
            datos={'Ancho (≥ 3.25 m)',h32,i32;'Medio (≥ 2.75 m a < 3.25 m)',h33,i33;'Estrecho (≥ 0 m a < 2.75 m)',h34,i34};
            app.UITable_15.Data=datos;
            app.UITable_15.ColumnFormat={'','bank','bank'};
                %Séptimo subatributo - Curvatura
            datos={'Recto o ligeramente curvado',h35,i35;'Moderada',h36,i36;'Cerrada',h37,i37;'Muy cerrada',h38,i38};
            app.UITable_16.Data=datos;
            app.UITable_16.ColumnFormat={'','bank','bank'};
                %Octavo subatributo
            datos={'Adecuada',h39,i39;'Deficiente',h40,i40;'No aplica',h41,i41};
            app.UITable_17.Data=datos;
            app.UITable_17.ColumnFormat={'','bank','bank'};
                %Noveno subatributo - Pendiente
            datos={'0 % a < 7.5 %',h42,i42;'7.5 % a 10 %',h43,i43;'≥ 10 %',h44,i44};
            app.UITable_18.Data=datos;
            app.UITable_18.ColumnFormat={'','bank','bank'};
                %Décimo subatributo - Estado de la superficie de la vía
            datos={'Buena',h45,i45;'Regular',h46,i46;'Mala',h47,i47};
            app.UITable_19.Data=datos;
            app.UITable_19.ColumnFormat={'','bank','bank'};
                %Décimo primero subatributo - Resistencia al deslizamiento
            datos={'Pavimentado – adecuado',h48,i48;'Pavimentado – regular',h49,i49;'Pavimentado – pobre',h50,i50;'Sin pavimentar – adecuado',h51,i51;'Sin pavimentar – pobre',h52,i52};
            app.UITable_20.Data=datos;
            app.UITable_20.ColumnFormat={'','bank','bank'};
                %Décimo segundo subatributo - Delineación
            datos={'Adecuada',h53,i53;'Deficiente',h54,i54};
            app.UITable_21.Data=datos;
            app.UITable_21.ColumnFormat={'','bank','bank'};
                %Décimo tercero subatributo - Alumbrado público
            datos={'Ausente',h55,i55;'Presente',h56,i56};
            app.UITable_22.Data=datos;
            app.UITable_22.ColumnFormat={'','bank','bank'};
                %Décimo cuarto subatributo - Estacionamiento para vehículos
            datos={'Ninguno',h57,i57;'Un lado',h58,i58;'Dos lados',h59,i59};
            app.UITable_23.Data=datos;
            app.UITable_23.ColumnFormat={'','bank','bank'};
                %Décimo quinto subatributo - Vía de servicio
            datos={'Ausente',h60,i60;'Presente',h61,i61};
            app.UITable_24.Data=datos;
            app.UITable_24.ColumnFormat={'','bank','bank'};
                %Décimo sexto subatributo - Obras en la carretera
            datos={'Sin obras',h62,i62;'Obra menor en proceso',h63,i63;'Obra mayor en proceso',h64,i64};
            app.UITable_25.Data=datos;
            app.UITable_25.ColumnFormat={'','bank','bank'};
                %Décimo séptimo subatributo - Distancia de visibilidad
            datos={'Adecuada',h65,i65;'Deficiente',h66,i66};
            app.UITable_26.Data=datos;
            app.UITable_26.ColumnFormat={'','bank','bank'};
            
            %   INTERSECCIONES
            %Se usa la función para extraer los elementos del tercer atributo vial
            [h1,i1,h2,i2,h3,i3,h4,i4,h5,i5,h6,i6,h7,i7,h8,i8,h9,i9,h10,i10,h12,i12,h13,i13,h14,i14,h15,i15,h16,i16,h17,i17,h18,i18,h19,i19,h20,i20,h21,i21,h22,i22,h23,i23,h24,i24,h25,i25,h26,i26,h27,i27,h28,i28,h29,i29,h30,i30,h31,i31,h32,i32,h33,i33] = leer_tablas_2(app.Datos_interfaz_2);
            
                %Primer subatributo - Tipo de intersección
            datos={'Carril de incorporación',h1,i1;'Redondel',h2,i2;'3 ramas (no semaforizada) con carril de giro protegido',h3,i3;'3 ramas (no semaforizada) con carril de giro no protegido',h4,i4;'3 ramas (semaforizada) con carril de giro protegido',h5,i5;'3 ramas (semaforizada) con carril de giro no protegido',h6,i6;'4 ramas (no semaforizada) con carril de giro protegido',h7,i7;'4 ramas (no semaforizada) con carril de giro no protegido',h8,i8;'4 ramas (semaforizada) con carril de giro protegido',h9,i9;'4 ramas (semaforizada) con carril de giro no protegido',h10,i10;'Ninguno',h12,i12;'Cruce de ferrocarril - pasivo (solo señalización vertical)',h13,i13;'Cruce de ferrocarril - activo (semáforo intermitente / pluma)',h14,i14;'Punto de cruce de mediana - informal',h15,i15;'Punto de cruce de mediana - formal',h16,i16;'Mini redondel',h17,i17};
            app.UITable_28.Data=datos;
            app.UITable_28.ColumnFormat={'','bank','bank'};
                %Segundo subatributo - Canalización de la intersección
            datos={'Ausente',h18,i18;'Presente',h19,i19};
            app.UITable_27.Data=datos;
            app.UITable_27.ColumnFormat={'','bank','bank'};
                %Tercer subatributo - Volumen de la intersección
            datos={'≥ 15000 vehículos por día',h20,i20;'10000 a 15000 vehículos por día',h21,i21;'5000 a 10000 vehículos por día',h22,i22;'1000 a 5000 vehículos por día',h23,i23;'100 a 1000 vehículos por día',h24,i24;'1 a 100 vehículos por día',h25,i25;'Ninguno',h26,i26};
            app.UITable_30.Data=datos;
            app.UITable_30.ColumnFormat={'','bank','bank'};
                %Cuarto subatributo - Calidad de la intersección
            datos={'Adecuada',h27,i27;'Deficiente',h28,i28;'No aplica',h29,i29};
            app.UITable_29.Data=datos;
            app.UITable_29.ColumnFormat={'','bank','bank'};
                %Quinto subatributo - Puntos de acceso a la propiedad
            datos={'Acceso comercial 1+',h30,i30;'Acceso residencial 3+',h31,i31;'Acceso residencial 1 o 2',h32,i32;'Ninguno',h33,i33};
            app.UITable_31.Data=datos;
            app.UITable_31.ColumnFormat={'','bank','bank'};
            
            %   FLUJO
            %Se usa la función para extraer los elementos del cuarto atributo vial
            [h1,i1,h2,i2,h3,i3,h4,i4,h5,i5,h6,i6,h7,i7,h8,i8,h9,i9,h10,i10,h11,i11,h12,i12,h13,i13,h14,i14,h15,i15,h16,i16,h17,i17,h18,i18,h19,i19,h20,i20,h21,i21,h22,i22,h23,i23,h24,i24,h25,i25,h26,i26,h27,i27,h28,i28,h29,i29,h30,i30,h31,i31,h32,i32,h33,i33,h34,i34,h35,i35,h36,i36,h37,i37,h38,i38,h39,i39,h40,i40,h41,i41,h42,i42,h43,i43,h44,i44,h45,i45,h46,i46,h47,i47,h48,i48,h49,i49,h50,i50,h51,i51,h52,i52,h53,i53,h54,i54,h55,i55,h56,i56,h57,i57,h58,i58,h59,i59,h60,i60,h61,i61,h62,i62,h63,i63,h64,i64] = leer_tablas_3(app.Datos_interfaz_2);
            
                %Primer subatributo - Flujo vehicular (TDPA)
            datos={'0 - 1000 vehículos por día',h1,i1;'1000 - 5000 vehículos por día',h2,i2;'5000 - 10000 vehículos por día',h3,i3;'10000 - 15000 vehículos por día',h4,i4;'15000 - 20000 vehículos por día',h5,i5;'20000 - 40000 vehículos por día',h6,i6;'40000 - 60000 vehículos por día',h7,i7;'60000 - 80000 vehículos por día',h8,i8;'80000 - 100000 vehículos por día',h9,i9;'100000+ vehículos por día',h10,i10};
            app.UITable_33.Data=datos;
            app.UITable_33.ColumnFormat={'','bank','bank'};
                %Segundo subatributo - % motocicletas
            datos={'No registrado',h11,i11;'0 %',h12,i12;'1 % - 5 %',h13,i13;'6 % - 10 %',h14,i14;'11 % - 20 %',h15,i15;'21 % - 40 %',h16,i16;'41 % - 60 %',h17,i17;'61 % - 80 %',h18,i18;'81 % - 99 %',h19,i19;'100 %',h20,i20};
            app.UITable_32.Data=datos;
            app.UITable_32.ColumnFormat={'','bank','bank'};
                %Tercer subatributo - Flujo peatonal en hora pico que cruza la carretera
            datos={'0 peatones',h21,i21;'1 a 5 peatones',h22,i22;'6 a 25 peatones',h23,i23;'26 a 50 peatones',h24,i24;'51 a 100 peatones',h25,i25;'101 a 200 peatones',h26,i26;'201 a 300 peatones',h27,i27;'301 a 400 peatones',h28,i28;'401 a 500 peatones',h29,i29;'501 a 900 peatones',h30,i30;'900+ peatones',h31,i31};
            app.UITable_35.Data=datos;
            app.UITable_35.ColumnFormat={'','bank','bank'};
                %Cuarto subatributo - Flujo peatonal en hora pico a lo largo de la carretera (lado del conductor)
            datos={'0 peatones',h32,i32;'1 a 5 peatones',h33,i33;'6 a 25 peatones',h34,i34;'26 a 50 peatones',h35,i35;'51 a 100 peatones',h36,i36;'101 a 200 peatones',h37,i37;'201 a 300 peatones',h38,i38;'301 a 400 peatones',h39,i39;'401 a 500 peatones',h40,i40;'501 a 900 peatones',h41,i41;'900+ peatones',h42,i42};
            app.UITable_34.Data=datos;
            app.UITable_34.ColumnFormat={'','bank','bank'};
                %Quinto subatributo - Flujo peatonal en hora pico a lo largo de la carretera (lado del copiloto)
            datos={'0 peatones',h43,i43;'1 a 5 peatones',h44,i44;'6 a 25 peatones',h45,i45;'26 a 50 peatones',h46,i46;'51 a 100 peatones',h47,i47;'101 a 200 peatones',h48,i48;'201 a 300 peatones',h49,i49;'301 a 400 peatones',h50,i50;'401 a 500 peatones',h51,i51;'501 a 900 peatones',h52,i52;'900+ peatones',h53,i53};
            app.UITable_36.Data=datos;
            app.UITable_36.ColumnFormat={'','bank','bank'};
                %Sexto subatributo - Flujo de bicicletas en hora pico
            datos={'0 ciclistas',h54,i54;'1 a 5 ciclistas',h55,i55;'6 a 25 ciclistas',h56,i56;'26 a 50 ciclistas',h57,i57;'51 a 100 ciclistas',h58,i58;'101 a 200 ciclistas',h59,i59;'201 a 300 ciclistas',h60,i60;'301 a 400 ciclistas',h61,i61;'401 a 500 ciclistas',h62,i62;'501 a 900 ciclistas',h63,i63;'900+ ciclistas',h64,i64};
            app.UITable_37.Data=datos;
            app.UITable_37.ColumnFormat={'','bank','bank'};
            
            %   INFRAESTRUCTURA PARA USUARIOS VULNERABLES Y USO DEL SUELO
            %Se usa la función para extraer los elementos del quinto atributo vial
            [h1,i1,h2,i2,h3,i3,h4,i4,h6,i6,h7,i7,h8,i8,h9,i9,h10,i10,h11,i11,h13,i13,h14,i14,h15,i15,h16,i16,h17,i17,h18,i18,h19,i19,h20,i20,h21,i21,h22,i22,h23,i23,h24,i24,h25,i25,h26,i26,h27,i27,h28,i28,h29,i29,h30,i30,h31,i31,h32,i32,h33,i33,h34,i34,h35,i35,h36,i36,h37,i37,h38,i38,h39,i39,h40,i40,h41,i41,h43,i43,h44,i44,h45,i45,h46,i46,h47,i47,h48,i48,h49,i49,h50,i50,h51,i51,h52,i52,h53,i53,h54,i54,h55,i55,h56,i56,h57,i57,h58,i58,h59,i59,h60,i60,h61,i61,h62,i62,h63,i63,h64,i64,h65,i65,h66,i66,h67,i67,h68,i68,h69,i69,h70,i70,h71,i71,h72,i72,h73,i73,h74,i74,h75,i75,h76,i76,h77,i77,h78,i78] = leer_tablas_4(app.Datos_interfaz_2);
            
                %Primer subatributo - Uso de suelo (lado del conductor)
            datos={'Áreas no desarrolladas',h1,i1;'Agricultura y ganadería',h2,i2;'Residencial',h3,i3;'Comercial',h4,i4;'Educacional',h6,i6;'Industrial y manufactura',h7,i7};
            app.UITable_38.Data=datos;
            app.UITable_38.ColumnFormat={'','bank','bank'};
                %Segundo subatributo - Uso de suelo (lado del copiloto)
            datos={'Áreas no desarrolladas',h8,i8;'Agricultura y ganadería',h9,i9;'Residencial',h10,i10;'Comercial',h11,i11;'Educacional',h13,i13;'Industrial y manufactura',h14,i14};
            app.UITable_39.Data=datos;
            app.UITable_39.ColumnFormat={'','bank','bank'};
                %Tercer subatributo - Tipo de área
            datos={'Rural/área abierta',h15,i15;'Urbano/ población pequeña o pueblo',h16,i16};
            app.UITable_40.Data=datos;
            app.UITable_40.ColumnFormat={'','bank','bank'};
                %Cuarto subatributo - Infraestructura de cruce peatonal en el tramo analizado
            datos={'Infraestructura a desnivel',h17,i17;'Semaforizada con refugio',h18,i18;'Semaforizada sin refugio',h19,i19;'No semaforizada, señalizada con pintura y con refugio',h20,i20;'No semaforizada, señalizada con pintura y sin refugio',h21,i21;'Solo refugio',h22,i22;'Sin infraestructura',h23,i23;'Cruce demarcado elevado, sin semáforo, con refugio',h24,i24;'Cruce demarcado elevado, sin semáforo, sin refugio',h25,i25;'Cruce no demarcado elevado, con refugio',h26,i26;'Cruce no demarcado elevado, sin refugio',h27,i27};
            app.UITable_41.Data=datos;
            app.UITable_41.ColumnFormat={'','bank','bank'};
                %Quinto subatributo - Calidad de cruce peatonal
            datos={'Adecuado',h28,i28;'Pobre',h29,i29;'No aplica',h30,i30};
            app.UITable_42.Data=datos;
            app.UITable_42.ColumnFormat={'','bank','bank'};
                %Sexto subatributo - Infraestructura de cruce peatonal en la intersección
            datos={'Infraestructura a desnivel',h31,i31;'Semaforizada con refugio',h32,i32;'Semaforizada sin refugio',h33,i33;'No semaforizada, señalizada con pintura y con refugio',h34,i34;'No semaforizada, señalizada con pintura y sin refugio',h35,i35;'Solo refugio',h36,i36;'Sin infraestructura',h37,i37;'Cruce demarcado elevado, sin semáforo, con refugio',h38,i38;'Cruce demarcado elevado, sin semáforo, sin refugio',h39,i39;'Cruce no demarcado elevado, con refugio',h40,i40;'Cruce no demarcado elevado, sin refugio',h41,i41};
            app.UITable_43.Data=datos;
            app.UITable_43.ColumnFormat={'','bank','bank'};
                %Séptimo subatributo - Vallas peatonales
            datos={'Presente',h43,i43;'Ausente',h44,i44};
            app.UITable_44.Data=datos;
            app.UITable_44.ColumnFormat={'','bank','bank'};
                %Octavo subatributo - Acera (lado del conductor)
            datos={'Barrera física',h45,i45;'Separación no física ≥ 3.0 m',h46,i46;'Separación no física 1.0 m a 3.0 m',h47,i47;'Separación no física 0 m < 1.0 m',h48,i48;'Ninguno',h49,i49;'Camino informal ≥ 1.0 m',h50,i50;'Camino informal de 0 m a < 1.0 m',h51,i51};
            app.UITable_45.Data=datos;
            app.UITable_45.ColumnFormat={'','bank','bank'};
                %Noveno subatributo - Acera (lado del copiloto)
            datos={'Barrera física',h52,i52;'Separación no física ≥ 3.0 m',h53,i53;'Separación no física 1.0 m a 3.0 m',h54,i54;'Separación no física 0 m < 1.0 m',h55,i55;'Ninguno',h56,i56;'Camino informal ≥ 1.0 m',h57,i57;'Camino informal de 0 m a < 1.0 m',h58,i58};
            app.UITable_46.Data=datos;
            app.UITable_46.ColumnFormat={'','bank','bank'};
                %Décimo subatributo - Infraestructura para motocicletas
            datos={'Motovía exclusiva de un sentido con barrera',h59,i59;'Motovía exclusiva de un sentido sin barrera',h60,i60;'Motovía exclusiva de dos sentidos con barrera',h61,i61;'Motovía exclusiva de dos sentidos sin barrera',h62,i62;'Motovía compartida en la carretera',h63,i63;'Ninguno',h64,i64};
            app.UITable_47.Data=datos;
            app.UITable_47.ColumnFormat={'','bank','bank'};
                %Décimo primero subatributo - Infraestructura para bicicletas
            datos={'Ciclovía fuera de la carretera con barrera',h65,i65;'Ciclovía fuera de la carretera sin barrera',h66,i66;'Ciclovía en la carretera',h67,i67;'Ninguno',h68,i68;'Carril extra fuera de la calzada (≥ 4.2 m)',h69,i69;'Vía compartida con señalización vertical',h70,i70;'Ciclovía de uso compartido ',h71,i71};
            app.UITable_48.Data=datos;
            app.UITable_48.ColumnFormat={'','bank','bank'};
                %Décimo segundo subatributo - Advertencia de zona escolar
            datos={'Balizas intermitentes en zona escolar',h72,i72;'Señalización estática o demarcación horizontal en zona escolar',h73,i73;'Sin señal de advertencia de zona escolar',h74,i74;'No aplica (no hay escuela en el lugar)',h75,i75};
            app.UITable_49.Data=datos;
            app.UITable_49.ColumnFormat={'','bank','bank'};
                %Décimo tercero subatributo - Supervisor en el cruce de zona escolar
            datos={'Supervisor de cruce en zona escolar presente en horario de entrada y salida de la escuela',h76,i76;'Supervisor de cruce en zona escolar no presente',h77,i77;'No aplica (no hay escuela en el lugar)',h78,i78};
            app.UITable_50.Data=datos;
            app.UITable_50.ColumnFormat={'','bank','bank'};
            
            %   VELOCIDADES
            %Se usa la función para extraer los elementos del sexto atributo vial
            [h1,i1,h2,i2,h3,i3,h4,i4,h5,i5,h6,i6,h7,i7,h8,i8,h9,i9,h10,i10,h11,i11,h12,i12,h13,i13,h14,i14,h15,i15,h16,i16,h17,i17,h18,i18,h19,i19,h20,i20,h21,i21,h22,i22,h23,i23,h24,i24,h25,i25,h26,i26,h27,i27,h28,i28,h29,i29,h30,i30,h31,i31,h32,i32,h33,i33,h34,i34,h35,i35,h36,i36,h37,i37,h38,i38,h39,i39,h40,i40,h41,i41,h42,i42,h43,i43,h44,i44,h45,i45,h46,i46,h47,i47,h48,i48,h49,i49,h50,i50,h51,i51,h52,i52,h53,i53,h54,i54] = leer_tablas_5(app.Datos_interfaz_2);
            
                %Primer subatributo - Límite de velocidad
            datos={'< 30 km/h',h1,i1;'35 km/h',h2,i2;'40 km/h',h3,i3;'45 km/h',h4,i4;'50 km/h',h5,i5;'55 km/h',h6,i6;'60 km/h',h7,i7;'65 km/h',h8,i8;'70 km/h',h9,i9;'75 km/h',h10,i10;'80 km/h',h11,i11;'85 km/h',h12,i12;'90 km/h',h13,i13;'95 km/h',h14,i14;'100 km/h',h15,i15;'105 km/h',h16,i16;'110 km/h',h17,i17;'115 km/h',h18,i18;'120 km/h',h19,i19;'125 km/h',h20,i20;'130 km/h',h21,i21;'135 km/h',h22,i22;'140 km/h',h23,i23;'145 km/h',h24,i24;' ≥ 150 km/h',h25,i25};
            app.UITable_52.Data=datos;
            app.UITable_52.ColumnFormat={'','bank','bank'};
                %Segundo subatributo - Diferencial de límites de velocidad 
            datos={'Ausente',h26,i26;'Presente',h27,i27};
            app.UITable_51.Data=datos;
            app.UITable_51.ColumnFormat={'','bank','bank'};
                %Tercer subatributo - Gestión de la velocidad/calmante del tráfico
            datos={'Ausente',h28,i28;'Presente',h29,i29};
            app.UITable_54.Data=datos;
            app.UITable_54.ColumnFormat={'','bank','bank'};
                %Cuarto subatributo - Velocidad de operación (percentil 85)
            datos={'< 30 km/h',h30,i30;'35 km/h',h31,i31;'40 km/h',h32,i32;'45 km/h',h33,i33;'50 km/h',h34,i34;'55 km/h',h35,i35;'60 km/h',h36,i36;'65 km/h',h37,i37;'70 km/h',h38,i38;'75 km/h',h39,i39;'80 km/h',h40,i40;'85 km/h',h41,i41;'90 km/h',h42,i42;'95 km/h',h43,i43;'100 km/h',h44,i44;'105 km/h',h45,i45;'110 km/h',h46,i46;'115 km/h',h47,i47;'120 km/h',h48,i48;'125 km/h',h49,i49;'130 km/h',h50,i50;'135 km/h',h51,i51;'140 km/h',h52,i52;'145 km/h',h53,i53;' ≥ 150 km/h',h54,i54};
            app.UITable_53.Data=datos;
            app.UITable_53.ColumnFormat={'','bank','bank'};
            
            % Segunda parte de la interfaz - Reporte Detallado
            
                % Estilo de la tabla de la clasificación por estrellas 
            app.s1=uistyle('BackgroundColor',[0, 0.5, 0]);
            addStyle(app.UITable_9,app.s1,'cell',[1,1]);
            app.s2=uistyle('BackgroundColor',[1,1,0]);
            addStyle(app.UITable_9,app.s2,'cell',[2,1]);
            app.s3=uistyle('BackgroundColor',[0.9290, 0.6940, 0.1250]);
            addStyle(app.UITable_9,app.s3,'cell',[3,1]);
            app.s4=uistyle('BackgroundColor',[1,0,0]);
            addStyle(app.UITable_9,app.s4,'cell',[4,1]);
            app.s5=uistyle('BackgroundColor',[0,0,0]);
            addStyle(app.UITable_9,app.s5,'cell',[5,1]);
            app.s6=uistyle('BackgroundColor',[0.85 0.85 0.85]);
            addStyle(app.UITable_9,app.s6,'cell',[6,1]);
            app.s7=uistyle('FontColor',[1,1,1]);
            addStyle(app.UITable_9,app.s7,'cell',[5,1]);
            
            app.UITable_9.ColumnFormat={'','bank','bank'};

                % Valores para el Ocupante del vehículo en la
                    % tabla clasificaión por estrellas que se presentar al
                        % inicial la aplicación 
            [D,P,D01,P01,D02,P02,D03,P03,D1,P1,D2,P2,D3,P3,D4,P4,D5,P5,D6,P6,D7,P7,D8,P8,D9,P9,D10,P10,D11,P11,D12,P12,D13,P13,D14,P14,D15,P15,D16,P16,D17,P17,D18,P18,D19,P19,D20,P20]=Usuarios(app.Datos_interfaz_2); 
            Distancia_Total=(D+D1+D2+D3+D4+D5);
            Porcentaje_Total=(P+P1+P2+P3+P4+P5);
            app.Tabla_estrellas_Ocupante={'5 Estrellas',D5,P5;'4 Estrellas',D4,P4;'3 Estrellas',D3,P3;'2 Estrellas',D2,P2;'1 Estrella',D1,P1;'No aplica',D,P;'Total',Distancia_Total,Porcentaje_Total};
            app.UITable_9.Data=app.Tabla_estrellas_Ocupante;
            plot(app.UIAxes_2,cumsum(app.Datos_interfaz_2.Distancia),app.Datos_interfaz_2.Puntuacion_Ocupante_del_vehiculo);
            
            % Valores iniciales para la gráfica 'Mapa'
            load('../mat/preproceso_identificador.mat');
            if identificador==1
               app.Datos_video=load('../mat/preproceso_datos_video.mat');
               y=app.Datos_video.vbo.channels(3).data;
               x=app.Datos_video.vbo.channels(4).data;
               plot(app.UIAxes,x,y,'b');
               hold(app.UIAxes,"on");
               c1=sum(arrayfun(@(Datos_interfaz_dos) ~isempty(Datos_interfaz_dos.Tramos),Datos_interfaz_dos));
               for i=1:c1
                if Datos_interfaz_dos(i).Ocupante_del_vehiculo ==0    
                plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.85 0.85 0.85],'linewidth',3);
                hold(app.UIAxes,"on");
                elseif Datos_interfaz_dos(i).Ocupante_del_vehiculo ==1    
                plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'k','linewidth',3);
                hold(app.UIAxes,"on");
                elseif Datos_interfaz_dos(i).Ocupante_del_vehiculo ==2
                plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'r','linewidth',3);
                hold(app.UIAxes,"on");
                elseif Datos_interfaz_dos(i).Ocupante_del_vehiculo ==3
                plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.9290, 0.6940, 0.1250],'linewidth',3);
                hold(app.UIAxes,"on");
                elseif Datos_interfaz_dos(i).Ocupante_del_vehiculo ==4
                plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'y','linewidth',3);
                hold(app.UIAxes,"on");
                elseif Datos_interfaz_dos(i).Ocupante_del_vehiculo ==5
                plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0, 0.5, 0],'linewidth',3);
                hold(app.UIAxes,"on");
                end
               end 
               
            elseif identificador==2
               
               c1=sum(arrayfun(@(Datos_interfaz_dos) ~isempty(Datos_interfaz_dos.Tramos),Datos_interfaz_dos));
               for i=1:c1
                if Datos_interfaz_dos(i).Ocupante_del_vehiculo ==0
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                    scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0.85 0.85 0.85],'MarkerEdgeColor',[0.85 0.85 0.85]);
                    hold(app.UIAxes,"on");
                    end
                elseif Datos_interfaz_dos(i).Ocupante_del_vehiculo ==1
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                    scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','k','MarkerEdgeColor','k');
                    hold(app.UIAxes,"on");
                    end
                elseif Datos_interfaz_dos(i).Ocupante_del_vehiculo ==2
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                    scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','r','MarkerEdgeColor','r');
                    hold(app.UIAxes,"on");
                    end
                elseif Datos_interfaz_dos(i).Ocupante_del_vehiculo ==3
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                    scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0.9290, 0.6940, 0.1250],'MarkerEdgeColor',[0.9290, 0.6940, 0.1250]);
                    hold(app.UIAxes,"on");
                    end
                elseif Datos_interfaz_dos(i).Ocupante_del_vehiculo ==4
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                    scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','y','MarkerEdgeColor','y');
                    hold(app.UIAxes,"on");
                    end
                elseif Datos_interfaz_dos(i).Ocupante_del_vehiculo ==5
                    if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                    scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0, 0.5, 0],'MarkerEdgeColor',[0, 0.5, 0]);
                    hold(app.UIAxes,"on");
                    end
                end
               end 
            end
               %app.Datos_video=load('../mat/preproceso_datos_video.mat');
               %y=app.Datos_video.vbo.channels(3).data;
               %x=app.Datos_video.vbo.channels(4).data;
               %plot(app.UIAxes,x,y,'b');
               %hold(app.UIAxes,"on");
     
            % Tercera parte de la interfaz - Medidas de mejoramiento
            %Contramedidas
            app.UITable2.Data=datos;
            Contramedidas=readtable('Ejemplo.xlsx');
            app.UITable2.Data=Contramedidas;
            
            %Valor del cuadro
            Infinicial=load('../mat/Inf_proyecto.mat');
            app.NombredelproyectoTextArea.Value=Infinicial.T_proyecto;
            
            
        end

        % Selection changed function: UsuariosvialesButtonGroup
        function UsuariosvialesButtonGroupSelectionChanged(app, event)
            
            %Para el recorrido (Mapa)
            load('../mat/Datos_interfaz_dos_proceso.mat');
            c1=sum(arrayfun(@(Datos_interfaz_dos) ~isempty(Datos_interfaz_dos.Tramos),Datos_interfaz_dos));
            
            %Datos sin suavizar
            %Y=load('ejemplo3.mat'); 
            [D,P,D01,P01,D02,P02,D03,P03,D1,P1,D2,P2,D3,P3,D4,P4,D5,P5,D6,P6,D7,P7,D8,P8,D9,P9,D10,P10,D11,P11,D12,P12,D13,P13,D14,P14,D15,P15,D16,P16,D17,P17,D18,P18,D19,P19,D20,P20] = Usuarios(app.Datos_interfaz_2);
            %Ocupante del vehículo
            Distancia_Total=(D+D1+D2+D3+D4+D5);
            Porcentaje_Total=(P+P1+P2+P3+P4+P5);
            
            app.Tabla_estrellas_Ocupante={'5 Estrellas',D5,P5;'4 Estrellas',D4,P4;'3 Estrellas',D3,P3;'2 Estrellas',D2,P2;'1 Estrella',D1,P1;'No aplica',D,P;'Total',Distancia_Total,Porcentaje_Total;};

            %Motociclista
            Distancia_Total_1=(D01+D6+D7+D8+D9+D10);
            Porcentaje_Total_1=(P01+P6+P7+P8+P9+P10);
            app.Tabla_estrellas_Motociclista={'5 Estrellas',D10,P10;'4 Estrellas',D9,P9;'3 Estrellas',D8,P8;'2 Estrellas',D7,P7;'1 Estrella',D6,P6;'No aplica',D01,P01;'Total',Distancia_Total_1,Porcentaje_Total_1;};

            %Ciclista
            Distancia_Total_2=(D02+D11+D12+D13+D14+D15);
            Porcentaje_Total_2=(P02+P11+P12+P13+P14+P15);
            app.Tabla_estrellas_Cilista={'5 Estrellas',D15,P15;'4 Estrellas',D14,P14;'3 Estrellas',D13,P13;'2 Estrellas',D12,P12;'1 Estrella',D11,P11;'No aplica',D02,P02;'Total',Distancia_Total_2,Porcentaje_Total_2;};

            %Peatón
            Distancia_Total_3=(D03+D16+D17+D18+D19+D20);
            Porcentaje_Total_3=(P03+P16+P17+P18+P19+P20);
            app.Tabla_estrellas_Peaton={'5 Estrellas',D20,P20;'4 Estrellas',D19,P19;'3 Estrellas',D18,P18;'2 Estrellas',D17,P17;'1 Estrella',D16,P16;'No aplica',D03,P03;'Total',Distancia_Total_3,Porcentaje_Total_3;};       
     
            %Datos suavizados
            load('../mat/Datos_suavizado_postproceso.mat')
            c1=sum(arrayfun(@(Datos_interfaz_dos) ~isempty(Datos_interfaz_dos.Tramos),Datos_interfaz_dos));
            
            [D,P,D01,P01,D02,P02,D03,P03,D1,P1,D2,P2,D3,P3,D4,P4,D5,P5,D6,P6,D7,P7,D8,P8,D9,P9,D10,P10,D11,P11,D12,P12,D13,P13,D14,P14,D15,P15,D16,P16,D17,P17,D18,P18,D19,P19,D20,P20] = Usuarios(Datos_suavizado);
            %Ocupante del vehículo
            Distancia_Total=(D+D1+D2+D3+D4+D5);
            Porcentaje_Total=(P+P1+P2+P3+P4+P5);
            app.Tabla_Nuevaestrellas_Ocupante={'5 Estrellas',D5,P5;'4 Estrellas',D4,P4;'3 Estrellas',D3,P3;'2 Estrellas',D2,P2;'1 Estrella',D1,P1;'No aplica',D,P;'Total',Distancia_Total,Porcentaje_Total;};

            %Motociclista
            Distancia_Total_1=(D01+D6+D7+D8+D9+D10);
            Porcentaje_Total_1=(P01+P6+P7+P8+P9+P10);
            app.Tabla_Nuevaestrellas_Motociclista={'5 Estrellas',D10,P10;'4 Estrellas',D9,P9;'3 Estrellas',D8,P8;'2 Estrellas',D7,P7;'1 Estrella',D6,P6;'No aplica',D01,P01;'Total',Distancia_Total_1,Porcentaje_Total_1;};

            %Ciclista
            Distancia_Total_2=(D02+D11+D12+D13+D14+D15);
            Porcentaje_Total_2=(P02+P11+P12+P13+P14+P15);
            app.Tabla_Nuevaestrellas_Cilista={'5 Estrellas',D15,P15;'4 Estrellas',D14,P14;'3 Estrellas',D13,P13;'2 Estrellas',D12,P12;'1 Estrella',D11,P11;'No aplica',D02,P02;'Total',Distancia_Total_2,Porcentaje_Total_2;};

            %Peatón
            Distancia_Total_3=(D03+D16+D17+D18+D19+D20);
            Porcentaje_Total_3=(P03+P16+P17+P18+P19+P20);
            app.Tabla_Nuevaestrellas_Peaton={'5 Estrellas',D20,P20;'4 Estrellas',D19,P19;'3 Estrellas',D18,P18;'2 Estrellas',D17,P17;'1 Estrella',D16,P16;'No aplica',D03,P03;'Total',Distancia_Total_3,Porcentaje_Total_3;};       

            %Asignaciones de los botones para cada usuario
            V=app.OcupantedevehculosButton.Value;
            M=app.MotociclistaButton.Value;
            C=app.CiclistaButton.Value;
            P=app.PeatnButton.Value;
            
            %Análisis de las condiciones 
            load('../mat/preproceso_identificador.mat');
            if identificador==1
            
            if app.SuavizadoCheckBox.Value ==1 && app.OcupantedevehculosButton.Value ==1
                   app.UITable_9.Data=app.Tabla_Nuevaestrellas_Ocupante;
                   plot(app.UIAxes_2,cumsum(Datos_suavizado.Distancia),Datos_suavizado.Puntuacion_Ocupante_del_vehiculo);
                   for i=1:length(Datos_suavizado.Ocupante_del_vehiculo)
                    if Datos_suavizado.Ocupante_del_vehiculo(i)==0
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.85 0.85 0.85],'linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_suavizado.Ocupante_del_vehiculo(i)==1
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'k','linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_suavizado.Ocupante_del_vehiculo(i) ==2
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'r','linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_suavizado.Ocupante_del_vehiculo(i) ==3
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.9290, 0.6940, 0.1250],'linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_suavizado.Ocupante_del_vehiculo(i) ==4
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'y','linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_suavizado.Ocupante_del_vehiculo(i) ==5
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0, 0.5, 0],'linewidth',3);
                    hold(app.UIAxes,"on");
                    end
                   end
            end
            if app.SuavizadoCheckBox.Value ==0 && V==1
                app.UITable_9.Data=app.Tabla_estrellas_Ocupante;
                plot(app.UIAxes_2,cumsum(app.Datos_interfaz_2.Distancia),app.Datos_interfaz_2.Puntuacion_Ocupante_del_vehiculo);
                for i=1:c1
                    if Datos_interfaz_dos(i).Ocupante_del_vehiculo ==0    
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.85 0.85 0.85],'linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_interfaz_dos(i).Ocupante_del_vehiculo ==1    
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'k','linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_interfaz_dos(i).Ocupante_del_vehiculo ==2
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'r','linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_interfaz_dos(i).Ocupante_del_vehiculo ==3
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.9290, 0.6940, 0.1250],'linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_interfaz_dos(i).Ocupante_del_vehiculo ==4
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'y','linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_interfaz_dos(i).Ocupante_del_vehiculo ==5
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0, 0.5, 0],'linewidth',3);
                    hold(app.UIAxes,"on");
                    end
                end
            end
            if app.SuavizadoCheckBox.Value ==1 && app.MotociclistaButton.Value ==1
                   app.UITable_9.Data=app.Tabla_Nuevaestrellas_Motociclista;
                   plot(app.UIAxes_2,cumsum(Datos_suavizado.Distancia),Datos_suavizado.Puntuacion_Motociclista);
                   for i=1:length(Datos_suavizado.Motociclista)
                    if Datos_suavizado.Motociclista(i)==0
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.85 0.85 0.85],'linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_suavizado.Motociclista(i)==1
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'k','linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_suavizado.Motociclista(i) ==2
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'r','linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_suavizado.Motociclista(i) ==3
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.9290, 0.6940, 0.1250],'linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_suavizado.Motociclista(i) ==4
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'y','linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_suavizado.Motociclista(i) ==5
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0, 0.5, 0],'linewidth',3);
                    hold(app.UIAxes,"on");
                    end
                   end
                   end
            if app.SuavizadoCheckBox.Value ==0 && M==1
                app.UITable_9.Data=app.Tabla_estrellas_Motociclista;
                plot(app.UIAxes_2,cumsum(app.Datos_interfaz_2.Distancia),app.Datos_interfaz_2.Puntuacion_Motociclista);
                for i=1:c1
                    if Datos_interfaz_dos(i).Motociclista ==0    
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.85 0.85 0.85],'linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_interfaz_dos(i).Motociclista ==1    
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'k','linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_interfaz_dos(i).Motociclista ==2
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'r','linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_interfaz_dos(i).Motociclista ==3
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.9290, 0.6940, 0.1250],'linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_interfaz_dos(i).Motociclista ==4
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'y','linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_interfaz_dos(i).Motociclista ==5
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0, 0.5, 0],'linewidth',3);
                    hold(app.UIAxes,"on");
                    end
                end
            end
            
            if app.SuavizadoCheckBox.Value ==1 && app.CiclistaButton.Value ==1
                   app.UITable_9.Data=app.Tabla_Nuevaestrellas_Cilista;
                   plot(app.UIAxes_2,cumsum(Datos_suavizado.Distancia),Datos_suavizado.Puntuacion_Ciclista);
                   for i=1:length(Datos_suavizado.Ciclista)
                    if Datos_suavizado.Ciclista(i)==0
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.85 0.85 0.85],'linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_suavizado.Ciclista(i)==1
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'k','linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_suavizado.Ciclista(i) ==2
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'r','linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_suavizado.Ciclista(i) ==3
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.9290, 0.6940, 0.1250],'linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_suavizado.Ciclista(i) ==4
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'y','linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_suavizado.Ciclista(i) ==5
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0, 0.5, 0],'linewidth',3);
                    hold(app.UIAxes,"on");
                    end
                   end     
                   end
            if app.SuavizadoCheckBox.Value ==0 && C==1
                app.UITable_9.Data=app.Tabla_estrellas_Cilista;
                plot(app.UIAxes_2,cumsum(app.Datos_interfaz_2.Distancia),app.Datos_interfaz_2.Puntuacion_Ciclista);
                for i=1:c1
                    if Datos_interfaz_dos(i).Ciclista ==0    
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.85 0.85 0.85],'linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_interfaz_dos(i).Ciclista ==1    
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'k','linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_interfaz_dos(i).Ciclista ==2
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'r','linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_interfaz_dos(i).Ciclista ==3
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.9290, 0.6940, 0.1250],'linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_interfaz_dos(i).Ciclista ==4
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'y','linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_interfaz_dos(i).Ciclista ==5
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0, 0.5, 0],'linewidth',3);
                    hold(app.UIAxes,"on");
                    end
                end
            end
            
            if app.SuavizadoCheckBox.Value ==1 && app.PeatnButton.Value ==1
                   app.UITable_9.Data=app.Tabla_Nuevaestrellas_Peaton;
                   plot(app.UIAxes_2,cumsum(Datos_suavizado.Distancia),Datos_suavizado.Puntuacion_Peaton); 
                   for i=1:length(Datos_suavizado.Peaton)
                    if Datos_suavizado.Peaton(i)==0
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.85 0.85 0.85],'linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_suavizado.Peaton(i)==1
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'k','linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_suavizado.Peaton(i) ==2
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'r','linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_suavizado.Peaton(i) ==3
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.9290, 0.6940, 0.1250],'linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_suavizado.Peaton(i) ==4
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'y','linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_suavizado.Peaton(i) ==5
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0, 0.5, 0],'linewidth',3);
                    hold(app.UIAxes,"on");
                    end
                   end
                   end 
            
            if app.SuavizadoCheckBox.Value==0 && P==1
                app.UITable_9.Data=app.Tabla_estrellas_Peaton; 
                plot(app.UIAxes_2,cumsum(app.Datos_interfaz_2.Distancia),app.Datos_interfaz_2.Puntuacion_Peaton);
                for i=1:c1
                    if Datos_interfaz_dos(i).Peaton ==0    
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.85 0.85 0.85],'linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_interfaz_dos(i).Peaton ==1    
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'k','linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_interfaz_dos(i).Peaton ==2
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'r','linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_interfaz_dos(i).Peaton ==3
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.9290, 0.6940, 0.1250],'linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_interfaz_dos(i).Peaton ==4
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'y','linewidth',3);
                    hold(app.UIAxes,"on");
                    elseif Datos_interfaz_dos(i).Peaton ==5
                    plot(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0, 0.5, 0],'linewidth',3);
                    hold(app.UIAxes,"on");
                    end
                end
            end
            
            elseif identificador==2
            if app.SuavizadoCheckBox.Value ==1 && app.OcupantedevehculosButton.Value ==1
                   app.UITable_9.Data=app.Tabla_Nuevaestrellas_Ocupante;
                   plot(app.UIAxes_2,cumsum(Datos_suavizado.Distancia),Datos_suavizado.Puntuacion_Ocupante_del_vehiculo);
                   for i=1:length(Datos_suavizado.Ocupante_del_vehiculo)
                    if Datos_suavizado.Ocupante_del_vehiculo(i)==0
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0.85 0.85 0.85],'MarkerEdgeColor',[0.85 0.85 0.85]);
                        hold(app.UIAxes,"on");
                        end
                    elseif Datos_suavizado.Ocupante_del_vehiculo(i)==1
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','k','MarkerEdgeColor','k');
                        hold(app.UIAxes,"on");
                        end
                    elseif Datos_suavizado.Ocupante_del_vehiculo(i) ==2
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','r','MarkerEdgeColor','r');
                        hold(app.UIAxes,"on");
                        end
                    elseif Datos_suavizado.Ocupante_del_vehiculo(i) ==3
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0.9290, 0.6940, 0.1250],'MarkerEdgeColor',[0.9290, 0.6940, 0.1250]);
                        hold(app.UIAxes,"on");
                        end
                    elseif Datos_suavizado.Ocupante_del_vehiculo(i) ==4
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','y','MarkerEdgeColor','y');
                        hold(app.UIAxes,"on");
                        end
                    elseif Datos_suavizado.Ocupante_del_vehiculo(i) ==5
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0, 0.5, 0],'MarkerEdgeColor',[0, 0.5, 0]);
                        hold(app.UIAxes,"on");
                        end
                    end
                   end
            end
            if app.SuavizadoCheckBox.Value ==0 && V==1
                app.UITable_9.Data=app.Tabla_estrellas_Ocupante;
                plot(app.UIAxes_2,cumsum(app.Datos_interfaz_2.Distancia),app.Datos_interfaz_2.Puntuacion_Ocupante_del_vehiculo);
                for i=1:c1
                    if Datos_interfaz_dos(i).Ocupante_del_vehiculo ==0  
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0.85 0.85 0.85],'MarkerEdgeColor',[0.85 0.85 0.85]);
                        hold(app.UIAxes,"on");
                        end
                    elseif Datos_interfaz_dos(i).Ocupante_del_vehiculo ==1
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','k','MarkerEdgeColor','k');
                        hold(app.UIAxes,"on");
                        end
                    elseif Datos_interfaz_dos(i).Ocupante_del_vehiculo ==2
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','r','MarkerEdgeColor','r');
                        hold(app.UIAxes,"on");
                        end
                    elseif Datos_interfaz_dos(i).Ocupante_del_vehiculo ==3
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0.9290, 0.6940, 0.1250],'MarkerEdgeColor',[0.9290, 0.6940, 0.1250]);
                        hold(app.UIAxes,"on");
                        end
                    elseif Datos_interfaz_dos(i).Ocupante_del_vehiculo ==4
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','y','MarkerEdgeColor','y');
                        hold(app.UIAxes,"on");
                        end
                    elseif Datos_interfaz_dos(i).Ocupante_del_vehiculo ==5
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0, 0.5, 0],'MarkerEdgeColor',[0, 0.5, 0]);
                        hold(app.UIAxes,"on");
                        end
                    end
                end
            end
            if app.SuavizadoCheckBox.Value ==1 && app.MotociclistaButton.Value ==1
                   app.UITable_9.Data=app.Tabla_Nuevaestrellas_Motociclista;
                   plot(app.UIAxes_2,cumsum(Datos_suavizado.Distancia),Datos_suavizado.Puntuacion_Motociclista);
                   for i=1:length(Datos_suavizado.Motociclista)
                    if Datos_suavizado.Motociclista(i)==0
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0.85 0.85 0.85],'MarkerEdgeColor',[0.85 0.85 0.85]);
                        hold(app.UIAxes,"on");
                        end
                    elseif Datos_suavizado.Motociclista(i)==1
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','k','MarkerEdgeColor','k');
                        hold(app.UIAxes,"on");
                        end
                    elseif Datos_suavizado.Motociclista(i) ==2
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','r','MarkerEdgeColor','r');
                        hold(app.UIAxes,"on");
                        end
                    elseif Datos_suavizado.Motociclista(i) ==3
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0.9290, 0.6940, 0.1250],'MarkerEdgeColor',[0.9290, 0.6940, 0.1250]);
                        hold(app.UIAxes,"on");
                        end
                    elseif Datos_suavizado.Motociclista(i) ==4
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','y','MarkerEdgeColor','y');
                        hold(app.UIAxes,"on");
                        end
                    elseif Datos_suavizado.Motociclista(i) ==5
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0, 0.5, 0],'MarkerEdgeColor',[0, 0.5, 0]);
                        hold(app.UIAxes,"on");
                        end
                    end
                   end
                   end
            if app.SuavizadoCheckBox.Value ==0 && M==1
                app.UITable_9.Data=app.Tabla_estrellas_Motociclista;
                plot(app.UIAxes_2,cumsum(app.Datos_interfaz_2.Distancia),app.Datos_interfaz_2.Puntuacion_Motociclista);
                for i=1:c1
                    if Datos_interfaz_dos(i).Motociclista ==0   
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0.85 0.85 0.85],'MarkerEdgeColor',[0.85 0.85 0.85]);
                        hold(app.UIAxes,"on");
                        end
                    elseif Datos_interfaz_dos(i).Motociclista ==1   
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','k','MarkerEdgeColor','k');
                        hold(app.UIAxes,"on");
                        end
                    elseif Datos_interfaz_dos(i).Motociclista ==2
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','r','MarkerEdgeColor','r');
                        hold(app.UIAxes,"on");
                        end
                    elseif Datos_interfaz_dos(i).Motociclista ==3
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0.9290, 0.6940, 0.1250],'MarkerEdgeColor',[0.9290, 0.6940, 0.1250]);
                        hold(app.UIAxes,"on");
                        end
                    elseif Datos_interfaz_dos(i).Motociclista ==4
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','y','MarkerEdgeColor','y');
                        hold(app.UIAxes,"on");
                        end
                    elseif Datos_interfaz_dos(i).Motociclista ==5
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0, 0.5, 0],'MarkerEdgeColor',[0, 0.5, 0]);
                        hold(app.UIAxes,"on");
                        end
                    end
                end
            end
            
            if app.SuavizadoCheckBox.Value ==1 && app.CiclistaButton.Value ==1
                   app.UITable_9.Data=app.Tabla_Nuevaestrellas_Cilista;
                   plot(app.UIAxes_2,cumsum(Datos_suavizado.Distancia),Datos_suavizado.Puntuacion_Ciclista);
                   for i=1:length(Datos_suavizado.Ciclista)
                    if Datos_suavizado.Ciclista(i)==0
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0.85 0.85 0.85],'MarkerEdgeColor',[0.85 0.85 0.85]);
                        hold(app.UIAxes,"on");
                        end
                    elseif Datos_suavizado.Ciclista(i)==1
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','k','MarkerEdgeColor','k');
                        hold(app.UIAxes,"on");
                        end
                    elseif Datos_suavizado.Ciclista(i) ==2
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','r','MarkerEdgeColor','r');
                        hold(app.UIAxes,"on");
                        end
                    elseif Datos_suavizado.Ciclista(i) ==3
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0.9290, 0.6940, 0.1250],'MarkerEdgeColor',[0.9290, 0.6940, 0.1250]);
                        hold(app.UIAxes,"on");
                        end
                    elseif Datos_suavizado.Ciclista(i) ==4
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','y','MarkerEdgeColor','y');
                        hold(app.UIAxes,"on");
                        end
                    elseif Datos_suavizado.Ciclista(i) ==5
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0, 0.5, 0],'MarkerEdgeColor',[0, 0.5, 0]);
                        hold(app.UIAxes,"on");
                        end
                    end
                   end     
                   end
            if app.SuavizadoCheckBox.Value ==0 && C==1
                app.UITable_9.Data=app.Tabla_estrellas_Cilista;
                plot(app.UIAxes_2,cumsum(app.Datos_interfaz_2.Distancia),app.Datos_interfaz_2.Puntuacion_Ciclista);
                for i=1:c1
                    if Datos_interfaz_dos(i).Ciclista ==0  
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0.85 0.85 0.85],'MarkerEdgeColor',[0.85 0.85 0.85]);
                        hold(app.UIAxes,"on");
                        end
                    elseif Datos_interfaz_dos(i).Ciclista ==1    
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','k','MarkerEdgeColor','k');
                        hold(app.UIAxes,"on");
                        end
                    elseif Datos_interfaz_dos(i).Ciclista ==2
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','r','MarkerEdgeColor','r');
                        hold(app.UIAxes,"on");
                        end
                    elseif Datos_interfaz_dos(i).Ciclista ==3
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0.9290, 0.6940, 0.1250],'MarkerEdgeColor',[0.9290, 0.6940, 0.1250]);
                        hold(app.UIAxes,"on");
                        end
                    elseif Datos_interfaz_dos(i).Ciclista ==4
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','y','MarkerEdgeColor','y');
                        hold(app.UIAxes,"on");
                        end
                    elseif Datos_interfaz_dos(i).Ciclista ==5
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0, 0.5, 0],'MarkerEdgeColor',[0, 0.5, 0]);
                        hold(app.UIAxes,"on");
                        end
                    end
                end
            end
            
            if app.SuavizadoCheckBox.Value ==1 && app.PeatnButton.Value ==1
                   app.UITable_9.Data=app.Tabla_Nuevaestrellas_Peaton;
                   plot(app.UIAxes_2,cumsum(Datos_suavizado.Distancia),Datos_suavizado.Puntuacion_Peaton); 
                   for i=1:length(Datos_suavizado.Peaton)
                    if Datos_suavizado.Peaton(i)==0
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0.85 0.85 0.85],'MarkerEdgeColor',[0.85 0.85 0.85]);
                        hold(app.UIAxes,"on");
                        end
                    elseif Datos_suavizado.Peaton(i)==1
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','k','MarkerEdgeColor','k');
                        hold(app.UIAxes,"on");
                        end
                    elseif Datos_suavizado.Peaton(i) ==2
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','r','MarkerEdgeColor','r');
                        hold(app.UIAxes,"on");
                        end
                    elseif Datos_suavizado.Peaton(i) ==3
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0.9290, 0.6940, 0.1250],'MarkerEdgeColor',[0.9290, 0.6940, 0.1250]);
                        hold(app.UIAxes,"on");
                        end
                    elseif Datos_suavizado.Peaton(i) ==4
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','y','MarkerEdgeColor','y');
                        hold(app.UIAxes,"on");
                        end
                    elseif Datos_suavizado.Peaton(i) ==5
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0, 0.5, 0],'MarkerEdgeColor',[0, 0.5, 0]);
                        hold(app.UIAxes,"on");
                        end
                    end
                   end
                   end 
            
            if app.SuavizadoCheckBox.Value==0 && P==1
                app.UITable_9.Data=app.Tabla_estrellas_Peaton; 
                plot(app.UIAxes_2,cumsum(app.Datos_interfaz_2.Distancia),app.Datos_interfaz_2.Puntuacion_Peaton);
                for i=1:c1
                    if Datos_interfaz_dos(i).Peaton ==0   
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0.85 0.85 0.85],'MarkerEdgeColor',[0.85 0.85 0.85]);
                        hold(app.UIAxes,"on");
                        end
                    elseif Datos_interfaz_dos(i).Peaton ==1 
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','k','MarkerEdgeColor','k');
                        hold(app.UIAxes,"on");
                        end
                    elseif Datos_interfaz_dos(i).Peaton ==2
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','r','MarkerEdgeColor','r');
                        hold(app.UIAxes,"on");
                        end
                    elseif Datos_interfaz_dos(i).Peaton ==3
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0.9290, 0.6940, 0.1250],'MarkerEdgeColor',[0.9290, 0.6940, 0.1250]);
                        hold(app.UIAxes,"on");
                        end
                    elseif Datos_interfaz_dos(i).Peaton ==4
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','y','MarkerEdgeColor','y');
                        hold(app.UIAxes,"on");
                        end
                    elseif Datos_interfaz_dos(i).Peaton ==5
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(app.UIAxes,Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0, 0.5, 0],'MarkerEdgeColor',[0, 0.5, 0]);
                        hold(app.UIAxes,"on");
                        end
                    end
                end
            end
    
            end
            
        end

        % Button pushed function: PDFButton_3
        function PDFButton_3Pushed(app, event)

            UsuariosvialesButtonGroupSelectionChanged(app)
            SuavizadoCheckBoxValueChanged(app)
            load('../mat/Datos_interfaz_dos_proceso.mat');
            c1=sum(arrayfun(@(Datos_interfaz_dos) ~isempty(Datos_interfaz_dos.Tramos),Datos_interfaz_dos));
            
            load('../mat/preproceso_identificador.mat');
            if identificador==1
                load('../mat/preproceso_datos_video.mat');
                y=vbo.channels(3).data;
                x=vbo.channels(4).data;
            end
            
            
            load('../mat/Datos_suavizado_postproceso.mat');
            Infinicial=load('../mat/Inf_proyecto.mat');

            % Reporte 
            import mlreportgen.report.*
            import mlreportgen.dom.*
            rpt = Report('Reporte Final', 'pdf');
            
            %Portada
            Tstyle={HAlign('center'),Bold(1),FontFamily('Gotham'),...
             FontSize('20pt'),Color('Black'),...
             OuterMargin('0in','0in','.5in','.5in'),...
             HAlign('center')};             

            titlepg = TitlePage();
            tp = Paragraph('UNIVERSIDAD TÉCNICA PARTICULAR DE LOJA');
            tp.Style=Tstyle;
            titlepg.Title = tp;
            
            a=Paragraph('Sistema Integrado para La Valoración VIAl (SILVVIA)');
            a.Style=Tstyle;
            titlepg.Subtitle = a;
            
            a1=Paragraph('Reporte Detallado');
            a1.Style=Tstyle;
            titlepg.Author = a1;
            
            add(rpt,titlepg)
            
            %Tabla de contenidos
            add(rpt,TableOfContents);

            %Capítulos
            %Capítulo 1-Descargo de responsabilidad
            chap0 = Chapter('Descargo de responsabilidad');
            p=Paragraph('El software SILVVIA y su documentación es propiedad intelectual de la Universidad Técnica Particular de Loja (UTPL). SILVVIA fue desarrollado para estimar el nivel de seguridad que ofrece las carreteras rurales utilizando el método iRAP. El usuario comprende los supuestos de la metodología utilizada en el software. La interpretación de los resultados de SILVVIA son de exclusiva responsabilidad del usuario. En ningún caso la UTPL será responsable por daños directos, indirectos, especiales, incidentales o consecuentes que surjan a partir del uso del programa y/o documentación asociada. El usuario comprende que SILVVIA es un software para fines educativos e informativos, los fines comerciales sin el permiso por escrito de los titulares de los derechos de autor está prohibida. Si no está satisfecho con alguna parte del software SILVVIA o con alguno de estos términos de uso, su único y exclusivo recurso es dejar de usar el programa. ');
            p.FontFamilyName='Gotham';
            p.HAlign='justify';
            add(chap0,p)
            add(rpt,chap0);
            
            
            %Capítulo 2-Información del proyecto
            chap = Chapter('Información del archivo');
            
            Ubi1='Nombre del proyecto:';
            Ubi2=Infinicial.T_proyecto;
            p1=Paragraph(strcat(Ubi1," ",Ubi2));
            p1.FontFamilyName='Gotham';
            add(chap,p1);
            
            Ubi1='Ubicación del proyecto:';
            Ubi2=Infinicial.Lugar;
            p1=Paragraph(strcat(Ubi1," ",Ubi2));
            p1.FontFamilyName='Gotham';
            add(chap,p1);

            LonTotal1='Longitud total del proyecto:';
            LonTotal2=num2str(sum(app.Datos_interfaz_2.Distancia)*(1/1000));
            LonTotal3='Kilómetros';
            p1=Paragraph(strcat(LonTotal1," ",LonTotal2," ",LonTotal3));
            p1.FontFamilyName='Gotham';
            add(chap,p1);

            fecha1='Fecha de elaboración del análisis:';
            fecha2=num2str(date());
            p1=Paragraph(strcat(fecha1," ",fecha2));
            p1.FontFamilyName='Gotham';
            add(chap,p1);
            
            Des1='Descripción del proyecto:';
            Des2=Infinicial.Descrip;
            p1=Paragraph(strcat(Des1," ",Des2));
            p1.FontFamilyName='Gotham';
            add(chap,p1);
            add(rpt,chap);
            
            %Estilo de tabla establecido
            tableStyle = { ...
                Width('100%'), ...
                Border('solid','black','1px'), ...
                ColSep('solid','black','1px'), ...
                FontFamily('Gotham'),...
                Hyphenation(false),...
                RowSep('solid','black','1px') ...
                };
            %Ancho de las tablas
            grps(1) = TableColSpecGroup;
            grps(1).Span = 3;
            
            specs(1) = TableColSpec;
            specs(1).Span = 1;
            specs(1).Style = {Width("64%"),HAlign('left')};
            
            specs(2) = TableColSpec;
            specs(2).Span = 2;
            specs(2).Style = {Width("18%"),HAlign('center')};
            
            grps(1).ColSpecs = specs;
            
            %Estilo establecido para los encabezados de las tablas
            HeaderStyle = {Bold(),BackgroundColor('lightgray'),FontSize('10pt'),FontFamily('Gotham')};
            
            %Estilo establecido para el cuerpo de la tabla
            BodyStyle={BackgroundColor('paleturquoise'),Color('black'),FontSize('10pt'),FontFamily('Gotham'),HAlign('left')};
            
            
            %Capítulo 3
            chap3 = Chapter('Condiciones detalladas de la carretera');
            p1=Paragraph('A continuación se presentan las cantidades y porcentajes de los atributos que se presentan en toda la carretera. Estos atributos son: costado de la vía, bloque central, intersecciones, flujo, infraestructura para usuarios vulnerables y uso del suelo, y velocidades.');
            p1.FontFamilyName='Gotham';
            p1.HAlign='justify';
            add(chap3,p1)
            add(chap3,Paragraph(''))
            
            p1=Paragraph('Costado de la vía');
            p1.Bold=1;
            p1.FontFamilyName='Gotham';
            add(chap3,p1)
            add(chap3,Paragraph(''))
            
            Nombres8=app.UITable_2.Data(1:4,1);
            E8=cell2mat(app.UITable_2.Data(1:4,2));
            F8=cell2mat(app.UITable_2.Data(1:4,3));
            T8=table(categorical(Nombres8(:,1)),f(E8),f(F8));
            T8.Properties.VariableNames = {'Severidad lateral - distancia al objeto (lado del conductor)', 'Km', '%'};
            Tabla2=FormalTable(T8);
            Tabla2.ColSpecGroups = grps;
            Tabla2.TableEntriesVAlign = 'middle';
            Tabla2.TableEntriesHAlign = 'center';
            Tabla2.Style = tableStyle;
            Tabla2.Header.Children(1).Style =HeaderStyle;
            Tabla2.Body.Style=BodyStyle;
            add(chap3,Tabla2);
            add(chap3,Paragraph(''))
            
            Nombres9=app.UITable.Data(1:17,1);
            E9=cell2mat(app.UITable.Data(1:17,2));
            F9=cell2mat(app.UITable.Data(1:17,3));
            T9=table(categorical(Nombres9(:,1)),f(E9),f(F9));
            T9.Properties.VariableNames = {'Severidad lateral – objeto (lado del conductor)', 'Km', '%'};
            Tabla1=FormalTable(T9);
            Tabla1.ColSpecGroups = grps;
            Tabla1.TableEntriesVAlign = 'middle';
            Tabla1.TableEntriesHAlign = 'center';
            Tabla1.Style = tableStyle;
            Tabla1.Header.Children(1).Style =HeaderStyle;
            Tabla1.Body.Style=BodyStyle;
            add(chap3,Tabla1);
            add(chap3,Paragraph(''))
            
            Nombres10=app.UITable_4.Data(1:4,1);
            E10=cell2mat(app.UITable_4.Data(1:4,2));
            F10=cell2mat(app.UITable_4.Data(1:4,3));
            T10=table(categorical(Nombres10(:,1)),f(E10),f(F10));
            T10.Properties.VariableNames = {'Severidad lateral – distancia al objeto (lado del copiloto)', 'Km', '%'};
            Tabla4=FormalTable(T10);
            Tabla4.ColSpecGroups = grps;
            Tabla4.TableEntriesVAlign = 'middle';
            Tabla4.TableEntriesHAlign = 'center';
            Tabla4.Style = tableStyle;
            Tabla4.Header.Children(1).Style =HeaderStyle;
            Tabla4.Body.Style=BodyStyle;
            add(chap3,Tabla4);
            add(chap3,Paragraph(''))
            
            Nombres11=app.UITable_3.Data(1:17,1);
            E11=cell2mat(app.UITable_3.Data(1:17,2));
            F11=cell2mat(app.UITable_3.Data(1:17,3));
            T11=table(categorical(Nombres11(:,1)),f(E11),f(F11));
            T11.Properties.VariableNames = {'Severidad lateral - objeto (lado del copiloto)', 'Km', '%'};
            Tabla3=FormalTable(T11);
            Tabla3.ColSpecGroups = grps;
            Tabla3.TableEntriesVAlign = 'middle';
            Tabla3.TableEntriesHAlign = 'center';
            Tabla3.Style = tableStyle;
            Tabla3.Header.Children(1).Style =HeaderStyle;
            Tabla3.Body.Style=BodyStyle;
            add(chap3,Tabla3);
            add(chap3,Paragraph(''))
            
            Nombres12=app.UITable_7.Data(1:2,1);
            E12=cell2mat(app.UITable_7.Data(1:2,2));
            F12=cell2mat(app.UITable_7.Data(1:2,3));
            T12=table(categorical(Nombres12(:,1)),f(E12),f(F12));
            T12.Properties.VariableNames = {'Bandas sonoras en el espaldón', 'Km', '%'};
            Tabla7=FormalTable(T12);
            Tabla7.ColSpecGroups = grps;
            Tabla7.TableEntriesVAlign = 'middle';
            Tabla7.TableEntriesHAlign = 'center';
            Tabla7.Style = tableStyle;
            Tabla7.Header.Children(1).Style =HeaderStyle;
            Tabla7.Body.Style=BodyStyle;
            add(chap3,Tabla7);
            add(chap3,Paragraph(''))
            
            Nombres13=app.UITable_6.Data(1:4,1);
            E13=cell2mat(app.UITable_6.Data(1:4,2));
            F13=cell2mat(app.UITable_6.Data(1:4,3));
            T13=table(categorical(Nombres13(:,1)),f(E13),f(F13));
            T13.Properties.VariableNames = {'Ancho de espaldón pavimentado (lado del conductor)', 'Km', '%'};
            Tabla6=FormalTable(T13);
            Tabla6.ColSpecGroups = grps;
            Tabla6.TableEntriesVAlign = 'middle';
            Tabla6.TableEntriesHAlign = 'center';
            Tabla6.Style = tableStyle;
            Tabla6.Header.Children(1).Style =HeaderStyle;
            Tabla6.Body.Style=BodyStyle;
            add(chap3,Tabla6);
            add(chap3,Paragraph(''))
            
            Nombres14=app.UITable_5.Data(1:4,1);
            E14=cell2mat(app.UITable_5.Data(1:4,2));
            F14=cell2mat(app.UITable_5.Data(1:4,3));
            T14=table(categorical(Nombres14(:,1)),f(E14),f(F14));
            T14.Properties.VariableNames = {'Ancho de espaldón pavimentado (lado del copiloto)', 'Km', '%'};
            Tabla5=FormalTable(T14);
            Tabla5.ColSpecGroups = grps;
            Tabla5.TableEntriesVAlign = 'middle';
            Tabla5.TableEntriesHAlign = 'center';
            Tabla5.Style = tableStyle;
            Tabla5.Header.Children(1).Style =HeaderStyle;
            Tabla5.Body.Style=BodyStyle;
            add(chap3,Tabla5);
            add(chap3,Paragraph(''))
           
            %Mediana
            p2=Paragraph('Bloque central');
            p2.Bold=1;
            p2.FontFamilyName='Gotham';
            add(chap3,p2)
            add(chap3,Paragraph(''))
            
            Nombres15=app.UITable_10.Data(1:5,1);
            E15=cell2mat(app.UITable_10.Data(1:5,2));
            F15=cell2mat(app.UITable_10.Data(1:5,3));
            T15=table(categorical(Nombres15(:,1)),f(E15),f(F15));
            T15.Properties.VariableNames = {'Tipo de carretera', 'Km', '%'};
            Tabla10=FormalTable(T15);
            Tabla10.ColSpecGroups = grps;
            Tabla10.TableEntriesVAlign = 'middle'; 
            Tabla10.TableEntriesHAlign = 'center';
            Tabla10.Style = tableStyle;
            Tabla10.Header.Children(1).Style =HeaderStyle;
            Tabla10.Body.Style=BodyStyle;
            add(chap3,Tabla10);
            add(chap3,Paragraph(''))
            
            Nombres16=app.UITable_11.Data(1:3,1);
            E16=cell2mat(app.UITable_11.Data(1:3,2));
            F16=cell2mat(app.UITable_11.Data(1:3,3));
            T16=table(categorical(Nombres16(:,1)),f(E16),f(F16));
            T16.Properties.VariableNames = {'Costo de modernización', 'Km', '%'};
            Tabla11=FormalTable(T16);
            Tabla11.ColSpecGroups = grps;
            Tabla11.TableEntriesVAlign = 'middle';
            Tabla11.TableEntriesHAlign = 'center';
            Tabla11.Style = tableStyle;
            Tabla11.Header.Children(1).Style =HeaderStyle;
            Tabla11.Body.Style=BodyStyle;
            add(chap3,Tabla11);
            add(chap3,Paragraph(''))
            
            Nombres17=app.UITable_12.Data(1:15,1);
            E17=cell2mat(app.UITable_12.Data(1:15,2));
            F17=cell2mat(app.UITable_12.Data(1:15,3));
            T17=table(categorical(Nombres17(:,1)),f(E17),f(F17));
            T17.Properties.VariableNames = {'Tipo de mediana', 'Km', '%'};
            Tabla12=FormalTable(T17);
            Tabla12.ColSpecGroups = grps;
            Tabla12.TableEntriesVAlign = 'middle';
            Tabla12.TableEntriesHAlign = 'center';
            Tabla12.Style = tableStyle;
            Tabla12.Header.Children(1).Style =HeaderStyle;
            Tabla12.Body.Style=BodyStyle;
            add(chap3,Tabla12);
            add(chap3,Paragraph(''))
            
            Nombres18=app.UITable_13.Data(1:2,1);
            E18=cell2mat(app.UITable_13.Data(1:2,2));
            F18=cell2mat(app.UITable_13.Data(1:2,3));
            T18=table(categorical(Nombres18(:,1)),f(E18),f(F18));
            T18.Properties.VariableNames = {'Bandas sonoras centrales', 'Km', '%'};
            Tabla13=FormalTable(T18);
            Tabla13.ColSpecGroups = grps;
            Tabla13.TableEntriesVAlign = 'middle';
            Tabla13.TableEntriesHAlign = 'center';
            Tabla13.Style = tableStyle;
            Tabla13.Header.Children(1).Style =HeaderStyle;
            Tabla13.Body.Style=BodyStyle;
            add(chap3,Tabla13);
            add(chap3,Paragraph(''))
            
            Nombres19=app.UITable_14.Data(1:6,1);
            E19=cell2mat(app.UITable_14.Data(1:6,2));
            F19=cell2mat(app.UITable_14.Data(1:6,3));
            T19=table(categorical(Nombres19(:,1)),f(E19),f(F19));
            T19.Properties.VariableNames = {'Número de carriles', 'Km', '%'};
            Tabla14=FormalTable(T19);
            Tabla14.ColSpecGroups = grps;
            Tabla14.TableEntriesVAlign = 'middle';
            Tabla14.TableEntriesHAlign = 'center';
            Tabla14.Style = tableStyle;
            Tabla14.Header.Children(1).Style =HeaderStyle;
            Tabla14.Body.Style=BodyStyle;
            add(chap3,Tabla14);
            add(chap3,Paragraph(''))
            
            Nombres20=app.UITable_15.Data(1:3,1);
            E20=cell2mat(app.UITable_15.Data(1:3,2));
            F20=cell2mat(app.UITable_15.Data(1:3,3));
            T20=table(categorical(Nombres20(:,1)),f(E20),f(F20));
            T20.Properties.VariableNames = {'Ancho de carril', 'Km', '%'};
            Tabla15=FormalTable(T20);
            Tabla15.ColSpecGroups = grps;
            Tabla15.TableEntriesVAlign = 'middle';
            Tabla15.TableEntriesHAlign = 'center';
            Tabla15.Style = tableStyle;
            Tabla15.Header.Children(1).Style =HeaderStyle;
            Tabla15.Body.Style=BodyStyle;
            add(chap3,Tabla15);
            add(chap3,Paragraph(''))
            
            Nombres21=app.UITable_16.Data(1:4,1);
            E21=cell2mat(app.UITable_16.Data(1:4,2));
            F21=cell2mat(app.UITable_16.Data(1:4,3));
            T21=table(categorical(Nombres21(:,1)),f(E21),f(F21));
            T21.Properties.VariableNames = {'Curvatura', 'Km', '%'};
            Tabla16=FormalTable(T21);
            Tabla16.ColSpecGroups = grps;
            Tabla16.TableEntriesVAlign = 'middle';
            Tabla16.TableEntriesHAlign = 'center';
            Tabla16.Style = tableStyle;
            Tabla16.Header.Children(1).Style =HeaderStyle;
            Tabla16.Body.Style=BodyStyle;
            add(chap3,Tabla16);
            add(chap3,Paragraph(''))
            
            Nombres22=app.UITable_17.Data(1:3,1);
            E22=cell2mat(app.UITable_17.Data(1:3,2));
            F22=cell2mat(app.UITable_17.Data(1:3,3));
            T22=table(categorical(Nombres22(:,1)),f(E22),f(F22));
            T22.Properties.VariableNames = {'Calidad de la curva', 'Km', '%'};
            Tabla17=FormalTable(T22);
            Tabla17.ColSpecGroups = grps;
            Tabla17.TableEntriesVAlign = 'middle';
            Tabla17.TableEntriesHAlign = 'center';
            Tabla17.Style = tableStyle;
            Tabla17.Header.Children(1).Style =HeaderStyle;
            Tabla17.Body.Style=BodyStyle;
            add(chap3,Tabla17);
            add(chap3,Paragraph(''))
            
            Nombres23=app.UITable_18.Data(1:3,1);
            E23=cell2mat(app.UITable_18.Data(1:3,2));
            F23=cell2mat(app.UITable_18.Data(1:3,3));
            T23=table(categorical(Nombres23(:,1)),f(E23),f(F23));
            T23.Properties.VariableNames = {'Pendiente', 'Km', '%'};
            Tabla18=FormalTable(T23);
            Tabla18.ColSpecGroups = grps;
            Tabla18.TableEntriesVAlign = 'middle';
            Tabla18.TableEntriesHAlign = 'center';
            Tabla18.Style = tableStyle;
            Tabla18.Header.Children(1).Style =HeaderStyle;
            Tabla18.Body.Style=BodyStyle;
            add(chap3,Tabla18);
            add(chap3,Paragraph(''))
            
            Nombres24=app.UITable_19.Data(1:3,1);
            E24=cell2mat(app.UITable_19.Data(1:3,2));
            F24=cell2mat(app.UITable_19.Data(1:3,3));
            T24=table(categorical(Nombres24(:,1)),f(E24),f(F24));
            T24.Properties.VariableNames = {'Estado de la superficie de la vía', 'Km', '%'};
            Tabla19=FormalTable(T24);
            Tabla19.ColSpecGroups = grps;
            Tabla19.TableEntriesVAlign = 'middle';
            Tabla19.TableEntriesHAlign = 'center';
            Tabla19.Style = tableStyle;
            Tabla19.Header.Children(1).Style =HeaderStyle;
            Tabla19.Body.Style=BodyStyle;
            add(chap3,Tabla19);
            add(chap3,Paragraph(''))
            
            Nombres25=app.UITable_20.Data(1:5,1);
            E25=cell2mat(app.UITable_20.Data(1:5,2));
            F25=cell2mat(app.UITable_20.Data(1:5,3));
            T25=table(categorical(Nombres25(:,1)),f(E25),f(F25));
            T25.Properties.VariableNames = {'Resistencia al deslizamiento', 'Km', '%'};
            Tabla20=FormalTable(T25);
            Tabla20.ColSpecGroups = grps;
            Tabla20.TableEntriesVAlign = 'middle';
            Tabla20.TableEntriesHAlign = 'center';
            Tabla20.Style = tableStyle;
            Tabla20.Header.Children(1).Style =HeaderStyle;
            Tabla20.Body.Style=BodyStyle;
            add(chap3,Tabla20);
            add(chap3,Paragraph(''))
            
            Nombres26=app.UITable_21.Data(1:2,1);
            E26=cell2mat(app.UITable_21.Data(1:2,2));
            F26=cell2mat(app.UITable_21.Data(1:2,3));
            T26=table(categorical(Nombres26(:,1)),f(E26),f(F26));
            T26.Properties.VariableNames = {'Delineación', 'Km', '%'};
            Tabla21=FormalTable(T26);
            Tabla21.ColSpecGroups = grps;
            Tabla21.TableEntriesVAlign = 'middle';
            Tabla21.TableEntriesHAlign = 'center';
            Tabla21.Style = tableStyle;
            Tabla21.Header.Children(1).Style =HeaderStyle;
            Tabla21.Body.Style=BodyStyle;
            add(chap3,Tabla21);
            add(chap3,Paragraph(''))
            
            Nombres27=app.UITable_22.Data(1:2,1);
            E27=cell2mat(app.UITable_22.Data(1:2,2));
            F27=cell2mat(app.UITable_22.Data(1:2,3));
            T27=table(categorical(Nombres27(:,1)),f(E27),f(F27));
            T27.Properties.VariableNames = {'Alumbrado público', 'Km', '%'};
            Tabla22=FormalTable(T27);
            Tabla22.ColSpecGroups = grps;
            Tabla22.TableEntriesVAlign = 'middle';
            Tabla22.TableEntriesHAlign = 'center';
            Tabla22.Style = tableStyle;
            Tabla22.Header.Children(1).Style =HeaderStyle;
            Tabla22.Body.Style=BodyStyle;
            add(chap3,Tabla22);
            add(chap3,Paragraph(''))
            
            Nombres28=app.UITable_23.Data(1:3,1);
            E28=cell2mat(app.UITable_23.Data(1:3,2));
            F28=cell2mat(app.UITable_23.Data(1:3,3));
            T28=table(categorical(Nombres28(:,1)),f(E28),f(F28));
            T28.Properties.VariableNames = {'Estacionamiento para vehículos', 'Km', '%'};
            Tabla23=FormalTable(T28);
            Tabla23.ColSpecGroups = grps;
            Tabla23.TableEntriesVAlign = 'middle';
            Tabla23.TableEntriesHAlign = 'center';
            Tabla23.Style = tableStyle;
            Tabla23.Header.Children(1).Style =HeaderStyle;
            Tabla23.Body.Style=BodyStyle;
            add(chap3,Tabla23);
            add(chap3,Paragraph(''))
            
            Nombres29=app.UITable_24.Data(1:2,1);
            E29=cell2mat(app.UITable_24.Data(1:2,2));
            F29=cell2mat(app.UITable_24.Data(1:2,3));
            T29=table(categorical(Nombres29(:,1)),f(E29),f(F29));
            T29.Properties.VariableNames = {'Vía de servicio', 'Km', '%'};
            Tabla24=FormalTable(T29);
            Tabla24.ColSpecGroups = grps;
            Tabla24.TableEntriesVAlign = 'middle';
            Tabla24.TableEntriesHAlign = 'center';
            Tabla24.Style = tableStyle;
            Tabla24.Header.Children(1).Style =HeaderStyle;
            Tabla24.Body.Style=BodyStyle;
            add(chap3,Tabla24);
            add(chap3,Paragraph(''))
            
            Nombres30=app.UITable_25.Data(1:3,1);
            E30=cell2mat(app.UITable_25.Data(1:3,2));
            F30=cell2mat(app.UITable_25.Data(1:3,3));
            T30=table(categorical(Nombres30(:,1)),f(E30),f(F30));
            T30.Properties.VariableNames = {'Obras en la carretera', 'Km', '%'};
            Tabla25=FormalTable(T30);
            Tabla25.ColSpecGroups = grps;
            Tabla25.TableEntriesVAlign = 'middle';
            Tabla25.TableEntriesHAlign = 'center';
            Tabla25.Style = tableStyle;
            Tabla25.Header.Children(1).Style =HeaderStyle;
            Tabla25.Body.Style=BodyStyle;
            add(chap3,Tabla25);
            add(chap3,Paragraph(''))
            
            Nombres31=app.UITable_26.Data(1:2,1);
            E31=cell2mat(app.UITable_26.Data(1:2,2));
            F31=cell2mat(app.UITable_26.Data(1:2,3));
            T31=table(categorical(Nombres31(:,1)),f(E31),f(F31));
            T31.Properties.VariableNames = {'Distancia de visibilidad', 'Km', '%'};
            Tabla26=FormalTable(T31);
            Tabla26.ColSpecGroups = grps;
            Tabla26.TableEntriesVAlign = 'middle';
            Tabla26.TableEntriesHAlign = 'center';
            Tabla26.Style = tableStyle;
            Tabla26.Header.Children(1).Style =HeaderStyle;
            Tabla26.Body.Style=BodyStyle;
            add(chap3,Tabla26);
            add(chap3,Paragraph(''))
            
            %Intersecciones
            p3=Paragraph('Intersecciones');
            p3.Bold=1;
            p3.FontFamilyName='Gotham';
            add(chap3,p3)
            add(chap3,Paragraph(''))
            
            Nombres32=app.UITable_28.Data(1:16,1);
            E32=cell2mat(app.UITable_28.Data(1:16,2));
            F32=cell2mat(app.UITable_28.Data(1:16,3));
            T32=table(categorical(Nombres32(:,1)),f(E32),f(F32));
            T32.Properties.VariableNames = {'Tipo de intersección', 'Km', '%'};
            Tabla28=FormalTable(T32);
            Tabla28.ColSpecGroups = grps;
            Tabla28.TableEntriesVAlign = 'middle';
            Tabla28.TableEntriesHAlign = 'center';
            Tabla28.Style = tableStyle;
            Tabla28.Header.Children(1).Style =HeaderStyle;
            Tabla28.Body.Style=BodyStyle;
            add(chap3,Tabla28);
            add(chap3,Paragraph(''))
            
            Nombres33=app.UITable_27.Data(1:2,1);
            E33=cell2mat(app.UITable_27.Data(1:2,2));
            F33=cell2mat(app.UITable_27.Data(1:2,3));
            T33=table(categorical(Nombres33(:,1)),f(E33),f(F33));
            T33.Properties.VariableNames = {'Canalización de la intersección', 'Km', '%'};
            Tabla27=FormalTable(T33);
            Tabla27.ColSpecGroups = grps;
            Tabla27.TableEntriesVAlign = 'middle';
            Tabla27.TableEntriesHAlign = 'center';
            Tabla27.Style = tableStyle;
            Tabla27.Header.Children(1).Style =HeaderStyle;
            Tabla27.Body.Style=BodyStyle;
            add(chap3,Tabla27);
            add(chap3,Paragraph(''))
            
            Nombres34=app.UITable_30.Data(1:7,1);
            E34=cell2mat(app.UITable_30.Data(1:7,2));
            F34=cell2mat(app.UITable_30.Data(1:7,3));
            T34=table(categorical(Nombres34(:,1)),f(E34),f(F34));
            T34.Properties.VariableNames = {'Volumen de la intersección', 'Km', '%'};
            Tabla30=FormalTable(T34);
            Tabla30.ColSpecGroups = grps;
            Tabla30.TableEntriesVAlign = 'middle';
            Tabla30.TableEntriesHAlign = 'center';
            Tabla30.Style = tableStyle;
            Tabla30.Header.Children(1).Style =HeaderStyle;
            Tabla30.Body.Style=BodyStyle;
            add(chap3,Tabla30);
            add(chap3,Paragraph(''))
            
            Nombres35=app.UITable_29.Data(1:3,1);
            E35=cell2mat(app.UITable_29.Data(1:3,2));
            F35=cell2mat(app.UITable_29.Data(1:3,3));
            T35=table(categorical(Nombres35(:,1)),f(E35),f(F35));
            T35.Properties.VariableNames = {'Calidad de la intersección', 'Km', '%'};
            Tabla29=FormalTable(T35);
            Tabla29.ColSpecGroups = grps;
            Tabla29.TableEntriesVAlign = 'middle';
            Tabla29.TableEntriesHAlign = 'center';
            Tabla29.Style = tableStyle;
            Tabla29.Header.Children(1).Style =HeaderStyle;
            Tabla29.Body.Style=BodyStyle;
            add(chap3,Tabla29);
            add(chap3,Paragraph(''))
            
            Nombres36=app.UITable_31.Data(1:4,1);
            E36=cell2mat(app.UITable_31.Data(1:4,2));
            F36=cell2mat(app.UITable_31.Data(1:4,3));
            T36=table(categorical(Nombres36(:,1)),f(E36),f(F36));
            T36.Properties.VariableNames = {'Puntos de acceso a la propiedad', 'Km', '%'};
            Tabla31=FormalTable(T36);
            Tabla31.ColSpecGroups = grps;
            Tabla31.TableEntriesVAlign = 'middle';
            Tabla31.TableEntriesHAlign = 'center';
            Tabla31.Style = tableStyle;
            Tabla31.Header.Children(1).Style =HeaderStyle;
            Tabla31.Body.Style=BodyStyle;
            add(chap3,Tabla31);
            add(chap3,Paragraph(''))
            
            %Flujo
            p4=Paragraph('Flujo');
            p4.Bold=1;
            p4.FontFamilyName='Gotham';
            add(chap3,p4)
            add(chap3,Paragraph(''))
            
            Nombres37=app.UITable_33.Data(1:10,1);
            E37=cell2mat(app.UITable_33.Data(1:10,2));
            F37=cell2mat(app.UITable_33.Data(1:10,3));
            T37=table(categorical(Nombres37(:,1)),f(E37),f(F37));
            T37.Properties.VariableNames = {'Flujo vehicular (TDPA)', 'Km', '%'};
            Tabla33=FormalTable(T37);
            Tabla33.ColSpecGroups = grps;
            Tabla33.TableEntriesVAlign = 'middle';
            Tabla33.TableEntriesHAlign = 'center';
            Tabla33.Style = tableStyle;
            Tabla33.Header.Children(1).Style =HeaderStyle;
            Tabla33.Body.Style=BodyStyle;
            add(chap3,Tabla33);
            add(chap3,Paragraph(''))
            
            Nombres38=app.UITable_32.Data(1:10,1);
            E38=cell2mat(app.UITable_32.Data(1:10,2));
            F38=cell2mat(app.UITable_32.Data(1:10,3));
            T38=table(categorical(Nombres38(:,1)),f(E38),f(F38));
            T38.Properties.VariableNames = {'% motocicletas', 'Km', '%'};
            Tabla32=FormalTable(T38);
            Tabla32.ColSpecGroups = grps;
            Tabla32.TableEntriesVAlign = 'middle';
            Tabla32.TableEntriesHAlign = 'center';
            Tabla32.Style = tableStyle;
            Tabla32.Header.Children(1).Style =HeaderStyle;
            Tabla32.Body.Style=BodyStyle;
            add(chap3,Tabla32);
            add(chap3,Paragraph(''))
            
            Nombres39=app.UITable_35.Data(1:11,1);
            E39=cell2mat(app.UITable_35.Data(1:11,2));
            F39=cell2mat(app.UITable_35.Data(1:11,3));
            T39=table(categorical(Nombres39(:,1)),f(E39),f(F39));
            T39.Properties.VariableNames = {'Flujo peatonal en hora pico que cruza la carretera', 'Km', '%'};
            Tabla35=FormalTable(T39);
            Tabla35.ColSpecGroups = grps;
            Tabla35.TableEntriesVAlign = 'middle';
            Tabla35.TableEntriesHAlign = 'center';
            Tabla35.Style = tableStyle;
            Tabla35.Header.Children(1).Style =HeaderStyle;
            Tabla35.Body.Style=BodyStyle;
            add(chap3,Tabla35);
            add(chap3,Paragraph(''))
            
            Nombres40=app.UITable_34.Data(1:11,1);
            E40=cell2mat(app.UITable_34.Data(1:11,2));
            F40=cell2mat(app.UITable_34.Data(1:11,3));
            T40=table(categorical(Nombres40(:,1)),f(E40),f(F40));
            T40.Properties.VariableNames = {'Flujo peatonal en hora pico a lo largo (lado del conductor)', 'Km', '%'};
            Tabla34=FormalTable(T40);
            Tabla34.ColSpecGroups = grps;
            Tabla34.TableEntriesVAlign = 'middle';
            Tabla34.TableEntriesHAlign = 'center';
            Tabla34.Style = tableStyle;
            Tabla34.Header.Children(1).Style =HeaderStyle;
            Tabla34.Body.Style=BodyStyle;
            add(chap3,Tabla34);
            add(chap3,Paragraph(''))
            
            Nombres41=app.UITable_36.Data(1:11,1);
            E41=cell2mat(app.UITable_36.Data(1:11,2));
            F41=cell2mat(app.UITable_36.Data(1:11,3));
            T41=table(categorical(Nombres41(:,1)),f(E41),f(F41));
            T41.Properties.VariableNames = {'Flujo peatonal en hora pico a lo largo (lado del copiloto)', 'Km', '%'};
            Tabla36=FormalTable(T41);
            Tabla36.ColSpecGroups = grps;
            Tabla36.TableEntriesVAlign = 'middle';
            Tabla36.TableEntriesHAlign = 'center';
            Tabla36.Style = tableStyle;
            Tabla36.Header.Children(1).Style =HeaderStyle;
            Tabla36.Body.Style=BodyStyle;
            add(chap3,Tabla36);
            add(chap3,Paragraph(''))
            
            Nombres42=app.UITable_37.Data(1:11,1);
            E42=cell2mat(app.UITable_37.Data(1:11,2));
            F42=cell2mat(app.UITable_37.Data(1:11,3));
            T42=table(categorical(Nombres42(:,1)),f(E42),f(F42));
            T42.Properties.VariableNames = {'Flujo de bicicletas en hora pico', 'Km', '%'};
            Tabla37=FormalTable(T42);
            Tabla37.ColSpecGroups = grps;
            Tabla37.TableEntriesVAlign = 'middle';
            Tabla37.TableEntriesHAlign = 'center';
            Tabla37.Style = tableStyle;
            Tabla37.Header.Children(1).Style =HeaderStyle;
            Tabla37.Body.Style=BodyStyle;
            add(chap3,Tabla37);
            add(chap3,Paragraph(''))
            
            %Infraestructura para usuarios vulnerables y uso del suelo
            p5=Paragraph('Infraestructura para usuarios vulnerables y uso del suelo');
            p5.Bold=1;
            p5.FontFamilyName='Gotham';
            add(chap3,p5)
            add(chap3,Paragraph(''))
            
            Nombres43=app.UITable_38.Data(1:6,1);
            E43=cell2mat(app.UITable_38.Data(1:6,2));
            F43=cell2mat(app.UITable_38.Data(1:6,3));
            T43=table(categorical(Nombres43(:,1)),f(E43),f(F43));
            T43.Properties.VariableNames = {'Uso de suelo (lado del conductor)', 'Km', '%'};
            Tabla38=FormalTable(T43);
            Tabla38.ColSpecGroups = grps;
            Tabla38.TableEntriesVAlign = 'middle';
            Tabla38.TableEntriesHAlign = 'center';
            Tabla38.Style = tableStyle;
            Tabla38.Header.Children(1).Style =HeaderStyle;
            Tabla38.Body.Style=BodyStyle;
            add(chap3,Tabla38);
            add(chap3,Paragraph(''))
            
            Nombres44=app.UITable_39.Data(1:6,1);
            E44=cell2mat(app.UITable_39.Data(1:6,2));
            F44=cell2mat(app.UITable_39.Data(1:6,3));
            T44=table(categorical(Nombres44(:,1)),f(E44),f(F44));
            T44.Properties.VariableNames = {'Uso de suelo (lado del copiloto)', 'Km', '%'};
            Tabla39=FormalTable(T44);
            Tabla39.ColSpecGroups = grps;
            Tabla39.TableEntriesVAlign = 'middle';
            Tabla39.TableEntriesHAlign = 'center';
            Tabla39.Style = tableStyle;
            Tabla39.Header.Children(1).Style =HeaderStyle;
            Tabla39.Body.Style=BodyStyle;
            add(chap3,Tabla39);
            add(chap3,Paragraph(''))
            
            Nombres45=app.UITable_40.Data(1:2,1);
            E45=cell2mat(app.UITable_40.Data(1:2,2));
            F45=cell2mat(app.UITable_40.Data(1:2,3));
            T45=table(categorical(Nombres45(:,1)),f(E45),f(F45));
            T45.Properties.VariableNames = {'Tipo de área', 'Km', '%'};
            Tabla40=FormalTable(T45);
            Tabla40.ColSpecGroups = grps;
            Tabla40.TableEntriesVAlign = 'middle';
            Tabla40.TableEntriesHAlign = 'center';
            Tabla40.Style = tableStyle;
            Tabla40.Header.Children(1).Style =HeaderStyle;
            Tabla40.Body.Style=BodyStyle;
            add(chap3,Tabla40);
            add(chap3,Paragraph(''))
            
            Nombres46=app.UITable_41.Data(1:11,1);
            E46=cell2mat(app.UITable_41.Data(1:11,2));
            F46=cell2mat(app.UITable_41.Data(1:11,3));
            T46=table(categorical(Nombres46(:,1)),f(E46),f(F46));
            T46.Properties.VariableNames = {'Infraestructura de cruce peatonal en el tramo analizado', 'Km', '%'};
            Tabla41=FormalTable(T46);
            Tabla41.ColSpecGroups = grps;
            Tabla41.TableEntriesVAlign = 'middle';
            Tabla41.TableEntriesHAlign = 'center';
            Tabla41.Style = tableStyle;
            Tabla41.Header.Children(1).Style =HeaderStyle;
            Tabla41.Body.Style=BodyStyle;
            add(chap3,Tabla41);
            add(chap3,Paragraph(''))
            
            Nombres47=app.UITable_42.Data(1:3,1);
            E47=cell2mat(app.UITable_42.Data(1:3,2));
            F47=cell2mat(app.UITable_42.Data(1:3,3));
            T47=table(categorical(Nombres47(:,1)),f(E47),f(F47));
            T47.Properties.VariableNames = {'Calidad de cruce peatonal', 'Km', '%'};
            Tabla42=FormalTable(T47);
            Tabla42.ColSpecGroups = grps;
            Tabla42.TableEntriesVAlign = 'middle';
            Tabla42.TableEntriesHAlign = 'center';
            Tabla42.Style = tableStyle;
            Tabla42.Header.Children(1).Style =HeaderStyle;
            Tabla42.Body.Style=BodyStyle;
            add(chap3,Tabla42);
            add(chap3,Paragraph(''))
            
            Nombres48=app.UITable_43.Data(1:11,1);
            E48=cell2mat(app.UITable_43.Data(1:11,2));
            F48=cell2mat(app.UITable_43.Data(1:11,3));
            T48=table(categorical(Nombres48(:,1)),f(E48),f(F48));
            T48.Properties.VariableNames = {'Infraestructura de cruce peatonal en la intersección', 'Km', '%'};
            Tabla43=FormalTable(T48);
            Tabla43.ColSpecGroups = grps;
            Tabla43.TableEntriesVAlign = 'middle';
            Tabla43.TableEntriesHAlign = 'center';
            Tabla43.Style = tableStyle;
            Tabla43.Header.Children(1).Style =HeaderStyle;
            Tabla43.Body.Style=BodyStyle;
            add(chap3,Tabla43);
            add(chap3,Paragraph(''))
            
            Nombres49=app.UITable_44.Data(1:2,1);
            E49=cell2mat(app.UITable_44.Data(1:2,2));
            F49=cell2mat(app.UITable_44.Data(1:2,3));
            T49=table(categorical(Nombres49(:,1)),f(E49),f(F49));
            T49.Properties.VariableNames = {'Vallas peatonales', 'Km', '%'};
            Tabla44=FormalTable(T49);
            Tabla44.ColSpecGroups = grps;
            Tabla44.TableEntriesVAlign = 'middle';
            Tabla44.TableEntriesHAlign = 'center';
            Tabla44.Style = tableStyle;
            Tabla44.Header.Children(1).Style =HeaderStyle;
            Tabla44.Body.Style=BodyStyle;
            add(chap3,Tabla44);
            add(chap3,Paragraph(''))
            
            Nombres50=app.UITable_45.Data(1:7,1);
            E50=cell2mat(app.UITable_45.Data(1:7,2));
            F50=cell2mat(app.UITable_45.Data(1:7,3));
            T50=table(categorical(Nombres50(:,1)),f(E50),f(F50));
            T50.Properties.VariableNames = {'Acera (lado del conductor)', 'Km', '%'};
            Tabla45=FormalTable(T50);
            Tabla45.ColSpecGroups = grps;
            Tabla45.TableEntriesVAlign = 'middle';
            Tabla45.TableEntriesHAlign = 'center';
            Tabla45.Style = tableStyle;
            Tabla45.Header.Children(1).Style =HeaderStyle;
            Tabla45.Body.Style=BodyStyle;
            add(chap3,Tabla45);
            add(chap3,Paragraph(''))
            
            Nombres51=app.UITable_46.Data(1:7,1);
            E51=cell2mat(app.UITable_46.Data(1:7,2));
            F51=cell2mat(app.UITable_46.Data(1:7,3));
            T51=table(categorical(Nombres51(:,1)),f(E51),f(F51));
            T51.Properties.VariableNames = {'Acera (lado del copiloto)', 'Km', '%'};
            Tabla46=FormalTable(T51);
            Tabla46.ColSpecGroups = grps;
            Tabla46.TableEntriesVAlign = 'middle';
            Tabla46.TableEntriesHAlign = 'center';
            Tabla46.Style = tableStyle;
            Tabla46.Header.Children(1).Style =HeaderStyle;
            Tabla46.Body.Style=BodyStyle;
            add(chap3,Tabla46);
            add(chap3,Paragraph(''))
            
            Nombres52=app.UITable_47.Data(1:6,1);
            E52=cell2mat(app.UITable_47.Data(1:6,2));
            F52=cell2mat(app.UITable_47.Data(1:6,3));
            T52=table(categorical(Nombres52(:,1)),f(E52),f(F52));
            T52.Properties.VariableNames = {'Infraestructura para motocicletas', 'Km', '%'};
            Tabla47=FormalTable(T52);
            Tabla47.ColSpecGroups = grps;
            Tabla47.TableEntriesVAlign = 'middle';
            Tabla47.TableEntriesHAlign = 'center';
            Tabla47.Style = tableStyle;
            Tabla47.Header.Children(1).Style =HeaderStyle;
            Tabla47.Body.Style=BodyStyle;
            add(chap3,Tabla47);
            add(chap3,Paragraph(''))
            
            Nombres53=app.UITable_48.Data(1:7,1);
            E53=cell2mat(app.UITable_48.Data(1:7,2));
            F53=cell2mat(app.UITable_48.Data(1:7,3));
            T53=table(categorical(Nombres53(:,1)),f(E53),f(F53));
            T53.Properties.VariableNames = {'Infraestructura para bicicletas', 'Km', '%'};
            Tabla48=FormalTable(T53);
            Tabla48.ColSpecGroups = grps;
            Tabla48.TableEntriesVAlign = 'middle';
            Tabla48.TableEntriesHAlign = 'center';
            Tabla48.Style = tableStyle;
            Tabla48.Header.Children(1).Style =HeaderStyle;
            Tabla48.Body.Style=BodyStyle;
            add(chap3,Tabla48);
            add(chap3,Paragraph(''))
            
            Nombres54=app.UITable_49.Data(1:4,1);
            E54=cell2mat(app.UITable_49.Data(1:4,2));
            F54=cell2mat(app.UITable_49.Data(1:4,3));
            T54=table(categorical(Nombres54(:,1)),f(E54),f(F54));
            T54.Properties.VariableNames = {'Advertencia de zona escolar', 'Km', '%'};
            Tabla49=FormalTable(T54);
            Tabla49.ColSpecGroups = grps;
            Tabla49.TableEntriesVAlign = 'middle';
            Tabla49.TableEntriesHAlign = 'center';
            Tabla49.Style = tableStyle;
            Tabla49.Header.Children(1).Style =HeaderStyle;
            Tabla49.Body.Style=BodyStyle;
            add(chap3,Tabla49);
            add(chap3,Paragraph(''))
            
            Nombres55=app.UITable_50.Data(1:3,1);
            E55=cell2mat(app.UITable_50.Data(1:3,2));
            F55=cell2mat(app.UITable_50.Data(1:3,3));
            T55=table(categorical(Nombres55(:,1)),f(E55),f(F55));
            T55.Properties.VariableNames = {'Supervisor en el cruce de en zona escolar', 'Km', '%'};
            Tabla50=FormalTable(T55);
            Tabla50.ColSpecGroups = grps;
            Tabla50.TableEntriesVAlign = 'middle';
            Tabla50.TableEntriesHAlign = 'center';
            Tabla50.Style = tableStyle;
            Tabla50.Header.Children(1).Style =HeaderStyle;
            Tabla50.Body.Style=BodyStyle;
            add(chap3,Tabla50);
            add(chap3,Paragraph(''))
            
            %Velocidades
            p6=Paragraph('Velocidades');
            p6.Bold=1;
            p6.FontFamilyName='Gotham';
            add(chap3,p6)
            add(chap3,Paragraph(''))
            
            Nombres56=app.UITable_52.Data(1:25,1);
            E56=cell2mat(app.UITable_52.Data(1:25,2));
            F56=cell2mat(app.UITable_52.Data(1:25,3));
            T56=table(categorical(Nombres56(:,1)),f(E56),f(F56));
            T56.Properties.VariableNames = {'Límite de velocidad', 'Km', '%'};
            Tabla52=FormalTable(T56);
            Tabla52.ColSpecGroups = grps;
            Tabla52.TableEntriesVAlign = 'middle';
            Tabla52.TableEntriesHAlign = 'center';
            Tabla52.Style = tableStyle;
            Tabla52.Header.Children(1).Style =HeaderStyle;
            Tabla52.Body.Style=BodyStyle;
            add(chap3,Tabla52);
            add(chap3,Paragraph(''))
            
            Nombres57=app.UITable_51.Data(1:2,1);
            E57=cell2mat(app.UITable_51.Data(1:2,2));
            F57=cell2mat(app.UITable_51.Data(1:2,3));
            T57=table(categorical(Nombres57(:,1)),f(E57),f(F57));
            T57.Properties.VariableNames = {'Diferencial de límites de velocidad', 'Km', '%'};
            Tabla51=FormalTable(T57);
            Tabla51.ColSpecGroups = grps;
            Tabla51.TableEntriesVAlign = 'middle';
            Tabla51.TableEntriesHAlign = 'center';
            Tabla51.Style = tableStyle;
            Tabla51.Header.Children(1).Style =HeaderStyle;
            Tabla51.Body.Style=BodyStyle;
            add(chap3,Tabla51);
            add(chap3,Paragraph(''))
            
            Nombres58=app.UITable_54.Data(1:2,1);
            E58=cell2mat(app.UITable_54.Data(1:2,2));
            F58=cell2mat(app.UITable_54.Data(1:2,3));
            T58=table(categorical(Nombres58(:,1)),f(E58),f(F58));
            T58.Properties.VariableNames = {'Gestión de la velocidad/calmado el tráfico', 'Km', '%'};
            Tabla54=FormalTable(T58);
            Tabla54.ColSpecGroups = grps;
            Tabla54.TableEntriesVAlign = 'middle';
            Tabla54.TableEntriesHAlign = 'center';
            Tabla54.Style = tableStyle;
            Tabla54.Header.Children(1).Style =HeaderStyle;
            Tabla54.Body.Style=BodyStyle;
            add(chap3,Tabla54);
            add(chap3,Paragraph(''))
            
            Nombres59=app.UITable_53.Data(1:25,1);
            E59=cell2mat(app.UITable_53.Data(1:25,2));
            F59=cell2mat(app.UITable_53.Data(1:25,3));
            T59=table(categorical(Nombres59(:,1)),f(E59),f(F59));
            T59.Properties.VariableNames = {'Velocidad de operación (percentil 85)', 'Km', '%'};
            Tabla53=FormalTable(T59);
            Tabla53.ColSpecGroups = grps;
            Tabla53.TableEntriesVAlign = 'middle';
            Tabla53.TableEntriesHAlign = 'center';
            Tabla53.Style = tableStyle;
            Tabla53.Header.Children(1).Style =HeaderStyle;
            Tabla53.Body.Style=BodyStyle;
            add(chap3,Tabla53);
            add(chap3,Paragraph(''))
            
            add(rpt,chap3);
            
            
           %Capítulo 4 - Clasifiación por estrellas de la carretera
           chap2 = Chapter('Clasificación por estrellas de la carretera');
           
            %Ocupantes de vehículos
           
            p1=Paragraph('Ocupantes de vehículos');
            p1.Bold=1;
            p1.FontFamilyName='Gotham';
            add(chap2,p1)
            p1=Paragraph('a) Resumen de la clasificación por estrellas (SR) de la carretera analizada');
            p1.Underline='Single';
            add(chap2,p1);
            p1=Paragraph('En la siguiente tabla se indica la clasificación por estrellas sin suavizar para ');
            p1.FontFamilyName='Gotham';
            p1.HAlign='justify';
            tex1= Text('ocupantes de vehículos.');
            tex1.Style = {Bold(true)};
            append(p1,tex1);
            add(chap2,p1);
            add(chap2,Paragraph(''));
            
            Nombres=app.Tabla_estrellas_Ocupante(1:7,1);
            E=cell2mat(app.Tabla_estrellas_Ocupante(1:7,2));
            F=cell2mat(app.Tabla_estrellas_Ocupante(1:7,3));
            T=table(categorical(Nombres(:,1)),f(E),f(F));
            T.Properties.VariableNames = {'Clasificación por estrellas', 'Longitud (km)', 'Porcentaje (%)'};
            Tablax=FormalTable(T);
            Tablax.TableEntriesHAlign = 'center';
            Tablax.Style = tableStyle;
            Tablax.Header.Children(1).Style =HeaderStyle;
            Tablax.Body.Style=BodyStyle;
            add(chap2,Tablax);
            add(chap2,Paragraph(''))


            p1=Paragraph('En la siguiente tabla se indica la clasificación por estrellas suavizada para ');
            p1.FontFamilyName='Gotham';
            p1.HAlign='justify';
            tex1= Text('ocupantes de vehículos.');
            tex1.Style = {Bold(true)};
            append(p1,tex1);
            add(chap2,p1);
            add(chap2,Paragraph(''))
            
            Nombres1=app.Tabla_Nuevaestrellas_Ocupante(1:7,1);
            E1=cell2mat(app.Tabla_Nuevaestrellas_Ocupante(1:7,2));
            F1=cell2mat(app.Tabla_Nuevaestrellas_Ocupante(1:7,3));
            T1=table(categorical(Nombres1(:,1)),f(E1),f(F1));
            T1.Properties.VariableNames = {'Clasificación por estrellas', 'Longitud (km)', 'Porcentaje (%)'};
            Tablax=FormalTable(T1);
            Tablax.TableEntriesHAlign = 'center';
            Tablax.Style = tableStyle;
            Tablax.Header.Children(1).Style =HeaderStyle;
            Tablax.Body.Style=BodyStyle;
            add(chap2,Tablax);
            add(chap2,Paragraph(''))
           
            p2=Paragraph('b) Puntaje de clasificación por estrellas (SRS) a lo largo de la carretera analizada ');
            p2.Underline='Single';
            p2.FontFamilyName='Gotham';
            add(chap2,p2);
            p2=Paragraph('A continuación, se muestra en la figura, los SRS (línea gris) de cada sección del tramo en análisis. Los SRS suavizados (línea azul) es el promedio de los SRS en distancias más largas que se realizan con el fin de producir resultados más significativos. En la suavización se realizan dos procedimientos, media móvil con un ancho de ventana de 9, y luego una suavización exponencial con un valor alfa de 0,1.');
            p2.FontFamilyName='Gotham';
            p2.HAlign='justify';
            add(chap2,p2);
            
            fig1 = figure('visible','off');
            ax = axes(fig1,"FontSize",10);
            plot(cumsum(app.Datos_interfaz_2.Distancia),app.Datos_interfaz_2.Puntuacion_Ocupante_del_vehiculo,'color',[0.7 0.7 0.7],'LineWidth',1);
            xlabel("Distancia (m)");
            ylabel('Puntaje de clasificación por estrellas (SRS)');
            title("Puntaje de clasificación por estrellas (SRS) a lo largo de la carretera analizada");
            hold on
            plot(cumsum(Datos_suavizado.Distancia),Datos_suavizado.Puntuacion_Ocupante_del_vehiculo,'b','LineWidth',1)
            hold off
            add(chap2,Figure(fig1))
            
            p3=Paragraph('c) Ubicación de los SRS (datos sin suavización y suavizados) en la carretera analizada');
            p3.Underline='Single';
            p3.FontFamilyName='Gotham';
            add(chap2,p3);
            p3=Paragraph('En la siguiente gráfica se muestra el mapa del recorrido de la carretera analizada sin suavizar los datos.');
            p3.FontFamilyName='Gotham';
            p3.HAlign='justify';
            add(chap2,p3);
            
            if identificador==1
            fig = figure('visible','off');
            plot(x,y,'-r','LineWidth',1,...
                'MarkerEdgeColor','k',...
                'MarkerFaceColor','g',...
                'MarkerSize',10)
            xlabel("Longitud");
            ylabel('Latitud');
            title("Ubicación de los SRS sin suavización en la carretera analizada");
            hold on
            for i=1:c1 
                if Datos_interfaz_dos(i).Ocupante_del_vehiculo ==0    
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.85 0.85 0.85],'linewidth',2);
                elseif Datos_interfaz_dos(i).Ocupante_del_vehiculo ==1
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'k','linewidth',2);
                elseif Datos_interfaz_dos(i).Ocupante_del_vehiculo ==2
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'r','linewidth',2);
                elseif Datos_interfaz_dos(i).Ocupante_del_vehiculo ==3
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.9290, 0.6940, 0.1250],'linewidth',2);
                elseif Datos_interfaz_dos(i).Ocupante_del_vehiculo ==4
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'y','linewidth',2);
                elseif Datos_interfaz_dos(i).Ocupante_del_vehiculo ==5
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0, 0.5, 0],'linewidth',2);
                end
            end
            hold on 
            h = zeros(6, 1);
            h(1) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1), 'Color',[0.85 0.85 0.85],'visible', 'on');
            h(2) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'k','visible', 'on');
            h(3) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'r','visible', 'on');
            h(4) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1), 'Color',[0.9290, 0.6940, 0.1250],'visible', 'on');
            h(5) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'y','visible', 'on');
            h(6) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'Color',[0, 0.5, 0],'visible', 'on');
            hold off
            legend(h, 'No aplica','1 estrella','2 estrellas','3 estrellas','4 estrellas','5 estrellas','Location','northwest','FontSize',10);
            add(chap2,Figure(fig));
            
            elseif identificador==2
            fig = figure('visible','off');
            xlabel("Longitud");
            ylabel('Latitud');
            title("Ubicación de los SRS sin suavización en la carretera analizada");
            hold on
            for i=1:c1
                    if Datos_interfaz_dos(i).Ocupante_del_vehiculo ==0  
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0.85 0.85 0.85],'MarkerEdgeColor',[0.85 0.85 0.85]);
                        end
                    elseif Datos_interfaz_dos(i).Ocupante_del_vehiculo ==1
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','k','MarkerEdgeColor','k');
                        end
                    elseif Datos_interfaz_dos(i).Ocupante_del_vehiculo ==2
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','r','MarkerEdgeColor','r');
                        end
                    elseif Datos_interfaz_dos(i).Ocupante_del_vehiculo ==3
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0.9290, 0.6940, 0.1250],'MarkerEdgeColor',[0.9290, 0.6940, 0.1250]);
                        end
                    elseif Datos_interfaz_dos(i).Ocupante_del_vehiculo ==4
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','y','MarkerEdgeColor','y');
                        end
                    elseif Datos_interfaz_dos(i).Ocupante_del_vehiculo ==5
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0, 0.5, 0],'MarkerEdgeColor',[0, 0.5, 0]);
                        end
                    end
            end
            hold on 
            h = zeros(6, 1);
            h(1) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1), 'Color',[0.85 0.85 0.85],'visible', 'on');
            h(2) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'k','visible', 'on');
            h(3) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'r','visible', 'on');
            h(4) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1), 'Color',[0.9290, 0.6940, 0.1250],'visible', 'on');
            h(5) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'y','visible', 'on');
            h(6) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'Color',[0, 0.5, 0],'visible', 'on');
            hold off
            legend(h, 'No aplica','1 estrella','2 estrellas','3 estrellas','4 estrellas','5 estrellas','Location','northwest','FontSize',10);
            add(chap2,Figure(fig));
            end


            p3=Paragraph('En la siguiente gráfica se muestra el mapa del recorrido de la carretera analizada con los datos suavizados.');
            p3.FontFamilyName='Gotham';
            add(chap2,p3);
            
            if identificador==1
            fig = figure('visible','off');
            plot(x,y,'-r','LineWidth',1,...
                'MarkerEdgeColor','k',...
                'MarkerFaceColor','g',...
                'MarkerSize',10)
            xlabel("Longitud");
            ylabel('Latitud');
            title("Ubicación de los SRS suavizados en la carretera analizada");
            hold on
            for i=1:length(Datos_suavizado.Ocupante_del_vehiculo)
                    if Datos_suavizado.Ocupante_del_vehiculo(i)==0
                    plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.85 0.85 0.85],'linewidth',2);
                    elseif Datos_suavizado.Ocupante_del_vehiculo(i)==1
                    plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'k','linewidth',2);
                    elseif Datos_suavizado.Ocupante_del_vehiculo(i) ==2
                    plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'r','linewidth',2);
                    elseif Datos_suavizado.Ocupante_del_vehiculo(i) ==3
                    plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.9290, 0.6940, 0.1250],'linewidth',2);
                    elseif Datos_suavizado.Ocupante_del_vehiculo(i) ==4
                    plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'y','linewidth',2);
                    elseif Datos_suavizado.Ocupante_del_vehiculo(i) ==5
                    plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0, 0.5, 0],'linewidth',2);
                    end
            end
            hold on 
            h = zeros(6, 1);
            h(1) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1), 'Color',[0.85 0.85 0.85],'visible', 'on');
            h(2) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'k','visible', 'on');
            h(3) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'r','visible', 'on');
            h(4) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1), 'Color',[0.9290, 0.6940, 0.1250],'visible', 'on');
            h(5) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'y','visible', 'on');
            h(6) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'Color',[0, 0.5, 0],'visible', 'on');
            hold off
            legend(h, 'No aplica','1 estrella','2 estrellas','3 estrellas','4 estrellas','5 estrellas','Location','northwest','FontSize',10);
            add(chap2,Figure(fig));
            

            elseif identificador==2
            fig = figure('visible','off');
            xlabel("Longitud");
            ylabel('Latitud');
            title("Ubicación de los SRS suavizados en la carretera analizada");
            hold on 
            for i=1:length(Datos_suavizado.Ocupante_del_vehiculo)
                    if Datos_suavizado.Ocupante_del_vehiculo(i)==0
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0.85 0.85 0.85],'MarkerEdgeColor',[0.85 0.85 0.85]);
                        end
                    elseif Datos_suavizado.Ocupante_del_vehiculo(i)==1
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','k','MarkerEdgeColor','k');
                        end
                    elseif Datos_suavizado.Ocupante_del_vehiculo(i) ==2
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','r','MarkerEdgeColor','r');
                        end
                    elseif Datos_suavizado.Ocupante_del_vehiculo(i) ==3
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0.9290, 0.6940, 0.1250],'MarkerEdgeColor',[0.9290, 0.6940, 0.1250]);
                        end
                    elseif Datos_suavizado.Ocupante_del_vehiculo(i) ==4
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','y','MarkerEdgeColor','y');
                        end
                    elseif Datos_suavizado.Ocupante_del_vehiculo(i) ==5
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0, 0.5, 0],'MarkerEdgeColor',[0, 0.5, 0]);
                        end
                    end
            end 
            hold on 
            h = zeros(6, 1);
            h(1) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1), 'Color',[0.85 0.85 0.85],'visible', 'on');
            h(2) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'k','visible', 'on');
            h(3) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'r','visible', 'on');
            h(4) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1), 'Color',[0.9290, 0.6940, 0.1250],'visible', 'on');
            h(5) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'y','visible', 'on');
            h(6) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'Color',[0, 0.5, 0],'visible', 'on');
            hold off
            legend(h, 'No aplica','1 estrella','2 estrellas','3 estrellas','4 estrellas','5 estrellas','Location','northwest','FontSize',10);
            add(chap2,Figure(fig));  
            end


            %Motociclista
            p3=Paragraph('Motociclistas');
            p3.Bold=1;
            p3.FontFamilyName='Gotham';
            add(chap2,p3)
            p3=Paragraph('a) Resumen de la clasificación por estrellas (SR) de la carretera analizada');
            p3.Underline='Single';
            add(chap2,p3);
            p3=Paragraph('En la siguiente tabla se indica la clasificación por estrellas sin suavizar para los ');
            p3.FontFamilyName='Gotham';
            p3.HAlign='justify';
            tex3= Text('motociclistas.');
            tex3.Style = {Bold(true)};
            append(p3,tex3);
            add(chap2,p3);
            add(chap2,Paragraph(''));
            
            Nombres2=app.Tabla_estrellas_Motociclista(1:7,1);
            E2=cell2mat(app.Tabla_estrellas_Motociclista(1:7,2));
            F2=cell2mat(app.Tabla_estrellas_Motociclista(1:7,3));
            T2=table(categorical(Nombres2(:,1)),f(E2),f(F2));
            T2.Properties.VariableNames = {'Clasificación por estrellas', 'Longitud (km)', 'Porcentaje (%)'};
            Tablay=FormalTable(T2);
            Tablay.TableEntriesHAlign = 'center';
            Tablay.TableEntriesVAlign = 'middle';
            Tablay.Style = tableStyle;
            Tablay.Header.Children(1).Style =HeaderStyle;
            Tablay.Body.Style=BodyStyle;
            add(chap2,Tablay);
            add(chap2,Paragraph(''));
            
            p3=Paragraph('En la siguiente tabla se indica la clasificación por estrellas suavizada para los ');
            p3.FontFamilyName='Gotham';
            p3.HAlign='justify';
            tex3= Text('motociclistas.');
            tex3.Style = {Bold(true)};
            append(p3,tex3);
            add(chap2,p3);
            add(chap2,Paragraph(''))
            
            
            Nombres3=app.Tabla_Nuevaestrellas_Motociclista(1:7,1);
            E3=cell2mat(app.Tabla_Nuevaestrellas_Motociclista(1:7,2));
            F3=cell2mat(app.Tabla_Nuevaestrellas_Motociclista(1:7,3));
            T3=table(categorical(Nombres3(:,1)),f(E3),f(F3));
            T3.Properties.VariableNames = {'Clasificación por estrellas', 'Longitud (km)', 'Porcentaje (%)'};
            Tablay=FormalTable(T3);
            Tablay.TableEntriesHAlign = 'center';
            Tablay.Style = tableStyle;
            Tablay.Header.Children(1).Style =HeaderStyle;
            Tablay.Body.Style=BodyStyle;
            add(chap2,Tablay);
            add(chap2,Paragraph(''));
           
            p4=Paragraph('b) Puntaje de clasificación por estrellas (SRS) a lo largo de la carretera analizada ');
            p4.Underline='Single';
            p4.FontFamilyName='Gotham';
            add(chap2,p4);
            p4=Paragraph('A continuación, se muestra en la figura, los SRS (línea gris) de cada sección del tramo en análisis. Los SRS suavizados (línea azul) es el promedio de los SRS en distancias más largas que se realizan con el fin de producir resultados más significativos. En la suavización se realizan dos procedimientos, media móvil con un ancho de ventana de 9, y luego una suavización exponencial con un valor alfa de 0,1.');
            p4.FontFamilyName='Gotham';
            p4.HAlign='justify';
            add(chap2,p4);
            
            fig2 = figure('visible','off');
            ax = axes(fig2,"FontSize",10);
            plot(cumsum(app.Datos_interfaz_2.Distancia),app.Datos_interfaz_2.Puntuacion_Motociclista,'color',[0.7 0.7 0.7],'LineWidth',1,...
                'MarkerEdgeColor','k',...
                'MarkerFaceColor','g',...
                'MarkerSize',10)
            xlabel("Distancia (m)");
            ylabel('Puntaje de clasificación por estrellas (SRS)');
            title("Puntaje de clasificación por estrellas (SRS) a lo largo de la carretera analizada");
            hold on
            plot(cumsum(Datos_suavizado.Distancia),Datos_suavizado.Puntuacion_Motociclista,'b','LineWidth',1)
            hold off
            add(chap2,Figure(fig2))
            
            p5=Paragraph('c) Ubicación de los SRS (datos sin suavización y suavizados) en la carretera analizada');
            p5.Underline='Single';
            p5.FontFamilyName='Gotham';
            add(chap2,p5);
            p5=Paragraph('En la siguiente gráfica se muestra el mapa del recorrido de la carretera analizada sin suavizar los datos.');
            p5.FontFamilyName='Gotham';
            p5.HAlign='justify';
            add(chap2,p5);
            
            if identificador==1
            fig3 = figure('visible','off');
            plot(x,y,'-r','LineWidth',1,...
                'MarkerEdgeColor','k',...
                'MarkerFaceColor','g',...
                'MarkerSize',10)
            xlabel("Longitud");
            ylabel('Latitud');
            title("Ubicación de los SRS sin suavización en la carretera analizada");
            hold on
            for i=1:c1 
                if Datos_interfaz_dos(i).Motociclista ==0    
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.85 0.85 0.85],'linewidth',2);
                elseif Datos_interfaz_dos(i).Motociclista ==1 
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'k','linewidth',2);
                elseif Datos_interfaz_dos(i).Motociclista ==2
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'r','linewidth',2);
                elseif Datos_interfaz_dos(i).Motociclista ==3
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.9290, 0.6940, 0.1250],'linewidth',2);
                elseif Datos_interfaz_dos(i).Motociclista ==4
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'y','linewidth',2);
                elseif Datos_interfaz_dos(i).Motociclista ==5
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0, 0.5, 0],'linewidth',2);
                end
            end
            hold on 
            h = zeros(6, 1);
            h(1) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1), 'Color',[0.85 0.85 0.85],'visible', 'on');
            h(2) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'k','visible', 'on');
            h(3) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'r','visible', 'on');
            h(4) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1), 'Color',[0.9290, 0.6940, 0.1250],'visible', 'on');
            h(5) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'y','visible', 'on');
            h(6) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'Color',[0, 0.5, 0],'visible', 'on');
            hold off
            legend(h, 'No aplica','1 estrella','2 estrellas','3 estrellas','4 estrellas','5 estrellas','Location','northwest','FontSize',10);
            add(chap2,Figure(fig3));
            elseif identificador==2
            fig3 = figure('visible','off');
            xlabel("Longitud");
            ylabel('Latitud');
            title("Ubicación de los SRS sin suavización en la carretera analizada");
            hold on
            for i=1:c1
                    if Datos_interfaz_dos(i).Motociclista ==0   
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0.85 0.85 0.85],'MarkerEdgeColor',[0.85 0.85 0.85]);
                        end
                    elseif Datos_interfaz_dos(i).Motociclista ==1   
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','k','MarkerEdgeColor','k');
                        end
                    elseif Datos_interfaz_dos(i).Motociclista ==2
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','r','MarkerEdgeColor','r');
                        end
                    elseif Datos_interfaz_dos(i).Motociclista ==3
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0.9290, 0.6940, 0.1250],'MarkerEdgeColor',[0.9290, 0.6940, 0.1250]);
                        end
                    elseif Datos_interfaz_dos(i).Motociclista ==4
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','y','MarkerEdgeColor','y');
                        end
                    elseif Datos_interfaz_dos(i).Motociclista ==5
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0, 0.5, 0],'MarkerEdgeColor',[0, 0.5, 0]);
                        end
                    end
            end
            hold on 
            h = zeros(6, 1);
            h(1) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1), 'Color',[0.85 0.85 0.85],'visible', 'on');
            h(2) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'k','visible', 'on');
            h(3) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'r','visible', 'on');
            h(4) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1), 'Color',[0.9290, 0.6940, 0.1250],'visible', 'on');
            h(5) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'y','visible', 'on');
            h(6) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'Color',[0, 0.5, 0],'visible', 'on');
            hold off
            legend(h, 'No aplica','1 estrella','2 estrellas','3 estrellas','4 estrellas','5 estrellas','Location','northwest','FontSize',10);
            add(chap2,Figure(fig3));
            end
            
            p5=Paragraph('En la siguiente gráfica se muestra el mapa del recorrido de la carretera analizada con los datos suavizados.');
            p5.FontFamilyName='Gotham';
            add(chap2,p5);
            
            if identificador==1
            fig5 = figure('visible','off');
            plot(x,y,'-r','LineWidth',1,...
                'MarkerEdgeColor','k',...
                'MarkerFaceColor','g',...
                'MarkerSize',10)
            xlabel("Longitud");
            ylabel('Latitud');
            title("Ubicación de los SRS suavizados en la carretera analizada");
            hold on
            for i=1:length(Datos_suavizado.Motociclista)
                    if Datos_suavizado.Motociclista(i)==0
                    plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.85 0.85 0.85],'linewidth',2);
                    elseif Datos_suavizado.Motociclista(i)==1
                    plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'k','linewidth',2);
                    elseif Datos_suavizado.Motociclista(i)==2
                    plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'r','linewidth',2);
                    elseif Datos_suavizado.Motociclista(i)==3
                    plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.9290, 0.6940, 0.1250],'linewidth',2);
                    elseif Datos_suavizado.Motociclista(i)==4
                    plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'y','linewidth',2);
                    elseif Datos_suavizado.Motociclista(i)==5
                    plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0, 0.5, 0],'linewidth',2);
                    end
            end
            hold on 
            h = zeros(6, 1);
            h(1) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1), 'Color',[0.85 0.85 0.85],'visible', 'on');
            h(2) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'k','visible', 'on');
            h(3) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'r','visible', 'on');
            h(4) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1), 'Color',[0.9290, 0.6940, 0.1250],'visible', 'on');
            h(5) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'y','visible', 'on');
            h(6) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'Color',[0, 0.5, 0],'visible', 'on');
            hold off
            legend(h, 'No aplica','1 estrella','2 estrellas','3 estrellas','4 estrellas','5 estrellas','Location','northwest','FontSize',10);
            add(chap2,Figure(fig5));
            elseif identificador==2
            fig5 = figure('visible','off');
            xlabel("Longitud");
            ylabel('Latitud');
            title("Ubicación de los SRS suavizados en la carretera analizada");
            hold on
            for i=1:length(Datos_suavizado.Motociclista)
                    if Datos_suavizado.Motociclista(i)==0
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0.85 0.85 0.85],'MarkerEdgeColor',[0.85 0.85 0.85]);
                        end
                    elseif Datos_suavizado.Motociclista(i)==1
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','k','MarkerEdgeColor','k');
                        end
                    elseif Datos_suavizado.Motociclista(i) ==2
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','r','MarkerEdgeColor','r');
                        end
                    elseif Datos_suavizado.Motociclista(i) ==3
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0.9290, 0.6940, 0.1250],'MarkerEdgeColor',[0.9290, 0.6940, 0.1250]);
                        end
                    elseif Datos_suavizado.Motociclista(i) ==4
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','y','MarkerEdgeColor','y');
                        end
                    elseif Datos_suavizado.Motociclista(i) ==5
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0, 0.5, 0],'MarkerEdgeColor',[0, 0.5, 0]);
                        end
                    end
            end
            hold on 
            h = zeros(6, 1);
            h(1) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1), 'Color',[0.85 0.85 0.85],'visible', 'on');
            h(2) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'k','visible', 'on');
            h(3) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'r','visible', 'on');
            h(4) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1), 'Color',[0.9290, 0.6940, 0.1250],'visible', 'on');
            h(5) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'y','visible', 'on');
            h(6) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'Color',[0, 0.5, 0],'visible', 'on');
            hold off
            legend(h, 'No aplica','1 estrella','2 estrellas','3 estrellas','4 estrellas','5 estrellas','Location','northwest','FontSize',10);
            add(chap2,Figure(fig5));
            end

            
            %Ciclista
            p6=Paragraph('Ciclistas');
            p6.Bold=1;
            p6.FontFamilyName='Gotham';
            add(chap2,p6)
            p6=Paragraph('a) Resumen de la clasificación por estrellas (SR) de la carretera analizada');
            p6.Underline='Single';
            add(chap2,p6);
            p6=Paragraph('En la siguiente tabla se indica la clasificación por estrellas sin suavizar para los ');
            p6.FontFamilyName='Gotham';
            p6.HAlign='justify';
            tex6= Text('ciclistas.');
            tex6.Style = {Bold(true)};
            append(p6,tex6);
            add(chap2,p6);
            add(chap2,Paragraph(''));
            
            Nombres4=app.Tabla_estrellas_Cilista(1:7,1);
            E4=cell2mat(app.Tabla_estrellas_Cilista(1:7,2));
            F4=cell2mat(app.Tabla_estrellas_Cilista(1:7,3));
            T4=table(categorical(Nombres4(:,1)),f(E4),f(F4));
            T4.Properties.VariableNames = {'Clasificación por estrellas', 'Longitud (km)', 'Porcentaje (%)'};
            Tablaz=FormalTable(T4);
            Tablaz.TableEntriesHAlign = 'center';
            Tablaz.Style = tableStyle;
            Tablaz.Header.Children(1).Style =HeaderStyle;
            Tablaz.Body.Style=BodyStyle;
            add(chap2,Tablaz);
            add(chap2,Paragraph(''));
            
            p6=Paragraph('En la siguiente tabla se indica la clasificación por estrellas suavizada para los ');
            p6.FontFamilyName='Gotham';
            p6.HAlign='justify';
            tex6= Text('ciclistas.');
            tex6.Style = {Bold(true)};
            append(p6,tex6);
            add(chap2,p6);
            add(chap2,Paragraph(''))
            
            Nombres5=app.Tabla_Nuevaestrellas_Cilista(1:7,1);
            E5=cell2mat(app.Tabla_Nuevaestrellas_Cilista(1:7,2));
            F5=cell2mat(app.Tabla_Nuevaestrellas_Cilista(1:7,3));
            T5=table(categorical(Nombres5(:,1)),f(E5),f(F5));
            T5.Properties.VariableNames = {'Clasificación por estrellas', 'Longitud (km)', 'Porcentaje (%)'};
            Tablaz=FormalTable(T5);
            Tablaz.TableEntriesHAlign = 'center';
            Tablaz.Style = tableStyle;
            Tablaz.Header.Children(1).Style =HeaderStyle;
            Tablaz.Body.Style=BodyStyle;
            add(chap2,Tablaz);
            add(chap2,Paragraph(''));
            
            p7=Paragraph('b) Puntaje de clasificación por estrellas (SRS) a lo largo de la carretera analizada ');
            p7.Underline='Single';
            p7.FontFamilyName='Gotham';
            add(chap2,p7);
            p7=Paragraph('A continuación, se muestra en la figura, los SRS (línea gris) de cada sección del tramo en análisis. Los SRS suavizados (línea azul) es el promedio de los SRS en distancias más largas que se realizan con el fin de producir resultados más significativos. En la suavización se realizan dos procedimientos, media móvil con un ancho de ventana de 9, y luego una suavización exponencial con un valor alfa de 0,1.');
            p7.FontFamilyName='Gotham';
            p7.HAlign='justify';
            add(chap2,p7);
           
            fig4 = figure('visible','off');
            ax = axes(fig4,"FontSize",10);
            plot(cumsum(app.Datos_interfaz_2.Distancia),app.Datos_interfaz_2.Puntuacion_Ciclista,'color',[0.7 0.7 0.7],'LineWidth',1,...
                'MarkerEdgeColor','k',...
                'MarkerFaceColor','g',...
                'MarkerSize',10)
            xlabel("Distancia (m)");
            ylabel('Puntaje de clasificación por estrellas (SRS)');
            title("Puntaje de clasificación por estrellas (SRS) a lo largo de la carretera analizada");
            hold on
            plot(cumsum(Datos_suavizado.Distancia),Datos_suavizado.Puntuacion_Ciclista,'b','LineWidth',1)
            hold off
            add(chap2,Figure(fig4))
            
            p8=Paragraph('c) Ubicación de los SRS (datos sin suavización y suavizados) en la carretera analizada');
            p8.Underline='Single';
            p8.FontFamilyName='Gotham';
            add(chap2,p8);
            p8=Paragraph('En la siguiente gráfica se muestra el mapa del recorrido de la carretera analizada sin suavizar los datos.');
            p8.FontFamilyName='Gotham';
            p8.HAlign='justify';
            add(chap2,p8);
            
            if identificador==1
            fig5 = figure('visible','off');
            plot(x,y,'-r','LineWidth',1,...
                'MarkerEdgeColor','k',...
                'MarkerFaceColor','g',...
                'MarkerSize',10)
            xlabel("Longitud");
            ylabel('Latitud');
            title("Ubicación de los SRS sin suavización en la carretera analizada");
            hold on
            for i=1:c1 
                if Datos_interfaz_dos(i).Ciclista ==0    
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.85 0.85 0.85],'linewidth',2);
                elseif Datos_interfaz_dos(i).Ciclista ==1 
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'k','linewidth',2);
                elseif Datos_interfaz_dos(i).Ciclista ==2
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'r','linewidth',2);
                elseif Datos_interfaz_dos(i).Ciclista ==3
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.9290, 0.6940, 0.1250],'linewidth',2);
                elseif Datos_interfaz_dos(i).Ciclista ==4
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'y','linewidth',2);
                elseif Datos_interfaz_dos(i).Ciclista ==5
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0, 0.5, 0],'linewidth',2);
                end
            end
            hold on 
            h = zeros(6, 1);
            h(1) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1), 'Color',[0.85 0.85 0.85],'visible', 'on');
            h(2) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'k','visible', 'on');
            h(3) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'r','visible', 'on');
            h(4) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1), 'Color',[0.9290, 0.6940, 0.1250],'visible', 'on');
            h(5) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'y','visible', 'on');
            h(6) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'Color',[0, 0.5, 0],'visible', 'on');
            hold off
            legend(h, 'No aplica','1 estrella','2 estrellas','3 estrellas','4 estrellas','5 estrellas','Location','northwest','FontSize',10);
            add(chap2,Figure(fig5));
            elseif identificador==2
            fig5 = figure('visible','off');
            xlabel("Longitud");
            ylabel('Latitud');
            title("Ubicación de los SRS sin suavización en la carretera analizada");
            hold on
            for i=1:c1
                    if Datos_interfaz_dos(i).Ciclista ==0  
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0.85 0.85 0.85],'MarkerEdgeColor',[0.85 0.85 0.85]);
                        end
                    elseif Datos_interfaz_dos(i).Ciclista ==1    
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','k','MarkerEdgeColor','k');
                        end
                    elseif Datos_interfaz_dos(i).Ciclista ==2
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','r','MarkerEdgeColor','r');
                        end
                    elseif Datos_interfaz_dos(i).Ciclista ==3
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0.9290, 0.6940, 0.1250],'MarkerEdgeColor',[0.9290, 0.6940, 0.1250]);
                        end
                    elseif Datos_interfaz_dos(i).Ciclista ==4
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','y','MarkerEdgeColor','y');
                        end
                    elseif Datos_interfaz_dos(i).Ciclista ==5
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0, 0.5, 0],'MarkerEdgeColor',[0, 0.5, 0]);
                        end
                    end
            end
            hold on 
            h = zeros(6, 1);
            h(1) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1), 'Color',[0.85 0.85 0.85],'visible', 'on');
            h(2) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'k','visible', 'on');
            h(3) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'r','visible', 'on');
            h(4) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1), 'Color',[0.9290, 0.6940, 0.1250],'visible', 'on');
            h(5) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'y','visible', 'on');
            h(6) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'Color',[0, 0.5, 0],'visible', 'on');
            hold off
            legend(h, 'No aplica','1 estrella','2 estrellas','3 estrellas','4 estrellas','5 estrellas','Location','northwest','FontSize',10);
            add(chap2,Figure(fig5));
            
            end
            
            p8=Paragraph('En la siguiente gráfica se muestra el mapa del recorrido de la carretera analizada con los datos suavizados.');
            p8.FontFamilyName='Gotham';
            add(chap2,p8);
            
            if identificador==1
            fig5 = figure('visible','off');
            plot(x,y,'-r','LineWidth',1,...
                'MarkerEdgeColor','k',...
                'MarkerFaceColor','g',...
                'MarkerSize',10)
            xlabel("Longitud");
            ylabel('Latitud');
            title("Ubicación de los SRS suavizados en la carretera analizada");
            hold on
            for i=1:length(Datos_suavizado.Ciclista)
                    if Datos_suavizado.Ciclista(i)==0
                    plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.85 0.85 0.85],'linewidth',2);
                    elseif Datos_suavizado.Ciclista(i)==1
                    plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'k','linewidth',2);
                    elseif Datos_suavizado.Ciclista(i)==2
                    plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'r','linewidth',2);
                    elseif Datos_suavizado.Ciclista(i)==3
                    plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.9290, 0.6940, 0.1250],'linewidth',2);
                    elseif Datos_suavizado.Ciclista(i)==4
                    plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'y','linewidth',2);
                    elseif Datos_suavizado.Ciclista(i)==5
                    plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0, 0.5, 0],'linewidth',2);
                    end
            end
            hold on 
            h = zeros(6, 1);
            h(1) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1), 'Color',[0.85 0.85 0.85],'visible', 'on');
            h(2) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'k','visible', 'on');
            h(3) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'r','visible', 'on');
            h(4) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1), 'Color',[0.9290, 0.6940, 0.1250],'visible', 'on');
            h(5) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'y','visible', 'on');
            h(6) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'Color',[0, 0.5, 0],'visible', 'on');
            hold off
            legend(h, 'No aplica','1 estrella','2 estrellas','3 estrellas','4 estrellas','5 estrellas','Location','northwest','FontSize',10);
            add(chap2,Figure(fig5));
            elseif identificador==2
            fig5 = figure('visible','off');
            xlabel("Longitud");
            ylabel('Latitud');
            title("Ubicación de los SRS suavizados en la carretera analizada");
            hold on
            for i=1:length(Datos_suavizado.Ciclista)
                    if Datos_suavizado.Ciclista(i)==0
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0.85 0.85 0.85],'MarkerEdgeColor',[0.85 0.85 0.85]);
                        end
                    elseif Datos_suavizado.Ciclista(i)==1
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','k','MarkerEdgeColor','k');
                        end
                    elseif Datos_suavizado.Ciclista(i) ==2
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','r','MarkerEdgeColor','r');
                        end
                    elseif Datos_suavizado.Ciclista(i) ==3
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0.9290, 0.6940, 0.1250],'MarkerEdgeColor',[0.9290, 0.6940, 0.1250]);
                        end
                    elseif Datos_suavizado.Ciclista(i) ==4
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','y','MarkerEdgeColor','y');
                        end
                    elseif Datos_suavizado.Ciclista(i) ==5
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0, 0.5, 0],'MarkerEdgeColor',[0, 0.5, 0]);
                        end
                    end
            end 
            hold on 
            h = zeros(6, 1);
            h(1) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1), 'Color',[0.85 0.85 0.85],'visible', 'on');
            h(2) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'k','visible', 'on');
            h(3) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'r','visible', 'on');
            h(4) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1), 'Color',[0.9290, 0.6940, 0.1250],'visible', 'on');
            h(5) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'y','visible', 'on');
            h(6) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'Color',[0, 0.5, 0],'visible', 'on');
            hold off
            legend(h, 'No aplica','1 estrella','2 estrellas','3 estrellas','4 estrellas','5 estrellas','Location','northwest','FontSize',10);
            add(chap2,Figure(fig5));
            end

            
            %Peatón
            p9=Paragraph('Peatones');
            p9.Bold=1;
            p9.FontFamilyName='Gotham';
            add(chap2,p9)
            
            p9=Paragraph('a) Resumen de la clasificación por estrellas (SR) de la carretera analizada');
            p9.Underline='Single';
            add(chap2,p9);
            p9=Paragraph('En la siguiente tabla se indica la clasificación por estrellas sin suavizar para los ');
            p9.FontFamilyName='Gotham';
            p9.HAlign='justify';
            tex9= Text('peatones.');
            tex9.Style = {Bold(true)};
            append(p9,tex9);
            add(chap2,p9);
            add(chap2,Paragraph(''));
            
            Nombres6=app.Tabla_estrellas_Peaton(1:7,1);
            E6=cell2mat(app.Tabla_estrellas_Peaton(1:7,2));
            F6=cell2mat(app.Tabla_estrellas_Peaton(1:7,3));
            T6=table(categorical(Nombres6(:,1)),f(E6),f(F6));
            T6.Properties.VariableNames = {'Clasificación por estrellas', 'Longitud (km)', 'Porcentaje (%)'};
            Tablaa=FormalTable(T6);
            Tablaa.TableEntriesHAlign = 'center';
            Tablaa.Style = tableStyle;
            Tablaa.Header.Children(1).Style =HeaderStyle;
            Tablaa.Body.Style=BodyStyle;
            add(chap2,Tablaa);
            add(chap2,Paragraph(''));
            
            
            p9=Paragraph('En la siguiente tabla se indica la clasificación por estrellas suavizada para los ');
            p9.FontFamilyName='Gotham';
            p9.HAlign='justify';
            tex9= Text('peatones.');
            tex9.Style = {Bold(true)};
            append(p9,tex9);
            add(chap2,p9);
            add(chap2,Paragraph(''))
            
            Nombres7=app.Tabla_Nuevaestrellas_Peaton(1:7,1);
            E7=cell2mat(app.Tabla_Nuevaestrellas_Peaton(1:7,2));
            F7=cell2mat(app.Tabla_Nuevaestrellas_Peaton(1:7,3));
            T7=table(categorical(Nombres7(:,1)),f(E7),f(F7));
            T7.Properties.VariableNames = {'Clasificación por estrellas', 'Longitud (km)', 'Porcentaje (%)'};
            Tablaa=FormalTable(T7);
            Tablaa.TableEntriesHAlign = 'center';
            Tablaa.Style = tableStyle;
            Tablaa.Header.Children(1).Style =HeaderStyle;
            Tablaa.Body.Style=BodyStyle;
            add(chap2,Tablaa);
            add(chap2,Paragraph(''));
            
            p10=Paragraph('b) Puntaje de clasificación por estrellas (SRS) a lo largo de la carretera analizada ');
            p10.Underline='Single';
            p10.FontFamilyName='Gotham';
            add(chap2,p10);
            p10=Paragraph('A continuación, se muestra en la figura, los SRS (línea gris) de cada sección del tramo en análisis. Los SRS suavizados (línea azul) es el promedio de los SRS en distancias más largas que se realizan con el fin de producir resultados más significativos. En la suavización se realizan dos procedimientos, media móvil con un ancho de ventana de 9, y luego una suavización exponencial con un valor alfa de 0,1.');
            p10.FontFamilyName='Gotham';
            p10.HAlign='justify';
            add(chap2,p10);
           
            fig6 = figure('visible','off');
            ax = axes(fig6,"FontSize",10);
            plot(cumsum(app.Datos_interfaz_2.Distancia),app.Datos_interfaz_2.Puntuacion_Peaton,'color',[0.7 0.7 0.7],'LineWidth',1,...
                'MarkerEdgeColor','k',...
                'MarkerFaceColor','g',...
                'MarkerSize',10)
            xlabel("Distancia (m)");
            ylabel('Puntaje de clasificación por estrellas (SRS)');
            title("Puntaje de clasificación por estrellas (SRS) a lo largo de la carretera analizada");
            hold on
            plot(cumsum(Datos_suavizado.Distancia),Datos_suavizado.Puntuacion_Peaton,'b','LineWidth',1)
            hold off
            add(chap2,Figure(fig6))
            
            p11=Paragraph('c) Ubicación de los SRS (datos sin suavización y suavizados) en la carretera analizada');
            p11.Underline='Single';
            p11.FontFamilyName='Gotham';
            add(chap2,p11);
            p11=Paragraph('En la siguiente gráfica se muestra el mapa del recorrido de la carretera analizada sin suavizar los datos.');
            p11.FontFamilyName='Gotham';
            add(chap2,p11);
            
            if identificador==1
            fig7 = figure('visible','off');
            plot(x,y,'-r','LineWidth',1,...
                'MarkerEdgeColor','k',...
                'MarkerFaceColor','g',...
                'MarkerSize',10)
            xlabel("Longitud");
            ylabel('Latitud');
            title("Ubicación de los SRS sin suavización en la carretera analizada");
            hold on
            for i=1:c1 
                if Datos_interfaz_dos(i).Peaton ==0    
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.85 0.85 0.85],'linewidth',2);
                elseif Datos_interfaz_dos(i).Peaton ==1 
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'k','linewidth',2);
                elseif Datos_interfaz_dos(i).Peaton ==2
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'r','linewidth',2);
                elseif Datos_interfaz_dos(i).Peaton ==3
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.9290, 0.6940, 0.1250],'linewidth',2);
                elseif Datos_interfaz_dos(i).Peaton ==4
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'y','linewidth',2);
                elseif Datos_interfaz_dos(i).Peaton ==5
                plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0, 0.5, 0],'linewidth',2);
                end
            end
            hold on 
            h = zeros(6, 1);
            h(1) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1), 'Color',[0.85 0.85 0.85],'visible', 'on');
            h(2) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'k','visible', 'on');
            h(3) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'r','visible', 'on');
            h(4) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1), 'Color',[0.9290, 0.6940, 0.1250],'visible', 'on');
            h(5) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'y','visible', 'on');
            h(6) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'Color',[0, 0.5, 0],'visible', 'on');
            hold off
            legend(h, 'No aplica','1 estrella','2 estrellas','3 estrellas','4 estrellas','5 estrellas','Location','northwest','FontSize',10);
            add(chap2,Figure(fig7));
            elseif identificador==2
            fig7 = figure('visible','off');
            xlabel("Longitud");
            ylabel('Latitud');
            title("Ubicación de los SRS sin suavización en la carretera analizada");
            hold on
            for i=1:c1
                    if Datos_interfaz_dos(i).Peaton ==0   
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0.85 0.85 0.85],'MarkerEdgeColor',[0.85 0.85 0.85]);
                        end
                    elseif Datos_interfaz_dos(i).Peaton ==1 
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','k','MarkerEdgeColor','k');
                        end
                    elseif Datos_interfaz_dos(i).Peaton ==2
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','r','MarkerEdgeColor','r');
                        end
                    elseif Datos_interfaz_dos(i).Peaton ==3
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0.9290, 0.6940, 0.1250],'MarkerEdgeColor',[0.9290, 0.6940, 0.1250]);
                        end
                    elseif Datos_interfaz_dos(i).Peaton ==4
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','y','MarkerEdgeColor','y');
                        end
                    elseif Datos_interfaz_dos(i).Peaton ==5
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0, 0.5, 0],'MarkerEdgeColor',[0, 0.5, 0]);
                        end
                    end
            end 
            hold on 
            h = zeros(6, 1);
            h(1) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1), 'Color',[0.85 0.85 0.85],'visible', 'on');
            h(2) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'k','visible', 'on');
            h(3) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'r','visible', 'on');
            h(4) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1), 'Color',[0.9290, 0.6940, 0.1250],'visible', 'on');
            h(5) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'y','visible', 'on');
            h(6) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'Color',[0, 0.5, 0],'visible', 'on');
            hold off
            legend(h, 'No aplica','1 estrella','2 estrellas','3 estrellas','4 estrellas','5 estrellas','Location','northwest','FontSize',10);
            add(chap2,Figure(fig7));
            end

            
            p11=Paragraph('En la siguiente gráfica se muestra el mapa del recorrido de la carretera analizada con los datos suavizados.');
            p11.FontFamilyName='Gotham';
            add(chap2,p11);
            if identificador==1
            fig15 = figure('visible','off');
            plot(x,y,'-r','LineWidth',1,...
                'MarkerEdgeColor','k',...
                'MarkerFaceColor','g',...
                'MarkerSize',10)
            xlabel("Longitud");
            ylabel('Latitud');
            title("Ubicación de los SRS suavizados en la carretera analizada");
            hold on
            for i=1:length(Datos_suavizado.Peaton)
                    if Datos_suavizado.Peaton(i)==0
                    plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.85 0.85 0.85],'linewidth',2);
                    elseif Datos_suavizado.Peaton(i)==1
                    plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'k','linewidth',2);
                    elseif Datos_suavizado.Peaton(i)==2
                    plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'r','linewidth',2);
                    elseif Datos_suavizado.Peaton(i)==3
                    plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0.9290, 0.6940, 0.1250],'linewidth',2);
                    elseif Datos_suavizado.Peaton(i)==4
                    plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'y','linewidth',2);
                    elseif Datos_suavizado.Peaton(i)==5
                    plot(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'Color',[0, 0.5, 0],'linewidth',2);
                    end
            end
            hold on 
            h = zeros(6, 1);
            h(1) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1), 'Color',[0.85 0.85 0.85],'visible', 'on');
            h(2) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'k','visible', 'on');
            h(3) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'r','visible', 'on');
            h(4) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1), 'Color',[0.9290, 0.6940, 0.1250],'visible', 'on');
            h(5) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'y','visible', 'on');
            h(6) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'Color',[0, 0.5, 0],'visible', 'on');
            hold off
            legend(h, 'No aplica','1 estrella','2 estrellas','3 estrellas','4 estrellas','5 estrellas','Location','northwest','FontSize',10);
            add(chap2,Figure(fig15));
            elseif identificador==2
            fig15 = figure('visible','off');
            xlabel("Longitud");
            ylabel('Latitud');
            title("Ubicación de los SRS suavizados en la carretera analizada");
            hold on
            for i=1:length(Datos_suavizado.Peaton)
                    if Datos_suavizado.Peaton(i)==0
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0.85 0.85 0.85],'MarkerEdgeColor',[0.85 0.85 0.85]);
                        end
                    elseif Datos_suavizado.Peaton(i)==1
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','k','MarkerEdgeColor','k');
                        end
                    elseif Datos_suavizado.Peaton(i) ==2
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','r','MarkerEdgeColor','r');
                        end
                    elseif Datos_suavizado.Peaton(i) ==3
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0.9290, 0.6940, 0.1250],'MarkerEdgeColor',[0.9290, 0.6940, 0.1250]);
                        end
                    elseif Datos_suavizado.Peaton(i) ==4
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor','y','MarkerEdgeColor','y');
                        end
                    elseif Datos_suavizado.Peaton(i) ==5
                        if Datos_interfaz_dos(i).Xt~=0 && Datos_interfaz_dos(i).yt~=0
                        scatter(Datos_interfaz_dos(i).Xt,Datos_interfaz_dos(i).yt,'MarkerFaceColor',[0, 0.5, 0],'MarkerEdgeColor',[0, 0.5, 0]);
                        end
                    end
            end
            hold on 
            h = zeros(6, 1);
            h(1) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1), 'Color',[0.85 0.85 0.85],'visible', 'on');
            h(2) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'k','visible', 'on');
            h(3) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'r','visible', 'on');
            h(4) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1), 'Color',[0.9290, 0.6940, 0.1250],'visible', 'on');
            h(5) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'color', 'y','visible', 'on');
            h(6) = plot(Datos_interfaz_dos(1).Xt(1),Datos_interfaz_dos(1).yt(1),'Color',[0, 0.5, 0],'visible', 'on');
            hold off
            legend(h, 'No aplica','1 estrella','2 estrellas','3 estrellas','4 estrellas','5 estrellas','Location','northwest','FontSize',10);
            add(chap2,Figure(fig15));
            end
            
            add(rpt,chap2);
            

            close(rpt);
            rptview(rpt);
        end

        % Value changed function: TramosguardadosSpinner
        function TramosguardadosSpinnerValueChanged(app, event)
            B=app.Datos_interfaz_2.Tramos;
            app.TramosguardadosSpinner.Limits=[1 length(B)];
            app.b1 = app.TramosguardadosSpinner.Value;
            buscar(app);
            buscar_1(app)
            
           
        end

        % Value changed function: SuavizadoCheckBox
        function SuavizadoCheckBoxValueChanged(app, event)
            UsuariosvialesButtonGroupSelectionChanged(app)


        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
         load('../mat/Datos_atributos_proceso.mat')
         load('../mat/Datos_interfaz_dos_proceso.mat');
         load('../mat/Matriz_resumen_contramedidas_postproceso.mat')
            
         
         app.b1; %Este es el valor que se asumio que eligue el datos del spinner
         app.Matriz=Atributo_elegido(app.b1).Atributo;
         
         %Valores para la tabla resumen de las contramedidas
         Tramos=[Datos_interfaz_dos(app.b1).Tramos];
         Distancia=[Datos_interfaz_dos(app.b1).Distancia];
            
         Datos_Tcontramedidas(app.b1).Tramos=Tramos;
         Datos_Tcontramedidas(app.b1).Distancia=Distancia;
         
         %
         Contramedida1=cell(118,1);
         Categoria1=cell(118,1);
         Identificador1=zeros(118,1);
         
         %app.Matriz2={}; %Ojo se  debe eliminar
         app.Matriz2=cell(1,94);
         app.Matriz2(1,:) = {'No aplicada'};
         app.Matriz3=zeros(1,53);
         app.Matriz3(38)=Atributo_elegido(app.b1).Atributo(38);
         app.Matriz3(48)=Atributo_elegido(app.b1).Atributo(48);
         app.Matriz3(25)=Atributo_elegido(app.b1).Atributo(25);
         app.Matriz3(29)=Atributo_elegido(app.b1).Atributo(29);
         app.O_V_SalidaCaminoconductor=zeros(1,12);
         app.O_V_SalidaCaminocopiloto=zeros(1,12);
         app.O_V_PerdidaControl=zeros(1,10);
         app.O_V_Adelantamiento=zeros(1,6);
         app.O_V_Intersecciones=zeros(1,10);
         if Atributo_elegido(app.b1).Atributo(25)==1
             app.O_V_Intersecciones(1)=6;
             app.O_V_Intersecciones(9)=15;
         elseif Atributo_elegido(app.b1).Atributo(25)==2
             app.O_V_Intersecciones(1)=15;
             app.O_V_Intersecciones(9)=15;
         elseif Atributo_elegido(app.b1).Atributo(25)==3
             app.O_V_Intersecciones(1)=13;
             app.O_V_Intersecciones(9)=45;
         elseif Atributo_elegido(app.b1).Atributo(25)==4
             app.O_V_Intersecciones(1)=16;
             app.O_V_Intersecciones(9)=45;
         elseif Atributo_elegido(app.b1).Atributo(25)==5
             app.O_V_Intersecciones(1)=9;
             app.O_V_Intersecciones(9)=45;
         elseif Atributo_elegido(app.b1).Atributo(25)==6
             app.O_V_Intersecciones(1)=12;
             app.O_V_Intersecciones(9)=45;
         elseif Atributo_elegido(app.b1).Atributo(25)==7
             app.O_V_Intersecciones(1)=16;
             app.O_V_Intersecciones(9)=50;
         elseif Atributo_elegido(app.b1).Atributo(25)==8
             app.O_V_Intersecciones(1)=23;
             app.O_V_Intersecciones(9)=50;
         elseif Atributo_elegido(app.b1).Atributo(25)==9
             app.O_V_Intersecciones(1)=10;
             app.O_V_Intersecciones(9)=50;
         elseif Atributo_elegido(app.b1).Atributo(25)==10
             app.O_V_Intersecciones(1)=15;
             app.O_V_Intersecciones(9)=50;
         elseif Atributo_elegido(app.b1).Atributo(25)==11
             app.O_V_Intersecciones(1)=0;
             app.O_V_Intersecciones(9)=0;
         elseif Atributo_elegido(app.b1).Atributo(25)==12
             app.O_V_Intersecciones(1)=1;
             app.O_V_Intersecciones(9)=150;
         elseif Atributo_elegido(app.b1).Atributo(25)==13
             app.O_V_Intersecciones(1)=0.5;
             app.O_V_Intersecciones(9)=150;
         elseif Atributo_elegido(app.b1).Atributo(25)==14
             app.O_V_Intersecciones(1)=0.5;
             app.O_V_Intersecciones(9)=45;
         elseif Atributo_elegido(app.b1).Atributo(25)==15
             app.O_V_Intersecciones(1)=0.3;
             app.O_V_Intersecciones(9)=45;
         else Atributo_elegido(app.b1).Atributo(25)==16
             app.O_V_Intersecciones(1)=16;
             app.O_V_Intersecciones(9)=35;
         end
             
         app.O_V_Accpropiedad=zeros(1,5);
         if Atributo_elegido(app.b1).Atributo(29)==1
             app.O_V_Accpropiedad(1)=2;
             app.O_V_Accpropiedad(4)=50;
         elseif Atributo_elegido(app.b1).Atributo(29)==2
             app.O_V_Accpropiedad(1)=1.3;
             app.O_V_Accpropiedad(4)=50;
         elseif Atributo_elegido(app.b1).Atributo(29)==3
             app.O_V_Accpropiedad(1)=1.1;
             app.O_V_Accpropiedad(4)=50;
         else Atributo_elegido(app.b1).Atributo(29)==4
             app.O_V_Accpropiedad(1)=1;
             app.O_V_Accpropiedad(4)=0;
         end
         
         app.Moto_SalidaCaminoconductor=zeros(1,12);
         app.Moto_SalidaCaminocopiloto=zeros(1,12);
         app.Moto_PerdidaControl=zeros(1,10);
         app.Moto_Adelantamiento=zeros(1,6);
         app.Moto_Intersecciones=zeros(1,10);
         if Atributo_elegido(app.b1).Atributo(25)==1
             app.Moto_Intersecciones(1)=6;
             app.Moto_Intersecciones(9)=20;
         elseif Atributo_elegido(app.b1).Atributo(25)==2
             app.Moto_Intersecciones(1)=30;
             app.Moto_Intersecciones(9)=30;
         elseif Atributo_elegido(app.b1).Atributo(25)==3
             app.Moto_Intersecciones(1)=17;
             app.Moto_Intersecciones(9)=45;
         elseif Atributo_elegido(app.b1).Atributo(25)==4
             app.Moto_Intersecciones(1)=20;
             app.Moto_Intersecciones(9)=45;
         elseif Atributo_elegido(app.b1).Atributo(25)==5
             app.Moto_Intersecciones(1)=9;
             app.Moto_Intersecciones(9)=45;
         elseif Atributo_elegido(app.b1).Atributo(25)==6
             app.Moto_Intersecciones(1)=14;
             app.Moto_Intersecciones(9)=45;
         elseif Atributo_elegido(app.b1).Atributo(25)==7
             app.Moto_Intersecciones(1)=16;
             app.Moto_Intersecciones(9)=50;
         elseif Atributo_elegido(app.b1).Atributo(25)==8
             app.Moto_Intersecciones(1)=26;
             app.Moto_Intersecciones(9)=50;
         elseif Atributo_elegido(app.b1).Atributo(25)==9
             app.Moto_Intersecciones(1)=10;
             app.Moto_Intersecciones(9)=50;
         elseif Atributo_elegido(app.b1).Atributo(25)==10
             app.Moto_Intersecciones(1)=16;
             app.Moto_Intersecciones(9)=50;
         elseif Atributo_elegido(app.b1).Atributo(25)==11
             app.Moto_Intersecciones(1)=0;
             app.Moto_Intersecciones(9)=0;
         elseif Atributo_elegido(app.b1).Atributo(25)==12
             app.Moto_Intersecciones(1)=1;
             app.Moto_Intersecciones(9)=150;
         elseif Atributo_elegido(app.b1).Atributo(25)==13
             app.Moto_Intersecciones(1)=0.5;
             app.Moto_Intersecciones(9)=150;
         elseif Atributo_elegido(app.b1).Atributo(25)==14
             app.Moto_Intersecciones(1)=0.5;
             app.Moto_Intersecciones(9)=45;
         elseif Atributo_elegido(app.b1).Atributo(25)==15
             app.Moto_Intersecciones(1)=0.3;
             app.Moto_Intersecciones(9)=45;
         else Atributo_elegido(app.b1).Atributo(25)==16
             app.Moto_Intersecciones(1)=16;
             app.Moto_Intersecciones(9)=35;
         end
         
         app.Moto_Accpropiedad=zeros(1,4);
         if Atributo_elegido(app.b1).Atributo(29)==1
             app.Moto_Accpropiedad(1)=2;
             app.Moto_Accpropiedad(3)=50;
         elseif Atributo_elegido(app.b1).Atributo(29)==2
             app.Moto_Accpropiedad(1)=1.3;
             app.Moto_Accpropiedad(3)=50;
         elseif Atributo_elegido(app.b1).Atributo(29)==3
             app.Moto_Accpropiedad(1)=1.1;
             app.Moto_Accpropiedad(3)=50;
         else Atributo_elegido(app.b1).Atributo(29)==4
             app.Moto_Accpropiedad(1)=1;
             app.Moto_Accpropiedad(3)=0;
         end
         
         app.Moto_Alolargo=zeros(1,3);
         
         app.Cicli_Alolargo =zeros(1,15);
         app.Cicli_SalidadCamino =zeros(1,13);
         app.Cicli_Interseccion=zeros(1,12);
         if Atributo_elegido(app.b1).Atributo(25)==1
             app.Cicli_Interseccion(1)=40;
             app.Cicli_Interseccion(11)=20;
         elseif Atributo_elegido(app.b1).Atributo(25)==2
             app.Cicli_Interseccion(1)=150;
             app.Cicli_Interseccion(11)=30;
         elseif Atributo_elegido(app.b1).Atributo(25)==3
             app.Cicli_Interseccion(1)=45;
             app.Cicli_Interseccion(11)=45;
         elseif Atributo_elegido(app.b1).Atributo(25)==4
             app.Cicli_Interseccion(1)=55;
             app.Cicli_Interseccion(11)=45;
         elseif Atributo_elegido(app.b1).Atributo(25)==5
             app.Cicli_Interseccion(1)=30;
             app.Cicli_Interseccion(11)=45;
         elseif Atributo_elegido(app.b1).Atributo(25)==6
             app.Cicli_Interseccion(1)=40;
             app.Cicli_Interseccion(11)=45;
         elseif Atributo_elegido(app.b1).Atributo(25)==7
             app.Cicli_Interseccion(1)=55;
             app.Cicli_Interseccion(11)=50;
         elseif Atributo_elegido(app.b1).Atributo(25)==8
             app.Cicli_Interseccion(1)=80;
             app.Cicli_Interseccion(11)=50;
         elseif Atributo_elegido(app.b1).Atributo(25)==9
             app.Cicli_Interseccion(1)=35;
             app.Cicli_Interseccion(11)=50;
         elseif Atributo_elegido(app.b1).Atributo(25)==10
             app.Cicli_Interseccion(1)=50;
             app.Cicli_Interseccion(11)=50;
         elseif Atributo_elegido(app.b1).Atributo(25)==11
             app.Cicli_Interseccion(1)=0;
             app.Cicli_Interseccion(11)=0;
         elseif Atributo_elegido(app.b1).Atributo(25)==12
             app.Cicli_Interseccion(1)=3;
             app.Cicli_Interseccion(11)=150;
         elseif Atributo_elegido(app.b1).Atributo(25)==13
             app.Cicli_Interseccion(1)=1;
             app.Cicli_Interseccion(11)=150;
         elseif Atributo_elegido(app.b1).Atributo(25)==14
             app.Cicli_Interseccion(1)=2;
             app.Cicli_Interseccion(11)=45;
         elseif Atributo_elegido(app.b1).Atributo(25)==15
             app.Cicli_Interseccion(1)=1;
             app.Cicli_Interseccion(11)=45;
         else Atributo_elegido(app.b1).Atributo(25)==16
             app.Cicli_Interseccion(1)=55;
             app.Cicli_Interseccion(11)=35;
         end
         
         app.Peat_Alolargoconductor=zeros(1,15);
         app.Peat_Alolargocopiloto=zeros(1,15);
         app.Peat_CrucepeatonalIns=zeros(1,14);
         app.Peat_CrucepeatonalIns(13)=90;
         if Atributo_elegido(app.b1).Atributo(25)==1
             app.Peat_CrucepeatonalIns(5)=1.05;
         elseif Atributo_elegido(app.b1).Atributo(25)==2
             app.Peat_CrucepeatonalIns(5)=1.50;
         elseif Atributo_elegido(app.b1).Atributo(25)==3
             app.Peat_CrucepeatonalIns(5)=1.10;
         elseif Atributo_elegido(app.b1).Atributo(25)==4
             app.Peat_CrucepeatonalIns(5)=1.10;
         elseif Atributo_elegido(app.b1).Atributo(25)==5
             app.Peat_CrucepeatonalIns(5)=1.10;
         elseif Atributo_elegido(app.b1).Atributo(25)==6
             app.Peat_CrucepeatonalIns(5)=1.10;
         elseif Atributo_elegido(app.b1).Atributo(25)==7
             app.Peat_CrucepeatonalIns(5)=1.20;
         elseif Atributo_elegido(app.b1).Atributo(25)==8
             app.Peat_CrucepeatonalIns(5)=1.20;
         elseif Atributo_elegido(app.b1).Atributo(25)==9
             app.Peat_CrucepeatonalIns(5)=1.20;
         elseif Atributo_elegido(app.b1).Atributo(25)==10
             app.Peat_CrucepeatonalIns(5)=1.20;
         elseif Atributo_elegido(app.b1).Atributo(25)==11
             app.Peat_CrucepeatonalIns(5)=1;
         elseif Atributo_elegido(app.b1).Atributo(25)==12
             app.Peat_CrucepeatonalIns(5)=1;
         elseif Atributo_elegido(app.b1).Atributo(25)==13
             app.Peat_CrucepeatonalIns(5)=1;
         elseif Atributo_elegido(app.b1).Atributo(25)==14
             app.Peat_CrucepeatonalIns(5)=1.10;
         elseif Atributo_elegido(app.b1).Atributo(25)==15
             app.Peat_CrucepeatonalIns(5)=1.10;
         else app.Peat_CrucepeatonalIns(5).Atributo(25)==16
             app.Cicli_Interseccion(1)=1.30;
         end
         app.Peat_CrucepeatonalLat=zeros(1,14);
         app.Peat_CrucepeatonalLat(13)=90;
         if Atributo_elegido(app.b1).Atributo(25)==1
             app.Peat_CrucepeatonalLat(5)=1.05;
         elseif Atributo_elegido(app.b1).Atributo(25)==2
             app.Peat_CrucepeatonalLat(5)=1.50;
         elseif Atributo_elegido(app.b1).Atributo(25)==3
             app.Peat_CrucepeatonalLat(5)=1.10;
         elseif Atributo_elegido(app.b1).Atributo(25)==4
             app.Peat_CrucepeatonalLat(5)=1.10;
         elseif Atributo_elegido(app.b1).Atributo(25)==5
             app.Peat_CrucepeatonalLat(5)=1.10;
         elseif Atributo_elegido(app.b1).Atributo(25)==6
             app.Peat_CrucepeatonalLat(5)=1.10;
         elseif Atributo_elegido(app.b1).Atributo(25)==7
             app.Peat_CrucepeatonalLat(5)=1.20;
         elseif Atributo_elegido(app.b1).Atributo(25)==8
             app.Peat_CrucepeatonalLat(5)=1.20;
         elseif Atributo_elegido(app.b1).Atributo(25)==9
             app.Peat_CrucepeatonalLat(5)=1.20;
         elseif Atributo_elegido(app.b1).Atributo(25)==10
             app.Peat_CrucepeatonalLat(5)=1.20;
         elseif Atributo_elegido(app.b1).Atributo(25)==11
             app.Peat_CrucepeatonalLat(5)=1;
         elseif Atributo_elegido(app.b1).Atributo(25)==12
             app.Peat_CrucepeatonalLat(5)=1;
         elseif Atributo_elegido(app.b1).Atributo(25)==13
             app.Peat_CrucepeatonalLat(5)=1;
         elseif Atributo_elegido(app.b1).Atributo(25)==14
             app.Peat_CrucepeatonalLat(5)=1.10;
         elseif Atributo_elegido(app.b1).Atributo(25)==15
             app.Peat_CrucepeatonalLat(5)=1.10;
         else app.Peat_CrucepeatonalIns(5).Atributo(25)==16
             app.Peat_CrucepeatonalLat(1)=1.30;
         end
        
            if app.UITable2.Data{1,5}==1
               Contramedida1(1,1)=cellstr(app.UITable2.Data{1,2});
               Categoria1(1,1)=cellstr(app.UITable2.Data{1,4});
               Identificador1(1)=1;
               app.Matriz(16)=1;
               app.O_V_SalidaCaminoconductor(7)=1;
               app.O_V_SalidaCaminocopiloto(7)=1;
               app.O_V_PerdidaControl(7)=1;
               app.O_V_Adelantamiento(2)=1;
               app.O_V_Intersecciones(3)=1;
               app.Moto_SalidaCaminoconductor(7)=1;
               app.Moto_SalidaCaminocopiloto(7)=1;
               app.Moto_PerdidaControl(7)=1;
               app.Moto_Adelantamiento(2)=1;
               app.Moto_Intersecciones(3)=1;
               app.Cicli_Alolargo(7)=1;
               app.Cicli_SalidadCamino(7)=1;
               app.Cicli_Interseccion(3)=1;
               app.Peat_Alolargoconductor(7)=1;
               app.Peat_Alolargocopiloto(7)=1;
               app.Matriz3(16)=Atributo_elegido(app.b1).Atributo(16);
               app.Matriz2(1)=cellstr('Alineación vertical (mayor)');
            end
            if app.UITable2.Data{2,5}==1
               Contramedida1(2,1)=cellstr(app.UITable2.Data{2,2});
               Categoria1(2,1)=cellstr(app.UITable2.Data{2,4});
               Identificador1(2)=1;
               app.Matriz(24)=1;
               app.O_V_Intersecciones(6)=1;
               app.Moto_Intersecciones(6)=1;
               app.Cicli_Alolargo(4)=1;
               app.Cicli_Interseccion(7)=1;
               app.Peat_Alolargoconductor(4)=1;
               app.Peat_Alolargocopiloto(4)=1;
               app.Peat_CrucepeatonalIns(10)=1;
               app.Peat_CrucepeatonalLat(10)=1;
               app.Matriz3(24)=Atributo_elegido(app.b1).Atributo(24);
               app.Matriz2(2)=cellstr('Realineamiento (mejora de la distancia visual)');
            end
            if app.UITable2.Data{3,5}==1
               Contramedida1(3,1)=cellstr(app.UITable2.Data{3,2});
               Categoria1(3,1)=cellstr(app.UITable2.Data{3,4});
               Identificador1(3)=1;
               app.Matriz(14)=1;
               app.O_V_SalidaCaminoconductor(2)=1;
               app.O_V_SalidaCaminocopiloto(2)=1;
               app.O_V_PerdidaControl(2)=1;
               app.Moto_SalidaCaminoconductor(2)=1;
               app.Moto_SalidaCaminocopiloto(2)=1;
               app.Moto_PerdidaControl(2)=1;
               app.Cicli_Alolargo(2)=1;
               app.Cicli_SalidadCamino(2)=1;
               app.Peat_Alolargoconductor(2)=1;
               app.Peat_Alolargocopiloto(2)=1;
               app.Matriz3(14)=Atributo_elegido(app.b1).Atributo(14);
               app.Matriz2(3)=cellstr('Realineamiento horizontal');
            end
            if app.UITable2.Data{4,5}==1
               Contramedida1(4,1)=cellstr(app.UITable2.Data{4,2});
               Categoria1(4,1)=cellstr(app.UITable2.Data{4,4});
               Identificador1(4)=1;
               app.Matriz(10)=3;
               app.O_V_PerdidaControl(9)=2;
               app.O_V_Adelantamiento(5)=0;
               app.O_V_Accpropiedad(3)=0.7;
               app.Moto_PerdidaControl(9)=2;
               app.Moto_Adelantamiento(5)=0;
               app.Peat_CrucepeatonalIns(2)=1;
               app.Peat_CrucepeatonalLat(2)=1;
               app.Matriz3(10)=Atributo_elegido(app.b1).Atributo(10);
               app.Matriz2(4)=cellstr('Amplicación/Duplicación de calzada con una mediana o faja separadora mayor a 20 m de ancho');
            end
            if app.UITable2.Data{5,5}==1
               Contramedida1(5,1)=cellstr(app.UITable2.Data{5,2});
               Categoria1(5,1)=cellstr(app.UITable2.Data{5,4});
               Identificador1(5)=1;
               app.Matriz(12)=2;
               app.O_V_Adelantamiento(1)=0.02;
               app.Moto_Adelantamiento(1)=0.02;
               app.Peat_CrucepeatonalIns(1)=2.8;
               app.Peat_CrucepeatonalLat(1)=2.8;
               app.Matriz3(12)=Atributo_elegido(app.b1).Atributo(12);
               app.Matriz2(4)=cellstr('Amplicación/Duplicación de calzada con una mediana o faja separadora mayor a 20 m de ancho');
            end
            if app.UITable2.Data{6,5}==1
               Contramedida1(6,1)=cellstr(app.UITable2.Data{6,2});
               Categoria1(6,1)=cellstr(app.UITable2.Data{6,4});
               Identificador1(6)=1;
               app.Matriz(10)=4;
               app.O_V_PerdidaControl(9)=10;
               app.O_V_Adelantamiento(5)=0;
               app.O_V_Accpropiedad(3)=0.7;
               app.Moto_PerdidaControl(9)=10;
               app.Moto_Adelantamiento(5)=0;
               app.Peat_CrucepeatonalIns(2)=1;
               app.Peat_CrucepeatonalLat(2)=1;
               app.Matriz3(10)=Atributo_elegido(app.b1).Atributo(10);
               app.Matriz2(5)=cellstr('Ampliación/Duplicación de calzada con una mediana o faja separadora de 10 a 20m de ancho');
            end
            if app.UITable2.Data{7,5}==1
               Contramedida1(7,1)=cellstr(app.UITable2.Data{7,2});
               Categoria1(7,1)=cellstr(app.UITable2.Data{7,4});
               Identificador1(7)=1;
               app.Matriz(12)=2;
               app.O_V_Adelantamiento(1)=0.02;
               app.Moto_Adelantamiento(1)=0.02;
               app.Peat_CrucepeatonalIns(1)=2.8;
               app.Peat_CrucepeatonalLat(1)=2.8;
               app.Matriz3(12)=Atributo_elegido(app.b1).Atributo(12);
               app.Matriz2(5)=cellstr('Ampliación/Duplicación de calzada con una mediana o faja separadora de 10 a 20m de ancho');
            end
            if app.UITable2.Data{8,5}==1
               Contramedida1(8,1)=cellstr(app.UITable2.Data{8,2});
               Categoria1(8,1)=cellstr(app.UITable2.Data{8,4});
               Identificador1(8)=1;
               app.Matriz(10)=5;
               app.O_V_PerdidaControl(9)=35;
               app.O_V_Adelantamiento(5)=0;
               app.O_V_Accpropiedad(3)=0.7;
               app.Moto_PerdidaControl(9)=35;
               app.Moto_Adelantamiento(5)=0;
               app.Peat_CrucepeatonalIns(2)=1;
               app.Peat_CrucepeatonalLat(2)=1;
               app.Matriz3(10)=Atributo_elegido(app.b1).Atributo(10);
               app.Matriz2(6)=cellstr('Ampliación/Duplicación de calzada con una mediana o faja separadora de 5 a 10 m de ancho');
            end
            if app.UITable2.Data{9,5}==1
               Contramedida1(9,1)=cellstr(app.UITable2.Data{9,2});
               Categoria1(9,1)=cellstr(app.UITable2.Data{9,4});
               Identificador1(9)=1;
               app.Matriz(12)=2;
               app.O_V_Adelantamiento(1)=0.02;
               app.Moto_Adelantamiento(1)=0.02;
               app.Peat_CrucepeatonalIns(1)=2.8;
               app.Peat_CrucepeatonalLat(1)=2.8;
               app.Matriz3(12)=Atributo_elegido(app.b1).Atributo(12);
               app.Matriz2(6)=cellstr('Ampliación/Duplicación de calzada con una mediana o faja separadora de 5 a 10 m de ancho');
            end
            if app.UITable2.Data{10,5}==1
               Contramedida1(10,1)=cellstr(app.UITable2.Data{10,2});
               Categoria1(10,1)=cellstr(app.UITable2.Data{10,4});
               Identificador1(10)=1;
               app.Matriz(10)=6;
               app.O_V_PerdidaControl(9)=80;
               app.O_V_Adelantamiento(5)=0;
               app.O_V_Accpropiedad(3)=0.7;
               app.Moto_PerdidaControl(9)=80;
               app.Moto_Adelantamiento(5)=0;
               app.Peat_CrucepeatonalIns(2)=1;
               app.Peat_CrucepeatonalLat(2)=1;
               app.Matriz3(10)=Atributo_elegido(app.b1).Atributo(10);
               app.Matriz2(7)=cellstr('Ampliación/Duplicación de calzada con una mediana o faja separadora de 1 a 5 m de ancho');
            end
            if app.UITable2.Data{11,5}==1
               Contramedida1(11,1)=cellstr(app.UITable2.Data{11,2});
               Categoria1(11,1)=cellstr(app.UITable2.Data{11,4});
               Identificador1(11)=1;
               app.Matriz(12)=2;
               app.O_V_Adelantamiento(1)=0.02;
               app.Moto_Adelantamiento(1)=0.02;
               app.Peat_CrucepeatonalIns(1)=2.8;
               app.Peat_CrucepeatonalLat(1)=2.8;
               app.Matriz3(12)=Atributo_elegido(app.b1).Atributo(12);
               app.Matriz2(7)=cellstr('Ampliación/Duplicación de calzada con una mediana o faja separadora de 1 a 5 m de ancho');
            end
            if app.UITable2.Data{12,5}==1
               Contramedida1(12,1)=cellstr(app.UITable2.Data{12,2});
               Categoria1(12,1)=cellstr(app.UITable2.Data{12,4});
               Identificador1(12)=1;
               app.Matriz(10)=7;
               app.O_V_PerdidaControl(9)=90;
               app.O_V_Adelantamiento(5)=0;
               app.O_V_Accpropiedad(3)=0.7;
               app.Moto_PerdidaControl(9)=90;
               app.Moto_Adelantamiento(5)=0;
               app.Peat_CrucepeatonalIns(2)=1.60;
               app.Peat_CrucepeatonalLat(2)=1.60;
               app.Matriz3(10)=Atributo_elegido(app.b1).Atributo(10);
               app.Matriz2(8)=cellstr('Ampliación/Duplicación de calzada con una mediana o faja separadora central de hasta 1 m de ancho');
            end
            if app.UITable2.Data{13,5}==1
               Contramedida1(13,1)=cellstr(app.UITable2.Data{13,2});
               Categoria1(13,1)=cellstr(app.UITable2.Data{13,4});
               Identificador1(13)=1;
               app.Matriz(12)=2;  
               app.O_V_Adelantamiento(1)=0.02;
               app.Moto_Adelantamiento(1)=0.02;
               app.Peat_CrucepeatonalIns(1)=2.8;
               app.Peat_CrucepeatonalLat(1)=2.8;
               app.Matriz3(12)=Atributo_elegido(app.b1).Atributo(12);
               app.Matriz2(8)=cellstr('Ampliación/Duplicación de calzada con una mediana o faja separadora central de hasta 1 m de ancho');
            end
            if app.UITable2.Data{14,5}==1
               Contramedida1(14,1)=cellstr(app.UITable2.Data{14,2});
               Categoria1(14,1)=cellstr(app.UITable2.Data{14,4});
               Identificador1(14)=1;
               app.Matriz(10)=12;
               app.O_V_PerdidaControl(9)=0;
               app.O_V_Adelantamiento(5)=0;
               app.O_V_Accpropiedad(3)=0.7;
               app.Moto_PerdidaControl(9)=0;
               app.Moto_Adelantamiento(5)=0;
               app.Peat_CrucepeatonalIns(2)=1;
               app.Peat_CrucepeatonalLat(2)=1;
               app.Matriz3(10)=Atributo_elegido(app.b1).Atributo(10);
               app.Matriz2(9)=cellstr('Ampliación/Duplicación de calzada con barrera central');
            end
            if app.UITable2.Data{15,5}==1
               Contramedida1(15,1)=cellstr(app.UITable2.Data{15,2});
               Categoria1(15,1)=cellstr(app.UITable2.Data{15,4});
               Identificador1(15)=1;
               app.Matriz(12)=2;
               app.O_V_Adelantamiento(1)=0.02;
               app.Moto_Adelantamiento(1)=0.02;
               app.Peat_CrucepeatonalIns(1)=2.8;
               app.Peat_CrucepeatonalLat(1)=2.8;
               app.Matriz3(12)=Atributo_elegido(app.b1).Atributo(12);
               app.Matriz2(9)=cellstr('Ampliación/Duplicación de calzada con barrera central');
            end
            if app.UITable2.Data{16,5}==1
               Contramedida1(16,1)=cellstr(app.UITable2.Data{16,2});
               Categoria1(16,1)=cellstr(app.UITable2.Data{16,4});
               Identificador1(16)=1;
               app.Matriz(29)=3;
               app.O_V_Accpropiedad(1)=1.1;
               app.O_V_Accpropiedad(4)=50;
               app.Moto_Accpropiedad(1)=1.1;
               app.Moto_Accpropiedad(3)=50;
               app.Matriz3(29)=Atributo_elegido(app.b1).Atributo(29);
               app.Matriz2(10)=cellstr('Vía lateral o auxiliar / Camino de servicio');
            end
            if app.UITable2.Data{17,5}==1
               Contramedida1(17,1)=cellstr(app.UITable2.Data{17,2});
               Categoria1(17,1)=cellstr(app.UITable2.Data{17,4});
               Identificador1(17)=1;
               app.Matriz(22)=2; 
               app.O_V_Accpropiedad(2)=1;
               app.Moto_Accpropiedad(2)=1;
               app.Matriz3(22)=Atributo_elegido(app.b1).Atributo(22);
               app.Matriz2(10)=cellstr('Vía lateral o auxiliar / Camino de servicio');
            end
            if app.UITable2.Data{18,5}==1
               Contramedida1(18,1)=cellstr(app.UITable2.Data{18,2});
               Categoria1(18,1)=cellstr(app.UITable2.Data{18,4});
               Identificador1(18)=1;
               app.Matriz(10)=15;
               app.O_V_PerdidaControl(9)=0;
               app.O_V_Adelantamiento(5)=0;
               app.O_V_Accpropiedad(3)=0.7;
               app.Moto_PerdidaControl(9)=0;
               app.Moto_Adelantamiento(5)=0;
               app.Peat_CrucepeatonalIns(2)=1;
               app.Peat_CrucepeatonalLat(2)=1;
               app.Matriz3(10)=Atributo_elegido(app.b1).Atributo(10);
               app.Matriz2(11)=cellstr('Carril adicional (2 + 1 con barrera)');
            end
            if app.UITable2.Data{19,5}==1
               Contramedida1(19,1)=cellstr(app.UITable2.Data{19,2});
               Categoria1(19,1)=cellstr(app.UITable2.Data{19,4});
               Identificador1(19)=1;
               app.Matriz(12)=5;
               app.O_V_Adelantamiento(1)=0.5;
               app.Moto_Adelantamiento(1)=0.5;
               app.Peat_CrucepeatonalIns(1)=1.8;
               app.Peat_CrucepeatonalLat(1)=1.8;
               app.Matriz3(12)=Atributo_elegido(app.b1).Atributo(12);
               app.Matriz2(11)=cellstr('Carril adicional (2 + 1 con barrera)');
            end
            if app.UITable2.Data{20,5}==1
               Contramedida1(20,1)=cellstr(app.UITable2.Data{20,2});
               Categoria1(20,1)=cellstr(app.UITable2.Data{20,4});
               Identificador1(20)=1;
               app.Matriz(10)=13;
               app.O_V_PerdidaControl(9)=0;
               app.O_V_Adelantamiento(5)=0;
               app.O_V_Accpropiedad(3)=0.7;
               app.Moto_PerdidaControl(9)=0;
               app.Moto_Adelantamiento(5)=0;
               app.Peat_CrucepeatonalIns(2)=1;
               app.Peat_CrucepeatonalLat(2)=1;
               app.Matriz3(10)=Atributo_elegido(app.b1).Atributo(10);
               app.Matriz2(12)=cellstr('Implementar red de un sentido');
            end
            if app.UITable2.Data{21,5}==1
               Contramedida1(21,1)=cellstr(app.UITable2.Data{21,2});
               Categoria1(21,1)=cellstr(app.UITable2.Data{21,4});
               Identificador1(21)=1;
               app.Matriz(12)=5;
               app.O_V_Adelantamiento(1)=0.5;
               app.Moto_Adelantamiento(1)=0.5;
               app.Peat_CrucepeatonalIns(1)=1.8;
               app.Peat_CrucepeatonalLat(1)=1.8;
               app.Matriz3(12)=Atributo_elegido(app.b1).Atributo(12);
               app.Matriz2(13)=cellstr('Carril de adelantamiento');
            end
            if app.UITable2.Data{22,5}==1
               Contramedida1(22,1)=cellstr(app.UITable2.Data{22,2});
               Categoria1(22,1)=cellstr(app.UITable2.Data{22,4});
               Identificador1(22)=1;
               app.Matriz(25)=1;
               app.O_V_Intersecciones(1)=6;
               app.O_V_Intersecciones(9)=15;
               app.Moto_Intersecciones(1)=6;
               app.Moto_Intersecciones(9)=20;
               app.Peat_CrucepeatonalIns(5)=1.05;
               app.Peat_CrucepeatonalLat(5)=1.05;
               app.Cicli_Interseccion(1)=40;
               app.Cicli_Interseccion(11)=20;
               app.Matriz3(25)=Atributo_elegido(app.b1).Atributo(25);
               app.Matriz2(14)=cellstr('Paso a desnivel en intersección');
            end
            if app.UITable2.Data{23,5}==1
               Contramedida1(23,1)=cellstr(app.UITable2.Data{23,2});
               Categoria1(23,1)=cellstr(app.UITable2.Data{23,4});
               Identificador1(23)=1;
               app.Matriz(10)=15;
               app.O_V_PerdidaControl(9)=0;
               app.O_V_Adelantamiento(5)=0;
               app.O_V_Accpropiedad(3)=0.7;
               app.Moto_PerdidaControl(9)=0;
               app.Moto_Adelantamiento(5)=0;
               app.Peat_CrucepeatonalIns(2)=1;
               app.Peat_CrucepeatonalLat(2)=1;
               app.Matriz3(10)=Atributo_elegido(app.b1).Atributo(10);
               app.Matriz2(15)=cellstr('Barrera mediana central (sin duplicación de calzada)/Barrera central en vías de más de un carril por sentido');
            end
            if app.UITable2.Data{24,5}==1
               Contramedida1(24,1)=cellstr(app.UITable2.Data{24,2});
               Categoria1(24,1)=cellstr(app.UITable2.Data{24,4});
               Identificador1(24)=1;
               app.Matriz(10)=8;
               app.O_V_PerdidaControl(9)=77;
               app.O_V_Adelantamiento(5)=25;
               app.O_V_Accpropiedad(3)=1;
               app.Moto_PerdidaControl(9)=77;
               app.Moto_Adelantamiento(5)=25;
               app.Peat_CrucepeatonalIns(2)=3;
               app.Peat_CrucepeatonalLat(2)=3;
               app.Matriz3(10)=Atributo_elegido(app.b1).Atributo(10);
               app.Matriz2(16)=cellstr('Carril de giro central a lo largo de toda la vía');
            end
            if app.UITable2.Data{25,5}==1
               Contramedida1(25,1)=cellstr(app.UITable2.Data{25,2});
               Categoria1(25,1)=cellstr(app.UITable2.Data{25,4});
               Identificador1(25)=1;
               app.Matriz(10)=15;
               app.O_V_PerdidaControl(9)=0;
               app.O_V_Adelantamiento(5)=0;
               app.O_V_Accpropiedad(3)=0.7;
               app.Moto_PerdidaControl(9)=0;
               app.Moto_Adelantamiento(5)=0;
               app.Peat_CrucepeatonalIns(2)=1;
               app.Peat_CrucepeatonalLat(2)=1;
               app.Matriz3(10)=Atributo_elegido(app.b1).Atributo(10);
               app.Matriz2(17)=cellstr('Barrera mediana central (1+1)');
            end
            if app.UITable2.Data{26,5}==1
               Contramedida1(26,1)=cellstr(app.UITable2.Data{26,2});
               Categoria1(26,1)=cellstr(app.UITable2.Data{26,4});
               Identificador1(26)=1;
               app.Matriz(10)=9;
               app.O_V_PerdidaControl(9)=90;
               app.O_V_Adelantamiento(5)=0;
               app.O_V_Accpropiedad(3)=1;
               app.Moto_PerdidaControl(9)=90;
               app.Moto_Adelantamiento(5)=0;
               app.Peat_CrucepeatonalIns(2)=2.70;
               app.Peat_CrucepeatonalLat(2)=2.70;
               app.Matriz3(10)=Atributo_elegido(app.b1).Atributo(10);
               app.Matriz2(18)=cellstr('Bandas de alerta centrales / postes flexibles centrales');
            end
            if app.UITable2.Data{27,5}==1
               Contramedida1(27,1)=cellstr(app.UITable2.Data{27,2});
               Categoria1(27,1)=cellstr(app.UITable2.Data{27,4});
               Identificador1(27)=1;
               app.Matriz(10)=10;
               app.O_V_PerdidaControl(9)=83;
               app.O_V_Adelantamiento(5)=82.5;
               app.O_V_Accpropiedad(3)=1;
               app.Moto_PerdidaControl(9)=83;
               app.Moto_Adelantamiento(5)=82.5;
               app.Peat_CrucepeatonalIns(2)=2.40;
               app.Peat_CrucepeatonalLat(2)=2.40;
               app.Matriz3(10)=Atributo_elegido(app.b1).Atributo(10);
               app.Matriz2(19)=cellstr('Achurado central (Raya separadora de sentidos de circulación doble y rayas diagonales)');
            end
            if app.UITable2.Data{28,5}==1
               Contramedida1(28,1)=cellstr(app.UITable2.Data{28,2});
               Categoria1(28,1)=cellstr(app.UITable2.Data{28,4});
               Identificador1(28)=1;
               app.Matriz(10)=14;
               app.O_V_PerdidaControl(9)=95;
               app.O_V_Adelantamiento(5)=100;
               app.O_V_Accpropiedad(3)=1;
               app.Moto_PerdidaControl(9)=95;
               app.Moto_Adelantamiento(5)=100;
               app.Peat_CrucepeatonalIns(2)=2.70;
               app.Peat_CrucepeatonalLat(2)=2.70;
               app.Matriz3(10)=Atributo_elegido(app.b1).Atributo(10);
               app.Matriz2(20)=cellstr('Línea central ancha');
            end
            if app.UITable2.Data{29,5}==1
               Contramedida1(29,1)=cellstr(app.UITable2.Data{29,2});
               Categoria1(29,1)=cellstr(app.UITable2.Data{29,4});
               Identificador1(29)=1;
               app.Matriz(45)=2;
               app.Moto_Alolargo(1)=0.1;
               app.Moto_Alolargo(2)=50;
               app.Matriz3(45)=Atributo_elegido(app.b1).Atributo(45);
               app.Matriz2(21)=cellstr('Carril para motocicletas (Segregado)');
            end
            if app.UITable2.Data{30,5}==1
               Contramedida1(30,1)=cellstr(app.UITable2.Data{30,2});
               Categoria1(30,1)=cellstr(app.UITable2.Data{30,4});
               Identificador1(30)=1;
               app.Matriz(45)=5;
               app.Moto_Alolargo(1)=1;
               app.Moto_Alolargo(2)=50; 
               app.Matriz3(45)=Atributo_elegido(app.b1).Atributo(45);
               app.Matriz2(22)=cellstr('Carril para motocicletas (construir en vía existente)');
            end
            if app.UITable2.Data{31,5}==1
               Contramedida1(31,1)=cellstr(app.UITable2.Data{31,2});
               Categoria1(31,1)=cellstr(app.UITable2.Data{31,4});
               Identificador1(31)=1;
               app.Matriz(45)=5;
               app.Moto_Alolargo(1)=1;
               app.Moto_Alolargo(2)=50; 
               app.Matriz3(45)=Atributo_elegido(app.b1).Atributo(45);
               app.Matriz2(23)=cellstr('Carril para motocicletas (logotipos pintados solamente en la carretera)');
            end
            if app.UITable2.Data{32,5}==1
               Contramedida1(32,1)=cellstr(app.UITable2.Data{32,2});
               Categoria1(32,1)=cellstr(app.UITable2.Data{32,4});
               Identificador1(32)=1;
               app.Matriz(13)=1;
               app.O_V_SalidaCaminoconductor(1)=1;
               app.O_V_SalidaCaminocopiloto(1)=1;
               app.O_V_PerdidaControl(1)=1;
               app.Moto_SalidaCaminoconductor(1)=1;
               app.Moto_SalidaCaminocopiloto(1)=1;
               app.Moto_PerdidaControl(1)=1;
               app.Cicli_Alolargo(5)=1;
               app.Cicli_SalidadCamino(1)=1;
               app.Peat_Alolargoconductor(5)=1;
               app.Peat_Alolargocopiloto(5)=1;
               app.Matriz3(13)=Atributo_elegido(app.b1).Atributo(13);
               app.Matriz3(38)=Atributo_elegido(app.b1).Atributo(38);
               app.Matriz2(24)=cellstr('Ampliar/Ensanchar carril (> 0.5 m)');
            end
            if app.UITable2.Data{33,5}==1
               Contramedida1(33,1)=cellstr(app.UITable2.Data{33,2});
               Categoria1(33,1)=cellstr(app.UITable2.Data{33,4});
               Identificador1(33)=1;
               app.Matriz(13)=1;
               app.O_V_SalidaCaminoconductor(1)=1;
               app.O_V_SalidaCaminocopiloto(1)=1;
               app.O_V_PerdidaControl(1)=1;
               app.Moto_SalidaCaminoconductor(1)=1;
               app.Moto_SalidaCaminocopiloto(1)=1;
               app.Moto_PerdidaControl(1)=1;
               app.Cicli_Alolargo(5)=1;
               app.Cicli_SalidadCamino(1)=1;
               app.Peat_Alolargoconductor(5)=1;
               app.Peat_Alolargocopiloto(5)=1;
               app.Matriz3(13)=Atributo_elegido(app.b1).Atributo(13);
               app.Matriz3(38)=Atributo_elegido(app.b1).Atributo(38);
               app.Matriz2(25)=cellstr('Ampliar/Ensanchar carril (hasta 0.5 m)');
            end
            if app.UITable2.Data{34,5}==1
               Contramedida1(34,1)=cellstr(app.UITable2.Data{34,2});
               Categoria1(34,1)=cellstr(app.UITable2.Data{34,4});
               Identificador1(34)=1;
               app.Matriz(7)=1;
               app.O_V_SalidaCaminocopiloto(11)=0.77;
               app.Moto_SalidaCaminocopiloto(11)=0.77;
               app.Matriz3(7)=Atributo_elegido(app.b1).Atributo(7);
               app.Matriz2(26)=cellstr('Pavimentación de espaldón de lado del copiloto mayor a 1 m');
            end
            if app.UITable2.Data{35,5}==1
               Contramedida1(35,1)=cellstr(app.UITable2.Data{35,2});
               Categoria1(35,1)=cellstr(app.UITable2.Data{35,4});
               Identificador1(35)=1;
               app.Matriz(7)=2;
               app.O_V_SalidaCaminocopiloto(11)=0.83;
               app.Moto_SalidaCaminocopiloto(11)=0.83;
               app.Matriz3(7)=Atributo_elegido(app.b1).Atributo(7);
               app.Matriz2(27)=cellstr('Pavimentación de espaldón de lado del copiloto hasta 1 m');
            end
            if app.UITable2.Data{36,5}==1
               Contramedida1(36,1)=cellstr(app.UITable2.Data{36,2});
               Categoria1(36,1)=cellstr(app.UITable2.Data{36,4});
               Identificador1(36)=1;
               app.Matriz(6)=1;
               app.O_V_SalidaCaminoconductor(11)=0.77;
               app.Moto_SalidaCaminoconductor(11)=0.77;
               app.Matriz3(6)=Atributo_elegido(app.b1).Atributo(6);
               app.Matriz2(28)=cellstr('Pavimentación de espaldón de lado del conductor mayor a 1 m');
            end
            if app.UITable2.Data{37,5}==1
               Contramedida1(37,1)=cellstr(app.UITable2.Data{37,2});
               Categoria1(37,1)=cellstr(app.UITable2.Data{37,4});
               Identificador1(37)=1;
               app.Matriz(6)=2; 
               app.O_V_SalidaCaminoconductor(11)=0.83;
               app.Moto_SalidaCaminoconductor(11)=0.83;
               app.Matriz3(6)=Atributo_elegido(app.b1).Atributo(6);
               app.Matriz2(29)=cellstr('Pavimentación de espaldón de lado del conductor hasta 1 m');
            end
            if app.UITable2.Data{38,5}==1
               Contramedida1(38,1)=cellstr(app.UITable2.Data{38,2});
               Categoria1(38,1)=cellstr(app.UITable2.Data{38,4});
               Identificador1(38)=1;
               app.Matriz(5)=2;
               app.O_V_SalidaCaminoconductor(5)=1;
               app.O_V_SalidaCaminocopiloto(5)=1;
               app.Moto_SalidaCaminoconductor(5)=1;
               app.Moto_SalidaCaminocopiloto(5)=1;
               app.Cicli_Alolargo(10)=1;
               app.Peat_Alolargoconductor(11)=1;
               app.Peat_Alolargocopiloto(11)=1;
               app.Matriz3(5)=Atributo_elegido(app.b1).Atributo(5);
               app.Matriz2(30)=cellstr('Bandas alertadoras sobre el espaldón');
            end
            if app.UITable2.Data{39,5}==1
               Contramedida1(39,1)=cellstr(app.UITable2.Data{39,2});
               Categoria1(39,1)=cellstr(app.UITable2.Data{39,4});
               Identificador1(39)=1;
               app.Matriz(1)=3;
               app.O_V_SalidaCaminoconductor(10)=0.35;
               app.Moto_SalidaCaminoconductor(10)=0.35;
               app.Cicli_SalidadCamino(10)=0.10;
               app.Matriz3(1)=Atributo_elegido(app.b1).Atributo(1);
               app.Matriz2(31)=cellstr('Barreras laterales – lado del conductor');
            end
            if app.UITable2.Data{40,5}==1
               Contramedida1(40,1)=cellstr(app.UITable2.Data{40,2});
               Categoria1(40,1)=cellstr(app.UITable2.Data{40,4});
               Identificador1(40)=1;
               app.Matriz(2)=3;
               app.O_V_SalidaCaminoconductor(9)=12;
               app.Moto_SalidaCaminoconductor(9)=20;
               app.Cicli_SalidadCamino(9)=20;
               app.Matriz3(2)=Atributo_elegido(app.b1).Atributo(2);
               app.Matriz2(31)=cellstr('Barreras laterales – lado del conductor');
            end
            if app.UITable2.Data{41,5}==1
               Contramedida1(41,1)=cellstr(app.UITable2.Data{41,2});
               Categoria1(41,1)=cellstr(app.UITable2.Data{41,4});
               Identificador1(41)=1;
               app.Matriz(3)=3;
               app.O_V_SalidaCaminocopiloto(10)=0.35;
               app.Moto_SalidaCaminocopiloto(10)=0.35;
               app.Cicli_SalidadCamino(12)=0.10;
               app.Matriz3(3)=Atributo_elegido(app.b1).Atributo(3);
               app.Matriz2(32)=cellstr('Barreras laterales – lado del copiloto');
            end
            if app.UITable2.Data{42,5}==1
               Contramedida1(42,1)=cellstr(app.UITable2.Data{42,2});
               Categoria1(42,1)=cellstr(app.UITable2.Data{42,4});
               Identificador1(42)=1;
               app.Matriz(4)=3;
               app.O_V_SalidaCaminocopiloto(9)=12;
               app.Moto_SalidaCaminocopiloto(9)=20;
               app.Cicli_SalidadCamino(11)=20;
               app.Matriz3(4)=Atributo_elegido(app.b1).Atributo(4);
               app.Matriz2(32)=cellstr('Barreras laterales – lado del copiloto');
            end
            if app.UITable2.Data{43,5}==1
               Contramedida1(43,1)=cellstr(app.UITable2.Data{43,2});
               Categoria1(43,1)=cellstr(app.UITable2.Data{43,4});
               Identificador1(43)=1;
               app.Matriz(1)=4;
               app.O_V_SalidaCaminoconductor(10)=0.10;
               app.Moto_SalidaCaminoconductor(10)=0.10;
               app.Cicli_SalidadCamino(10)=0.01;
               app.Matriz3(1)=Atributo_elegido(app.b1).Atributo(1);
               app.Matriz2(33)=cellstr('Eliminar peligros laterales – lado conductor');
            end
            if app.UITable2.Data{44,5}==1
               Contramedida1(44,1)=cellstr(app.UITable2.Data{44,2});
               Categoria1(44,1)=cellstr(app.UITable2.Data{44,4});
               Identificador1(44)=1;
               app.Matriz(2)=17;
               app.O_V_SalidaCaminoconductor(9)=35;
               app.Moto_SalidaCaminoconductor(9)=35;
               app.Cicli_SalidadCamino(9)=35;%Valor de Ninguno
               app.Matriz3(2)=Atributo_elegido(app.b1).Atributo(2);
               app.Matriz2(33)=cellstr('Eliminar peligros laterales – lado conductor');
            end
            if app.UITable2.Data{45,5}==1
               Contramedida1(45,1)=cellstr(app.UITable2.Data{45,2});
               Categoria1(45,1)=cellstr(app.UITable2.Data{45,4});
               Identificador1(45)=1;
               app.Matriz(3)=4;
               app.O_V_SalidaCaminocopiloto(10)=0.10;
               app.Moto_SalidaCaminocopiloto(10)=0.10;
               app.Cicli_SalidadCamino(12)=0.01;
               app.Matriz3(3)=Atributo_elegido(app.b1).Atributo(3);
               app.Matriz2(34)=cellstr('Eliminar peligros laterales – lado del copiloto');
            end
            if app.UITable2.Data{46,5}==1
               Contramedida1(46,1)=cellstr(app.UITable2.Data{46,2});
               Categoria1(46,1)=cellstr(app.UITable2.Data{46,4});
               Identificador1(46)=1;
               app.Matriz(4)=17;
               app.O_V_SalidaCaminocopiloto(9)=35;
               app.Moto_SalidaCaminocopiloto(9)=35;
               app.Cicli_SalidadCamino(11)=35; %Valor de Ninguno
               app.Matriz3(4)=Atributo_elegido(app.b1).Atributo(4);
               app.Matriz2(34)=cellstr('Eliminar peligros laterales – lado del copiloto');
            end
            if app.UITable2.Data{47,5}==1
               Contramedida1(47,1)=cellstr(app.UITable2.Data{47,2});
               Categoria1(47,1)=cellstr(app.UITable2.Data{47,4});
               Identificador1(47)=1;
               app.Matriz(1)=4;
               app.O_V_SalidaCaminoconductor(10)=0.10;
               app.Moto_SalidaCaminoconductor(10)=0.10;
               app.Cicli_SalidadCamino(10)=0.01;
               app.Matriz3(1)=Atributo_elegido(app.b1).Atributo(1);
               app.Matriz2(35)=cellstr('Mejoramiento de taludes y terraplenes laterales – lado conductor');
            end
            if app.UITable2.Data{48,5}==1
               Contramedida1(48,1)=cellstr(app.UITable2.Data{48,2});
               Categoria1(48,1)=cellstr(app.UITable2.Data{48,4});
               Identificador1(48)=1;
               app.Matriz(2)=9;
               app.O_V_SalidaCaminoconductor(9)=45;
               app.Moto_SalidaCaminoconductor(9)=45;
               app.Cicli_SalidadCamino(9)=45;
               app.Matriz3(2)=Atributo_elegido(app.b1).Atributo(2);
               app.Matriz2(35)=cellstr('Mejoramiento de taludes y terraplenes laterales – lado conductor');
            end
            if app.UITable2.Data{49,5}==1
               Contramedida1(49,1)=cellstr(app.UITable2.Data{49,2});
               Categoria1(49,1)=cellstr(app.UITable2.Data{49,4});
               Identificador1(49)=1;
               app.Matriz(3)=4;
               app.O_V_SalidaCaminocopiloto(10)=0.10;
               app.Moto_SalidaCaminocopiloto(10)=0.10;
               app.Cicli_SalidadCamino(12)=0.01;
               app.Matriz3(3)=Atributo_elegido(app.b1).Atributo(3);
               app.Matriz2(36)=cellstr('Mejoramiento de taludes y terraplenes laterales – lado del copiloto');
            end
            if app.UITable2.Data{50,5}==1
               Contramedida1(50,1)=cellstr(app.UITable2.Data{50,2});
               Categoria1(50,1)=cellstr(app.UITable2.Data{50,4});
               Identificador1(50)=1;
               app.Matriz(4)=9;
               app.O_V_SalidaCaminocopiloto(9)=45;
               app.Moto_SalidaCaminocopiloto(9)=45;
               app.Cicli_SalidadCamino(11)=45;
               app.Matriz3(4)=Atributo_elegido(app.b1).Atributo(4);
               app.Matriz2(36)=cellstr('Mejoramiento de taludes y terraplenes laterales – lado del copiloto');
            end
            if app.UITable2.Data{51,5}==1
               Contramedida1(51,1)=cellstr(app.UITable2.Data{51,2});
               Categoria1(51,1)=cellstr(app.UITable2.Data{51,4});
               Identificador1(51)=1;
               app.Matriz(25)=2;
               app.O_V_Intersecciones(1)=15;
               app.O_V_Intersecciones(9)=15;
               app.Moto_Intersecciones(1)=30;
               app.Moto_Intersecciones(9)=30;
               app.Peat_CrucepeatonalIns(5)=1.5;
               app.Peat_CrucepeatonalLat(5)=1.5;
               app.Cicli_Interseccion(1)=150;
               app.Cicli_Interseccion(11)=30;
               app.Matriz3(25)=Atributo_elegido(app.b1).Atributo(25);
               app.Matriz2(37)=cellstr('Redondel');
            end
            if app.UITable2.Data{52,5}==1
               Contramedida1(52,1)=cellstr(app.UITable2.Data{52,2});
               Categoria1(52,1)=cellstr(app.UITable2.Data{52,4});
               Identificador1(52)=1;
               app.Matriz(18)=2;
               app.O_V_SalidaCaminoconductor(8)=1.40;
               app.O_V_SalidaCaminocopiloto(8)=1.40;
               app.O_V_PerdidaControl(8)=1.40;
               app.O_V_Adelantamiento(3)=1.40;
               app.O_V_Intersecciones(5)=1.40;
               app.Moto_SalidaCaminoconductor(8)=1.60;
               app.Moto_SalidaCaminocopiloto(8)=1.60;
               app.Moto_PerdidaControl(8)=1.60;
               app.Moto_Adelantamiento(3)=1.60;
               app.Moto_Intersecciones(5)=1.60;
               app.Cicli_Alolargo(12)=1.60;
               app.Cicli_SalidadCamino(8)=1.60;
               app.Cicli_Interseccion(4)=1.60;
               app.Peat_Alolargoconductor(13)=1.40;
               app.Peat_Alolargocopiloto(13)=1.40;
               app.Peat_CrucepeatonalIns(8)=1.40;
               app.Peat_CrucepeatonalLat(8)=1.40;
               app.Matriz3(18)=Atributo_elegido(app.b1).Atributo(18);
               app.Matriz2(38)=cellstr('Pavimentar superficie de la vía');
            end
            if app.UITable2.Data{53,5}==1
               Contramedida1(53,1)=cellstr(app.UITable2.Data{53,2});
               Categoria1(53,1)=cellstr(app.UITable2.Data{53,4});
               Identificador1(53)=1;
               app.Matriz(17)=1;
               app.O_V_SalidaCaminoconductor(6)=1;
               app.O_V_SalidaCaminocopiloto(6)=1;
               app.O_V_PerdidaControl(6)=1;
               app.Moto_SalidaCaminoconductor(6)=1;
               app.Moto_SalidaCaminocopiloto(6)=1;
               app.Moto_PerdidaControl(6)=1;
               app.Cicli_Alolargo(8)=1;
               app.Cicli_SalidadCamino(6)=1;
               app.Peat_Alolargoconductor(8)=1;
               app.Peat_Alolargocopiloto(8)=1;
               app.Matriz3(17)=Atributo_elegido(app.b1).Atributo(17);
               app.Matriz2(39)=cellstr('Rehabilitación de la superficie de la vía');
            end
            if app.UITable2.Data{54,5}==1
               Contramedida1(54,1)=cellstr(app.UITable2.Data{54,2});
               Categoria1(54,1)=cellstr(app.UITable2.Data{54,4});
               Identificador1(54)=1;
               app.Matriz(18)=1;
               app.O_V_SalidaCaminoconductor(8)=1;
               app.O_V_SalidaCaminocopiloto(8)=1;
               app.O_V_PerdidaControl(8)=1;
               app.O_V_Adelantamiento(3)=1;
               app.O_V_Intersecciones(5)=1;
               app.Moto_SalidaCaminoconductor(8)=1;
               app.Moto_SalidaCaminocopiloto(8)=1;
               app.Moto_PerdidaControl(8)=1;
               app.Moto_Adelantamiento(3)=1;
               app.Moto_Intersecciones(5)=1;
               app.Cicli_Alolargo(12)=1;
               app.Cicli_SalidadCamino(8)=1;
               app.Cicli_Interseccion(4)=1;
               app.Peat_Alolargoconductor(13)=1;
               app.Peat_Alolargocopiloto(13)=1;
               app.Peat_CrucepeatonalIns(8)=1;
               app.Peat_CrucepeatonalLat(8)=1;
               app.Matriz3(18)=Atributo_elegido(app.b1).Atributo(18);
               app.Matriz2(40)=cellstr('Resistencia al deslizamiento (vía pavimentada)');
            end
            if app.UITable2.Data{55,5}==1
               Contramedida1(55,1)=cellstr(app.UITable2.Data{55,2});
               Categoria1(55,1)=cellstr(app.UITable2.Data{55,4});
               Identificador1(55)=1;
               app.Matriz(18)=4;
               app.O_V_SalidaCaminoconductor(8)=3;
               app.O_V_SalidaCaminocopiloto(8)=3;
               app.O_V_PerdidaControl(8)=3;
               app.O_V_Adelantamiento(3)=3;
               app.O_V_Intersecciones(5)=3;
               app.Moto_SalidaCaminoconductor(8)=4;
               app.Moto_SalidaCaminocopiloto(8)=4;
               app.Moto_PerdidaControl(8)=4;
               app.Moto_Adelantamiento(3)=4;
               app.Moto_Intersecciones(5)=4;
               app.Cicli_Alolargo(12)=4;
               app.Cicli_SalidadCamino(8)=4;
               app.Cicli_Interseccion(4)=4;
               app.Peat_Alolargoconductor(13)=3;
               app.Peat_Alolargocopiloto(13)=3;
               app.Peat_CrucepeatonalIns(8)=3;
               app.Peat_CrucepeatonalLat(8)=3;
               app.Matriz3(18)=Atributo_elegido(app.b1).Atributo(18);
               app.Matriz2(41)=cellstr('Resistencia al deslizamiento (vía sin pavimentar)');
            end
            if app.UITable2.Data{56,5}==1
               Contramedida1(56,1)=cellstr(app.UITable2.Data{56,2});
               Categoria1(56,1)=cellstr(app.UITable2.Data{56,4});
               Identificador1(56)=1;
               app.Matriz(25)=9;
               app.O_V_Intersecciones(1)=10;
               app.O_V_Intersecciones(9)=50;
               app.Moto_Intersecciones(1)=10;
               app.Moto_Intersecciones(9)=50;
               app.Peat_CrucepeatonalIns(5)=1.2;
               app.Peat_CrucepeatonalLat(5)=1.2;
               app.Cicli_Interseccion(1)=35;
               app.Cicli_Interseccion(11)=50;
               app.Matriz3(25)=Atributo_elegido(app.b1).Atributo(25);
               app.Matriz2(42)=cellstr('Semaforizar intersección (4 ramas)');
            end
            if app.UITable2.Data{57,5}==1
               Contramedida1(57,1)=cellstr(app.UITable2.Data{57,2});
               Categoria1(57,1)=cellstr(app.UITable2.Data{57,4});
               Identificador1(57)=1;
               app.Matriz(25)=9;
               app.O_V_Intersecciones(1)=10;
               app.O_V_Intersecciones(9)=50;
               app.Moto_Intersecciones(1)=10;
               app.Moto_Intersecciones(9)=50;
               app.Peat_CrucepeatonalIns(5)=1.2;
               app.Peat_CrucepeatonalLat(5)=1.2;
               app.Cicli_Interseccion(1)=35;
               app.Cicli_Interseccion(11)=50;
               app.Matriz3(25)=Atributo_elegido(app.b1).Atributo(25);
               app.Matriz2(43)=cellstr('Carril de giro izquierdo en interseccion de 4 ramas con semáforo');
            end
            if app.UITable2.Data{58,5}==1
               Contramedida1(58,1)=cellstr(app.UITable2.Data{58,2});
               Categoria1(58,1)=cellstr(app.UITable2.Data{58,4});
               Identificador1(58)=1;
               app.Matriz(25)=7;
               app.O_V_Intersecciones(1)=16;
               app.O_V_Intersecciones(9)=50;
               app.Moto_Intersecciones(1)=16;
               app.Moto_Intersecciones(9)=50;
               app.Peat_CrucepeatonalIns(5)=1.2;
               app.Peat_CrucepeatonalLat(5)=1.2;
               app.Cicli_Interseccion(1)=55;
               app.Cicli_Interseccion(11)=50;
               app.Matriz3(25)=Atributo_elegido(app.b1).Atributo(25);
               app.Matriz2(44)=cellstr('Carril de giro a la izquierda en intersección de 4 ramas sin semáforo');
            end
            if app.UITable2.Data{59,5}==1
               Contramedida1(59,1)=cellstr(app.UITable2.Data{59,2});
               Categoria1(59,1)=cellstr(app.UITable2.Data{59,4});
               Identificador1(59)=1;
               app.Matriz(25)=5;
               app.O_V_Intersecciones(1)=9;
               app.O_V_Intersecciones(9)=45;
               app.Moto_Intersecciones(1)=9;
               app.Moto_Intersecciones(9)=45;
               app.Peat_CrucepeatonalIns(5)=1.1;
               app.Peat_CrucepeatonalLat(5)=1.1;
               app.Cicli_Interseccion(1)=30;
               app.Cicli_Interseccion(11)=45;
               app.Matriz3(25)=Atributo_elegido(app.b1).Atributo(25);
               app.Matriz2(45)=cellstr('Semaforizar intersección de 3 ramas');
            end
            if app.UITable2.Data{60,5}==1
               Contramedida1(60,1)=cellstr(app.UITable2.Data{60,2});
               Categoria1(60,1)=cellstr(app.UITable2.Data{60,4});
               Identificador1(60)=1;
               app.Matriz(25)=5;
               app.O_V_Intersecciones(1)=9;
               app.O_V_Intersecciones(9)=45;
               app.Moto_Intersecciones(1)=9;
               app.Moto_Intersecciones(9)=45;
               app.Peat_CrucepeatonalIns(5)=1.1;
               app.Peat_CrucepeatonalLat(5)=1.1;
               app.Cicli_Interseccion(1)=30;
               app.Cicli_Interseccion(11)=45;
               app.Matriz3(25)=Atributo_elegido(app.b1).Atributo(25);
               app.Matriz2(46)=cellstr('Carril de giro izquierdo en interseccion de 3 ramas con semáforo');
            end
            if app.UITable2.Data{61,5}==1
               Contramedida1(61,1)=cellstr(app.UITable2.Data{61,2});
               Categoria1(61,1)=cellstr(app.UITable2.Data{61,4});
               Identificador1(61)=1;
               app.Matriz(25)=3; 
               app.O_V_Intersecciones(1)=13;
               app.O_V_Intersecciones(9)=45;
               app.Moto_Intersecciones(1)=17;
               app.Moto_Intersecciones(9)=45;
               app.Peat_CrucepeatonalIns(5)=1.1;
               app.Peat_CrucepeatonalLat(5)=1.1;
               app.Cicli_Interseccion(1)=45;
               app.Cicli_Interseccion(11)=45;
               app.Matriz3(25)=Atributo_elegido(app.b1).Atributo(25);
               app.Matriz2(47)=cellstr('Carril de giro a la izquierda en intersección 3 ramas sin semáforo');
            end
            if app.UITable2.Data{62,5}==1
               Contramedida1(62,1)=cellstr(app.UITable2.Data{62,2});
               Categoria1(62,1)=cellstr(app.UITable2.Data{62,4});
               Identificador1(62)=1;
               app.Matriz(25)=13;
               app.O_V_Intersecciones(1)=0.5;
               app.O_V_Intersecciones(9)=150;
               app.Moto_Intersecciones(1)=0.5;
               app.Moto_Intersecciones(9)=150;
               app.Peat_CrucepeatonalIns(5)=1;
               app.Peat_CrucepeatonalLat(5)=1;
               app.Cicli_Interseccion(1)=1;
               app.Cicli_Interseccion(11)=150;
               app.Matriz3(25)=Atributo_elegido(app.b1).Atributo(25);
               app.Matriz2(48)=cellstr('Mejora en cruce ferroviario');
            end
            if app.UITable2.Data{63,5}==1
               Contramedida1(63,1)=cellstr(app.UITable2.Data{63,2});
               Categoria1(63,1)=cellstr(app.UITable2.Data{63,4});
               Identificador1(63)=1;
               app.Matriz(25)=15;
               app.O_V_Intersecciones(1)=0.3;
               app.O_V_Intersecciones(9)=45;
               app.Moto_Intersecciones(1)=0.3;
               app.Moto_Intersecciones(9)=45;
               app.Peat_CrucepeatonalIns(5)=1.1;
               app.Peat_CrucepeatonalLat(5)=1.1;
               app.Cicli_Interseccion(1)=1;
               app.Cicli_Interseccion(11)=45;
               app.Matriz3(25)=Atributo_elegido(app.b1).Atributo(25);
               app.Matriz2(49)=cellstr('Mejora de cruce de mediana');
            end
            if app.UITable2.Data{64,5}==1
               Contramedida1(64,1)=cellstr(app.UITable2.Data{64,2});
               Categoria1(64,1)=cellstr(app.UITable2.Data{64,4});
               Identificador1(64)=1;
               app.Matriz(28)=1;
               app.O_V_Intersecciones(2)=1;
               app.Moto_Intersecciones(2)=1;
               app.Peat_CrucepeatonalIns(6)=1;
               app.Peat_CrucepeatonalLat(6)=1;
               app.Cicli_Interseccion(2)=1;
               app.Matriz3(28)=Atributo_elegido(app.b1).Atributo(28);
               app.Matriz2(49)=cellstr('Mejora de cruce de mediana');
            end
            if app.UITable2.Data{65,5}==1
               Contramedida1(65,1)=cellstr(app.UITable2.Data{65,2});
               Categoria1(65,1)=cellstr(app.UITable2.Data{65,4});
               Identificador1(65)=1;
               app.Matriz(46)=1;
               app.Cicli_Alolargo(1)=0;
               app.Cicli_Alolargo(14)=0;
               app.Cicli_Interseccion(5)=1;
               app.Matriz3(46)=Atributo_elegido(app.b1).Atributo(46);
               app.Matriz2(50)=cellstr('Carril para bicicletas (fuera de la vía)');
            end
            if app.UITable2.Data{66,5}==1
               Contramedida1(66,1)=cellstr(app.UITable2.Data{66,2});
               Categoria1(66,1)=cellstr(app.UITable2.Data{66,4});
               Identificador1(66)=1;
               app.Matriz(46)=3;
               app.Cicli_Alolargo(1)=12;
               app.Cicli_Alolargo(14)=90;
               app.Cicli_Interseccion(5)=1;
               app.Matriz3(46)=Atributo_elegido(app.b1).Atributo(46);
               app.Matriz2(51)=cellstr('Carril para bicicletas (dentro de la vía)');
            end
            if app.UITable2.Data{67,5}==1
               Contramedida1(67,1)=cellstr(app.UITable2.Data{67,2});
               Categoria1(67,1)=cellstr(app.UITable2.Data{67,4});
               Identificador1(67)=1;
               app.Matriz(39)=1;
               if Atributo_elegido(app.b1).Atributo(48)==1 
               app.Peat_CrucepeatonalIns(3)=0.3;
               app.Peat_CrucepeatonalIns(13)=90;
               elseif Atributo_elegido(app.b1).Atributo(48)==2
               app.Peat_CrucepeatonalIns(3)=0.4;
               app.Peat_CrucepeatonalIns(13)=90;
               elseif Atributo_elegido(app.b1).Atributo(48)==3
               app.Peat_CrucepeatonalIns(3)=0.4;
               app.Peat_CrucepeatonalIns(13)=90;
               end
               app.Matriz3(39)=Atributo_elegido(app.b1).Atributo(39);
               app.Matriz3(48)=Atributo_elegido(app.b1).Atributo(48);
               app.Matriz2(52)=cellstr('Cruce peatonal - paso a desnivel');
            end
            if app.UITable2.Data{68,5}==1
               Contramedida1(68,1)=cellstr(app.UITable2.Data{68,2});
               Categoria1(68,1)=cellstr(app.UITable2.Data{68,4});
               Identificador1(68)=1;
               app.Matriz(39)=2;
               if Atributo_elegido(app.b1).Atributo(48)==1 
               app.Peat_CrucepeatonalIns(3)=0.95;
               app.Peat_CrucepeatonalIns(13)=90;
               elseif Atributo_elegido(app.b1).Atributo(48)==2
               app.Peat_CrucepeatonalIns(3)=1;
               app.Peat_CrucepeatonalIns(13)=90;
               elseif Atributo_elegido(app.b1).Atributo(48)==3
               app.Peat_CrucepeatonalIns(3)=1;
               app.Peat_CrucepeatonalIns(13)=90;
               end
               app.Matriz3(39)=Atributo_elegido(app.b1).Atributo(39);
               app.Matriz3(48)=Atributo_elegido(app.b1).Atributo(48);
               app.Matriz2(53)=cellstr('Cruce peatonal con semáforo');
            end
            if app.UITable2.Data{69,5}==1
               Contramedida1(69,1)=cellstr(app.UITable2.Data{69,2});
               Categoria1(69,1)=cellstr(app.UITable2.Data{69,4});
               Identificador1(69)=1;
               app.Matriz(48)=1;
               app.Matriz3(48)=Atributo_elegido(app.b1).Atributo(48);
               app.Matriz2(54)=cellstr('Zona escolar - guardia o supervisor de cruce peatonal');
            end
            if app.UITable2.Data{70,5}==1
               Contramedida1(70,1)=cellstr(app.UITable2.Data{70,2});
               Categoria1(70,1)=cellstr(app.UITable2.Data{70,4});
               Identificador1(70)=1;
               app.Matriz(39)=8;
               if Atributo_elegido(app.b1).Atributo(48)==1 
               app.Peat_CrucepeatonalIns(3)=1;
               app.Peat_CrucepeatonalIns(13)=90;
               elseif Atributo_elegido(app.b1).Atributo(48)==2
               app.Peat_CrucepeatonalIns(3)=2.5;
               app.Peat_CrucepeatonalIns(13)=90;
               elseif Atributo_elegido(app.b1).Atributo(48)==3
               app.Peat_CrucepeatonalIns(3)=2.5;
               app.Peat_CrucepeatonalIns(13)=90;
               end
               app.Matriz3(39)=Atributo_elegido(app.b1).Atributo(39);
               app.Matriz3(48)=Atributo_elegido(app.b1).Atributo(48);
               app.Matriz2(55)=cellstr('Cruce peatonal elevado de la superficie de rodado (plataforma peatonal) sin semáforo');
            end
            if app.UITable2.Data{71,5}==1
               Contramedida1(71,1)=cellstr(app.UITable2.Data{71,2});
               Categoria1(71,1)=cellstr(app.UITable2.Data{71,4});
               Identificador1(71)=1;
               app.Matriz(39)=2;
               if Atributo_elegido(app.b1).Atributo(48)==1 
               app.Peat_CrucepeatonalIns(3)=0.95;
               app.Peat_CrucepeatonalIns(13)=90;
               elseif Atributo_elegido(app.b1).Atributo(48)==2
               app.Peat_CrucepeatonalIns(3)=1;
               app.Peat_CrucepeatonalIns(13)=90;
               elseif Atributo_elegido(app.b1).Atributo(48)==3
               app.Peat_CrucepeatonalIns(3)=1;
               app.Peat_CrucepeatonalIns(13)=90;
               end
               app.Matriz3(39)=Atributo_elegido(app.b1).Atributo(39);
               app.Matriz3(48)=Atributo_elegido(app.b1).Atributo(48);
               app.Matriz2(56)=cellstr('Cruce peatonal sin semáforo');
            end
            if app.UITable2.Data{72,5}==1
               Contramedida1(72,1)=cellstr(app.UITable2.Data{72,2});
               Categoria1(72,1)=cellstr(app.UITable2.Data{72,4});
               Identificador1(72)=1;
               app.Matriz(39)=6;
               if Atributo_elegido(app.b1).Atributo(48)==1 
               app.Peat_CrucepeatonalIns(3)=3.8;
               app.Peat_CrucepeatonalIns(13)=90;
               elseif Atributo_elegido(app.b1).Atributo(48)==2
               app.Peat_CrucepeatonalIns(3)=5.1;
               app.Peat_CrucepeatonalIns(13)=90;
               elseif Atributo_elegido(app.b1).Atributo(48)==3
               app.Peat_CrucepeatonalIns(3)=5.1;
               app.Peat_CrucepeatonalIns(13)=90;
               end
               app.Matriz3(39)=Atributo_elegido(app.b1).Atributo(39);
               app.Matriz3(48)=Atributo_elegido(app.b1).Atributo(48);
               app.Matriz2(57)=cellstr('Isla de refugio');
            end
            if app.UITable2.Data{73,5}==1
               Contramedida1(73,1)=cellstr(app.UITable2.Data{73,2});
               Categoria1(73,1)=cellstr(app.UITable2.Data{73,4});
               Identificador1(73)=1;
               app.Matriz(40)=1;
               app.Peat_CrucepeatonalIns(4)=1;
               app.Peat_CrucepeatonalLat(4)=1;
               app.Matriz3(40)=Atributo_elegido(app.b1).Atributo(40);
               app.Matriz2(58)=cellstr('Mejorar la calidad de la infraestructura para peatones');
            end
            if app.UITable2.Data{74,5}==1
               Contramedida1(74,1)=cellstr(app.UITable2.Data{74,2});
               Categoria1(74,1)=cellstr(app.UITable2.Data{74,4});
               Identificador1(74)=1;
               app.Matriz(41)=1;
               if Atributo_elegido(app.b1).Atributo(48)==1
               app.Cicli_Interseccion(10)=0.3;    
               app.Peat_CrucepeatonalLat(3)=0.3;
               app.Peat_CrucepeatonalLat(13)=90;
               elseif Atributo_elegido(app.b1).Atributo(48)==2
               app.Cicli_Interseccion(10)=0.4;
               app.Peat_CrucepeatonalLat(3)=0.4;
               app.Peat_CrucepeatonalLat(13)=90;
               elseif Atributo_elegido(app.b1).Atributo(48)==3
               app.Cicli_Interseccion(10)=0.4;
               app.Peat_CrucepeatonalLat(3)=0.4;
               app.Peat_CrucepeatonalLat(13)=90;
               end
               app.Matriz3(41)=Atributo_elegido(app.b1).Atributo(39);
               app.Matriz3(48)=Atributo_elegido(app.b1).Atributo(48);
               app.Matriz2(59)=cellstr('Infraestructura para peatones a desnivel en la carretera secundaria o lateral');
            end
            if app.UITable2.Data{75,5}==1
               Contramedida1(75,1)=cellstr(app.UITable2.Data{75,2});
               Categoria1(75,1)=cellstr(app.UITable2.Data{75,4});
               Identificador1(75)=1;
               app.Matriz(41)=2;
               if Atributo_elegido(app.b1).Atributo(48)==1
               app.Cicli_Interseccion(10)=0.95;    
               app.Peat_CrucepeatonalLat(3)=0.95;
               app.Peat_CrucepeatonalLat(13)=90;
               elseif Atributo_elegido(app.b1).Atributo(48)==2
               app.Cicli_Interseccion(10)=1;
               app.Peat_CrucepeatonalLat(3)=1;
               app.Peat_CrucepeatonalLat(13)=90;
               elseif Atributo_elegido(app.b1).Atributo(48)==3
               app.Cicli_Interseccion(10)=1;
               app.Peat_CrucepeatonalLat(3)=1;
               app.Peat_CrucepeatonalLat(13)=90;
               end
               app.Matriz3(41)=Atributo_elegido(app.b1).Atributo(39);
               app.Matriz3(48)=Atributo_elegido(app.b1).Atributo(48);
               app.Matriz2(60)=cellstr('Cruce peatonal con semáforo en la carretera secundaria o lateral');
            end
            if app.UITable2.Data{76,5}==1
               Contramedida1(76,1)=cellstr(app.UITable2.Data{76,2});
               Categoria1(76,1)=cellstr(app.UITable2.Data{76,4});
               Identificador1(76)=1;
               app.Matriz(41)=4;
               if Atributo_elegido(app.b1).Atributo(48)==1
               app.Cicli_Interseccion(10)=1;    
               app.Peat_CrucepeatonalLat(3)=1;
               app.Peat_CrucepeatonalLat(13)=90;
               elseif Atributo_elegido(app.b1).Atributo(48)==2
               app.Cicli_Interseccion(10)=3.8;
               app.Peat_CrucepeatonalLat(3)=3.8;
               app.Peat_CrucepeatonalLat(13)=90;
               elseif Atributo_elegido(app.b1).Atributo(48)==3
               app.Cicli_Interseccion(10)=3.8;
               app.Peat_CrucepeatonalLat(3)=3.8;
               app.Peat_CrucepeatonalLat(13)=90;
               end
               app.Matriz3(41)=Atributo_elegido(app.b1).Atributo(39);
               app.Matriz3(48)=Atributo_elegido(app.b1).Atributo(48);
               app.Matriz2(61)=cellstr('Cruce peatonal sin semáforo en la vía lateral');
            end
            if app.UITable2.Data{77,5}==1
               Contramedida1(77,1)=cellstr(app.UITable2.Data{77,2});
               Categoria1(77,1)=cellstr(app.UITable2.Data{77,4});
               Identificador1(77)=1;
               app.Matriz(44)=1;
               app.Peat_Alolargocopiloto(1)=0;
               app.Peat_Alolargocopiloto(14)=90;
               app.Matriz3(44)=Atributo_elegido(app.b1).Atributo(44);
               app.Matriz2(62)=cellstr('Acera peatonal del lado del copiloto (con barrera)');
            end
            if app.UITable2.Data{78,5}==1
               Contramedida1(78,1)=cellstr(app.UITable2.Data{78,2});
               Categoria1(78,1)=cellstr(app.UITable2.Data{78,4});
               Identificador1(78)=1;
               app.Matriz(44)=2;
               app.Peat_Alolargocopiloto(1)=0.075;
               app.Peat_Alolargocopiloto(14)=90;
               app.Matriz3(44)=Atributo_elegido(app.b1).Atributo(44);
               app.Matriz2(63)=cellstr('Acera peatonal de lado del copiloto (separada del camino > 3 m)');
            end
            if app.UITable2.Data{79,5}==1
               Contramedida1(79,1)=cellstr(app.UITable2.Data{79,2});
               Categoria1(79,1)=cellstr(app.UITable2.Data{79,4});
               Identificador1(79)=1;
               app.Matriz(44)=3;
               app.Peat_Alolargocopiloto(1)=0.09;
               app.Peat_Alolargocopiloto(14)=90;
               app.Matriz3(44)=Atributo_elegido(app.b1).Atributo(44);
               app.Matriz2(64)=cellstr('Acera peatonal de lado del copiloto (adyacente al camino)');
            end
            if app.UITable2.Data{80,5}==1
               Contramedida1(80,1)=cellstr(app.UITable2.Data{80,2});
               Categoria1(80,1)=cellstr(app.UITable2.Data{80,4});
               Identificador1(80)=1;
               app.Matriz(44)=7;
               app.Peat_Alolargocopiloto(1)=6;
               app.Peat_Alolargocopiloto(14)=90;
               app.Matriz3(44)=Atributo_elegido(app.b1).Atributo(44);
               app.Matriz2(65)=cellstr('Provisión de sendero peatonal informal > 1m del lado del copiloto');
            end
            if app.UITable2.Data{81,5}==1
               Contramedida1(81,1)=cellstr(app.UITable2.Data{81,2});
               Categoria1(81,1)=cellstr(app.UITable2.Data{81,4});
               Identificador1(81)=1;
               app.Matriz(43)=1;
               app.Peat_Alolargoconductor(1)=0;
               app.Peat_Alolargoconductor(14)=90;
               app.Matriz3(43)=Atributo_elegido(app.b1).Atributo(43);
               app.Matriz2(66)=cellstr('Provisión de acera costado del conductor (con barrera)');
            end
            if app.UITable2.Data{82,5}==1
               Contramedida1(82,1)=cellstr(app.UITable2.Data{82,2});
               Categoria1(82,1)=cellstr(app.UITable2.Data{82,4});
               Identificador1(82)=1;
               app.Matriz(43)=2;
               app.Peat_Alolargoconductor(1)=0.075;
               app.Peat_Alolargoconductor(14)=90;
               app.Matriz3(43)=Atributo_elegido(app.b1).Atributo(43);
               app.Matriz2(67)=cellstr('Provisión de acera costado del conductor (>3 m de la vía)');
            end
            if app.UITable2.Data{83,5}==1
               Contramedida1(83,1)=cellstr(app.UITable2.Data{83,2});
               Categoria1(83,1)=cellstr(app.UITable2.Data{83,4});
               Identificador1(83)=1;
               app.Matriz(43)=3;
               app.Peat_Alolargoconductor(1)=0.09;
               app.Peat_Alolargoconductor(14)=90;
               app.Matriz3(43)=Atributo_elegido(app.b1).Atributo(43);
               app.Matriz2(68)=cellstr('Provisión de acera costado del conductor (adyacente al camino)');
            end
            if app.UITable2.Data{84,5}==1
               Contramedida1(84,1)=cellstr(app.UITable2.Data{84,2});
               Categoria1(84,1)=cellstr(app.UITable2.Data{84,4});
               Identificador1(84)=1;
               app.Matriz(43)=7;
               app.Peat_Alolargoconductor(1)=6;
               app.Peat_Alolargoconductor(14)=90;
               app.Matriz3(43)=Atributo_elegido(app.b1).Atributo(43);
               app.Matriz2(69)=cellstr('Provisión de acera costado del conductor (acera informal >1 m)');
            end
            if app.UITable2.Data{85,5}==1
               Contramedida1(85,1)=cellstr(app.UITable2.Data{85,2});
               Categoria1(85,1)=cellstr(app.UITable2.Data{85,4});
               Identificador1(85)=1;
               app.Matriz(42)=1; 
               app.Peat_CrucepeatonalIns(7)=1;
               app.Peat_CrucepeatonalLat(7)=1;
               app.Matriz3(42)=Atributo_elegido(app.b1).Atributo(42);
               app.Matriz2(70)=cellstr('Vallas peatonales');
            end
            if app.UITable2.Data{86,5}==1
               Contramedida1(86,1)=cellstr(app.UITable2.Data{86,2});
               Categoria1(86,1)=cellstr(app.UITable2.Data{86,4});
               Identificador1(86)=1;
               app.Matriz(20)=2;
               app.O_V_Intersecciones(4)=1;
               app.Moto_Intersecciones(4)=1;
               app.Cicli_Alolargo(13)=1;
               app.Cicli_SalidadCamino(5)=1;
               app.Cicli_Interseccion(6)=1;
               app.Peat_Alolargoconductor(12)=1;
               app.Peat_Alolargocopiloto(12)=1;
               app.Peat_CrucepeatonalIns(9)=1;
               app.Peat_CrucepeatonalLat(9)=1;             
               app.Matriz3(20)=Atributo_elegido(app.b1).Atributo(20);
               app.Matriz2(71)=cellstr('Alumbrado público (intersección)');
            end
            if app.UITable2.Data{87,5}==1
               Contramedida1(87,1)=cellstr(app.UITable2.Data{87,2});
               Categoria1(87,1)=cellstr(app.UITable2.Data{87,4});
               Identificador1(87)=1;
               app.Matriz(20)=2;
               app.O_V_Intersecciones(4)=1;
               app.Moto_Intersecciones(4)=1;
               app.Cicli_Alolargo(13)=1;
               app.Cicli_SalidadCamino(5)=1;
               app.Cicli_Interseccion(6)=1;
               app.Peat_Alolargoconductor(12)=1;
               app.Peat_Alolargocopiloto(12)=1;
               app.Peat_CrucepeatonalIns(9)=1;
               app.Peat_CrucepeatonalLat(9)=1;
               app.Matriz3(20)=Atributo_elegido(app.b1).Atributo(20);
               app.Matriz2(72)=cellstr('Alumbrado público (cruce peatonal)');
            end
            if app.UITable2.Data{88,5}==1
               Contramedida1(88,1)=cellstr(app.UITable2.Data{88,2});
               Categoria1(88,1)=cellstr(app.UITable2.Data{88,4});
               Identificador1(88)=1;
               app.Matriz(20)=2;
               app.O_V_Intersecciones(4)=1;
               app.Moto_Intersecciones(4)=1;
               app.Cicli_Alolargo(13)=1;
               app.Cicli_SalidadCamino(5)=1;
               app.Cicli_Interseccion(6)=1;
               app.Peat_Alolargoconductor(12)=1;
               app.Peat_Alolargocopiloto(12)=1;
               app.Peat_CrucepeatonalIns(9)=1;
               app.Peat_CrucepeatonalLat(9)=1;
               app.Matriz3(20)=Atributo_elegido(app.b1).Atributo(20);
               app.Matriz2(73)=cellstr('Alumbrado público (en tramo carretero)');
            end
            if app.UITable2.Data{89,5}==1
               Contramedida1(89,1)=cellstr(app.UITable2.Data{89,2});
               Categoria1(89,1)=cellstr(app.UITable2.Data{89,4});
               Identificador1(89)=1;
               app.Matriz(24)=1;
               app.O_V_Intersecciones(6)=1;
               app.Moto_Intersecciones(6)=1;
               app.Cicli_Alolargo(4)=1;
               app.Cicli_Interseccion(7)=1;
               app.Peat_Alolargoconductor(4)=1;
               app.Peat_Alolargocopiloto(4)=1;
               app.Peat_CrucepeatonalIns(10)=1;
               app.Peat_CrucepeatonalLat(10)=1;
               app.Matriz3(24)=Atributo_elegido(app.b1).Atributo(24);
               app.Matriz2(74)=cellstr('Distancia visual (eliminación de obstrucciones)');
            end
            if app.UITable2.Data{90,5}==1
               Contramedida1(90,1)=cellstr(app.UITable2.Data{90,2});
               Categoria1(90,1)=cellstr(app.UITable2.Data{90,4});
               Identificador1(90)=1;
               app.Matriz(47)=1;
               app.Matriz3(47)=Atributo_elegido(app.b1).Atributo(47);
               app.Matriz2(75)=cellstr('Advertencia de zona escolar – semaforo intermitente');
            end
            if app.UITable2.Data{91,5}==1
               Contramedida1(91,1)=cellstr(app.UITable2.Data{91,2});
               Categoria1(91,1)=cellstr(app.UITable2.Data{91,4});
               Identificador1(91)=1;
               app.Matriz(47)=2;
               app.Matriz3(47)=Atributo_elegido(app.b1).Atributo(47);
               app.Matriz2(76)=cellstr('Advertencia en zona escolar - señales y demarcaciones');
            end
            if app.UITable2.Data{92,5}==1
               Contramedida1(92,1)=cellstr(app.UITable2.Data{92,2});
               Categoria1(92,1)=cellstr(app.UITable2.Data{92,4});
               Identificador1(92)=1;
               app.Matriz(28)=1;
               app.O_V_Intersecciones(2)=1;
               app.Moto_Intersecciones(2)=1;
               app.Peat_CrucepeatonalIns(6)=1;
               app.Peat_CrucepeatonalLat(6)=1;
               app.Cicli_Interseccion(2)=1;
               app.Matriz3(28)=Atributo_elegido(app.b1).Atributo(28);
               app.Matriz2(77)=cellstr('Señalamiento y delineación (intersección)');
            end
            if app.UITable2.Data{93,5}==1
               Contramedida1(93,1)=cellstr(app.UITable2.Data{93,2});
               Categoria1(93,1)=cellstr(app.UITable2.Data{93,4});
               Identificador1(93)=1;
               app.Matriz(15)=1;
               app.O_V_SalidaCaminoconductor(3)=1;
               app.O_V_SalidaCaminocopiloto(3)=1;
               app.O_V_PerdidaControl(3)=1;
               app.Moto_SalidaCaminoconductor(3)=1;
               app.Moto_SalidaCaminocopiloto(3)=1;
               app.Moto_PerdidaControl(3)=1;
               app.Cicli_Alolargo(3)=1;
               app.Cicli_SalidadCamino(3)=1;
               app.Peat_Alolargoconductor(3)=1;
               app.Peat_Alolargocopiloto(3)=1;
               app.Matriz3(15)=Atributo_elegido(app.b1).Atributo(15);
               app.Matriz2(78)=cellstr('Mejorar delineación de la curva');
            end
            if app.UITable2.Data{94,5}==1
               Contramedida1(94,1)=cellstr(app.UITable2.Data{94,2});
               Categoria1(94,1)=cellstr(app.UITable2.Data{94,4});
               Identificador1(94)=1;
               app.Matriz(19)=1;
               app.O_V_SalidaCaminoconductor(4)=1;
               app.O_V_SalidaCaminocopiloto(4)=1;
               app.O_V_PerdidaControl(4)=1;
               app.Moto_SalidaCaminoconductor(4)=1;
               app.Moto_SalidaCaminocopiloto(4)=1;
               app.Moto_PerdidaControl(4)=1;
               app.Cicli_Alolargo(6)=1;
               app.Cicli_SalidadCamino(4)=1;
               app.Peat_Alolargoconductor(6)=1;
               app.Peat_Alolargocopiloto(6)=1;
               app.Matriz3(19)=Atributo_elegido(app.b1).Atributo(19);
               app.Matriz2(79)=cellstr('Mejorar delineación');
            end
            if app.UITable2.Data{95,5}==1
               Contramedida1(95,1)=cellstr(app.UITable2.Data{95,2});
               Categoria1(95,1)=cellstr(app.UITable2.Data{95,4});
               Identificador1(95)=1;
               app.Matriz(29)=4;
               app.O_V_Accpropiedad(1)=1;
               app.O_V_Accpropiedad(4)=0;
               app.Moto_Accpropiedad(1)=1;
               app.Moto_Accpropiedad(3)=0;
               app.Matriz3(29)=Atributo_elegido(app.b1).Atributo(29);
               app.Matriz2(80)=cellstr('Restringir/combinar puntos de acceso directo');
            end
            if app.UITable2.Data{96,5}==1
               Contramedida1(96,1)=cellstr(app.UITable2.Data{96,2});
               Categoria1(96,1)=cellstr(app.UITable2.Data{96,4});
               Identificador1(96)=1;
               app.Matriz(51)=2;
               app.O_V_Intersecciones(8)=1;
               app.Moto_Intersecciones(8)=1;
               app.Cicli_Alolargo(9)=1;
               app.Cicli_Interseccion(9)=1;
               app.Peat_Alolargoconductor(9)=1;
               app.Peat_Alolargocopiloto(9)=1;
               app.Peat_CrucepeatonalIns(12)=1;
               app.Peat_CrucepeatonalLat(12)=1;
               app.Matriz3(51)=Atributo_elegido(app.b1).Atributo(51);
               app.Matriz2(81)=cellstr('Calmantes del tráfico');
            end
            if app.UITable2.Data{97,5}==1
               Contramedida1(97,1)=cellstr(app.UITable2.Data{97,2});
               Categoria1(97,1)=cellstr(app.UITable2.Data{97,4});
               Identificador1(97)=1;
               app.Matriz(21)=2;
               app.Cicli_Alolargo(11)=1.2;
               app.Peat_Alolargoconductor(10)=1.2;
               app.Peat_Alolargocopiloto(10)=1.2;
               app.Peat_CrucepeatonalIns(11)=1.2;
               app.Peat_CrucepeatonalLat(11)=1.2;
               app.Matriz3(21)=Atributo_elegido(app.b1).Atributo(21);
               app.Matriz2(82)=cellstr('Mejoras de estacionamiento');
            end
            if app.UITable2.Data{98,5}==1
               Contramedida1(98,1)=cellstr(app.UITable2.Data{98,2});
               Categoria1(98,1)=cellstr(app.UITable2.Data{98,4});
               Identificador1(98)=1;
               app.Matriz(3)=3;
               app.O_V_SalidaCaminocopiloto(10)=0.35;
               app.Moto_SalidaCaminocopiloto(10)=0.35;
               app.Cicli_SalidadCamino(12)=0.10;
               app.Matriz3(3)=Atributo_elegido(app.b1).Atributo(3);
               app.Matriz2(83)=cellstr('Mejora del talud y terraplenes laterales (carril para bicicletas)');
            end
            if app.UITable2.Data{99,5}==1
               Contramedida1(99,1)=cellstr(app.UITable2.Data{99,2});
               Categoria1(99,1)=cellstr(app.UITable2.Data{99,4});
               Identificador1(99)=1;
               app.Matriz(4)=9;
               app.O_V_SalidaCaminocopiloto(9)=45;
               app.Moto_SalidaCaminocopiloto(9)=45;
               app.Cicli_SalidadCamino(11)=45;
               app.Matriz3(4)=Atributo_elegido(app.b1).Atributo(4);
               app.Matriz2(83)=cellstr('Mejora del talud y terraplenes laterales (carril para bicicletas)');
            end
            if app.UITable2.Data{100,5}==1
               Contramedida1(100,1)=cellstr(app.UITable2.Data{100,2});
               Categoria1(100,1)=cellstr(app.UITable2.Data{100,4});
               Identificador1(100)=1;
               app.Matriz(3)=3;
               app.O_V_SalidaCaminocopiloto(10)=0.35;
               app.Moto_SalidaCaminocopiloto(10)=0.35;
               app.Cicli_SalidadCamino(12)=0.10;
               app.Matriz3(3)=Atributo_elegido(app.b1).Atributo(3);
               app.Matriz2(84)=cellstr('Eliminar peligros laterales (carril para bicicleta)');
            end
            if app.UITable2.Data{101,5}==1
               Contramedida1(101,1)=cellstr(app.UITable2.Data{101,2});
               Categoria1(101,1)=cellstr(app.UITable2.Data{101,4});
               Identificador1(101)=1;
               app.Matriz(4)=17;
               app.O_V_SalidaCaminocopiloto(9)=35;
               app.Moto_SalidaCaminocopiloto(9)=35;
               app.Cicli_SalidadCamino(11)=35;
               app.Matriz3(4)=Atributo_elegido(app.b1).Atributo(4);
               app.Matriz2(84)=cellstr('Eliminar peligros laterales (carril para bicicleta)');
            end
            if app.UITable2.Data{102,5}==1
               Contramedida1(102,1)=cellstr(app.UITable2.Data{102,2});
               Categoria1(102,1)=cellstr(app.UITable2.Data{102,4});
               Identificador1(102)=1;
               app.Matriz(3)=3;
               app.O_V_SalidaCaminocopiloto(10)=0.35;
               app.Moto_SalidaCaminocopiloto(10)=0.35;
               app.Cicli_SalidadCamino(12)=0.10;
               app.Matriz3(3)=Atributo_elegido(app.b1).Atributo(3);
               app.Matriz2(85)=cellstr('Barreras laterales (carril para bicicletas)');
            end
            if app.UITable2.Data{103,5}==1
               Contramedida1(103,1)=cellstr(app.UITable2.Data{103,2});
               Categoria1(103,1)=cellstr(app.UITable2.Data{103,4});
               Identificador1(103)=1;
               app.Matriz(4)=3;
               app.O_V_SalidaCaminocopiloto(9)=12;
               app.Moto_SalidaCaminocopiloto(9)=20;
               app.Cicli_SalidadCamino(11)=20;
               app.Matriz3(4)=Atributo_elegido(app.b1).Atributo(4);
               app.Matriz2(85)=cellstr('Barreras laterales (carril para bicicletas)');
            end
            if app.UITable2.Data{104,5}==1
               Contramedida1(104,1)=cellstr(app.UITable2.Data{104,2});
               Categoria1(104,1)=cellstr(app.UITable2.Data{104,4});
               Identificador1(104)=1;
               app.Matriz(45)=4;
               app.Moto_Alolargo(1)=0.1;
               app.Moto_Alolargo(2)=50; 
               app.Matriz3(45)=Atributo_elegido(app.b1).Atributo(45);
               app.Matriz2(86)=cellstr('Barrera mediana central (carril de motocicletas)');
            end
            if app.UITable2.Data{105,5}==1
               Contramedida1(105,1)=cellstr(app.UITable2.Data{105,2});
               Categoria1(105,1)=cellstr(app.UITable2.Data{105,4});
               Identificador1(105)=1;
               app.Matriz(3)=4;
               app.O_V_SalidaCaminocopiloto(10)=0.10;
               app.Moto_SalidaCaminocopiloto(10)=0.10;
               app.Cicli_SalidadCamino(12)=0.01;
               app.Matriz3(3)=Atributo_elegido(app.b1).Atributo(3);
               app.Matriz2(87)=cellstr('Mejora del talud lateral y terraplene (carril de motocicletas) lado del copiloto');
            end
            if app.UITable2.Data{106,5}==1
               Contramedida1(106,1)=cellstr(app.UITable2.Data{106,2});
               Categoria1(106,1)=cellstr(app.UITable2.Data{106,4});
               Identificador1(106)=1;
               app.Matriz(4)=9;
               app.O_V_SalidaCaminocopiloto(9)=45;
               app.Moto_SalidaCaminocopiloto(9)=45;
               app.Cicli_SalidadCamino(11)=45;
               app.Matriz3(4)=Atributo_elegido(app.b1).Atributo(4);
               app.Matriz2(87)=cellstr('Mejora del talud lateral y terraplene (carril de motocicletas) lado del copiloto');
            end
            if app.UITable2.Data{107,5}==1
               Contramedida1(107,1)=cellstr(app.UITable2.Data{107,2});
               Categoria1(107,1)=cellstr(app.UITable2.Data{107,4});
               Identificador1(107)=1;
               app.Matriz(3)=4;
               app.O_V_SalidaCaminocopiloto(10)=0.10;
               app.Moto_SalidaCaminocopiloto(10)=0.10;
               app.Cicli_SalidadCamino(12)=0.01;
               app.Matriz3(3)=Atributo_elegido(app.b1).Atributo(3);
               app.Matriz2(88)=cellstr('Eliminar peligros laterales evidentes (carril de motocicletas) lado del copiloto');
            end
            if app.UITable2.Data{108,5}==1
               Contramedida1(108,1)=cellstr(app.UITable2.Data{108,2});
               Categoria1(108,1)=cellstr(app.UITable2.Data{108,4});
               Identificador1(108)=1;
               app.Matriz(4)=17;
               app.O_V_SalidaCaminocopiloto(9)=35;
               app.Moto_SalidaCaminocopiloto(9)=35;
               app.Cicli_SalidadCamino(11)=35;
               app.Matriz3(4)=Atributo_elegido(app.b1).Atributo(4);
               app.Matriz2(88)=cellstr('Eliminar peligros laterales evidentes (carril de motocicletas) lado del copiloto');
            end
            if app.UITable2.Data{109,5}==1
               Contramedida1(109,1)=cellstr(app.UITable2.Data{109,2});
               Categoria1(109,1)=cellstr(app.UITable2.Data{109,4});
               Identificador1(109)=1;
               app.Matriz(3)=3;
               app.O_V_SalidaCaminocopiloto(10)=0.35;
               app.Moto_SalidaCaminocopiloto(10)=0.35;
               app.Cicli_SalidadCamino(12)=0.10;
               app.Matriz3(3)=Atributo_elegido(app.b1).Atributo(3);
               app.Matriz2(89)=cellstr('Barreras laterales (carril de motocicletas) lado del copiloto');
            end
            if app.UITable2.Data{110,5}==1
               Contramedida1(110,1)=cellstr(app.UITable2.Data{110,2});
               Categoria1(110,1)=cellstr(app.UITable2.Data{110,4});
               Identificador1(110)=1;
               app.Matriz(4)=3;
               app.O_V_SalidaCaminocopiloto(9)=12;
               app.Moto_SalidaCaminocopiloto(9)=20;
               app.Cicli_SalidadCamino(11)=20;
               app.Matriz3(4)=Atributo_elegido(app.b1).Atributo(4);
               app.Matriz2(89)=cellstr('Barreras laterales (carril de motocicletas) lado del copiloto');
            end
            if app.UITable2.Data{111,5}==1
               Contramedida1(111,1)=cellstr(app.UITable2.Data{111,2});
               Categoria1(111,1)=cellstr(app.UITable2.Data{111,4});
               Identificador1(111)=1;
               app.Matriz(1)=4;
               app.O_V_SalidaCaminoconductor(10)=0.10;
               app.Moto_SalidaCaminoconductor(10)=0.10;
               app.Cicli_SalidadCamino(10)=0.01;
               app.Matriz3(1)=Atributo_elegido(app.b1).Atributo(1);
               app.Matriz2(90)=cellstr('Mejora del talud y terraplen lateral (carril de motocicletas) lado del conductor');
            end
            if app.UITable2.Data{112,5}==1
               Contramedida1(112,1)=cellstr(app.UITable2.Data{112,2});
               Categoria1(112,1)=cellstr(app.UITable2.Data{112,4});
               Identificador1(112)=1;
               app.Matriz(2)=9;
               app.O_V_SalidaCaminoconductor(9)=45;
               app.Moto_SalidaCaminoconductor(9)=45;
               app.Cicli_SalidadCamino(9)=45;
               app.Matriz3(2)=Atributo_elegido(app.b1).Atributo(2);
               app.Matriz2(90)=cellstr('Mejora del talud y terraplen lateral (carril de motocicletas) lado del conductor');
            end
            if app.UITable2.Data{113,5}==1
               Contramedida1(113,1)=cellstr(app.UITable2.Data{113,2});
               Categoria1(113,1)=cellstr(app.UITable2.Data{113,4});
               Identificador1(113)=1;
               app.Matriz(1)=4;
               app.O_V_SalidaCaminoconductor(10)=0.10;
               app.Moto_SalidaCaminoconductor(10)=0.10;
               app.Cicli_SalidadCamino(10)=0.01;
               app.Matriz3(1)=Atributo_elegido(app.b1).Atributo(1);
               app.Matriz2(91)=cellstr('Eliminar peligros laterales evidentes (carril de motocicletas) lado del conductor');
            end
            if app.UITable2.Data{114,5}==1
               Contramedida1(114,1)=cellstr(app.UITable2.Data{114,2});
               Categoria1(114,1)=cellstr(app.UITable2.Data{114,4});
               Identificador1(114)=1;
               app.Matriz(2)=17;
               app.O_V_SalidaCaminoconductor(9)=35;
               app.Moto_SalidaCaminoconductor(9)=35;
               app.Cicli_SalidadCamino(9)=35;
               app.Matriz3(2)=Atributo_elegido(app.b1).Atributo(2);
               app.Matriz2(91)=cellstr('Eliminar peligros laterales evidentes (carril de motocicletas) lado del conductor');
            end 
            if app.UITable2.Data{115,5}==1
               Contramedida1(115,1)=cellstr(app.UITable2.Data{115,2});
               Categoria1(115,1)=cellstr(app.UITable2.Data{115,4});
               Identificador1(115)=1;
               app.Matriz(1)=3;
               app.O_V_SalidaCaminoconductor(10)=0.35;
               app.Moto_SalidaCaminoconductor(10)=0.35;
               app.Cicli_SalidadCamino(10)=0.10;
               app.Matriz3(1)=Atributo_elegido(app.b1).Atributo(1);
               app.Matriz2(92)=cellstr('Barreras laterales (carril para motocicletas) lado del conductor');
            end
            if app.UITable2.Data{116,5}==1
               Contramedida1(116,1)=cellstr(app.UITable2.Data{116,2});
               Categoria1(116,1)=cellstr(app.UITable2.Data{116,4});
               Identificador1(116)=1;
               app.Matriz(2)=3;
               app.O_V_SalidaCaminoconductor(9)=12;
               app.Moto_SalidaCaminoconductor(9)=20;
               app.Cicli_SalidadCamino(9)=20;
               app.Matriz3(2)=Atributo_elegido(app.b1).Atributo(2);
               app.Matriz2(92)=cellstr('Barreras laterales (carril para motocicletas) lado del conductor');
            end
            if app.UITable2.Data{117,5}==1
               Contramedida1(117,1)=cellstr(app.UITable2.Data{117,2});
               Categoria1(117,1)=cellstr(app.UITable2.Data{117,4});
               Identificador1(117)=1;
               app.Matriz(52)=30;
               app.O_V_SalidaCaminoconductor(12)=0;
               app.O_V_SalidaCaminocopiloto(12)=0;
               app.O_V_PerdidaControl(10)=0;
               app.O_V_Adelantamiento(6)=0;
               app.O_V_Intersecciones(10)=0;
               app.O_V_Accpropiedad(5)=0;
               app.Moto_SalidaCaminoconductor(12)=0;
               app.Moto_SalidaCaminocopiloto(12)=0;
               app.Moto_PerdidaControl(10)=0;
               app.Moto_Adelantamiento(6)=0;
               app.Moto_Intersecciones(10)=0;
               app.Moto_Accpropiedad(4)=0;
               app.Moto_Alolargo(3)=0;   
                   
               if Atributo_elegido(app.b1).Atributo(2)==10
                   app.O_V_SalidaCaminoconductor(12)=0.20;
                   app.O_V_SalidaCaminocopiloto(12)=0.20;
                   app.O_V_PerdidaControl(10)=0.20;
                   app.O_V_Adelantamiento(6)=0.20;
                   app.O_V_Intersecciones(10)=0.20;
                   app.O_V_Accpropiedad(5)=0.20;
                   app.Moto_SalidaCaminoconductor(12)=0.20;
                   app.Moto_SalidaCaminocopiloto(12)=0.20;
                   app.Moto_PerdidaControl(10)=0.20;
                   app.Moto_Adelantamiento(6)=0.20;
                   app.Moto_Intersecciones(10)=0.20;
                   app.Moto_Accpropiedad(4)=0.20;
                   app.Moto_Alolargo(3)=0.20;
               elseif Atributo_elegido(app.b1).Atributo(4)==10
                   app.O_V_SalidaCaminoconductor(12)=0.20;
                   app.O_V_SalidaCaminocopiloto(12)=0.20;
                   app.O_V_PerdidaControl(10)=0.20;
                   app.O_V_Adelantamiento(6)=0.20;
                   app.O_V_Intersecciones(10)=0.20;
                   app.O_V_Accpropiedad(5)=0.20;
                   app.Moto_SalidaCaminoconductor(12)=0.20;
                   app.Moto_SalidaCaminocopiloto(12)=0.20;
                   app.Moto_PerdidaControl(10)=0.20;
                   app.Moto_Adelantamiento(6)=0.20;
                   app.Moto_Intersecciones(10)=0.20;
                   app.Moto_Accpropiedad(4)=0.20;
                   app.Moto_Alolargo(3)=0.20;
               elseif Atributo_elegido(app.b1).Atributo(25)==12
                   app.O_V_SalidaCaminoconductor(12)=0.20;
                   app.O_V_SalidaCaminocopiloto(12)=0.20;
                   app.O_V_PerdidaControl(10)=0.20;
                   app.O_V_Adelantamiento(6)=0.20;
                   app.O_V_Intersecciones(10)=0.20;
                   app.O_V_Accpropiedad(5)=0.20;
                   app.Moto_SalidaCaminoconductor(12)=0.20;
                   app.Moto_SalidaCaminocopiloto(12)=0.20;
                   app.Moto_PerdidaControl(10)=0.20;
                   app.Moto_Adelantamiento(6)=0.20;
                   app.Moto_Intersecciones(10)=0.20;
                   app.Moto_Accpropiedad(4)=0.20;
                   app.Moto_Alolargo(3)=0.20;
               elseif Atributo_elegido(app.b1).Atributo(25)==13
                   app.O_V_SalidaCaminoconductor(12)=0.20;
                   app.O_V_SalidaCaminocopiloto(12)=0.20;
                   app.O_V_PerdidaControl(10)=0.20;
                   app.O_V_Adelantamiento(6)=0.20;
                   app.O_V_Intersecciones(10)=0.20;
                   app.O_V_Accpropiedad(5)=0.20;
                   app.Moto_SalidaCaminoconductor(12)=0.20;
                   app.Moto_SalidaCaminocopiloto(12)=0.20;
                   app.Moto_PerdidaControl(10)=0.20;
                   app.Moto_Adelantamiento(6)=0.20;
                   app.Moto_Intersecciones(10)=0.20;
                   app.Moto_Accpropiedad(4)=0.20;
                   app.Moto_Alolargo(3)=0.20;
               end

               app.Cicli_Alolargo(15)=0.01;
               app.Cicli_SalidadCamino(13)=0.01;
               app.Cicli_Interseccion(12)=0.01;
               app.Peat_Alolargoconductor(15)=0.01;
               app.Peat_Alolargocopiloto(15)=0.01;
               app.Peat_CrucepeatonalIns(14)=0.01;
               app.Peat_CrucepeatonalLat(14)=0.01;
               app.Matriz3(52)=Atributo_elegido(app.b1).Atributo(52);
               app.Matriz2(93)=cellstr('Revisiones de la gestión de velocidad');
            end
            if app.UITable2.Data{118,5}==1
               Contramedida1(118,1)=cellstr(app.UITable2.Data{118,2});
               Categoria1(118,1)=cellstr(app.UITable2.Data{118,4});
               Identificador1(118)=1;
               app.Matriz(49)=30; 
               app.Matriz3(49)=Atributo_elegido(app.b1).Atributo(49);
               app.Matriz2(94)=cellstr('Revisiones de la gestión de velocidad (carril de motocicletas)');
            end

        [cosalidaconductorveh,cosalidacopilotoveh,coperdidacontrolveh,coadelantamientoveh,cointerseccionveh,coaccesopropiedadesveh,cosalidaconductormot,cosalidacopilotomot,coperdidacontrolmot,coadelantamientomot,cointerseccionmot,coaccesopropiedadesmot,colargomot,colargoci,cosalidaci,cointerseccionci,colargoconpe,colargocoppe,coinsppe,colatpe,EstVehiculo,EstMotocicleta,EstCiclista,EstPeaton,EstVehicul,EstMoto,EstCicl,EstPeato]=ESTRELLAS(app.Matriz);
         app.OcupantedevehculosEditField_5.Value=EstVehicul;
         app.MotociclistaEditField_5.Value=EstMoto;
         app.CiclistaEditField_5.Value=EstCicl;
         app.PeatnEditField_5.Value=EstPeato;
         
         app.OcupantedevehculosEditField_6.Value=EstVehiculo;
         app.MotociclistaEditField_6.Value=EstMotocicleta;
         app.CiclistaEditField_6.Value=EstCiclista;
         app.PeatnEditField_6.Value=EstPeaton;
         
         load('../mat/Matriz_factores_contramedidas_postproceso.mat')
         Matriz_y(app.b1).Factores1=app.O_V_SalidaCaminoconductor;
         Matriz_y(app.b1).Factores2=app.O_V_SalidaCaminocopiloto;
         Matriz_y(app.b1).Factores3=app.O_V_PerdidaControl;
         Matriz_y(app.b1).Factores4=app.O_V_Adelantamiento;
         Matriz_y(app.b1).Factores5=app.O_V_Intersecciones;
         Matriz_y(app.b1).Factores6=app.O_V_Accpropiedad;
         Matriz_y(app.b1).Factores7=app.Moto_SalidaCaminoconductor;
         Matriz_y(app.b1).Factores8=app.Moto_SalidaCaminocopiloto;
         Matriz_y(app.b1).Factores9=app.Moto_PerdidaControl;
         Matriz_y(app.b1).Factores10=app.Moto_Adelantamiento;
         Matriz_y(app.b1).Factores11=app.Moto_Intersecciones;
         Matriz_y(app.b1).Factores12=app.Moto_Accpropiedad;
         Matriz_y(app.b1).Factores13=app.Moto_Alolargo;
         Matriz_y(app.b1).Factores14=app.Cicli_Alolargo ;
         Matriz_y(app.b1).Factores15=app.Cicli_SalidadCamino;
         Matriz_y(app.b1).Factores16=app.Cicli_Interseccion;
         Matriz_y(app.b1).Factores17=app.Peat_Alolargoconductor;
         Matriz_y(app.b1).Factores18=app.Peat_Alolargocopiloto;
         Matriz_y(app.b1).Factores19=app.Peat_CrucepeatonalIns;
         Matriz_y(app.b1).Factores20=app.Peat_CrucepeatonalLat;
         
         save('../mat/Matriz_factores_contramedidas_postproceso.mat','Matriz_y')

         load('../mat/Matriz_indices_nuevos_postproceso.mat')
         Matriz_x(app.b1).Atributos=app.Matriz;
         save('../mat/Matriz_indices_nuevos_postproceso','Matriz_x')
         
         load('../mat/Matriz_contramedidas_postproceso.mat')
         Matriz_x1(app.b1).Factor=app.Matriz3;
         save('../mat/Matriz_contramedidas_postproceso','Matriz_x1')
         
         load('../mat/Nueva_clasificación_postproceso.mat')
         Nuevos_datos(app.b1).Nueva_Estrella_Ocupante_vehiculo=app.OcupantedevehculosEditField_5.Value;
         Nuevos_datos(app.b1).Nueva_Estrella_Motociclista=app.MotociclistaEditField_5.Value;
         Nuevos_datos(app.b1).Nueva_Estrella_Ciclista=app.CiclistaEditField_5.Value;
         Nuevos_datos(app.b1).Nueva_Estrella_Peaton=app.PeatnEditField_5.Value;

         Nuevos_datos(app.b1).Nuevo_Puntaje_Ocupante_vehiculo=app.OcupantedevehculosEditField_6.Value;
         Nuevos_datos(app.b1).Nuevo_Puntaje_Motociclista=app.MotociclistaEditField_6.Value;
         Nuevos_datos(app.b1).Nuevo_Puntaje_Ciclista=app.CiclistaEditField_6.Value;
         Nuevos_datos(app.b1).Nuevo_Puntaje_Peaton=app.PeatnEditField_6.Value;
         save('../mat/Nueva_clasificación_postproceso',"Nuevos_datos")
         
         %Para obtener la tabla de las contramedidas aplicadas
         T = table(Contramedida1,Categoria1,Identificador1);
         col3 = Identificador1;
         columnas = ismember (col3, 1 );
         columnasextraidas = T (columnas, :);
         Contramedidas=columnasextraidas.Contramedida1;
         Categoria=columnasextraidas.Categoria1;
         app.Tablacontramedidas=table(Contramedidas,Categoria);
         
         load('../mat/Matriz_contramedidas_seleccionadas_postproceso.mat')
         Matriz_contramedidas(app.b1).Resultadoscontramedidas= app.Tablacontramedidas;
         save('../mat/Matriz_contramedidas_seleccionadas_postproceso','Matriz_contramedidas')
         
         Datos_Tcontramedidas(app.b1).Contramedida1 = app.Matriz2(1);
            Datos_Tcontramedidas(app.b1).Contramedida2 = app.Matriz2(2);
            Datos_Tcontramedidas(app.b1).Contramedida3 = app.Matriz2(3);
            Datos_Tcontramedidas(app.b1).Contramedida4 = app.Matriz2(4);
            Datos_Tcontramedidas(app.b1).Contramedida5 = app.Matriz2(5);
            Datos_Tcontramedidas(app.b1).Contramedida6 = app.Matriz2(6);
            Datos_Tcontramedidas(app.b1).Contramedida7 = app.Matriz2(7);
            Datos_Tcontramedidas(app.b1).Contramedida8 = app.Matriz2(8);
            Datos_Tcontramedidas(app.b1).Contramedida9 = app.Matriz2(9);
            Datos_Tcontramedidas(app.b1).Contramedida10 = app.Matriz2(10);
            Datos_Tcontramedidas(app.b1).Contramedida11 = app.Matriz2(11);
            Datos_Tcontramedidas(app.b1).Contramedida12 = app.Matriz2(12);
            Datos_Tcontramedidas(app.b1).Contramedida13 = app.Matriz2(13);
            Datos_Tcontramedidas(app.b1).Contramedida14 = app.Matriz2(14);
            Datos_Tcontramedidas(app.b1).Contramedida15 = app.Matriz2(15);
            Datos_Tcontramedidas(app.b1).Contramedida16 = app.Matriz2(16);
            Datos_Tcontramedidas(app.b1).Contramedida17 = app.Matriz2(17);
            Datos_Tcontramedidas(app.b1).Contramedida18 = app.Matriz2(18);
            Datos_Tcontramedidas(app.b1).Contramedida19 = app.Matriz2(19);
            Datos_Tcontramedidas(app.b1).Contramedida20 = app.Matriz2(20);
            Datos_Tcontramedidas(app.b1).Contramedida21 = app.Matriz2(21);
            Datos_Tcontramedidas(app.b1).Contramedida22 = app.Matriz2(22);
            Datos_Tcontramedidas(app.b1).Contramedida23 = app.Matriz2(23);
            Datos_Tcontramedidas(app.b1).Contramedida24 = app.Matriz2(24);
            Datos_Tcontramedidas(app.b1).Contramedida25 = app.Matriz2(25);
            Datos_Tcontramedidas(app.b1).Contramedida26 = app.Matriz2(26);
            Datos_Tcontramedidas(app.b1).Contramedida27 = app.Matriz2(27);
            Datos_Tcontramedidas(app.b1).Contramedida28 = app.Matriz2(28);
            Datos_Tcontramedidas(app.b1).Contramedida29 = app.Matriz2(29);
            Datos_Tcontramedidas(app.b1).Contramedida30 = app.Matriz2(30);
            Datos_Tcontramedidas(app.b1).Contramedida31 = app.Matriz2(31);
            Datos_Tcontramedidas(app.b1).Contramedida32 = app.Matriz2(32);
            Datos_Tcontramedidas(app.b1).Contramedida33 = app.Matriz2(33);
            Datos_Tcontramedidas(app.b1).Contramedida34 = app.Matriz2(34);
            Datos_Tcontramedidas(app.b1).Contramedida35 = app.Matriz2(35);
            Datos_Tcontramedidas(app.b1).Contramedida36 = app.Matriz2(36);
            Datos_Tcontramedidas(app.b1).Contramedida37 = app.Matriz2(37);
            Datos_Tcontramedidas(app.b1).Contramedida38 = app.Matriz2(38);
            Datos_Tcontramedidas(app.b1).Contramedida39 = app.Matriz2(39);
            Datos_Tcontramedidas(app.b1).Contramedida40 = app.Matriz2(40);
            Datos_Tcontramedidas(app.b1).Contramedida41 = app.Matriz2(41);
            Datos_Tcontramedidas(app.b1).Contramedida42 = app.Matriz2(42);
            Datos_Tcontramedidas(app.b1).Contramedida43 = app.Matriz2(43);
            Datos_Tcontramedidas(app.b1).Contramedida44 = app.Matriz2(44);
            Datos_Tcontramedidas(app.b1).Contramedida45 = app.Matriz2(45);
            Datos_Tcontramedidas(app.b1).Contramedida46 = app.Matriz2(46);
            Datos_Tcontramedidas(app.b1).Contramedida47 = app.Matriz2(47);
            Datos_Tcontramedidas(app.b1).Contramedida48 = app.Matriz2(48);
            Datos_Tcontramedidas(app.b1).Contramedida49 = app.Matriz2(49);
            Datos_Tcontramedidas(app.b1).Contramedida50 = app.Matriz2(50);
            Datos_Tcontramedidas(app.b1).Contramedida51 = app.Matriz2(51);
            Datos_Tcontramedidas(app.b1).Contramedida52 = app.Matriz2(52);
            Datos_Tcontramedidas(app.b1).Contramedida53 = app.Matriz2(53);
            Datos_Tcontramedidas(app.b1).Contramedida54 = app.Matriz2(54);
            Datos_Tcontramedidas(app.b1).Contramedida55 = app.Matriz2(55);
            Datos_Tcontramedidas(app.b1).Contramedida56 = app.Matriz2(56);
            Datos_Tcontramedidas(app.b1).Contramedida57 = app.Matriz2(57);
            Datos_Tcontramedidas(app.b1).Contramedida58 = app.Matriz2(58);
            Datos_Tcontramedidas(app.b1).Contramedida59 = app.Matriz2(59);
            Datos_Tcontramedidas(app.b1).Contramedida60 = app.Matriz2(60);
            Datos_Tcontramedidas(app.b1).Contramedida61 = app.Matriz2(61);
            Datos_Tcontramedidas(app.b1).Contramedida62 = app.Matriz2(62);
            Datos_Tcontramedidas(app.b1).Contramedida63 = app.Matriz2(63);
            Datos_Tcontramedidas(app.b1).Contramedida64 = app.Matriz2(64);
            Datos_Tcontramedidas(app.b1).Contramedida65 = app.Matriz2(65);
            Datos_Tcontramedidas(app.b1).Contramedida66 = app.Matriz2(66);
            Datos_Tcontramedidas(app.b1).Contramedida67 = app.Matriz2(67);
            Datos_Tcontramedidas(app.b1).Contramedida68 = app.Matriz2(68);
            Datos_Tcontramedidas(app.b1).Contramedida69 = app.Matriz2(69);
            Datos_Tcontramedidas(app.b1).Contramedida70 = app.Matriz2(70);
            Datos_Tcontramedidas(app.b1).Contramedida71 = app.Matriz2(71);
            Datos_Tcontramedidas(app.b1).Contramedida72 = app.Matriz2(72);
            Datos_Tcontramedidas(app.b1).Contramedida73 = app.Matriz2(73);
            Datos_Tcontramedidas(app.b1).Contramedida74 = app.Matriz2(74);
            Datos_Tcontramedidas(app.b1).Contramedida75 = app.Matriz2(75);
            Datos_Tcontramedidas(app.b1).Contramedida76 = app.Matriz2(76);
            Datos_Tcontramedidas(app.b1).Contramedida77 = app.Matriz2(77);
            Datos_Tcontramedidas(app.b1).Contramedida78 = app.Matriz2(78);
            Datos_Tcontramedidas(app.b1).Contramedida79 = app.Matriz2(79);
            Datos_Tcontramedidas(app.b1).Contramedida80 = app.Matriz2(80);
            Datos_Tcontramedidas(app.b1).Contramedida81 = app.Matriz2(81);
            Datos_Tcontramedidas(app.b1).Contramedida82 = app.Matriz2(82);
            Datos_Tcontramedidas(app.b1).Contramedida83 = app.Matriz2(83);
            Datos_Tcontramedidas(app.b1).Contramedida84 = app.Matriz2(84);
            Datos_Tcontramedidas(app.b1).Contramedida85 = app.Matriz2(85);
            Datos_Tcontramedidas(app.b1).Contramedida86 = app.Matriz2(86);
            Datos_Tcontramedidas(app.b1).Contramedida87 = app.Matriz2(87);
            Datos_Tcontramedidas(app.b1).Contramedida88 = app.Matriz2(88);
            Datos_Tcontramedidas(app.b1).Contramedida89 = app.Matriz2(89);
            Datos_Tcontramedidas(app.b1).Contramedida90 = app.Matriz2(90);
            Datos_Tcontramedidas(app.b1).Contramedida91 = app.Matriz2(91);
            Datos_Tcontramedidas(app.b1).Contramedida92 = app.Matriz2(92);
            Datos_Tcontramedidas(app.b1).Contramedida93 = app.Matriz2(93);
            Datos_Tcontramedidas(app.b1).Contramedida94 = app.Matriz2(94);
            
            save('../mat/Matriz_resumen_contramedidas_postproceso','Datos_Tcontramedidas')
         
         %Para llamar a la aplicación que realiza el cálculo de los MLG
         MLG(app.TramosguardadosSpinner.Value)
        end

        % Callback function
        function UITable_55CellEdit(app, event)
            
        end

        % Cell edit callback: UITable2
        function UITable2CellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
            
            app.OcupantedevehculosEditField_5.Value=0;
            app.MotociclistaEditField_5.Value=0;
            app.CiclistaEditField_5.Value=0;
            app.PeatnEditField_5.Value=0;
            app.OcupantedevehculosEditField_6.Value=0;
            app.MotociclistaEditField_6.Value=0;
            app.CiclistaEditField_6.Value=0;
            app.PeatnEditField_6.Value=0;
            
            %TramosguardadosSpinnerValueChanged(app)
            %app.TramosguardadosSpinner.Value=app.TramosguardadosSpinner.Value+1;
            
            app.Matriz=[];
            app.UITable2.Data{:,5}=0;
            
            
        end

        % Button pushed function: PDFButton_2
        function PDFButton_2Pushed(app, event)

            load('../mat/Datos_interfaz_dos_proceso.mat');
            c1=sum(arrayfun(@(Datos_interfaz_dos) ~isempty(Datos_interfaz_dos.Tramos),Datos_interfaz_dos));
            a=load('../mat/Datos_interfaz_dos_proceso.mat');
            Infinicial=load('../mat/Inf_proyecto.mat');
            

            % Reporte # 2
            import mlreportgen.report.*
            import mlreportgen.dom.*
            rpt_name = strcat('Reporte Detallado - Tramo', int2str(app.b1));
            rpt = Report(rpt_name,'pdf');
            %rpt = Report(' Reporte Contramedidas', 'pdf');
            
            %Portada
            Tstyle={HAlign('center'),Bold(1),FontFamily('Gotham'),...
             FontSize('20pt'),Color('Black'),...
             OuterMargin('0in','0in','.5in','.5in'),...
             HAlign('center')};             

            titlepg = TitlePage();
            tp = Paragraph('UNIVERSIDAD TÉCNICA PARTICULAR DE LOJA');
            tp.Style=Tstyle;
            titlepg.Title = tp;
            
            a=Paragraph('Sistema Integrado para La Valoración VIAl (SILVVIA)');
            a.Style=Tstyle;
            titlepg.Subtitle = a;
            
            a1=Paragraph('Reporte de la aplicación de las contramedidas en un tramo de carretera');
            a1.Style=Tstyle;
            titlepg.Author = a1;
            
            add(rpt,titlepg)
            
            %Tabla de contenidos
            add(rpt,TableOfContents);

            %Capítulos
            %Descargo de responsabilidad
            chap0 = Chapter('Descargo de responsabilidad');
            p=Paragraph('El software SILVVIA y su documentación es propiedad intelectual de la Universidad Técnica Particular de Loja (UTPL). SILVVIA fue desarrollado para estimar el nivel de seguridad que ofrece las carreteras rurales utilizando el método iRAP. El usuario comprende los supuestos de la metodología utilizada en el software. La interpretación de los resultados de SILVVIA son de exclusiva responsabilidad del usuario. En ningún caso la UTPL será responsable por daños directos, indirectos, especiales, incidentales o consecuentes que surjan a partir del uso del programa y/o documentación asociada. El usuario comprende que SILVVIA es un software para fines educativos e informativos, los fines comerciales sin el permiso por escrito de los titulares de los derechos de autor está prohibida. Si no está satisfecho con alguna parte del software SILVVIA o con alguno de estos términos de uso, su único y exclusivo recurso es dejar de usar el programa. ');
            p.FontFamilyName='Gotham';
            p.HAlign='justify';
            add(chap0,p)
            add(rpt,chap0);
            
            %Capítulo 1
            chap = Chapter('Información del archivo');
            
            Ubi1='Nombre del proyecto:';
            Ubi2=Infinicial.T_proyecto;
            p1=Paragraph(strcat(Ubi1," ",Ubi2));
            p1.FontFamilyName='Gotham';
            add(chap,p1);
            
            Ubi1='Ubicación del proyecto:';
            Ubi2=Infinicial.Lugar;
            p1=Paragraph(strcat(Ubi1," ",Ubi2));
            p1.FontFamilyName='Gotham';
            add(chap,p1);
            
            Tramo1='Tramo analizado: #';
            Tramo2=num2str(app.b1);
            p1=Paragraph(strcat(Tramo1," ",Tramo2));
            p1.FontFamilyName='Gotham';
            add(chap,p1);

            LonTramo1='Longitud del tramo analizado:';
            LonTramo2=num2str(app.Datos_interfaz_2.Distancia(app.b1));
            LonTramo3='metros';
            p1=Paragraph(strcat(LonTramo1," ",LonTramo2," ",LonTramo3));
            p1.FontFamilyName='Gotham';
            add(chap,p1);

            fecha1='Fecha de elaboración del análisis:';
            fecha2=num2str(date());
            p1=Paragraph(strcat(fecha1," ",fecha2));
            p1.FontFamilyName='Gotham';
            add(chap,p1);
 
            add(rpt,chap);
            
            
            %Capítulo 2
            chap2 = Chapter('Condiciones iniciales del tramo');
          
            %Estilo de tabla establecido
            tableStyle = { ...
                Width('100%'), ...
                Border('solid','black','1px'), ...
                ColSep('solid','black','1px'), ...
                FontFamily('Gotham'),...
                Hyphenation(false),...
                RowSep('solid','black','1px') ...
                };
            %Ancho de las tablas
            grps(1) = TableColSpecGroup;
            grps(1).Span = 3;
            
            specs(1) = TableColSpec;
            specs(1).Span = 1;
            specs(1).Style = {Width("60%"),HAlign('left')};
            
            specs(2) = TableColSpec;
            specs(2).Span = 2;
            specs(2).Style = {Width("40%"),HAlign('center')};
            
            grps(1).ColSpecs = specs;
            
            %Estilo establecido para los encabezados de las tablas
            HeaderStyle = {Bold(),BackgroundColor('lightgray'),FontSize('10pt'),FontFamily('Gotham')};
            
            %Estilo establecido para el cuerpo de la tabla
            BodyStyle={BackgroundColor('paleturquoise'),Color('black'),FontSize('10pt'),FontFamily('Gotham'),HAlign('left')};
            
            %Sin contramedidas
            p1=Paragraph('Atributos del tramo');
            p1.Bold=1;
            p1.FontFamilyName='Gotham';
            add(chap2,p1)
            add(chap2,Paragraph(''));
            
            p1=Paragraph('a) Costado de la vía');
            p1.Underline='Single';
            p1.FontFamilyName='Gotham';
            add(chap2,p1);
            add(chap2,Paragraph(''));
            Tabla1=FormalTable(app.UITable_55.Data);
            Tabla1.ColSpecGroups = grps;
            Tabla1.TableEntriesVAlign = 'middle';
            Tabla1.TableEntriesHAlign = 'center';
            Tabla1.Style = tableStyle;
            r = TableRow();
            append(r,TableEntry('Atributos'));
            append(r,TableEntry('Datos seleccionados'));
            append(Tabla1.Header,r);
            Tabla1.Header.Children(1).Style =HeaderStyle;
            Tabla1.Body.Style=BodyStyle;
            add(chap2,Tabla1);
            add(chap2,Paragraph(''))
            
            p1=Paragraph('b) Bloque central');
            p1.Underline='Single';
            p1.FontFamilyName='Gotham';
            add(chap2,p1);
            add(chap2,Paragraph(''));
            Tabla2=FormalTable(app.UITable_56.Data);
            Tabla2.ColSpecGroups = grps;
            Tabla2.TableEntriesVAlign = 'middle';
            Tabla2.TableEntriesHAlign = 'center';
            Tabla2.Style = tableStyle;
            r = TableRow();
            append(r,TableEntry('Atributos'));
            append(r,TableEntry('Datos seleccionados'));
            append(Tabla2.Header,r);
            Tabla2.Header.Children(1).Style =HeaderStyle;
            Tabla2.Body.Style=BodyStyle;
            add(chap2,Tabla2);
            add(chap2,Paragraph(''))
            
            p1=Paragraph('c) Intersecciones');
            p1.Underline='Single';
            p1.FontFamilyName='Gotham';
            add(chap2,p1);
            add(chap2,Paragraph(''));
            Tabla3=FormalTable(app.UITable_57.Data);
            Tabla3.ColSpecGroups = grps;
            Tabla3.TableEntriesVAlign = 'middle';
            Tabla3.TableEntriesHAlign = 'center';
            Tabla3.Style = tableStyle;
            r = TableRow();
            append(r,TableEntry('Atributos'));
            append(r,TableEntry('Datos seleccionados'));
            append(Tabla3.Header,r);
            Tabla3.Header.Children(1).Style =HeaderStyle;
            Tabla3.Body.Style=BodyStyle;
            add(chap2,Tabla3);
            add(chap2,Paragraph(''))
            
            p1=Paragraph('d) Flujo');
            p1.Underline='Single';
            p1.FontFamilyName='Gotham';
            add(chap2,p1);
            add(chap2,Paragraph(''));
            Tabla4=FormalTable(app.UITable_58.Data);
            Tabla4.ColSpecGroups = grps;
            Tabla4.TableEntriesVAlign = 'middle';
            Tabla4.TableEntriesHAlign = 'center';
            Tabla4.Style = tableStyle;
            r = TableRow();
            append(r,TableEntry('Atributos'));
            append(r,TableEntry('Datos seleccionados'));
            append(Tabla4.Header,r);
            Tabla4.Header.Children(1).Style =HeaderStyle;
            Tabla4.Body.Style=BodyStyle;
            add(chap2,Tabla4);
            add(chap2,Paragraph(''))
            
            p1=Paragraph('e) Infraestructura para usuarios vulnerables y uso del suelo');
            p1.Underline='Single';
            p1.FontFamilyName='Gotham';
            add(chap2,p1);
            add(chap2,Paragraph(''));
            Tabla5=FormalTable(app.UITable_59.Data);
            Tabla5.ColSpecGroups = grps;
            Tabla5.TableEntriesVAlign = 'middle';
            Tabla5.TableEntriesHAlign = 'center';
            Tabla5.Style = tableStyle;
            r = TableRow();
            append(r,TableEntry('Atributos'));
            append(r,TableEntry('Datos seleccionados'));
            append(Tabla5.Header,r);
            Tabla5.Header.Children(1).Style =HeaderStyle;
            Tabla5.Body.Style=BodyStyle;
            add(chap2,Tabla5);
            add(chap2,Paragraph(''))


            p1=Paragraph('e) Velocidades');
            p1.Underline='Single';
            p1.FontFamilyName='Gotham';
            add(chap2,p1);
            add(chap2,Paragraph(''));
            Tabla6=FormalTable(app.UITable_60.Data);
            Tabla6.ColSpecGroups = grps;
            Tabla6.TableEntriesVAlign = 'middle';
            Tabla6.TableEntriesHAlign = 'center';
            Tabla6.Style = tableStyle;
            r = TableRow();
            append(r,TableEntry('Atributos'));
            append(r,TableEntry('Datos seleccionados'));
            append(Tabla6.Header,r);
            Tabla6.Header.Children(1).Style =HeaderStyle;
            Tabla6.Body.Style=BodyStyle;
            add(chap2,Tabla6);
            add(chap2,Paragraph(''))
            
            %Nuevo ancho de las tablas
            grps1(1) = TableColSpecGroup;
            grps1(1).Span = 3;
            
            specs1(1) = TableColSpec;
            specs1(1).Span = 1;
            specs1(1).Style = {Width("30%"),HAlign('left')};
            
            specs1(2) = TableColSpec;
            specs1(2).Span = 2;
            specs1(2).Style = {Width("35%"),HAlign('center')};
            
            grps1(1).ColSpecs = specs1;
            
            
            p1=Paragraph('En la siguiente tabla se presenta el puntaje y la clasificación por estrellas que alcanzó el tramo analizado inicialmente.');
            p1.FontFamilyName='Gotham';
            add(chap2,p1);
            add(chap2,Paragraph(''));
            
            k1='Ocupante de vehículos';
            k1_1=round(app.OcupantedevehculosEditField_4.Value,3);

            k2='Motociclista';
            k2_1=round(app.MotociclistaEditField_4.Value,3);

            k3='Ciclistas';
            k3_1=round(app.CiclistaEditField_4.Value,3);
 
            k4='Peatón';
            k4_1=round(app.PeatnEditField_4.Value,3);

            Nombres19={k1;k2;k3;k4};
            F9=[k1_1;k2_1;k3_1;k4_1];

            %Ocupante de vehículos
            k5_1=round(app.OcupantedevehculosEditField_3.Value,3);
            j5=num2str(k5_1);
            if app.OcupantedevehculosEditField_3.Value==1 
                f5='estrella';
            else
                f5='estrellas';
            end
            K9_1=(strcat(j5," ",f5));

            
            %Motociclista
            k6_1=round(app.MotociclistaEditField_3.Value,3);
            j6=num2str(k6_1);
            if app.MotociclistaEditField_3.Value==1 
                f6='estrella';
            else
                f6='estrellas';
            end
            K10_1=(strcat(j6," ",f6));

            
            %Ciclistas
            k7_1=round(app.CiclistaEditField_3.Value,3);
            j7=num2str(k7_1);
            if app.CiclistaEditField_3.Value==1 
                f7='estrella';
            else
                f7='estrellas';
            end
            K11_1=(strcat(j7," ",f7));
            
            %Peatón
            k8_1=round(app.PeatnEditField_3.Value,3);
            j8=num2str(k8_1);
            if app.PeatnEditField_3.Value==1 
                f8='estrella';
            else
                f8='estrellas';
            end
            K12_1=(strcat(j8," ",f8));
            
            E9={K9_1;K10_1;K11_1;K12_1};
            T_19=table(categorical(Nombres19),f(F9),E9);
            T_19.Properties.VariableNames = {'Usuario Viales', 'Puntaje de clasificación por estrellas (SRS)','Clasificación por estrellas (SR)'};
            Tabla1_1=FormalTable(T_19);
            
            Tabla1_1.ColSpecGroups = grps1;
            Tabla1_1.TableEntriesVAlign = 'middle';
            Tabla1_1.TableEntriesHAlign = 'center';
            Tabla1_1.Style = tableStyle;
            Tabla1_1.Header.Children(1).Style =HeaderStyle;
            Tabla1_1.Body.Style=BodyStyle;
            add(chap2,Tabla1_1);
            add(chap2,Paragraph(''))
            
            add(rpt,chap2);
            
            %Capítulo 3
            chap3 = Chapter('Aplicación de las contramedidas');

            p1=Paragraph('Es recomendable obtener un número de estrellas igual o superior a 3 para que la carretera sea considerada como segura, lo cual se puede realizar empleando una medida de mejoramiento (contramedida) o combinación de ellas. A continuación se presentan las contramedidas propuestas para mejorar la clasificación del tramo en análisis.');
            p1.FontFamilyName='Gotham';
            p1.HAlign='justify';
            add(chap3,p1);
            add(chap3,Paragraph(''));
            
            p1=Paragraph('Contramedidas propuestas');
            p1.FontFamilyName='Gotham';
            p1.Bold=1;
            p1.HAlign='justify';
            add(chap3,p1);
            add(chap3,Paragraph(''));
            
            ContramediasTabla=app.Tablacontramedidas;

            ContramediasTabla.Properties.VariableNames = {'Contramedidas','Categoría'};
            
            Tabla2=FormalTable(ContramediasTabla);
            Tabla2.ColSpecGroups = grps;
            Tabla2.TableEntriesVAlign = 'middle';
            Tabla2.TableEntriesHAlign = 'center';
            Tabla2.Style = tableStyle;
            Tabla2.Header.Children(1).Style =HeaderStyle;
            Tabla2.Body.Style=BodyStyle;
            add(chap3,Tabla2);
            add(chap3,Paragraph(''))
            
            p1=Paragraph('A continuación se presenta el puntaje y la clasificación por estrellas que alcanzó el tramo analizado al aplicar las contramedidas propuestas');
            p1.Underline='Single';
            p1.FontFamilyName='Gotham';
            add(chap3,p1);
            add(chap3,Paragraph(''));
            
            %Ocupante de vehículos
            x_1=round(app.OcupantedevehculosEditField_5.Value,3);

            
            %Motociclista
            x1_1=round(app.MotociclistaEditField_5.Value,3);

            
            %Ciclistas
            x2_1=round(app.CiclistaEditField_5.Value,3);
            
            %Peatón
            x3_1=round(app.PeatnEditField_5.Value,3);

            FF9=[x_1;x1_1;x2_1;x3_1];
            
            %Ocupante de vehículos
            x4_1=round(app.OcupantedevehculosEditField_6.Value,3);
            x4=num2str(x4_1);
            if app.OcupantedevehculosEditField_6.Value==1 
                z4='estrella';
            else
                z4='estrellas';
            end
            x8_1=(strcat(x4," ",z4));

            
            %Motociclista
            x5_1=round(app.MotociclistaEditField_6.Value,3);
            x5=num2str(x5_1);
            if app.MotociclistaEditField_6.Value==1 
                z5='estrella';
            else
                z5='estrellas';
            end
            x9_1=(strcat(x5," ",z5));

            
            %Ciclistas
            x6_1=round(app.CiclistaEditField_6.Value,3);
            x6=num2str(x6_1);
            if app.CiclistaEditField_6.Value==1 
                z6='estrella';
            else
                z6='estrellas';
            end
            x10_1=(strcat(x6," ",z6));

            
            %Peatón
            x7_1=round(app.PeatnEditField_6.Value,3);
            x7=num2str(x7_1);
            if app.PeatnEditField_6.Value==1 
                z7='estrella';
            else
                z7='estrellas';
            end
            x11_1=(strcat(x7," ",z7));
            
            EE9={x8_1;x9_1;x10_1;x11_1};
            TT_19=table(categorical(Nombres19),f(FF9),EE9);
            TT_19.Properties.VariableNames = {'Usuario Viales', 'Puntaje de clasificación por estrellas (SRS)','Clasificación por estrellas (SR)'};
            TTabla1_1=FormalTable(TT_19);
            
            TTabla1_1.ColSpecGroups = grps1;
            TTabla1_1.TableEntriesVAlign = 'middle';
            TTabla1_1.TableEntriesHAlign = 'center';
            TTabla1_1.Style = tableStyle;
            TTabla1_1.Header.Children(1).Style =HeaderStyle;
            TTabla1_1.Body.Style=BodyStyle;
            add(chap3,TTabla1_1);
            add(chap3,Paragraph(''))
            

            add(rpt,chap3);
            
           
            close(rpt);
            rptview(rpt);
        end

        % Callback function
        function VerificarButtonPushed(app, event)
            %Inter=app.TramosguardadosSpinner.Value;
            MLG(app.TramosguardadosSpinner.Value)
        end

        % Button pushed function: ResultadoButton
        function ResultadoButtonPushed(app, event)
            app_Aplicacion_contramedidas
        end

        % Close request function: Reporte
        function ReporteCloseRequest(app, event)
    
            copyfile('../mat/Nueva_clasificación_postproceso.mat','../doc/');
            copyfile('../mat/Matriz_indices_nuevos_postproceso.mat','../doc/');
            copyfile('../mat/Matriz_factores_contramedidas_postproceso.mat','../doc/');
            copyfile('../mat/Matriz_contramedidas_postproceso.mat','../doc/');
            copyfile('../mat/Matriz_contramedidas_seleccionadas_postproceso.mat','../doc/');
            copyfile('../mat/Matriz_resumen_contramedidas_postproceso.mat','../doc/');
            
            delete(app)
        end

        % Button pushed function: CargarButton
        function CargarButtonPushed(app, event)
            copyfile('../doc/Nueva_clasificación_postproceso.mat','../mat/');
            copyfile('../doc/Matriz_indices_nuevos_postproceso.mat','../mat/');
            copyfile('../doc/Matriz_factores_contramedidas_postproceso.mat','../mat/');
            copyfile('../doc/Matriz_contramedidas_postproceso.mat','../mat/');
            copyfile('../doc/Matriz_contramedidas_seleccionadas_postproceso.mat','../mat/');
            copyfile('../doc/Matriz_resumen_contramedidas_postproceso.mat','../mat/');
            copyfile('../doc/Matriz_Resultados_MGL_postproceso.mat','../mat/');
            load('../mat/Nueva_clasificación_postproceso.mat')
            Intervalo=sum(arrayfun(@(Nuevos_datos) ~isempty(Nuevos_datos.Nueva_Estrella_Ocupante_vehiculo),Nuevos_datos));
            app.TramosguardadosSpinner.Value=Intervalo;
            TramosguardadosSpinnerValueChanged(app)
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Reporte = uifigure('Visible', 'off');
            app.Reporte.Name = 'MATLAB App';
            app.Reporte.CloseRequestFcn = createCallbackFcn(app, @ReporteCloseRequest, true);
            app.TabGroup2 = uitabgroup(app.Reporte);
            app.ReporteGeneralTab = uitab(app.TabGroup2);
            app.ReporteGeneralTab.Title = 'Reporte General';
            app.TabGroup = uitabgroup(app.ReporteGeneralTab);
            app.CostadodelavaTab = uitab(app.TabGroup);
            app.CostadodelavaTab.Title = 'Costado de la vía';
            app.UITable = uitable(app.CostadodelavaTab);
            app.UITable.ColumnName = {'Severidad lateral - objeto (lado del conductor)'; 'Km'; '%'};
            app.UITable.RowName = {};
            app.UITable_2 = uitable(app.CostadodelavaTab);
            app.UITable_2.ColumnName = {'Severidad lateral - distancia al objeto (lado del conductor)'; 'Km'; '%'};
            app.UITable_2.RowName = {};
            app.UITable_3 = uitable(app.CostadodelavaTab);
            app.UITable_3.ColumnName = {'Severidad lateral - objeto (lado del copiloto)'; 'Km'; '%'};
            app.UITable_3.RowName = {};
            app.UITable_4 = uitable(app.CostadodelavaTab);
            app.UITable_4.ColumnName = {'Severidad lateral - distancia al objeto (lado del copiloto)'; 'Km'; '%'};
            app.UITable_4.RowName = {};
            app.UITable_5 = uitable(app.CostadodelavaTab);
            app.UITable_5.ColumnName = {'Ancho de espaldón pavimentado (lado del copiloto)'; 'Km'; '%'};
            app.UITable_5.RowName = {};
            app.UITable_6 = uitable(app.CostadodelavaTab);
            app.UITable_6.ColumnName = {'Ancho de espaldón pavimentado (lado del conductor)'; 'Km'; '%'};
            app.UITable_6.RowName = {};
            app.UITable_7 = uitable(app.CostadodelavaTab);
            app.UITable_7.ColumnName = {'Bandas sonoras en el espaldón'; 'Km'; '%'};
            app.UITable_7.RowName = {};
            app.BloquecentralTab = uitab(app.TabGroup);
            app.BloquecentralTab.Title = 'Bloque central';
            app.UITable_10 = uitable(app.BloquecentralTab);
            app.UITable_10.ColumnName = {'Tipo de carretera'; 'Km'; '%'};
            app.UITable_10.RowName = {};
            app.UITable_11 = uitable(app.BloquecentralTab);
            app.UITable_11.ColumnName = {'Costo de modernización'; 'Km'; '%'};
            app.UITable_11.RowName = {};
            app.UITable_13 = uitable(app.BloquecentralTab);
            app.UITable_13.ColumnName = {'Bandas sonoras centrales'; 'Km'; '%'};
            app.UITable_13.RowName = {};
            app.UITable_12 = uitable(app.BloquecentralTab);
            app.UITable_12.ColumnName = {'Tipo de mediana'; 'Km'; '%'};
            app.UITable_12.RowName = {};
            app.UITable_14 = uitable(app.BloquecentralTab);
            app.UITable_14.ColumnName = {'Número de carriles'; 'Km'; '%'};
            app.UITable_14.RowName = {};
            app.UITable_15 = uitable(app.BloquecentralTab);
            app.UITable_15.ColumnName = {'Ancho de carril'; 'Km'; '%'};
            app.UITable_15.RowName = {};
            app.UITable_16 = uitable(app.BloquecentralTab);
            app.UITable_16.ColumnName = {'Curvatura'; 'Km'; '%'};
            app.UITable_16.RowName = {};
            app.UITable_17 = uitable(app.BloquecentralTab);
            app.UITable_17.ColumnName = {'Calidad de la curva'; 'Km'; '%'};
            app.UITable_17.RowName = {};
            app.UITable_18 = uitable(app.BloquecentralTab);
            app.UITable_18.ColumnName = {'Pendiente'; 'Km'; '%'};
            app.UITable_18.RowName = {};
            app.UITable_19 = uitable(app.BloquecentralTab);
            app.UITable_19.ColumnName = {'Estado de la superficie de la vía'; 'Km'; '%'};
            app.UITable_19.RowName = {};
            app.UITable_20 = uitable(app.BloquecentralTab);
            app.UITable_20.ColumnName = {'Resistencia al deslizamiento'; 'Km'; '%'};
            app.UITable_20.RowName = {};
            app.UITable_21 = uitable(app.BloquecentralTab);
            app.UITable_21.ColumnName = {'Delineación'; 'Km'; '%'};
            app.UITable_21.RowName = {};
            app.UITable_22 = uitable(app.BloquecentralTab);
            app.UITable_22.ColumnName = {'Alumbrado público'; 'Km'; '%'};
            app.UITable_22.RowName = {};
            app.UITable_23 = uitable(app.BloquecentralTab);
            app.UITable_23.ColumnName = {'Estacionamiento para vehículos'; 'Km'; '%'};
            app.UITable_23.RowName = {};
            app.UITable_24 = uitable(app.BloquecentralTab);
            app.UITable_24.ColumnName = {'Vía de servicio'; 'Km'; '%'};
            app.UITable_24.RowName = {};
            app.UITable_25 = uitable(app.BloquecentralTab);
            app.UITable_25.ColumnName = {'Obras en la carretera'; 'Km'; '%'};
            app.UITable_25.RowName = {};
            app.UITable_26 = uitable(app.BloquecentralTab);
            app.UITable_26.ColumnName = {'Distancia de visibilidad'; 'Km'; '%'};
            app.UITable_26.RowName = {};
            app.InterseccionesTab = uitab(app.TabGroup);
            app.InterseccionesTab.Title = 'Intersecciones';
            app.UITable_27 = uitable(app.InterseccionesTab);
            app.UITable_27.ColumnName = {'Canalización de intersección'; 'Km'; '%'};
            app.UITable_27.RowName = {};
            app.UITable_28 = uitable(app.InterseccionesTab);
            app.UITable_28.ColumnName = {'Tipo de intersección'; 'Km'; '%'};
            app.UITable_28.RowName = {};
            app.UITable_29 = uitable(app.InterseccionesTab);
            app.UITable_29.ColumnName = {'Calidad de la intersección'; 'Km'; '%'};
            app.UITable_29.RowName = {};
            app.UITable_30 = uitable(app.InterseccionesTab);
            app.UITable_30.ColumnName = {'Volumen de la intersección'; 'Km'; '%'};
            app.UITable_30.RowName = {};
            app.UITable_31 = uitable(app.InterseccionesTab);
            app.UITable_31.ColumnName = {'Puntos de acceso a la propiedad'; 'Km'; '%'};
            app.UITable_31.RowName = {};
            app.FlujoTab = uitab(app.TabGroup);
            app.FlujoTab.Title = 'Flujo';
            app.UITable_32 = uitable(app.FlujoTab);
            app.UITable_32.ColumnName = {'% motocicletas'; 'Km'; '%'};
            app.UITable_32.RowName = {};
            app.UITable_33 = uitable(app.FlujoTab);
            app.UITable_33.ColumnName = {'Flujo vehicular (TDPA)'; 'Km'; '%'};
            app.UITable_33.RowName = {};
            app.UITable_34 = uitable(app.FlujoTab);
            app.UITable_34.ColumnName = {'Flujo peatonal en hora pico a lo largo de la carretera (lado del conductor)'; 'Km'; '%'};
            app.UITable_34.RowName = {};
            app.UITable_35 = uitable(app.FlujoTab);
            app.UITable_35.ColumnName = {'Flujo peatonal en hora pico que cruza la carretera'; 'Km'; '%'};
            app.UITable_35.RowName = {};
            app.UITable_36 = uitable(app.FlujoTab);
            app.UITable_36.ColumnName = {'Flujo peatonal en hora pico a lo largo de la carretera (lado del copiloto)'; 'Km'; '%'};
            app.UITable_36.RowName = {};
            app.UITable_37 = uitable(app.FlujoTab);
            app.UITable_37.ColumnName = {'Flujo de bicicletas en hora pico'; 'Km'; '%'};
            app.UITable_37.RowName = {};
            app.InfraestructuraparausuariosvulnerablesyusodelsueloTab = uitab(app.TabGroup);
            app.InfraestructuraparausuariosvulnerablesyusodelsueloTab.Title = 'Infraestructura para usuarios vulnerables y uso del suelo';
            app.UITable_38 = uitable(app.InfraestructuraparausuariosvulnerablesyusodelsueloTab);
            app.UITable_38.ColumnName = {'Uso de suelo (lado del conductor)'; 'Km'; '%'};
            app.UITable_38.RowName = {};
            app.UITable_39 = uitable(app.InfraestructuraparausuariosvulnerablesyusodelsueloTab);
            app.UITable_39.ColumnName = {'Uso de suelo (lado del copiloto)'; 'Km'; '%'};
            app.UITable_39.RowName = {};
            app.UITable_40 = uitable(app.InfraestructuraparausuariosvulnerablesyusodelsueloTab);
            app.UITable_40.ColumnName = {'Tipo de área'; 'Km'; '%'};
            app.UITable_40.RowName = {};
            app.UITable_41 = uitable(app.InfraestructuraparausuariosvulnerablesyusodelsueloTab);
            app.UITable_41.ColumnName = {'Infraestructura de cruce peatonal en el tramo analizado'; 'Km'; '%'};
            app.UITable_41.RowName = {};
            app.UITable_42 = uitable(app.InfraestructuraparausuariosvulnerablesyusodelsueloTab);
            app.UITable_42.ColumnName = {' Calidad de cruce peatonal'; 'Km'; '%'};
            app.UITable_42.RowName = {};
            app.UITable_43 = uitable(app.InfraestructuraparausuariosvulnerablesyusodelsueloTab);
            app.UITable_43.ColumnName = {'Infraestructura de cruce peatonal en la intersección'; 'Km'; '%'};
            app.UITable_43.RowName = {};
            app.UITable_44 = uitable(app.InfraestructuraparausuariosvulnerablesyusodelsueloTab);
            app.UITable_44.ColumnName = {' Vallas peatonales '; 'Km'; '%'};
            app.UITable_44.RowName = {};
            app.UITable_45 = uitable(app.InfraestructuraparausuariosvulnerablesyusodelsueloTab);
            app.UITable_45.ColumnName = {'Acera (lado del conductor)'; 'Km'; '%'};
            app.UITable_45.RowName = {};
            app.UITable_46 = uitable(app.InfraestructuraparausuariosvulnerablesyusodelsueloTab);
            app.UITable_46.ColumnName = {'Acera (lado del copiloto) '; 'Km'; '%'};
            app.UITable_46.RowName = {};
            app.UITable_47 = uitable(app.InfraestructuraparausuariosvulnerablesyusodelsueloTab);
            app.UITable_47.ColumnName = {'Infraestructura para motocicletas '; 'Km'; '%'};
            app.UITable_47.RowName = {};
            app.UITable_48 = uitable(app.InfraestructuraparausuariosvulnerablesyusodelsueloTab);
            app.UITable_48.ColumnName = {'Infraestructura para bicicletas'; 'Km'; '%'};
            app.UITable_48.RowName = {};
            app.UITable_49 = uitable(app.InfraestructuraparausuariosvulnerablesyusodelsueloTab);
            app.UITable_49.ColumnName = {'Advertencia de zona escolar'; 'Km'; '%'};
            app.UITable_49.RowName = {};
            app.UITable_50 = uitable(app.InfraestructuraparausuariosvulnerablesyusodelsueloTab);
            app.UITable_50.ColumnName = {' Supervisor en el cruce de zona escolar'; 'Km'; '%'};
            app.UITable_50.RowName = {};
            app.VelocidadesTab = uitab(app.TabGroup);
            app.VelocidadesTab.Title = 'Velocidades';
            app.UITable_51 = uitable(app.VelocidadesTab);
            app.UITable_51.ColumnName = {'Diferencial de límites de velocidad '; 'Km'; '%'};
            app.UITable_51.RowName = {};
            app.UITable_52 = uitable(app.VelocidadesTab);
            app.UITable_52.ColumnName = {'Límite de velocidad'; 'Km'; '%'};
            app.UITable_52.RowName = {};
            app.UITable_53 = uitable(app.VelocidadesTab);
            app.UITable_53.ColumnName = {'Velocidad de operación (percentil 85)'; 'Km'; '%'};
            app.UITable_53.RowName = {};
            app.UITable_54 = uitable(app.VelocidadesTab);
            app.UITable_54.ColumnName = {'Gestión de la velocidad/calmado el tráfico'; 'Km'; '%'};
            app.UITable_54.RowName = {};
            app.ReportedetalladoTab = uitab(app.TabGroup2);
            app.ReportedetalladoTab.Title = 'Reporte detallado';
            app.TabGroup_2 = uitabgroup(app.ReportedetalladoTab);
            app.ClasificacindeestrellasTab = uitab(app.TabGroup_2);
            app.ClasificacindeestrellasTab.Title = 'Clasificación de estrellas';
            app.UITable_9 = uitable(app.ClasificacindeestrellasTab);
            app.UITable_9.ColumnName = {'Clasificación de estrellas'; 'Longitud (Km)'; 'Porcentaje (%)'};
            app.UITable_9.RowName = {};
            app.UsuariosvialesButtonGroup = uibuttongroup(app.ReportedetalladoTab);
            app.UsuariosvialesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @UsuariosvialesButtonGroupSelectionChanged, true);
            app.UsuariosvialesButtonGroup.Title = 'Usuarios viales';
            app.OcupantedevehculosButton = uiradiobutton(app.UsuariosvialesButtonGroup);
            app.OcupantedevehculosButton.Text = 'Ocupante de vehículos';
            app.OcupantedevehculosButton.Value = true;
            app.MotociclistaButton = uiradiobutton(app.UsuariosvialesButtonGroup);
            app.MotociclistaButton.Text = 'Motociclista';
            app.CiclistaButton = uiradiobutton(app.UsuariosvialesButtonGroup);
            app.CiclistaButton.Text = 'Ciclista';
            app.PeatnButton = uiradiobutton(app.UsuariosvialesButtonGroup);
            app.PeatnButton.Text = 'Peatón';
            app.PDFButton_3 = uibutton(app.ReportedetalladoTab, 'push');
            app.PDFButton_3.ButtonPushedFcn = createCallbackFcn(app, @PDFButton_3Pushed, true);
            app.PDFButton_3.Icon = 'icono-PDF.png';
            app.PDFButton_3.Text = 'PDF';
            app.UbicacindelosSRSalolargodelacarreteraenanlisisPanel = uipanel(app.ReportedetalladoTab);
            app.UbicacindelosSRSalolargodelacarreteraenanlisisPanel.Title = 'Ubicación de los SRS a lo largo de la carretera en análisis';
            app.UIAxes = uiaxes(app.UbicacindelosSRSalolargodelacarreteraenanlisisPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'Longitud')
            ylabel(app.UIAxes, 'Latitud')
            app.PuntajedeclasificacinSRSalolargodelacarreteraenanlisisPanel = uipanel(app.ReportedetalladoTab);
            app.PuntajedeclasificacinSRSalolargodelacarreteraenanlisisPanel.Title = 'Puntaje de clasificación (SRS) a lo largo de la carretera en análisis';
            app.UIAxes_2 = uiaxes(app.PuntajedeclasificacinSRSalolargodelacarreteraenanlisisPanel);
            title(app.UIAxes_2, '')
            xlabel(app.UIAxes_2, 'Distancia (m)')
            ylabel(app.UIAxes_2, 'Puntajes de clasificación por estrellas (SRS)')
            app.SuavizadoCheckBox = uicheckbox(app.ReportedetalladoTab);
            app.SuavizadoCheckBox.ValueChangedFcn = createCallbackFcn(app, @SuavizadoCheckBoxValueChanged, true);
            app.SuavizadoCheckBox.Text = 'Suavizado';
            app.MedidasdemejoramientoTab = uitab(app.TabGroup2);
            app.MedidasdemejoramientoTab.Title = 'Medidas de mejoramiento';
            app.DATOSGENERALESDELACARRETERAPanel = uipanel(app.MedidasdemejoramientoTab);
            app.DATOSGENERALESDELACARRETERAPanel.Title = 'DATOS GENERALES DE LA CARRETERA';
            app.NombredelproyectoTextAreaLabel = uilabel(app.DATOSGENERALESDELACARRETERAPanel);
            app.NombredelproyectoTextAreaLabel.Text = 'Nombre del proyecto:';
            app.NombredelproyectoTextArea = uitextarea(app.DATOSGENERALESDELACARRETERAPanel);
            app.TramosguardadosSpinnerLabel = uilabel(app.DATOSGENERALESDELACARRETERAPanel);
            app.TramosguardadosSpinnerLabel.Text = 'Tramos guardados';
            app.TramosguardadosSpinner = uispinner(app.DATOSGENERALESDELACARRETERAPanel);
            app.TramosguardadosSpinner.ValueChangedFcn = createCallbackFcn(app, @TramosguardadosSpinnerValueChanged, true);
            app.CargarButton = uibutton(app.DATOSGENERALESDELACARRETERAPanel, 'push');
            app.CargarButton.ButtonPushedFcn = createCallbackFcn(app, @CargarButtonPushed, true);
            app.CargarButton.Text = 'Cargar';
            app.ATRIBUTOSCODIFICADOSPanel = uipanel(app.MedidasdemejoramientoTab);
            app.ATRIBUTOSCODIFICADOSPanel.Title = 'ATRIBUTOS CODIFICADOS';
            app.TabGroup3 = uitabgroup(app.ATRIBUTOSCODIFICADOSPanel);
            app.CostadodelavaTab_2 = uitab(app.TabGroup3);
            app.CostadodelavaTab_2.Title = 'Costado de la vía';
            app.UITable_55 = uitable(app.CostadodelavaTab_2);
            app.UITable_55.ColumnName = {'Atributo'; 'Dato seleccionado'};
            app.UITable_55.RowName = {};
            app.BloquecentralTab_2 = uitab(app.TabGroup3);
            app.BloquecentralTab_2.Title = 'Bloque central';
            app.UITable_56 = uitable(app.BloquecentralTab_2);
            app.UITable_56.ColumnName = {'Atributo'; 'Dato seleccionado'};
            app.UITable_56.RowName = {};
            app.InterseccionesTab_2 = uitab(app.TabGroup3);
            app.InterseccionesTab_2.Title = 'Intersecciones';
            app.UITable_57 = uitable(app.InterseccionesTab_2);
            app.UITable_57.ColumnName = {'Atributo'; 'Dato seleccionado'};
            app.UITable_57.RowName = {};
            app.FlujoTab_2 = uitab(app.TabGroup3);
            app.FlujoTab_2.Title = 'Flujo';
            app.UITable_58 = uitable(app.FlujoTab_2);
            app.UITable_58.ColumnName = {'Atributo'; 'Dato seleccionado'};
            app.UITable_58.RowName = {};
            app.InfraestructuraparausuariosvulnerablesyusodelsueloTab_2 = uitab(app.TabGroup3);
            app.InfraestructuraparausuariosvulnerablesyusodelsueloTab_2.Title = 'Infraestructura para usuarios vulnerables y uso del suelo';
            app.UITable_59 = uitable(app.InfraestructuraparausuariosvulnerablesyusodelsueloTab_2);
            app.UITable_59.ColumnName = {'Atributo'; 'Dato seleccionado'};
            app.UITable_59.RowName = {};
            app.VelocidadesTab_2 = uitab(app.TabGroup3);
            app.VelocidadesTab_2.Title = 'Velocidades';
            app.UITable_60 = uitable(app.VelocidadesTab_2);
            app.UITable_60.ColumnName = {'Atributo'; 'Dato seleccionado'};
            app.UITable_60.RowName = {};
            app.ClasificacinporestrellasPanel = uipanel(app.ATRIBUTOSCODIFICADOSPanel);
            app.ClasificacinporestrellasPanel.Title = 'Clasificación por estrellas';
            app.OcupantedevehculosEditField_3Label = uilabel(app.ClasificacinporestrellasPanel);
            app.OcupantedevehculosEditField_3Label.Text = 'Ocupante de vehículos';
            app.OcupantedevehculosEditField_3 = uieditfield(app.ClasificacinporestrellasPanel, 'numeric');
            app.OcupantedevehculosEditField_3.Editable = 'off';
            app.MotociclistaEditField_3Label = uilabel(app.ClasificacinporestrellasPanel);
            app.MotociclistaEditField_3Label.Text = 'Motociclista';
            app.MotociclistaEditField_3 = uieditfield(app.ClasificacinporestrellasPanel, 'numeric');
            app.MotociclistaEditField_3.Editable = 'off';
            app.CiclistaEditField_3Label = uilabel(app.ClasificacinporestrellasPanel);
            app.CiclistaEditField_3Label.Text = 'Ciclista';
            app.CiclistaEditField_3 = uieditfield(app.ClasificacinporestrellasPanel, 'numeric');
            app.CiclistaEditField_3.Editable = 'off';
            app.PeatnEditField_3Label = uilabel(app.ClasificacinporestrellasPanel);
            app.PeatnEditField_3Label.Text = 'Peatón';
            app.PeatnEditField_3 = uieditfield(app.ClasificacinporestrellasPanel, 'numeric');
            app.PeatnEditField_3.Editable = 'off';
            app.PuntajedeclasificacinPanel = uipanel(app.ATRIBUTOSCODIFICADOSPanel);
            app.PuntajedeclasificacinPanel.Title = 'Puntaje de clasificación';
            app.OcupantedevehculosEditField_4Label = uilabel(app.PuntajedeclasificacinPanel);
            app.OcupantedevehculosEditField_4Label.Text = 'Ocupante de vehículos';
            app.OcupantedevehculosEditField_4 = uieditfield(app.PuntajedeclasificacinPanel, 'numeric');
            app.OcupantedevehculosEditField_4.Editable = 'off';
            app.CiclistaEditField_4Label = uilabel(app.PuntajedeclasificacinPanel);
            app.CiclistaEditField_4Label.Text = 'Ciclista';
            app.CiclistaEditField_4 = uieditfield(app.PuntajedeclasificacinPanel, 'numeric');
            app.CiclistaEditField_4.Editable = 'off';
            app.MotociclistaEditField_4Label = uilabel(app.PuntajedeclasificacinPanel);
            app.MotociclistaEditField_4Label.Text = 'Motociclista';
            app.MotociclistaEditField_4 = uieditfield(app.PuntajedeclasificacinPanel, 'numeric');
            app.MotociclistaEditField_4.Editable = 'off';
            app.PeatnEditField_4Label = uilabel(app.PuntajedeclasificacinPanel);
            app.PeatnEditField_4Label.Text = 'Peatón';
            app.PeatnEditField_4 = uieditfield(app.PuntajedeclasificacinPanel, 'numeric');
            app.PeatnEditField_4.Editable = 'off';
            app.MEDIDASADOPTADASPanel = uipanel(app.MedidasdemejoramientoTab);
            app.MEDIDASADOPTADASPanel.Title = 'MEDIDAS ADOPTADAS';
            app.UITable2 = uitable(app.MEDIDASADOPTADASPanel);
            app.UITable2.ColumnName = {'#'; 'Contramedida'; 'Atributo analizado'; 'Categoría'; 'Selección'};
            app.UITable2.RowName = {};
            app.UITable2.ColumnEditable = true;
            app.UITable2.CellEditCallback = createCallbackFcn(app, @UITable2CellEdit, true);
            app.CalcularButton = uibutton(app.MEDIDASADOPTADASPanel, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.PDFButton_2 = uibutton(app.MEDIDASADOPTADASPanel, 'push');
            app.PDFButton_2.ButtonPushedFcn = createCallbackFcn(app, @PDFButton_2Pushed, true);
            app.PDFButton_2.Icon = 'icono-PDF.png';
            app.PDFButton_2.Text = 'PDF';
            app.PuntajedeclasificacinPanel_2 = uipanel(app.MEDIDASADOPTADASPanel);
            app.PuntajedeclasificacinPanel_2.Title = 'Puntaje de clasificación';
            app.OcupantedevehculosEditField_5Label = uilabel(app.PuntajedeclasificacinPanel_2);
            app.OcupantedevehculosEditField_5Label.Text = 'Ocupante de vehículos';
            app.OcupantedevehculosEditField_5 = uieditfield(app.PuntajedeclasificacinPanel_2, 'numeric');
            app.OcupantedevehculosEditField_5.Editable = 'off';
            app.CiclistaEditField_5Label = uilabel(app.PuntajedeclasificacinPanel_2);
            app.CiclistaEditField_5Label.Text = 'Ciclista';
            app.CiclistaEditField_5 = uieditfield(app.PuntajedeclasificacinPanel_2, 'numeric');
            app.CiclistaEditField_5.Editable = 'off';
            app.MotociclistaEditField_5Label = uilabel(app.PuntajedeclasificacinPanel_2);
            app.MotociclistaEditField_5Label.Text = 'Motociclista';
            app.MotociclistaEditField_5 = uieditfield(app.PuntajedeclasificacinPanel_2, 'numeric');
            app.MotociclistaEditField_5.Editable = 'off';
            app.PeatnEditField_5Label = uilabel(app.PuntajedeclasificacinPanel_2);
            app.PeatnEditField_5Label.Text = 'Peatón';
            app.PeatnEditField_5 = uieditfield(app.PuntajedeclasificacinPanel_2, 'numeric');
            app.PeatnEditField_5.Editable = 'off';
            app.ClasificacinporestrellasPanel_2 = uipanel(app.MEDIDASADOPTADASPanel);
            app.ClasificacinporestrellasPanel_2.Title = 'Clasificación por estrellas';
            app.OcupantedevehculosEditField_6Label = uilabel(app.ClasificacinporestrellasPanel_2);
            app.OcupantedevehculosEditField_6Label.Text = 'Ocupante de vehículos';
            app.OcupantedevehculosEditField_6 = uieditfield(app.ClasificacinporestrellasPanel_2, 'numeric');
            app.OcupantedevehculosEditField_6.Editable = 'off';
            app.MotociclistaEditField_6Label = uilabel(app.ClasificacinporestrellasPanel_2);
            app.MotociclistaEditField_6Label.Text = 'Motociclista';
            app.MotociclistaEditField_6 = uieditfield(app.ClasificacinporestrellasPanel_2, 'numeric');
            app.MotociclistaEditField_6.Editable = 'off';
            app.CiclistaEditField_6Label = uilabel(app.ClasificacinporestrellasPanel_2);
            app.CiclistaEditField_6Label.Text = 'Ciclista';
            app.CiclistaEditField_6 = uieditfield(app.ClasificacinporestrellasPanel_2, 'numeric');
            app.CiclistaEditField_6.Editable = 'off';
            app.PeatnEditField_6Label = uilabel(app.ClasificacinporestrellasPanel_2);
            app.PeatnEditField_6Label.Text = 'Peatón';
            app.PeatnEditField_6 = uieditfield(app.ClasificacinporestrellasPanel_2, 'numeric');
            app.PeatnEditField_6.Editable = 'off';
            app.GuardarButton = uibutton(app.MEDIDASADOPTADASPanel, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Text = 'Guardar';
            app.ResultadoButton = uibutton(app.MEDIDASADOPTADASPanel, 'push');
            app.ResultadoButton.ButtonPushedFcn = createCallbackFcn(app, @ResultadoButtonPushed, true);
            app.ResultadoButton.Text = 'Resultado';
            % Show the figure after all components are created
            app.Reporte.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_Reporte

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Reporte)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Reporte)
        end
    end
end